classdef coulomb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main_menu_window                matlab.ui.Figure
        OpenSaveMenu                    matlab.ui.container.Menu
        AboutCoulomb40Menu              matlab.ui.container.Menu
        OpeninputfileMenu               matlab.ui.container.Menu
        OpenmostrecentinputfileMenu     matlab.ui.container.Menu
        Build_CMTorfocalmeshdataMenu    matlab.ui.container.Menu
        Build_XYmapMenu                 matlab.ui.container.Menu
        SaveinputfileMenu               matlab.ui.container.Menu
        ascsvMenu                       matlab.ui.container.Menu
        asinporinrMenu                  matlab.ui.container.Menu
        asmatbinaryMenu                 matlab.ui.container.Menu
        SaveasEPSfileMenu               matlab.ui.container.Menu
        ExportfigureMenu                matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        OptionMenu                      matlab.ui.container.Menu
        GridSizeMenu                    matlab.ui.container.Menu
        ChangestudyareaMenu             matlab.ui.container.Menu
        ChangefigureaxisrangeMenu       matlab.ui.container.Menu
        TaperSplitMenu                  matlab.ui.container.Menu
        AddmodifyfaultMenu              matlab.ui.container.Menu
        DeletemodifyfaultMenu           matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        CreateOverlayFilesMenu          matlab.ui.container.Menu
        CoastlineMenu                   matlab.ui.container.Menu
        PluginMenu                      matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        TemporarysavevarsMenu           matlab.ui.container.Menu
        UndoMenu_2                      matlab.ui.container.Menu
        DMenu                           matlab.ui.container.Menu
        IsosurfaceMenu                  matlab.ui.container.Menu
        DeleteIsosurfaceMenu            matlab.ui.container.Menu
        Draw3DstresschangesfrommatfileMenu  matlab.ui.container.Menu
        ShearstresschangeMenu           matlab.ui.container.Menu
        UnclampingstresschangeMenu      matlab.ui.container.Menu
        CoulombstresschangeMenu         matlab.ui.container.Menu
        EQcountMenu                     matlab.ui.container.Menu
        MessageTextAreaLabel            matlab.ui.control.Label
        TitleImage                      matlab.ui.control.Image
        MessageTextArea                 matlab.ui.control.TextArea
        SW_StresswindowPanel            matlab.ui.container.Panel
        SW_SF_Panel                     matlab.ui.container.Panel
        SW_Label_1                      matlab.ui.control.Label
        SW_StrikeEditField              matlab.ui.control.NumericEditField
        StrikeEditFieldLabel            matlab.ui.control.Label
        SW_Label_2                      matlab.ui.control.Label
        SW_DipEditField                 matlab.ui.control.NumericEditField
        DipEditFieldLabel               matlab.ui.control.Label
        SW_Label_3                      matlab.ui.control.Label
        SW_RakeEditField                matlab.ui.control.NumericEditField
        RakeEditFieldLabel              matlab.ui.control.Label
        SW_Receiver_ButtonGroup         matlab.ui.container.ButtonGroup
        SW_SpecifiedButton              matlab.ui.control.RadioButton
        SW_OptStrikeSButton             matlab.ui.control.RadioButton
        SW_OptThrustsButton             matlab.ui.control.RadioButton
        SW_OptNormalButton              matlab.ui.control.RadioButton
        SW_OptFaultsButton              matlab.ui.control.RadioButton
        SW_StrikelineCheckBox           matlab.ui.control.CheckBox
        SW_PrincipalaxesCheckBox        matlab.ui.control.CheckBox
        SW_text_Label                   matlab.ui.control.Label
        SW_CalcDepthRangeCheckBox       matlab.ui.control.CheckBox
        SW_DepthEditField_Label         matlab.ui.control.Label
        SW_DepthEditField               matlab.ui.control.EditField
        SW_3Dcalc_CheckBox              matlab.ui.control.CheckBox
        SW_CalcViewButton               matlab.ui.control.Button
        EIP_ElementInputPanel           matlab.ui.container.Panel
        InputfileasreceiverfaultsButton  matlab.ui.control.Button
        SplitTaperPanel                 matlab.ui.container.Panel
        STP_text1_Label                 matlab.ui.control.Label
        STP_text2_Label                 matlab.ui.control.Label
        STP_TargetFN_EditField          matlab.ui.control.NumericEditField
        TargetfaultnumberEditField_2Label  matlab.ui.control.Label
        STP_Lineartapering_CheckBox     matlab.ui.control.CheckBox
        STP_dist_EditField              matlab.ui.control.NumericEditField
        STP_downdip_EditField           matlab.ui.control.NumericEditField
        STP_NoPatches_EditField         matlab.ui.control.NumericEditField
        STP_Splitting_CheckBox          matlab.ui.control.CheckBox
        STP_alongstrike_EditField       matlab.ui.control.NumericEditField
        alongstrikeEditField_2Label     matlab.ui.control.Label
        distfredgesalongstrikekmEditField_2Label  matlab.ui.control.Label
        NoofnestedpatchesEditField_2Label  matlab.ui.control.Label
        downdipkmEditField_2Label       matlab.ui.control.Label
        STP_alongdowndip_EditField      matlab.ui.control.NumericEditField
        alongdowndipEditField_2Label    matlab.ui.control.Label
        STP_HelpButton                  matlab.ui.control.Button
        STP_AddFaultButton              matlab.ui.control.Button
        NewFaultCreationPanel           matlab.ui.container.Panel
        NFC_text1_Label                 matlab.ui.control.Label
        NFC_text2_Label                 matlab.ui.control.Label
        NFC_text3_Label                 matlab.ui.control.Label
        NFC_Type_DropDown               matlab.ui.control.DropDown
        CoordinateTypeDropDownLabel     matlab.ui.control.Label
        NFC_XkmLabel                    matlab.ui.control.Label
        NFC_YkmLabel                    matlab.ui.control.Label
        NFC_StartLabel                  matlab.ui.control.Label
        TargetfaultnumberEditField_2Label_2  matlab.ui.control.Label
        NFC_FinishLabel                 matlab.ui.control.Label
        NFC_MouseClickButton            matlab.ui.control.Button
        NFC_Xstart_EditField            matlab.ui.control.NumericEditField
        NFC_Ystart_EditField            matlab.ui.control.NumericEditField
        NFC_Xfinish_EditField           matlab.ui.control.NumericEditField
        NFC_Yfinish_EditField           matlab.ui.control.NumericEditField
        NFC_NFNEditField                matlab.ui.control.NumericEditField
        NFC_HelpButton                  matlab.ui.control.Button
        NFC_AddFaultButton              matlab.ui.control.Button
        DeleteFaultPanel                matlab.ui.container.Panel
        DFP_ResetButton                 matlab.ui.control.Button
        DFP_text1_Label                 matlab.ui.control.Label
        DFP_text2_Label                 matlab.ui.control.Label
        DFP_Note_text_Label             matlab.ui.control.Label
        DFP_FaultnumberListListBox      matlab.ui.control.ListBox
        FaultnumberListListBoxLabel     matlab.ui.control.Label
        DFP_Fault_candidate_TextArea    matlab.ui.control.TextArea
        FaultnumberofthecandidatefordeletionTextAreaLabel  matlab.ui.control.Label
        DFP_DeleteButton                matlab.ui.control.Button
        EIP_text1_Label                 matlab.ui.control.Label
        EIP_EFTotalNumber               matlab.ui.control.NumericEditField
        EIP_text2_Label                 matlab.ui.control.Label
        EIP_CancelButton                matlab.ui.control.Button
        EIP_CloseButton                 matlab.ui.control.Button
        EIP_OKButton                    matlab.ui.control.Button
        EIP_FaultDataLabel              matlab.ui.control.Label
        TotalNumberofFaultsEditFieldLabel_2  matlab.ui.control.Label
        EIP_UITable_input_element       matlab.ui.control.Table
        SW_DepthRangePanel              matlab.ui.container.Panel
        SW_CDR_OKButton                 matlab.ui.control.Button
        SW_Top_EditField                matlab.ui.control.NumericEditField
        SW_kmLabel_1                    matlab.ui.control.Label
        SW_Bottom_EditField             matlab.ui.control.NumericEditField
        SW_kmLabel_2                    matlab.ui.control.Label
        SW_Depthinc_EditField           matlab.ui.control.NumericEditField
        SW_kmLabel_3                    matlab.ui.control.Label
        SW_MaxMeanValueButton           matlab.ui.container.ButtonGroup
        SW_MeanvaluesButton             matlab.ui.control.RadioButton
        SW_MaxvaluesButton              matlab.ui.control.RadioButton
        DepthincrementEditFieldLabel    matlab.ui.control.Label
        BottomcalcdepthEditFieldLabel   matlab.ui.control.Label
        TopcalcdepthEditFieldLabel      matlab.ui.control.Label
        SW_SliceDepthPanel              matlab.ui.container.Panel
        SW_S_Top_EditField              matlab.ui.control.NumericEditField
        SW_S_kmLabel_1                  matlab.ui.control.Label
        SW_S_Bottom_EditField           matlab.ui.control.NumericEditField
        SW_S_kmLabel_2                  matlab.ui.control.Label
        SW_S_Step_EditField             matlab.ui.control.NumericEditField
        SW_S_01_Label                   matlab.ui.control.Label
        SW_S_Zex_EditField              matlab.ui.control.NumericEditField
        SlicestepLabel_2                matlab.ui.control.Label
        SW_S_OKButton                   matlab.ui.control.Button
        SlicestepLabel                  matlab.ui.control.Label
        BottomcalcdepthEditFieldLabel_2  matlab.ui.control.Label
        TopcalcdepthEditFieldLabel_2    matlab.ui.control.Label
        SearchtheISCBulletinPanel       matlab.ui.container.Panel
        DepthrangeEditFieldLabel        matlab.ui.control.Label
        ISC_Image                       matlab.ui.control.Image
        ISC_CancelButton                matlab.ui.control.Button
        ISC_text1_Label                 matlab.ui.control.Label
        ISC_SearchTypeDropDown          matlab.ui.control.DropDown
        MwrangeEditFieldLabel           matlab.ui.control.Label
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        AuthorDropDownLabel             matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        ISC_text2_Label                 matlab.ui.control.Label
        ISC_StartDateDatePicker         matlab.ui.control.DatePicker
        ISC_StartHourEditField          matlab.ui.control.NumericEditField
        ISC_StartMinuteEditField        matlab.ui.control.NumericEditField
        ISC_StartSecondEditField        matlab.ui.control.NumericEditField
        ISC_text3_Label                 matlab.ui.control.Label
        ISC_EndDateDatePicker           matlab.ui.control.DatePicker
        ISC_EndHourEditField            matlab.ui.control.NumericEditField
        ISC_EndMinuteEditField          matlab.ui.control.NumericEditField
        ISC_EndSecondEditField          matlab.ui.control.NumericEditField
        ISC_text4_Label                 matlab.ui.control.Label
        ISC_DepthMaxEditField           matlab.ui.control.NumericEditField
        ISC_DepthMinEditField           matlab.ui.control.NumericEditField
        ISC_DepthrangeSlider            matlab.ui.control.RangeSlider
        ISC_text5_Label                 matlab.ui.control.Label
        ISC_MwMaxEditField              matlab.ui.control.NumericEditField
        ISC_MwMinEditField              matlab.ui.control.NumericEditField
        ISC_MwrangeSlider               matlab.ui.control.RangeSlider
        ISC_MwTypeDropDown              matlab.ui.control.DropDown
        ISC_MwAuthorDropDown            matlab.ui.control.DropDown
        ISC_text6_Label                 matlab.ui.control.Label
        SecondEditField_2Label          matlab.ui.control.Label
        SecondEditFieldLabel            matlab.ui.control.Label
        HourEditFieldLabel              matlab.ui.control.Label
        HourEditField_2Label            matlab.ui.control.Label
        ISC_MaxPhaseEditField           matlab.ui.control.EditField
        ISC_MinPhaseEditField           matlab.ui.control.EditField
        MinuteLabel_2                   matlab.ui.control.Label
        EndDateDatePickerLabel          matlab.ui.control.Label
        MinuteLabel                     matlab.ui.control.Label
        StartDateDatePickerLabel        matlab.ui.control.Label
        SearchTypeDropDownLabel         matlab.ui.control.Label
        ISC_OutputOptionsLabel          matlab.ui.control.Label
        ISC_OutputPRIMEhypo_CheckBox    matlab.ui.control.CheckBox
        ISC_OutputPhasesCheckBox        matlab.ui.control.CheckBox
        ISC_OutputMagnitudesCheckBox    matlab.ui.control.CheckBox
        ISC_OutputWebLinksCheckBox      matlab.ui.control.CheckBox
        ISC_OutputHeadersCheckBox       matlab.ui.control.CheckBox
        ISC_OutputCommentsCheckBox      matlab.ui.control.CheckBox
        ISC_DownloadButton              matlab.ui.control.Button
        ISC_SaveButton                  matlab.ui.control.Button
        EarthquakeDataScreeningPanel    matlab.ui.container.Panel
        EDS_text1_Label                 matlab.ui.control.Label
        EDS_text2_Label                 matlab.ui.control.Label
        ofEarthquakessincatalogEditFieldLabel  matlab.ui.control.Label
        EDS_EQNO_catalog_EditField      matlab.ui.control.NumericEditField
        EDS_start_date_time_Label       matlab.ui.control.Label
        StartDateDatePicker_2Label      matlab.ui.control.Label
        EDS_StartDateDatePicker         matlab.ui.control.DatePicker
        HourEditFieldLabel_2            matlab.ui.control.Label
        EDS_StartHourEditField          matlab.ui.control.NumericEditField
        MinuteLabel_4                   matlab.ui.control.Label
        EDS_StartMinuteEditField        matlab.ui.control.NumericEditField
        EDS_end_date_time_Label         matlab.ui.control.Label
        EndDateDatePicker_2Label        matlab.ui.control.Label
        EDS_EndDateDatePicker           matlab.ui.control.DatePicker
        HourEditField_2Label_2          matlab.ui.control.Label
        EDS_EndHourEditField            matlab.ui.control.NumericEditField
        MinuteLabel_3                   matlab.ui.control.Label
        EDS_EndMinuteEditField          matlab.ui.control.NumericEditField
        EDS_MinLabel                    matlab.ui.control.Label
        EDS_MaxLabel                    matlab.ui.control.Label
        EDS_LondegLabel                 matlab.ui.control.Label
        EDS_LonSlider                   matlab.ui.control.RangeSlider
        EDS_MinLonEditField             matlab.ui.control.NumericEditField
        EDS_MaxLonEditField             matlab.ui.control.NumericEditField
        EDS_LatdegLabel                 matlab.ui.control.Label
        EDS_LatSlider                   matlab.ui.control.RangeSlider
        EDS_MinLatEditField             matlab.ui.control.NumericEditField
        EDS_MaxLatEditField             matlab.ui.control.NumericEditField
        EDS_MagnitudeLabel              matlab.ui.control.Label
        EDS_MwSlider                    matlab.ui.control.RangeSlider
        EDS_MinMwEditField              matlab.ui.control.NumericEditField
        EDS_MaxMwEditField              matlab.ui.control.NumericEditField
        EDS_DepthkmLabel                matlab.ui.control.Label
        EDS_DepthSlider                 matlab.ui.control.RangeSlider
        EDS_MinDepthEditField           matlab.ui.control.NumericEditField
        EDS_MaxDepthEditField           matlab.ui.control.NumericEditField
        EDS_CloseButton                 matlab.ui.control.Button
        EDS_CancelButton                matlab.ui.control.Button
        EDS_OKButton                    matlab.ui.control.Button
        BCF_BuildCMTorfocalmeshdataPanel  matlab.ui.container.Panel
        BCF_text1_Label                 matlab.ui.control.Label
        BCF_XSLabel_14                  matlab.ui.control.Label
        BCF_XSLabel_13                  matlab.ui.control.Label
        BCF_RefPoint_DropDown           matlab.ui.control.DropDown
        BCF_FaultType_DropDown          matlab.ui.control.DropDown
        EarthquakeInformationLabel      matlab.ui.control.Label
        BCF_EQlon_EditField             matlab.ui.control.NumericEditField
        BCF_EQlat_EditField             matlab.ui.control.NumericEditField
        BCF_EQdepth_EditField           matlab.ui.control.NumericEditField
        BCF_EQmw_EditField              matlab.ui.control.NumericEditField
        BCF_USGS_Button                 matlab.ui.control.Button
        BCF_EQstrike_EditField          matlab.ui.control.NumericEditField
        BCF_EQdip_EditField             matlab.ui.control.NumericEditField
        BCF_EQrake_EditField            matlab.ui.control.NumericEditField
        BCF_LawDropDown                 matlab.ui.control.DropDown
        EmpricallawLabel                matlab.ui.control.Label
        BCF_EmpricalLaw_CalcButton      matlab.ui.control.Button
        BCF_EQlength_EditField          matlab.ui.control.NumericEditField
        BCF_EQwidth_EditField           matlab.ui.control.NumericEditField
        BCF_text3_Label                 matlab.ui.control.Label
        BCF_text4_Label                 matlab.ui.control.Label
        BCF_Lineartapering_CheckBox     matlab.ui.control.CheckBox
        BCF_dist_EditField              matlab.ui.control.NumericEditField
        BCF_downdip_EditField           matlab.ui.control.NumericEditField
        BCF_NoPatches_EditField         matlab.ui.control.NumericEditField
        BCF_Splitting_CheckBox          matlab.ui.control.CheckBox
        BCF_alongstrike_EditField       matlab.ui.control.NumericEditField
        BCF_alongdowndip_EditField      matlab.ui.control.NumericEditField
        BCF_HelpButton                  matlab.ui.control.Button
        BCF_AddFaultButton              matlab.ui.control.Button
        BCF_text5_Label                 matlab.ui.control.Label
        BCF_UITable_element             matlab.ui.control.Table
        BCF_FE_EQ_CloseButton           matlab.ui.control.Button
        BCF_FE_EQ_ResetButton           matlab.ui.control.Button
        BCF_FE_EQ_AddtomapButton        matlab.ui.control.Button
        NoofnestedpatchesEditField_2Label_2  matlab.ui.control.Label
        alongstrikeEditField_2Label_2   matlab.ui.control.Label
        distfredgesalongstrikekmEditField_2Label_2  matlab.ui.control.Label
        downdipkmEditField_2Label_2     matlab.ui.control.Label
        alongdowndipEditField_2Label_2  matlab.ui.control.Label
        BCF_XSLabel_11                  matlab.ui.control.Label
        BCF_XSLabel_10                  matlab.ui.control.Label
        BCF_XSLabel_9                   matlab.ui.control.Label
        BCF_XSLabel_12                  matlab.ui.control.Label
        BCF_XSLabel_7                   matlab.ui.control.Label
        BCF_XSLabel_6                   matlab.ui.control.Label
        BCF_XSLabel_5                   matlab.ui.control.Label
        FaultTypeDropDownLabel_4        matlab.ui.control.Label
        ReferencePointLabel             matlab.ui.control.Label
        BXY_BuildiXYmapPanel            matlab.ui.container.Panel
        BXY_StudyAreaPanel              matlab.ui.container.Panel
        BXY_StudyAreaLabel              matlab.ui.control.Label
        BXY_startLabel                  matlab.ui.control.Label
        BXY_finishLabel                 matlab.ui.control.Label
        BXY_XY_incrLabel                matlab.ui.control.Label
        BXY_XaxisLabel                  matlab.ui.control.Label
        BXY_XstartEditField             matlab.ui.control.NumericEditField
        BXY_XfinishEditField            matlab.ui.control.NumericEditField
        BXY_XincrEditField              matlab.ui.control.NumericEditField
        BXY_YaxisLabel                  matlab.ui.control.Label
        BXY_YstartEditField             matlab.ui.control.NumericEditField
        BXY_YfinishEditField            matlab.ui.control.NumericEditField
        BXY_YincrEditField              matlab.ui.control.NumericEditField
        BXY_Add_lonlat_Button           matlab.ui.control.Button
        BXY_minLabel                    matlab.ui.control.Label
        BXY_maxLabel                    matlab.ui.control.Label
        BFY_zeroLabel                   matlab.ui.control.Label
        BXY_longitudeLabel              matlab.ui.control.Label
        BXY_minlonEditField             matlab.ui.control.NumericEditField
        BXY_maxlonEditField             matlab.ui.control.NumericEditField
        BXY_zerolonEditField            matlab.ui.control.NumericEditField
        BXY_latitudeLabel               matlab.ui.control.Label
        BXY_minlatEditField             matlab.ui.control.NumericEditField
        BXY_maxlatEditField             matlab.ui.control.NumericEditField
        BXY_zerolatEditField            matlab.ui.control.NumericEditField
        BXY_SetmapButton                matlab.ui.control.Button
        BXY_NFC_Panel                   matlab.ui.container.Panel
        AddfaultButton                  matlab.ui.control.Button
        BXY_NFC_text1_Label             matlab.ui.control.Label
        BXY_NFC_text2_Label             matlab.ui.control.Label
        BXY_NFC_text3_Label             matlab.ui.control.Label
        BXY_Type_DropDown               matlab.ui.control.DropDown
        CoordinateTypeDropDownLabel_2   matlab.ui.control.Label
        BXY_XkmLabel                    matlab.ui.control.Label
        BXY_YkmLabel                    matlab.ui.control.Label
        BXY_StartLabel                  matlab.ui.control.Label
        BXY_FinishLabel                 matlab.ui.control.Label
        BXY_Xstart_EditField            matlab.ui.control.NumericEditField
        BXY_Ystart_EditField            matlab.ui.control.NumericEditField
        BXY_Xfinish_EditField           matlab.ui.control.NumericEditField
        BXY_Yfinish_EditField           matlab.ui.control.NumericEditField
        MouseClickButton                matlab.ui.control.Button
        BXY_FaultDataLabel              matlab.ui.control.Label
        BXY_text1_Label                 matlab.ui.control.Label
        BXY_UITable_input_element       matlab.ui.control.Table
        BXY_text2_Label                 matlab.ui.control.Label
        BXY_CloseButton                 matlab.ui.control.Button
        BXY_ResetButton                 matlab.ui.control.Button
        BXY_HelpButton                  matlab.ui.control.Button
        BXY_addtomapButton              matlab.ui.control.Button
        CSAAP_CalcstressatapointPanel   matlab.ui.container.Panel
        CSAAP_text1_Label               matlab.ui.control.Label
        CSAAP_text2_Label               matlab.ui.control.Label
        CSAAP_XkmEditField              matlab.ui.control.NumericEditField
        CSAAP_YkmEditField              matlab.ui.control.NumericEditField
        CSAAP_ZkmEditField              matlab.ui.control.NumericEditField
        CSAAP_mouseclick_Button         matlab.ui.control.Button
        CSAAP_text3_Label               matlab.ui.control.Label
        CSAAP_strikeEditField           matlab.ui.control.NumericEditField
        CSAAP_dipEditField              matlab.ui.control.NumericEditField
        CSAAP_rakeEditField             matlab.ui.control.NumericEditField
        CSAAP_text4_Label               matlab.ui.control.Label
        CSAAP_text5_Label               matlab.ui.control.Label
        CSAAP_coulombEditField          matlab.ui.control.NumericEditField
        CSAAP_unclampingEditField       matlab.ui.control.NumericEditField
        CSAAP_shearEditField            matlab.ui.control.NumericEditField
        CSAAP_CalculationButton         matlab.ui.control.Button
        coulombEditFieldLabel           matlab.ui.control.Label
        unclampingEditFieldLabel        matlab.ui.control.Label
        shearEditFieldLabel             matlab.ui.control.Label
        rakeEditFieldLabel              matlab.ui.control.Label
        dipEditFieldLabel               matlab.ui.control.Label
        strikeEditFieldLabel            matlab.ui.control.Label
        ZkmEditFieldLabel               matlab.ui.control.Label
        YkmEditFieldLabel               matlab.ui.control.Label
        XkmEditFieldLabel               matlab.ui.control.Label
        CSOF_Calc_stress_on_faults_Panel  matlab.ui.container.Panel
        CSOF_equal_Label                matlab.ui.control.Label
        CSOF_degree_Label               matlab.ui.control.Label
        CSOF_ButtonGroup                matlab.ui.container.ButtonGroup
        CoefffrictionEditFieldLabel_2   matlab.ui.control.Label
        CSOF_rightlat_Button            matlab.ui.control.RadioButton
        CSOF_reverse_Button             matlab.ui.control.RadioButton
        CSOF_specifiedrake_Button       matlab.ui.control.RadioButton
        CSOF_individualrake_Button      matlab.ui.control.RadioButton
        CSOF_Unclamping_Button          matlab.ui.control.RadioButton
        CSOF_degree_EditField           matlab.ui.control.NumericEditField
        CSOF_Coefffriction_EditField    matlab.ui.control.NumericEditField
        CSOF_Colorlimits_EditField      matlab.ui.control.NumericEditField
        CSOF_barLabel                   matlab.ui.control.Label
        CSOF_CalcViewButton             matlab.ui.control.Button
        ColorsaturationlimitsEditFieldLabel  matlab.ui.control.Label
        CSNP_SelectNodalplanetypePanel  matlab.ui.container.Panel
        CSNP_text1_Label                matlab.ui.control.Label
        CSNP_text2_Label                matlab.ui.control.Label
        CSNP_text3_Label                matlab.ui.control.Label
        CSNP_ListBox                    matlab.ui.control.ListBox
        CSNP_LatestquakesontopCheckBox  matlab.ui.control.CheckBox
        CSNP_LargestquakesontopCheckBox  matlab.ui.control.CheckBox
        CSNP_CloseButton                matlab.ui.control.Button
        F3DwiredPanel                   matlab.ui.container.Panel
        F3D_SlipType_ButtonGroup        matlab.ui.container.ButtonGroup
        F3D_NetslipButton               matlab.ui.control.RadioButton
        F3D_StrikeslipButton            matlab.ui.control.RadioButton
        F3D_DipslipButton               matlab.ui.control.RadioButton
        F3D_Color_Label                 matlab.ui.control.Label
        F3D_Color_EditField             matlab.ui.control.NumericEditField
        F3D_mLabel                      matlab.ui.control.Label
        ViewPanel                       matlab.ui.container.Panel
        View_text1_Label                matlab.ui.control.Label
        View_text2_Label                matlab.ui.control.Label
        View_AzimuthEditField           matlab.ui.control.NumericEditField
        View_ElevationEditField         matlab.ui.control.NumericEditField
        View_RedrawButton               matlab.ui.control.Button
        elevationangleEditFieldLabel    matlab.ui.control.Label
        azimuthEditFieldLabel           matlab.ui.control.Label
        PreferencespanelPanel           matlab.ui.container.Panel
        ColorschemeDropDownLabel        matlab.ui.control.Label
        PP_SelectcolorLabel             matlab.ui.control.Label
        PP_LinewidthplotLabel           matlab.ui.control.Label
        PP_FaultColorPicker             matlab.ui.control.ColorPicker
        PP_FaultpointEditField          matlab.ui.control.NumericEditField
        PP_FaultpointLabel              matlab.ui.control.Label
        PP_DispvectorWireframeColorPicker  matlab.ui.control.ColorPicker
        PP_DispvectorpointEditField     matlab.ui.control.NumericEditField
        PP_DispvectorpointLabel         matlab.ui.control.Label
        PP_GridColorPicker              matlab.ui.control.ColorPicker
        PP_GridpointEditField           matlab.ui.control.NumericEditField
        PP_GridpointLabel               matlab.ui.control.Label
        PP_CoastlineColorPicker         matlab.ui.control.ColorPicker
        PP_CoastlinepointEditField      matlab.ui.control.NumericEditField
        PP_CoastlinepointLabel          matlab.ui.control.Label
        PP_ActivefaultColorPicker       matlab.ui.control.ColorPicker
        PP_ActivefaultpointEditField    matlab.ui.control.NumericEditField
        PP_ActivefaultpointLabel        matlab.ui.control.Label
        PP_EarthquakeColorPicker        matlab.ui.control.ColorPicker
        PP_EarthquakepointEditField     matlab.ui.control.NumericEditField
        PP_EarthquakepointLabel         matlab.ui.control.Label
        PP_VolcanoColorPicker           matlab.ui.control.ColorPicker
        PP_VolcanopointEditField        matlab.ui.control.NumericEditField
        PP_VolcanopointLabel            matlab.ui.control.Label
        PP_ColorschemeDropDown          matlab.ui.control.DropDown
        PP_DefaultButton                matlab.ui.control.Button
        PP_FaultnumbervisibleCheckBox   matlab.ui.control.CheckBox
        PP_CalcViewButton               matlab.ui.control.Button
        PP_ClosePanelButton             matlab.ui.control.Button
        VolcanoColorPickerLabel         matlab.ui.control.Label
        EarthquakeColorPickerLabel      matlab.ui.control.Label
        ActivefaultColorPickerLabel     matlab.ui.control.Label
        CoastlineColorPickerLabel       matlab.ui.control.Label
        GridColorPickerLabel            matlab.ui.control.Label
        DispvectorWireframeColorPickerLabel  matlab.ui.control.Label
        FaultColorPickerLabel           matlab.ui.control.Label
        ChangestudyareaPanel            matlab.ui.container.Panel
        CS_text1_Label                  matlab.ui.control.Label
        CS_LonBar                       matlab.ui.control.Label
        CS_LonMinEditField              matlab.ui.control.NumericEditField
        CS_LonMaxEditField              matlab.ui.control.NumericEditField
        CS_text2_Label                  matlab.ui.control.Label
        CS_LatBar                       matlab.ui.control.Label
        CS_LatMinEditField              matlab.ui.control.NumericEditField
        CS_LatMaxEditField              matlab.ui.control.NumericEditField
        CS_CancelButton                 matlab.ui.control.Button
        CSOKButton                      matlab.ui.control.Button
        MaxEditField_3Label             matlab.ui.control.Label
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_3Label             matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        RefreshgraphicButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalcTab                         matlab.ui.container.Tab
        ZexxaEditField                  matlab.ui.control.NumericEditField
        ZexxaEditFieldLabel             matlab.ui.control.Label
        isosurfaceynormalButton         matlab.ui.control.Button
        DstressfrommatynormalButton     matlab.ui.control.Button
        paralleltestButton              matlab.ui.control.Button
        testbuttonButton                matlab.ui.control.Button
        MosaicLabel                     matlab.ui.control.Label
        CoordinatesSwitch_2Label        matlab.ui.control.Label
        ExaggerationLabel_2             matlab.ui.control.Label
        IntervalUnitLabel               matlab.ui.control.Label
        IntervalEditField               matlab.ui.control.NumericEditField
        IntervalEditFieldLabel          matlab.ui.control.Label
        Rescaleoutput_Label             matlab.ui.control.Label
        Rescale_output_x_EditField      matlab.ui.control.NumericEditField
        Rescale_output_Slider           matlab.ui.control.Slider
        DisplexaggerationEditField      matlab.ui.control.NumericEditField
        CoefffrictionEditFieldLabel     matlab.ui.control.Label
        FrictionEditField               matlab.ui.control.NumericEditField
        CalcDepthEditField              matlab.ui.control.NumericEditField
        CalcDepthkmLabel                matlab.ui.control.Label
        CrossSectionButton              matlab.ui.control.Button
        ContourlineCheckBox             matlab.ui.control.CheckBox
        MosaicSwitch                    matlab.ui.control.Switch
        CoordinatesSwitch               matlab.ui.control.Switch
        FunctionButtonGroup             matlab.ui.container.ButtonGroup
        mapview2DButton                 matlab.ui.control.RadioButton
        view3DButton                    matlab.ui.control.RadioButton
        HDvectorsButton                 matlab.ui.control.RadioButton
        HDwireframeButton               matlab.ui.control.RadioButton
        VDButton                        matlab.ui.control.RadioButton
        image3DdrapeButton              matlab.ui.control.RadioButton
        image3DwireframedsurfaceButton  matlab.ui.control.RadioButton
        vectors3DButton                 matlab.ui.control.RadioButton
        exxstrainButton                 matlab.ui.control.RadioButton
        eyystrainButton                 matlab.ui.control.RadioButton
        ezzstrainButton                 matlab.ui.control.RadioButton
        exystrainButton                 matlab.ui.control.RadioButton
        exzstrainButton                 matlab.ui.control.RadioButton
        eyzstrainButton                 matlab.ui.control.RadioButton
        DilatationButton                matlab.ui.control.RadioButton
        ShearstresschangeButton         matlab.ui.control.RadioButton
        UnclampingstresschangeButton    matlab.ui.control.RadioButton
        CoulombstresschangeButton       matlab.ui.control.RadioButton
        CalcStFaultsButton              matlab.ui.control.RadioButton
        CalcStPointButton               matlab.ui.control.RadioButton
        CalcStNodalplanesButton         matlab.ui.control.RadioButton
        OverlaysPanel                   matlab.ui.container.Panel
        CoastlinesCheckBox              matlab.ui.control.CheckBox
        ActivefaultsCheckBox            matlab.ui.control.CheckBox
        VolcanoesCheckBox               matlab.ui.control.CheckBox
        EarthquakesCheckBox             matlab.ui.control.CheckBox
        GNSSstationsCheckBox            matlab.ui.control.CheckBox
        CitiesCheckBox                  matlab.ui.control.CheckBox
        InpreparationCheckBox           matlab.ui.control.CheckBox
        DeleteOverlayDataButton         matlab.ui.control.Button
        FixpointPanel                   matlab.ui.container.Panel
        FP_ButtonGroup                  matlab.ui.container.ButtonGroup
        NofixedpointButton              matlab.ui.control.RadioButton
        FixedpointButton                matlab.ui.control.RadioButton
        FP_MouseClickButton             matlab.ui.control.Button
        FP_xkmLabel                     matlab.ui.control.Label
        FP_ykmLabel                     matlab.ui.control.Label
        yEditFieldLabel                 matlab.ui.control.Label
        FP_fixXEditField                matlab.ui.control.NumericEditField
        FP_fixYEditField                matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        CrossSectionTab                 matlab.ui.container.Tab
        CrossSectionControlPanel        matlab.ui.container.Panel
        CSC_XLabel                      matlab.ui.control.Label
        CSC_YLabel                      matlab.ui.control.Label
        CSC_StartALabel                 matlab.ui.control.Label
        CSC_FinishBLabel                matlab.ui.control.Label
        CSC_StartX_EditField            matlab.ui.control.NumericEditField
        CSC_StartY_EditField            matlab.ui.control.NumericEditField
        CSC_FinishX_EditField           matlab.ui.control.NumericEditField
        CSC_FinishY_EditField           matlab.ui.control.NumericEditField
        CSC_StartkmLabel                matlab.ui.control.Label
        CSC_FinishkmLabel               matlab.ui.control.Label
        CSC_CheckPositionButton         matlab.ui.control.Button
        CSC_CrossMouseClickButton       matlab.ui.control.Button
        CSC_HI_EditField                matlab.ui.control.NumericEditField
        CSC_HI_km_Label                 matlab.ui.control.Label
        CSC_OpenpositionfileButton      matlab.ui.control.Button
        CSC_DepthEditField              matlab.ui.control.NumericEditField
        CSC_depthkm_Label               matlab.ui.control.Label
        CSC_DepthIncEditField           matlab.ui.control.NumericEditField
        CSC_depthinckm_Label            matlab.ui.control.Label
        CSC_DowndipIncEditField         matlab.ui.control.NumericEditField
        CSC_downinckm_Label             matlab.ui.control.Label
        CSC_DipEditField                matlab.ui.control.NumericEditField
        CSC_dip_Label                   matlab.ui.control.Label
        CSC_SectionCalcViewButton       matlab.ui.control.Button
        DipEditField_2Label             matlab.ui.control.Label
        DowndipIncEditFieldLabel        matlab.ui.control.Label
        DepthIncEditFieldLabel          matlab.ui.control.Label
        DepthEditFieldLabel             matlab.ui.control.Label
        HorizontalIncrementEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ISCtoolboxTab                   matlab.ui.container.Tab
        ISCtexttoISCmatButton           matlab.ui.control.Button
        iscurltoiscmatButton            matlab.ui.control.Button
        usgstoISCmatButton              matlab.ui.control.Button
        ISC_Openinputfile_Button        matlab.ui.control.Button
        ISC_input_file_name_Label       matlab.ui.control.Label
        test2Button                     matlab.ui.control.Button
        ISC_FunctionButtonGroup         matlab.ui.container.ButtonGroup
        ISC_location_Button             matlab.ui.control.RadioButton
        ISC_EQMw_timeplot_Button        matlab.ui.control.RadioButton
        ISC_EQdestributions_Button      matlab.ui.control.RadioButton
        ISC_EQdestributions_3D_Button   matlab.ui.control.RadioButton
        ISC_Depth_EQdistribution_time_Button  matlab.ui.control.RadioButton
        ISC_ISCmb_vs_ISCMS_Button       matlab.ui.control.RadioButton
        ISC_ISCmb_vs_GMCTMw_Button      matlab.ui.control.RadioButton
        ISC_ISCMS_vs_GMCTMw_Button      matlab.ui.control.RadioButton
        ISC_Mc_bvalue_mb_Button         matlab.ui.control.RadioButton
        ISC_Mc_bvalue_MS_Button         matlab.ui.control.RadioButton
        ISC_Mc_bvalue_Mw_Button         matlab.ui.control.RadioButton
        ISC_Button1                     matlab.ui.control.RadioButton
        ISC_Button2                     matlab.ui.control.RadioButton
        ISC_Button3                     matlab.ui.control.RadioButton
        ISC_Button4                     matlab.ui.control.RadioButton
        ISC_Button5                     matlab.ui.control.RadioButton
        ISC_Button6                     matlab.ui.control.RadioButton
        ISC_Button7                     matlab.ui.control.RadioButton
        ISC_Button8                     matlab.ui.control.RadioButton
        ISC_Button9                     matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        BCF_USGS_Panel                  matlab.ui.container.Panel
        URLEditField                    matlab.ui.control.EditField
        URLEditFieldLabel               matlab.ui.control.Label
        BCF_FE_EQ_cancelButton          matlab.ui.control.Button
        BCF_FE_EQ_OKButton              matlab.ui.control.Button
        BCF_USGS_text2_Label            matlab.ui.control.Label
        BCF_USGS_text1_Label            matlab.ui.control.Label
        PluginPanel                     matlab.ui.container.Panel
        Plugin_use_label                matlab.ui.control.Label
        Plugin_ListBox                  matlab.ui.control.ListBox
        Plugin_CloseButton              matlab.ui.control.Button
        BuildCMTorfocalmeshdataPanel_2  matlab.ui.container.Panel
        BCF_StudyAreaPanel              matlab.ui.container.Panel
        BCF_StudyAreaLabel              matlab.ui.control.Label
        BCF_RPxy_Label                  matlab.ui.control.Label
        BCF_LongitudeEditField          matlab.ui.control.NumericEditField
        BCF_LatitudeEditField           matlab.ui.control.NumericEditField
        BCF_LongitudeLabel              matlab.ui.control.Label
        BCF_LatitudeLabel               matlab.ui.control.Label
        BCF_incrLabel                   matlab.ui.control.Label
        BCF_maxLabel                    matlab.ui.control.Label
        BCF_minLabel                    matlab.ui.control.Label
        BCF_minLongitudeEditField       matlab.ui.control.NumericEditField
        BCF_maxLongitudeEditField       matlab.ui.control.NumericEditField
        BCF_incrLongitudeEditField      matlab.ui.control.NumericEditField
        BCF_minLatitudeEditField        matlab.ui.control.NumericEditField
        BCF_maxLatitudeEditField        matlab.ui.control.NumericEditField
        BCF_incrLatitudeEditField       matlab.ui.control.NumericEditField
        BCF_CalcButton                  matlab.ui.control.Button
        BCF_AddtomapButton              matlab.ui.control.Button
        BCF_XSLabel                     matlab.ui.control.Label
        BCF_XSEditField                 matlab.ui.control.NumericEditField
        BCF_XSkmLabel                   matlab.ui.control.Label
        BCF_YSLabel                     matlab.ui.control.Label
        BCF_YSEditField                 matlab.ui.control.NumericEditField
        BCF_YSkmLabel                   matlab.ui.control.Label
        BCF_XFLabel                     matlab.ui.control.Label
        BCF_XFEditField                 matlab.ui.control.NumericEditField
        BCF_XFkmLabel                   matlab.ui.control.Label
        BCF_YFLabel                     matlab.ui.control.Label
        BCF_YFEditField                 matlab.ui.control.NumericEditField
        BCF_YFkmLabel                   matlab.ui.control.Label
        BCF_XILabel                     matlab.ui.control.Label
        BCF_XIEditField                 matlab.ui.control.NumericEditField
        BCF_XIkmLabel                   matlab.ui.control.Label
        BCF_YILabel                     matlab.ui.control.Label
        BCF_YIEditField                 matlab.ui.control.NumericEditField
        BCF_YIkmLabel                   matlab.ui.control.Label
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LongitudeEditFieldLabel         matlab.ui.control.Label
        BCF_FaultElementsPanel          matlab.ui.container.Panel
        BCF_FE_Label                    matlab.ui.control.Label
        BCF_FE_TotalNumber              matlab.ui.control.NumericEditField
        BCF_FE_text1_Label              matlab.ui.control.Label
        BCF_FE_TabGroup                 matlab.ui.container.TabGroup
        BCF_FE_RPTab                    matlab.ui.container.Tab
        EarthquakeLocationLabel         matlab.ui.control.Label
        BCF_XSLabel_4                   matlab.ui.control.Label
        BCF_RPlon_EditField             matlab.ui.control.NumericEditField
        BCF_RPlat_EditField             matlab.ui.control.NumericEditField
        BCF_RPdepth_EditField           matlab.ui.control.NumericEditField
        BCF_XSLabel_3                   matlab.ui.control.Label
        BCF_XSLabel_2                   matlab.ui.control.Label
        BCF_RP_Faultnumber_ListBox      matlab.ui.control.ListBox
        FaultnumberListListBoxLabel_3   matlab.ui.control.Label
        BCF_RP_DropDown                 matlab.ui.control.DropDown
        FaultTypeDropDownLabel_2        matlab.ui.control.Label
        BCF_FE_FaultTypeTab             matlab.ui.container.Tab
        BCF_FaultTypeDropDown           matlab.ui.control.DropDown
        BCF_FT_Faultnumber_ListBox      matlab.ui.control.ListBox
        FaultnumberListListBoxLabel_2   matlab.ui.control.Label
        FaultTypeDropDownLabel          matlab.ui.control.Label
        NormalTextAreaLabel             matlab.ui.control.Label
        BCF_AF_ResetButton              matlab.ui.control.Button
        BCF_AllfaultsTextArea           matlab.ui.control.TextArea
        BCF_SS_ResetButton              matlab.ui.control.Button
        BCF_StrikeslipTextArea          matlab.ui.control.TextArea
        BCF_R_ResetButton               matlab.ui.control.Button
        BCF_ReverseTextArea             matlab.ui.control.TextArea
        BCF_N_ResetButton               matlab.ui.control.Button
        BCF_NormalTextArea              matlab.ui.control.TextArea
        BCF_FaultTypeCalcButton         matlab.ui.control.Button
        ReverseTextAreaLabel            matlab.ui.control.Label
        StrikeslipTextAreaLabel         matlab.ui.control.Label
        AllfaultsLabel                  matlab.ui.control.Label
        AddfaultTab                     matlab.ui.container.Tab
        BCF_FE_UITable                  matlab.ui.control.Table
        BCF_FE_CloseButton              matlab.ui.control.Button
        BCF_FE_CalcButton               matlab.ui.control.Button
        BCF_FE_AddtomapButton           matlab.ui.control.Button
        TotalNumberofFaultsEditFieldLabel_3  matlab.ui.control.Label
    end

    properties (Access = public)

        % =====================================================================
        %                アプリで使用するグローバル変数をappに格納
        % =====================================================================
        H_MAIN   % メイングラフィックウィンドウのハンドル

        % スクリーンサイズ制御に関するプロパティ
        SCR_SIZE = struct(...
            'SCRS', [],...   %スクリーンサイズ [左,下,幅,高さ]
            'SCRW_X', [],... % 余白の幅
            'SCRW_Y', []...  % 余白の高さ
        );

        % 入力ファイル関連のプロパティ
        INPUT_VARS = struct(...
            'HEAD', [], ...        % ヘッダー
            'NUM', [], ...         % 総断層数
            'POIS', [], ...        % ポアソン比
            'CALC_DEPTH', [], ...  % 計算深度
            'YOUNG', [], ...       % ヤング率
            'FRIC', [], ...        % 破壊摩擦係数
            'R_STRESS', [], ...    % 地域応力
            'ID', [], ...          % 各入力エレメントの最初の列
            'INUM', [], ...        % 断層番号
            'KODE', [], ...        % 計算のタイプ
            'ELEMENT', [], ...     % 断層の位置情報
            'FCOMMENT', [], ...    % 個々の断層への短いコメント
            'GRID', [], ...        % グリッドパラメータ
            'SIZE', [], ...        % サイズパラメータ
            'SECTION', [] ...      % クロスセクションデフォルト
        );

        % 調査地域と座標制御に関するプロパティ
        COORD_VARS = struct(...
            'ICOORD', [], ...      % 1: xy座標, 2: 経度緯度座標
            'XGRID', [], ...       % グリッドのx座標
            'YGRID', [], ...       % グリッドのy座標
            'XY_RATIO', [], ...    % 縦横比
            'MIN_LAT', [], ...     % 最小緯度
            'MAX_LAT', [], ...     % 最大緯度
            'ZERO_LAT', [], ...    % 緯度の原点
            'MIN_LON', [], ...     % 最小経度
            'MAX_LON', [], ...     % 最大経度
            'ZERO_LON', [], ...    % 経度の原点
            'LON_GRID', [], ...    % 経度のグリッド
            'LAT_GRID', [] ...     % 緯度のグリッド
        );

        % 計算制御に関するプロパティ
        CALC_CONTROL = struct(...
            'IACT', [], ...               % Okadaコードの計算状態 0:未計算 1:計算済み 2:
            'FUNC_SWITCH', [], ...        % 計算のタイプ
            'STRAIN_SWITCH', [], ...      % 歪みのタイプを切り替えるフラグ
            'SHADE_TYPE', [], ...         % シェーディングの種類
            'STRESS_TYPE', [], ...        % レシーバー断層の種類
            'DEPTH_RANGE_TYPE', [], ...   % 計算深度の種類
            'STRIKE', [], ...             % 断層の走向
            'DIP', [], ...                % 断層の傾斜
            'RAKE', [], ...               % レーク角
            'IRAKE', [], ...              % 計算方法のフラグ right-lat & reverse slip format:0 or empty, rake & net-slip format:1
            'MAPTLFLAG', [], ...          % Mapping Toolbox の有無フラグ
            'RECEIVERS', [], ...          % 受信者情報
            'IND_RAKE', [], ...           % 個々のレーク情報
            'IIRET', [] ...               % 結果インデックス
        );

        % 岡田式の計算結果保持用プロパティ
        OKADA_OUTPUT = struct(...
            'DC3D', [], ...               % DC3D計算結果
            'DC3DS', [], ...              % クロスセクション計算用
            'DC3DE', [], ...              % エラー計算用
            'S_ELEMENT', [], ...          % 要素情報
            'CC', [] ...                  % その他の計算結果
        );

        % 断面図に関連するプロパティ
        SECTION_VARS = struct(...
            'SEC_XS', [], ...
            'SEC_YS', [], ...
            'SEC_XF', [], ...
            'SEC_YF', [], ...
            'SEC_INCRE', [], ...
            'SEC_DEPTH', [], ...
            'SEC_DEPTHINC', [], ...
            'SEC_FLAG', [], ...
            'SEC_DIP', [], ...
            'SEC_DOWNDIP_INC', [] ...
        );

        % オーバーレイデータと制御に関連するプロパティ
        OVERLAY_VARS = struct(...
            'COAST_DATA', [], ...         % 海岸線データ
            'AFAULT_DATA', [], ...        % 活断層データ
            'EQ_DATA', [], ...            % 地震データ
            'GNSS_DATA', [], ...          % GNSSデータ
            'GNSS_FLAG', [], ...          % GNSSフラグ
            'GNSS_SEQN_FLAG', [], ...     % GNSSシーケンスフラグ
            'VOLCANO', [], ...            % 火山データ
            'SEISSTATION', [], ...        % 地震観測所データ
            'OVERLAYFLAG', [], ...        % オーバーレイフラグ
            'OVERLAY_MARGIN', [], ...     % オーバーレイのマージン
            'EQPICK_WIDTH', [], ...       % 地震ピックの幅
            'BASEMAP', [] ...             % MappingToolBoxで用いるベースマップ（'名称', 'コピーする地図グラフ'）
        );

        % グラフィック制御に関するプロパティ
        GRAPHICS_VARS = struct(...
            'ANATOLIA', [], ...           % カラーマップ（紫-白-赤）
            'SEIS_RATE', [], ...          % 地震率カラーマップ（青-白-赤）
            'C_SAT', [], ...              % カラーの飽和値
            'CONT_INTERVAL', [] ...       % 等値線の間隔
        );

        % コンピュータID、ディレクトリ制御、設定に関連するプロパティ
        SYSTEM_VARS = struct(...
            'PLATFORM', computer, ...       % コンピュータの種類
            'CURRENT_VERSION', '4.0.0', ... % 現行バージョン
            'PREF_DIR', [], ...             % デフォルトの出力ディレクトリ
            'HOME_DIR', pwd, ...            % ユーザのホームディレクトリ
            'INPUT_FILE', [], ...           % 入力ファイルの名前
            'PREF', [], ...                 % Coulombの初期設定
            'C_SLIP_SAT', [], ...           % スリップ飽和値
            'IVECTOR', [], ...              % ベクトル情報
            'IMAXSHEAR', [] ...             % 最大せん断応力
        );

        % =====================================================================
        %                アプリで使用する補助変数
        % =====================================================================
        % 計算で使用する補助変数のプロパティ
        ASSIST_VARS = struct(...
            'DIALOG_SKIP', [], ...        % 入力履歴に基づき入力方法を変更するフラグ
            'FNUM_ONOFF', [] , ...        % 断層番号を表示するかどうかを制御するフラグ
            'INODAL', [], ...             % ノーダル面識別用
            'COORD', [], ...              % 1: xy座標, 2: 経度緯度座標, ICOORDとの違いは不明
            'BEFORE_ELEMENT', [], ...     % change study areaでのELEMENT一時保存
            'BEFORE_GRID', [], ...        % change study areaでのGRID一時保存
            'BEFORE_MINLON', [], ...      % change study areaでのMINLON一時保存
            'BEFORE_MAXLON', [], ...      % change study areaでのMAXLON一時保存
            'BEFORE_MINLAT', [], ...      % change study areaでのMINLAT一時保存
            'BEFORE_MAXLAT', [], ...      % change study areaでのMAXLAT一時保存
            'DISP_SCALE', [], ...         %
            'FIXX', [], ...               % 固定点のx座標
            'FIXY', [], ...               % 固定点のy座標
            'FIXFLAG', [], ...            % 0: 固定点なし, 1: 固定点あり
            'XYCOORD', [], ...            % 
            'IACTS', [], ...              % 0: 最初の弾性体計算，1:一度計算済みのものを保持する場合
            'PHII', [], ...               % 
            'A_GRID', [], ...             % グリッドのハンドル、delete(app.ASSIST_VARS.A_GRID) で削除したりできる
            'VIEW_AZ', [], ...            % 3Dビューの指定 (初期方位角、度単位)
            'VIEW_EL', [], ...            % 3Dビューの指定 (初期垂直角度、度単位)
            'F3D_SLIP_TYPE', [], ...      % すべりの種類　1:ネットスリップ
            'FLAG_SLIP_LINE', [], ...     % Coulombの結果表示の際に各ノードに断層走向を示すかどうか(Coulomb windowのチェックマーク　0:示さない，1:示す)
            'FLAG_PR_AXES', [], ...       % Coulombの結果表示の際に各ノードに応力主軸を示すかどうか(Coulomb windowのチェックマーク　0:示さない，1:示す)
            'FLAG_DEPTH', [], ...         % 
            'VD_CHECKED', [], ...         %
            'NODAL_ACT', [], ...          %
            'NODAL_STRESS', [], ...       %
            'AV_STRIKE', [], ...          % 平均走向角度
            'AV_DIP', [], ...             % 平均傾斜角度
            'AV_DEPTH', [], ...           % 平均深度
            'AV_RAKE', [], ...            % 平均すべり角（レイク）
            'ELE_STRIKE', [], ...         % エレメントの走行角度
            'LON_PER_X', [], ...          % デカルト座標Xの1kmあたりの地図座標°
            'LAT_PER_Y', [], ...          % デカルト座標Yの1kmあたりの地図座標°
            'N_CELL', [], ...             % 断層のエレメント（小断層）の総数．分割する前の情報を保持するため
            'NDEPTH', [], ...             % たぶん（計算深度の数，複数深度面で最大・平均応力を計算する際のループに使用）
            'NXINC', [], ...              %
            'NYINC', [], ...              % 
            'NSEC', [], ...               %
            'NCOUNT', [], ...             %
            'nsec', [], ...               % 
            'ndepth', [], ...             % 
            'pt_rs', [], ...              % stress計算におけるstlike lineの描画に用いる
            'strike2', [], ...            % stress計算におけるstlike lineの描画に用いる
            'AX', [], ...                 % 
            'AY', [], ...                 % 
            'AZ', [], ...                 % 
            'CALC_DEPTH_RANGE', [], ...   % 複数の計算深度があるときの複数の深さをベクトルで格納，深さ方向に正，単位km
            'CALC_DEPTH_TOP', [], ...     % 
            'CALC_DEPTH_BOTTOM', [], ...  %
            'CALC_DEPTH_INC', [], ...     %
            'COULOMB_RIGHT', [], ...      % 特定断層面上の応力計算での剪断応力変化値（水平成分），特に数値ファイル出力のため
            'COULOMB_UP', [], ...         % 特定断層面上の応力計算での剪断応力変化値（上下成分，逆・正），特に数値ファイル出力のため
            'COULOMB_PREF', [], ...       % 特定断層面上の応力計算でのユーザーが指定したレイクへのクーロン応力変化値，特に数値ファイル出力のため
            'COULOMB_RAKE', [], ...       % 特定断層面上の応力計算での入力ファイルが指定したレイクへのクーロン応力変化値，特に数値ファイル出力のため
            'EC_RAKE', [], ...            % 特定断層面上の応力計算でのユーザーがdialogを通して指定したレイク
            'EC_NORMAL', [], ...          % 特定断層面上の応力計算での法線応力変化
            'EC_STRESS_TYPE', [], ...     % 特定断層面状の応力計算での計算タイプ，ラジオボタンに対応．1:横ずれ，2:縦ずれ（正・逆），3:ユーザ指定のレイク，4:入力ファイルにあるレイク
            'NORMAL', [], ...             %
            'SHEAR', [], ...              %
            'PK_DEPTH', [], ...           %
            'IRET', [], ...               %
            'GTEXT_DATA', [], ...         %
            'NH_COMMENT', [], ...         %
            'C_POINT', [], ...            %
            'XSEC_FLAG', [], ...          % クロスセクションが計算済み（１）かどうか（未計算：０）
            'TAPER_CALLED', [], ...       % テーパー関連
            'CLICK_SPLIT', [], ...        % 
            'CLICK_TAPER', [], ...        % 
            'N_STR', [], ...              % along strikeでの分割の個数
            'N_DIP', [], ...              % along down dipでの分割の個数
            'TD_STR', [], ...             % dist. fr. edges along strikeの値
            'TD_DIP', [], ...             % down dipの値
            'SD_LIMIT', [], ...           %
            'DD_LIMIT', [], ...           %
            'TN', [], ...                 % No. of nested patchesの値
            'EQ_DIR', [], ...             % 地震データのディレクトリ
            'EQ_ZFORMAT_DATA', [], ...    % カタログの地震データ 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min, 10)strike1, 11)dip1, 12)rake1, 13)strike2, 14)dip2, 15)rake2
            'EQ_EDIT_ZFORMAT', [], ...    % カタログの絞り込みを行った場合、保存しておく
            'EQ_FLAG', [], ...            % 読み込みが正しく終了したかどうかのフラグ（）
            'EDS_FLAG', [], ...           % スクリーニングパネルの結果（0: OK, 1:Cancel）
            'MAX_EQ_TIME', [], ...        % 地震カタログの収録終了時間[year, month, day, hour, min]
            'MIN_EQ_TIME', [], ...        % 地震カタログの収録開始時間
            'UTM_FLAG', [], ...           % 0の場合は座標の確認のみ、1の場合は入力ファイルを作成
            'INC_LON', [], ...            % BCFパネルで使用する、新たに作るメッシュの増分
            'INC_LAT', [], ...            % BCFパネルで使用する、新たに作るメッシュの増分
            'XS', [], ...                 % BCFパネルで使用する
            'YS', [], ...                 % BCFパネルで使用する
            'XF', [], ...                 % BCFパネルで使用する
            'YF', [], ...                 % BCFパネルで使用する
            'PC_LON', [], ...             % 
            'PC_LAT', [], ...             % 
            'EQ_LON', [], ...             % 地震位置の経度
            'EQ_LAT', [], ...             % 地震位置の緯度
            'EQ_DEPTH', [], ...           % 地震の深さ
            'EQ_LENGTH', [], ...          % 断層の長さ
            'EQ_WIDTH', [], ...           % 断層の幅
            'EQ_MW', [], ...              % モーメントマグニチュード
            'EQ_STRIKE', [], ...          % 断層の走行（方位角）
            'EQ_DIP', [], ...             % 断層のディップ（傾斜角）
            'EQ_RAKE', [], ...            % 断層のレイク（すべり角）
            'f_stress', [], ...           % focal mesh calcにて使用
            'COLORSN', [], ...            % たぶん（凡例の色の飽和値）
            'ZSCALE_3D', [], ...          %
            'CURRENT_3D_MATFILE', [] ...  % 現在描画に用いている3D matファイル
        );

        % 岡田式の計算で使用する補助プロパティ
        OKADA_CALC_VARS = struct( ...
            'ALP1', [], ...  %
            'ALP2', [], ...  %
            'ALP3', [], ...  %
            'ALP4', [], ...  %
            'ALP5', [], ...  %
            'SD', [], ...    %
            'CD', [], ...    %
            'SDSD', [], ...  %
            'CDCD', [], ...  %
            'SDCD', [], ...  %
            'S2D', [], ...   %
            'C2D', [], ...   %
            'P', [], ...     %
            'Q', [], ...     %
            'S', [], ...     %
            'T', [], ...     %
            'XY', [], ...    %
            'X2', [], ...    %
            'Y2', [], ...    %
            'D2', [], ...    %
            'R', [], ...     %
            'R2', [], ...    %
            'R3', [], ...    %
            'R5', [], ...    %
            'QR', [], ...    %
            'QRX', [], ...   %
            'A3', [], ...    %
            'A5', [], ...    %
            'B3', [], ...    %
            'C3', [], ...    %
            'UY', [], ...    %
            'VY', [], ...    %
            'WY', [], ...    %
            'UZ', [], ...    %
            'VZ', [], ...    %
            'WZ', [], ...    %
            'XI2', [], ...   %
            'ET2', [], ...   %
            'Q2', [], ...    %
            'Y', [], ...     %
            'D', [], ...     %
            'TT', [], ...    %
            'ALX', [], ...   %
            'ALE', [], ...   %
            'X11', [], ...   %
            'Y11', [], ...   %
            'X32', [], ...   %
            'Y32', [], ...   %
            'EY', [], ...    %
            'EZ', [], ...    %
            'FY', [], ...    %
            'FZ', [], ...    %
            'GY', [], ...    %
            'GZ', [], ...    %
            'HY', [], ...    %
            'HZ', [] ...     %
        );

        % ハンドルのプロパティ
        HANDLE_VARS = struct(...
            'H_INPUT', [], ...            % input_windowのハンドル？
            'H_UTM', [] ...              % 
        );
    end

    properties (Access = public)
        % 戻るボタンを実装するための変数の一時保存プロパティ
        TEMP_INPUT_VARS = struct(...
            'TEMP_HEAD', [], ...        % ヘッダー
            'TEMP_NUM', [], ...         % 総断層数
            'TEMP_POIS', [], ...        % ポアソン比
            'TEMP_CALC_DEPTH', [], ...  % 計算深度
            'TEMP_YOUNG', [], ...       % ヤング率
            'TEMP_FRIC', [], ...        % 破壊摩擦係数
            'TEMP_R_STRESS', [], ...    % 地域応力
            'TEMP_ID', [], ...          % 各入力エレメントの最初の列
            'TEMP_INUM', [], ...        % 断層の総数
            'TEMP_KODE', [], ...        % 計算のタイプ
            'TEMP_ELEMENT', [], ...     % 断層の位置情報
            'TEMP_FCOMMENT', [], ...    % 個々の断層への短いコメント
            'TEMP_GRID', [], ...        % グリッドパラメータ
            'TEMP_SIZE', [], ...        % サイズパラメータ
            'TEMP_SECTION', [] ...      % クロスセクションデフォルト
        );

        TEMP_COORD_VARS = struct(...
            'TEMP_ICOORD', [], ...      % 1: xy座標, 2: 経度緯度座標
            'TEMP_XGRID', [], ...       % グリッドのx座標
            'TEMP_YGRID', [], ...       % グリッドのy座標
            'TEMP_XY_RATIO', [], ...    % 縦横比
            'TEMP_MIN_LAT', [], ...     % 最小緯度
            'TEMP_MAX_LAT', [], ...     % 最大緯度
            'TEMP_ZERO_LAT', [], ...    % 緯度の原点
            'TEMP_MIN_LON', [], ...     % 最小経度
            'TEMP_MAX_LON', [], ...     % 最大経度
            'TEMP_ZERO_LON', [], ...    % 経度の原点
            'TEMP_LON_GRID', [], ...    % 経度のグリッド
            'TEMP_LAT_GRID', [] ...     % 緯度のグリッド
        );

        TEMP_CALC_CONTROL = struct(...
            'TEMP_IACT', [], ...               % Okadaコードの計算状態
            'TEMP_FUNC_SWITCH', [], ...        % 計算のタイプ
            'TEMP_STRAIN_SWITCH', [], ...      % 歪みのタイプを切り替えるフラグ
            'TEMP_SHADE_TYPE', [], ...         % シェーディングの種類
            'TEMP_STRESS_TYPE', [], ...        % レシーバー断層の種類
            'TEMP_DEPTH_RANGE_TYPE', [], ...   % 計算深度の種類
            'TEMP_STRIKE', [], ...             % 断層の走向
            'TEMP_DIP', [], ...                % 断層の傾斜
            'TEMP_RAKE', [], ...               % レーク角
            'TEMP_IRAKE', [], ...              % 計算方法のフラグ
            'TEMP_MAPTLFLAG', [], ...          % Mapping Toolbox の有無フラグ
            'TEMP_RECEIVERS', [], ...          % 受信者情報
            'TEMP_IND_RAKE', [], ...           % 個々のレーク情報
            'TEMP_IIRET', [] ...               % 結果インデックス
        );

        TEMP_OKADA_OUTPUT = struct(...
            'TEMP_DC3D', [], ...               % DC3D計算結果
            'TEMP_DC3DS', [], ...              % クロスセクション計算用
            'TEMP_DC3DE', [], ...              % エラー計算用
            'TEMP_S_ELEMENT', [], ...          % 要素情報
            'TEMP_CC', [] ...                  % その他の計算結果
        );

        TEMP_SECTION_VARS = struct(...
            'TEMP_SEC_XS', [], ...
            'TEMP_SEC_YS', [], ...
            'TEMP_SEC_XF', [], ...
            'TEMP_SEC_YF', [], ...
            'TEMP_SEC_INCRE', [], ...
            'TEMP_SEC_DEPTH', [], ...
            'TEMP_SEC_DEPTHINC', [], ...
            'TEMP_SEC_FLAG', [], ...
            'TEMP_SEC_DIP', [], ...
            'TEMP_SEC_DOWNDIP_INC', [] ...
        );

        TEMP_OVERLAY_VARS = struct(...
            'TEMP_COAST_DATA', [], ...         % 海岸線データ
            'TEMP_AFAULT_DATA', [], ...        % 活断層データ
            'TEMP_EQ_DATA', [], ...            % 地震データ
            'TEMP_GNSS_DATA', [], ...          % GNSSデータ
            'TEMP_GNSS_FLAG', [], ...          % GNSSフラグ
            'TEMP_GNSS_SEQN_FLAG', [], ...     % GNSSシーケンスフラグ
            'TEMP_VOLCANO', [], ...            % 火山データ
            'TEMP_SEISSTATION', [], ...        % 地震観測所データ
            'TEMP_OVERLAYFLAG', [], ...        % オーバーレイフラグ
            'TEMP_OVERLAY_MARGIN', [], ...     % オーバーレイのマージン
            'TEMP_EQPICK_WIDTH', [], ...       % 地震ピックの幅
            'TEMP_BASEMAP', [] ...             % MappingToolBoxで用いるベースマップ（'名称', 'コピーする地図グラフ'）
        );

        TEMP_GRAPHICS_VARS = struct(...
            'TEMP_ANATOLIA', [], ...           % カラーマップ（紫-白-赤）
            'TEMP_SEIS_RATE', [], ...          % 地震率カラーマップ（青-白-赤）
            'TEMP_C_SAT', [], ...              % カラーの飽和値
            'TEMP_CONT_INTERVAL', [] ...       % 等値線の間隔
        );

        TEMP_SYSTEM_VARS = struct(...
            'TEMP_PLATFORM', computer, ...       % コンピュータの種類
            'TEMP_CURRENT_VERSION', '4.0.0', ... % 現行バージョン
            'TEMP_PREF_DIR', [], ...             % デフォルトの出力ディレクトリ
            'TEMP_HOME_DIR', pwd, ...            % ユーザのホームディレクトリ
            'TEMP_INPUT_FILE', [], ...           % 入力ファイルの名前
            'TEMP_PREF', [], ...                 % Coulombの初期設定
            'TEMP_C_SLIP_SAT', [], ...           % スリップ飽和値
            'TEMP_IVECTOR', [], ...              % ベクトル情報
            'TEMP_IMAXSHEAR', [] ...             % 最大せん断応力
        );

        TEMP_ASSIST_VARS = struct(...
            'TEMP_DIALOG_SKIP', [], ...        % 入力履歴に基づき入力方法を変更するフラグ
            'TEMP_FNUM_ONOFF', [] , ...        % 断層番号を表示するかどうかを制御するフラグ
            'TEMP_INODAL', [], ...             % ノーダル面識別用
            'TEMP_COORD', [], ...              % 1: xy座標, 2: 経度緯度座標, ICOORDとの違いは不明
            'TEMP_BEFORE_ELEMENT', [], ...     % change study areaでのELEMENT一時保存
            'TEMP_BEFORE_GRID', [], ...        % change study areaでのGRID一時保存
            'TEMP_BEFORE_MINLON', [], ...      % change study areaでのMINLON一時保存
            'TEMP_BEFORE_MAXLON', [], ...      % change study areaでのMAXLON一時保存
            'TEMP_BEFORE_MINLAT', [], ...      % change study areaでのMINLAT一時保存
            'TEMP_BEFORE_MAXLAT', [], ...      % change study areaでのMAXLAT一時保存
            'TEMP_DISP_SCALE', [], ...         
            'TEMP_FIXX', [], ...               % 固定点のx座標
            'TEMP_FIXY', [], ...               % 固定点のy座標
            'TEMP_FIXFLAG', [], ...            % 0: 固定点なし, 1: 固定点あり
            'TEMP_XYCOORD', [], ...            
            'TEMP_IACTS', [], ...              % 0: 最初の弾性体計算，1:一度計算済みのものを保持する場合
            'TEMP_PHII', [], ...               
            'TEMP_A_GRID', [], ...            
            'TEMP_VIEW_AZ', [], ...            % 3Dビューの指定 (初期方位角、度単位)
            'TEMP_VIEW_EL', [], ...            % 3Dビューの指定 (初期垂直角度、度単位)
            'TEMP_F3D_SLIP_TYPE', [], ...      % すべりの種類　1:ネットスリップ
            'TEMP_FLAG_SLIP_LINE', [], ...     % Coulombの結果表示の際に各ノードに断層走向を示すかどうか
            'TEMP_FLAG_PR_AXES', [], ...       % Coulombの結果表示の際に各ノードに応力主軸を示すかどうか
            'TEMP_FLAG_DEPTH', [], ...         
            'TEMP_VD_CHECKED', [], ...         
            'TEMP_NODAL_ACT', [], ...          
            'TEMP_NODAL_STRESS', [], ...       %
            'TEMP_AV_STRIKE', [], ...          % 平均走向角度
            'TEMP_AV_DIP', [], ...             % 平均傾斜角度
            'TEMP_AV_DEPTH', [], ...           % 平均深度
            'TEMP_AV_RAKE', [], ...            % 平均すべり角（レイク）
            'TEMP_ELE_STRIKE', [], ...         % エレメントの走行角度
            'TEMP_LON_PER_X', [], ...          % デカルト座標Xの1kmあたりの地図座標°
            'TEMP_LAT_PER_Y', [], ...          % デカルト座標Yの1kmあたりの地図座標°
            'TEMP_N_CELL', [], ...             % 断層のエレメント（小断層）の総数
            'TEMP_NDEPTH', [], ...             
            'TEMP_NXINC', [], ...              
            'TEMP_NYINC', [], ...              
            'TEMP_NSEC', [], ...               
            'TEMP_NCOUNT', [], ...             
            'TEMP_nsec', [], ...               
            'TEMP_ndepth', [], ...     
            'TEMP_strike2', [], ...
            'TEMP_pt_rs', [], ...
            'TEMP_AX', [], ...                 
            'TEMP_AY', [], ...                 
            'TEMP_AZ', [], ...                 
            'TEMP_CALC_DEPTH_RANGE', [], ...   
            'TEMP_CALC_DEPTH_TOP', [], ...     
            'TEMP_CALC_DEPTH_BOTTOM', [], ...  
            'TEMP_CALC_DEPTH_INC', [], ...     
            'TEMP_COULOMB_RIGHT', [], ...      
            'TEMP_COULOMB_UP', [], ...         
            'TEMP_COULOMB_PREF', [], ...       
            'TEMP_COULOMB_RAKE', [], ...       
            'TEMP_EC_RAKE', [], ...            
            'TEMP_EC_NORMAL', [], ...          
            'TEMP_EC_STRESS_TYPE', [], ...     
            'TEMP_NORMAL', [], ...             
            'TEMP_SHEAR', [], ...              
            'TEMP_PK_DEPTH', [], ...           
            'TEMP_IRET', [], ...               
            'TEMP_GTEXT_DATA', [], ...         
            'TEMP_NH_COMMENT', [], ...         
            'TEMP_C_POINT', [], ...            
            'TEMP_XSEC_FLAG', [], ...          % クロスセクションが計算済み（１）かどうか
            'TEMP_TAPER_CALLED', [], ...       
            'TEMP_CLICK_SPLIT', [], ...        
            'TEMP_CLICK_TAPER', [], ...        
            'TEMP_N_STR', [], ...              
            'TEMP_N_DIP', [], ...              
            'TEMP_TD_STR', [], ...             
            'TEMP_TD_DIP', [], ...             
            'TEMP_SD_LIMIT', [], ...           
            'TEMP_DD_LIMIT', [], ...           
            'TEMP_TN', [], ...                 
            'TEMP_EQ_DIR', [], ...             
            'TEMP_EQ_ZFORMAT_DATA', [], ...    
            'TEMP_EQ_EDIT_ZFORMAT', [], ...    
            'TEMP_EQ_FLAG', [], ...     
            'TEMP_EDS_FLAG', [], ...           
            'TEMP_MAX_EQ_TIME', [], ...        
            'TEMP_MIN_EQ_TIME', [], ...        
            'TEMP_UTM_FLAG', [], ...           
            'TEMP_INC_LON', [], ...            % BCFパネルで使用する、新たに作るメッシュの増分
            'TEMP_INC_LAT', [], ...            % BCFパネルで使用する、新たに作るメッシュの増分
            'TEMP_XS', [], ...                 % BCFパネルで使用する
            'TEMP_YS', [], ...                 % BCFパネルで使用する
            'TEMP_XF', [], ...                 % BCFパネルで使用する
            'TEMP_YF', [], ...                 % BCFパネルで使用する
            'TEMP_PC_LON', [], ...             
            'TEMP_PC_LAT', [], ...             
            'TEMP_EQ_LON', [], ...             
            'TEMP_EQ_LAT', [], ...             
            'TEMP_EQ_DEPTH', [], ...           
            'TEMP_EQ_LENGTH', [], ...          
            'TEMP_EQ_WIDTH', [], ...           
            'TEMP_EQ_MW', [], ...              
            'TEMP_EQ_STRIKE', [], ...          
            'TEMP_EQ_DIP', [], ...             
            'TEMP_EQ_RAKE', [], ...  
            'TEMP_f_stress', [], ...           % focal mesh calcにて使用
            'TEMP_COLORSN', [], ... 
            'TEMP_ZSCALE_3D', [], ...
            'TEMP_CURRENT_3D_MATFILE', [] ...  % 現在描画に用いている3D matファイル
        );

        TEMP_OKADA_CALC_VARS = struct( ...
            'TEMP_SD', [], ...    %
            'TEMP_CD', [], ...    %
            'TEMP_SDSD', [], ...  %
            'TEMP_CDCD', [], ...  %
            'TEMP_SDCD', [], ...  %
            'TEMP_S2D', [], ...   %
            'TEMP_C2D', [], ...   %
            'TEMP_ALP1', [], ...  %
            'TEMP_ALP2', [], ...  %
            'TEMP_ALP3', [], ...  %
            'TEMP_ALP4', [], ...  %
            'TEMP_ALP5', [], ...  %
            'TEMP_P', [], ...     %
            'TEMP_Q', [], ...     %
            'TEMP_S', [], ...     %
            'TEMP_T', [], ...     %
            'TEMP_XY', [], ...    %
            'TEMP_X2', [], ...    %
            'TEMP_Y2', [], ...    %
            'TEMP_D2', [], ...    %
            'TEMP_R', [], ...     %
            'TEMP_R2', [], ...    %
            'TEMP_R3', [], ...    %
            'TEMP_R5', [], ...    %
            'TEMP_QR', [], ...    %
            'TEMP_QRX', [], ...   %
            'TEMP_A3', [], ...    %
            'TEMP_A5', [], ...    %
            'TEMP_B3', [], ...    %
            'TEMP_C3', [], ...    %
            'TEMP_UY', [], ...    %
            'TEMP_VY', [], ...    %
            'TEMP_WY', [], ...    %
            'TEMP_UZ', [], ...    %
            'TEMP_VZ', [], ...    %
            'TEMP_WZ', [], ...    %
            'TEMP_XI2', [], ...   %
            'TEMP_ET2', [], ...   %
            'TEMP_Q2', [], ...    %
            'TEMP_Y', [], ...     %
            'TEMP_D', [], ...     %
            'TEMP_TT', [], ...    %
            'TEMP_ALX', [], ...   %
            'TEMP_ALE', [], ...   %
            'TEMP_X11', [], ...   %
            'TEMP_Y11', [], ...   %
            'TEMP_X32', [], ...   %
            'TEMP_Y32', [], ...   %
            'TEMP_EY', [], ...    %
            'TEMP_EZ', [], ...    %
            'TEMP_FY', [], ...    %
            'TEMP_FZ', [], ...    %
            'TEMP_GY', [], ...    %
            'TEMP_GZ', [], ...    %
            'TEMP_HY', [], ...    %
            'TEMP_HZ', [] ...     %
        );

    end
    
    properties (Access = private)
        % 初期値を保存する構造体
        DefaultValues 

        % taper & splitで変更前の変数を保存しておく構造体
        BACKUP_VARS

        % ISC toolboxで用いるデータ
        allPrimes table
        allMagnitudes table
        allHypocentres table
        allFocalMechanisms

        % Primes table
        % Hypocentres table
        % Magnitudes table 
        % Phases table
        
        % Primes table = table([], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], ...
        %     'VariableNames', {'Date','Err','RMS','Latitude','Longitude','Smaj','Smin','Az','Depth', ...
        %                       'EpicentreFixed','Err1','Ndef','Nsta','Gap','mdist','Qual','Author','OrigID','EventID'});
        % 
        % Hypocentres table = table([], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], [], ...
        %     'VariableNames', {'Date','Err','RMS','Latitude','Longitude','Smaj','Smin','Az','Depth', ...
        %                       'EpicentreFixed','Err1','Ndef','Nsta','Gap','mdist','Qual','Author','OrigID','EventID'});
        % 
        % Magnitudes table = table(categorical(), [], [], [], categorical(), [], ...
        %     'VariableNames', {'MagnitudeType','Magnitude','Err','Nsta','Author','OrigID'});
        % 
        % Phases table = table(categorical(), [], [], categorical(), duration.empty(), [], [], [], [], [], ...
        %             categorical(), categorical(), categorical(), [], [], [], categorical(), categorical(), ...
        %             categorical(), categorical(), categorical(), categorical(), [], categorical(), ...
        %             categorical(), categorical(), categorical(), categorical(), categorical(), ...
        %             categorical(), categorical(), double.empty(), double.empty(), ...
        %             'VariableNames', {'Sta','Dist','EvAz','Phase','Time','TRes','Azim','AzRes','Slow','SlowRes', ...
        %                               'Tdef','Adef','Sdef','SNR','Amp','Per','Pick','Pol','Qual','MagType', ...
        %                               'MinMax','Mag','ArrID','Agency','Deploy','Loc','Auth','Report', ...
        %                               'Chan','AChan','LpFm','StaLat','StaLon'});

        Primes
        Hypocentres
        Magnitudes
        Phases
        FocalMechanisms


        allPrimesBefore table

        % --- geo ---
        GeoAxes3 matlab.graphics.axis.GeographicAxes
        GeoPanel3 matlab.ui.container.Panel
        GeoGrid3 matlab.ui.container.GridLayout
        GeoColorbar3 matlab.graphics.illustration.ColorBar
    
        % --- time plot ---
        TimePanel3 matlab.ui.container.Panel
        TimeGrid3 matlab.ui.container.GridLayout
        TimeAxesTop matlab.ui.control.UIAxes
        TimeAxesBottom matlab.ui.control.UIAxes

        % --- EQ distribution ---
        DistPanel3 matlab.ui.container.Panel
        DistGrid3  matlab.ui.container.GridLayout
    
        DistGeoAxes matlab.graphics.axis.GeographicAxes
        DistAxLat   matlab.ui.control.UIAxes
        DistAxLon   matlab.ui.control.UIAxes
    
        DistRightPanel matlab.ui.container.Panel
        DistRightGrid  matlab.ui.container.GridLayout
        DistCbarAxes   matlab.ui.control.UIAxes
        DistLogoAxes   matlab.ui.control.UIAxes
    
        DistColorbar matlab.graphics.illustration.ColorBar

        % --- EQ distribution 3D ---
        Dist3DPanel matlab.ui.container.Panel
        Dist3DAxes  matlab.ui.control.UIAxes
        Dist3DCbar  matlab.graphics.illustration.ColorBar

        % --- Depth EQ distribution Time ---
        DepthTimePanel4 matlab.ui.container.Panel
        DepthTimeGrid4  matlab.ui.container.GridLayout
    
        AxDepthTimeFull matlab.ui.control.UIAxes   % (1,1)-(1,2)
        AxDepthHistFull matlab.ui.control.UIAxes   % (1,3)
        AxDepthTime50   matlab.ui.control.UIAxes   % (2,1)-(2,2)
        AxDepthHist50   matlab.ui.control.UIAxes   % (2,3)
        AxDepthLegend   matlab.ui.control.UIAxes   % (3,3) legend用

        % --- ISC mb vs ISC MS ---
        MagCompPanel matlab.ui.container.Panel
        MagCompAxes  matlab.ui.control.UIAxes
        % 
        % isc_mbs  double
        % gcmt_mws double
        % isc_mSs  double
    end

    methods (Access = public)
        % ==========================================================================================================================================
        %                input, displ function
        % ==========================================================================================================================================

        function shouldContinue = input_open(app, N)
            % =====================================================================
            %                input_open(app, N)
            % =====================================================================
            % Coulomb形式の入力ファイルを読み込む関数
            % input_open はCoulomb形式の入力ファイルをMATLABに読み込みます。

            % Nは、ファイル内容を表示するかどうかを決定するための番号です。
            %   N=1: ダイアログを表示してパラメータの確認と変更を行う
            %   N=2: 新しいファイルを作成する
            %   N=3: ダイアログを表示せずにスキップする

            % shouldContinueは、中断された場合にfalseを返す。

            % デフォルトは続行
            shouldContinue = true;

            % 初期化
            app.CALC_CONTROL.IACT  = 0;
            app.ASSIST_VARS.IACTS = 0;
            app.ASSIST_VARS.FLAG_SLIP_LINE = 0;
            num_buffer = 100; % NUMを調整するためのバッファ

            % DIALOG_SKIP が未定義の場合のみ初期化
            if isempty(app.ASSIST_VARS.DIALOG_SKIP)
                app.ASSIST_VARS.DIALOG_SKIP = 0;
            end

            % DIALOG_SKIP がスカラー値であることを確認
            if ~isscalar(app.ASSIST_VARS.DIALOG_SKIP)
                error('app.ASSIST_VARS.DIALOG_SKIP must be a scalar value.');
            end

            % ディレクトリを移動
            cd(app.SYSTEM_VARS.HOME_DIR);
            % ダイアログがスキップされていない場合、またはDIALOG_SKIPが空の場合
            % つまり、直前の入力ファイルを再読み込みしない場合
            if app.ASSIST_VARS.DIALOG_SKIP ~= 1
                % input_filesのディレクトリに移動する処理
                try
                    % 'input_files' のディレクトリを設定
                    defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_files');
                catch
                    cd(app.SYSTEM_VARS.HOME_DIR); % エラーが出た場合はHOME_DIRへ
                    % 中断
                    shouldContinue = false;
                    return;
                end

                % ユーザーにファイルを選択させるダイアログを表示
                try
                    [fileName, filePath] = uigetfile( ...
                        { ...
                            '*.inp;*.inr;*.mat;*.csv', 'Open input file (*.inp, *.inr, *.mat, *.csv)'; ...
                            '*.inp',  'ASCII input (*.inp)'; ...
                            '*.inr',  'ASCII input (*.inr)'; ...
                            '*.mat',  'Binary input (*.mat)'; ...
                            '*.csv',  'CSV input (*.csv)' ...
                        }, ...
                        'Select Existing Input File', ...
                        defaultDir);

                    % 直前に使用したファイルを読み込む際のディレクトリを保存
                    app.SYSTEM_VARS.PREF_DIR   = filePath;
                    app.SYSTEM_VARS.INPUT_FILE = fileName;

                    cd(app.SYSTEM_VARS.HOME_DIR);
                    % キャンセルが選択された場合の処理
                    if isequal(fileName, 0) || isequal(filePath, 0)
                        cd(app.SYSTEM_VARS.HOME_DIR);
                        uialert(app.main_menu_window, 'File selection canceled.', 'Information', 'Icon', 'info');
                        app.CALC_CONTROL.IACT = 1; % 前回のセッション情報を保持するため
                        % 中断
                        shouldContinue = false;
                        % ディレクトリを移動
                        cd(app.SYSTEM_VARS.HOME_DIR);
                        return;
                    end
                catch
                    cd(app.SYSTEM_VARS.HOME_DIR);
                    uialert(app.main_menu_window, 'You might have opened wrong formatted file.', 'Error Message', 'Icon', 'error');

                    % 中断
                    shouldContinue = false;
                    % ディレクトリを移動
                    cd(app.SYSTEM_VARS.HOME_DIR);
                    return;
                end

            elseif app.ASSIST_VARS.DIALOG_SKIP == 1
                % DIALOG_SKIPが1のとき、つまり直前の入力ファイルを再読み込みする場合
                filePath = app.SYSTEM_VARS.PREF_DIR;
                fileName = app.SYSTEM_VARS.INPUT_FILE;

                if strcmp(fileName, 'empty')
                    uialert(app.main_menu_window, 'No file most recently used. Use menu "Open existing input file."', 'Error Message', 'Icon', 'error');
                    % 中断
                    shouldContinue = false;
                    % ディレクトリを移動
                    cd(app.SYSTEM_VARS.HOME_DIR);
                    return;
                end

                uialert(app.main_menu_window, ['User selected', fullfile(filePath, fileName)], 'Information', 'Icon', 'info');
                showMessage(app, ['User selected', fullfile(filePath, fileName)], false);
                app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
            end

            % フルパスの作成
            fullFilePath = fullfile(filePath, fileName);

            % ディレクトリを移動
            cd(app.SYSTEM_VARS.HOME_DIR);

            % ファイルの拡張子を取得
            [~, ~, ext] = fileparts(fileName);

            % --------------.csvファイルの場合の処理の読み込み処理-----------------
            % 拡張子が.csvで、Nが2でない場合（新規ファイルを開く場合）
            if strcmp(ext, '.csv') & N ~= 2
                % csvが読み込まれたことを表示
                uialert(app.main_menu_window, 'CSV file detected. Reading data from CSV...', 'Information', 'Icon', 'info');

                % 既存のCSVを読み込み
                existingData = readcell(fullFilePath, 'TextType', 'string');

                % "missing" 型のデータを "" に変換
                existingData = cellfun(@(x) replaceMissing(x), existingData, 'UniformOutput', false);

                % csvデータの最も右の列数を取得
                maxCols = size(existingData, 2);

                % 2列目〜maxCols列目に対応する列ラベルを作成（2列目などの番号）
                colLabels = arrayfun(@(i) sprintf('Column %d', i), 2:maxCols, 'UniformOutput', false);

                % ユーザーに列選択ダイアログを表示（複数選択OK）
                [selectionIdx, ok] = listdlg( ...
                    'PromptString', 'Select one or more columns to import:', ...
                    'SelectionMode', 'multiple', ...
                    'ListString', colLabels, ...
                    'Name', 'Select Columns');

                % キャンセルや選択なしは中止
                if ~ok || isempty(selectionIdx)
                    uialert(app.main_menu_window, 'No columns selected. Operation canceled.', 'Warning!');
                    % 中断
                    shouldContinue = false;
                    return;
                end                

                % 実際の列番号（2列目以降を選ばせてるので +1 にする）
                selectedCols = selectionIdx + 1;

                % 選択された列のデータ（2行目以降）を抽出
                selectedData = existingData(2:end, selectedCols);

                % === 選択列の一致条件の確認 ===
                % 複数列のデータを読み込んだあとに、特定の行範囲に対して "同じ行内の値がすべて同じか" をチェック
                % 満たされない場合は処理を止める
                % 条件チェック対象行（CSVの行番号）
                targetRows = [5:20, 27, 35:40, 42:44, 46:52, 54:59];

                % 処理継続フラグ
                canProceed = true;
                violatedRows = [];

                % チェック処理
                for row = targetRows
                    values = selectedData(row - 1, :);  % -1: selectedDataは2行目以降を対象にしているため
                    firstVal = values{1};

                    % 値がすべて一致しているか確認（NaNや空文字なども含む）
                    isEqual = all(cellfun(@(x) isequal(x, firstVal), values));

                    if ~isEqual
                        canProceed = false;
                        violatedRows(end+1) = row; %#ok<AGROW>
                    end
                end

                % 条件を満たさない場合、中断処理
                if ~canProceed
                    msg = sprintf(['The following rows contain inconsistent values across selected columns:\n' ...
                                   '%s\n\nProcessing has been aborted.'], mat2str(violatedRows));
                    uialert(app.main_menu_window, msg, 'Consistency Check Failed', 'Icon', 'error');
                    % 中断
                    shouldContinue = false;
                    return;
                end

                % === チェックを通過した後に実行する処理 ===
                % 入力を行う総断層数を変更する。
                % 選択された列の数を取得
                numSelectedCols = size(selectedData, 2);
                % "fixed" は4行目 → selectedDataの 3行目に該当（2行目から始まるため）
                % すべての列に対して同じ値をセット
                for c = 1:numSelectedCols
                    selectedData{3, c} = numSelectedCols;
                end

                % === 変数へ代入 ===
                % try
                    % 2,3行のテキスト内容をHEADに格納
                    % app.INPUT_VARS.HEAD(1) = string(selectedData{2 - 1, 1});
                    % app.INPUT_VARS.HEAD(2) = string(selectedData{3 - 1, 1});
                    % HEADの初期化（string型で）
                    % app.INPUT_VARS.HEAD = strings(2,1);
                    % for i = 1:2
                    %     val = selectedData{i,1};  % selectedData は 2行目以降なので {1,1}, {2,1} がHEADに対応
                    % 
                    %     if ischar(val) || isstring(val)
                    %         app.INPUT_VARS.HEAD(i) = string(val);
                    %     elseif isnumeric(val)
                    %         if isnan(val)
                    %             app.INPUT_VARS.HEAD(i) = "";  % NaNは空文字にするなど
                    %         else
                    %             app.INPUT_VARS.HEAD(i) = string(val);  % 数値を文字列に変換
                    %         end
                    %     else
                    %         app.INPUT_VARS.HEAD(i) = "";  % その他は空文字にする
                    %     end
                    % end

                    % HEADをセルとして扱うときの初期化
                    app.INPUT_VARS.HEAD = cell(2,1);
                    for i = 1:2
                        % selectedDataは既にcell配列
                        val = selectedData{i,1};
                        if ischar(val)
                            app.INPUT_VARS.HEAD{i} = val;
                        elseif isstring(val)
                            % string → char
                            app.INPUT_VARS.HEAD{i} = char(val);
                        elseif isnumeric(val)
                            if isnan(val)
                                app.INPUT_VARS.HEAD{i} = ''; % 空文字
                            else
                                % 数値 → char
                                app.INPUT_VARS.HEAD{i} = num2str(val);
                            end
                        else
                            app.INPUT_VARS.HEAD{i} = ''; % その他も空
                        end
                    end

                    % デフォルトの断層番号
                    app.INPUT_VARS.INUM = repmat(int16(1), 1, 1);

                    % % 列数を取得
                    % app.INPUT_VARS.NUM = size(selectedData, 2);

                    % 4〜20行目（固定スカラ値）
                    app.INPUT_VARS.POIS        = double(selectedData{5 - 1, 1}); % PR1
                    app.INPUT_VARS.CALC_DEPTH  = double(selectedData{6 - 1, 1});
                    app.INPUT_VARS.YOUNG       = double(selectedData{7 - 1, 1}); % E1
                    app.INPUT_VARS.FRIC        = double(selectedData{8 - 1, 1});
                    app.INPUT_VARS.R_STRESS = [...
                        selectedData{9 - 1, 1}, selectedData{10 - 1, 1}, selectedData{11 - 1, 1}, selectedData{12 - 1, 1}; ...
                        selectedData{13 - 1, 1}, selectedData{14 - 1, 1}, selectedData{15 - 1, 1}, selectedData{16 - 1, 1}; ...
                        selectedData{17 - 1, 1}, selectedData{18 - 1, 1}, selectedData{19 - 1, 1}, selectedData{20 - 1, 1} ...
                    ];

                    % 21〜22行目（ベクトル）
                    app.INPUT_VARS.ID   = uint16(cell2mat(selectedData(21 - 1, :)'));
                    app.INPUT_VARS.KODE = uint16(cell2mat(selectedData(22 - 1, :)'));

                    % 断層数を取得
                    app.INPUT_VARS.NUM = length(app.INPUT_VARS.ID);

                    % 23〜26行目（ELEMENTの座標）
                    app.INPUT_VARS.ELEMENT(:, 1) = double(cell2mat(selectedData(23 - 1, :)'));  % X-start
                    app.INPUT_VARS.ELEMENT(:, 2) = double(cell2mat(selectedData(24 - 1, :)'));  % Y-start
                    app.INPUT_VARS.ELEMENT(:, 3) = double(cell2mat(selectedData(25 - 1, :)'));  % X-finish
                    app.INPUT_VARS.ELEMENT(:, 4) = double(cell2mat(selectedData(26 - 1, :)'));  % Y-finish

                    % 27行目（IRAKE）
                    IrakeData = selectedData{27 - 1, 1};
                    % 空白か missing が含まれているか確認（空白の場合selectedDataには""が代入されている。）
                    if isempty(IrakeData)
                        % 空の場合、right-lat & reverse slip formatとして扱い0を代入
                        disp('No IrakeData is included in the input file. Therefore, assign 0.');
                        showMessage(app, 'No IrakeData is included in the input file. Therefore, assign 0.', false);
                        app.CALC_CONTROL.IRAKE = 0;
                    % elseif IrakeData == ""
                    %     % おそらく不要
                    %     % 空の場合、right-lat & reverse slip formatとして扱い0を代入
                    %     disp('No IrakeData is included in the input file. Therefore, assign 0.');
                    %     showMessage(app, 'No IrakeData is included in the input file. Therefore, assign 0.', false);
                    %     app.CALC_CONTROL.IRAKE = 0;
                    else
                        app.CALC_CONTROL.IRAKE = IrakeData;
                    end
                    app.CALC_CONTROL.IND_RAKE = [];

                    % 列ごとに異なる値のもの
                    % 28〜29行目（ELEMENTのrake / slip）
                    app.INPUT_VARS.ELEMENT(:, 5) = double(cell2mat(selectedData(28 - 1, :)'));  % rt.lat/rake
                    app.INPUT_VARS.ELEMENT(:, 6) = double(cell2mat(selectedData(29 - 1, :)'));  % reverse/net-slip

                    % 30〜32行目（ELEMENTのdip, top, bot）
                    app.INPUT_VARS.ELEMENT(:, 7) = double(cell2mat(selectedData(30 - 1, :)'));
                    app.INPUT_VARS.ELEMENT(:, 8) = double(cell2mat(selectedData(31 - 1, :)'));
                    app.INPUT_VARS.ELEMENT(:, 9) = double(cell2mat(selectedData(32 - 1, :)'));

                    % 33行目（コメント）の各列を文字列に変換して代入
                    app.INPUT_VARS.FCOMMENT = cellfun(@(x) string(x), selectedData(33 - 1, :)', 'UniformOutput', false);

                    % 35〜59行目（固定スカラ値）
                    % 35〜40行目（GRID）
                    app.INPUT_VARS.GRID = double(cell2mat(selectedData(35 - 1:40 - 1, 1)));

                    % 42〜44行目（SIZE）
                    app.INPUT_VARS.SIZE = cell2mat(selectedData(42 - 1:44 - 1, 1));

                    % 46〜52行目（SECTION）
                    app.INPUT_VARS.SECTION = cell2mat(selectedData(46 - 1:52 - 1, 1));
                    if isempty(app.INPUT_VARS.SECTION) == 1
                        disp('No info for a cross section line is included in the input file.');
                        showMessage(app, 'No info for a cross section line is included in the input file.', false);
                    end

                    % 54〜59行目（座標系）、地図情報の読み込みでは空白の場合、何もしない
                    latlonData = selectedData(54 - 1:59 - 1, 1);
                    % 空白か missing が含まれているか確認（空白の場合selectedDataには""が代入されている。）
                    if all(cellfun(@(x) isequal(x, "") || isempty(x) || ismissing(x), latlonData))
                        disp('No lat. & lon. information is included in the input file.');
                        showMessage(app, 'No lat. & lon. information is included in the input file.', false);
                    else
                        app.COORD_VARS.MIN_LON  = double(latlonData{1});
                        app.COORD_VARS.MAX_LON  = double(latlonData{2});
                        app.COORD_VARS.ZERO_LON = double(latlonData{3});
                        app.COORD_VARS.MIN_LAT  = double(latlonData{4});
                        app.COORD_VARS.MAX_LAT  = double(latlonData{5});
                        app.COORD_VARS.ZERO_LAT = double(latlonData{6});
                    end

                    % 読み込んだ列数（断層データ数）を取得
                    numFaults = size(selectedData, 2);
                    % 結果を表示
                    uialert(app.main_menu_window, ...
                            sprintf('Successfully loaded %d fault datasets.', numFaults), ...
                            'Import Complete', ...
                            'Icon', 'info');

                % catch
                %     % エラーがあった場合中断
                %     msgbox('Error processing CSV file.', 'Error', 'error');
                %     % 中断
                %     shouldContinue = false;
                %     return;
                % end

                % ------------------すべてのパラメータが正しく読み込まれたかをチェック--------------------
                % エラーチェック
                check_input_field(app.INPUT_VARS.NUM, 'Number of faults is not read. Check the input file.');
                check_input_field(app.INPUT_VARS.POIS, 'Poisson ratio is not read. Check the input file.');
                check_input_field(app.INPUT_VARS.YOUNG, 'Young modulus is not read. Check the input file.');
                check_input_field(app.INPUT_VARS.FRIC, 'Coefficient of friction is not read. Check the input file.');
                check_input_field(app.INPUT_VARS.R_STRESS, 'Regional stress values are not read. Check the input file.');
                check_input_field(app.INPUT_VARS.GRID, 'Grid info for study area is not read properly. Check the input file.');

                % "rake"と"net slip"のタイプを"right-lat"と"reverse"タイプに変更する処理
                if app.CALC_CONTROL.IRAKE == 1
                    if mean(app.INPUT_VARS.KODE) == 100
                        count = 0;
                        for j = 1:app.INPUT_VARS.NUM
                            for i = 1:app.INPUT_VARS.ID(j)
                                count = count + 1;
                                app.CALC_CONTROL.IND_RAKE(count, 1) = app.INPUT_VARS.ELEMENT(j, 5); % rake情報を保持
                            end
                        end
                        [app.INPUT_VARS.ELEMENT(:, 5), app.INPUT_VARS.ELEMENT(:, 6)] = rake2comp(app, app.INPUT_VARS.ELEMENT(:, 5), app.INPUT_VARS.ELEMENT(:, 6));
                    else
                        uialert(app.main_menu_window, 'Rake & net slip style should be with Kode 100.', 'Warning!');
                        return;
                    end
                end
                cd(app.SYSTEM_VARS.HOME_DIR);

                % ------------------その他のファイル形式の処理------------------
            elseif strcmp(ext, '.mat') & N ~=2
                % --------------.matファイルの場合の処理の読み込み処理-----------------
                try
                    % .matファイルを読み込む
                    matS = load(fullfile(filePath, fileName)); 
                    disp('.mat formatted file was read.');
                    showMessage(app, '.mat formatted file was read.', false);
                catch
                    errordlg('The file might be corrupted. Check the content.');
                    % 中断
                    shouldContinue = false;
                    return;
                end

                if size(app.SYSTEM_VARS.PREF, 1) == 8
                    dummy = app.SYSTEM_VARS.PREF;
                    app.SYSTEM_VARS.PREF = [dummy; [0.9 0.9 0.1 1.0]];
                end
                if N == 1
                    % 修正必要
                    input_window(app);
                end
                cd(app.SYSTEM_VARS.HOME_DIR);

                % 安全に app 構造体へ格納（存在確認しながら）
                if isfield(matS, 'HEAD'),        app.INPUT_VARS.HEAD = matS.HEAD; end
                if isfield(matS, 'NUM'),         app.INPUT_VARS.NUM = matS.NUM; end
                if isfield(matS, 'POIS'),        app.INPUT_VARS.POIS = matS.POIS; end
                if isfield(matS, 'CALC_DEPTH'),  app.INPUT_VARS.CALC_DEPTH = matS.CALC_DEPTH; end
                if isfield(matS, 'YOUNG'),       app.INPUT_VARS.YOUNG = matS.YOUNG; end
                if isfield(matS, 'FRIC'),        app.INPUT_VARS.FRIC = matS.FRIC; end
                if isfield(matS, 'R_STRESS'),    app.INPUT_VARS.R_STRESS = matS.R_STRESS; end
                if isfield(matS, 'ID'),          app.INPUT_VARS.ID = matS.ID; end
                if isfield(matS, 'KODE'),        app.INPUT_VARS.KODE = matS.KODE; end
                if isfield(matS, 'ELEMENT'),     app.INPUT_VARS.ELEMENT = matS.ELEMENT; end
                if isfield(matS, 'FCOMMENT'),    app.INPUT_VARS.FCOMMENT = matS.FCOMMENT; end
                if isfield(matS, 'GRID'),        app.INPUT_VARS.GRID = matS.GRID; end
                if isfield(matS, 'SIZE'),        app.INPUT_VARS.SIZE = matS.SIZE; end
                if isfield(matS, 'SECTION'),     app.INPUT_VARS.SECTION = matS.SECTION; end

                if isfield(matS, 'MIN_LAT'),     app.COORD_VARS.MIN_LAT = matS.MIN_LAT; end
                if isfield(matS, 'MAX_LAT'),     app.COORD_VARS.MAX_LAT = matS.MAX_LAT; end
                if isfield(matS, 'ZERO_LAT'),    app.COORD_VARS.ZERO_LAT = matS.ZERO_LAT; end
                if isfield(matS, 'MIN_LON'),     app.COORD_VARS.MIN_LON = matS.MIN_LON; end
                if isfield(matS, 'MAX_LON'),     app.COORD_VARS.MAX_LON = matS.MAX_LON; end
                if isfield(matS, 'ZERO_LON'),    app.COORD_VARS.ZERO_LON = matS.ZERO_LON; end

                if isfield(matS, 'PREF'),        app.SYSTEM_VARS.PREF = matS.PREF; end
                if isfield(matS, 'COAST_DATA'),  app.OVERLAY_VARS.COAST_DATA = matS.COAST_DATA; end
                if isfield(matS, 'AFAULT_DATA'), app.OVERLAY_VARS.AFAULT_DATA = matS.AFAULT_DATA; end
                if isfield(matS, 'EQ_DATA'),     app.OVERLAY_VARS.EQ_DATA = matS.EQ_DATA; end
                if isfield(matS, 'GPS_DATA')
                    app.OVERLAY_VARS.GNSS_DATA = matS.GPS_DATA;
                elseif isfield(matS, 'GNSS_DATA')
                    app.OVERLAY_VARS.GNSS_DATA = matS.GNSS_DATA;
                end
                if isfield(matS, 'VOLCANO'),     app.OVERLAY_VARS.VOLCANO = matS.VOLCANO; end
                if isfield(matS, 'SEISSTATION'), app.OVERLAY_VARS.SEISSTATION = matS.SEISSTATION; end

            else
                % --------------ASCIIファイル（inp, inr）の読み込み処理-----------------
                cd(app.SYSTEM_VARS.HOME_DIR);
                try
                    load(fullfile(filePath, fileName)); % ASCIIファイルを読み込む
                catch
                    try
                        fid = fopen(fullfile(filePath, fileName), 'r');
                        if fid == -1
                            errordlg('The file might be corrupted or wrong one');
                            % 中断
                            shouldContinue = false;
                            return;
                        end
                    catch
                        errordlg('The file might be corrupted. Check the content.');
                        % 中断
                        shouldContinue = false;
                        return;
                    end
                end
                if size(app.SYSTEM_VARS.PREF, 1) == 8
                    dummy = app.SYSTEM_VARS.PREF;
                    app.SYSTEM_VARS.PREF = [dummy; [0.9 0.9 0.1 1.0]];
                end
                if N == 1
                    % 修正必要
                    input_window(app);
                end
                cd(app.SYSTEM_VARS.HOME_DIR);


                % デフォルトの断層番号
                app.INPUT_VARS.INUM = repmat(int16(1), 1, 1);

                % 以下のASCIIファイルの処理では、元ファイルを1行ずつ読み込むため、textscanは削除しないこと！
                % Nが2でない場合、つまり既存のファイルを開く場合
                if N ~= 2
                    % ヘッダ情報の読み込み
                    % 1行目と2行目を丸ごと読み取る（行ごとであるため、長文の場合は不明）
                    line1 = fgetl(fid);
                    line2 = fgetl(fid);
                    % HEAD にそのまま格納
                    app.INPUT_VARS.HEAD = {line1; line2};

                    sp = ' ';

                    % number of faults
                    % 断層数の読み込み
                    d = textscan(fid, '%*s %*s %*s %*s %*s %4u16 %*s %*s', 1);
                    app.INPUT_VARS.NUM = int32([d{1}]) + num_buffer; % 最大の要素数は65,535

                    % Poisson's ratio & calculation depth
                    % ポアソン比と計算深度の読み込み
                    e = textscan(fid,'%*s %15.3f32 %*s %*s %*s %15.3f32',1);
                    app.INPUT_VARS.POIS = double([e{1}]);
                    app.INPUT_VARS.CALC_DEPTH = double([e{2}]);

                    % Young's modulus
                    % ヤング率の読み込み
                    f = textscan(fid, '%*s %n %*s %*s', 1);
                    app.INPUT_VARS.YOUNG = double([f{1}]);

                    % Passing symmetry parameters (no longer used for Coulomb)
                    % 対称性パラメータ（Coulombでは使用されない）の読み込み
                    g = textscan(fid, '%*s %*s %*s %*s', 1);    

                    % friction coefficient
                    % 摩擦係数の読み込み
                    h = textscan(fid, '%*s %15.3f32', 1);
                    app.INPUT_VARS.FRIC = double([h{1}]);

                    % regional stress field
                    % 広域応力場の読み込み
                    s = textscan(fid, '%*s %15.6f32 %*s %15.6f32 %*s %15.6f32 %*s %15.6f32', 3);
                    app.INPUT_VARS.R_STRESS = [s{:}];

                    % スリップ成分のタイプを検出（通常は「right-lat」と「reverse」）
                    dum0 = textscan(fid, '%s %s %s %s %s %s %s %s %s %s %s %s %s %s %s %s %s %s %s %s', 1);
                    app.CALC_CONTROL.IRAKE = 0;
                    app.CALC_CONTROL.IND_RAKE = [];

                    % rake情報が含まれている場合の処理（.inrファイルの場合）
                    % "rake" という文字列が dum0{k} に含まれているかを判定
                    for k = 1:20
                        if ~isempty(dum0{k}) && strncmp(dum0{k}, 'rake', length('rake'))
                            app.CALC_CONTROL.IRAKE = 1;
                        end
                    end

                    % dummy for passing through "xxxxxxxxxx xxxxxxxxxx" line
                    dum = textscan(fid, '%*s %*s %*s %*s %*s %*s %*s %*s %*s %*s %*s %*s %*s %*s', 1);

                    % fault elements
                    % ファイルの読み込み処理続行（要素の読み込みなど）
                    flt = textscan(fid,'%3u16 %10.9f32 %10.9f32 %10.9f32 %10.9f32 %3u16 %10.9f32 %10.9f32 %10.9f32 %10.9f32 %10.9f32 %s %s %s %s %s %s %s %s %s %s', app.INPUT_VARS.NUM);
                    app.INPUT_VARS.ID = uint16([flt{1}]);
                    if length(app.INPUT_VARS.ID) ~= app.INPUT_VARS.NUM - num_buffer
                        % 修正必要？要らないかも
                        disp('************************************************************************');
                        disp('**  Please change #fixed in the 3rd row in the input file afterward.  **');
                        disp('**  入力ファイルの3行目の#fixedを後で変更してください。  **');
                        disp('************************************************************************');
                        showMessage(app, 'Please change #fixed in the 3rd row in the input file afterward.', false);
                    end

                    app.INPUT_VARS.NUM = length(app.INPUT_VARS.ID);
                    app.INPUT_VARS.KODE = uint16([flt{6}]);

                    % (ELEMENT: xs, ys, xf, yf, latslip, dipslip, dip, top, bottom)
                    app.INPUT_VARS.ELEMENT = [flt{2:5} flt{7:11}];
                    app.INPUT_VARS.ELEMENT = double(app.INPUT_VARS.ELEMENT);

                    % 要素に付属するコメントの読み込み
                    a = [flt{12:21}];
                    % FCOMMENTをapp.INPUT_VARS.NUM行1列のセルに変更
                    app.INPUT_VARS.FCOMMENT = cell(app.INPUT_VARS.NUM, 1);
                    for k = 1:app.INPUT_VARS.NUM
                        b = char(a(k, :));
                        c = cellstr([deblank(b(1, :)) sp deblank(b(2, :)) sp deblank(b(3, :)) ...
                            sp deblank(b(4, :)) sp deblank(b(5, :)) sp deblank(b(6, :)) ...
                            sp deblank(b(7, :)) sp deblank(b(8, :)) sp deblank(b(9, :))]);
                        % コメントはセル配列なので、事前にapp.INPUT_VARS.FCOMMENT =
                        % []でdouble 型で宣言されたものをセル配列にこのループ前に変更する。
                        app.INPUT_VARS.FCOMMENT(k) = c;
                    end

                    dum = textscan(fid, '%*s %*s %*s %*s %*s %*s', 1);

                    % grid parameters
                    % グリッドパラメータの読み込み
                    gr = textscan(fid, '%*45c %16.7f32', 6);
                    app.INPUT_VARS.GRID = double([gr{:}]);

                    dum = textscan(fid, '%*s %*s %*s %*s %*s %*s', 1);

                    % size
                    % サイズの読み込み
                    sz = textscan(fid, '%*45c %16.7f32', 3);
                    app.INPUT_VARS.SIZE = [sz{:}];

                    dum = textscan(fid, '%*s %*s %*s %*s %*s %*s', 1);

                    % cross section
                    % 断面データの読み込み
                    cs = textscan(fid, '%*45c %16.7f32', 7);
                    app.INPUT_VARS.SECTION = double([cs{:}]);
                    if isempty(app.INPUT_VARS.SECTION) == 1
                        disp('No info for a cross section line is included in the input file.');
                        showMessage(app, 'No info for a cross section line is included in the input file.', false);
                    end

                    dum = textscan(fid, '%*s %*s %*s %*s %*s %*s', 1);

                    % map info parameters
                    % 地図情報の読み込み
                    mi = textscan(fid, '%*45c %16.7f32', 6);
                    mapinfo = double([mi{:}]);
                    if isempty(mapinfo) ~= 1
                        app.COORD_VARS.MIN_LON = mapinfo(1, 1);
                        app.COORD_VARS.MAX_LON = mapinfo(2, 1);
                        app.COORD_VARS.ZERO_LON = mapinfo(3, 1);
                        app.COORD_VARS.MIN_LAT = mapinfo(4, 1);
                        app.COORD_VARS.MAX_LAT = mapinfo(5, 1);
                        app.COORD_VARS.ZERO_LAT = mapinfo(6, 1);
                    else
                        disp('No lat. & lon. information is included in the input file.');
                        showMessage(app, 'No lat. & lon. information is included in the input file.', false);
                    end

                    % check if all parameters are properly read or not.
                    % ------------------すべてのパラメータが正しく読み込まれたかをチェック--------------------
                    check_input_field(app.INPUT_VARS.NUM, 'Number of faults is not read. Make sure the input file.');
                    check_input_field(app.INPUT_VARS.POIS, 'Poisson ratio is not read. Make sure the input file.');
                    check_input_field(app.INPUT_VARS.YOUNG, 'Young modulus is not read. Make sure the input file.');
                    check_input_field(app.INPUT_VARS.FRIC, 'Coefficient of friction is not read. Make sure the input file.');
                    check_input_field(app.INPUT_VARS.R_STRESS, 'Regional stress values are not read. Make sure the input file.');
                    check_input_field(app.INPUT_VARS.GRID, 'Grid info for study area is not read properly. Make sure the input file.');

                    fclose(fid); % ファイルを閉じる
                    clear a b1 b2 c1 c2 d e f g h s dum flt gr sz cd mi;
                end

                % "rake"と"net slip"のタイプを"right-lat"と"reverse"タイプに変更する処理
                if app.CALC_CONTROL.IRAKE == 1
                    if mean(app.INPUT_VARS.KODE) == 100
                        count = 0;
                        for j = 1:app.INPUT_VARS.NUM
                            for i = 1:app.INPUT_VARS.ID(j)
                                count = count + 1;
                                app.CALC_CONTROL.IND_RAKE(count, 1) = app.INPUT_VARS.ELEMENT(j, 5); % rake情報を保持
                            end
                        end
                        [app.INPUT_VARS.ELEMENT(:, 5), app.INPUT_VARS.ELEMENT(:, 6)] = rake2comp(app, app.INPUT_VARS.ELEMENT(:, 5), app.INPUT_VARS.ELEMENT(:, 6));
                    else
                        uialert(app.main_menu_window, 'Rake & net slip style should be with Kode 100.', 'Warning!');
                        return;
                    end
                end

                cd(app.SYSTEM_VARS.HOME_DIR);
            end

            % 基本情報の計算
            cd(app.SYSTEM_VARS.HOME_DIR);
            % XGRID, YGRIDの計算
            calc_element(app);

            if ~isempty(app.INPUT_VARS.SECTION)
                a = xy2lonlat(app, [app.INPUT_VARS.SECTION(1), app.INPUT_VARS.SECTION(2)]);
                app.SECTION_VARS.SEC_XS = a(1, 1);          
                app.SECTION_VARS.SEC_XF = a(1, 2);
                a = xy2lonlat(app, [app.INPUT_VARS.SECTION(3), app.INPUT_VARS.SECTION(4)]);
                app.SECTION_VARS.SEC_YS = a(1, 1); 
                app.SECTION_VARS.SEC_YF = a(1, 2);
                app.SECTION_VARS.SEC_INCRE = app.INPUT_VARS.SECTION(5);
                app.SECTION_VARS.SEC_DEPTH = app.INPUT_VARS.SECTION(6);
                app.SECTION_VARS.SEC_DEPTHINC = app.INPUT_VARS.SECTION(7);
                app.SECTION_VARS.SEC_DIP = 90.0;
                app.SECTION_VARS.SEC_DOWNDIP_INC = app.INPUT_VARS.SECTION(7);
            end

            %   N=1: ダイアログを表示してパラメータの確認と変更を行う
            if N == 1
                % 修正必要
                input_window(app);
            end

            % モーメントの計算を行う
            seis_moment(app);
            showMessage(app, 'To calculate deformation, select one of the button from "Functions".', false);

            cd(app.SYSTEM_VARS.HOME_DIR);

            % ネスト関数--------------------------------------
            % すべてのパラメータが正しく読み込まれたかをチェック
            function check_input_field(field, message)
                if isempty(field)
                    errordlg(message);
                    % 中断
                    shouldContinue = false;
                    return;
                end
            end

            % "missing" 型のセルを 空文字 "" に置き換える関数
            function value = replaceMissing(value)
                if ismissing(value)
                    value = "";
                end
            end
        end


        function displ_open(app, scl)
            % =====================================================================
            %                displ_open(app, scl)
            % =====================================================================
            % displ_open: 変位ベクトルを描画するための関数
            % scl: スケーリング値 (スライダー値などを反映)
        
            if isempty(app.GRAPHICS_VARS.C_SAT) == 1
                % default color saturation value is 5 bars % TODA corrected
                app.GRAPHICS_VARS.C_SAT = 5;
            end
        
            if app.CALC_CONTROL.FUNC_SWITCH == 1 || app.CALC_CONTROL.FUNC_SWITCH == 10
                dummy = zeros(length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
            else
                % データファイル 'Displacement.cou' からデータを読み込む
                filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Displacement.cou');

                fid = fopen(filePath, 'r');
                % fopenが失敗した場合のエラーチェック
                if fid == -1
                    error('Could not open file: %s', filePath);
                end
                coul = textscan(fid, '%f %f %f %f %f %f', 'delimiter', '\t', 'headerlines', 3);
                fclose(fid);
        
                % 読み込んだセルデータを行列に変換
                ux = [coul{4}];
                uy = [coul{5}];
                uz = [coul{6}];
                uux = reshape(ux, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                uuy = reshape(uy, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                uuz = reshape(uz, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));

                % 作業ディレクトリを元に戻す
                cd(app.SYSTEM_VARS.HOME_DIR);
            end
        
            hold(app.UIAxes, 'on');  % app.UIAxes で保持
            %-----------    Horizontal displ. mapview  ---------------------------
            if app.CALC_CONTROL.FUNC_SWITCH == 2 || app.CALC_CONTROL.FUNC_SWITCH == 3
                msg = msgbox('Now calculating...', 'Notification', 'help');
                grid_drawing(app);
                
                % resizing using "Exaggeration" in input file and slider value (scl)
                % notice that uux, uuy, and uuz are "m" unit but now looks "km" that's why I use "/1000"
                
                % スケール調整
                resz = double((app.INPUT_VARS.SIZE(3, 1) / 1000) * scl);
                % resizing using "Exaggeration" in input file and slider value (scl)
                % notice that uux, uuy, and uuz are "m" unit but now looks "km" that's why I use "/1000"

                if app.ASSIST_VARS.FIXFLAG == 0 
                    % 固定点がない場合
                    % no fixed point
                    hold(app.UIAxes, 'on'); % なぜかここのはないと描画されない。
                    if app.CALC_CONTROL.FUNC_SWITCH == 2
                        % Horizontal Displ. (vectors)
                        % ベクトルプロット
                        if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                            % lonlat座標
                            a = xy2lonlat(app, [reshape(app.COORD_VARS.XGRID, length(app.COORD_VARS.XGRID), 1), zeros(length(app.COORD_VARS.XGRID), 1)]);
                            b = xy2lonlat(app, [zeros(length(app.COORD_VARS.YGRID), 1), reshape(app.COORD_VARS.YGRID, length(app.COORD_VARS.YGRID), 1)]);
                            % quiver(app.UIAxes, a(:, 1), b(:, 2), uux * app.ASSIST_VARS.LON_PER_X * resz, uuy * app.ASSIST_VARS.LAT_PER_Y * resz, 'off', 'k');
                            a1 = quiver(app.UIAxes, a(:, 1), b(:, 2), uux * app.ASSIST_VARS.LON_PER_X * resz, uuy * app.ASSIST_VARS.LAT_PER_Y * resz, 0);
                        else
                            % cartesian座標
                            a1 = quiver(app.UIAxes, app.COORD_VARS.XGRID, app.COORD_VARS.YGRID, uux * resz, uuy * resz, 0);
                        end
                        a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                        a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                    
                    elseif app.CALC_CONTROL.FUNC_SWITCH == 3 
                        % Horizontal Displ. (wileframe)
                        % ワイヤーフレーム表示
                        % ワイヤーフレーム用 X, Y 座標の準備
                        if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                            % lonlat座標
                            a = xy2lonlat(app, [app.COORD_VARS.XGRID(:), zeros(numel(app.COORD_VARS.XGRID), 1)]);
                            b = xy2lonlat(app, [zeros(numel(app.COORD_VARS.YGRID), 1), app.COORD_VARS.YGRID(:)]);
                            xds = repmat(a(:, 1)', numel(b(:, 2)), 1);
                            yds = repmat(b(:, 2), 1, numel(a(:, 1)));
                            dx = app.ASSIST_VARS.LON_PER_X * resz;
                            dy = app.ASSIST_VARS.LAT_PER_Y * resz;
                            % a = xy2lonlat(app, [reshape(app.COORD_VARS.XGRID, length(app.COORD_VARS.XGRID), 1) zeros(length(app.COORD_VARS.XGRID), 1)]);
                            % b = xy2lonlat(app, [zeros(length(app.COORD_VARS.YGRID), 1) reshape(app.COORD_VARS.YGRID, length(app.COORD_VARS.YGRID), 1)]);
                            % xds = repmat(reshape(a(:, 1), 1, length(a(:, 1))), length(b(:, 2)), 1);
                            % yds = repmat(reshape(b(:, 2), length(b(:, 2)), 1), 1, length(a(:, 1)));
                        else
                            % cartesian座標
                            xds = repmat(app.COORD_VARS.XGRID, numel(app.COORD_VARS.YGRID), 1);
                            yds = repmat(app.COORD_VARS.YGRID(:), 1, numel(app.COORD_VARS.XGRID));
                            dx = resz;
                            dy = resz;
                        end

                        % ワイヤーフレームを描画
                        for k = 1:numel(app.COORD_VARS.XGRID)
                            a1 = plot(app.UIAxes, xds(:, k) + uux(:, k) * dx, yds(:, k) + uuy(:, k) * dy);
                            a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                            a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                        end
                        for k = 1:numel(app.COORD_VARS.YGRID)
                            a1 = plot(app.UIAxes, xds(k, :) + uux(k, :) * dx, yds(k, :) + uuy(k, :) * dy);
                            a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                            a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                        end
                    end
        
                else % --- 固定点を考慮した変位を再計算（app.ASSIST_VARS.FIXFLAG ~= 0） ---
                    dc3d_keep = app.OKADA_OUTPUT.DC3D;
                    Okada_halfspace_one(app);
                    a = app.OKADA_OUTPUT.DC3D(:, 1:2); % xx, yy
                    b = app.OKADA_OUTPUT.DC3D(:, 5:8); % zz, UX, UY, UZ
                    c = horzcat(a, b);                 % xx, yy, zz, UX, UY, UZ の1x6行列
                    app.OKADA_OUTPUT.DC3D = dc3d_keep; % dc3d_keep に戻す
        
                    if app.CALC_CONTROL.FUNC_SWITCH == 2
                        % ベクトルプロット
                        if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                            % lonlat座標
                            a = xy2lonlat(app, [reshape(app.COORD_VARS.XGRID, length(app.COORD_VARS.XGRID), 1), zeros(length(app.COORD_VARS.XGRID), 1)]);
                            b = xy2lonlat(app, [zeros(length(app.COORD_VARS.YGRID), 1), reshape(app.COORD_VARS.YGRID, length(app.COORD_VARS.YGRID), 1)]);
                            hold(app.UIAxes, 'on');
                            a1 = quiver(app.UIAxes, a(:, 1), b(:, 2), (uux - c(:, 4)) * app.ASSIST_VARS.LON_PER_X * resz, (uuy - c(:, 5)) * app.ASSIST_VARS.LAT_PER_Y * resz, 0);
                        else
                            hold(app.UIAxes, 'on');
                            a1 = quiver(app.UIAxes, app.COORD_VARS.XGRID, app.COORD_VARS.YGRID, (uux - c(:, 4)) * resz, (uuy - c(:, 5)) * resz, 0);
                        end
                        a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                        a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
        
                    elseif app.CALC_CONTROL.FUNC_SWITCH == 3
                        % ワイヤーフレームの変位計算
                        ny = length(app.COORD_VARS.YGRID);
                        nx = length(app.COORD_VARS.XGRID);
                        % 変換 or そのまま格子生成
                        if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                            % lonlat座標
                            a = xy2lonlat(app, [reshape(app.COORD_VARS.XGRID, nx, 1), reshape(app.COORD_VARS.YGRID, ny, 1)]);
                            xds = repmat(a(:, 1)', ny, 1);
                            yds = repmat(a(:, 2), 1, nx);
                            dx = app.ASSIST_VARS.LON_PER_X * resz;
                            dy = app.ASSIST_VARS.LAT_PER_Y * resz;
                        else
                            % cartesian座標
                            xds = repmat(app.COORD_VARS.XGRID, ny, 1);
                            yds = repmat(app.COORD_VARS.YGRID(:), 1, nx);
                            dx = resz;
                            dy = resz;
                        end

                        % 描画パラメータ
                        ux_offset = c(4);
                        uy_offset = c(5);
                        % ワイヤーフレームの変位描画
                        for k = 1:nx
                            x = xds(:, k) + (uux(:, k) - ux_offset) * dx;
                            y = yds(:, k) + (uuy(:, k) - uy_offset) * dy;
                            a1 = plot(app.UIAxes, x, y);
                            a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                            a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                        end
                        for k = 1:ny
                            x = xds(k, :) + (uux(k, :) - ux_offset) * dx;
                            y = yds(k, :) + (uuy(k, :) - uy_offset) * dy;
                            a1 = plot(app.UIAxes, x, y);
                            a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                            a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                        end
                    end
                    plot(app.UIAxes, app.ASSIST_VARS.FIXX, app.ASSIST_VARS.FIXY, 'ro');
                end
                        
                % 単位ベクトルの描画
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    a = xy2lonlat(app, [app.COORD_VARS.XGRID(:), zeros(length(app.COORD_VARS.XGRID), 1)]);
                    b = xy2lonlat(app, [zeros(length(app.COORD_VARS.YGRID), 1), app.COORD_VARS.YGRID(:)]);
                    xinc = a(2, 1) - a(1, 1);
                    yinc = b(2, 2) - b(1, 2);
                    [unit, unitText] = check_unit_vector(app, app.COORD_VARS.XGRID(1), app.COORD_VARS.XGRID(end), 1.0 * resz, 0.15, 0.4);
                    a0 = quiver(app.UIAxes, (a(1, 1) + xinc), (b(1, 2) + yinc * 1.5), unit * app.ASSIST_VARS.LON_PER_X * resz, 0., 0); %scale vector
                    h = text(app.UIAxes, (a(1, 1) + unit * app.ASSIST_VARS.LON_PER_X * resz * 0.5), (b(1, 2) + yinc * 2.2), unitText);
                else
                    % cartesian座標
                    xinc = app.COORD_VARS.XGRID(2) - app.COORD_VARS.XGRID(1);
                    yinc = app.COORD_VARS.YGRID(2) - app.COORD_VARS.YGRID(1);
                    [unit, unitText] = check_unit_vector(app, app.COORD_VARS.XGRID(1), app.COORD_VARS.XGRID(end), 1.0 * resz, 0.15, 0.4);
                    a0 = quiver(app.UIAxes, (app.COORD_VARS.XGRID(1) + xinc), (app.COORD_VARS.YGRID(1) + yinc * 1.5), unit * resz, 0., 0); %scale vector
                    h = text(app.UIAxes, app.COORD_VARS.XGRID(1) + unit * resz * 0.5, app.COORD_VARS.YGRID(1) + yinc * 2.2, unitText);
                end
                % プロパティ設定
                a0.Color = 'r';
                a0.LineWidth = 1.2;
                h.FontName = 'Helvetica';
                h.FontSize = 14;
                h.Color = 'r';
                h.FontWeight = 'bold';
                h.HorizontalAlignment = 'center';
                h.VerticalAlignment = 'bottom';
                
                % 断層データを描画
                fault_overlay(app);

                delete(msg);
        
            %-----------    Vertical displ. mapview  ---------------------------
            %--- 縦方向変位のマップビュー処理 ---
            elseif app.CALC_CONTROL.FUNC_SWITCH == 4
                msg = msgbox('Now calculating...', 'Notification', 'help');
                % グリッドを描画
                grid_drawing(app);

                app.OKADA_OUTPUT.CC = reshape(uuz, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                app.OKADA_OUTPUT.CC = app.OKADA_OUTPUT.CC(length(app.COORD_VARS.YGRID):-1:1, :);

                cmax = max(reshape(max(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                cmin = min(reshape(min(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                % % csvだとエラーが出ることがある。原因はuuz, app.OKADA_OUTPUT.CCにNaNが含まれているから？計算結果が微かに異なるのも影響している？
                % % 以下不要？修正必要？
                % cmean = mean(reshape(mean(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                % app.ASSIST_VARS.COLORSN = cmean;

                % グラフの視点の調整
                coulomb_view(app, app.ASSIST_VARS.COLORSN);
                % 断層を描画
                fault_overlay(app);
        
                % 鉛直変位の等高線間隔の設定
                a = cmax - cmin;
                if a > 10.0
                    app.GRAPHICS_VARS.CONT_INTERVAL = 1;
                elseif a > 5.0
                    app.GRAPHICS_VARS.CONT_INTERVAL = 0.5;
                elseif a > 1.0
                    app.GRAPHICS_VARS.CONT_INTERVAL = 0.1;
                else
                    app.GRAPHICS_VARS.CONT_INTERVAL = 0.01;
                end
                app.IntervalEditField.Value = app.GRAPHICS_VARS.CONT_INTERVAL;
                app.IntervalUnitLabel.Text = 'm';
                delete(msg);
        
            %-----------    3 dimensional displ. view  ---------------------------
            %--- 3次元変位ビュー処理 ---
            elseif app.CALC_CONTROL.FUNC_SWITCH == 5 || app.CALC_CONTROL.FUNC_SWITCH == 1 || ...
                app.CALC_CONTROL.FUNC_SWITCH == 5.5 || app.CALC_CONTROL.FUNC_SWITCH == 5.7 || ...
                app.CALC_CONTROL.FUNC_SWITCH == 10
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    nx = length(app.COORD_VARS.XGRID); ny = length(app.COORD_VARS.YGRID);
                    ax = xy2lonlat(app, [rot90(app.COORD_VARS.XGRID), ones(nx, 1) * (app.COORD_VARS.YGRID(1) + app.COORD_VARS.YGRID(end)) / 2]);
                    ay = xy2lonlat(app, [ones(ny, 1) * (app.COORD_VARS.XGRID(1) + app.COORD_VARS.XGRID(end)) / 2, rot90(app.COORD_VARS.YGRID)]);
                    xx = fliplr(rot90(ax(:, 1)));
                    yy = fliplr(rot90(ay(:, 2)));
                else
                    % cartesian座標
                    xx = app.COORD_VARS.XGRID;
                    yy = app.COORD_VARS.YGRID;
                end
        
                % 3次元表示に関連するパラメータの初期設定
                % Colormap の設定
                switch app.SYSTEM_VARS.PREF(7, 1)
                    case 1
                        colormap(app.UIAxes, jet);  % ColormapをJetに設定
                    case 2
                        colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);  % ColormapをANATOLIAに設定
                    case 3
                        colormap(app.UIAxes, flipud(gray));  % グレースケールの反転
                end
        
                % 変位の拡大処理
                resz = (app.INPUT_VARS.SIZE(3, 1) / 1000) * scl;
                if app.CALC_CONTROL.FUNC_SWITCH == 1 || app.CALC_CONTROL.FUNC_SWITCH == 10
                    topz = 0.0;  % 上部のZ軸の値
                elseif app.CALC_CONTROL.FUNC_SWITCH == 5.7
                    colormap(app.UIAxes, jet);
                    topz = max(rot90(max(uuz * double(resz))));  % 最大深度を計算
                    [xm, ym] = meshgrid(xx, yy);
                    zm = ones(length(yy), length(xx)) * (-1.0) * app.INPUT_VARS.CALC_DEPTH;

                    % メッシュの描画
                    mesh(app.UIAxes, xx, yy, zm);
                    % 線を裏側からでも見えるように設定
                    hidden(app.UIAxes, 'off');
        
                    % 変位ベクトルの描画
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        uux = uux * double(resz) * app.ASSIST_VARS.LON_PER_X;
                        uuy = uuy * double(resz) * app.ASSIST_VARS.LAT_PER_Y;
                        a1 = quiver3(app.UIAxes, xm, ym, zm, uux, uuy, uuz * double(resz), 0);
                    else
                        % cartesian座標
                        a1 = quiver3(app.UIAxes, xm, ym, zm, uux * double(resz), uuy * double(resz), uuz * double(resz), 0);
                    end
                    a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                    a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);

                elseif app.CALC_CONTROL.FUNC_SWITCH == 5.5
                    topz = max(rot90(max(uuz * double(resz))));  % 最大深度を計算
                    % メッシュの描画
                    mesh(app.UIAxes, xx, yy, uuz * double(resz) - app.INPUT_VARS.CALC_DEPTH);
                    % 線を裏側からでも見えるように設定
                    hidden(app.UIAxes, 'off');
                else
                    topz = max(rot90(max(uuz * double(resz))));  % 最大深度を計算
                    % サーフェスの描画
                    surf(app.UIAxes, xx, yy, uuz * double(resz) - app.INPUT_VARS.CALC_DEPTH);
                end
        
                % 描画領域の設定
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    xlim(app.UIAxes, [app.COORD_VARS.MIN_LON, app.COORD_VARS.MAX_LON]);
                    ylim(app.UIAxes, [app.COORD_VARS.MIN_LAT, app.COORD_VARS.MAX_LAT]);
                    xlabel(app.UIAxes, 'Lon (deg)');
                    ylabel(app.UIAxes, 'Lat (deg)');
                    zlabel(app.UIAxes, 'Z (km)');
                    xyz_aspect = [app.ASSIST_VARS.LON_PER_X, app.ASSIST_VARS.LAT_PER_Y, 1.0];
                else
                    % cartesian座標
                    xlim(app.UIAxes, [min(app.COORD_VARS.XGRID), max(app.COORD_VARS.XGRID)]);
                    ylim(app.UIAxes, [min(app.COORD_VARS.YGRID), max(app.COORD_VARS.YGRID)]);
                    xlabel(app.UIAxes, 'X (km)');
                    ylabel(app.UIAxes, 'Y (km)');
                    zlabel(app.UIAxes, 'Z (km)');
                    xyz_aspect = [app.COORD_VARS.XY_RATIO, 1, 1];
                end
        
                % ビューの設定
                try
                    % ユーザーが指定した視点で表示
                    view(app.UIAxes, app.ASSIST_VARS.VIEW_AZ, app.ASSIST_VARS.VIEW_EL);
                catch
                    % デフォルトのビュー設定（方位角15度、仰角40度）
                    view(app.UIAxes, 15, 40);
                end
        
                % 断層要素の描画
                depth_max = 0.0;  % 最大深度の初期化
                if isempty(app.OKADA_OUTPUT.S_ELEMENT)
                    app.OKADA_OUTPUT.S_ELEMENT = zeros(app.INPUT_VARS.NUM, 10);
                    app.OKADA_OUTPUT.S_ELEMENT = [app.INPUT_VARS.ELEMENT double(app.INPUT_VARS.KODE)];
                    m = app.INPUT_VARS.NUM;
                else
                    [m, ~] = size(app.OKADA_OUTPUT.S_ELEMENT);
                end
        
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    temp_element = app.OKADA_OUTPUT.S_ELEMENT(:, 1:4);
                    a = xy2lonlat(app, [app.OKADA_OUTPUT.S_ELEMENT(:, 1), app.OKADA_OUTPUT.S_ELEMENT(:, 2)]);
                    b = xy2lonlat(app, [app.OKADA_OUTPUT.S_ELEMENT(:, 3), app.OKADA_OUTPUT.S_ELEMENT(:, 4)]);
                    app.OKADA_OUTPUT.S_ELEMENT(:, 1) = a(:, 1);
                    app.OKADA_OUTPUT.S_ELEMENT(:, 2) = a(:, 2);
                    app.OKADA_OUTPUT.S_ELEMENT(:, 3) = b(:, 1);
                    app.OKADA_OUTPUT.S_ELEMENT(:, 4) = b(:, 2); 
                end
        
                % for coloring amount of fault slip for grid 3D
                slip_max = 0.0;
                slip_min = 0.0;
                for k = 1:m
                    if int16(app.OKADA_OUTPUT.S_ELEMENT(k, 10)) == 100
                        if app.ASSIST_VARS.F3D_SLIP_TYPE == 1
                            sl = sqrt(app.OKADA_OUTPUT.S_ELEMENT(k, 5) ^ 2.0 + app.OKADA_OUTPUT.S_ELEMENT(k, 6) ^ 2.0);
                        elseif app.ASSIST_VARS.F3D_SLIP_TYPE == 2
                            sl = app.OKADA_OUTPUT.S_ELEMENT(k, 5);
                        else
                            sl = app.OKADA_OUTPUT.S_ELEMENT(k, 6);
                        end
                        slip_max = max(slip_max, sl);
                        slip_min = min(slip_min, sl);
                    end
                end
        
                %============= k loop ================== (start)
                for k = 1:m
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        dist = sqrt((temp_element(k, 3) - temp_element(k, 1)) ^ 2 + (temp_element(k, 4) - temp_element(k, 2)) ^ 2);
                    else
                        % cartesian座標
                        dist = sqrt((app.OKADA_OUTPUT.S_ELEMENT(k, 3) - app.OKADA_OUTPUT.S_ELEMENT(k, 1))^2 + (app.OKADA_OUTPUT.S_ELEMENT(k, 4) - app.OKADA_OUTPUT.S_ELEMENT(k, 2))^2);
                    end
                    hh = sin(deg2rad(app.OKADA_OUTPUT.S_ELEMENT(k, 7)));
                    if hh == 0.0
                        hh = 0.000001;
                    end
                    ddip_length = (app.OKADA_OUTPUT.S_ELEMENT(k, 9) - app.OKADA_OUTPUT.S_ELEMENT(k, 8)) / hh;
                    middepth = (app.OKADA_OUTPUT.S_ELEMENT(k, 9) + app.OKADA_OUTPUT.S_ELEMENT(k, 8)) / 2.0;
                
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        e = fault_corners(app, temp_element(k, 1), temp_element(k, 2), temp_element(k, 3), temp_element(k, 4), ...
                            app.OKADA_OUTPUT.S_ELEMENT(k, 7), app.OKADA_OUTPUT.S_ELEMENT(k, 8), middepth);
                    else
                        % cartesian座標
                        e = fault_corners(app, app.OKADA_OUTPUT.S_ELEMENT(k, 1), app.OKADA_OUTPUT.S_ELEMENT(k, 2), app.OKADA_OUTPUT.S_ELEMENT(k, 3), ...
                            app.OKADA_OUTPUT.S_ELEMENT(k, 4), app.OKADA_OUTPUT.S_ELEMENT(k, 7), app.OKADA_OUTPUT.S_ELEMENT(k, 8), middepth);
                    end
        
                    xc = (e(3, 1) + e(4, 1)) / 2.0;
                    yc = (e(3, 2) + e(4, 2)) / 2.0;
                    xcs = xc - ddip_length / 2.0;
                    xcf = xc + ddip_length / 2.0;
                    ycs = yc - dist / 2.0;
                    ycf = yc + dist / 2.0;
        
                    % 塗りつぶしの色を決定
                    if app.CALC_CONTROL.FUNC_SWITCH ~= 10
                        c_index = zeros(64, 3);
                        switch app.SYSTEM_VARS.PREF(7, 1)
                            case 1
                                c_index = colormap(app.UIAxes, jet);
                            case 2
                                if all(app.ASSIST_VARS.F3D_SLIP_TYPE == 1) && all(app.CALC_CONTROL.FUNC_SWITCH == 1)
                                    c_index = colormap(app.UIAxes, app.GRAPHICS_VARS.SEIS_RATE);
                                else
                                    c_index = colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);
                                end
                            case 3
                                temp = flipud(gray);
                                c_index = colormap(app.UIAxes, temp);
                        end
        
                        % スリップの最大最小値を取得
                        if abs(slip_min) > abs(slip_max)
                            sb = abs(slip_min);
                        else
                            sb = abs(slip_max);
                        end
                        if slip_max == 0.0 && slip_min == 0.0
                            sb = 1.0;  % 値がない場合
                        end
                        if isempty(app.SYSTEM_VARS.C_SLIP_SAT)
                            app.SYSTEM_VARS.C_SLIP_SAT = sb;
                        end
                        c_unit = (app.SYSTEM_VARS.C_SLIP_SAT + app.SYSTEM_VARS.C_SLIP_SAT) / 64;
        
                        if app.ASSIST_VARS.F3D_SLIP_TYPE == 1
                            c_unit = app.SYSTEM_VARS.C_SLIP_SAT / 64;
                            rgb = sqrt(app.OKADA_OUTPUT.S_ELEMENT(k, 5) ^ 2.0 + app.OKADA_OUTPUT.S_ELEMENT(k, 6) ^ 2.0) / c_unit;
                        elseif app.ASSIST_VARS.F3D_SLIP_TYPE == 2
                            rgb = (app.OKADA_OUTPUT.S_ELEMENT(k, 5) + app.SYSTEM_VARS.C_SLIP_SAT) / c_unit;
                        else
                            rgb = (app.OKADA_OUTPUT.S_ELEMENT(k, 6) + app.SYSTEM_VARS.C_SLIP_SAT) / c_unit;
                        end
                        ni = int8(round(rgb));
                        if ni == 0
                            % 値が0の場合, 1を代入
                            ni = 1;
                        elseif ni > 64
                            % 最大値を超えた場合, 64を代入
                            ni = 64;  
                        elseif ni < 0
                            % Kode 100以外の場合, 64を代入
                            ni = 64;
                        end
                        fcolor = c_index(ni, :);
                    else
                        c_index = zeros(64, 3);
                        switch app.SYSTEM_VARS.PREF(7, 1)
                            case 1
                                c_index = colormap(app.UIAxes, jet);
                            case 2
                                c_index = colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);
                            case 3
                                temp = gray;
                                temp = flipud(temp);
                                c_index = colormap(app.UIAxes, temp);
                        end
                        c_unit = app.GRAPHICS_VARS.C_SAT * 2.0 / 64;
                        if isempty(app.ASSIST_VARS.EC_STRESS_TYPE) == 1
                            app.ASSIST_VARS.EC_STRESS_TYPE = 1; % default
                        end
                        if app.ASSIST_VARS.EC_STRESS_TYPE == 1
                            rgb = app.ASSIST_VARS.COULOMB_RIGHT(k) / app.GRAPHICS_VARS.C_SAT;
                        elseif app.ASSIST_VARS.EC_STRESS_TYPE == 2
                            rgb = app.ASSIST_VARS.COULOMB_UP(k) / app.GRAPHICS_VARS.C_SAT;
                        elseif app.ASSIST_VARS.EC_STRESS_TYPE == 3
                            rgb = app.ASSIST_VARS.COULOMB_PREF(k) / app.GRAPHICS_VARS.C_SAT;
                        elseif app.ASSIST_VARS.EC_STRESS_TYPE == 4
                            temp = isnan(app.ASSIST_VARS.COULOMB_RAKE(k));
                            rgb = app.ASSIST_VARS.COULOMB_RAKE(k) / app.GRAPHICS_VARS.C_SAT;
        
                            if temp == 1
                                rgb = 0.0;
                            end
                        else
                            rgb = app.ASSIST_VARS.EC_NORMAL(k) / app.GRAPHICS_VARS.C_SAT;
                        end
        
                        if rgb > 1.0
                            fcolor = c_index(64, :);
                        elseif rgb <= -1.0
                            fcolor = c_index(1, :);
                        else
                            ni = int8(round((rgb * app.GRAPHICS_VARS.C_SAT + app.GRAPHICS_VARS.C_SAT) / c_unit)) + 1;
                            if ni > 64
                                ni = 64; % in case
                            end
                            fcolor = c_index(ni, :);
                        end
                    end
                
                    % 図の塗りつぶし描画
                    if ~(app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID))
                        b = fill(app.UIAxes, [xcs xcf xcf xcs xcs], [ycf ycf ycs ycs ycf], fcolor);
                    end
        
                    % スリップ深度と座標の更新
                    axis(app.UIAxes, 'equal');
                    if app.OKADA_OUTPUT.S_ELEMENT(k, 9) > depth_max
                        depth_max = app.OKADA_OUTPUT.S_ELEMENT(k, 9);
                    end
                    zlim(app.UIAxes, [-depth_max * 2.0, topz + 1]);
            
                    % 修正前、strikeが180より大きいときに断層が対称に描かれてしまうバグ
                    % if ~(app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID))
                    %     denom = app.OKADA_OUTPUT.S_ELEMENT(k, 3) - app.OKADA_OUTPUT.S_ELEMENT(k, 1);
                    %     a = atan2(app.OKADA_OUTPUT.S_ELEMENT(k, 4) - app.OKADA_OUTPUT.S_ELEMENT(k, 2), denom);
                    %     if app.OKADA_OUTPUT.S_ELEMENT(k, 1) > app.OKADA_OUTPUT.S_ELEMENT(k, 3)
                    %         rstr = 1.5 * pi - a;
                    %     else
                    %         rstr = pi / 2.0 - a;
                    %     end
                    %     rdip = deg2rad(app.OKADA_OUTPUT.S_ELEMENT(k, 7));
                    % end
                    % 
                    % % 転送行列の設定と変換
                    % % 図形の変換行列
                    % if ~(app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID))
                    %     % ---- hgtransform の準備 ----
                    %     t = hgtransform(app.UIAxes);
                    %     set(b,'Parent', t);
                    %     % ---- スケーリング行列（現在は等倍） ----
                    %     Rsc = makehgtform('scale', [1, 1, 1]);
                    %     Rsc2 = makehgtform('scale', [1, 1, 1]); % 追加のスケーリング
                    %     % ---- 中心座標を計算（shift用）----
                    %     xshift = (xcf + xcs) / 2.0;
                    %     yshift = (ycf + ycs) / 2.0;
                    %     % ---- 回転行列 ----
                    %     Rz = makehgtform('zrotate', double(pi - rstr));
                    %     Rx = makehgtform('yrotate', double(-rdip));
                    %     % ---- 平行移動行列 ----
                    %     Rt  = makehgtform('translate', [xshift yshift -middepth]);
                    %     Rt2  = makehgtform('translate', [-xshift -yshift 0]); % 逆向きの平行移動
                    %     % ---- 合成変換行列を適用 ----
                    %     t.Matrix = Rt * Rsc * Rz * Rsc2 * Rx * Rt2;   % 変換の適用順序
                    % end

                    % 修正後、なぜか治った。
                    if ~(app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID))
                        % ---- 走向角 (rstr) の計算：象限を考慮して常に一意な角度を取得 ----
                        dx = app.OKADA_OUTPUT.S_ELEMENT(k, 3) - app.OKADA_OUTPUT.S_ELEMENT(k, 1);
                        dy = app.OKADA_OUTPUT.S_ELEMENT(k, 4) - app.OKADA_OUTPUT.S_ELEMENT(k, 2);
                        rstr = atan2(dy, dx);  % ← これだけでOK。条件分岐不要。
                    
                        % ---- 傾斜角 (rdip) [rad] ----
                        rdip = deg2rad(app.OKADA_OUTPUT.S_ELEMENT(k, 7));
                    
                        % ---- hgtransform の準備 ----
                        t = hgtransform(app.UIAxes);
                        set(b, 'Parent', t);
                    
                        % ---- スケーリング行列（必要なら調整）----
                        Rsc  = makehgtform('scale', [1, 1, 1]);
                        Rsc2 = makehgtform('scale', [1, 1, 1]);
                    
                        % ---- 中心座標（回転の基準点）----
                        xshift = (xcf + xcs) / 2.0;
                        yshift = (ycf + ycs) / 2.0;
                    
                        % ---- 回転行列 ----
                        % Z軸回りに (rstr - pi/2) 回して、+y軸を走向に合わせる
                        R_align = makehgtform('zrotate', double(rstr - pi/2));
                    
                        % 走向軸（+y）回りに +rdip だけ回して、常に下向き(-Z)に傾ける
                        R_dip = makehgtform('yrotate', double(+rdip));
                    
                        % ---- 平行移動行列 ----
                        Rt  = makehgtform('translate', [xshift, yshift, -middepth]);
                        Rt2 = makehgtform('translate', [-xshift, -yshift, 0]);
                    
                        % ---- 合成変換行列を適用 ----
                        % 回転の順序に注意：Rt * R_align * R_dip * Rt2
                        t.Matrix = Rt * Rsc * R_align * Rsc2 * R_dip * Rt2;
                    end

                
                    %----- to plot arrows on the fault (rake arrow) ----------------------
                    % skip putting rake arrows to the rendering much faster!
                    % 矢印（rake arrow）の描画
                    if app.SYSTEM_VARS.IMAXSHEAR == 2
                        continue;  % 矢印を描画しない場合
                    end
        
                    adj = 2.0;
                    offset = 0.2;
        
                    % Define variables depending on the coordinate system
                    % 矢印のサイズと位置の設定
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        unit_arrow = sqrt((temp_element(k, 3) - temp_element(k, 1))^2 + (temp_element(k, 4) - temp_element(k, 2))^2) * 0.03;
                        z0 = (app.OKADA_OUTPUT.S_ELEMENT(k, 8) + app.OKADA_OUTPUT.S_ELEMENT(k, 9)) / 2.0;
                        fc = fault_corners(app, temp_element(k, 1), temp_element(k, 2), temp_element(k, 3), temp_element(k, 4), ...
                            app.OKADA_OUTPUT.S_ELEMENT(k, 7), app.OKADA_OUTPUT.S_ELEMENT(k, 8), (app.OKADA_OUTPUT.S_ELEMENT(k, 8) + app.OKADA_OUTPUT.S_ELEMENT(k, 9)) / 2.0);
                    else
                        % cartesian座標
                        unit_arrow = sqrt((app.OKADA_OUTPUT.S_ELEMENT(k, 3) - app.OKADA_OUTPUT.S_ELEMENT(k, 1))^2 + (app.OKADA_OUTPUT.S_ELEMENT(k, 4) - app.OKADA_OUTPUT.S_ELEMENT(k, 2))^2) * 0.03;
                        z0 = (app.OKADA_OUTPUT.S_ELEMENT(k, 8) + app.OKADA_OUTPUT.S_ELEMENT(k, 9)) / 2.0;
                        fc = fault_corners(app, app.OKADA_OUTPUT.S_ELEMENT(k, 1), app.OKADA_OUTPUT.S_ELEMENT(k, 2), app.OKADA_OUTPUT.S_ELEMENT(k, 3), app.OKADA_OUTPUT.S_ELEMENT(k, 4), ...
                            app.OKADA_OUTPUT.S_ELEMENT(k, 7), app.OKADA_OUTPUT.S_ELEMENT(k, 8), (app.OKADA_OUTPUT.S_ELEMENT(k, 8) + app.OKADA_OUTPUT.S_ELEMENT(k, 9)) / 2.0);
                    end
                
                    % 矢印の描画 (quiver3)
                    deno = fc(4, 2) - fc(3, 2);
                    if deno == 0
                        deno = 0.0001; 
                    end
                    x0 = (fc(4, 1) + fc(3, 1)) / 2.0;
                    y0 = (fc(4, 2) + fc(3, 2)) / 2.0;
        
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        a = xy2lonlat(app, [x0 y0]);
                        x0 = a(1);
                        y0 = a(2);
                        offset = (app.ASSIST_VARS.LON_PER_X + app.ASSIST_VARS.LAT_PER_Y) / 2.0 * offset;
                        unit_arrow = (app.ASSIST_VARS.LON_PER_X + app.ASSIST_VARS.LAT_PER_Y) / 2.0 * unit_arrow;
                    end
        
                    % 傾斜角の計算と変換
                    str = rad2deg(atan((fc(4, 1) - fc(3, 1)) / deno));
                    if deno < 0.0
                        c = obj_trans(0, -app.OKADA_OUTPUT.S_ELEMENT(k, 7), str, 0, 0, 0, 1, 1, 1);
                    else
                        c = obj_trans(0, app.OKADA_OUTPUT.S_ELEMENT(k, 7), str, 0, 0, 0, 1, 1, 1);
                    end
                    c1 = c.';
        
                    % if rake is specified even without any slip (the case of app.CALC_CONTROL.IRAKE = 1)
                    % スリップが指定されている場合の処理
                    if isempty(app.ASSIST_VARS.EC_STRESS_TYPE) || app.ASSIST_VARS.EC_STRESS_TYPE == 4 || app.ASSIST_VARS.EC_STRESS_TYPE == 5
                        if app.CALC_CONTROL.IRAKE == 1
                            [latslip, dipslip] = rake2comp(app, app.CALC_CONTROL.IND_RAKE(k, 1), unit_arrow);
                        else
                            [rake, ~] = comp2rake(app, app.OKADA_OUTPUT.S_ELEMENT(k, 5), app.OKADA_OUTPUT.S_ELEMENT(k, 6));
                            [latslip, dipslip] = rake2comp(app, rake, unit_arrow);
                        end
                    elseif app.ASSIST_VARS.EC_STRESS_TYPE == 1 % 右横ずれ
                        [latslip, dipslip] = rake2comp(app, 180.0, unit_arrow);
                    elseif app.ASSIST_VARS.EC_STRESS_TYPE == 2 % 逆断層
                        [latslip, dipslip] = rake2comp(app, 90.0, unit_arrow);
                    elseif app.ASSIST_VARS.EC_STRESS_TYPE == 3 % 指定されたレイク
                        [latslip, dipslip] = rake2comp(app, app.ASSIST_VARS.EC_RAKE, unit_arrow);
                    end
            
                    % 変換行列を使って方向を決定
                    tf1 = c1 * [-dipslip; -latslip; 0];
                    tf2 = c1 * [dipslip; latslip; 0];
        
                    % 矢印の描画 (rake arrow)
                    quiver3(app.UIAxes, x0 + offset, y0 + offset, -z0, tf1(1) * adj, tf1(2) * adj, tf1(3) * adj, 2, 'Color', 'b', 'LineWidth', 1.0); % 一方の矢印
                    quiver3(app.UIAxes, x0 - offset, y0 - offset, -z0, tf2(1) * adj, tf2(2) * adj, tf2(3) * adj, 2, 'Color', 'b', 'LineWidth', 1.0); % もう一方の矢印
                    
                    %------ plot a circle in 'plot3d' for point source calculation -------------   
                    % 点源計算用の円を描画
                    if app.OKADA_OUTPUT.S_ELEMENT(k, 10) == 400 || app.OKADA_OUTPUT.S_ELEMENT(k, 10) == 500
                        tm = [app.OKADA_OUTPUT.S_ELEMENT(k, 1), app.OKADA_OUTPUT.S_ELEMENT(k, 2), app.OKADA_OUTPUT.S_ELEMENT(k, 3), app.OKADA_OUTPUT.S_ELEMENT(k, 4), ...
                            app.OKADA_OUTPUT.S_ELEMENT(k, 7), app.OKADA_OUTPUT.S_ELEMENT(k, 8), app.OKADA_OUTPUT.S_ELEMENT(k, 9)];
                        e_center = zeros(1, 3);
                        middle = (tm(6) + tm(7)) / 2.0;
                        fc = fault_corners(app, tm(1), tm(2), tm(3), tm(4), tm(5), tm(6), middle);
                        e_center(1, 1) = (fc(4, 1) + fc(3, 1)) / 2.0;
                        e_center(1, 2) = (fc(4, 2) + fc(3, 2)) / 2.0;
                        e_center(1, 3) = -middle; 
                        plot3(app.UIAxes, e_center(1, 1), e_center(1, 2), e_center(1, 3), 'ko'); % 円の中心に点を描画
                    end
                end
                %============= k loop ================== (end)
        
                %    ---- title and legend bar -------------------------
                % 3D表示におけるタイトルとカラーバーの設定
                % 一部タイトルはボタン変更での処理に変更
                if app.CALC_CONTROL.FUNC_SWITCH == 5 || app.CALC_CONTROL.FUNC_SWITCH == 5.5
                    temp = app.GRAPHICS_VARS.C_SAT;
                    dm = max(uuz * double(resz));
                    a = isnan(dm);
                    dm(a > 0) = 0;
                    app.GRAPHICS_VARS.C_SAT = max(rot90(dm)) * 0.3;
        
                    %---in case (temporal solution, need to be fixed) !!!!
                    % 問題がある場合に解決するための一時的な処理
                    if app.GRAPHICS_VARS.C_SAT < 0
                        app.GRAPHICS_VARS.C_SAT = abs(app.GRAPHICS_VARS.C_SAT);
                    end
                    %---------
                    if isnan(app.GRAPHICS_VARS.C_SAT)
                        app.GRAPHICS_VARS.C_SAT = 1.0;
                    end
        
                    % カラーマップの範囲を設定
                    clim(app.UIAxes, [(-1.0) * app.GRAPHICS_VARS.C_SAT - app.INPUT_VARS.CALC_DEPTH, app.GRAPHICS_VARS.C_SAT - app.INPUT_VARS.CALC_DEPTH]);
                    colorbar(app.UIAxes, 'Location', 'SouthOutside');
                    app.GRAPHICS_VARS.C_SAT = temp;
        
                elseif app.CALC_CONTROL.FUNC_SWITCH == 1
                    if app.ASSIST_VARS.F3D_SLIP_TYPE == 1
                        clim(app.UIAxes, [0.0, app.SYSTEM_VARS.C_SLIP_SAT]);
                    elseif app.ASSIST_VARS.F3D_SLIP_TYPE == 2
                        clim(app.UIAxes, [-app.SYSTEM_VARS.C_SLIP_SAT, app.SYSTEM_VARS.C_SLIP_SAT]);
                    else
                        clim(app.UIAxes, [-app.SYSTEM_VARS.C_SLIP_SAT, app.SYSTEM_VARS.C_SLIP_SAT]);
                    end
                    colorbar(app.UIAxes, 'Location', 'SouthOutside');
        
                elseif app.CALC_CONTROL.FUNC_SWITCH == 10
                    switch app.ASSIST_VARS.EC_STRESS_TYPE
                        case 1
                            title(app.UIAxes, 'Coulomb stress change for right-lat. slip (bar)', 'FontSize', 18);
                        case 2
                            title(app.UIAxes, 'Coulomb stress change for reverse slip (bar)', 'FontSize', 18);
                        case 3
                            title(app.UIAxes, ['Coulomb stress change for specified rake ' num2str(int16(app.ASSIST_VARS.EC_RAKE)) ' deg. (bar)'], 'FontSize', 18);
                        case 4
                            title(app.UIAxes, 'Coulomb stress change for individual rake (bar)', 'FontSize', 18);
                        case 5
                            title(app.UIAxes, 'Unclamping stress change (bar, unclamping positive)', 'FontSize', 18);
                        otherwise
                            title(app.UIAxes, 'Coulomb stress change (bar)', 'FontSize', 18);
                    end
                    clim(app.UIAxes, [(-1.0) * app.GRAPHICS_VARS.C_SAT, app.GRAPHICS_VARS.C_SAT]);
                    colorbar(app.UIAxes, 'Location', 'SouthOutside');
                    
                elseif app.CALC_CONTROL.FUNC_SWITCH == 5.7
                    % ボタン変更の部分に移動
                    % title(app.UIAxes, '3D displacement vectors', 'FontSize', 18);
                end
                
                % X軸・Y軸の範囲設定
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    xlim(app.UIAxes, [app.COORD_VARS.MIN_LON, app.COORD_VARS.MAX_LON]);
                    ylim(app.UIAxes, [app.COORD_VARS.MIN_LAT, app.COORD_VARS.MAX_LAT]);
                else
                    % cartesian座標
                    xlim(app.UIAxes, [min(app.COORD_VARS.XGRID), max(app.COORD_VARS.XGRID)]);
                    ylim(app.UIAxes, [min(app.COORD_VARS.YGRID), max(app.COORD_VARS.YGRID)]);
                end
                
                % アスペクト比の設定
                daspect(app.UIAxes, xyz_aspect);
                
                % 緯度経度の座標系であれば元に戻す
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    app.OKADA_OUTPUT.S_ELEMENT(:, 1:4) = temp_element;
                end

            end
        
            % ネスト関数-------------------------------------------------
            function [c] = obj_trans(rx, ry, rz, tx, ty, tz, sx, sy, sz)
                % rx = 30;
                % ry = 0;
                % rz = 0;
                % tx = 0;
                % ty = 0;
                % tz = 0;
                % sx = 1;
                % sy = 1;
                % sz = 1;
                rotx = [1 0 0 0;
                        0 cos(deg2rad(rx)) -sin(deg2rad(rx)) 0;
                        0 sin(deg2rad(rx)) cos(deg2rad(rx)) 0;
                        0 0 0 1];
                roty = [cos(deg2rad(ry)) 0 sin(deg2rad(ry)) 0;
                        0 1 0 0;
                        -sin(deg2rad(ry)) 0 cos(deg2rad(ry)) 0;
                        0 0 0 1];
                rotz = [cos(deg2rad(rz)) -sin(deg2rad(rz)) 0 0;
                        sin(deg2rad(rz)) cos(deg2rad(rz)) 0 0;
                        0 0 1 0;
                        0 0 0 1];
                r = rotx * roty * rotz;
                t = [1 0 0 tx; 0 1 0 ty; 0 0 1 tz; 0 0 0 1];
                s = [sx 0 0 0; 0 sy 0 0; 0 0 sz 0; 0 0 0 1];
                c0 = r * t * s;
                c = [c0(1, 1) c0(1, 2) c0(1, 3);
                     c0(2, 1) c0(2, 2) c0(2, 3);
                     c0(3, 1) c0(3, 2) c0(3, 3)];
            end
        end


        % ==========================================================================================================================================
        %                ButtonGroup function
        % ==========================================================================================================================================
        function menu_displacementcou_read_save(app, c)
            % =====================================================================
            %                menu_displacementcou_read_save(app, c)
            % =====================================================================
            % Displacement.couを読み込んで、上書きする関数
            cd (app.SYSTEM_VARS.HOME_DIR);
            format long;

            % 以下は旧バージョン
            % header1 = ['Input file selected: ', app.SYSTEM_VARS.INPUT_FILE]; % 選択された入力ファイル
            % header2 = 'x y z UX UY UZ';             % ヘッダー
            % header3 = '(km) (km) (km) (m) (m) (m)'; % ヘッダー
            % dlmwrite('Displacement.cou', header1, 'delimiter', '');            % Displacement.couにheader1を書き込む
            % dlmwrite('Displacement.cou', header2, '-append', 'delimiter', ''); % Displacement.couにheader2を追加
            % dlmwrite('Displacement.cou', header3, '-append', 'delimiter', ''); % Displacement.couにheader3を追加
            % dlmwrite('Displacement.cou', c, '-append', 'delimiter', '\t', 'precision', '%.8f'); % Displacement.couにcを追加
            % disp(['Displacement.cou is saved in ' pwd]);                                        % Displacement.couが保存されました
            % cd (app.SYSTEM_VARS.HOME_DIR);

            % データファイル 'Displacement.cou' からデータを読み込む
            filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Displacement.cou');
            fid = fopen(filePath, 'w');
            % ヘッダーを書き込む
            fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
            fprintf(fid, 'x y z UX UY UZ\n');
            fprintf(fid, '(km) (km) (km) (m) (m) (m)\n');
            % 数値データの書き込み（行ごとに書く）
            % c が Nx6 の行列と仮定
            for i = 1:size(c, 1)
                fprintf(fid, '%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\n', c(i, :));
            end
            fclose(fid);
            disp(['Displacement.cou is saved in ', pwd]);
            showMessage(app, ['Displacement.cou is saved in ', pwd], false);
            cd(app.SYSTEM_VARS.HOME_DIR);
        end


        function menu_grid_mapview_Callback(app)
            % =====================================================================
            %                menu_grid_mapview_Callback(app)
            % =====================================================================
            % ---------------mapview2DButton---------------
            % 2D map view
            msg = msgbox('Now calculating...', 'Notification', 'help');
            % メインウィンドウの既存のグラフを削除（app.UIAxesの内容をクリア）
            cla(app.UIAxes);
            % 2Dビューに変更
            view(app.UIAxes, 2);
            % カラーバーやタイトルも再設定
            title(app.UIAxes, '');
            % カラーバーをオフにする
            colorbar(app.UIAxes, 'off');

            hold(app.UIAxes, 'on');
            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            app.CALC_CONTROL.FUNC_SWITCH = 1;
            grid_drawing(app);
            fault_overlay(app);

            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end

            app.CALC_CONTROL.FUNC_SWITCH = 0;  % reset to 0
            flag = check_lonlat_info(app);     % 経度と緯度の情報をチェック
            app.OverlaysPanel.Enable = 'on';   % オーバーレイボックスを有効化
            if flag == 1
                % flagが1の場合
                app.FunctionButtonGroup.Enable = 'on';                 % Faultの操作ボタンを有効化
                app.CalcDepthEditField.Enable = 'on';           % 計算震度ボックスを有効化
                app.FrictionEditField.Enable = 'on';           % 摩擦ボックスを有効化
                app.DisplexaggerationEditField.Enable = 'on';  % 誇張ボックスを有効化
                app.CoordinatesSwitch.Enable = 'on';           % 座標切り替えボタンを有効化
                % オーバーレイボックスを有効化
                all_overlay_enable_on(app);
            end
            delete(msg);
        end
        
        
        function menu_grid_3d_Callback(app)
            % =====================================================================
            %                menu_grid_3d_Callback(app)
            % =====================================================================
            % ---------------view3DButton---------------
            % 3D view
            % 3Dグリッド描画のコールバック関数
            msg = msgbox('Now calculating...', 'Notification', 'help');
            % 緯度経度座標であれば警告を表示し、ボタンをmapview2DButtonにして再計算
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                uialert(app.main_menu_window, 'Sorry this is not available for lat / lon coordinates. Change to Cartesian coordinates.', 'Warning!');
                app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
                menu_grid_mapview_Callback(app);
                return;
            end

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % 関数スイッチを1に設定
            app.CALC_CONTROL.FUNC_SWITCH = 1;
            % ネットスリップ設定
            app.ASSIST_VARS.F3D_SLIP_TYPE = 1;  
            
            % 要素条件を適用
            element_condition(app, app.INPUT_VARS.ELEMENT, app.INPUT_VARS.POIS, app.INPUT_VARS.YOUNG, app.INPUT_VARS.FRIC, app.INPUT_VARS.ID);

            % C_SLIP_SATを空にする
            app.SYSTEM_VARS.C_SLIP_SAT = [];  
            
            % UIAxesに3Dグリッドを描画
            grid_drawing_3d(app);  
            
            % 3Dオーバーレイなどの追加処理
            displ_open(app, 2);  
            
            % GNSS 3DオーバーレイをUIAxesで描画
            overlay_gnss_3d_drawing(app);

            % 経度と緯度の情報をチェック
            flag = check_lonlat_info(app); 
            if flag == 1
                all_overlay_enable_on(app);  % すべてのオーバーレイを有効にする
            end

            % Coulomb stress windowを起動
            % F3DwiredPanelを有効化
            app.F3DwiredPanel.Enable = 'on';
            app.F3DwiredPanel.Visible = 'on';

            % View panelを有効化
            app.ViewPanel.Enable = 'on';
            app.ViewPanel.Visible = 'on';

            % 初期化関数
            F3Dwired_panel_start(app);
            
            delete(msg);
        end
        
        
        function menu_vectors_Callback(app)
            % =====================================================================
            %                menu_vectors_Callback(app)
            % =====================================================================
            % ---------------HDvectorsButton---------------
            % Horizontal Displ. (vectors)
            msg = msgbox('Now calculating...', 'Notification', 'help');
            app.CALC_CONTROL.FUNC_SWITCH = 2; % 関数スイッチを2に設定
            app.ASSIST_VARS.FIXFLAG = 0;      % app.ASSIST_VARS.FIXFLAGを0に設定
        
            % % Okadaハーフスペースの再計算を回避するため
            if app.CALC_CONTROL.IACT ~= 1        
                Okada_halfspace(app); % Okadaハーフスペースを計算
            end
            % Okada_halfspace(app); % Okadaハーフスペースを計算
        
            app.CALC_CONTROL.IACT = 1;         % Okadaの出力を保持するため
            a = app.OKADA_OUTPUT.DC3D(:, 1:2); % DC3Dの1から2列を取得
            b = app.OKADA_OUTPUT.DC3D(:, 5:8); % DC3Dの5から8列を取得
            c = horzcat(a, b);                 % aとbを水平に連結

            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            displ_open(app, 2); % 2を開く

            % 計算後、固定点のボタンなどを有効化
            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';

            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % 固定点関連を有効化
            app.FixpointPanel.Enable = 'on';

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';
            
            % 座標系に応じて固定点の表示単位を変更
            if app.CoordinatesSwitch.ValueIndex == 1
                % XYZのとき
                app.FP_xkmLabel.Text = "km";
                app.FP_ykmLabel.Text = "km";
            else
                % Lonlatのとき
                app.FP_xkmLabel.Text = "°";
                app.FP_ykmLabel.Text = "°";
            end
        
            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイを有効にする
            end
        
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end

            delete(msg);
        end

        
        function menu_wireframe_Callback(app)
            % =====================================================================
            %                menu_wireframe_Callback(app)
            % =====================================================================
            % ---------------HDwireframeButton---------------
            % Horizontal Displ. (wireframe)
            msg = msgbox('Now calculating...', 'Notification', 'help');
            app.CALC_CONTROL.FUNC_SWITCH = 3; % 関数スイッチを3に設定
            app.ASSIST_VARS.FIXFLAG = 0;      % FIXFLAGを0に設定
            % Okadaハーフスペースの再計算を回避するため
            if app.CALC_CONTROL.IACT ~= 1
                Okada_halfspace(app);           % Okadaハーフスペースを計算
            end
            app.CALC_CONTROL.IACT = 1;         % Okadaの出力を保持するため
            a = app.OKADA_OUTPUT.DC3D(:, 1:2); % DC3Dの1から2列を取得
            b = app.OKADA_OUTPUT.DC3D(:, 5:8); % DC3Dの5から8列を取得
            c = horzcat(a, b);             % aとbを水平に連結

            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            displ_open(app, 2); % 2を開く

            % 計算後、固定点の場端などを有効化
            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';

            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';

            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイを有効にする
            end

            % ----- overlay drawing オーバーレイの描画 --------------------------------
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end

            delete(msg);
        end

        
        function menu_contours_Callback(app)
            % =====================================================================
            %                menu_contours_Callback(app)
            % =====================================================================
            % ---------------VDButton---------------
            % Vertical Displ.
            msg = msgbox('Now calculating...', 'Notification', 'help');
            app.CALC_CONTROL.FUNC_SWITCH = 4;  % 関数スイッチを4に設定
            app.ASSIST_VARS.VD_CHECKED = 0;    % default
            app.CALC_CONTROL.SHADE_TYPE = 1;   % default

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';

            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効化
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効化
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            app.IntervalUnitLabel.Visible = 'on';

            % クロスセクション関連を有効化
            % クロスセクションは修正を検討する必要あり→たぶん不要
            % app.CrossSectionButton.Enable = 'on';

            grid_drawing(app); % グリッドの描画

            if app.CALC_CONTROL.IACT ~= 1
                Okada_halfspace(app);           % Okadaハーフスペースを計算
            end
            
            app.CALC_CONTROL.IACT = 1;         % to keep okada output
            a = app.OKADA_OUTPUT.DC3D(:, 1:2); % DC3Dの1から2列を取得
            b = app.OKADA_OUTPUT.DC3D(:, 5:8); % DC3Dの5から8列を取得
            c = horzcat(a, b);             % aとbを水平に連結

            cd (app.SYSTEM_VARS.HOME_DIR);
            format long;
            save Displacement.cou a -ascii
            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            displ_open(app, 2);
            fault_overlay(app); % フォルトオーバーレイ

            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイを有効にする
            end

            % ----- overlay drawing オーバーレイの描画 --------------------------------
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end

            delete(msg);
        end

        
        function menu_3d_Callback(app)
            % =====================================================================
            %                menu_3d_Callback(app)
            % =====================================================================
            % ---------------image3DdrapeButton---------------
            % 3D image drape
            app.CALC_CONTROL.FUNC_SWITCH = 5;
            if app.CoordinatesSwitch.ValueIndex == 2
                % if app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID) ~= 1
                % 警告ダイアログを表示
                uialert(app.main_menu_window, 'Sorry faults would be invisible so far. To see complete view, change to Cartesian coordinates.', 'Warning!');
            end
            msg = msgbox('Now calculating...', 'Notification', 'help');
            % Okadaハーフスペースの再計算を回避するため
            if app.CALC_CONTROL.IACT ~= 1        
                Okada_halfspace(app);
            end
            app.CALC_CONTROL.IACT = 1;
            a = app.OKADA_OUTPUT.DC3D(:, 1:2);
            b = app.OKADA_OUTPUT.DC3D(:, 5:8);
            c = horzcat(a, b);

            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効に
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効に
            app.CoordinatesSwitch.Enable = 'on';

            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイ関連を有効にする
            end

            % 描画
            grid_drawing_3d(app);
            hold(app.UIAxes, 'on');  % app.UIAxes で保持
            displ_open(app, 2);

            % 'xlines'タグのついた海岸線の描画を削除
            xlines = findall(app.UIAxes, 'Tag', 'xlines');
            if ~isempty(xlines)
                delete(xlines);
            end
            % 'ylines'タグのついた海岸線の描画を削除
            ylines = findall(app.UIAxes, 'Tag', 'ylines');
            if ~isempty(ylines)
                delete(ylines);
            end

            % View panelを有効化
            app.ViewPanel.Enable = 'on';
            app.ViewPanel.Visible = 'on';

            delete(msg);
        end
        
        
        function menu_3d_wire_Callback(app)
            % =====================================================================
            %                menu_3d_wire_Callback(app)
            % =====================================================================
            % ---------------image3DwireframedsurfaceButton---------------
            % 3D image wire framed surface
            app.CALC_CONTROL.FUNC_SWITCH = 5.5; % 関数スイッチを5.5に設定
            if app.CoordinatesSwitch.ValueIndex == 2
                % if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % 警告ダイアログを表示
                uialert(app.main_menu_window, 'Sorry faults would be invisible so far. To see complete view, change to Cartesian coordinates.', 'Warning!');
            end
            msg = msgbox('Now calculating...', 'Notification', 'help');
            % Okadaハーフスペースの再計算を回避するため
            if app.CALC_CONTROL.IACT ~= 1        
                Okada_halfspace(app); % Okadaハーフスペースを計算
            end
            app.CALC_CONTROL.IACT = 1;         % to keep okada output
            a = app.OKADA_OUTPUT.DC3D(:, 1:2); % DC3Dの1から2列を取得
            b = app.OKADA_OUTPUT.DC3D(:, 5:8); % DC3Dの5から8列を取得
            c = horzcat(a, b);             % aとbを水平に連結

            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効に
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効に
            app.CoordinatesSwitch.Enable = 'on';

            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイ関連を有効にする
            end

            % 描画
            grid_drawing_3d(app);
            hold(app.UIAxes, 'on');  % app.UIAxes で保持                     
            % 3Dグリッドの描画
            displ_open(app, 2); % 2を開く

            % 'xlines'タグのついた海岸線の描画を削除
            xlines = findall(app.UIAxes, 'Tag', 'xlines');
            if ~isempty(xlines)
                delete(xlines);
            end
            % 'ylines'タグのついた海岸線の描画を削除
            ylines = findall(app.UIAxes, 'Tag', 'ylines');
            if ~isempty(ylines)
                delete(ylines);
            end

            % View panelを有効化
            app.ViewPanel.Enable = 'on';
            app.ViewPanel.Visible = 'on';

            delete(msg);
        end
        
        
        function menu_3d_vectors_Callback(app)
            % =====================================================================
            %                menu_3d_vectors_Callback(app)
            % =====================================================================
            % ---------------vectors3DButton---------------
            % 3D vectors
            app.CALC_CONTROL.FUNC_SWITCH = 5.7; % 関数スイッチを5.7に設定
            if app.CoordinatesSwitch.ValueIndex == 2
                % if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID) % COORD_VARS.ICOORDが2で、COORD_VARS.LON_GRIDが空でない場合
                % 警告ダイアログを表示
                uialert(app.main_menu_window, 'Sorry faults would be invisible so far. To see complete view, change to Cartesian coordinates.', 'Warning!');
            end
            msg = msgbox('Now calculating...', 'Notification', 'help');
            % Okadaハーフスペースの再計算を回避するため
            if app.CALC_CONTROL.IACT ~= 1        
                Okada_halfspace(app);
            end
            app.CALC_CONTROL.IACT = 1;
            a = app.OKADA_OUTPUT.DC3D(:, 1:2);
            b = app.OKADA_OUTPUT.DC3D(:, 5:8);
            c = horzcat(a, b);

            % Displacement.couに書き込み
            menu_displacementcou_read_save(app, c);

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効に
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効に
            app.CoordinatesSwitch.Enable = 'on';

            flag = check_lonlat_info(app); % 経度と緯度の情報をチェック
            if flag == 1 % flagが1の場合
                all_overlay_enable_on(app); % すべてのオーバーレイ関連を有効にする
            end

            % 描画
            grid_drawing_3d(app);
            hold(app.UIAxes, 'on');  % app.UIAxes で保持
            displ_open(app, 2);

            % 'xlines'タグのついた海岸線の描画を削除
            xlines = findall(app.UIAxes, 'Tag', 'xlines');
            if ~isempty(xlines)
                delete(xlines);
            end
            % 'ylines'タグのついた海岸線の描画を削除
            ylines = findall(app.UIAxes, 'Tag', 'ylines');
            if ~isempty(ylines)
                delete(ylines);
            end

            % View panelを有効化
            app.ViewPanel.Enable = 'on';
            app.ViewPanel.Visible = 'on';

            delete(msg);
        end
        

        function menu_shear_stress_change_Callback(app)
            % =====================================================================
            %                 menu_shear_stress_change_Callback(app)
            % =====================================================================
            % ---------------ShearstresschangeButton---------------
            % Shear stress change
            app.CALC_CONTROL.IACT = 0;
            app.CALC_CONTROL.FUNC_SWITCH = 7;
            app.CALC_CONTROL.STRESS_TYPE = 5;

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効化
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効化
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            app.IntervalUnitLabel.Visible = 'on';
            % 単位のテキストを変更
            app.IntervalUnitLabel.Text = "bar";

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % 初期化関数
            SW_stress_panel_start(app);
            
            % 緯度・経度のチェック
            flag = check_lonlat_info(app);
            if flag == 1
                all_overlay_enable_on(app);
            end
        end
        

        function menu_normal_stress_change_Callback(app)
            % =====================================================================
            %                 menu_normal_stress_change_Callback(app)
            % =====================================================================
            % ---------------UnclampingstresschangeButton---------------
            % Unclamping stress change
            app.CALC_CONTROL.IACT = 0;
            app.CALC_CONTROL.FUNC_SWITCH = 8;
            app.CALC_CONTROL.STRESS_TYPE = 5;

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効化
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効化
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            app.IntervalUnitLabel.Visible = 'on';
            % 単位のテキストを変更
            app.IntervalUnitLabel.Text = "bar";

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % 初期化関数
            SW_stress_panel_start(app);
            
            % 緯度・経度のチェック
            flag = check_lonlat_info(app);
            if flag == 1
                all_overlay_enable_on(app);
            end
        end
        

        function menu_coulomb_stress_change_Callback(app)
            % =====================================================================
            %                 menu_coulomb_stress_change_Callback(app)
            % =====================================================================
            % ---------------CoulombstresschangeButton---------------
            % Coulomb stress change
            app.CALC_CONTROL.IACT = 0;
            app.CALC_CONTROL.FUNC_SWITCH = 9;
            app.CALC_CONTROL.STRESS_TYPE = 5;

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効化
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効化
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            app.IntervalUnitLabel.Visible = 'on';
            % 単位のテキストを変更
            app.IntervalUnitLabel.Text = "bar";

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % 初期化関数
            SW_stress_panel_start(app);
            
            % 緯度・経度のチェック
            flag = check_lonlat_info(app);
            if flag == 1
                % オーバーレイ関連を有効化
                all_overlay_enable_on(app);
            end
        end


        function menu_stress_change_keep_callback(app)
            % =====================================================================
            %                 menu_stress_change_keep_callback(app)
            % =====================================================================
            % 一度応力変化を計算している場合に、パネルを条件そのままで表示する関数

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Rescale outputを有効化
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効化
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効化
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            app.IntervalUnitLabel.Visible = 'on';
            % 単位のテキストを変更
            app.IntervalUnitLabel.Text = "bar";

            % クロスセクション関連を有効化
            app.CrossSectionButton.Enable = 'on';

            % Coulomb stress windowを起動
            % coulomb stress windowを有効化
            app.SW_StresswindowPanel.Enable = 'on';
            app.SW_StresswindowPanel.Visible = 'on';

            % オーバーレイ関連を有効化
            app.OverlaysPanel.Enable = 'on';
        end


        function menu_stress_on_faults_Callback(app)
            % =====================================================================
            %                 menu_stress_on_faults_Callback(app)
            % =====================================================================
            % ---------------CalcStFaultsButton---------------
            % Calc. stress on faults
            
            % そのままだと計算ボタンを押すまで空白になるので2dを表示
            app.CALC_CONTROL.FUNC_SWITCH = 1;
            grid_drawing(app);
            fault_overlay(app);
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end

            % 緯度経度座標であれば警告を表示し、ボタンをmapview2DButtonにして再計算
            if app.CoordinatesSwitch.ValueIndex == 2
                % if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                uialert(app.main_menu_window, 'Sorry this is not available for lat / lon coordinates. Change to Cartesian coordinates.', 'Warning!');
                app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
                menu_grid_mapview_Callback(app);
                return;
            end
            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % 緯度・経度のチェック
            flag = check_lonlat_info(app);
            if flag == 1
                % オーバーレイ関連を有効化
                all_overlay_enable_on(app);
            end

            app.CALC_CONTROL.FUNC_SWITCH = 10;
            % Calc. stress on faultsを開く
            % Calc stress on faults Panel windowを有効化
            app.CSOF_Calc_stress_on_faults_Panel.Enable = 'on';
            app.CSOF_Calc_stress_on_faults_Panel.Visible = 'on';

            % View panelを有効化
            app.ViewPanel.Enable = 'on';
            app.ViewPanel.Visible = 'on';

            % 初期化関数を起動
            CSOF_StOnF_start(app);
        end


        function menu_stress_on_a_fault_Callback(app)
            % =====================================================================
            %                 menu_stress_on_a_fault_Callback(app)
            % =====================================================================
            % ---------------CalcStPointButton---------------
            % Calc. stress at a point
            app.CALC_CONTROL.IACT = 0;

            % 修正、どこに問題があって以下に直したのか不明
            app.CALC_CONTROL.FUNC_SWITCH = 1;
            grid_drawing(app);
            fault_overlay(app);
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end
            % if app.CALC_CONTROL.FUNC_SWITCH ~= 7 && app.CALC_CONTROL.FUNC_SWITCH ~= 8 && app.CALC_CONTROL.FUNC_SWITCH ~= 9
            %     app.CALC_CONTROL.FUNC_SWITCH = 1;
            %     grid_drawing(app);
            %     fault_overlay(app);
            % end
            % if app.CALC_CONTROL.FUNC_SWITCH == 7 || app.CALC_CONTROL.FUNC_SWITCH == 8 || app.CALC_CONTROL.FUNC_SWITCH == 9 || app.CALC_CONTROL.FUNC_SWITCH == 10
            %     % 直前が応力の描画ボタンだった場合そのまま表示。
            %     % 修正必要、Calcボタンを推していない場合だと空白になってしまう。
            % 
            % else
            %     % 直前が応力以外の計算ボタンだった場合、2D map viewを表示
            %     app.CALC_CONTROL.FUNC_SWITCH = 1;
            %     grid_drawing(app);
            %     fault_overlay(app);
            %     % データが存在する場合、overlay_drawingを実行
            %     if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
            %             ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
            %         hold(app.UIAxes, 'on');
            %         overlay_drawing(app);
            %     end
            % end

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % 座標の切り替えスイッチを有効化
            app.CoordinatesSwitch.Enable = 'on';

            % Calc. stress at a pointを開く
            % Calc. stress at a pointを有効化
            app.CSAAP_CalcstressatapointPanel.Enable = 'on';
            app.CSAAP_CalcstressatapointPanel.Visible = 'on';

            % 座養鶏に応じてラベルの単位を変更
            if app.CoordinatesSwitch.ValueIndex == 1
                % xyz
                app.XkmEditFieldLabel.Text = "X (km)";
                app.YkmEditFieldLabel.Text = "Y (km)";
            else
                % lonlat
                app.XkmEditFieldLabel.Text = "lon. (deg)";
                app.YkmEditFieldLabel.Text = "lat. (deg)";
            end

            % 初期化関数
            CSAAP_calc_stress_point_start(app);

            % 経度と緯度の情報をチェック
            flag = check_lonlat_info(app);
            if flag == 1
                all_overlay_enable_on(app);
            end
        end
        

        function menu_focal_mech_Callback(app)
            % =====================================================================
            %                 menu_focal_mech_Callback(app)
            % =====================================================================
            % ---------------CalcStNodalplanesButton---------------
            % Calc. stress on nodal planes
            app.CALC_CONTROL.FUNC_SWITCH = 11; % 関数スイッチを11に設定
            app.ASSIST_VARS.NODAL_ACT = 0;     % app.ASSIST_VARS.NODAL_ACTを0に設定
            app.ASSIST_VARS.NODAL_STRESS = []; % app.ASSIST_VARS.NODAL_STRESSを空にする
            cd (app.SYSTEM_VARS.HOME_DIR);     % HOME_DIRに移動

            % 地震データがない場合、警告して終了し、ボタンをmapview2DButtonにして再計算
            if isempty(app.OVERLAY_VARS.EQ_DATA)
                uialert(app.main_menu_window, 'No focal mechanism data available in this catalog.', 'Warning!');
                app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
                menu_grid_mapview_Callback(app);
                return;
            end

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);
            % 経度と緯度の情報をチェック
            flag = check_lonlat_info(app);
            if flag == 1
                % オーバーレイ関連を有効化
                all_overlay_enable_on(app);
            end

            % チェックボックスをデフォルトでオフに
            app.CSNP_LargestquakesontopCheckBox.Value = 0;
            app.CSNP_LatestquakesontopCheckBox.Value = 0;

            app.CSNP_ListBox.ValueIndex = 1;

            % フォーカルメカニズム計算
            focal_mech_calc(app);
        end


        function recalc_button(app)
            % =====================================================================
            %                recalc_button(app)
            % =====================================================================
            % ボタンの値に応じて再計算
            % 'record_stamp'タグのついたスタンプの描画を削除
            record_stamp = findall(app.UIAxes, 'Tag', 'record_stamp');
            if ~isempty(record_stamp)
                delete(record_stamp);
            end
            % 凡例が続いていかないように削除
            sigmalegend = findall(app.UIAxes, 'Tag', 'sigmalegend');
            if ~isempty(sigmalegend)
                delete(sigmalegend);
            end

            selectedButton = app.FunctionButtonGroup.SelectedObject;
            % ボタンに応じてコールバックを設定
            if selectedButton == app.mapview2DButton % ---------------menu_grid_mapview_Callback---------------
                menu_grid_mapview_Callback(app);
                % タイトルを削除（Now　calculating...から変更）
                title(app.UIAxes, '');

            elseif selectedButton == app.view3DButton % ---------------menu_grid_3d_Callback---------------
                menu_grid_3d_Callback(app);
                % タイトルをNow　calculating...から変更
                if app.ASSIST_VARS.F3D_SLIP_TYPE == 1
                    title(app.UIAxes, 'Amount of net slip on each fault (m)', 'FontSize', 18);
                elseif app.ASSIST_VARS.F3D_SLIP_TYPE == 2
                    title(app.UIAxes, 'Amount of strike slip on each fault (m). Right lat. positive', 'FontSize', 18);
                else
                    title(app.UIAxes, 'Amount of dip slip on each fault patch (m). Reverse. positive', 'FontSize', 18);
                end

            elseif selectedButton == app.HDvectorsButton % ---------------menu_vectors_Callback---------------
                menu_vectors_Callback(app);
                % タイトルを削除（Now　calculating...から変更）
                title(app.UIAxes, '');

            elseif selectedButton == app.HDwireframeButton % ---------------menu_wireframe_Callback---------------
                menu_wireframe_Callback(app);
                % タイトルを削除（Now　calculating...から変更）
                title(app.UIAxes, '');

            elseif selectedButton == app.VDButton % ---------------menu_contours_Callback---------------
                menu_contours_Callback(app);
                % タイトルをNow　calculating...から変更
                title(app.UIAxes, 'Vertical displacement (m)', 'FontSize', 18);

            elseif selectedButton == app.image3DdrapeButton % ---------------menu_3d_Callback---------------
                menu_3d_Callback(app);
                title(app.UIAxes, 'Vertical displacement (exaggerated depth)', 'FontSize', 18);

            elseif selectedButton == app.image3DwireframedsurfaceButton % ---------------menu_3d_wire_Callback---------------
                menu_3d_wire_Callback(app);
                title(app.UIAxes, 'Vertical displacement (exaggerated depth)', 'FontSize', 18);
                
            elseif selectedButton == app.vectors3DButton % ---------------menu_3d_vectors_Callback---------------
                menu_3d_vectors_Callback(app);
                % タイトルをNow　calculating...から変更
                title(app.UIAxes, '3D displacement vectors', 'FontSize', 18);
                
            elseif selectedButton == app.exxstrainButton % ---------------radiobutton_sxx_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 1;
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.eyystrainButton % ---------------radiobutton_syy_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 2;
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.ezzstrainButton % ---------------radiobutton_szz_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 3;
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.exystrainButton % ---------------radiobutton_sxy_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 6;    % 計算の関係で6であることに注意
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.exzstrainButton % ---------------radiobutton_sxz_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 5;
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.eyzstrainButton % ---------------radiobutton_syz_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 4;     % 計算の関係で4であることに注意
                strain_view_button_Callback(app);
               
            elseif selectedButton == app.DilatationButton % ---------------radiobutton_dilatation_map_Callback---------------
                % strain menu 実行時の操作
                before_strain_cal_function(app);
                % クロスセクション関連を有効化
                app.CrossSectionButton.Enable = 'on';
                % 歪のタイプを設定し計算
                app.CALC_CONTROL.STRAIN_SWITCH = 7;
                strain_view_button_Callback(app);
                
            elseif selectedButton == app.ShearstresschangeButton % ---------------menu_shear_stress_change_Callback---------------
                uialert(app.main_menu_window, 'Please operate on the right panel.', 'Information', 'Icon', 'info');
                menu_shear_stress_change_Callback(app);
                
            elseif selectedButton == app.UnclampingstresschangeButton % ---------------menu_normal_stress_change_Callback---------------
                uialert(app.main_menu_window, 'Please operate on the right panel.', 'Information', 'Icon', 'info');
                menu_normal_stress_change_Callback(app);
                
            elseif selectedButton == app.CoulombstresschangeButton % ---------------menu_coulomb_stress_change_Callback---------------
                uialert(app.main_menu_window, 'Please operate on the right panel.', 'Information', 'Icon', 'info');
                menu_coulomb_stress_change_Callback(app);
                
            elseif selectedButton == app.CalcStFaultsButton % ---------------menu_stress_on_faults_Callback---------------
                uialert(app.main_menu_window, 'Please operate on the right panel.', 'Information', 'Icon', 'info');
                menu_stress_on_faults_Callback(app);

            elseif selectedButton == app.CalcStPointButton % ---------------menu_stress_on_a_fault_Callback---------------
                uialert(app.main_menu_window, 'Follow the right panel and select the calculation point.', 'Information', 'Icon', 'info');
                menu_stress_on_a_fault_Callback(app);
                % タイトルを削除（Now　calculating...から変更）
                title(app.UIAxes, '');
                
            elseif selectedButton == app.CalcStNodalplanesButton % ---------------menu_focal_mech_Callback---------------
                menu_focal_mech_Callback(app);
            end
        end


        % ==========================================================================================================================================
        %                window関連
        % ==========================================================================================================================================
        
        function about_box_window(app)
            % =====================================================================
            %                about_box_window(app)
            % =====================================================================
            % タイトル画面
            % イメージの設定
            str = 'Cover_figure_ver4.jpg';
            im = imread(str);  % 画像を読み込む
            cd ..
        
            % 画像のサイズを取得
            [imgHeight, imgWidth, ~] = size(im);
        
            % スクリーンのサイズを取得
            screenWidth = app.SCR_SIZE.SCRS(3);  % スクリーンの幅
            screenHeight = app.SCR_SIZE.SCRS(4);  % スクリーンの高さ
        
            % 最大ウィンドウサイズをスクリーンの50%に設定
            maxWindowWidth = screenWidth * 0.5;
            maxWindowHeight = screenHeight * 0.5;
        
            % 画像の縦横比を保持しながらサイズを調整
            aspectRatio = imgWidth / imgHeight;
            if imgWidth > maxWindowWidth || imgHeight > maxWindowHeight
                % 画像が大きすぎる場合、スクリーンサイズに合わせて縮小
                if (maxWindowWidth / aspectRatio) <= maxWindowHeight
                    wind_width = maxWindowWidth;
                    wind_height = maxWindowWidth / aspectRatio;
                else
                    wind_height = maxWindowHeight;
                    wind_width = maxWindowHeight * aspectRatio;
                end
            else
                % 画像がスクリーンに収まる場合は、元のサイズを使用
                wind_width = imgWidth;
                wind_height = imgHeight;
            end
        
            % ウィンドウの位置をスクリーンの右上に設定
            xpos = app.SCR_SIZE.SCRW_X;
            ypos = (app.SCR_SIZE.SCRS(4) - app.SCR_SIZE.SCRW_Y) - wind_height;
            aboutWindow = uifigure('Name', 'About Coulomb', 'Position', [xpos, ypos, wind_width, wind_height], 'WindowStyle', 'modal');
        
            % uiaxesを作成し、画像の表示領域をウィンドウのサイズに合わせて設定
            ax = uiaxes(aboutWindow, 'Position', [0, 0, wind_width, wind_height]);
            imshow(im, 'Parent', ax);
            axis(ax, 'image');  % アスペクト比を保持して画像を表示

            % ウィンドウの描画を強制的に完了させる
            drawnow;  % これでUIの描画を完了させる

            % 一定時間待機後にウィンドウを自動で閉じる
            pause(2); % 2秒間待機
            close(aboutWindow); % ウィンドウを閉じる
        end

        
        function [x, y] = selectDataPoints(~, ax)
            % =====================================================================
            %                selectDataPoints(~, ax)
            % =====================================================================
            % マウスクリックにより座標を取得する関数
            % [x, y] = selectDataPoints(app, app.UIAxes);
            % を用いて使用する。
            % Image Processing Toolboxが必要
            roi = drawpoint(ax);
            x = roi.Position(1);
            y = roi.Position(2);
            roi.Tag = 'selectPoint';
        end

        
        function coulomb_init(app)
            % =====================================================================
            %                coulomb_init(app)
            % =====================================================================
            % この関数は、Coulombプログラムが起動された際に、一部のappプロパティを初期化し、いくつかの変数に初期値を設定。
            % 特異点を検出するためのフラグ (IIRET > 0の場合、特異点がある)
            app.CALC_CONTROL.IIRET = 0;
            % 入力ファイルフォーマットを明示するためのフラグ
            app.CALC_CONTROL.IRAKE = 0;

            % 入力ファイルからのパラメータ初期化
            app.INPUT_VARS.HEAD = [];       % ヘッダー情報
            app.INPUT_VARS.NUM = [];        % 総断層数
            app.INPUT_VARS.POIS = [];       % ポアソン比
            app.INPUT_VARS.CALC_DEPTH = []; % 計算深度
            app.INPUT_VARS.YOUNG = [];      % ヤング率
            app.INPUT_VARS.FRIC = [];       % 摩擦係数
            app.INPUT_VARS.R_STRESS = [];   % 残留応力
            app.INPUT_VARS.ID = [];         % ID情報
            app.INPUT_VARS.KODE = [];       % コード情報
            app.INPUT_VARS.ELEMENT = [];    % 要素データ
            app.INPUT_VARS.IND_RAKE = [];   % 考慮されるすべりの方向
            app.INPUT_VARS.FCOMMENT = [];   % コメントを格納する構造体,  セル配列app.INPUT_VARS.NUM行1列
            app.INPUT_VARS.GRID = [];       % グリッド情報
            app.INPUT_VARS.SIZE = [];       % サイズ情報
            app.INPUT_VARS.SECTION = [];    % セクション情報
            app.OKADA_OUTPUT.S_ELEMENT = [];  % S要素データ
        
            % flag for horizontal displ calc (0: no fixed point, 1: a fixed)
            % 水平方向の変位計算のためのフラグ (0: 固定点なし, 1: 固定点あり)
            app.ASSIST_VARS.FIXFLAG = repmat(uint8(0), 1, 1);  % 初期化：固定点なし
            % default fixed point
            % デフォルトの固定点を設定
            app.ASSIST_VARS.FIXX = 0;
            app.ASSIST_VARS.FIXY = 0;
        
            % 応力タイプとシェーディングタイプの初期化
            app.CALC_CONTROL.STRESS_TYPE = repmat(uint8(5), 1, 1); % 応力タイプ
            app.CALC_CONTROL.SHADE_TYPE = repmat(uint8(1), 1, 1);  % シェーディングタイプ
            app.SECTION_VARS.SEC_FLAG = repmat(uint8(0), 1, 1);    % セクションフラグ
            app.COORD_VARS.LON_GRID = [];                          % 経度グリッド
            app.COORD_VARS.LAT_GRID = [];                          % 緯度グリッド
        
            % flag to speed up EC calc to skip the max. shear calc routine
            % 最大剪断応力の計算をスキップするためのフラグ
            app.SYSTEM_VARS.IMAXSHEAR = 2; % 1: 検索, 2: スキップ, 1: search, 2: skip
        
            % initialization for overlay functions
            % オーバーレイ機能の初期化
            app.OVERLAY_VARS.COAST_DATA  = [];  % 海岸線データ
            app.OVERLAY_VARS.AFAULT_DATA = [];  % 活断層データ
            app.OVERLAY_VARS.EQ_DATA     = [];  % 地震データ
            app.OVERLAY_VARS.PS_DATA     = [];  % GNSSデータ
            app.OVERLAY_VARS.BASEMAP     = zeros(2, 1);  % ベースマップデータ
            app.OVERLAY_VARS.GNSS_FLAG   = 'horizontal'; % GNSSフラグ（初期値: 水平）
            app.OVERLAY_VARS.GNSS_SEQN_FLAG = 'off';     % GNSSシーケンスフラグ（初期値: オフ）
        
            % 3D viewpoint specification (azimuth and vertical elevation, degree)
            % 3Dビューの指定 (方位角と垂直の角度、度単位)
            app.ASSIST_VARS.VIEW_AZ = 15;  % 方位角（初期値）
            app.ASSIST_VARS.VIEW_EL = 40;  % 垂直角度（初期値）
        
            % overlay function check item off
            % オーバーレイ機能のチェックアイテムをオフにする
            app.CoastlinesCheckBox.Value = 0;
            app.ActivefaultsCheckBox.Value = 0;
            app.VolcanoesCheckBox.Value = 0;
            app.EarthquakesCheckBox.Value = 0;
            app.GNSSstationsCheckBox.Value = 0;
            app.CitiesCheckBox.Value = 0;

            % クロスセクションが計算済み（１）かどうか（未計算：０）
            app.ASSIST_VARS.XSEC_FLAG = 0;
        
            % 深度範囲タイプと軸表示フラグの初期化
            app.CALC_CONTROL.DEPTH_RANGE_TYPE = repmat(uint8(0), 1, 1);
            app.ASSIST_VARS.FLAG_PR_AXES = repmat(uint8(0), 1, 1);
            app.OVERLAY_VARS.OVERLAY_MARGIN = 5;  % オーバーレイのマージン（初期値）
        end

        
        function all_propaties_reset(app)
            % =====================================================================
            %                all_propaties_reset(app)
            % =====================================================================
            % プロパティを起動時の状態に戻す
            % DefaultValues から各構造体を復元
            app.SCR_SIZE = app.DefaultValues.SCR_SIZE;
            app.INPUT_VARS = app.DefaultValues.INPUT_VARS;
            app.COORD_VARS = app.DefaultValues.COORD_VARS;
            app.CALC_CONTROL = app.DefaultValues.CALC_CONTROL;
            app.OKADA_OUTPUT = app.DefaultValues.OKADA_OUTPUT;
            app.SECTION_VARS = app.DefaultValues.SECTION_VARS;
            app.OVERLAY_VARS = app.DefaultValues.OVERLAY_VARS;
            app.GRAPHICS_VARS = app.DefaultValues.GRAPHICS_VARS;
            app.SYSTEM_VARS = app.DefaultValues.SYSTEM_VARS;
            app.ASSIST_VARS = app.DefaultValues.ASSIST_VARS;
            app.OKADA_CALC_VARS = app.DefaultValues.OKADA_CALC_VARS;
            app.HANDLE_VARS = app.DefaultValues.HANDLE_VARS;
            app.H_MAIN = app.DefaultValues.H_MAIN;
        end


        % ==========================================================================================================================================
        %                パブリック関数（アプリ外の関数でも使用可能）
        % ==========================================================================================================================================

        % =====================================================================
        %                メニューのオンオフ関連
        % =====================================================================
        function all_functions_enable_on(app)
            % 全ての機能をオンに
            app.FunctionButtonGroup.Enable = 'on';         % Faultの操作ボタンを有効化
            app.CalcDepthEditField.Enable = 'on';           % 計算震度ボックスを有効化
            app.FrictionEditField.Enable = 'on';           % 摩擦ボックスを有効化
            app.DisplexaggerationEditField.Enable = 'on';  % 誇張ボックスを有効化
            app.CoordinatesSwitch.Enable = 'on';           % 座標切り替えボタンを有効化
            app.Rescale_output_Slider.Enable = 'on';       % Rescale outputスライダーを有効化
            app.Rescale_output_x_EditField.Enable = 'on';  % Rescale output xEditボックスを有効化
            app.FixpointPanel.Enable = 'on';               % 固定点関連を有効化
            app.MosaicSwitch.Enable = 'on';                % 画像の平滑化スイッチを有効化
            app.ContourlineCheckBox.Enable = 'on';         % 等高線チェックボックスを有効化
            app.IntervalEditField.Enable = 'on';           % 等高線間隔編集フィールドを有効化
            app.CrossSectionButton.Enable = 'on';          % クロスセクションを有効化
        end

        % =====================================================================
        function all_functions_enable_off(app)
            % 全ての機能をオフに
            app.FunctionButtonGroup.Enable = 'off';        % Faultの操作ボタンを無効化
            app.CalcDepthEditField.Enable = 'off';          % 計算震度ボックスを無効化
            app.FrictionEditField.Enable = 'off';          % 摩擦ボックスを無効化
            app.DisplexaggerationEditField.Enable = 'off'; % 誇張ボックスを無効化
            app.CoordinatesSwitch.Enable = 'off';          % 座標切り替えボタンを無効化
            app.Rescale_output_Slider.Enable = 'off';      % Rescale outputスライダーを無効化
            app.Rescale_output_x_EditField.Enable = 'off'; % Rescale output xEditボックスを無効化
            app.FixpointPanel.Enable = 'off';              % 固定点関連を無効化
            app.MosaicSwitch.Enable = 'off';               % 画像の平滑化スイッチを無効化
            app.ContourlineCheckBox.Enable = 'off';        % 等高線チェックボックスを無効化
            app.IntervalEditField.Enable = 'off';          % 等高線間隔編集フィールドを無効化
            app.CrossSectionButton.Enable = 'off';         % クロスセクションを無効化
        end

        % =====================================================================
        function after_button_change_function_off(app)
            % ボタンを切り替えした際、一度CalcTab上の全てのボタンをオフに。
            % その後、それぞれで使えるもののみをオンにする
            % Rescale outputを使用不可に
            app.Rescale_output_Slider.Enable = 'off';
            app.Rescale_output_x_EditField.Enable = 'off';
            
            % 座標の切り替えスイッチを使用不可に
            app.CoordinatesSwitch.Enable = 'off';

            % カラーをなめらかに切り替えるスイッチを使用不可に
            app.MosaicSwitch.Enable = 'off';

            % 等高線を使用不可に
            app.ContourlineCheckBox.Enable = 'off';
            app.IntervalEditField.Enable = 'off';
            app.IntervalUnitLabel.Visible = 'off';
            % 単位のテキストをデフォルトではmに変更
            app.IntervalUnitLabel.Text = "m";

            % 固定点関連を使用不可に
            app.FixpointPanel.Enable = 'off';

            % クロスセクション関連を使用不可に
            app.CrossSectionButton.Enable = 'off';

            % オーバーレイ関連を使用不可に
            app.OverlaysPanel.Enable = 'off';

            % Element Input Panelを使用不可に
            app.EIP_ElementInputPanel.Enable = 'off';
            app.EIP_ElementInputPanel.Visible = 'off';

            % PreferencePanelを使用不可に
            app.PreferencespanelPanel.Enable = 'off';
            app.PreferencespanelPanel.Visible = 'off';
        end


        % =====================================================================
        function before_menu_changed_panel_close(app)
            app.F3DwiredPanel.Enable = 'off';
            app.F3DwiredPanel.Visible = 'off';
            % 
            app.SW_StresswindowPanel.Enable = 'off';
            app.SW_StresswindowPanel.Visible = 'off';
            app.SW_DepthRangePanel.Enable = 'off';
            app.SW_DepthRangePanel.Visible = 'off';
            app.SW_SliceDepthPanel.Enable = 'off';
            app.SW_SliceDepthPanel.Visible = 'off';
            % 
            app.SW_Receiver_ButtonGroup.Enable = 'off';
            % 
            app.CSAAP_CalcstressatapointPanel.Enable = 'off';
            app.CSAAP_CalcstressatapointPanel.Visible = 'off';
            % 
            app.CSOF_Calc_stress_on_faults_Panel.Enable = 'off';
            app.CSOF_Calc_stress_on_faults_Panel.Visible = 'off';

            % テーパースプリットで用いるパネルをオフに
            app.EIP_ElementInputPanel.Enable = 'off';
            app.EIP_ElementInputPanel.Visible = 'off';

            % 断層新設時に用いるパネルをオフに
            app.NewFaultCreationPanel.Enable = 'off';
            app.NewFaultCreationPanel.Visible = 'off';

            % 断層削除時に用いるパネルをオフに
            app.DeleteFaultPanel.Enable = 'off';
            app.DeleteFaultPanel.Visible = 'off';

            % クロスセクションコントロールパネルを無効化
            app.CrossSectionControlPanel.Enable = 'off';

            % Earthquake Screening Panelをオフに
            app.EarthquakeDataScreeningPanel.Visible = 'off';
            app.EarthquakeDataScreeningPanel.Enable = 'off';

            % ISC Panelをオフに
            app.SearchtheISCBulletinPanel.Visible = 'off';
            app.SearchtheISCBulletinPanel.Enable = 'off';
 
            % ChangestudyareaPanelをオフに
            app.ChangestudyareaPanel.Visible = 'off';
            app.ChangestudyareaPanel.Enable = 'off';

            % PreferencePanelをオフに
            app.PreferencespanelPanel.Enable = 'off';
            app.PreferencespanelPanel.Visible = 'off';

            % BCF panelをオフに
            app.BCF_BuildCMTorfocalmeshdataPanel.Enable = 'off';
            app.BCF_BuildCMTorfocalmeshdataPanel.Visible = 'off';

            % BXY panelをオフに
            app.BXY_BuildiXYmapPanel.Enable = 'off';
            app.BXY_BuildiXYmapPanel.Visible = 'off';

            % Select Nodal plane type Panelをオフに
            app.CSNP_SelectNodalplanetypePanel.Enable = 'off';
            app.CSNP_SelectNodalplanetypePanel.Visible = 'off';

            % plugin panelをオフに
            app.PluginPanel.Enable = 'off';
            app.PluginPanel.Visible = 'off';

            % View panelをオフに
            app.ViewPanel.Enable = 'off';
            app.ViewPanel.Visible = 'off';
        end

        % =====================================================================
        function before_strain_cal_function(app)
            % =====================================================================
            %                before_strain_cal_function(app)
            % =====================================================================
            % ボタンを変えた際にstrainの計算は共通のため、共通で機能を解放する
            % app.CALC_CONTROL.IACT = 0;
            app.CALC_CONTROL.FUNC_SWITCH = 6;
            app.CALC_CONTROL.SHADE_TYPE = 1;    % default
            app.CALC_CONTROL.STRAIN_SWITCH = 1; % default sig XX

            % 一時的にすべて使用不可にして、使用可能なものをオンに
            after_button_change_function_off(app);

            % Exaggerationを使用不可に
            app.DisplexaggerationEditField.Enable = 'off';

            % Rescale outputを有効に
            app.Rescale_output_Slider.Enable = 'on';
            app.Rescale_output_x_EditField.Enable = 'on';
            
            % 座標の切り替えスイッチを有効に
            app.CoordinatesSwitch.Enable = 'on';

            % カラーをなめらかに切り替えるスイッチを有効に
            app.MosaicSwitch.Enable = 'on';

            % 等高線を有効に
            % 等高線の単位は無次元なので見えないままに
            app.ContourlineCheckBox.Enable = 'on';
            app.IntervalEditField.Enable = 'on';
            
            % クロスセクションはDilitationのみ

            flag = check_lonlat_info(app);      % 経度と緯度の情報をチェック
            if flag == 1
                all_overlay_enable_on(app);      % オーバーレイ関連を有効にする
            end
            
            % ----- overlay drawing --------------------------------
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end
        end

        % =====================================================================
        function all_overlay_enable_on(app)
            app.OverlaysPanel.Enable = 'on'; % オーバーレイボックスを有効化
        end

        % =====================================================================
        function all_overlay_enable_off(app)
            app.OverlaysPanel.Enable = 'off'; % オーバーレイボックスを無効化
            % チェックボックスをオフに
            app.CoastlinesCheckBox.Value = 0;
            app.ActivefaultsCheckBox.Value = 0;
            app.VolcanoesCheckBox.Value = 0;
            app.EarthquakesCheckBox.Value = 0;
            app.GNSSstationsCheckBox.Value = 0;
            app.CitiesCheckBox.Value = 0;
        end

        % =====================================================================
        function subfig_clear(app)
            % 計算前に、計算ボタン専用のパネル類を全て無効化し、見えなくする関数
            app.SW_StresswindowPanel.Enable = 'off';
            app.SW_StresswindowPanel.Visible = 'off';
            app.SW_DepthRangePanel.Enable = 'off';
            app.SW_DepthRangePanel.Visible = 'off';
            app.SW_SliceDepthPanel.Enable = 'off';
            app.SW_SliceDepthPanel.Visible = 'off';
            app.CSOF_Calc_stress_on_faults_Panel.Enable = 'off';
            app.CSOF_Calc_stress_on_faults_Panel.Visible = 'off';
            app.CSAAP_CalcstressatapointPanel.Enable = 'off';
            app.CSAAP_CalcstressatapointPanel.Visible = 'off';
            app.F3DwiredPanel.Enable = 'off';
            app.F3DwiredPanel.Visible = 'off';
            app.PreferencespanelPanel.Enable = 'off';
            app.PreferencespanelPanel.Visible = 'off';
            % Select Nodal plane type Panelをオフに
            app.CSNP_SelectNodalplanetypePanel.Enable = 'off';
            app.CSNP_SelectNodalplanetypePanel.Visible = 'off';
        end

        % =====================================================================
        function showMessage(app, msg, overwrite)
            % showMessage - TextAreaにメッセージを表示する
            %   msg: string または cell array of strings
            %   overwrite: trueならリセットして表示、falseなら追記
            %   明示しない場合はtrue扱いとなる
            
            if nargin < 3
                overwrite = true;
            end
    
            % msg → string 配列に変換
            if ischar(msg)
                msg = string(msg);
            elseif iscell(msg)
                msg = string(msg);
            elseif ~isstring(msg)
                error('msg must be char, string, or cell array');
            end
    
            % 追加のメッセージを縦ベクトルに変換し、配列の連結時のエラーを防ぐ。
            % 列ベクトル化（N行1列）
            msg = msg(:);
            if overwrite
                app.MessageTextArea.Value = msg;
            else
                % 現在のTextArea値を取得
                currentValue = app.MessageTextArea.Value;
    
                % char/cell/string 全対応して string列ベクトルに変換
                if ischar(currentValue)
                    currentValue = string(currentValue);
                elseif iscell(currentValue)
                    currentValue = string(currentValue);
                elseif ~isstring(currentValue)
                    currentValue = string(currentValue);
                end
    
                % 現在のメッセージを縦ベクトルに変換し、配列の連結時のエラーを防ぐ。
                currentValue = currentValue(:); % ← これが重要
                % 空白行を追加し、その後に新しいメッセージを結合
                spacer = "";  % 空行
                % 縦ベクトルで連結
                app.MessageTextArea.Value = [currentValue; spacer; msg];
            end
        end

        % =====================================================================
        function input_save_ascii(app)
            % ASCIIで保存する際に用いる関数
            % 保存先ディレクトリの指定
            targetDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_files');
            
            % IRAKEの値に基づいて拡張子を決定
            if isfield(app.CALC_CONTROL, 'IRAKE') && isequal(app.CALC_CONTROL.IRAKE, 1)
                % app.CALC_CONTROL.IRAKE が1である場合、inrファイルに。
                % rake & net-slip format
                defaultExt = '*.inr';
                defaultName = ['new_file' defaultExt(2:end)];
                filterSpec = {'*.inr', 'Right-lat / Reverse Input (*.inr)'};
            else
                % それ以外の場合
                % right-lat & reverse slip format
                defaultExt = '*.inp';
                defaultName = ['new_file' defaultExt(2:end)];
                filterSpec = {'*.inp', 'ASCII Input File (*.inp)'};
            end
            
            % ファイル保存ダイアログを表示（適切な拡張子で）
            [fileName, filePath] = uiputfile(filterSpec, 'Create New Input File', fullfile(targetDir, defaultName));
            
            % キャンセル処理
            if isequal(fileName, 0) || isequal(filePath, 0)
                uialert(app.main_menu_window, 'File creation canceled.', 'Information', 'Icon', 'info');
                return;
            end
            
            % 拡張子が付いていない場合は自動で付ける
            [~, ~, ext] = fileparts(fileName);
            if isempty(ext)
                if isequal(app.CALC_CONTROL.IRAKE, 1)
                    fileName = [fileName '.inr'];
                else
                    fileName = [fileName '.inp'];
                end
            end
            
            % フルパスでファイル作成
            fullFilePath = fullfile(filePath, fileName);
            % 書き込み用に開く
            fid = fopen(fullFilePath, 'wt');
            % エラーチェック
            if fid == -1
                error('Could not create file: %s', fullFilePath);
            end

            % ヘッダーはセルから取り出し書き込み
            % fprintf(fid, '%s\n', app.INPUT_VARS.HEAD(1));
            % fprintf(fid,' \n');
            % fprintf(fid, '%s\n', app.INPUT_VARS.HEAD(2));
            % fprintf(fid,' \n');
            fprintf(fid,[app.INPUT_VARS.HEAD{1}]);
            fprintf(fid,' \n');
            fprintf(fid,[app.INPUT_VARS.HEAD{2}]);
            fprintf(fid,' \n');
            fprintf(fid, '#reg1=  0  #reg2=  0  #fixed= %3i  sym=  1\n', app.INPUT_VARS.NUM);
            fprintf(fid, ' PR1=%12.3f     PR2=%12.3f   DEPTH=%12.3f\n', app.INPUT_VARS.POIS, app.INPUT_VARS.POIS, app.INPUT_VARS.CALC_DEPTH);
            fprintf(fid, '  E1=%15.3e   E2=%15.3e\n', app.INPUT_VARS.YOUNG, app.INPUT_VARS.YOUNG);
            fprintf(fid, 'XSYM=       .000     YSYM=       .000\n');
            fprintf(fid, 'FRIC=%15.3f\n', app.INPUT_VARS.FRIC);
            fprintf(fid, 'S1DR=%15.3f', app.INPUT_VARS.R_STRESS(1, 1));
            fprintf(fid, ' S1DP=%15.3f', app.INPUT_VARS.R_STRESS(1, 2));
            fprintf(fid, ' S1IN=%15.3f', app.INPUT_VARS.R_STRESS(1, 3));
            fprintf(fid, ' S1GD=%15.3f\n', app.INPUT_VARS.R_STRESS(1, 4));
            fprintf(fid, 'S2DR=%15.3f', app.INPUT_VARS.R_STRESS(2, 1));
            fprintf(fid, ' S2DP=%15.3f', app.INPUT_VARS.R_STRESS(2, 2));
            fprintf(fid, ' S2IN=%15.3f', app.INPUT_VARS.R_STRESS(2, 3));
            fprintf(fid, ' S2GD=%15.3f\n', app.INPUT_VARS.R_STRESS(2, 4));
            fprintf(fid, 'S3DR=%15.3f', app.INPUT_VARS.R_STRESS(3, 1));
            fprintf(fid, ' S3DP=%15.3f', app.INPUT_VARS.R_STRESS(3, 2));
            fprintf(fid, ' S3IN=%15.3f', app.INPUT_VARS.R_STRESS(3, 3));
            fprintf(fid, ' S3GD=%15.3f\n\n', app.INPUT_VARS.R_STRESS(3, 4));

            temp_element = app.INPUT_VARS.ELEMENT;
            % to avoid accidental overwriting
            if app.CALC_CONTROL.IRAKE == 0 || isempty(app.CALC_CONTROL.IRAKE) == 1
                % right-lat & reverse slip format
                fprintf(fid, '  #   X-start    Y-start     X-fin      Y-fin   Kode  rt.lat    ');
                fprintf(fid, 'reverse   dip angle     top      bot\n');
                fprintf(fid, 'xxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxx xxxxxxxxxx ');
                fprintf(fid, 'xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx\n');
            elseif app.CALC_CONTROL.IRAKE == 1
                % rake & net-slip format
                fprintf(fid, '  #   X-start    Y-start     X-fin      Y-fin   Kode   rake     ');
                fprintf(fid, 'netslip   dip angle     top        bot\n');
                fprintf(fid, 'xxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxx xxxxxxxxxx ');
                fprintf(fid, 'xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx xxxxxxxxxx\n');
                imessage = 0;
                if isempty(app.CALC_CONTROL.IND_RAKE)
                    for k = 1:app.INPUT_VARS.NUM
                        if app.INPUT_VARS.ELEMENT(k, 5)==0.0 && app.INPUT_VARS.ELEMENT(k, 6)==0.0
                            temp_element(k, 5) = 0.0;
                            temp_element(k, 5) = 0.0;
                            imessage = 1;
                        else
                            [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6));
                        end
                    end
                    if imessage == 1
                        warndlg({'No slip element exists.'; 'See command window for details.'});
                        disp('Since slip is zero, rake and net slip have been set to 0.0; you must assign a rake to this patch if you want to use it as receiver.');
                        showMessage(app, {'Since slip is zero, rake and net slip have been set to 0.0.', ...
                            'You must assign a rake to this patch if you want to use it as receiver.'}, false);
                    end
                else
                    for k = 1:app.INPUT_VARS.NUM
                        if app.INPUT_VARS.ELEMENT(k, 5) == 0.0 && app.INPUT_VARS.ELEMENT(k, 6) == 0.0
                            temp_element(k, 5) = app.CALC_CONTROL.IND_RAKE(k, 1);
                            temp_element(k, 5) = 0.0;
                        else
                            [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6));
                        end
                    end
                end
            end
            sp = '   ';
            for k=1:app.INPUT_VARS.NUM
                fprintf(fid, '%3i %10.4f %10.4f %10.4f %10.4f ', app.INPUT_VARS.ID(k), app.INPUT_VARS.ELEMENT(k, 1), app.INPUT_VARS.ELEMENT(k, 2), app.INPUT_VARS.ELEMENT(k, 3), app.INPUT_VARS.ELEMENT(k, 4));
                if app.INPUT_VARS.KODE(k) == 100
                    fprintf(fid, '%3i %10.4f %10.4f %10.4f %10.4f %10.4f %s', app.INPUT_VARS.KODE(k),...
                        temp_element(k, 5), temp_element(k, 6), app.INPUT_VARS.ELEMENT(k, 7), app.INPUT_VARS.ELEMENT(k, 8), app.INPUT_VARS.ELEMENT(k, 9), sp);
                else
                    fprintf(fid, '%3i %10i %10i %10.4f %10.4f %10.4f %s', app.INPUT_VARS.KODE(k),...
                        int64(temp_element(k, 5)), int64(temp_element(k, 6)), app.INPUT_VARS.ELEMENT(k, 7), app.INPUT_VARS.ELEMENT(k, 8), app.INPUT_VARS.ELEMENT(k, 9), sp);
                end
                try
                    if iscell(app.INPUT_VARS.FCOMMENT(k)) == 1
                        fprintf(fid, cell2mat(app.INPUT_VARS.FCOMMENT(k)));
                        fprintf(fid, ' \n');
                    elseif iscell(app.INPUT_VARS.FCOMMENT(k)) == 0
                        fprintf(fid, app.INPUT_VARS.FCOMMENT(k));
                        fprintf(fid, ' \n');
                    end
                catch
                    fprintf(fid,' \n');
                end
            end
            fprintf(fid, '  \n');
            fprintf(fid, '    Grid Parameters\n');
            fprintf(fid, '  1  ----------------------------  Start-x = %16.7f\n', app.INPUT_VARS.GRID(1, 1));
            fprintf(fid, '  2  ----------------------------  Start-y = %16.7f\n', app.INPUT_VARS.GRID(2, 1));
            fprintf(fid, '  3  --------------------------   Finish-x = %16.7f\n', app.INPUT_VARS.GRID(3, 1));
            fprintf(fid, '  4  --------------------------   Finish-y = %16.7f\n', app.INPUT_VARS.GRID(4, 1));
            fprintf(fid, '  5  ------------------------  x-increment = %16.7f\n', app.INPUT_VARS.GRID(5, 1));
            fprintf(fid, '  6  ------------------------  y-increment = %16.7f\n', app.INPUT_VARS.GRID(6, 1));
            fprintf(fid, '     Size Parameters\n');
            fprintf(fid, '  1  --------------------------  Plot size = %16.7f\n', app.INPUT_VARS.SIZE(1, 1));
            fprintf(fid, '  2  --------------  Shade/Color increment = %16.7f\n', app.INPUT_VARS.SIZE(2, 1));
            fprintf(fid, '  3  ------  Exaggeration for disp.& dist. = %16.7f\n', app.INPUT_VARS.SIZE(3, 1));
            fprintf(fid, '  \n');
            
            if ~isempty(app.SECTION_VARS.SEC_XS) && ~isempty(app.SECTION_VARS.SEC_YS) && ~isempty(app.SECTION_VARS.SEC_XF) && ~isempty(app.SECTION_VARS.SEC_YF)...
                    && ~isempty(app.SECTION_VARS.SEC_INCRE) && ~isempty(app.SECTION_VARS.SEC_DEPTH) && ~isempty(app.SECTION_VARS.SEC_DEPTHINC)
                % クロスセクション関連
                fprintf(fid, '     Cross section default\n');
                fprintf(fid, '  1  ----------------------------  Start-x = %16.7f\n', app.SECTION_VARS.SEC_XS);
                fprintf(fid, '  2  ----------------------------  Start-y = %16.7f\n', app.SECTION_VARS.SEC_YS);
                fprintf(fid, '  3  --------------------------   Finish-x = %16.7f\n', app.SECTION_VARS.SEC_XF);
                fprintf(fid, '  4  --------------------------   Finish-y = %16.7f\n', app.SECTION_VARS.SEC_YF);
                fprintf(fid, '  5  ------------------  Distant-increment = %16.7f\n', app.SECTION_VARS.SEC_INCRE);
                % 値がマイナスになったりプラスになったりするのでとりあえず正の値に調整しておく。
                % 修正必要？→たぶん大丈夫
                if app.SECTION_VARS.SEC_DEPTH < 0
                    fprintf(fid, '  6  ----------------------------  Z-depth = %16.7f\n', (-1.0) * app.SECTION_VARS.SEC_DEPTH);
                else
                    fprintf(fid, '  6  ----------------------------  Z-depth = %16.7f\n', app.SECTION_VARS.SEC_DEPTH);
                end
                fprintf(fid, '  7  ------------------------  Z-increment = %16.7f\n', app.SECTION_VARS.SEC_DEPTHINC);
            elseif isempty(app.INPUT_VARS.SECTION) ~= 1
                % セクション関連
                fprintf(fid, '     Cross section default\n');
                fprintf(fid, '  1  ----------------------------  Start-x = %16.7f\n', app.INPUT_VARS.SECTION(1, 1));
                fprintf(fid, '  2  ----------------------------  Start-y = %16.7f\n', app.INPUT_VARS.SECTION(2, 1));
                fprintf(fid, '  3  --------------------------   Finish-x = %16.7f\n', app.INPUT_VARS.SECTION(3, 1));
                fprintf(fid, '  4  --------------------------   Finish-y = %16.7f\n', app.INPUT_VARS.SECTION(4, 1));
                fprintf(fid, '  5  ------------------  Distant-increment = %16.7f\n', app.INPUT_VARS.SECTION(5, 1));
                fprintf(fid, '  6  ----------------------------  Z-depth = %16.7f\n', app.INPUT_VARS.SECTION(6, 1));
                fprintf(fid, '  7  ------------------------  Z-increment = %16.7f\n', app.INPUT_VARS.SECTION(7, 1));
            end
            if isempty(app.COORD_VARS.MIN_LON) ~= 1 && isempty(app.COORD_VARS.MAX_LON) ~= 1
                if isempty(app.COORD_VARS.MIN_LAT) ~= 1 && isempty(app.COORD_VARS.MAX_LAT) ~= 1
                    if isempty(app.COORD_VARS.ZERO_LON) ~= 1 && isempty(app.COORD_VARS.ZERO_LAT) ~= 1
                        % 座標関連の指定がある場合
                        fprintf(fid, '     Map info\n');
                        fprintf(fid, '  1  ---------------------------- min. lon = %16.7f\n', app.COORD_VARS.MIN_LON);
                        fprintf(fid, '  2  ---------------------------- max. lon = %16.7f\n', app.COORD_VARS.MAX_LON);
                        fprintf(fid, '  3  ---------------------------- zero lon = %16.7f\n', app.COORD_VARS.ZERO_LON);
                        fprintf(fid, '  4  ---------------------------- min. lat = %16.7f\n', app.COORD_VARS.MIN_LAT);
                        fprintf(fid, '  5  ---------------------------- max. lat = %16.7f\n', app.COORD_VARS.MAX_LAT);
                        fprintf(fid, '  6  ---------------------------- zero lat = %16.7f\n', app.COORD_VARS.ZERO_LAT);
                    end
                end
            end
            fclose(fid);
        
            % 完了メッセージ
            uialert(app.main_menu_window, ...
                    sprintf('ASCII file "%s" has been created successfully!', fullFilePath), ...
                    'File Saved', ...
                    'Icon', 'info');

            cd(app.SYSTEM_VARS.HOME_DIR);
        end

        % =====================================================================
        function input_save_csv(app)
            % ディレクトリを移動
            cd(app.SYSTEM_VARS.HOME_DIR);

            % 'input_files' のディレクトリを設定
            defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_files');
            
            % 'input_files' フォルダがなければ作成
            if ~exist(defaultDir, 'dir')
                mkdir(defaultDir);
            end
        
            % ユーザーに新規作成 or 既存ファイルの使用を選択させる
            choice = questdlg('Do you want to create a new CSV file or use an existing one?', ...
                              'CSV File Selection', 'Create New', 'Use Existing', 'Cancel', 'Cancel');
        
            % キャンセルの場合、ディレクトリを戻し終了
            if strcmp(choice, 'Cancel') || isempty(choice)
                cd(app.SYSTEM_VARS.HOME_DIR); % 元のディレクトリに戻す
                uialert(app.main_menu_window, 'File creation canceled.', 'Information', 'Icon', 'info');
                return;
            end
        
            % === ① 新規作成の処理 ===================
            if strcmp(choice, 'Create New')
                [fileName, filePath] = uiputfile(fullfile(defaultDir, '*.csv'), 'Save New CSV File As');
                
                % キャンセルの場合、ディレクトリを戻し終了
                if isequal(fileName, 0) || isequal(filePath, 0)
                    cd(app.SYSTEM_VARS.HOME_DIR); % 元のディレクトリに戻す
                    uialert(app.main_menu_window, 'File selection canceled.', 'Information', 'Icon', 'info');
                    return;
                end
        
                % フルパスの作成
                fullFilePath = fullfile(filePath, fileName);
        
                % A列に書き込むデータリスト（セル配列）
                % right-lat & reverse slip format
                % right-latとreverse slipで計算を行う場合
                valNames = {
                    'ValName'; 'Header1'; 'Header2'; 'fixed'; 'Poisson`s ratio'; 'Depth [km]'; 'Young`s modules [bars]'; 'Friction coefficient'; 
                    'S1DR'; 'S1DP'; 'S1IN'; 'S1GD'; 'S2DR'; 'S2DP'; 'S2IN'; 'S2GD';
                    'S3DR'; 'S3DP'; 'S3IN'; 'S3GD'; 'ID'; 'Kode'; 'X-start'; 'Y-start'; 
                    'X-fin'; 'Y-fin'; 'IRAKE'; 'Right-lateral slip [m]/ rake [°]'; 'reverse slip [m]/ net-slip [m]'; 'Dip-angle [°]'; 'Top [km]'; 'Bottom [km]'; 
                    'Comment'; 'Grid-Parameters'; 'Start-x [km]'; 'Start-y [km]'; 'Finish-x [km]'; 'Finish-y [km]'; 
                    'x-increment'; 'y-increment'; 'Size-Parameters'; 'Plot size'; 
                    'Shade/Color increment'; 'Exaggeration for disp.& dist.'; 'Cross-section-default'; 
                    'Start-x'; 'Start-y'; 'Finish-x'; 'Finish-y'; 'Distant-increment'; 
                    'Z-depth'; 'Z-increment'; 'Map-infomation'; 'min. lon'; 'max. lon'; 
                    'zero lon'; 'min. lat'; 'max. lat'; 'zero lat'; 'Saved Date'
                };
        
                % 新規作成時の列番号
                numColumns = 1 + app.INPUT_VARS.NUM;
        
                % 書き込むデータ
                % 書き込み用のセルを用意
                rowData = cell(60,1);
                % 4 ~ 20行目の変数
                rowData(4:20) = {
                    app.INPUT_VARS.NUM; app.INPUT_VARS.POIS; app.INPUT_VARS.CALC_DEPTH; app.INPUT_VARS.YOUNG; app.INPUT_VARS.FRIC; 
                    app.INPUT_VARS.R_STRESS(1, 1); app.INPUT_VARS.R_STRESS(1, 2); app.INPUT_VARS.R_STRESS(1, 3); app.INPUT_VARS.R_STRESS(1, 4); ...
                    app.INPUT_VARS.R_STRESS(2, 1); app.INPUT_VARS.R_STRESS(2, 2); app.INPUT_VARS.R_STRESS(2, 3); app.INPUT_VARS.R_STRESS(2, 4); ...
                    app.INPUT_VARS.R_STRESS(3, 1); app.INPUT_VARS.R_STRESS(3, 2); app.INPUT_VARS.R_STRESS(3, 3); app.INPUT_VARS.R_STRESS(3, 4) ...
                };
                % 27 行目のIRAKE
                if isempty(app.CALC_CONTROL.IRAKE) == 1
                    % 空の場合、right-lat & reverse slip formatとして扱い0を代入
                    rowData(27) = {0};
                else
                    % 値がある場合、0:right-lat & reverse slip format、1:rake & net-slip format
                    rowData(27) = {app.CALC_CONTROL.IRAKE};
                end
        
                % 28 ~ 29 行目、`rt.lat/rake` と `reverse/net-slip` を計算
                temp_element = app.INPUT_VARS.ELEMENT;
                if app.CALC_CONTROL.IRAKE == 1
                    imessage = 0;
                    if isempty(app.CALC_CONTROL.IND_RAKE)
                        for k = 1:app.INPUT_VARS.NUM
                            if app.INPUT_VARS.ELEMENT(k, 5) == 0.0 && app.INPUT_VARS.ELEMENT(k, 6) == 0.0
                                temp_element(k, 5) = 0.0;
                                temp_element(k, 6) = 0.0;
                                imessage = 1;
                            else
                                [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6));
                            end
                        end
                        if imessage == 1
                            warndlg({'No slip element exists.', 'See command window for details.'});
                            disp('Since slip is zero, rake and net slip have been set to 0.0; you must assign a rake to this patch if you want to use it as receiver.');
                            showMessage(app, {'Since slip is zero, rake and net slip have been set to 0.0.', ...
                                'You must assign a rake to this patch if you want to use it as receiver.'}, false);
                        end
                    else
                        for k = 1:app.INPUT_VARS.NUM
                            if app.INPUT_VARS.ELEMENT(k, 5) == 0.0 && app.INPUT_VARS.ELEMENT(k, 6) == 0.0
                                temp_element(k, 5) = app.CALC_CONTROL.IND_RAKE(k, 1);
                                temp_element(k, 6) = 0.0;
                            else
                                [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6)); 
                            end
                        end
                    end
                end

                % 35 ~ 40行目の変数
                rowData(35:40) = {
                    app.INPUT_VARS.GRID(1, 1); app.INPUT_VARS.GRID(2, 1); app.INPUT_VARS.GRID(3, 1); 
                    app.INPUT_VARS.GRID(4, 1); app.INPUT_VARS.GRID(5, 1); app.INPUT_VARS.GRID(6, 1)
                };
                % 42 ~ 44行目の変数
                rowData(42:44) = {
                    app.INPUT_VARS.SIZE(1, 1); app.INPUT_VARS.SIZE(2, 1); app.INPUT_VARS.SIZE(3, 1)
                };
                % 46 ~ 52行目の変数
                % クロスセクション関連の条件分岐
                if ~isempty(app.SECTION_VARS.SEC_XS) && ~isempty(app.SECTION_VARS.SEC_YS) && ~isempty(app.SECTION_VARS.SEC_XF) && ~isempty(app.SECTION_VARS.SEC_YF)...
                    && ~isempty(app.SECTION_VARS.SEC_INCRE) && ~isempty(app.SECTION_VARS.SEC_DEPTH) && ~isempty(app.SECTION_VARS.SEC_DEPTHINC)
                    % クロスセクション関連
                    % z-depth を―1倍する必要があるか不明
                    % 値がマイナスになったりプラスになったりするのでとりあえず正の値に調整しておく。修正必要？→たぶん大丈夫
                    if app.SECTION_VARS.SEC_DEPTH < 0
                        rowData(46:52) = {
                            app.SECTION_VARS.SEC_XS; app.SECTION_VARS.SEC_YS; app.SECTION_VARS.SEC_XF; app.SECTION_VARS.SEC_YF;
                            app.SECTION_VARS.SEC_INCRE; (-1.0) * app.SECTION_VARS.SEC_DEPTH; app.SECTION_VARS.SEC_DEPTHINC
                        };
                    else
                        rowData(46:52) = {
                            app.SECTION_VARS.SEC_XS; app.SECTION_VARS.SEC_YS; app.SECTION_VARS.SEC_XF; app.SECTION_VARS.SEC_YF; ...
                            app.SECTION_VARS.SEC_INCRE; app.SECTION_VARS.SEC_DEPTH; app.SECTION_VARS.SEC_DEPTHINC ...
                        };
                    end
                elseif isempty(app.INPUT_VARS.SECTION) ~= 1
                    % セクション関連
                    rowData(46:52) = {
                        app.INPUT_VARS.SECTION(1, 1); app.INPUT_VARS.SECTION(2, 1); app.INPUT_VARS.SECTION(3, 1); app.INPUT_VARS.SECTION(4, 1); ...
                        app.INPUT_VARS.SECTION(5, 1); app.INPUT_VARS.SECTION(6, 1); app.INPUT_VARS.SECTION(7, 1) ...
                    };
                end
                % 54 ~ 59行目の変数
                if isempty(app.COORD_VARS.MIN_LON) ~= 1 && isempty(app.COORD_VARS.MAX_LON) ~= 1
                    if isempty(app.COORD_VARS.MIN_LAT) ~= 1 && isempty(app.COORD_VARS.MAX_LAT) ~= 1
                        if isempty(app.COORD_VARS.ZERO_LON) ~= 1 && isempty(app.COORD_VARS.ZERO_LAT) ~= 1
                            % 座標関連の指定がある場合
                            rowData(54:59) = {
                                app.COORD_VARS.MIN_LON; app.COORD_VARS.MAX_LON; app.COORD_VARS.ZERO_LON;
                                app.COORD_VARS.MIN_LAT; app.COORD_VARS.MAX_LAT; app.COORD_VARS.ZERO_LAT
                            };
                        end
                    end
                end
                % 60行目の変数
                % 現在のタイムスタンプを取得
                rowData(60) = {
                    datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss')
                };
        
                % 空のセル配列（表全体）を作成
                tableData = cell(60, numColumns);
                % A列に見出しをセット
                tableData(:, 1) = valNames;
        
                % 指定の行にデータを挿入
                tableData(1, 2) = {'Value'};
                tableData(2, 2) = {char(app.INPUT_VARS.HEAD(1))};
                tableData(3, 2) = {char(app.INPUT_VARS.HEAD(2))};
                for i = 4:20
                    tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                % 列ベクトルはそのまま代入
                tableData(21, 2:numColumns) = num2cell(app.INPUT_VARS.ID(:, 1)');      % 21行目 (ID) はベクトル
                tableData(22, 2:numColumns) = num2cell(app.INPUT_VARS.KODE(:, 1)');    % 22行目 (KODE) もベクトル
                tableData(23, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 1)'); % 23行目 (X-start) もベクトル
                tableData(24, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 2)'); % 24行目 (Y-start) もベクトル
                tableData(25, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 3)'); % 25行目 (X-fin) もベクトル
                tableData(26, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 4)'); % 26行目 (Y-fin) もベクトル
                tableData(27, 2:numColumns) = num2cell(repmat(rowData{27}, 1, app.INPUT_VARS.NUM)); % 27行目 (IRAKE) 

                % KODEが100出ない場合、int64(temp_element(:, 5)'), int64(temp_element(:, 6)')にする修正が必要？
                tableData(28, 2:numColumns) = num2cell(temp_element(:, 5)');           % 28行目 (rt.lat/rake) もベクトル
                tableData(29, 2:numColumns) = num2cell(temp_element(:, 6)');           % 29行目 (reverse/net-slip) もベクトル
                tableData(30, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 7)'); % 30行目 (dip) もベクトル
                tableData(31, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 8)'); % 31行目 (top) もベクトル
                tableData(32, 2:numColumns) = num2cell(app.INPUT_VARS.ELEMENT(:, 9)'); % 32行目 (bot) もベクトル
                % Comment
                tableData(33, 2:numColumns) = app.INPUT_VARS.FCOMMENT; % 33行目 (COMMENT) 
                
                for i = 35:40
                    tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                for i = 42:44
                    tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                if ~isempty(app.SECTION_VARS.SEC_XS) && ~isempty(app.SECTION_VARS.SEC_YS) && ~isempty(app.SECTION_VARS.SEC_XF) && ~isempty(app.SECTION_VARS.SEC_YF)...
                    && ~isempty(app.SECTION_VARS.SEC_INCRE) && ~isempty(app.SECTION_VARS.SEC_DEPTH) && ~isempty(app.SECTION_VARS.SEC_DEPTHINC)
                    % クロスセクション関連
                    for i = 46:52
                        tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                    end
                elseif isempty(app.INPUT_VARS.SECTION) ~= 1
                    % セクション関連
                    for i = 46:52
                        tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                    end
                end
                if isempty(app.COORD_VARS.MIN_LON) ~= 1 && isempty(app.COORD_VARS.MAX_LON) ~= 1
                    if isempty(app.COORD_VARS.MIN_LAT) ~= 1 && isempty(app.COORD_VARS.MAX_LAT) ~= 1
                        if isempty(app.COORD_VARS.ZERO_LON) ~= 1 && isempty(app.COORD_VARS.ZERO_LAT) ~= 1
                            % 座標関連の指定がある場合
                            for i = 54:59
                                tableData(i, 2:numColumns) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                            end
                        end
                    end
                end
                %tableData(60, 2:numColumns) = num2cell(repmat(rowData{60}, 1, app.INPUT_VARS.NUM));  % 60行目にタイムスタンプ
                % 60行目にタイムスタンプ、最初の列のみタイムスタンプ、次の列から終わりまでは-を記入
                tableData(60, 2) = num2cell(rowData{60});
                tableData(60, 3:numColumns) = num2cell(repmat('-', 1, app.INPUT_VARS.NUM - 1));

                % CSVファイルに書き込む
                writecell(tableData, fullFilePath);
            
                % 完了メッセージ
                uialert(app.main_menu_window, ...
                    sprintf('CSV file "%s" has been created successfully!', fullFilePath), ...
                    'File Saved', ...
                    'Icon', 'info');
    
            % === ② 既存のCSVにデータを追加 ===================
            elseif strcmp(choice, 'Use Existing')

                [fileName, filePath] = uigetfile(fullfile(defaultDir, '*.csv'), 'Select Existing CSV File');
        
                if isequal(fileName, 0) || isequal(filePath, 0)
                    cd(app.SYSTEM_VARS.HOME_DIR);
                    uialert(app.main_menu_window, 'File selection canceled.', 'Information', 'Icon', 'info');
                    return;
                end
        
                % フルパスの作成
                fullFilePath = fullfile(filePath, fileName);
        
                % 既存のCSVを読み込み
                existingData = readcell(fullFilePath, 'TextType', 'string');
        
                % "missing" 型のデータを "" に変換
                existingData = cellfun(@(x) replaceMissing(x), existingData, 'UniformOutput', false);
        
                % 既存データの最も右の列数を取得
                maxCols = size(existingData, 2);
                newColIndex = maxCols + 1;
                endColIndex = newColIndex + app.INPUT_VARS.NUM - 1;
        
                % 書き込むデータ
                % 書き込み用のセルを用意
                rowData = cell(60,1);
                % 4 ~ 20行目の変数
                rowData(4:20) = {
                    app.INPUT_VARS.NUM; app.INPUT_VARS.POIS; app.INPUT_VARS.CALC_DEPTH; app.INPUT_VARS.YOUNG; app.INPUT_VARS.FRIC; 
                    app.INPUT_VARS.R_STRESS(1, 1); app.INPUT_VARS.R_STRESS(1, 2); app.INPUT_VARS.R_STRESS(1, 3); app.INPUT_VARS.R_STRESS(1, 4); 
                    app.INPUT_VARS.R_STRESS(2, 1); app.INPUT_VARS.R_STRESS(2, 2); app.INPUT_VARS.R_STRESS(2, 3); app.INPUT_VARS.R_STRESS(2, 4); 
                    app.INPUT_VARS.R_STRESS(3, 1); app.INPUT_VARS.R_STRESS(3, 2); app.INPUT_VARS.R_STRESS(3, 3); app.INPUT_VARS.R_STRESS(3, 4)
                };
                % 27 行目のIRAKE
                if isempty(app.CALC_CONTROL.IRAKE) == 1
                    % 空の場合、right-lat & reverse slip formatとして扱い0を代入
                    rowData(27) = {0};
                else
                    % 値がある場合、0:right-lat & reverse slip format、1:rake & net-slip format
                    rowData(27) = {app.CALC_CONTROL.IRAKE};
                end

                % 28 ~ 29 行目、`rt.lat/rake` と `reverse/net-slip` を計算
                temp_element = app.INPUT_VARS.ELEMENT;
                if app.CALC_CONTROL.IRAKE == 1
                    imessage = 0;
                    if isempty(app.CALC_CONTROL.IND_RAKE)
                        for k = 1:app.INPUT_VARS.NUM
                            if app.INPUT_VARS.ELEMENT(k, 5) == 0.0 && app.INPUT_VARS.ELEMENT(k, 6) == 0.0
                                temp_element(k, 5) = 0.0;
                                temp_element(k, 6) = 0.0;
                                imessage = 1;
                            else
                                [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6));
                            end
                        end
                        if imessage == 1
                            warndlg({'No slip element exists.', 'See command window for details.'});
                            disp('Since slip is zero, rake and net slip have been set to 0.0; you must assign a rake to this patch if you want to use it as receiver.');
                            showMessage(app, {'Since slip is zero, rake and net slip have been set to 0.0.', ...
                                'You must assign a rake to this patch if you want to use it as receiver.'}, false);
                        end
                    else
                        for k = 1:app.INPUT_VARS.NUM
                            if app.INPUT_VARS.ELEMENT(k, 5) == 0.0 && app.INPUT_VARS.ELEMENT(k, 6) == 0.0
                                temp_element(k, 5) = app.CALC_CONTROL.IND_RAKE(k, 1);
                                temp_element(k, 6) = 0.0;
                            else
                                [temp_element(k, 5), temp_element(k, 6)] = comp2rake(app, app.INPUT_VARS.ELEMENT(k, 5), app.INPUT_VARS.ELEMENT(k, 6)); 
                            end
                        end
                    end
                end

                % 35 ~ 40行目の変数
                rowData(35:40) = {
                    app.INPUT_VARS.GRID(1, 1); app.INPUT_VARS.GRID(2, 1); app.INPUT_VARS.GRID(3, 1); 
                    app.INPUT_VARS.GRID(4, 1); app.INPUT_VARS.GRID(5, 1); app.INPUT_VARS.GRID(6, 1)
                };
                % 42 ~ 44行目の変数
                rowData(42:44) = {
                    app.INPUT_VARS.SIZE(1, 1); app.INPUT_VARS.SIZE(2, 1); app.INPUT_VARS.SIZE(3, 1)
                };
                % 46 ~ 52行目の変数
                % クロスセクション関連の条件分岐
                if ~isempty(app.SECTION_VARS.SEC_XS) && ~isempty(app.SECTION_VARS.SEC_YS) && ~isempty(app.SECTION_VARS.SEC_XF) && ~isempty(app.SECTION_VARS.SEC_YF)...
                    && ~isempty(app.SECTION_VARS.SEC_INCRE) && ~isempty(app.SECTION_VARS.SEC_DEPTH) && ~isempty(app.SECTION_VARS.SEC_DEPTHINC)
                    % クロスセクション関連
                    rowData(46:52) = {
                        app.SECTION_VARS.SEC_XS; app.SECTION_VARS.SEC_YS; app.SECTION_VARS.SEC_XF; app.SECTION_VARS.SEC_YF;
                        app.SECTION_VARS.SEC_INCRE; (-1.0) * app.SECTION_VARS.SEC_DEPTH; app.SECTION_VARS.SEC_DEPTHINC
                    };
                elseif isempty(app.INPUT_VARS.SECTION) ~= 1
                    % セクション関連
                    rowData(46:52) = {
                        app.INPUT_VARS.SECTION(1, 1); app.INPUT_VARS.SECTION(2, 1); app.INPUT_VARS.SECTION(3, 1); app.INPUT_VARS.SECTION(4, 1);
                        app.INPUT_VARS.SECTION(5, 1); app.INPUT_VARS.SECTION(6, 1); app.INPUT_VARS.SECTION(7, 1)
                    };
                end
                % 54 ~ 59行目の変数
                if isempty(app.COORD_VARS.MIN_LON) ~= 1 && isempty(app.COORD_VARS.MAX_LON) ~= 1
                    if isempty(app.COORD_VARS.MIN_LAT) ~= 1 && isempty(app.COORD_VARS.MAX_LAT) ~= 1
                        if isempty(app.COORD_VARS.ZERO_LON) ~= 1 && isempty(app.COORD_VARS.ZERO_LAT) ~= 1
                            % 座標関連の指定がある場合
                            rowData(54:59) = {
                                app.COORD_VARS.MIN_LON; app.COORD_VARS.MAX_LON; app.COORD_VARS.ZERO_LON;
                                app.COORD_VARS.MIN_LAT; app.COORD_VARS.MAX_LAT; app.COORD_VARS.ZERO_LAT
                            };
                        end
                    end
                end
                % 60行目の変数
                % 現在のタイムスタンプを取得
                rowData(60) = {
                    datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss')
                };
        
                % 指定の行にデータを挿入
                existingData(1, newColIndex) = {'Value'};
                existingData(2, newColIndex) = app.INPUT_VARS.HEAD(1);
                existingData(3, newColIndex) = app.INPUT_VARS.HEAD(2);
                for i = 4:20
                    existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                % 列ベクトルはそのまま代入
                existingData(21, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ID(:, 1)');      % 21行目 (ID) はベクトル
                existingData(22, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.KODE(:, 1)');    % 22行目 (KODE) もベクトル
                existingData(23, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 1)'); % 23行目 (X-start) もベクトル
                existingData(24, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 2)'); % 24行目 (Y-start) もベクトル
                existingData(25, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 3)'); % 25行目 (X-fin) もベクトル
                existingData(26, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 4)'); % 26行目 (Y-fin) もベクトル
                existingData(27, newColIndex:endColIndex) = num2cell(repmat(rowData{27}, 1, app.INPUT_VARS.NUM)); % 27行目 (IRAKE) 

                % KODEが100出ない場合、int64(temp_element(:, 5)'), int64(temp_element(:, 6)')にする修正が必要？
                existingData(28, newColIndex:endColIndex) = num2cell(temp_element(:, 5)');           % 28行目 (rt.lat/rake) もベクトル
                existingData(29, newColIndex:endColIndex) = num2cell(temp_element(:, 6)');           % 29行目 (reverse/net-slip) もベクトル
                existingData(30, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 7)'); % 30行目 (dip) もベクトル
                existingData(31, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 8)'); % 31行目 (top) もベクトル
                existingData(32, newColIndex:endColIndex) = num2cell(app.INPUT_VARS.ELEMENT(:, 9)'); % 32行目 (bot) もベクトル
                % Comment
                existingData(33, newColIndex:endColIndex) = app.INPUT_VARS.FCOMMENT; % 33行目 (COMMENT) セル配列をそのまま代入
                
                for i = 35:40
                    existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                for i = 42:44
                    existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                end
                if ~isempty(app.SECTION_VARS.SEC_XS) && ~isempty(app.SECTION_VARS.SEC_YS) && ~isempty(app.SECTION_VARS.SEC_XF) && ~isempty(app.SECTION_VARS.SEC_YF)...
                    && ~isempty(app.SECTION_VARS.SEC_INCRE) && ~isempty(app.SECTION_VARS.SEC_DEPTH) && ~isempty(app.SECTION_VARS.SEC_DEPTHINC)
                    % クロスセクション関連
                    for i = 46:52
                        existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                    end
                elseif isempty(app.INPUT_VARS.SECTION) ~= 1
                    % セクション関連
                    for i = 46:52
                        existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                    end
                end
                if isempty(app.COORD_VARS.MIN_LON) ~= 1 && isempty(app.COORD_VARS.MAX_LON) ~= 1
                    if isempty(app.COORD_VARS.MIN_LAT) ~= 1 && isempty(app.COORD_VARS.MAX_LAT) ~= 1
                        if isempty(app.COORD_VARS.ZERO_LON) ~= 1 && isempty(app.COORD_VARS.ZERO_LAT) ~= 1
                            % 座標関連の指定がある場合
                            for i = 54:59
                                existingData(i, newColIndex:endColIndex) = num2cell(repmat(rowData{i}, 1, app.INPUT_VARS.NUM));
                            end
                        end
                    end
                end
                % 60行目にタイムスタンプ、最初の列のみタイムスタンプ、次の列から終わりまでは-を記入
                existingData(60, newColIndex) = num2cell(rowData{60});
                existingData(60, newColIndex + 1:endColIndex) = num2cell(repmat('-', 1, app.INPUT_VARS.NUM - 1));
        
                % CSVに上書き保存
                writecell(existingData, fullFilePath);
        
                % 完了メッセージをポップアップで表示
                uialert(app.main_menu_window, ...
                    sprintf('Updated CSV file "%s" with new data.', fullFilePath), ...
                    'File Saved', ...
                    'Icon', 'info');

            end


            % ディレクトリを移動
            cd(app.SYSTEM_VARS.HOME_DIR);

            % === 追加関数: "missing" 型を処理 ===
            function value = replaceMissing(value)
                % "missing" 型のセルを 空文字 "" に置き換える関数
                if ismissing(value)
                    value = "";
                end
            end
        end

        % ==========================================================================================================================================
        %                座標変換の計算関連
        % ==========================================================================================================================================

        function a = lonlat2xy(app, b)
            % =====================================================================
            %                lonlat2xy(app, b)
            % =====================================================================
            % 緯度経度座標をデカルト座標に変換する関数
            % b は [lon lat] 形式のベクトル（マップ上の座標）
            % a は変換された [x y] 形式のベクトル（デカルト座標）
        
            % b のサイズを取得し、m は行数、n は列数を表す
            [m, ~] = size(b);
            
            % 変換後の座標を格納するためのゼロ行列 a を初期化
            a = zeros(m, 2);  
            
            % 経度と緯度の差分を格納するためのベクトルを初期化
            dlon = zeros(m, 1);  
            dlat = zeros(m, 1);  
        
            % マップ上の経度・緯度の増分を計算
            loninc = app.COORD_VARS.LON_GRID(2) - app.COORD_VARS.LON_GRID(1);  % 経度の増分
            latinc = app.COORD_VARS.LAT_GRID(2) - app.COORD_VARS.LAT_GRID(1);  % 緯度の増分
            
            % デカルト座標系の X および Y 軸の増分を計算
            xinc = app.COORD_VARS.XGRID(2) - app.COORD_VARS.XGRID(1);  % X 軸の増分
            yinc = app.COORD_VARS.YGRID(2) - app.COORD_VARS.YGRID(1);  % Y 軸の増分
            
            % 経度・緯度あたりのデカルト座標の増分を計算
            x_per_lon = xinc / loninc;  % 経度あたりの X 軸の変換率
            y_per_lat = yinc / latinc;  % 緯度あたりの Y 軸の変換率
            
            % 入力された経度・緯度と最小経度・緯度の差分を計算
            dlon(:, 1) = b(:, 1) - app.COORD_VARS.MIN_LON;  % 経度の差分
            dlat(:, 1) = b(:, 2) - app.COORD_VARS.MIN_LAT;  % 緯度の差分
            
            % デカルト座標の X, Y を計算し、a に格納
            a(:, 1) = app.INPUT_VARS.GRID(1) + dlon .* x_per_lon;  % X 座標
            a(:, 2) = app.INPUT_VARS.GRID(2) + dlat .* y_per_lat;  % Y 座標
        end

        function a = xy2lonlat(app, b)
            % =====================================================================
            %                xy2lonlat(app, b)
            % =====================================================================
            % This converts cartesian coordinate to mapped coordinate following
            % information from input file
            % it requires 'calc_element.m' calculation in advance.
            %
            % b is vector [x y] in the cartesian coordinate
            % a is converted [lon lat] in the mapped coordinate
        
            % flag is prepared for not assigning and taking over the temporal but wrong
            % info about LON_GRID which influences the coordinate mapping
            flag1 = 0;
            flag2 = 0;
            flag3 = 0;
            flag4 = 0;
        
            %in case (to prevent error message)
            if isempty(app.COORD_VARS.MIN_LAT)
                app.COORD_VARS.MIN_LAT = 0.0;
                flag1 = 1;
            end
            if isempty(app.COORD_VARS.MIN_LON)
                app.COORD_VARS.MIN_LON = 0.0;
                flag2 = 1;
            end
            if isempty(app.COORD_VARS.LON_GRID)
                app.COORD_VARS.LON_GRID(2) = 1.0;
                app.COORD_VARS.LON_GRID(1) = 0.0;
                flag3 = 1;
            end
            if isempty(app.COORD_VARS.LAT_GRID)
                app.COORD_VARS.LAT_GRID(2) = 1.0;
                app.COORD_VARS.LAT_GRID(1) = 0.0;
                flag4 = 1;
            end
        
            [m, ~] = size(b);
            a = zeros(m,2);
            dx = zeros(m,1);
            dy = zeros(m,1);
        
            xinc = app.COORD_VARS.XGRID(2) - app.COORD_VARS.XGRID(1);
            yinc = app.COORD_VARS.YGRID(2) - app.COORD_VARS.YGRID(1);
            loninc = app.COORD_VARS.LON_GRID(2) - app.COORD_VARS.LON_GRID(1);
            latinc = app.COORD_VARS.LAT_GRID(2) - app.COORD_VARS.LAT_GRID(1);
            app.ASSIST_VARS.LON_PER_X = loninc / xinc;
            app.ASSIST_VARS.LAT_PER_Y = latinc / yinc;
            app.COORD_VARS.XY_RATIO = app.ASSIST_VARS.LON_PER_X / app.ASSIST_VARS.LAT_PER_Y;  % x / y
            dx(:,1) = b(:,1) - app.INPUT_VARS.GRID(1);
            dy(:,1) = b(:,2) - app.INPUT_VARS.GRID(2);
            a(:,1) = app.COORD_VARS.MIN_LON + dx .* app.ASSIST_VARS.LON_PER_X;
            a(:,2) = app.COORD_VARS.MIN_LAT + dy .* app.ASSIST_VARS.LAT_PER_Y;
        
            flag = flag1 + flag2 + flag3 + flag4;
            if flag >= 1
                app.COORD_VARS.MIN_LAT = [];
                app.COORD_VARS.MIN_LON = [];
                app.COORD_VARS.LON_GRID = [];
                app.COORD_VARS.LAT_GRID = [];
            end
        end


        % function utm_window(app)
        %     % =====================================================================
        %     %                utm_window(app)
        %     % =====================================================================
        %     % 緯度経度からxyzに変換するウィンドウを表示する関数:utm_window
        % 
        %     % 編集フィールドのデフォルト値の設定
        %     if isempty(app.COORD_VARS.ZERO_LON)
        %         app.COORD_VARS.ZERO_LON = 130.2;
        %     end
        %     if isempty(app.COORD_VARS.MIN_LON) || app.COORD_VARS.MIN_LON == 0
        %         app.COORD_VARS.MIN_LON = 129.5;
        %     end
        %     if isempty(app.COORD_VARS.MAX_LON)
        %         app.COORD_VARS.MAX_LON = 131.0;
        %     end
        %     if isempty(app.ASSIST_VARS.INC_LON)
        %         app.ASSIST_VARS.INC_LON = 0.05;
        %     end
        %     if isempty(app.COORD_VARS.ZERO_LAT)
        %         app.COORD_VARS.ZERO_LAT = 33.8;
        %     end
        %     if isempty(app.COORD_VARS.MIN_LAT) || app.COORD_VARS.MIN_LAT == 0
        %         app.COORD_VARS.MIN_LAT = 33.0;
        %     end
        %     if isempty(app.COORD_VARS.MAX_LAT)
        %         app.COORD_VARS.MAX_LAT = 34.5;
        %     end
        %     if isempty(app.ASSIST_VARS.INC_LAT)
        %         app.ASSIST_VARS.INC_LAT = 0.05;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_LON)
        %         app.ASSIST_VARS.EQ_LON = 130.178;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_LAT)
        %         app.ASSIST_VARS.EQ_LAT = 33.741;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_DEPTH)
        %         app.ASSIST_VARS.EQ_DEPTH = 7.5;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_LENGTH)
        %         app.ASSIST_VARS.EQ_LENGTH = 20.0;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_WIDTH)
        %         app.ASSIST_VARS.EQ_WIDTH = 10.0;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_MW)
        %         app.ASSIST_VARS.EQ_MW = 6.6;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_STRIKE)
        %         app.ASSIST_VARS.EQ_STRIKE = 303.0;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_DIP)
        %         app.ASSIST_VARS.EQ_DIP = 81.0;
        %     end
        %     if isempty(app.ASSIST_VARS.EQ_RAKE)
        %         app.ASSIST_VARS.EQ_RAKE = 4.0;
        %     end
        % 
        %     % ポップアップウィンドウの作成---------------------------------------------------------
        %     popup = uifigure('Name', 'Build input from CMT or focal mech data', 'Position', [100 100 500 680]); % ウィンドウの位置とサイズ
        % 
        %     % グリッドレイアウトを作成:
        %     grid = uigridlayout(popup, [20, 6]); % グリッドの個数（行数、列数）
        %     grid.RowHeight = {'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit', ...
        %         'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit', ...
        %         'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit'}; % グリッドの高さ（行ごとに指定）
        %     grid.ColumnWidth = repmat({'1x'}, 1, 6); % 等間隔のグリッドの列幅
        % 
        %     % 説明文ラベル
        %     uilabel(grid, 'Text', 'If there is no problem, press the OK button.', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 1, 'Column', [1, 6])); % 文字ラベル
        % 
        %     % Study Areaラベル
        %     uilabel(grid, 'Text', 'Study Area', ...
        %         'FontWeight', 'bold', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 2, 'Column', [1, 2])); % 文字ラベル
        % 
        %     % lonlatラベル----------------------------------------
        %     uilabel(grid, 'Text', 'Lon (°E)', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 3, 'Column', 4)); % 文字ラベル
        %     uilabel(grid, 'Text', 'Lat (°N)', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 3, 'Column', 5)); % 文字ラベル
        % 
        %     % ラベルと入力フィールドを追加（Reference point）
        %     uilabel(grid, 'Text', 'Reference point (x, y) = (0, 0)', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 4, 'Column', [1, 3])); % 文字ラベル(1 ~ 3列目を結合): Reference point
        %     edit_center_lon = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.ZERO_LON, ...
        %         'Tag', 'edit_center_lon', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 4, 'Column', 4)); % Lon
        %     edit_center_lat = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.ZERO_LAT, ...
        %         'Tag', 'edit_center_lat', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 4, 'Column', 5)); % Lat
        % 
        %     % 緯度経度の範囲のラベル----------------------------------------
        %     uilabel(grid, 'Text', 'Min (°)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 5, 'Column', 2)); % 文字ラベル: min
        %     uilabel(grid, 'Text', 'Max (°)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 5, 'Column', 3)); % 文字ラベル: max
        %     uilabel(grid, 'Text', 'Incre. (°)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 5, 'Column', 4)); % 文字ラベル: incr.
        % 
        %     % 緯度経度の範囲取得
        %     uilabel(grid, 'Text', 'Lon (°E)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 6, 'Column', 1)); % 文字ラベル: Lon (°E)
        %     edit_min_lon = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.MIN_LON, ...
        %         'Tag', 'edit_min_lon', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 6, 'Column', 2)); % min_lon
        %     edit_max_lon = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.MAX_LON, ...
        %         'Tag', 'edit_max_lon', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 6, 'Column', 3)); % max_lon
        %     edit_inc_lon = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.INC_LON, ...
        %         'Tag', 'edit_inc_lon', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 6, 'Column', 4)); % inc_lon
        %     % Calc ボタン
        %     Calc_SA_Button = uibutton(grid, 'Text', 'Calc.', 'ButtonPushedFcn', @(btn, event) onSACalcClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 6, 'Column', 6)); % Study AreaのCalc button
        % 
        %     uilabel(grid, 'Text', 'Lat (°N)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 7, 'Column', 1)); % 文字ラベル: Lat (°N)
        %     edit_min_lat = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.MIN_LAT, ...
        %         'Tag', 'edit_min_lat', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 7, 'Column', 2)); % min_lat
        %     edit_max_lat = uieditfield(grid, 'numeric', 'Value', app.COORD_VARS.MAX_LAT, ...
        %         'Tag', 'edit_max_lat', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 7, 'Column', 3)); % max_lat
        %     edit_inc_lat = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.INC_LAT, ...
        %         'Tag', 'edit_inc_lat', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 7, 'Column', 4)); % inc_lat
        %     % Calc ボタン
        %     AddMap_Button = uibutton(grid, 'Text', 'Add to Map', ...
        %         'Tag', 'pushbutton_add', ...
        %         'ButtonPushedFcn', @(btn, event) onAddMapClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 7, 'Column', 6)); % Study AreaのCalc button
        % 
        %     % ラベルと入力フィールドを追加（xyの計算結果）----------------------------------------
        %     uilabel(grid, 'Text', 'X Start', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 1)); % 文字ラベル: X Start
        %     uilabel(grid, 'Text', 'Y Start', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 2)); % 文字ラベル: Y Start
        %     uilabel(grid, 'Text', 'X Finish', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 3)); % 文字ラベル: X Finish
        %     uilabel(grid, 'Text', 'Y Finish', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 4)); % 文字ラベル: Y Finish
        %     uilabel(grid, 'Text', 'X incr.', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 5)); % 文字ラベル: X incr.
        %     uilabel(grid, 'Text', 'Y incr.', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 8, 'Column', 6)); % 文字ラベル: Y incr.
        % 
        %     % 編集フィールド
        %     edit_x_start = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(1, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_x_start', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 1)); % x_start
        %     edit_y_start = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(2, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_y_start', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 2)); % y_start
        %     edit_x_finish = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(3, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_x_finish', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 3)); % x_finish
        %     edit_y_finish = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(4, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_y_finish', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 4)); % y_finish
        %     edit_x_inc = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(5, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_x_inc', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 5)); % x_inc
        %     edit_y_inc = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.GRID(6, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_y_inc', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 9, 'Column', 6)); % y_inc
        % 
        %     uilabel(grid, 'Text', ['--------------------------------------------------------' ...
        %         '--------------------------------------------------------'], ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 10, 'Column', [1, 6])); % 文字ラベル: -------------
        % 
        %     % Fault Elementsラベル
        %     uilabel(grid, 'Text', 'Fault Elements', ...
        %         'FontWeight', 'bold', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 11, 'Column', [1, 2])); % 文字ラベル
        % 
        %     % Fault Reference pointラベル
        %     uilabel(grid, 'Text', 'Fault Reference point', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 12, 'Column', [1, 2])); % 文字ラベル
        %     uilabel(grid, 'Text', 'Lon (°E)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 12, 'Column', 3)); % 文字ラベル: Lon (°E)
        %     uilabel(grid, 'Text', 'Lat (°N)', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 12, 'Column', 4)); % 文字ラベル: Lat (°N)
        %     uilabel(grid, 'Text', 'Depth to Source', 'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 12, 'Column', 5)); % 文字ラベル: Depth to source
        % 
        %     edit_eq_lon = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_LON, ...
        %         'Tag', 'edit_eq_lon', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 13, 'Column', 3)); % lon
        %     edit_eq_lat = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_LAT, ...
        %         'Tag', 'edit_eq_lat', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 13, 'Column', 4)); % lat
        %     edit_eq_depth = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_DEPTH, ...
        %         'Tag', 'edit_eq_depth', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 13, 'Column', 5)); % depth
        % 
        %     uilabel(grid, 'Text', ['--------------------------------------------------------' ...
        %         '--------------------------------------------------------'], ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 14, 'Column', [1, 6])); % 文字ラベル: -------------
        % 
        %     % IDごとの断層要素----------------------------------------
        %     uilabel(grid, 'Text', 'Fault ID Number', ...
        %         'FontWeight', 'bold', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 15, 'Column', [1, 2])); % 文字ラベル: ID Number
        %     edit_id_number = uieditfield(grid, 'numeric', 'Value', 1, ...
        %         'ValueDisplayFormat', '%.0f', ...
        %         'FontWeight', 'bold', ...
        %         'Tag', 'edit_id_number', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 15, 'Column', 3)); % ID Number
        %     fromempiricalButton = uibutton(grid, 'Text', 'From Empirical Relations', ...
        %         'ButtonPushedFcn', @(btn, event) onfromempiricalClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 15, 'Column', [5, 6])); % From Empirical Relations button
        % 
        %     uilabel(grid, 'Text', 'Length', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 1)); % 文字ラベル: Length
        %     uilabel(grid, 'Text', 'Width', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 2)); % 文字ラベル: Width
        %     uilabel(grid, 'Text', 'Mw', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 3)); % 文字ラベル: Mw
        %     uilabel(grid, 'Text', 'Strike', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 4)); % 文字ラベル: Strike
        %     uilabel(grid, 'Text', 'Dip', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 5)); % 文字ラベル: Dip
        %     uilabel(grid, 'Text', 'Rake', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 16, 'Column', 6)); % 文字ラベル: Rake
        % 
        %     % 編集フィールド
        %     edit_eq_length = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_LENGTH, ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_eq_length', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 1)); % Length
        %     edit_eq_width = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_WIDTH, ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_eq_width', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 2)); % Width
        %     edit_eq_moment_mw = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_MW, ...
        %         'ValueDisplayFormat', '%.1f', ...
        %         'Tag', 'edit_eq_moment_mw', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 3)); % Mw
        %     edit_eq_strike = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_STRIKE, ...
        %         'ValueDisplayFormat', '%.1f °', ...
        %         'Tag', 'edit_eq_strike', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 4)); % Strike
        %     edit_eq_dip = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_DIP, ...
        %         'ValueDisplayFormat', '%.1f °', ...
        %         'Tag', 'edit_eq_dip', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 5)); % Dip
        %     edit_eq_rake = uieditfield(grid, 'numeric', 'Value', app.ASSIST_VARS.EQ_RAKE, ...
        %         'ValueDisplayFormat', '%.1f °', ...
        %         'Tag', 'edit_eq_rake', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 17, 'Column', 6)); % Rake
        % 
        %     % ラベルと入力フィールドを追加（IDごとの断層要素の計算結果）----------------------------------------
        %     uilabel(grid, 'Text', 'X Start', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 18, 'Column', 1)); % 文字ラベル: X Start
        %     uilabel(grid, 'Text', 'Y Start', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 18, 'Column', 2)); % 文字ラベル: Y Start
        %     uilabel(grid, 'Text', 'X Finish', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 18, 'Column', 3)); % 文字ラベル: X Finish
        %     uilabel(grid, 'Text', 'Y Finish', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 18, 'Column', 4)); % 文字ラベル: Y Finish
        %     % 編集フィールド
        %     edit_fx_start = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 1), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_fx_start', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 19, 'Column', 1)); % fx_start
        %     edit_fy_start = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 2), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_fy_start', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 19, 'Column', 2)); % fy_start
        %     edit_fx_finish = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 3), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_fx_finish', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 19, 'Column', 3)); % fx_finish
        %     edit_fy_finish = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 4), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_fy_finish', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 19, 'Column', 4)); % fy_finish
        %     % Calc ボタン
        %     FE_Button = uibutton(grid, 'Text', 'Calc.', 'ButtonPushedFcn', @(btn, event) onFaultElemntsCalcClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 19, 'Column', 6)); % Fault elementsのCalc button
        % 
        %     uilabel(grid, 'Text', 'right lat.', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 20, 'Column', 1)); % 文字ラベル: right lat.
        %     uilabel(grid, 'Text', 'rev. slip', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 20, 'Column', 2)); % 文字ラベル: rev. slip
        %     uilabel(grid, 'Text', 'top', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 20, 'Column', 3)); % 文字ラベル: top
        %     uilabel(grid, 'Text', 'bottom', ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 20, 'Column', 4)); % 文字ラベル: bottom
        %     % 編集フィールド
        %     edit_right_lat = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 5), ...
        %         'ValueDisplayFormat', '%.2f m', ...
        %         'Tag', 'edit_right_lat', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 21, 'Column', 1)); % right_lat
        %     edit_rev_slip = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 6), ...
        %         'ValueDisplayFormat', '%.2f m', ...
        %         'Tag', 'edit_rev_slip', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 21, 'Column', 2)); % rev_slip
        %      edit_f_top = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 8), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', ' edit_f_top', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 21, 'Column', 3)); %  f_top
        %     edit_f_bottom = uieditfield(grid, 'numeric', 'Value', app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 9), ...
        %         'ValueDisplayFormat', '%.2f km', ...
        %         'Tag', 'edit_f_bottom', ...
        %         'BackgroundColor', 'Yellow', ...
        %         'ValueChangedFcn', @(src, event) onValueChanged(app, src), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 21, 'Column', 4)); % f_bottom
        %     % Add to Map ボタン
        %     FEAddMap_Button = uibutton(grid, 'Text', 'Add to Map', 'ButtonPushedFcn', @(btn, event) onFEAddMapClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 21, 'Column', 6)); % Fault elementsのCalc button
        % 
        %     uilabel(grid, 'Text', ['--------------------------------------------------------' ...
        %         '--------------------------------------------------------'], ...
        %         'HorizontalAlignment', 'center', ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 22, 'Column', [1, 6])); % 文字ラベル: -------------
        % 
        %     % OK & Cancel ボタン
        %     editallinputButton = uibutton(grid, 'Text', 'Edit all input parameters', ...
        %         'ButtonPushedFcn', @(btn, event) onfromempiricalClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 23, 'Column', [3, 4])); % Edit all input parameters button
        %     okButton = uibutton(grid, 'Text', 'OK', ...
        %         'FontWeight', 'bold', ...
        %         'ButtonPushedFcn', @(btn, event) oneditallinputClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 23, 'Column', 5)); % OK button
        %     cancelButton = uibutton(grid, 'Text', 'Cancel', ...
        %         'FontWeight', 'bold', ...
        %         'ButtonPushedFcn', @(btn, event) onCancelClicked(app, popup), ...
        %         'Layout', matlab.ui.layout.GridLayoutOptions('Row', 23, 'Column', 6)); % Cancel button
        % 
        %     % データから入力した元々の編集フィールドの値を下に複製
        %     % app.ASSIST_VARS.EQ_EDIT_ZFORMAT = [EQDataSize, SYearField, SMonthField, SDayField, SHourField, SMinuteField, ...
        %     %     FYearField, FMonthField, FDayField, FHourField, FMinuteField, Minlon, Minlat, MinMw, MinDep, Maxlon, Maxlat, MaxMw, MaxDep];
        % 
        %     % ネスト関数---------------------------------------------------------
        %     function onValueChanged(app, src)
        %         % 数値変更時のコールバック
        %         switch src.Tag
        %             case 'edit_center_lon'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(1) = num2str(src.Value);
        %             case 'edit_center_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(2) = num2str(src.Value);
        %             case 'edit_min_lon'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(3) = num2str(src.Value);
        %             case 'edit_max_lon'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(4) = num2str(src.Value);
        %             case 'edit_inc_lon'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(5) = num2str(src.Value);
        %             case 'edit_min_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(6) = num2str(src.Value);
        %             case 'edit_max_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(7) = num2str(src.Value);
        %             case 'edit_inc_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(8) = num2str(src.Value);
        %             case 'edit_x_start'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(9) = num2str(src.Value);
        %             case 'edit_y_start'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(10) = num2str(src.Value);
        %             case 'edit_x_finish'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(11) = num2str(src.Value);
        %             case 'edit_y_finish'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(12) = num2str(src.Value);
        %             case 'edit_x_inc'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(13) = num2str(src.Value);
        %             case 'edit_y_inc'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(14) = num2str(src.Value);
        %             case 'edit_eq_lon'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(15) = num2str(src.Value);
        %             case 'edit_eq_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(16) = num2str(src.Value);
        %             case 'edit_eq_depth'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(17) = num2str(src.Value);
        %             case 'edit_eq_length'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(18) = num2str(src.Value);
        %             case 'edit_eq_width'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_eq_moment_mw'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_eq_strike'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_eq_dip'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_eq_rake'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_fx_start'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_fy_start'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_fx_finish'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_fy_finish'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_right_lat'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_rev_slip'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_f_top'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             case 'edit_f_bottom'
        %                 app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19) = num2str(src.Value);
        %             otherwise
        %                 uialert(app.main_menu_window, 'Unknown field changed.', 'Warning!');
        %         end
        %     end
        % 
        %     function onSACalcClicked(app, ~)
        %         % Study AreaのCalcボタンを押したときに実行される関数
        %         % UTM_FLAGが0の場合は座標の確認のみ、1の場合は入力ファイルを作成
        % 
        %         % 警告ダイアログ：座標の大小関係を確認し、問題がある場合は警告を表示
        %         if app.COORD_VARS.MIN_LAT >= app.COORD_VARS.MAX_LAT
        %             uialert(app.main_menu_window, 'max lat should be larger than min lat', 'Warning!');
        %         end
        %         if app.COORD_VARS.MIN_LON >= app.COORD_VARS.MAX_LON
        %             uialert(app.main_menu_window, 'max lon should be larger than min lon', 'Warning!');
        %         end
        %         if app.COORD_VARS.ZERO_LON > app.COORD_VARS.MAX_LON || app.COORD_VARS.ZERO_LON < app.COORD_VARS.MIN_LON
        %             uialert(app.main_menu_window, 'ref lon should be in the study area', 'Warning!');
        %         end
        %         if app.COORD_VARS.ZERO_LAT > app.COORD_VARS.MAX_LAT || app.COORD_VARS.ZERO_LAT < app.COORD_VARS.MIN_LAT
        %             uialert(app.main_menu_window, 'ref lat should be in the study area', 'Warning!');
        %         end
        % 
        %         % グリッドの設定
        %         ndlon = int16((app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON) / app.ASSIST_VARS.INC_LON);
        %         ndlat = int16((app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT) / app.ASSIST_VARS.INC_LAT);
        %         modlon = mod((app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON), app.ASSIST_VARS.INC_LON);
        %         modlat = mod((app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT), app.ASSIST_VARS.INC_LAT);
        % 
        %         if ndlon <= 5 || ndlat <= 5
        %              uialert(app.main_menu_window, 'grid interval is so wide', 'Warning!');
        %         end
        % 
        %         pcent_lon = (app.COORD_VARS.MAX_LON + app.COORD_VARS.MIN_LON) / 2.0;
        %         pcent_lat = (app.COORD_VARS.MAX_LAT + app.COORD_VARS.MIN_LAT) / 2.0;
        % 
        %         % 総距離の計算（X軸およびY軸）
        %         if app.CALC_CONTROL.MAPTLFLAG == 1
        %             xdeg  = distance(pcent_lat, app.COORD_VARS.MIN_LON, pcent_lat, app.COORD_VARS.MAX_LON);
        %             xdist = deg2km(xdeg);
        %             xmin  = deg2km(distance(pcent_lat, app.COORD_VARS.MIN_LON, pcent_lat, app.COORD_VARS.ZERO_LON));
        %             xmax  = deg2km(distance(pcent_lat, app.COORD_VARS.MAX_LON, pcent_lat, app.COORD_VARS.ZERO_LON));
        %             ydeg  = distance(app.COORD_VARS.MIN_LAT, pcent_lon, app.COORD_VARS.MAX_LAT, pcent_lon);
        %             ydist = deg2km(ydeg);
        %             ymin  = deg2km(distance(app.COORD_VARS.MIN_LAT, pcent_lon, app.COORD_VARS.ZERO_LAT, pcent_lon));
        %             ymax  = deg2km(distance(app.COORD_VARS.MAX_LAT, pcent_lon, app.COORD_VARS.ZERO_LAT, pcent_lon));
        %         else
	    %             [xdist, flag1] = distance2(app, pcent_lat, app.COORD_VARS.MIN_LON, pcent_lat, app.COORD_VARS.MAX_LON);
        %             [xmin, flag3] = distance2(app, pcent_lat, app.COORD_VARS.MIN_LON, pcent_lat, app.COORD_VARS.ZERO_LON);
        %             [xmax, flag5] = distance2(app, pcent_lat, app.COORD_VARS.MAX_LON, pcent_lat, app.COORD_VARS.ZERO_LON);
	    %             [ydist, flag2] = distance2(app, app.COORD_VARS.MIN_LAT, pcent_lon, app.COORD_VARS.MAX_LAT, pcent_lon);
        %             [ymin, flag4] = distance2(app, app.COORD_VARS.MIN_LAT, pcent_lon, app.COORD_VARS.ZERO_LAT, pcent_lon);
        %             [ymax, flag6] = distance2(app, app.COORD_VARS.MAX_LAT, pcent_lon, app.COORD_VARS.ZERO_LAT, pcent_lon);
        %             dummy = flag1 + flag2 + flag3 + flag4 + flag5 + flag6;
        % 
        %             if dummy >= 1
        %                 msgbox('The points are over two UTM zones. It will be switched to simple great circle calc.','Notice','warn');
        %                 xdist = greatCircleDistance(deg2rad(pcent_lat), deg2rad(app.COORD_VARS.MIN_LON), deg2rad(pcent_lat), deg2rad(app.COORD_VARS.MAX_LON));
        %                 xmin  = greatCircleDistance(deg2rad(pcent_lat), deg2rad(app.COORD_VARS.MIN_LON), deg2rad(pcent_lat), deg2rad(app.COORD_VARS.ZERO_LON));
        %                 xmax  = greatCircleDistance(deg2rad(pcent_lat), deg2rad(app.COORD_VARS.MAX_LON), deg2rad(pcent_lat), deg2rad(app.COORD_VARS.ZERO_LON));
        %                 ydist = greatCircleDistance(deg2rad(app.COORD_VARS.MIN_LAT), deg2rad(pcent_lon), deg2rad(app.COORD_VARS.MAX_LAT), deg2rad(pcent_lon));
        %                 ymin  = greatCircleDistance(deg2rad(app.COORD_VARS.MIN_LAT), deg2rad(pcent_lon), deg2rad(app.COORD_VARS.ZERO_LAT), deg2rad(pcent_lon));
        %                 ymax  = greatCircleDistance(deg2rad(app.COORD_VARS.MAX_LAT), deg2rad(pcent_lon), deg2rad(app.COORD_VARS.ZERO_LAT), deg2rad(pcent_lon));
        %             end
        %         end
        % 
        %         if app.COORD_VARS.ZERO_LON > app.COORD_VARS.MIN_LON
        %             xmin = -xmin;
        %         end
        %         if app.COORD_VARS.ZERO_LAT > app.COORD_VARS.MIN_LAT
        %             ymin = -ymin;
        %         end
        %         if app.COORD_VARS.ZERO_LON > app.COORD_VARS.MAX_LON
        %             xmax = -xmax;
        %         end
        %         if app.COORD_VARS.ZERO_LAT > app.COORD_VARS.MAX_LAT
        %             ymax = -ymax;
        %         end
        % 
        %         set(findobj('Tag','edit_x_start'),'String',num2str(xmin,'%8.2f'));
        %         set(findobj('Tag','edit_y_start'),'String',num2str(ymin,'%8.2f'));
        %         set(findobj('Tag','edit_x_finish'),'String',num2str(xmax,'%8.2f'));
        %         set(findobj('Tag','edit_y_finish'),'String',num2str(ymax,'%8.2f'));
        % 
        %         % グリッドの設定
        %         XS = xmin; app.INPUT_VARS.GRID(1, 1) = xmin;
        %         XF = xmax; app.INPUT_VARS.GRID(3, 1) = xmax;
        %         YS = ymin; app.INPUT_VARS.GRID(2, 1) = ymin;
        %         YF = ymax; app.INPUT_VARS.GRID(4, 1) = ymax;
        %         app.INPUT_VARS.GRID(5, 1) = (xmax - xmin) / double(ndlon);
        %         app.INPUT_VARS.GRID(6, 1) = (ymax - ymin) / double(ndlat);
        % 
        %         set(findobj('Tag','edit_x_inc'), 'String', num2str(app.INPUT_VARS.GRID(5, 1), '%8.2f'));
        %         set(findobj('Tag','edit_y_inc'), 'String', num2str(app.INPUT_VARS.GRID(6, 1), '%8.2f'));
        % 
        %         app.ASSIST_VARS.PC_LON = pcent_lon;
        %         app.ASSIST_VARS.PC_LAT = pcent_lat;
        % 
        %         set(findobj('Tag','pushbutton_add'),'Enable','on');
        %         set(findobj('Tag','edit_eq_lon'),'Enable','on');
        %         set(findobj('Tag','edit_eq_lat'),'Enable','on');
        %         set(findobj('Tag','edit_eq_depth'),'Enable','on');
        %         set(findobj('Tag','edit_eq_length'),'Enable','on');
        %         set(findobj('Tag','edit_eq_width'),'Enable','on');
        %         set(findobj('Tag','edit_eq_mo'),'Enable','on');
        %         set(findobj('Tag','edit_eq_strike'),'Enable','on');
        %         set(findobj('Tag','edit_eq_dip'),'Enable','on');
        %         set(findobj('Tag','edit_eq_rake'),'Enable','on');
        %         set(findobj('Tag','edit_id_number'),'Enable','on');
        %         set(findobj('Tag','edit_fx_start'),'Enable','on');
        %         set(findobj('Tag','edit_fx_finish'),'Enable','on');
        %         set(findobj('Tag','edit_fy_start'),'Enable','on');
        %         set(findobj('Tag','edit_fy_finish'),'Enable','on');
        %         set(findobj('Tag','edit_right_lat'),'Enable','on');
        %         set(findobj('Tag','edit_rev_slip'),'Enable','on');
        %         set(findobj('Tag','edit_f_top'),'Enable','on');
        %         set(findobj('Tag','edit_f_bottom'),'Enable','on');
        %         set(findobj('Tag','pushbutton_empirical'),'Enable','on');
        %         set(findobj('Tag','pushbutton_f_add'),'Enable','off');
        % 
        %         % 座標の確認ツールとして使用する場合
        %         if app.ASSIST_VARS.UTM_FLAG == 0
        %             set(findobj('Tag','pushbutton_f_calc'),'Enable','on');
        %         else
        %             set(findobj('Tag','pushbutton_f_calc'),'Enable','off');
        %         end
        %         set(findobj('Tag','edit_all_input_params'),'Enable','off');
        %     end
        % 
        %     function onAddMapClicked(app, ~)
        %         % グリッド位置を計算し、他のすべての関数で使用できるように保持
        %         xstart = app.INPUT_VARS.GRID(1, 1);
        %         ystart = app.INPUT_VARS.GRID(2, 1);
        %         xfinish = app.INPUT_VARS.GRID(3, 1);
        %         yfinish = app.INPUT_VARS.GRID(4, 1);
        %         xinc = app.INPUT_VARS.GRID(5, 1);
        %         yinc = app.INPUT_VARS.GRID(6, 1);
        %         nxinc = int16((xfinish - xstart) / xinc + 1);
        %         nyinc = int16((yfinish - ystart) / yinc + 1);
        %         xpp = 1:1:nxinc;
        %         ypp = 1:1:nyinc;
        %         app.COORD_VARS.XGRID = double(xstart) + (double(1:1:(nxinc - 1)) - 1.0) * double(xinc);
        %         app.COORD_VARS.YGRID = double(ystart) + (double(1:1:(nyinc - 1)) - 1.0) * double(yinc);
        %         app.CALC_CONTROL.FUNC_SWITCH = 1;
        %         app.INPUT_VARS.CALC_DEPTH = 0; % グリッド描画機能のための一時的な値
        % 
        % 
        %         %===== マップ情報が存在する場合の処理
        %         if ~isempty(app.COORD_VARS.MIN_LON) && ~isempty(app.COORD_VARS.MAX_LON)
        %             if ~isempty(app.COORD_VARS.MIN_LAT) && ~isempty(app.COORD_VARS.MAX_LAT)
        %                 if ~isempty(app.COORD_VARS.ZERO_LON) && ~isempty(app.COORD_VARS.ZERO_LAT)
        %                     xinc = double(app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON) / double(nxinc - 1);
        %                     yinc = double(app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT) / double(nyinc - 1);
        %                     app.COORD_VARS.LON_GRID = double(app.COORD_VARS.MIN_LON) + (double(1:1:nxinc) - 1.0) * double(xinc);
        %                     app.COORD_VARS.LAT_GRID = double(app.COORD_VARS.MIN_LAT) + (double(1:1:nyinc) - 1.0) * double(yinc);
        %                 end
        %             end
        %         end
        %         %=====
        % 
        %         % グリッド描画
        %         grid_drawing(app);
        %         app.CALC_CONTROL.FUNC_SWITCH = 0;
        %         % メニュー関連の表示設定
        %         set(findobj('Tag','pushbutton_f_calc'),'Enable','on');
        %         set(findobj('Tag','menu_gridlines'),'Enable','On');
        %         set(findobj('Tag','menu_coastlines'),'Enable','On');
        %         set(findobj('Tag','menu_activefaults'),'Enable','On');
        %         set(findobj('Tag','menu_earthquakes'),'Enable','On');    
        %     end
        % 
        %     function onfromempiricalClicked(app, ~)
        %         wells_coppersmith_window(app);
        %     end
        % 
        %     function onFaultElemntsCalcClicked(app, ~)
        %         % 断層の長さ、幅、モーメントマグニチュードをGUIから取得
        %         % 修正必要
        %         app.ASSIST_VARS.EQ_LENGTH = str2double(get(findobj('Tag', 'edit_eq_length'), 'String'));
        %         app.ASSIST_VARS.EQ_WIDTH  = str2double(get(findobj('Tag', 'edit_eq_width'), 'String'));
        %         app.ASSIST_VARS.EQ_MW     = str2double(get(findobj('Tag', 'edit_eq_mo'), 'String'));
        % 
        %         % 断層の数（INUM）を数える
        %         % app.INPUT_VARS.INUM = app.INPUT_VARS.INUM + 1;
        % 
        %         % 断層のID番号を更新
        %         % 修正必要
        %         set(findobj('Tag', 'edit_id_number'), 'String', num2str(app.INPUT_VARS.INUM, '%2i'));
        %         % 断層の総数
        %         app.INPUT_VARS.NUM = length(app.INPUT_VARS.ID);
        %         % Kode：計算のタイプを指定するコード，通常の計算では100
        %         app.INPUT_VARS.KODE(app.INPUT_VARS.INUM, 1) = 100;
        %         app.INPUT_VARS.ID(app.INPUT_VARS.INUM, 1) = 1;
        %         app.INPUT_VARS.FCOMMENT(app.INPUT_VARS.INUM, :) = ['Fault ' num2str(app.INPUT_VARS.INUM, '%3i')];
        % 
        %         % 断層の位置参照方法を取得（中央または端部）
        %         % fref = 0：中心の位置参照、fref = 1：端部の位置参照
        %         fref = 0;
        %         fref = get(handles.radiobutton_edgepos, 'Value');
        % 
        %         % 地震の震源位置に基づいて断層の始点と終点のX, Y座標を計算
        %         if app.CALC_CONTROL.MAPTLFLAG == 1
        %             xdist = deg2km(distance(app.ASSIST_VARS.PC_LAT, app.ASSIST_VARS.EQ_LON, app.ASSIST_VARS.PC_LAT, app.COORD_VARS.ZERO_LON));
        %         else
        %             [xdist, flag] = distance2(app, app.ASSIST_VARS.PC_LAT, app.ASSIST_VARS.EQ_LON, app.ASSIST_VARS.PC_LAT, app.COORD_VARS.ZERO_LON);
        %             if flag == 1
        %                 xdist  = greatCircleDistance(deg2rad(app.ASSIST_VARS.PC_LAT), deg2rad(app.ASSIST_VARS.EQ_LON), deg2rad(app.ASSIST_VARS.PC_LAT), deg2rad(app.COORD_VARS.ZERO_LON)); 
        %             end
        %         end
        %         if app.ASSIST_VARS.EQ_LON < app.COORD_VARS.ZERO_LON
        %             xdist = -xdist;
        %         end
        %         if app.CALC_CONTROL.MAPTLFLAG == 1
        %             ydist = deg2km(distance(app.ASSIST_VARS.EQ_LAT, app.ASSIST_VARS.PC_LON, app.COORD_VARS.ZERO_LAT, app.ASSIST_VARS.PC_LON));
        %         else
        %             [ydist, flag] = distance2(app, app.ASSIST_VARS.EQ_LAT, app.ASSIST_VARS.PC_LON, app.COORD_VARS.ZERO_LAT, app.ASSIST_VARS.PC_LON);
        %             if flag == 1
        %                 ydist  = greatCircleDistance(deg2rad(app.ASSIST_VARS.EQ_LAT), deg2rad(app.ASSIST_VARS.PC_LON), deg2rad(app.COORD_VARS.ZERO_LAT), deg2rad(app.ASSIST_VARS.PC_LON)); 
        %             end
        %         end
        %         if app.ASSIST_VARS.EQ_LAT < app.COORD_VARS.ZERO_LAT
        %             ydist = -ydist;
        %         end
        % 
        %         % 断層の終点を計算（端部または中央参照）
        %         if fref == 1 
        %             % 端部位置参照
        %             xfn = xdist + sin(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH;
        %             yfn = ydist + cos(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH;
        %         else
        %             % 中央位置参照
        %             xtemp = xdist;
        %             ytemp = ydist;
        %             xdist = xtemp - sin(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH / 2.0;
        %             ydist = ytemp - cos(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH / 2.0;
        %             xfn   = xtemp + sin(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH / 2.0;
        %             yfn   = ytemp + cos(deg2rad(app.ASSIST_VARS.EQ_STRIKE)) * app.ASSIST_VARS.EQ_LENGTH / 2.0;
        % 
        %            % 断層の傾斜角度（dip-slip direction）に基づいて、始点と終点の調整を行う
        %             dd = (app.ASSIST_VARS.EQ_WIDTH / 2.) * cos(deg2rad(app.ASSIST_VARS.EQ_DIP));
        %             theta = atan(abs(yfn - ydist) / abs(xfn - xdist));
        %             zx = dd * sin(theta);   % x軸方向の調整量（単位距離）
        %             zy = dd * cos(theta);   % y軸方向の調整量（単位距離）
        %             if xfn >= xdist
        %                 if yfn >= ydist
        %                     zx = -zx;
        %                 end
        %             else
        %                 if yfn >= ydist
        %                     zx = -zx;
        %                     zy = -zy;
        %                 else
        %                     zy = -zy;
        %                 end
        %             end 
        %             xdist = xdist + zx;
        %             ydist = ydist + zy;
        %             xfn   = xfn + zx;
        %             yfn   = yfn + zy;
        %         end
        % 
        %         % 断層の始点と終点をGUIに表示し、変数に保存
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 1) = xdist;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 2) = ydist;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 3) = xfn;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 4) = yfn;
        % 
        %         % モーメントマグニチュードから断層の変位を計算し、表示。
        %         % 剪断モジュール
        %         shr = 3.4e+11;
        %         % 地震モーメント
        %         mo = power(10, (1.5 * app.ASSIST_VARS.EQ_MW + 9.1)) * 1.0e+7;
        %         slip = mo / (shr * app.ASSIST_VARS.EQ_LENGTH * app.ASSIST_VARS.EQ_WIDTH * 1.0e+10);
        %         rlslip = ((-1.0) * cos(deg2rad(app.ASSIST_VARS.EQ_RAKE)) * slip) / 100;
        %         rvslip = (sin(deg2rad(app.ASSIST_VARS.EQ_RAKE)) * slip) / 100;
        % 
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 5) = rlslip;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 6) = rvslip;
        % 
        %         % 断層の深さを計算
        %         hd = (app.ASSIST_VARS.EQ_WIDTH / 2.0) * sin(deg2rad(app.ASSIST_VARS.EQ_DIP));
        %         if fref == 1
        %             % 端部位置参照
        %             bt = app.ASSIST_VARS.EQ_DEPTH + 2.0 * hd;
        %         else
        %             % 中央位置参照
        %             tp = app.ASSIST_VARS.EQ_DEPTH - hd;
        %             bt = app.ASSIST_VARS.EQ_DEPTH + hd;
        %             if tp < 0.0
        %                 warndlg('Fault top depth above the surface (negative). Change the source depth or fault width.');
        %             end
        %         end
        % 
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 7) = app.ASSIST_VARS.EQ_DIP;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 8) = tp;
        %         app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 9) = bt;
        % 
        %         % 計算結果を他のボタンやGUIコンポーネントで利用可能にする
        %         % 修正必要
        %         set(findobj('Tag','pushbutton_f_add'),'Enable','on');
        %     end
        % 
        %     function onFEAddMapClicked(app, ~)
        %         % 「Add Fault」ボタンが押されたときに実行されるコールバック関数
        %         % 断層の深さ、ポアソン比、ヤング率のデフォルト値を設定
        %         app.INPUT_VARS.CALC_DEPTH = (app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 8) + app.INPUT_VARS.ELEMENT(app.INPUT_VARS.INUM, 9)) / 2.0;
        %         app.INPUT_VARS.POIS = 0.25;
        %         app.INPUT_VARS.YOUNG = 800000;
        % 
        %         % その他のデフォルト値を設定
        %         % KODE = 100;
        %         app.INPUT_VARS.FRIC = 0.4;
        %         app.INPUT_VARS.SIZE = [2;1;10000]; % サイズに関する設定
        % 
        %         x1 = 'header line 1';
        %         x2 = 'header line 2';
        % 
        %         app.INPUT_VARS.HEAD{1} = x1;  % ヘッダー行1
        %         app.INPUT_VARS.HEAD{2} = x2;  % ヘッダー行2
        % 
        %         % 応力テンソルに関する設定
        %         app.INPUT_VARS.R_STRESS = [19.00 -0.01 100.0 0.0;
        %                                     89.99 89.99  30.0 0.0;
        %                                    109.00 -0.01   0.0 0.0];
        % 
        %         % セクションに関する設定
        %         app.INPUT_VARS.SECTION = [-16; -16; 18; 26; 1; 30; 1];
        % 
        %         % fault_overlay関数を呼び出して断層を表示
        %         fault_overlay(app);
        % 
        %         % EQ_LAT、EQ_LONを使用して座標変換を行い、UTM座標系でプロット
        %         a = lonlat2xy(app, [app.ASSIST_VARS.EQ_LON app.ASSIST_VARS.EQ_LAT]);
        %         hold on;
        %         % 緯度経度でプロット
        %         if app.COORD_VARS.ICOORD == 2 && isempty(app.COORD_VARS.LON_GRID) ~= 1
        %             scatter(app.UIAxes, app.ASSIST_VARS.EQ_LON, app.ASSIST_VARS.EQ_LAT, 'filled', 'bo');
        %         % UTM座標系でプロット
        %         else
        %             scatter(app.UIAxes, a(1), a(2),'filled','bo');
        %         end
        % 
        %         % メニューやボタンを有効化
        %         % 修正必要
        %         set(findobj('Tag','menu_grid'),'Enable','On');
        %         set(findobj('Tag','menu_displacement'),'Enable','On');
        %         set(findobj('Tag','menu_strain'),'Enable','On');
        %         set(findobj('Tag','menu_stress'),'Enable','On');
        %         set(findobj('Tag','menu_change_parameters'),'Enable','On');
        %         set(findobj('Tag','edit_all_input_params'),'Enable','on');
        %         set(findobj('Tag','menu_file_save'),'Enable','On');
        %         set(findobj('Tag','menu_file_save_ascii'),'Enable','On');
        %         set(findobj('Tag','menu_map_info'),'Enable','On');
        % 
        %         % INUM = INUM + 1：次の断層IDを設定
        %         % 修正必要
        %         set(findobj('Tag', 'edit_id_number'), 'String', num2str(app.INPUT_VARS.INUM, '%2i'));
        %     end
        % 
        %     function onOKClicked(app, ~)
        %         % OKボタンを押したときの計算とコールバック関数
        %         m = size(app.ASSIST_VARS.EQ_ZFORMAT_DATA, 1);
        %         sec = zeros(m, 1);
        %         eq_datetime = zeros(m, 1);
        %         eq_datetime = datetime(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 3), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 4),...
        %             app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 5), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 8), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 9), sec);
        %         syr = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(2);
        %         smo = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(3);
        %         sdy = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(4);
        %         shr = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(5);
        %         smn = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(6);
        %         mintime = datetime(syr, smo, sdy, shr, smn, 0);
        % 
        %         fyr = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(7);
        %         fmo = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(8);
        %         fdy = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(9);
        %         fhr = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(10);
        %         fmn = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(11);
        %         maxtime = datetime(fyr, fmo, fdy, fhr, fmn, 0);
        %         temp = [app.ASSIST_VARS.EQ_ZFORMAT_DATA eq_datetime];
        % 
        %         %------------------------------------------------------------
        %         % z map format
        %         % 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min,
        %         % 10)strike1, 11)dip1, 12)rake1,13)strike2, 14)dip2, 15)rake2, 16) eq_datanum
        %         %------------------------------------------------------------
        % 
        %         dummyWidth = 0.000999;
        %         minlon = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(12) - dummyWidth;
        %         maxlon = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(16) + dummyWidth;
        %         minlat = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(13) - dummyWidth;
        %         maxlat = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(17) + dummyWidth;
        %         minmag = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(14);
        %         maxmag = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(18);
        %         mindepth = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(15);
        %         maxdepth = app.ASSIST_VARS.EQ_EDIT_ZFORMAT(19);
        % 
        %         % ----- screening process ------------------
        %         sc1 = find(temp(:, 16) >= mintime & temp(:, 16) <= maxtime);     % time sorting
        %         sc2 = temp(sc1, 1) >= minlon & temp(sc1, 1) <= maxlon &...       % catalog areal sorting
        %             temp(sc1, 1) >= app.COORD_VARS.MIN_LON & temp(sc1, 1) <= app.COORD_VARS.MAX_LON;
        %         sc3 = sc1(sc2);
        %         sc4 = temp(sc3, 2) >= minlat & temp(sc3, 2) <= maxlat &...       % study areal sorting
        %             temp(sc3, 2) >= app.COORD_VARS.MIN_LAT & temp(sc3, 2) <= app.COORD_VARS.MAX_LAT;
        %         sc5	= sc3(sc4);
        %         sc6 = temp(sc5, 7) >= mindepth & temp(sc5, 7) <= maxdepth;       % depth range sorting
        %         sc7 = sc5(sc6);
        %         sc8 = temp(sc7, 6) >= minmag & temp(sc7, 6) <= maxmag;           % magnitude range sorting
        %         sc9 = sc7(sc8);
        %         new_cat = temp(sc9, 1:15);
        %         % ------------------------------------------
        % 
        %         try
        %             [mm, nn] = size(new_cat);
        %             app.ASSIST_VARS.EQ_ZFORMAT_DATA = zeros(mm, nn);
        %             app.ASSIST_VARS.EQ_ZFORMAT_DATA = new_cat;
        %         catch
        %             app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
        %         end
        % 
        %     end
        % 
        %     function onCancelClicked(app, popup)
        %         app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
        %         delete(popup); % ポップアップウィンドウを閉じる
        %     end
        % end       

       
        function [outkm, flag] = distance2(~, lat1, lon1, lat2, lon2)
            % =====================================================================
            %                distance2(~, lat1, lon1, lat2, lon2)
            % =====================================================================
            % Inputs:
            %    lat1: Latitude scalar.   Degrees.  +ddd.ddddd  WGS84
            %    lon1: Longitude scalar.  Degrees.  +ddd.ddddd  WGS84
            %    lat2: Latitude scalar.   Degrees.  +ddd.ddddd  WGS84
            %    lon2: Longitude scalar.  Degrees.  +ddd.ddddd  WGS84
            % Outputs:
            %    outkm: distance between two point on a map
            %   flag: 1 OK, 0 different UTM zone
    
            % use a function deg2utm written by Rafael Palacios
            [x1,y1,utmzone1] = deg2utm(lat1, lon1);
            [x2,y2,utmzone2] = deg2utm(lat2, lon2);
            
            if ~strcmp(utmzone1, utmzone2)
                flag = 1;
            else
                flag = 0;
            end
            
            % meter to kilometer
            outkm = sqrt((x1 - x2) ^ 2.0 + (y1 - y2) ^ 2.0) / 1000.0;
        end


        % ==========================================================================================================================================
        %                計算関連の関数
        % ==========================================================================================================================================
        
        function element_condition(app, e90, poisson, young, friction, id)
            % =====================================================================
            %                element_condition(app, e90, poisson, young, friction, id)
            % =====================================================================
            % INPUT:
            % Each fault element (supposed to be directly taken over from ELEMENT)
            % 各断層要素は次のデータを持つ
            %   e90(:,1) xstart (km) 始点X座標
            %   e90(:,2) ystart (km) 始点Y座標
            %   e90(:,3) xfinish (km) 終点X座標
            %   e90(:,4) yfinish (km) 終点Y座標
            %   e90(:,5) right-lat. slip (m) 右横ずれ変位
            %   e90(:,6) reverse slip (m) 逆断層変位
            %   e90(:,7) dip (degree) 傾斜角
            %   e90(:,8) fault top depth (km) 断層上部深さ
            %   e90(:,9) fault bottom depth (km) 断層下部深さ
            %   poisson: Poisson ratio ポアソン比
            %   young: Young modulus ヤング率
            %   friction: friction of coefficient 摩擦係数
            %   id: ID of the element
        
            % ELEMENT を分割し、新しい要素を e9 に格納
            e100 = [e90 double(app.INPUT_VARS.KODE)];
            [e9, nid] = split_element(app, id, e100);
            [m, ~] = size(e9);  % 要素数 m を取得
            temp = app.ASSIST_VARS.N_CELL;      % 元の要素数を保存
            app.ASSIST_VARS.N_CELL = m;         % 分割後の要素数を N_CELL に設定
            app.OKADA_OUTPUT.S_ELEMENT = zeros(m,10);
            app.OKADA_OUTPUT.S_ELEMENT = e9;    % 分割後の要素を保存
        
            % 各種行列を初期化
            app.OKADA_OUTPUT.DC3DE = zeros(m,14,'double');
            app.OKADA_OUTPUT.DC3DE0 = zeros(m,14,'double');
            UX = zeros(m,1,'double');
            UY = zeros(m,1,'double');
            UZ = zeros(m,1,'double');
            UXX = zeros(m,1,'double');
            UYX = zeros(m,1,'double');
            UZX = zeros(m,1,'double');
            UXY = zeros(m,1,'double');
            UYY = zeros(m,1,'double');
            UZY = zeros(m,1,'double');
            UXZ = zeros(m,1,'double');
            UZZ = zeros(m,1,'double');
            IRET = zeros(m,1);
            e_comp = zeros(m,3);
        
            %--------------strike, dip for each element (各要素のストライクとディップを計算)
            a = rad2deg(atan((e9(:, 4) - e9(:, 2)) ./ (e9(:, 3) - e9(:, 1))));
            % 断層長を計算
            f_length = sqrt((e9(:, 3) - e9(:, 1)) .^ 2 + (e9(:, 4) - e9(:, 1)) .^ 2);
            ind1 = e9(:, 1) > e9(:, 3);
            ind2 = e9(:, 1) <= e9(:, 3);
            % ストライクを計算
            e_comp(:, 1) = (270.0 - a) .* ind1 + (90.0 - a) .* ind2;
            % ディップを設定
            e_comp(:, 2) = e9(:, 7);
            % レイクを計算
            ind3 = e9(:, 5) ~= 0.0;
            ind4 = e9(:, 5) == 0.0;
            b = rad2deg(atan(e9(:, 6) ./ e9(:, 5))) .* ind3 + rad2deg(atan(e9(:, 6) ./ 0.000001)) .* ind4;
            ind5 = e9(:, 5) >= 0.0;
            ind6 = e9(:, 5) < 0.0;
            ind7 = e9(:, 6) >= 0.0;
            ind8 = e9(:, 6) < 0.0;
            e_comp(:, 3) = (180.0 - b) .* ind5 .* ind7 + (-180.0 - b) .* ind5 .* ind8 + (-b) .* ind6;
        
            %--------------
            % Each fault element
            %       ELEMENT(:,1) xstart (km)
            %       ELEMENT(:,2) ystart (km)
            %       ELEMENT(:,3) xfinish (km)
            %       ELEMENT(:,4) yfinish (km)
            %       ELEMENT(:,5) right-lat. slip (m)
            %       ELEMENT(:,6) reverse slip (m)
            %       ELEMENT(:,7) dip (degree)
            %       ELEMENT(:,8) fault top depth (km)
            %       ELEMENT(:,9) fault bottom depth (km)
        
            % 断層の中心座標を計算
            e7 = [e9(:,1) e9(:,2) e9(:,3) e9(:,4),e9(:,7) e9(:,8) e9(:,9)];
        
            for k = 1:m
                middle = (e7(k,6) + e7(k,7)) / 2.0;  % 断層の中心深さ
                fc = fault_corners(app, e7(k,1),e7(k,2),e7(k,3),e7(k,4),e7(k,5),e7(k,6), middle);  % 断層の四隅の座標を計算
                e_center(k,1) = (fc(4,1) + fc(3,1)) / 2.0;  % 中心X座標
                e_center(k,2) = (fc(4,2) + fc(3,2)) / 2.0;  % 中心Y座標
                e_center(k,3) = middle;  % 中心深さ
            end
        
            % app.CALC_CONTROL.FUNC_SWITCH が 1 でない場合は Okada モデルを実行
            if app.CALC_CONTROL.FUNC_SWITCH ~= 1
                % ========================================== k loop (start)
                %  this loop is for making 'OKADA_OUTPUT.DC3DE'
                %  loop for receiver points (internal matrix corresponds to a set of sources)
                % Okada の結果を計算
                for k = 1:m
                    if e9(k,5)==0.0 && e9(k,6)==0.0  % スリップがない場合はスキップ
                        continue
                    end
                    depth = (e9(k,8) + e9(k,9)) / 2.0;  % 断層の深さを計算
                    [c1,c2,c3,c4] = coord_conversion(app, e_center(:,1),e_center(:,2),e9(k,1),e9(k,2),...
                                e9(k,3),e9(k,4),e9(k,8),e9(k,9),e9(k,7));
        
                    alpha  =  1.0/(2.0*(1.0-poisson));
                    z  = e_center(:,3) * (-1.0);
                    aa = zeros(m,1,'double') + double(alpha);
                    zz = zeros(m,1,'double') + double(z);
                    dp = zeros(m,1,'double') + double(depth);
                    e7 = zeros(m,1,'double') + double(e9(k,7));
                    e5 = zeros(m,1,'double') - double(e9(k,5)); % 左横ずれは正とする
                    e6 = zeros(m,1,'double') + double(e9(k,6));
                    zr = zeros(m,1,'double');
        
                    % その他の Kode に基づく計算
                    if int16(e9(k,10)) == 200
                        e5 = zeros(m,1,'double') + double(e9(k,5));
                        e6 = zeros(m,1,'double');
                        zr = zeros(m,1,'double') + double(e9(k,6));    
                    elseif int16(e9(k,10)) == 300
                        e5 = zeros(m,1,'double');
                        e6 = zeros(m,1,'double') + double(e9(k,6));
                        zr = zeros(m,1,'double') + double(e9(k,5));   
                    end
        
                    % 座標変換および応力の計算を行う
                    x = zeros(m,1,'double') + double(c1);
                    y = zeros(m,1,'double') + double(c2);
                    al = zeros(m,1,'double') + double(c3);
                    aw = zeros(m,1,'double') + double(c4);
                    if int16(e9(k,10)) == 400
                        aw = zeros(m,1,'double') - double(e9(k,5));
                        e5 = zeros(m,1,'double') + double(e9(k,6));
                        e6 = zeros(m,1,'double');
                        zr = zeros(m,1,'double');    
                    elseif int16(e9(k,10)) == 500
                        aw = zeros(m,1,'double');
                        e5 = zeros(m,1,'double');
                        e6 = zeros(m,1,'double') + double(e9(k,5));
                        zr = zeros(m,1,'double') + double(e9(k,6));   
                    end
        
                    % 応力テンソルの変換と計算を行う
                    a = [aa x y zz dp e7 al al aw aw e5 e6 zr];
                    adj = 0.0001; % to avoid singular point for Kode 400 and Kode 500 (point source)
        
                    % Okada モデルを呼び出して変位場を計算する
                    if int16(e9(k,10)) == 100 | int16(e9(k,10)) == 200 | int16(e9(k,10)) == 300
                        [UX(:,1),UY(:,1),UZ(:,1),UXX(:,1),UYX(:,1),UZX(:,1),UXY(:,1),UYY(:,1),UZY(:,1),...
                        UXZ(:,1),UYZ(:,1),UZZ(:,1),IRET(:,1)]=Okada_DC3D(app, a(:,1),a(:,2),a(:,3),...
                        a(:,4),a(:,5),a(:,6),a(:,7),a(:,8),a(:,9),a(:,10),a(:,11),a(:,12),a(:,13));
                    elseif int16(e9(k,10)) == 400 | int16(e9(k,10)) == 500
                        [UX(:,1),UY(:,1),UZ(:,1),UXX(:,1),UYX(:,1),UZX(:,1),UXY(:,1),UYY(:,1),UZY(:,1),...
                        UXZ(:,1),UYZ(:,1),UZZ(:,1),IRET(:,1)]=Okada_DC3D0(app, a(:,1),a(:,2)+adj,a(:,3),...
                        a(:,4),a(:,5),a(:,6),a(:,10),a(:,11),a(:,12),a(:,13));
                    end
        
                    % cell to matrices
                    % 得られた変位場の座標を計算
                    X = a(:, 2);
                    Y = a(:, 3);
                    Z = a(:, 4);
        
                    %-- Displacement Conversion from Okada's field to Given field -------------
                    % Okada モデルの変位場を入力座標系に変換
                    sw = sqrt((e9(k, 4) - e9(k, 2)) ^ 2 + (e9(k, 3) - e9(k, 1)) ^ 2);
                    sina = (e9(k, 4) - e9(k, 2)) / double(sw);
                    cosa = (e9(k, 3) - e9(k, 1)) / double(sw);
                    UXG = UX * cosa - UY * sina;  % X方向の変位
                    UYG = UX * sina + UY * cosa;  % Y方向の変位
                    UZG = UZ;                     % Z方向の変位
        
                    % C-- Strain to Stress for the normal component -----------------------------
                    %----- 応力テンソルの計算 -----
                    sk = young / (1.0 + poisson);         % 応力スケール係数
                    gk = poisson / (1.0 - 2.0 * poisson); % 応力の係数
                    vol = UXX + UYY + UZZ;                % 体積変化
                    % caution! strain dimension is from x,y,z coordinate (should be /1000)
                    sxx = sk * (gk * vol + UXX) * 0.001;  % X方向の応力
                    syy = sk * (gk * vol + UYY) * 0.001;  % Y方向の応力
                    szz = sk * (gk * vol + UZZ) * 0.001;  % Z方向の応力
        
                    sxy = (young / (2.0 * (1.0 + poisson))) * (UXY + UYX) * 0.001; % XY方向の応力
                    sxz = (young / (2.0 * (1.0 + poisson))) * (UXZ + UZX) * 0.001; % XZ方向の応力
                    syz = (young / (2.0 * (1.0 + poisson))) * (UYZ + UZY) * 0.001; % YZ方向の応力
        
                    % テンソル行列の変換
                    s0 = [reshape(sxx, 1, m); reshape(syy, 1, m); reshape(szz, 1, m); ...
                          reshape(syz, 1, m); reshape(sxz, 1, m); reshape(sxy, 1, m)];
        
                    %-- Strain Conversion from Okada's field to Given field -------------
                    s1 = tensor_trans(app, sina, cosa, s0, m);  % テンソル変換
                    SXX = reshape(s1(1, :), m, 1);  % X方向の最終応力
                    SYY = reshape(s1(2, :), m, 1);  % Y方向の最終応力
                    SZZ = reshape(s1(3, :), m, 1);  % Z方向の最終応力
                    SYZ = reshape(s1(4, :), m, 1);  % YZ方向の最終応力
                    SXZ = reshape(s1(5, :), m, 1);  % XZ方向の最終応力
                    SXY = reshape(s1(6, :), m, 1);  % XY方向の最終応力
        
                    % 初回ループの結果を格納
                    if k == 1
                        app.OKADA_OUTPUT.DC3DE0 = horzcat(e_center(:,1:2),X,Y,Z,UXG,UYG,UZG,SXX,SYY,SZZ,SYZ,SXZ,SXY);
                    else
                        app.OKADA_OUTPUT.DC3DE0 = horzcat(zeros(m, 1), zeros(m, 1), zeros(m, 1), zeros(m, 1), zeros(m, 1), ...
                                UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                    end
        
                    app.OKADA_OUTPUT.DC3DE = app.OKADA_OUTPUT.DC3DE + app.OKADA_OUTPUT.DC3DE0;  % 結果を合計
                end
                % ========================================== k loop (end)
        
                % Coulomb 応力の計算
                s9 = reshape(app.OKADA_OUTPUT.DC3DE(:, 9), 1, m);
                s10 = reshape(app.OKADA_OUTPUT.DC3DE(:, 10), 1, m);
                s11 = reshape(app.OKADA_OUTPUT.DC3DE(:, 11), 1, m);
                s12 = reshape(app.OKADA_OUTPUT.DC3DE(:, 12), 1, m);
                s13 = reshape(app.OKADA_OUTPUT.DC3DE(:, 13), 1, m);
                s14 = reshape(app.OKADA_OUTPUT.DC3DE(:, 14), 1, m);
                ss = [s9; s10; s11; s12; s13; s14];
        
                % Coulomb 応力の最大値とその方向を計算
                coulomb_max = ones(m, 1, 'double') * (-10000);
                coulomb_max_dir = zeros(m, 1, 'double');
                c1 = zeros(m, 1, 'double') + e_comp(:, 1);  % ストライク
                c2 = zeros(m, 1, 'double') + e_comp(:, 2);  % ディップ
                c4 = zeros(m, 1, 'double') + friction;     % 摩擦係数
        
                % right-lat. calc.
                % 右横ずれの Coulomb 応力を計算
                c3 = zeros(m, 1, 'double') + 180.0;
                [shear_right, app.ASSIST_VARS.EC_NORMAL, app.ASSIST_VARS.COULOMB_RIGHT] = calc_coulomb(app, c1, c2, c3, c4, ss);
        
                % reverse slip. calc.
                % 逆断層の Coulomb 応力を計算
                c3 = zeros(m,1,'double') + 90.0;
                [shear_up, app.ASSIST_VARS.EC_NORMAL, app.ASSIST_VARS.COULOMB_UP] = calc_coulomb(app, c1, c2, c3, c4, ss);
        
                % preferred slip. calc.
                % 指定された滑り角の Coulomb 応力を計算
                if isempty(app.ASSIST_VARS.EC_RAKE)
                    app.ASSIST_VARS.EC_RAKE = 140.0;  % デフォルトの滑り角
                end
        
                ec_calc_rake = zeros(m, 1, 'double') + app.ASSIST_VARS.EC_RAKE;
                c3 = zeros(m, 1, 'double') + app.ASSIST_VARS.EC_RAKE;
                [~, app.ASSIST_VARS.EC_NORMAL, app.ASSIST_VARS.COULOMB_PREF] = calc_coulomb(app, c1, c2, c3, c4, ss);
        
                % 個別の滑り角が指定されている場合、その滑り角で計算
                if isempty(app.CALC_CONTROL.IND_RAKE)
                    each_rake = zeros(m, 1, 'double') + app.ASSIST_VARS.EC_RAKE;
                    disp('Warning: No individual rake is assgined in this file.');
                    showMessage(app, 'Warning: No individual rake is assgined in this file.', false);
                else
                    each_rake = zeros(m, 1, 'double') + app.CALC_CONTROL.IND_RAKE;
                end
                [~, app.ASSIST_VARS.EC_NORMAL, app.ASSIST_VARS.COULOMB_RAKE] = calc_coulomb(app, c1, c2, each_rake, c4, ss);
        
                %====================================
                % SYSTEM_VARS.IMAXSHEAR = 1: Search
                % SYSTEM_VARS.IMAXSHEAR = 2 or others: skip the max shear direction to speed up
                % Coulomb 応力の最大方向を探索
                if app.SYSTEM_VARS.IMAXSHEAR == 1
                    deg_inc = 1.0;
                    nsearch = round(360.0 / deg_inc);
                    for k = 1:nsearch
                        crake = -180.0 + k * deg_inc;
                        c3 = zeros(m, 1, 'double') + crake;
                        [~, ~, dummy3] = calc_coulomb(app, c1, c2, c3, c4, ss);
                        for l = 1:m
                            if dummy3(l) > coulomb_max(l)
                                coulomb_max(l) = dummy3(l);
                                coulomb_max_dir(l) = crake;
                            end
                        end
                    end
                else
                    for l = 1:m
                        coulomb_max(l) = NaN;
                        coulomb_max_dir(l) = NaN;
                    end
                end
        
                % Coulomb 応力の計算結果をまとめる
                b = [double(nid(:, 1)) app.OKADA_OUTPUT.DC3DE(:, 1) app.OKADA_OUTPUT.DC3DE(:, 2) -app.OKADA_OUTPUT.DC3DE(:, 5) f_length(:) e_comp(:,1) e_comp(:, 2)...
                    e9(:, 5) e9(:, 6) shear_right(:) shear_up(:) app.ASSIST_VARS.EC_NORMAL(:) app.ASSIST_VARS.COULOMB_RIGHT(:) app.ASSIST_VARS.COULOMB_UP(:)...
                    coulomb_max_dir(:) coulomb_max(:) ec_calc_rake(:) app.ASSIST_VARS.COULOMB_PREF(:) each_rake(:) app.ASSIST_VARS.COULOMB_RAKE(:)];
        
                format long;
        
                % 出力ファイルに結果を書き込む
                header1 = 'Fault,X-center,Y-center,Z-center,length,strike,dip,lat-slip,dip-slip,sig-right,sig_reverse,normal,coul-right,coul-reverse,opt-rake,opt-coul,spec-rake,spec-coul,element-rake,el-rake-coul\n';
                header2 = '#,(km),(km),(km),(km),(km),(deg),(m),(m),(bar),(bar),(bar),(bar),(bar),(deg),(bar),(deg),(bar),(deg),(bar)\n';
        
                fid = fopen('Element_conditions.csv', 'wt');
                cm = ',';
                fprintf(fid, header1);
                fprintf(fid, header2);
        
                % 各要素の結果をファイルに書き込むループ
                for m = 1:size(b, 1)
                    fprintf(fid, '%5i %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.3f %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.6f %c %15.3f %c %15.6f %c %15.3f %c %15.6f %c %15.3f %c %15.6f %s',...
                        b(m, 1), cm, b(m, 2), cm, b(m, 3), cm, b(m, 4), cm, b(m, 5), cm, b(m, 6), cm, b(m, 7), ...
                        cm, b(m, 8), cm, b(m, 9), cm, b(m, 10), cm, b(m, 11), cm, b(m, 12), cm, ...
                        b(m, 13), cm, b(m, 14), cm, b(m, 15), cm, b(m, 16), cm, b(m, 17), cm, ...
                        b(m, 18), cm, b(m, 19), cm, b(m, 20), cm);
                        
                    % コメントがある場合、それをファイルに書き込む
                    comment = app.INPUT_VARS.FCOMMENT(m);
                    if iscell(comment)
                        fprintf(fid, '%s\n', comment{1});  % セル配列FCOMMENT → 中身commentを取り出して書き込む
                    elseif ischar(comment) || isstring(comment)
                        fprintf(fid, '%s\n', comment);     % 文字列ならそのまま書き込む
                    else
                        warning('FCOMMENT has unexpected format.');  % 万一に備えて
                    end
                end
        
                fclose(fid); % ファイルを閉じる
                cd (app.SYSTEM_VARS.HOME_DIR); % 作業ディレクトリを元に戻す
                coulomb_in_3D(app); % Coulomb 応力の3次元描画を行う関数を呼び出す
            end
        
            app.ASSIST_VARS.N_CELL = temp; % 元の要素数を復元する
        end
        
        % ネスト関数------------------------------------------------------------
        function [s_element, new_id] = split_element(~, id, e9)
            % この関数は、断層要素をより小さなセグメントに分割する。
            % INPUT:
            %   id   - Vector containing the number of splits for each element.
            %   e9   - Matrix containing the fault element parameters:
            %          [xstart, ystart, xfinish, yfinish, lat-slip, reverse-slip, dip, top-depth, bottom-depth, KODE]
            % OUTPUT:
            %   s_element - Matrix containing the split elements.
            %   new_id    - Vector with the ID of each split element.
        
            m = length(id);
            s_element = zeros(m, 10);
            new_id = ones(m, 1);
            nct = 0;
            for k = 1:m
                n = int16(id(k));
                xinc = (e9(k, 3) - e9(k, 1)) / double(n);
                yinc = (e9(k, 4) - e9(k, 2)) / double(n);
                for l = 1:n
                   nct = nct + 1;
                   new_id(nct, 1) = k;
                   s_element(nct, 1) = e9(k, 1) + double(l - 1) * xinc; % xstart
                   s_element(nct, 3) = e9(k, 1) + double(l) * xinc;     % xfinish
                   s_element(nct, 2) = e9(k, 2) + double(l - 1) * yinc; % ystart
                   s_element(nct, 4) = e9(k, 2) + double(l) * yinc;     % yfinish
                   s_element(nct, 5) = e9(k, 5);                        % right-lat. slip (m)
                   s_element(nct, 6) = e9(k, 6);                        % reverse slip (m)
                   s_element(nct, 7) = e9(k, 7);                        % dip (degree)
                   s_element(nct, 8) = e9(k, 8);                        % fault top depth (km)
                   s_element(nct, 9) = e9(k, 9);                        % fault bottom depth (km)
                   s_element(nct, 10) = e9(k, 10);                      % KODE
                end
            end
        end
        
        function coulomb_in_3D(app)
            % クーロン応力転移を3次元でプロットするための代替手法を提供する関数。
            % 現在はスクリプトとして始めているが、最終的には関数に適応させる予定。
                
            % INPUT_VARS.ELEMENT変数から入力データを読み取る
            xstart = app.INPUT_VARS.ELEMENT(:, 1);  % 開始X座標
            ystart = app.INPUT_VARS.ELEMENT(:, 2);  % 開始Y座標
            xend = app.INPUT_VARS.ELEMENT(:, 3);    % 終了X座標
            yend = app.INPUT_VARS.ELEMENT(:, 4);    % 終了Y座標
            dip = app.INPUT_VARS.ELEMENT(:, 7);     % 傾斜角
            top = app.INPUT_VARS.ELEMENT(:, 8);     % 上部深度
            bottom = app.INPUT_VARS.ELEMENT(:, 9);  % 下部深度
                
            % 各要素についての処理
            for j = 1:size(app.INPUT_VARS.ELEMENT, 1)
                % セグメントのストライク（方位角）を計算
                if xstart(j) <= xend(j)
                    el_strike(j) = 90 + atand((ystart(j) - yend(j)) / (xend(j) - xstart(j)));
                elseif xstart(j) > xend(j)
                    el_strike(j) = 270 + atand((ystart(j) - yend(j)) / (xend(j) - xstart(j)));
                else
                    disp('Error in strike calculations.')
                    showMessage(app, 'Error in strike calculations.', false);
                end
                
                % ボックスの下部点を計算
                projdir = el_strike(j) + 90;
                if projdir > 360
                    projdir = projdir - 360;
                end
                
                % 表面からの距離を計算
                del_surf = (bottom(j) - top(j)) / tand(dip(j));
                x(1, j) = xstart(j);
                x(2, j) = xend(j);
                y(1, j) = ystart(j);
                y(2, j) = yend(j);
                
                % 投影方向に応じてボックスの座標を計算
                if projdir < 90
                    dx = del_surf * sind(projdir);
                    dy = del_surf * cosd(projdir);
                    x(4, j) = xstart(j) + dx;
                    x(3, j) = xend(j) + dx;
                    y(4, j) = ystart(j) + dy;
                    y(3, j) = yend(j) + dy;
                elseif projdir < 180 && projdir >= 90
                    dx = del_surf * sind(180 - projdir);
                    dy = del_surf * cosd(180 - projdir);
                    x(4, j) = xstart(j) + dx;
                    x(3, j) = xend(j) + dx;
                    y(4, j) = ystart(j) - dy;
                    y(3, j) = yend(j) - dy;
                elseif projdir < 270 && projdir >= 180 
                    dx = del_surf * sind(projdir - 180);
                    dy = del_surf * cosd(projdir - 180);
                    x(4, j) = xstart(j) - dx;
                    x(3, j) = xend(j) - dx;
                    y(4, j) = ystart(j) - dy;
                    y(3, j) = yend(j) - dy;
                elseif projdir < 360 && projdir >= 270 
                    dy = del_surf * sind(projdir - 270);
                    dx = del_surf * cosd(projdir - 270);
                    x(4, j) = xstart(j) - dx;
                    x(3, j) = xend(j) - dx;
                    y(4, j) = ystart(j) + dy;
                    y(3, j) = yend(j) + dy;
                else
                    disp('Error: box coordinates cannot be calculated.')
                    showMessage(app, 'Error: box coordinates cannot be calculated.', false);
                end
                    
                % 深度情報の設定
                z(1, j) = -top(j);
                z(2, j) = -top(j);
                z(3, j) = -bottom(j);
                z(4, j) = -bottom(j);
            end
                
            % 3Dプロットの作成（app.UIAxes上にプロット）
            axes(app.UIAxes);  % app.UIAxesを使用
            cla(app.UIAxes);    % 既存のプロットをクリア
        
            view(app.UIAxes, 3);                                 % 3Dビューの設定
            colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);    % カラーマップの設定
            cb = colorbar(app.UIAxes, 'southoutside');           % カラーバーの表示
            title(cb, 'Transferred stress (bars)');              % カラーバーのタイトル設定
            clim(app.UIAxes, [-0.1 0.1]);                        % カラースケールの範囲設定
            xlabel(app.UIAxes, 'UTM x');                         % X軸ラベル
            ylabel(app.UIAxes, 'UTM y');                         % Y軸ラベル
            zlabel(app.UIAxes, 'Depth (km)');                    % Z軸ラベル
            axis(app.UIAxes, 'equal');                           % 軸のスケールを均等に設定
            hold(app.UIAxes, 'on');
        
            % グリッド線の描画
            for i = app.INPUT_VARS.GRID(1, 1):app.INPUT_VARS.GRID(5, 1):app.INPUT_VARS.GRID(3, 1)
                line(app.UIAxes, [i i], [app.INPUT_VARS.GRID(2, 1) app.INPUT_VARS.GRID(4, 1)], 'Color', [0.5 0.5 0.5]);  % X軸のグリッド線
            end
            for i = app.INPUT_VARS.GRID(2, 1):app.INPUT_VARS.GRID(6, 1):app.INPUT_VARS.GRID(4, 1)
                line(app.UIAxes, [app.INPUT_VARS.GRID(1, 1) app.INPUT_VARS.GRID(3, 1)], [i i], 'Color', [0.5 0.5 0.5]);  % Y軸のグリッド線
            end
        end

        
        function calc_element(app)
            % =====================================================================
            %                calc_element(app)
            % =====================================================================
            % calc_element: 要素のストライク角、傾斜角、すべり角、深度を計算し、グリッドや座標に関するデータを計算・保持する関数
            app.ASSIST_VARS.ELE_STRIKE = [];
            app.ASSIST_VARS.AV_STRIKE = [];
            app.ASSIST_VARS.AV_DIP = [];
            app.ASSIST_VARS.AV_RAKE = [];
        
            % グリッドの位置を計算し、それらを他の関数で使用できるように保持する
            xstart = app.INPUT_VARS.GRID(1, 1);
            ystart = app.INPUT_VARS.GRID(2, 1);
            xfinish = app.INPUT_VARS.GRID(3, 1);
            yfinish = app.INPUT_VARS.GRID(4, 1);
            xinc = app.INPUT_VARS.GRID(5, 1);
            yinc = app.INPUT_VARS.GRID(6, 1);
            nxinc = int32((xfinish - xstart) / xinc + 1);
            nyinc = int32((yfinish - ystart) / yinc + 1);
            app.COORD_VARS.XGRID = double(xstart) + (double(1:1:nxinc) - 1.0) * double(xinc);
            app.COORD_VARS.YGRID = double(ystart) + (double(1:1:nyinc) - 1.0) * double(yinc);
        
            %===== 地図情報が存在する場合の処理
            if ~isempty(app.COORD_VARS.MIN_LON) && ~isempty(app.COORD_VARS.MAX_LON)
                if ~isempty(app.COORD_VARS.MIN_LAT) && ~isempty(app.COORD_VARS.MAX_LAT)
                    if ~isempty(app.COORD_VARS.ZERO_LON) && ~isempty(app.COORD_VARS.ZERO_LAT)
                        % 緯度経度のグリッド情報を計算
                        xstart = double(app.COORD_VARS.MIN_LON);
                        ystart = double(app.COORD_VARS.MIN_LAT);
                        xfinish = double(app.COORD_VARS.MAX_LON);
                        yfinish = double(app.COORD_VARS.MAX_LAT);
                        xinc = (xfinish - xstart) / double(nxinc - 1);
                        yinc = (yfinish - ystart) / double(nyinc - 1);
                        app.COORD_VARS.LON_GRID = double(xstart) + (double(1:1:nxinc) - 1.0) * double(xinc);
                        app.COORD_VARS.LAT_GRID = double(ystart) + (double(1:1:nyinc) - 1.0) * double(yinc);
                    end
                end
            end
            %===== 地図情報が存在する場合の処理
        
            % 要素データが存在しない場合は処理を終了
            if isempty(app.INPUT_VARS.NUM)
                return;
            end
        
            % 各要素のストライク角（断層の走向角）を計算
            for n = 1:app.INPUT_VARS.NUM
                % ELEMENT: xs, ys, xf, yf, latslip, dipslip, dip, top, bottom
                xs = app.INPUT_VARS.ELEMENT(n, 1);
                ys = app.INPUT_VARS.ELEMENT(n, 2);
                xf = app.INPUT_VARS.ELEMENT(n, 3);
                yf = app.INPUT_VARS.ELEMENT(n, 4);
                % ストライク角を計算
                a = rad2deg(atan((yf - ys) / (xf - xs)));  
                if xs > xf
                    app.ASSIST_VARS.ELE_STRIKE(n) = 270.0 - a;  % ストライク角を調整
                else
                    app.ASSIST_VARS.ELE_STRIKE(n) = 90.0 - a;  % ストライク角を調整
                end
            end
        
            % 平均ストライク角、傾斜角、すべり角、深度を計算
            app.ASSIST_VARS.AV_STRIKE = sum(app.ASSIST_VARS.ELE_STRIKE) / double(app.INPUT_VARS.NUM);
            app.ASSIST_VARS.AV_DIP = sum(app.INPUT_VARS.ELEMENT(:, 7)) / double(app.INPUT_VARS.NUM);
            av_lat_slip = sum(app.INPUT_VARS.ELEMENT(:, 5)) / double(app.INPUT_VARS.NUM);
            av_dip_slip = sum(app.INPUT_VARS.ELEMENT(:, 6)) / double(app.INPUT_VARS.NUM);
            app.ASSIST_VARS.AV_DEPTH = (sum(app.INPUT_VARS.ELEMENT(:, 8)) + sum(app.INPUT_VARS.ELEMENT(:, 9))) / (2 * double(app.INPUT_VARS.NUM));
        
            % 平均すべり角の計算（ゼロ除算を回避）
            if av_lat_slip == 0.0
                % ゼロ除算を避けるために小さい値を設定
                av_lat_slip = 0.000001;
            end
        
            b = rad2deg(atan(av_dip_slip / av_lat_slip));
            if av_lat_slip >= 0.0
                if av_dip_slip >= 0.0
                    app.ASSIST_VARS.AV_RAKE = 180.0 - b;
                else
                    app.ASSIST_VARS.AV_RAKE = -180.0 - b;
                end
            else
                app.ASSIST_VARS.AV_RAKE = -b;
            end
        
            % マップ座標のパラメータを取得するためのダミー計算
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % XY座標を緯度経度に変換
                xy2lonlat(app, [app.INPUT_VARS.GRID(1, 1), app.INPUT_VARS.GRID(2, 1)]);
            end
            % IACTフラグを再計算モードに設定
            app.CALC_CONTROL.IACT = 0;
        end

        
        function seis_moment(app)
            % =====================================================================
            %                seis_moment(app)
            % =====================================================================
            % 地震モーメントとモーメントマグニチュードを計算する関数
            % KODEが100であることを確認（100でない場合はモーメントを計算しない）
            check = max(app.INPUT_VARS.KODE);
            if check == 100
                amo = 0.0; % 総モーメントを初期化
        
                % 要素ごとの地震モーメントを計算
                for k = 1:app.INPUT_VARS.NUM
                    % 剛性率を計算
                    shearmod = app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS));
        
                    % 断層長を計算
                    flength = sqrt((app.INPUT_VARS.ELEMENT(k, 1) - app.INPUT_VARS.ELEMENT(k, 3)) ^ 2 + ...
                                   (app.INPUT_VARS.ELEMENT(k, 2) - app.INPUT_VARS.ELEMENT(k, 4)) ^ 2);
        
                    % 断層の上下端の深さから断層高さを計算
                    hfault = app.INPUT_VARS.ELEMENT(k, 9) - app.INPUT_VARS.ELEMENT(k, 8);
        
                    % 傾斜角から断層の幅を計算
                    wfault = hfault / sin(deg2rad(app.INPUT_VARS.ELEMENT(k, 7)));
        
                    % すべり量を計算
                    slip = sqrt(app.INPUT_VARS.ELEMENT(k, 5) ^ 2.0 + app.INPUT_VARS.ELEMENT(k, 6) ^ 2.0);
        
                    % 地震モーメントを計算
                    smo = shearmod * flength * wfault * slip * 1.0e+18;  % dyne-cm 単位
        
                    % 総モーメントに加算
                    amo = amo + smo;
                end
        
                % モーメントマグニチュードを計算
                mw = (2/3) * log10(amo) - 10.7;
        
                % 計算結果を表示
                disp(['   Total seismic moment = ' num2str(amo, '%6.2e') ' dyne cm (Mw = ', num2str(mw,'%4.2f') ')']);
                showMessage(app, ['Total seismic moment = ' num2str(amo, '%6.2e') ' dyne cm (Mw = ', num2str(mw,'%4.2f') ')'], false);
            else
                disp('Moment calculations are possible only when KODE = 100.');
                disp('モーメント計算は KODE = 100 の場合のみ可能です。');
                showMessage(app, 'Moment calculations are possible only when KODE = 100.', false);
            end
        end

        
        function [latslip, dipslip] = rake2comp(~, rake, netslip)
            % =====================================================================
            %                comp2rake(~, latslip, dipslip)
            % =====================================================================
            % to convert lateral slip and dip slip column to rake and net slip columns each parameter is one column   
            %     c1 = netslip == 0; c2 = netslip ~= 0;
            %     adjslip = c1 .* 0.1 + c2 .* netslip; % add 0.1m slip for non-slip patch temporally
            % % right-lat. slip
            %     latslip = adjslip .* (-1.0) .* cos(deg2rad(rake));
            % % dip slip
            % 	dipslip = adjslip .* sin(deg2rad(rake));
                
            % right-lat. slip
            latslip = netslip .* (-1.0) .* cos(deg2rad(rake));
            % dip slip
	        dipslip = netslip .* sin(deg2rad(rake));
        end
        
        
        function [rake, netslip] = comp2rake(~, latslip, dipslip)
            % =====================================================================
            %                comp2rake(~, latslip, dipslip)
            % =====================================================================
            % to convert lateral slip and dip slip column to rake and net slip columns each parameter is one column
            % 横すべり（lateral slip）と傾斜すべり（dip slip）の列を、レーキ（rake）とnet-slipの列に変換する割合。各パラメータは1つの列である。

            n = length(latslip);
            rake = zeros(n, 1);
            netslip = zeros(n, 1);
            
            % 総ずれ量を計算 - ピタゴラスの定理を使用
            netslip = sqrt(latslip .^ 2.0 + dipslip .^ 2.0);
            % 横ずれの方向を条件分岐で計算
            % 横ずれが0以上（右横ずれ）の要素に対して 1（true）を返す
            c1 = latslip >= 0.0;
            % 横ずれが負（左横ずれ）の要素に対して 1 を返す
            c2 = latslip < 0.0;
            rake = c1 .* (180.0 - rad2deg(atan(dipslip ./ latslip))) + c2 .* (-1.0) .* rad2deg(atan(dipslip ./ latslip));
            
            if rake > 180
                rake = rake - 360.0;
            end
        end

        
        function grid_drawing(app)
            % =====================================================================
            %                grid_drawing(app)
            % =====================================================================
            % グリッドメッシュを描画し、メニューバーやツールバーの設定も行う
            % グリッド範囲の設定
            xstart = app.INPUT_VARS.GRID(1, 1);
            ystart = app.INPUT_VARS.GRID(2, 1);
            xfinish = app.INPUT_VARS.GRID(3, 1);
            yfinish = app.INPUT_VARS.GRID(4, 1);
        
            % 座標系が緯度経度の場合、緯度経度に変換
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a = xy2lonlat(app, [xstart, ystart]);
                xstart = a(1);
                ystart = a(2);
                a = xy2lonlat(app, [xfinish, yfinish]);
                xfinish = a(1);
                yfinish = a(2);
            else
                app.COORD_VARS.XY_RATIO = 1;  % xy座標の場合、比率は1
            end
        
            % メインウィンドウのUIAxesに描画
            axes(app.UIAxes);
            hold(app.UIAxes, 'off'); % 既存の描画をクリア
        
            % グリッドの描画
            app.ASSIST_VARS.A_GRID = plot(app.UIAxes, ...
                [xstart, xfinish, xfinish, xstart, xstart],...
                [ystart, ystart, yfinish, yfinish, ystart]);
        
            % 軸の設定
            set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO, 1, 1], ...
                'Color', [0.9, 0.9, 0.9], ...
                'PlotBoxAspectRatio', [1, 1, 1], ...
                'XLim', [xstart, xfinish], ...
                'YLim', [ystart, yfinish]);

            hold(app.UIAxes, 'on');
            % XとYのラベルを設定
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % lonlat座標
                xlabel(app.UIAxes, 'Longitude (degree)', 'FontSize', 14);
                ylabel(app.UIAxes, 'Latitude  (degree)', 'FontSize', 14);
            else
                % cartesian座標
                xlabel(app.UIAxes, 'X (km)', 'FontSize', 14);
                ylabel(app.UIAxes, 'Y (km)', 'FontSize', 14);
            end
        
            % if GRID line width is 0.0, skip the following block.
            % グリッド描画の条件
            if app.SYSTEM_VARS.PREF(3, 4) ~= 0.0
                % X軸グリッド描画
                for m = 1:length(app.COORD_VARS.XGRID)
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        % lat/lon coordinata (ICOORD == 2)
                        a = xy2lonlat(app, [app.COORD_VARS.XGRID(m), ystart]); % ystart is dummy
                        gridln1 = plot(app.UIAxes, [a(1), a(1)], [ystart, yfinish]);
                    else
                        % cartesian座標
                        % x/y coordinate (ICOORD == 1)
                        gridln1 = plot(app.UIAxes, [app.COORD_VARS.XGRID(m), app.COORD_VARS.XGRID(m)], [ystart, yfinish]);
                    end
                    gridln1.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                    gridln1.Color = app.SYSTEM_VARS.PREF(3, 1:3);
                end
        
                % Y軸グリッド描画
                for n = 1:length(app.COORD_VARS.YGRID)
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        a = xy2lonlat(app, [xstart, app.COORD_VARS.YGRID(n)]); % xstart is dummy
                        gridln2 = plot(app.UIAxes, [xstart, xfinish], [a(2), a(2)]);
                    else
                        % cartesian座標
                        gridln2 = plot(app.UIAxes, [xstart, xfinish], [app.COORD_VARS.YGRID(n), app.COORD_VARS.YGRID(n)]);
                    end
                    gridln2.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                    gridln2.Color = app.SYSTEM_VARS.PREF(3, 1:3);
                end
            end
        
            % ↓ここが繰り返される理由が不明
            % hold(app.UIAxes, 'on');
            % app.ASSIST_VARS.A_GRID = plot(app.UIAxes, ...
            %     [xstart, xfinish, xfinish, xstart, xstart],...
            %     [ystart, ystart, yfinish, yfinish, ystart]);
            % 
            % set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO, 1, 1],...
            %     'Color', [0.9, 0.9, 0.9], ...
            %     'PlotBoxAspectRatio', [1, 1, 1],...
            %     'XLim', [xstart, xfinish],...
            %     'YLim', [ystart, yfinish]);
            % 
            % % 軸のラベルを更新
            % if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
            %     % lonlat座標
            %     xlabel(app.UIAxes, 'Longitude (degree)', 'FontSize', 14);
            %     ylabel(app.UIAxes, 'Latitude (degree)', 'FontSize', 14);
            % else
            %     % cartesian座標
            %     xlabel(app.UIAxes, 'X (km)', 'FontSize', 14);
            %     ylabel(app.UIAxes, 'Y (km)', 'FontSize', 14);
            % end

            % 座標切り替えボタンを有効化し、入力されているファイルの座標にスイッチを設定
            app.CoordinatesSwitch.Enable = 'on';
            if app.COORD_VARS.ICOORD == 1
                app.CoordinatesSwitch.Value = 'XYZ';
            else
                app.CoordinatesSwitch.Value = 'LonLat';
            end
        
            % データとファイル名のスタンプを追加
            add_record_stamp(app, xstart, xfinish, ystart, yfinish);
            hold(app.UIAxes, 'off');
        end

        %------------補助関数---------------
        % データとファイル名のスタンプを追加する関数
        function add_record_stamp(app, xstart, xfinish, ystart, yfinish)
            % データとファイル名のスタンプを追加
            hold(app.UIAxes, 'on');
            % スタンプの位置を計算
            r = (yfinish - ystart) / (xfinish - xstart);
            if r > 1
                r = 1;
            end
            % x = xstart + ((xfinish - xstart) / (10.0 / r));
            x = xstart;
            y = ystart - ((yfinish - ystart) / 10.0) / r;
            lsp = ((xfinish - xstart) + (yfinish - ystart)) / 75.0;

            % ===============================================================
            % DEPTH_RANGE_TYPE に応じて Depth 情報をセット
            % ===============================================================
            if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
                depthType = 0;
                depthMin = app.INPUT_VARS.CALC_DEPTH;
                depthMax = app.INPUT_VARS.CALC_DEPTH;
            else
                depthType = app.CALC_CONTROL.DEPTH_RANGE_TYPE;
                depthMin = app.ASSIST_VARS.CALC_DEPTH_RANGE(1);
                depthMax = app.ASSIST_VARS.CALC_DEPTH_RANGE(end);
            end
        
            % ===============================================================
            % 選択している関数のボタンに応じて FunctionType 情報をセット
            % ===============================================================
            % 現在選択されているボタンを取得
            selectedButton = app.FunctionButtonGroup.SelectedObject;
            % デフォルト値
            funcType = 0;
            % ボタンに応じて FunctionType を決定
            if selectedButton == app.mapview2DButton
                % app.CALC_CONTROL.FUNC_SWITCH = 1
                funcType = 1;
            elseif selectedButton == app.view3DButton
                % app.CALC_CONTROL.FUNC_SWITCH = 1
                funcType = 2;
            elseif selectedButton == app.HDvectorsButton
                % app.CALC_CONTROL.FUNC_SWITCH = 2
                funcType = 3;
            elseif selectedButton == app.HDwireframeButton
                % app.CALC_CONTROL.FUNC_SWITCH = 3
                funcType = 4;
            elseif selectedButton == app.VDButton
                % app.CALC_CONTROL.FUNC_SWITCH = 4
                funcType = 5;
            elseif selectedButton == app.image3DdrapeButton
                % app.CALC_CONTROL.FUNC_SWITCH = 5
                funcType = 6;
            elseif selectedButton == app.image3DwireframedsurfaceButton
                % app.CALC_CONTROL.FUNC_SWITCH = 5.5
                funcType = 7;
            elseif selectedButton == app.vectors3DButton
                % app.CALC_CONTROL.FUNC_SWITCH = 5.7
                funcType = 8;
            elseif selectedButton == app.exxstrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 1
                funcType = 9;
            elseif selectedButton == app.eyystrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 2
                funcType = 10;
            elseif selectedButton == app.ezzstrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 3
                funcType = 11;
            elseif selectedButton == app.exystrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 4
                funcType = 12;
            elseif selectedButton == app.exzstrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 5
                funcType = 13;
            elseif selectedButton == app.eyzstrainButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 6
                funcType = 14;
            elseif selectedButton == app.DilatationButton
                % app.CALC_CONTROL.FUNC_SWITCH = 6
                % app.CALC_CONTROL.STRAIN_SWITCH = 7
                funcType = 15;
            elseif selectedButton == app.ShearstresschangeButton
                % app.CALC_CONTROL.FUNC_SWITCH = 7
                funcType = 16;
            elseif selectedButton == app.UnclampingstresschangeButton
                % app.CALC_CONTROL.FUNC_SWITCH = 8
                funcType = 17;
            elseif selectedButton == app.CoulombstresschangeButton
                % app.CALC_CONTROL.FUNC_SWITCH = 9
                funcType = 18;
            elseif selectedButton == app.CalcStFaultsButton
                % app.CALC_CONTROL.FUNC_SWITCH = 10 
                % ただし、描画の都合上処理の最初で1にして、その後10にする
                funcType = 19;
            elseif selectedButton == app.CalcStPointButton
                % app.CALC_CONTROL.FUNC_SWITCH = 1 ???
                funcType = 20;
            elseif selectedButton == app.CalcStNodalplanesButton
                % app.CALC_CONTROL.FUNC_SWITCH = 11
                funcType = 21;
            end

            % ===============================================================
            % 構造体を構成して record_stamp に渡す
            % ===============================================================
            partialOpts = struct( ...
                'FileName', app.SYSTEM_VARS.INPUT_FILE, ...
                'LineSpace', lsp, ...
                'FunctionType', funcType, ...  % 'FunctionType', app.CALC_CONTROL.FUNC_SWITCH, ... から修正
                'Friction', app.INPUT_VARS.FRIC, ...
                'Depth', app.INPUT_VARS.CALC_DEPTH, ...
                'DepthType', depthType, ...
                'DepthMin', depthMin, ...
                'DepthMax', depthMax, ...
                'FontSize', 9 ...
            );
    
            opts = buildStampOptions(app, partialOpts);
            record_stamp(app, app.UIAxes, x, y, opts);

        end


        function record_stamp(app, hw, x, y, opts)
            % =====================================================================
            %                record_stamp(app, hw, x, y, opts)
            % =====================================================================
            % スタンプを追加する関数（構造体 opts を使用）
        
            % --- x, y の座標が空でないかチェック ---
            if isempty(x) || isempty(y)
                error('Empty coordinates passed to record_stamp');
            end
        
            % --- デフォルト値（opts 側で空のときに使う） ---
            defaults = struct( ...
                'SoftwareVersion', '4.0.0', ...
                'FileName', 'Untitled', ...
                'LineSpace', 3.0, ...
                'SectionFlag', 0, ...
                'FunctionType', 1, ...
                'StressType', 5, ...
                'DepthType', 0, ...
                'DepthMin', 0, ...
                'DepthMax', 20, ...
                'Friction', 0.4, ...
                'Depth', 10.0, ...
                'Strike', 90.0, ...
                'Dip', 90.0, ...
                'Rake', 180.0, ...
                'FontName', 'Arial', ...
                'FontSize', 10, ...
                'SectionStartX', -100, ...
                'SectionStartY', -100, ...
                'SectionFinishX', 100, ...
                'SectionFinishY', 100, ...
                'SectionDip', 90);

            % --- 未指定フィールドにデフォルトを適用 ---
            fields = fieldnames(defaults);
            for i = 1:numel(fields)
                if isempty(opts.(fields{i}))
                    opts.(fields{i}) = defaults.(fields{i});
                end
            end
        
            % --- 機能タイプに応じたスタンプ作成 ---
            if opts.FunctionType >= 1 && opts.FunctionType <= 6
                calc_stamp = get_calc_stamp(app, opts.FunctionType);
                if opts.SectionFlag == 0
                    depth_stamp = ['  Depth: ', num2str(opts.Depth, '%6.2f'), ' km'];
                else
                    calc_stamp = ' ';
                    depth_stamp = [' A(', num2str(opts.SectionStartX), ',', num2str(opts.SectionStartY), ...
                                   ') --- B(', num2str(opts.SectionFinishX), ',', num2str(opts.SectionFinishY), ')'];
                end
                h3_stamp = [calc_stamp, ', ', depth_stamp];
        
            elseif opts.FunctionType >= 7 && opts.FunctionType <= 9
                calc_stamp = get_stress_type_stamp(app, opts.StressType, opts.Strike, opts.Dip, opts.Rake);
                fric_stamp = ['  Friction: ', num2str(opts.Friction, '%4.2f')];
                if opts.SectionFlag == 0
                    if opts.SectionFlag == 0
                        % DEPTH_RANGE_TYPE に応じた深度ラベル生成
                        if opts.DepthType == 1
                            % ---- 最大値モード ----
                            depth_stamp = sprintf('  Depth: %.1f–%.1f km (Maximum values)', ...
                                                  opts.DepthMin, opts.DepthMax);
                        elseif opts.DepthType == 2
                            % ---- 平均値モード ----
                            depth_stamp = sprintf('  Depth: %.1f–%.1f km (Mean values)', ...
                                                  opts.DepthMin, opts.DepthMax);
                        else
                            % ---- 単層モード ----
                            depth_stamp = sprintf('  Depth: %.2f km', opts.Depth);
                        end
                    else
                        % 断面モードの場合は従来通り
                        depth_stamp = [' A(', num2str(opts.SectionStartX), ',', num2str(opts.SectionStartY), ...
                                       ') --- B(', num2str(opts.SectionFinishX), ',', num2str(opts.SectionFinishY), ...
                                       ') dip ', num2str(opts.SectionDip), ' deg.'];
                    end
                    % 修正
                    % if opts.DepthType == 1 || opts.DepthType == 2
                    %     depth_stamp = ['  Depth: ', num2str(opts.DepthMin), '-', num2str(opts.DepthMax), ' km'];
                    % else
                    %     depth_stamp = ['  Depth: ', num2str(opts.Depth, '%6.2f'), ' km'];
                    % end

                else
                    depth_stamp = [' A(', num2str(opts.SectionStartX), ',', num2str(opts.SectionStartY), ...
                                   ') --- B(', num2str(opts.SectionFinishX), ',', num2str(opts.SectionFinishY), ...
                                   ') dip ', num2str(opts.SectionDip), ' deg.'];
                end
                h3_stamp = [calc_stamp, ', ', depth_stamp, ', ', fric_stamp];
        
            else
                calc_stamp = get_nodal_plane_stamp(app);
                fric_stamp = ['  Friction: ', num2str(opts.Friction, '%4.2f')];
                h3_stamp = [calc_stamp, ', ', fric_stamp];
            end

            % --- スタンプ用の文字列準備 ---
            opts.FileName = strrep(opts.FileName, newline, '');
            opts.SoftwareVersion = strrep(opts.SoftwareVersion, newline, '');
            FileNameRep = strrep(opts.FileName, '_', '\_');  % アンダースコアのエスケープ
            DateStamp = string(datetime('now'));  % 日付取得
            

            hold(hw, 'on');  % UIAxes に対して hold on
            % --- スタンプの描画 ---
            stamp_lines = {['Coulomb ' opts.SoftwareVersion ', ' char(DateStamp)],  FileNameRep, h3_stamp};
            h = text(hw, x, y, stamp_lines);
            h.HorizontalAlignment = 'left';
            h.FontSize = opts.FontSize;
            h.FontName = opts.FontName;
            h.VerticalAlignment = 'top';
            h.Tag = 'record_stamp';
        end
        
        % --- 機能タイプに応じたスタンプメッセージを返す関数 ---
        function calc_stamp = get_calc_stamp(~, FunctionType)
            switch FunctionType
                case 1, calc_stamp = 'Map view 2D grid';
                case 2, calc_stamp = 'Map view 3D grid';
                case 3, calc_stamp = 'Horizontal vectors';
                case 4, calc_stamp = 'Horizontal Deformed wireframe';
                case 5, calc_stamp = 'Vertical Displacement';
                case 6, calc_stamp = '3D drape';
                case 7, calc_stamp = '3D wireframe surface';
                case 8, calc_stamp = '3D vectors';
                case 9, calc_stamp = 'EXX Strain calc.';
                case 10, calc_stamp = 'EYY Strain calc.';
                case 11, calc_stamp = 'EZZ Strain calc.';
                case 12, calc_stamp = 'EXY Strain calc.';
                case 13, calc_stamp = 'EXZ Strain calc.';
                case 14, calc_stamp = 'EYZ Strain calc.';
                case 15, calc_stamp = 'Dilatation Strain calc.';
                otherwise, calc_stamp = '---';
                % app.CALC_CONTROL.FUNC_SWITCHで分類していた場合
                % case 1, calc_stamp = 'Map view grid';
                % case 2, calc_stamp = 'Horizontal vectors';
                % case 3, calc_stamp = 'Deformed wireframe';
                % case 4, calc_stamp = 'Vertical displacement';
                % case 6, calc_stamp = 'Strain calc.';
                % otherwise, calc_stamp = '---';
            end
        end

            % % ボタンに応じて FunctionType を決定
            % if selectedButton == app.mapview2DButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 1
            %     funcType = 1;
            % elseif selectedButton == app.view3DButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 1
            %     funcType = 2;
            % elseif selectedButton == app.HDvectorsButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 2
            %     funcType = 3;
            % elseif selectedButton == app.HDwireframeButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 3
            %     funcType = 4;
            % elseif selectedButton == app.VDButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 4
            %     funcType = 5;
            % elseif selectedButton == app.image3DdrapeButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 5
            %     funcType = 6;
            % elseif selectedButton == app.image3DwireframedsurfaceButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 5.5
            %     funcType = 7;
            % elseif selectedButton == app.vectors3DButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 5.7
            %     funcType = 8;
            % elseif selectedButton == app.exxstrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 1
            %     funcType = 9;
            % elseif selectedButton == app.eyystrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 2
            %     funcType = 10;
            % elseif selectedButton == app.ezzstrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 3
            %     funcType = 11;
            % elseif selectedButton == app.exystrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 4
            %     funcType = 12;
            % elseif selectedButton == app.exzstrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 5
            %     funcType = 13;
            % elseif selectedButton == app.eyzstrainButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 6
            %     funcType = 14;
            % elseif selectedButton == app.DilatationButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 6
            %     % app.CALC_CONTROL.STRAIN_SWITCH = 7
            %     funcType = 15;
            % elseif selectedButton == app.ShearstresschangeButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 7
            %     funcType = 16;
            % elseif selectedButton == app.UnclampingstresschangeButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 8
            %     funcType = 17;
            % elseif selectedButton == app.CoulombstresschangeButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 9
            %     funcType = 18;
            % elseif selectedButton == app.CalcStFaultsButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 10 
            %     % ただし、描画の都合上処理の最初で1にして、その後10にする
            %     funcType = 19;
            % elseif selectedButton == app.CalcStPointButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 1 ???
            %     funcType = 20;
            % elseif selectedButton == app.CalcStNodalplanesButton
            %     % app.CALC_CONTROL.FUNC_SWITCH = 11
            %     funcType = 21;
            % end

        
        % --- ストレスタイプに応じたスタンプメッセージを返す関数 ---
        function calc_stamp = get_stress_type_stamp(~, StressType, Strike, Dip, Rake)
            switch StressType
                case 1, calc_stamp = 'Opt. oriented faults';
                case 2, calc_stamp = 'Opt. strike-slip faults';
                case 3, calc_stamp = 'Opt. thrust faults';
                case 4, calc_stamp = 'Opt. normal faults';
                case 5, calc_stamp = ['Specified faults: ', num2str(Strike), '/', num2str(Dip), '/', num2str(Rake)];
                otherwise, calc_stamp = '---';
            end
        end
        
        % --- ノーダル面に応じたスタンプメッセージを返す関数 ---
        function calc_stamp = get_nodal_plane_stamp(app)
            if app.ASSIST_VARS.INODAL == 1
                calc_stamp = 'Stress on nodal plane 1';
            elseif app.ASSIST_VARS.INODAL == 2
                calc_stamp = 'Stress on nodal plane 2';
            else
                calc_stamp = 'Stress on randomly selected nodal planes';
            end
        end

        % --- スタンプに追加する要素を指定する構造体を作成する関数 ---
        function opts = buildStampOptions(~, inputStruct)
            AuthorizedOptions = {'SoftwareVersion', 'FileName', 'LineSpace', ...
                                 'SectionFlag', 'FunctionType', 'StressType', ...
                                 'DepthType', 'DepthMin', 'DepthMax', 'Friction', ...
                                 'Depth', 'Strike', 'Dip', 'Rake', 'FontName', ...
                                 'FontSize', 'SectionStartX', 'SectionStartY', ...
                                 'SectionFinishX', 'SectionFinishY', 'SectionDip'};
            opts = struct();
            for i = 1:numel(AuthorizedOptions)
                key = AuthorizedOptions{i};
                if isfield(inputStruct, key)
                    opts.(key) = inputStruct.(key);
                else
                    opts.(key) = [];  % 未設定なら空
                end
            end
        end

        
        function grid_drawing_3d(app)
            % =====================================================================
            %                grid_drawing_3d(app)
            % =====================================================================
            % グリッドオプションのためのグリッドメッシュ描画関数
            % 3Dプロット環境でグリッドや地理データ（海岸線、活断層、地震）を描画するための関数
            % この関数は、他のプロット関数の基本地図として頻繁に使用されます。
        
            % グリッドの範囲を取得
            xstart  = app.INPUT_VARS.GRID(1, 1);
            ystart  = app.INPUT_VARS.GRID(2, 1);
            xfinish = app.INPUT_VARS.GRID(3, 1);
            yfinish = app.INPUT_VARS.GRID(4, 1);
        
            % 座標系が緯度経度（ICOORD == 2）の場合、座標変換
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a  = xy2lonlat(app, [app.INPUT_VARS.GRID(1, 1) app.INPUT_VARS.GRID(2, 1)]);
                xstart = a(1);
                ystart = a(2);
                a = xy2lonlat(app, [app.INPUT_VARS.GRID(3, 1) app.INPUT_VARS.GRID(4, 1)]);
                xfinish = a(1);
                yfinish = a(2);
            end
        
            if app.COORD_VARS.ICOORD == 1
                app.COORD_VARS.XY_RATIO = 1;
            end
        
            % 既存のプロットをクリア
            cla(app.UIAxes);

            hold(app.UIAxes, 'on');
            % グリッドを3Dで描画
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                app.ASSIST_VARS.A_GRID = plot3(app.UIAxes, ...
                    [xstart xfinish xfinish xstart xstart], ...
                    [ystart ystart yfinish yfinish ystart], ...
                    [0.0 0.0 0.0 0.0 0.0]);
                xlabel(app.UIAxes, 'Longitude (degree)', 'FontSize', 18);
                ylabel(app.UIAxes, 'Latitude (degree)', 'FontSize', 18);
            else
                app.ASSIST_VARS.A_GRID = plot3(app.UIAxes, ...
                    [xstart xfinish xfinish xstart xstart], ...
                    [ystart ystart yfinish yfinish ystart], ...
                    [0.0 0.0 0.0 0.0 0.0]);
                xlabel(app.UIAxes, 'X (km)', 'FontSize', 18);
                ylabel(app.UIAxes, 'Y (km)', 'FontSize', 18);
            end
        
            % 軸のプロパティ設定
            set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO 1 1], ...
                'Color', [0.9 0.9 0.9], ...
                'PlotBoxAspectRatio', [1 1 1], ...
                'XLim', [xstart, xfinish], ...
                'YLim', [ystart, yfinish]);
        
            % グリッド線の描画
            if app.SYSTEM_VARS.PREF(3,4) ~= 0.0 % GRIDの線幅が0.0の場合、以下のブロックをスキップします。
                % 垂直方向のグリッド
                for m = 1:length(app.COORD_VARS.XGRID)
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        a = xy2lonlat(app, [app.COORD_VARS.XGRID(m), app.INPUT_VARS.GRID(2, 1)]); % ystart is dummy
                        x = a(1);
                    else
                        x = app.COORD_VARS.XGRID(m);
                    end
                    % 垂直方向の線を描画（Y方向に伸びる垂直線）
                    gridln1 = plot3(app.UIAxes, ...
                        [x, x], ...
                        [app.INPUT_VARS.GRID(2, 1), app.INPUT_VARS.GRID(4, 1)], ...
                        [0.0, 0.0]);
                    % 線のスタイル設定
                    gridln1.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                    if ismember(app.CALC_CONTROL.FUNC_SWITCH, [1, 2, 3, 10])
                        gridln1.Color = app.SYSTEM_VARS.PREF(3, 1:3);
                    else
                        gridln1.Color = [0.5, 0.5, 0.5];
                    end
                    gridln1.Tag = 'ylines';
                end
                % 水平方向のグリッド
                for n = 1:length(app.COORD_VARS.YGRID)
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        a = xy2lonlat(app, [app.INPUT_VARS.GRID(1, 1), app.COORD_VARS.YGRID(n)]); % xstart is dummy
                        y = a(2);
                    else
                        y = app.COORD_VARS.YGRID(n);
                    end
                    % 水平方向の線を描画（Y方向に伸びる水平線）
                    gridln2 = plot3(app.UIAxes, ...
                        [app.INPUT_VARS.GRID(1, 1), app.INPUT_VARS.GRID(3, 1)], ...
                        [y, y], ...
                        [0.0, 0.0]);
                    % 線のスタイル設定
                    gridln2.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                    if ismember(app.CALC_CONTROL.FUNC_SWITCH, [1, 2, 3, 10])
                        gridln2.Color = app.SYSTEM_VARS.PREF(3, 1:3);
                    else
                        gridln2.Color = [0.5, 0.5, 0.5];
                    end
                    gridln2.Tag = 'xlines';
                end
            end
        
            % 震源の描画-----------------
            % グリッド描画とコンテキストメニュー設定
            app.ASSIST_VARS.A_GRID = plot3(app.UIAxes, ...
                [app.INPUT_VARS.GRID(1, 1) app.INPUT_VARS.GRID(3, 1) app.INPUT_VARS.GRID(3, 1) app.INPUT_VARS.GRID(1, 1) app.INPUT_VARS.GRID(1, 1)], ...
                [app.INPUT_VARS.GRID(2, 1) app.INPUT_VARS.GRID(2, 1) app.INPUT_VARS.GRID(4, 1) app.INPUT_VARS.GRID(4, 1) app.INPUT_VARS.GRID(2, 1)], ...
                [0.0 0.0 0.0 0.0 0.0]);
            set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO 1 1], ...
                'Color', [0.9 0.9 0.9], ...
                'PlotBoxAspectRatio', [1 1 1], ...
                'XLim', [app.INPUT_VARS.GRID(1, 1), app.INPUT_VARS.GRID(3, 1)], ...
                'YLim', [app.INPUT_VARS.GRID(2, 1), app.INPUT_VARS.GRID(4, 1)]);
            % ラベル設定
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % lonlat座標
                xlabel(app.UIAxes, 'Longitude (degree)', 'FontSize', 18);
                ylabel(app.UIAxes, 'Latitude (degree)', 'FontSize', 18);
            else
                % cartesian座標
                xlabel(app.UIAxes, 'X (km)', 'FontSize', 18);
                ylabel(app.UIAxes, 'Y (km)', 'FontSize', 18);
            end
            
            % 座標切り替えボタンを、入力されているファイルの座標にスイッチを設定
            if app.COORD_VARS.ICOORD == 1
                app.CoordinatesSwitch.Value = 'XYZ';
            else
                app.CoordinatesSwitch.Value = 'LonLat';
            end
        
            %---------------------------------------------
            %	Coast line plot in 3D 海岸線のプロット
            %---------------------------------------------
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) && app.CoastlinesCheckBox.Value == 1
                [~, n] = size(app.OVERLAY_VARS.COAST_DATA);
                if n == 9
                    % 列数が9である場合は古いフォーマット
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        % COAST_DATAの列2と4を使ってx座標を、列3と5を使ってy座標を設定(rot90で配列を90度回転させながら)
                        x1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 2)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 4))];
                        y1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 3)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 5))];
                    else
                        % cartesian座標
                        % COAST_DATAの列6と8を使ってx座標を、列7と9を使ってy座標を設定
                        x1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 6)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 8))];
                        y1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 7)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 9))];
                    end
                else
                    % 新しいフォーマットの場合
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        x1 = app.OVERLAY_VARS.COAST_DATA(:, 1);
                        y1 = app.OVERLAY_VARS.COAST_DATA(:, 2);
                    else
                        % cartesian座標
                        x1 = app.OVERLAY_VARS.COAST_DATA(:, 3);
                        y1 = app.OVERLAY_VARS.COAST_DATA(:, 4);
                    end
                end
                
                % Z座標はすべて0に設定
                [mm, nn] = size(x1);
                z1 = zeros(mm, nn);
        
                % 海岸線を3Dでプロット
                h = plot3(app.UIAxes, x1, y1, z1);
                h.Color = app.SYSTEM_VARS.PREF(4, 1:3);
                h.LineWidth = app.SYSTEM_VARS.PREF(4, 4);
                h.Tag = 'CoastlineObj';
                % 軸の設定
                set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO 1 1], ... % データアスペクト比を設定
                                'Color', [0.9 0.9 0.9], ...                            % 背景色を設定
                                'PlotBoxAspectRatio', [1 1 1], ...                     % プロットボックスのアスペクト比を設定
                                'XLim', [app.INPUT_VARS.GRID(1, 1), app.INPUT_VARS.GRID(3, 1)], ...  % X軸の範囲を設定
                                'YLim', [app.INPUT_VARS.GRID(2, 1), app.INPUT_VARS.GRID(4, 1)]);     % Y軸の範囲を設定
            end
        
            %---------------------------------------------
            %	Active fault plot in 3D 活断層のプロット
            %---------------------------------------------
            if ~isempty(app.OVERLAY_VARS.AFAULT_DATA) && app.ActivefaultsCheckBox.Value == 1
                [~, n] = size(app.OVERLAY_VARS.AFAULT_DATA);
                if n == 9
                    % old format
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        x1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 2)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 4))];
                        y1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 3)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 5))];
                    else
                        % cartesian座標
                        x1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 6)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 8))];
                        y1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 7)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 9))];
                    end
                else
                    % new format
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        x1 = app.OVERLAY_VARS.AFAULT_DATA(:, 1);
                        y1 = app.OVERLAY_VARS.AFAULT_DATA(:, 2);
                    else
                        % cartesian座標
                        x1 = app.OVERLAY_VARS.AFAULT_DATA(:, 3);
                        y1 = app.OVERLAY_VARS.AFAULT_DATA(:, 4);
                    end
                end
                
                % Z座標は全て0に設定
                [mm, nn] = size(x1);
                z1 = zeros(mm, nn);
            
                % 3Dプロット
                hold(app.UIAxes, 'on');
                h = plot3(app.UIAxes, x1, y1, z1);
                h.Color = app.SYSTEM_VARS.PREF(6, 1:3);
                h.LineWidth = app.SYSTEM_VARS.PREF(6, 4);
                h.Tag = 'AfaultObj';

                % 軸の設定
                set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO 1 1], ...
                                'Color', [0.9 0.9 0.9], ...
                                'PlotBoxAspectRatio', [1 1 1], ...
                                'XLim', [app.INPUT_VARS.GRID(1, 1), app.INPUT_VARS.GRID(3, 1)], ...
                                'YLim', [app.INPUT_VARS.GRID(2, 1), app.INPUT_VARS.GRID(4, 1)]);
            end
 
            %---------------------------------------------
            %	Volcanoes plot in 3D 火山のプロット
            %---------------------------------------------
            % 修正必要？
            % 元々の関数にはない

            %---------------------------------------------
            %	Earthquake plot in 3D 地震のプロット
            %---------------------------------------------
            % EQ_DATAが空でなく、地震オーバーレイが'on'の場合
            if ~isempty(app.OVERLAY_VARS.EQ_DATA) && app.EarthquakesCheckBox.Value == 1
                dummy1 = 0; % dummy1を定義（機能未使用）
                
                if dummy1 == 1
                    depLim = 20.0;
                    b = app.OVERLAY_VARS.EQ_DATA(:, 7) ./ depLim;
                    c1 = b <= 1.0;   
                    c2 = b > 1.0;
                    b = b .* c1 + c2;
                    c3 = b <= 0.5;  
                    c4 = b > 0.5;
                    g  = b .* c3 * 2.0 + abs(0.5 - (b - 0.5)) .* c4 * 2.0;
                    r = (1.0 - b);
                end
            
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    h = scatter3(app.UIAxes, app.OVERLAY_VARS.EQ_DATA(:,1), app.OVERLAY_VARS.EQ_DATA(:,2), -app.OVERLAY_VARS.EQ_DATA(:,7), 5 * app.SYSTEM_VARS.PREF(5,4));
                else
                    % cartesian座標
                    if dummy1 == 1
                        for k = 1:size(app.OVERLAY_VARS.EQ_DATA, 1)
                            h = scatter3(app.UIAxes, app.OVERLAY_VARS.EQ_DATA(k,16), app.OVERLAY_VARS.EQ_DATA(k,17), -app.OVERLAY_VARS.EQ_DATA(k,7), 20.0, [r(k) g(k) b(k)]);
                        end
                    else
                        h = scatter3(app.UIAxes, app.OVERLAY_VARS.EQ_DATA(:,16), app.OVERLAY_VARS.EQ_DATA(:,17), -app.OVERLAY_VARS.EQ_DATA(:,7), 5 * app.SYSTEM_VARS.PREF(5,4));
                    end
                end
                h.MarkerEdgeColor = app.SYSTEM_VARS.PREF(5,1:3); % 地震のための白いエッジカラーを設定
                h.Tag = 'EqObj'; % 後で削除するためのタグを付ける
            end
            
            %---------------------------------------------
            %	GNSS station plot in 3D
            %---------------------------------------------
            if isempty(app.OVERLAY_VARS.GNSS_DATA)~=1 && app.GNSSstationsCheckBox.Value == 1
                zero = zeros(size(app.OVERLAY_VARS.GNSS_DATA, 1), 1);
                if ICOORD == 2 && isempty(LON_GRID) ~= 1
                    % lon.lat.coordinates
                    h = scatter3(app.OVERLAY_VARS.GNSS_DATA(:, 1), app.OVERLAY_VARS.GNSS_DATA(:, 2), zero, 5 * app.SYSTEM_VARS.PREF(5, 4));
                else
                    h = scatter3(app.OVERLAY_VARS.GNSS_DATA(:, 6), app.OVERLAY_VARS.GNSS_DATA(:, 7), zero, 5 * app.SYSTEM_VARS.PREF(5, 4));
                end
                h.MarkerEdgeColor = 'r'; % white edge color for GPS station 
                h.Tag = 'GPSObj';        % put a tag to remove them later
            end

            %---------------------------------------------
            % record_stamp
            % データとファイル名のスタンプを追加
            add_record_stamp(app, xstart, xfinish, ystart, yfinish);
            hold(app.UIAxes, 'off');
        end

        
        function [fc] = fault_corners(~, xs, ys, xf, yf, dip, top, bottom)
            % =====================================================================
            %                fault_corners(~, xs, ys, xf, yf, dip, top, bottom)
            % =====================================================================
            % fault_corners: 断層の4つのコーナー座標を計算する関数
            % 入力:
            %   xs, ys - 始点の x, y 座標
            %   xf, yf - 終点の x, y 座標
            %   dip    - 断層面の傾斜角度 (degrees)
            %   top    - 断層面の上端の深さ
            %   bottom - 断層面の下端の深さ
            % 出力:
            %   fc - 断層の4つのコーナー座標 (4x2 行列)

            % initialize to be all zeros to fasten the process
            fc = zeros(4, 2);
            % 角度の計算
            angle = atan((yf - ys) / (xf - xs));
            % % angle = atan2((yf - ys), (xf - xs));
            % 傾斜角度をラジアンに変換
            dipr = deg2rad(dip);

            % 深さに応じたシフトの計算
            shift = (bottom - top) / tan(dipr);
            % シフト成分の計算
            x_shift = shift * sin(angle);
            y_shift = shift * cos(angle);

            % 座標のシフト
            if (xf - xs) >= 0.0
                % 断層が右上がりの場合のシフト
                xs_bottom = xs + x_shift;
                ys_bottom = ys - y_shift;
                xf_bottom = xf + x_shift;
                yf_bottom = yf - y_shift;
            else
                % 断層が左上がりの場合のシフト
                xs_bottom = xs - x_shift;
                ys_bottom = ys + y_shift;
                xf_bottom = xf - x_shift;
                yf_bottom = yf + y_shift;
            end

            % 結果を時計回りに並べた 4x2 行列として返す
            fc = [xs, ys; xf, yf; xf_bottom, yf_bottom; xs_bottom, ys_bottom];
        end


        function fault_overlay(app)
            % =====================================================================
            %                fault_overlay(app)
            % =====================================================================
            % 入力したファイルデータの断層を描画する関数
            if ~isempty(app.UIAxes)
                axes(app.UIAxes);  % AppDesignerのUIAxesを前面に表示
            else
                uialert(app.main_menu_window, 'No window prepared for grid drawing!', 'Warning!');
                return;
            end
        
            hold(app.UIAxes, 'on');
            % 断層数ループ
            for n = 1:app.INPUT_VARS.NUM
        
                % map projection box for a source fault
                % 断層のコーナー情報を取得
                c = fault_corners(app, app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), ...
                                  app.INPUT_VARS.ELEMENT(n, 3), app.INPUT_VARS.ELEMENT(n, 4), ...
                                  app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));
        
                % map projection line for the surface
                % 表面プロジェクション用のライン
                d = fault_corners(app, app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), ...
                                  app.INPUT_VARS.ELEMENT(n, 3), app.INPUT_VARS.ELEMENT(n, 4), ...
                                  app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), 0.0);
        
                % map projection line for the calc. depth
                % 計算深度でのプロジェクション
                e = fault_corners(app, app.INPUT_VARS.ELEMENT(n,1), app.INPUT_VARS.ELEMENT(n, 2), ...
                                  app.INPUT_VARS.ELEMENT(n, 3), app.INPUT_VARS.ELEMENT(n, 4), ...
                                  app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.CALC_DEPTH);
        
                % Calculation depth
                % 深度範囲が選択されていない場合に計算
                if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
                    hold(app.UIAxes, 'on');
                    % to avoid line drawing when depth range was selected
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        d1 = xy2lonlat(app, [e(3, 1), e(3, 2)]);
                        d2 = xy2lonlat(app, [e(4, 1), e(4, 2)]);
                        a2 = plot(app.UIAxes, [d1(1), d2(1)], [d1(2), d2(2)]);
                    else
                        % cartesian座標
                        a2 = plot(app.UIAxes, [e(3, 1), e(4, 1)], [e(3, 2), e(4, 2)]);
                    end
                    a2.Color = 'k';
                    a2.LineWidth = 1;
                end
        
                % Surface projection
                % 表面プロジェクションの描画
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    d1 = xy2lonlat(app, [c(1, 1), c(1, 2)]);
                    d2 = xy2lonlat(app, [c(2, 1), c(2, 2)]);
                    d3 = xy2lonlat(app, [c(3, 1), c(3, 2)]);
                    d4 = xy2lonlat(app, [c(4, 1), c(4, 2)]);
                    a3 = plot(app.UIAxes, [d1(1), d2(1), d3(1), d4(1), d1(1)], [d1(2), d2(2), d3(2), d4(2), d1(2)]);
                else
                    % cartesian座標
                    a3 = plot(app.UIAxes, [c(1, 1), c(2, 1), c(3, 1), c(4, 1), c(1, 1)], [c(1, 2), c(2, 2), c(3, 2), c(4, 2), c(1, 2)]);
                end
                a3.Color = 'w';
                a3.LineWidth = 2.0;

                hold(app.UIAxes, 'on');
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    d1 = xy2lonlat(app, [c(1, 1), c(1, 2)]);
                    d2 = xy2lonlat(app, [c(2, 1), c(2, 2)]);
                    d3 = xy2lonlat(app, [c(3, 1), c(3, 2)]);
                    d4 = xy2lonlat(app, [c(4, 1), c(4, 2)]);
                    a4 = plot(app.UIAxes, [d1(1), d2(1), d3(1), d4(1), d1(1)], [d1(2), d2(2), d3(2), d4(2), d1(2)]);
                else
                    % cartesian座標
                    a4 = plot(app.UIAxes, [c(1, 1), c(2, 1), c(3, 1), c(4, 1), c(1, 1)], [c(1, 2), c(2, 2), c(3, 2), c(4, 2), c(1, 2)]);
                end
                a4.Color = app.SYSTEM_VARS.PREF(1, 1:3);
                a4.LineWidth = app.SYSTEM_VARS.PREF(1, 4);
        
                % Surface intersection
                % 表面交差の描画
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % lonlat座標
                    d1 = xy2lonlat(app, [d(3, 1), d(3, 2)]);
                    d2 = xy2lonlat(app, [d(4, 1), d(4, 2)]);
                    a1 = plot(app.UIAxes, [d1(1), d2(1)], [d1(2), d2(2)]);
                else
                    % cartesian座標
                    a1 = plot(app.UIAxes, [d(3, 1), d(4, 1)], [d(3, 2), d(4, 2)]);
                end
                a1.Color = 'g';
                a1.LineWidth = 2;
        
                % write fault number of the starting point
                % 断層番号の表示
                if app.ASSIST_VARS.FNUM_ONOFF == 1
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % lonlat座標
                        d1 = xy2lonlat(app, [c(1, 1), c(1, 2)]);
                        htx = text(app.UIAxes, d1(1), d1(2), num2str(n));
                    else
                        % cartesian座標
                        htx = text(app.UIAxes, c(1, 1), c(1, 2), num2str(n));
                    end
                    htx.FontSize = 14;
                    htx.FontWeight = 'bold';
                    htx.Color = [0.1, 0.1, 0.6];
                end
        
                % Point source
                % 修正必要、どっちが正しい？
                % Point source (点源) のプロット
                if any(app.INPUT_VARS.KODE == 400 | app.INPUT_VARS.KODE == 500)
                    % どれか一つでも400または500なら全部に実行
                % if app.INPUT_VARS.KODE(n) == 400 || app.INPUT_VARS.KODE(n) == 500
                    ap = plot(app.UIAxes, (e(3, 1) + e(4, 1)) / 2.0, (e(3, 2) + e(4, 2)) / 2.0, 'ko');
                    ap.LineWidth = 1.5;
                end
            end
        end


        function iflag = check_lonlat_info(app)
            % =====================================================================
            %                check_lonlat_info(app)
            % =====================================================================
            % グローバル変数に設定された緯度と経度の情報が適切に定義されているかをチェックし、その結果を iflag というフラグで返します。
            % 出力：iflag = 1 （全ての情報が設定されている場合）, iflag = 0 （いずれかの情報が不足している場合）

            % 最小緯度または最大緯度が設定されていない場合
            if isempty(app.COORD_VARS.MIN_LAT) == 1 || isempty(app.COORD_VARS.MAX_LAT) == 1
                iflag = 0;
            % 最小経度または最大経度が設定されていない場合
            elseif isempty(app.COORD_VARS.MIN_LON) == 1 || isempty(app.COORD_VARS.MAX_LON) == 1
                iflag = 0;
            % ゼロポイントの緯度または経度が設定されていない場合
            elseif isempty(app.COORD_VARS.ZERO_LAT) == 1 || isempty(app.COORD_VARS.ZERO_LON) == 1
                iflag = 0;
            % すべての緯度経度情報が適切に設定されている場合 
            else
                iflag = 1;
            end
        end


        function [unit, unitText] = check_unit_vector(~, xs, xf, oneMeterSize, ratioMin, ratioMax)
            % =====================================================================
            %                check_unit_vector(~, xs, xf, oneMeterSize, ratioMin, ratioMax)
            % =====================================================================
            % この関数は、ディスプレイに表示するための適切な単位長を決定します。
            % 地図やグラフ上での表示に適した単位ベクトルの長さとその表示用のテキストを返します。
            % 入力:
            %   xs: xの開始位置 (km単位) または、垂直方向の表示のためのyの開始位置
            %   xf: xの終了位置 (km単位) または、垂直方向の表示のためのyの終了位置
            %   oneMeterSize: 1メートルのベクトルを誇張して表したkm単位の長さ
            %   ratioMax: 0から1の範囲の最大比率 (例: 0.5は、グラフィック全体の半分を超えないようにする)
            %   ratioMin: 最小比率 (単位ベクトルを表示するための最小比率)
            % 出力:
            %   unit: 決定された単位長さ (例: 0.01, 0.1, 1, 10)
            %   unitText: 単位長さを表す文字列 (例: '1 m')
            
            % 総距離を計算
            totalLength = abs(xf - xs);           % 開始位置と終了位置の絶対距離を計算
            maxLength   = totalLength * ratioMax; % 最大長さの制限を設定
            minLength   = totalLength * ratioMin; % 最小長さの制限を設定
            
            % oneMeterSizeがmaxLengthより大きい場合、unitを0.1に縮小して再計算
            if oneMeterSize > maxLength 
                unit = 0.1; unitText = '0.1 m';             % 単位を0.1メートルに設定
                oneMeterSize = oneMeterSize * 0.1;          % oneMeterSizeを0.1倍に縮小
                if oneMeterSize > maxLength
                    unit = 0.01; unitText = '0.01 m';       % 単位を0.01メートルに設定
                    oneMeterSize = oneMeterSize * 0.1;      % oneMeterSizeをさらに0.1倍に縮小
                    if oneMeterSize > maxLength
                        unit = 0.001; unitText = '0.001 m'; % 単位を0.001メートルに設定
                    end
                end
            else
                % oneMeterSizeがminLength以上であれば、unitを1メートルに設定
                if oneMeterSize >= minLength
                    unit = 1;
                    unitText = '1 m';                     % 単位を1メートルに設定 
                elseif oneMeterSize < minLength
                    unit = 10;
                    unitText = '10 m';                    % 単位を10メートルに設定
                    oneMeterSize = oneMeterSize * 10;     % oneMeterSizeを10倍に拡大
                    if oneMeterSize > maxLength
                        unit = 1;
                        unitText = '1 m';                 % 再び単位を1メートルに設定
                    end
                    if oneMeterSize < minLength
                        unit = 10;
                        unitText = '100 m';               % 単位を100メートルに設定
                        oneMeterSize = oneMeterSize * 10; % oneMeterSizeを再び10倍に拡大
                        if oneMeterSize > maxLength
                            unit = 10;
                            unitText = '10 m';            % 単位を10メートルに再設定
                        end
                        if oneMeterSize < minLength
                            unit = 10;
                            unitText = '1000 m';          % 単位を1000メートルに設定
                        end
                    end
                end
            end
        end


        function [shear, normal, coulomb] = calc_coulomb(~, strike_m, dip_m, rake_m, friction_m, ss)
            % =====================================================================
            %                calc_coulomb(~, strike_m, dip_m, rake_m, friction_m, ss)
            % =====================================================================
            % [shear, normal, coulomb] = calc_coulomb(~, strike_m, dip_m, rake_m, friction_m, ss, varargin)
            % For calculating shear, normal and Coulomb stresses in a given fault strike, dip, and rake
            % 指定された断層のストライク、ディップ、レーキ角に基づいて、せん断応力、法線応力、およびクーロン応力を計算する関数
            % この関数は、断層の幾何学的な特性に基づいて応力状態を計算するために使用されます。
            % INPUT: 
            %   strike_m  - ストライク角の行列（断層の進行方向と北の間の角度）
            %   dip_m     - ディップ角の行列（断層面の傾斜角）
            %   rake_m    - レーキ角の行列（断層面上の移動方向と水平方向の角度）
            %   friction_m- 摩擦係数の行列
            %   ss        - 応力テンソルの行列（SXX, SYY, SZZ, SXY, SXZ, SYZ）
            % OUTPUT:
            %   shear     - せん断応力のベクトル
            %   normal    - 法線応力のベクトル
            %   coulomb   - クーロン応力のベクトル
            % "_m" が付いている変数は行列を示します。このため、条件式ではスカラー値のみ使用できます。
            % 以下では、行列内の全ての要素が同じ値であるため、行列をスカラー値に変換します。
        
            % 行列のサイズ（行数）を取得
            n = size(strike_m, 1);

            % % 初期化 - 行列をゼロベクトルで初期化
            % strike = zeros(n, 1);
            % dip    = zeros(n, 1);
            % rake   = zeros(n, 1);

            % 摩擦係数の行列をスカラーに変換
            friction = friction_m(1, 1);
        
            % 我々の座標系に合わせるための調整（Aki & Richardsのコンベンションからの変換）
            c1 = strike_m >= 180.0;
            c2 = strike_m < 180.0;                              % ストライク角に対する条件
            strike = (strike_m - 180.0) .* c1 + strike_m .* c2; % ストライク角の変換
            dip    = (-1.0) * dip_m .* c1 + dip_m .* c2;        % ディップ角の変換
            rake_m = rake_m - 90.0;                           % レーキ角の変換
            c1 = rake_m <= -180.0;
            c2 = rake_m > -180.0;                               % レーキ角に対する条件
            rake = (360.0 + rake_m) .* c1 + rake_m .* c2;       % レーキ角の変換
        
            % CAUTION.....................................
            % ストライク、ディップ、レーキ角をラジアンに変換
            strike = deg2rad(strike);
            dip = deg2rad(dip);
            rake = deg2rad(rake);
        
            % レーキ角の回転行列の生成
            for i = 1:n
                rsc = -rake(i, 1);                 % レーキ角の符号を反転
                rr = makehgtform('xrotate', rsc);  % レーキ角の回転行列を生成
                mtran(1:3, 1:3, i) = rr(1:3, 1:3); % 回転行列を格納
            end
        
            % せん断応力、法線応力、およびテンソルの初期化
            sn  = zeros(6, length(strike));    % 応力ベクトルの初期化
            sn9 = zeros(3, 3, length(strike)); % 9成分の応力テンソルの初期化
        
            % ベクトルおよびテンソルの初期化と座標変換
            ver = pi / 2.0;
            c1 = strike >= 0.0;
            c2 = strike < 0.0;
            c3 = strike <= ver;
            c4 = strike > ver;
            c24 = c2 + c4;
            cc24 = c24 > 0;
            d1 = dip >= 0.0;
            d2 = dip < 0.0;
            xbeta = (-1.0) * strike .* d1 + (pi - strike) .* d2;
            ybeta = (pi - strike) .* d1 + (-1.0) * strike .* d2;
            zbeta = (ver - strike) .* d1 + ((-1.0) * ver - strike) .* d2 .* c1 .* c3 + (pi + ver - strike) .* d2 .* cc24;
            xdel = ver - abs(dip);
            ydel = abs(dip);
            zdel = 0.0;
        
            % 再びスカラー値を行列（n x 1）に変換
            xbetam = zeros(n, 1) + xbeta;
            ybetam = zeros(n, 1) + ybeta;
            zbetam = zeros(n, 1) + zbeta;
            xdelm  = zeros(n, 1) + xdel;
            ydelm  = zeros(n, 1) + ydel;
            zdelm  = zeros(n, 1) + zdel;
        
            % ベクトルの成分を計算
            xl = cos(xdelm) .* cos(xbetam);
            xm = cos(xdelm) .* sin(xbetam);
            xn = sin(xdelm);
            yl = cos(ydelm) .* cos(ybetam);
            ym = cos(ydelm) .* sin(ybetam);
            yn = sin(ydelm);
            zl = cos(zdelm) .* cos(zbetam);
            zm = cos(zdelm) .* sin(zbetam);
            zn = sin(zdelm);
        
            % 応力テンソルの計算
            t(1, 1, :) = xl .* xl;
            t(1, 2, :) = xm .* xm;
            t(1, 3, :) = xn .* xn;
            t(1, 4, :) = 2.0 * xm .* xn;
            t(1, 5, :) = 2.0 * xn .* xl;
            t(1, 6, :) = 2.0 * xl .* xm;
            t(2, 1, :) = yl .* yl;
            t(2, 2, :) = ym .* ym;
            t(2, 3, :) = yn .* yn;
            t(2, 4, :) = 2.0 * ym .* yn;
            t(2, 5, :) = 2.0 * yn .* yl;
            t(2, 6, :) = 2.0 * yl .* ym;
            t(3, 1, :) = zl .* zl;
            t(3, 2, :) = zm .* zm;
            t(3, 3, :) = zn .* zn;
            t(3, 4, :) = 2.0 * zm .* zn;
            t(3, 5, :) = 2.0 * zn .* zl;
            t(3, 6, :) = 2.0 * zl .* zm;
            t(4, 1, :) = yl .* zl;
            t(4, 2, :) = ym .* zm;
            t(4, 3, :) = yn .* zn;
            t(4, 4, :) = ym .* zn + zm .* yn;
            t(4, 5, :) = yn .* zl + zn .* yl;
            t(4, 6, :) = yl .* zm + zl .* ym;
            t(5, 1, :) = zl .* xl;
            t(5, 2, :) = zm .* xm;
            t(5, 3, :) = zn .* xn;
            t(5, 4, :) = xm .* zn + zm .* xn;
            t(5, 5, :) = xn .* zl + zn .* xl;
            t(5, 6, :) = xl .* zm + zl .* xm;
            t(6, 1, :) = xl .* yl;
            t(6, 2, :) = xm .* ym;
            t(6, 3, :) = xn .* yn;
            t(6, 4, :) = xm .* yn + ym .* xn;
            t(6, 5, :) = xn .* yl + yn .* xl;
            t(6, 6, :) = xl .* ym + yl .* xm;
        
            % せん断応力、法線応力、およびテンソルの成分を計算し、テンソルの回転を適用
            for k = 1:n
                sn(:, k) = t(:, :, k) * ss(:, k); % 応力ベクトルの計算
                sn9(1, 1, k) = sn(1, k);          % 応力テンソルの成分に値を格納
                sn9(1, 2, k) = sn(6, k);
                sn9(1, 3, k) = sn(5, k);
                sn9(2, 1, k) = sn(6, k);
                sn9(2, 2, k) = sn(2, k);
                sn9(2, 3, k) = sn(4, k);
                sn9(3, 1, k) = sn(5, k);
                sn9(3, 2, k) = sn(4, k);
                sn9(3, 3, k) = sn(3, k);
                sn9(:, :, k) = sn9(:, :, k) * mtran(:, :, k); % テンソルの回転を適用
            end
        
            % せん断応力、法線応力、クーロン応力を計算
            shear   = reshape(sn9(1, 2, :), n, 1);    % せん断応力を計算し、ベクトルに変換
            normal  = reshape(sn9(1, 1, :), n, 1);    % 法線応力を計算し、ベクトルに変換
            coulomb = shear + friction .* normal; % クーロン応力を計算
        end


        function coulomb_view(app, N)
            % =====================================================================
            %                coulomb_view(app, N)
            % =====================================================================
            % この関数は、メインウィンドウにクーロン応力変化（または他の計算結果）を表示するためのものです。
            % 入力: N（カラーバーの飽和値の範囲を指定する値）

            % UIAxesでの描画をクリア
            hold(app.UIAxes, 'off'); 

            % NがNaN（Not a Number）の場合、エラーメッセージを表示して処理を終了する
            if isnan(N)
                uialert(app.main_menu_window, 'A calculation point hits a singular point. Shift the grid slightly.', 'warning');
                return;
            end

            % 機能が "strain function" の場合は N を10のN乗に変換する
            if app.CALC_CONTROL.FUNC_SWITCH == 6
                N = power(10, N); % 10のN乗に変換
            else
                N = abs(N); % Nを絶対値に変換
            end
            app.GRAPHICS_VARS.C_SAT = N; % C_SATにNを設定
            
            % COORD_VARS.ICOORD が1の場合、XY比を1に設定
            if app.COORD_VARS.ICOORD == 1
                app.COORD_VARS.XY_RATIO = 1;
            end
            
            % メインウィンドウ（UIAxes）のカラーマップをGRAPHICS_VARS.ANATOLIAに設定
            colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA); % UIAxesのカラーマップを設定
            
            % CALC_CONTROL.SHADE_TYPEが2の場合、細かいグリッドを作成し、補間を行う
            if app.CALC_CONTROL.SHADE_TYPE == 2
                n_interp = 10.0;    % グリッドの細かさを設定
                nxg = length(app.COORD_VARS.XGRID);
                xgmin = min(app.COORD_VARS.XGRID);
                xgmax = max(app.COORD_VARS.XGRID);
                nyg = length(app.COORD_VARS.YGRID);
                ygmin = min(app.COORD_VARS.YGRID);
                ygmax = max(app.COORD_VARS.YGRID);

                % 新しいグリッドを作成
                xnew_inc = (xgmax - xgmin) / (double(nxg) * n_interp);
                ynew_inc = (ygmax - ygmin) / (double(nyg) * n_interp);
                new_xgrid = xgmin:xnew_inc:xgmax;
                new_ygrid = rot90(ygmin:ynew_inc:ygmax);

                % グリッドをメッシュに変換
                new_xgrid_mtr = repmat(new_xgrid, length(new_ygrid), 1);
                new_ygrid_mtr = repmat(new_ygrid, 1, length(new_xgrid));
                old_xgrid_mtr = repmat(app.COORD_VARS.XGRID, length(app.COORD_VARS.YGRID), 1);
                old_ygrid_mtr = repmat(flipud(rot90(app.COORD_VARS.YGRID)), 1, length(app.COORD_VARS.XGRID));
        
                % interp2関数を使って補間を行う
                cc_new = interp2(old_xgrid_mtr, old_ygrid_mtr, fliplr(app.OKADA_OUTPUT.CC), new_xgrid_mtr, new_ygrid_mtr);
                cc_new = fliplr(cc_new);
        
                % COORD_VARS.ICOORDが2でCOORD_VARS.LON_GRIDが存在する場合、新しいグリッドを作成し、OKADA_OUTPUT.CCデータを保持
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    tempx = app.COORD_VARS.XGRID;
                    tempy = app.COORD_VARS.YGRID;
                    new_xgrid(1) = app.COORD_VARS.LON_GRID(1);
                    new_xgrid(end) = app.COORD_VARS.LON_GRID(end);
                    new_ygrid(1) = app.COORD_VARS.LAT_GRID(1);
                    new_ygrid(end) = app.COORD_VARS.LAT_GRID(end);
                    cc_new = flipud(cc_new);
                end
            else
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    tempx = app.COORD_VARS.XGRID;
                    tempy = app.COORD_VARS.YGRID;
                    app.COORD_VARS.XGRID = app.COORD_VARS.LON_GRID;
                    app.COORD_VARS.YGRID = app.COORD_VARS.LAT_GRID;
                end
            end
            
            % 生成されたデータを描画する
            if app.CALC_CONTROL.SHADE_TYPE == 2
                switch app.SYSTEM_VARS.PREF(7, 1)
                    case 1
                        image(app.UIAxes, 'CData', cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1) new_xgrid(end)], 'YData', [new_ygrid(end) new_ygrid(1)]);
                        colormap(app.UIAxes, jet);
                    case 2
                        image(app.UIAxes, 'CData', cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1) new_xgrid(end)], 'YData', [new_ygrid(end) new_ygrid(1)]);
                        colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);
                    case 3
                        image(app.UIAxes, 'CData', cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1) new_xgrid(end)], 'YData', [new_ygrid(end) new_ygrid(1)]);
                        colormap(app.UIAxes, gray);
                    otherwise
                        image(app.UIAxes, 'CData', cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1) new_xgrid(end)], 'YData', [new_ygrid(end) new_ygrid(1)]);
                        colormap(app.UIAxes, jet);
                end
                set(app.UIAxes, 'YDir', 'normal');
            else
                switch app.SYSTEM_VARS.PREF(7, 1)
                    case 1
                        image(app.UIAxes, 'CData', app.OKADA_OUTPUT.CC, 'CDataMapping', 'scaled', ...
                            'XData', [app.COORD_VARS.XGRID(1) app.COORD_VARS.XGRID(end)], ...
                            'YData', [app.COORD_VARS.YGRID(end) app.COORD_VARS.YGRID(1)]);
                        colormap(app.UIAxes, jet);
                    case 2
                        image(app.UIAxes, 'CData', app.OKADA_OUTPUT.CC, 'CDataMapping', 'scaled', ...
                            'XData', [app.COORD_VARS.XGRID(1) app.COORD_VARS.XGRID(end)], ...
                            'YData', [app.COORD_VARS.YGRID(end) app.COORD_VARS.YGRID(1)]);
                        colormap(app.UIAxes, app.GRAPHICS_VARS.ANATOLIA);
                    case 3
                        image(app.UIAxes, 'CData', app.OKADA_OUTPUT.CC, 'CDataMapping', 'scaled', ...
                            'XData', [app.COORD_VARS.XGRID(1) app.COORD_VARS.XGRID(end)], ...
                            'YData', [app.COORD_VARS.YGRID(end) app.COORD_VARS.YGRID(1)]);
                        colormap(app.UIAxes, gray);
                    otherwise
                        image(app.UIAxes, 'CData', app.OKADA_OUTPUT.CC, 'CDataMapping', 'scaled', ...
                            'XData', [app.COORD_VARS.XGRID(1) app.COORD_VARS.XGRID(end)], ...
                            'YData', [app.COORD_VARS.YGRID(end) app.COORD_VARS.YGRID(1)]);
                        colormap(app.UIAxes, jet);
                end
                set(app.UIAxes, 'YDir', 'normal');
            end
            
            % カラーマップのシェーディング設定
            hold(app.UIAxes, 'on');
            shading(app.UIAxes, 'interp');
            
            % カラーバーの範囲を設定
            clim(app.UIAxes, [-N N]);
            axis(app.UIAxes, 'image');
            
            % COORD_VARS.ICOORDが2でCOORD_VARS.LON_GRIDが存在する場合、アスペクト比を設定
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                set(app.UIAxes, 'DataAspectRatio', [app.COORD_VARS.XY_RATIO 1 1]);
            end
            
            % XとYのラベルを設定
            hold(app.UIAxes, 'on');
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                xlabel(app.UIAxes, 'Longitude (degree)', 'FontSize', 14);
                ylabel(app.UIAxes, 'Latitude  (degree)', 'FontSize', 14);
            else
                xlabel(app.UIAxes, 'X (km)', 'FontSize', 14);
                ylabel(app.UIAxes, 'Y (km)', 'FontSize', 14);
            end
            
            % タイトルを設定（CALC_CONTROL.FUNC_SWITCHの値によって変わる）
            switch app.CALC_CONTROL.FUNC_SWITCH            
                case 6  % ひずみ関数の場合
                    switch app.CALC_CONTROL.STRAIN_SWITCH
                        case 1  % SXX
                            title(app.UIAxes, 'Strain SXX component', 'FontSize', 18);  
                        case 2  % SYY
                            title(app.UIAxes, 'Strain SYY component', 'FontSize', 18);  
                        case 3  % SZZ
                            title(app.UIAxes, 'Strain SZZ component', 'FontSize', 18);  
                        case 4  % SXY
                            title(app.UIAxes, 'Strain SYZ component', 'FontSize', 18);  
                        case 5  % SXZ
                            title(app.UIAxes, 'Strain SXZ component', 'FontSize', 18);  
                        case 6  % SYZ
                            title(app.UIAxes, 'Strain SXY component', 'FontSize', 18);  
                        case 7  % 膨張
                            title(app.UIAxes, 'Dilatation', 'FontSize', 18);  
                        otherwise
                            title(app.UIAxes, 'Strain', 'FontSize', 18);  
                    end          
                case 7  % せん断応力の変化
                    title(app.UIAxes, 'Shear stress change (bar, right-lat. positive)', 'FontSize', 18);
                case 8  % 垂直応力の変化
                    title(app.UIAxes, 'Umclamping stress change (bar, unclamping positive)', 'FontSize', 18);
                case 9  % クーロン応力の変化
                    title(app.UIAxes, 'Coulomb stress change (bar)', 'FontSize', 18);
                otherwise
                    % その他のケースでは何もしない
            end
            
            % カラーバーを表示
            colorbar(app.UIAxes, 'location', 'EastOutside');
            hold(app.UIAxes, 'on');
            
            %-----------    せん断応力関数のための等高線オーバーレイ    ---------------------------
            if app.CALC_CONTROL.FUNC_SWITCH == 7 || app.CALC_CONTROL.FUNC_SWITCH == 8 || app.CALC_CONTROL.FUNC_SWITCH == 9
                h = app.ContourlineCheckBox.Value;  % チェックボックスの状態を取得
                if h == 1
                    hold(app.UIAxes, 'on');
                    [~, ~] = size(app.OKADA_OUTPUT.CC);
                    cmax = max(reshape(max(app.OKADA_OUTPUT.CC), length(app.COORD_VARS.XGRID), 1));
                    cmin = min(reshape(min(app.OKADA_OUTPUT.CC), length(app.COORD_VARS.XGRID), 1));
                    a = cmax - cmin;
                    
                    if isempty(app.GRAPHICS_VARS.CONT_INTERVAL)
                        if a > 10.0
                            app.GRAPHICS_VARS.CONT_INTERVAL = 1;
                        elseif a > 5.0
                            app.GRAPHICS_VARS.CONT_INTERVAL = 0.5;
                        else
                            app.GRAPHICS_VARS.CONT_INTERVAL = 0.1;
                        end
                        % ストレス間隔のテキストフィールドに値を表示
                        app.IntervalEditField.Value = app.GRAPHICS_VARS.CONT_INTERVAL;
                        % 単位のテキストを変更
                        app.IntervalUnitLabel.Text = "bar";
                    end
            
                    % 座標系が経度・緯度の場合の処理
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.LON_GRID, app.COORD_VARS.LAT_GRID, flipud(app.OKADA_OUTPUT.CC));
                    else
                        % カルテシアン座標の場合
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.XGRID, app.COORD_VARS.YGRID, flipud(app.OKADA_OUTPUT.CC));
                    end
            
                    % 等高線の色やラベルの表示設定
                    h.LineColor = 'k';
                    h.ShowText = 'on';
                    h.LevelStep = app.GRAPHICS_VARS.CONT_INTERVAL;
                    
                    % パッチオブジェクトの線幅設定
                    i = findobj(app.UIAxes, 'Type', 'patch');
                    % i.LineWidth = 1;
                    
                    hold(app.UIAxes, 'off');
                end
            end
            
            %----------- ひずみ関数のための等高線オーバーレイ  ---------------------------
            if app.CALC_CONTROL.FUNC_SWITCH == 6
                % チェックボックスの状態を取得
                contourvalue = app.ContourlineCheckBox.Value;
                if contourvalue == 1
                    hold(app.UIAxes, 'on');
                    
                    [m, n] = size(app.OKADA_OUTPUT.CC);
                    dd = zeros(m, n);
                    % ひずみデータの正負の符号に応じて対数変換
                    dd = (app.OKADA_OUTPUT.CC ./ abs(app.OKADA_OUTPUT.CC)) .* log10(abs(app.OKADA_OUTPUT.CC));
            
                    % 最大値と最小値を計算して、それに基づいて等高線の範囲を設定
                    cmax = round(max(reshape(max(dd), length(app.COORD_VARS.XGRID), 1)));
                    cmin = round(min(reshape(min(dd), length(app.COORD_VARS.XGRID), 1)));
            
                    % 等高線の間隔が未設定の場合、最大・最小値の差から計算
                    if isempty(app.GRAPHICS_VARS.CONT_INTERVAL)
                        app.GRAPHICS_VARS.CONT_INTERVAL = cmax - cmin + 1;
                        % ストレス間隔のテキストフィールドに値を表示
                        app.IntervalEditField.Value = app.GRAPHICS_VARS.CONT_INTERVAL;
                        % 単位のテキストを変更
                        app.IntervalUnitLabel.Text = '';
                    end
            
                    % 座標系が緯度・経度の場合の処理
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % 緯度・経度グリッドに基づく等高線の描画
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.LON_GRID, app.COORD_VARS.LAT_GRID, flipud(dd));
                    else
                        % カルテシアン座標グリッドに基づく等高線の描画
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.XGRID, app.COORD_VARS.YGRID, flipud(dd));
                    end
            
                    % 等高線の色やラベルの表示設定
                    h.LineColor = 'k';
                    h.ShowText = 'on';
                    h.LevelStep = app.GRAPHICS_VARS.CONT_INTERVAL;
            
                    % 線幅の設定
                    findobj(app.UIAxes, 'Type', 'patch', 'LineWidth', 1);
                    hold(app.UIAxes, 'off');
                end
            end
            
            %----------- 垂直変位関数のための等高線オーバーレイ ---------------------------
            if app.CALC_CONTROL.FUNC_SWITCH == 4
                % VDチェックボックスがオンかどうかを確認
                if app.ASSIST_VARS.VD_CHECKED == 1
                    hold(app.UIAxes, 'on');
                    
                    [~, ~] = size(app.OKADA_OUTPUT.CC);
                    
                    % 最大値と最小値を計算して、その差を取得
                    cmax = max(reshape(max(app.OKADA_OUTPUT.CC), length(app.COORD_VARS.XGRID), 1));
                    cmin = min(reshape(min(app.OKADA_OUTPUT.CC), length(app.COORD_VARS.XGRID), 1));
                    a = cmax - cmin;
                    
                    % 等高線の間隔が未設定の場合、aの値に基づいて設定
                    if isempty(app.GRAPHICS_VARS.CONT_INTERVAL)
                        if a > 10.0
                            app.GRAPHICS_VARS.CONT_INTERVAL = 1;
                        elseif a > 5.0
                            app.GRAPHICS_VARS.CONT_INTERVAL = 0.5;
                        elseif a > 1.0
                            app.GRAPHICS_VARS.CONT_INTERVAL = 0.1;
                        else
                            app.GRAPHICS_VARS.CONT_INTERVAL = 0.01;
                        end
                        % ストレス間隔のテキストフィールドに値を表示
                        app.IntervalEditField.Value = app.GRAPHICS_VARS.CONT_INTERVAL;
                        % 単位のテキストを変更
                        app.IntervalUnitLabel.Text = "m";
                    end
                    
                    % 座標系が緯度・経度の場合の処理
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        % 緯度・経度グリッドに基づく等高線の描画
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.LON_GRID, app.COORD_VARS.LAT_GRID, flipud(app.OKADA_OUTPUT.CC));
                    else
                        % カルテシアン座標グリッドに基づく等高線の描画
                        [~, h] = contour(app.UIAxes, app.COORD_VARS.XGRID, app.COORD_VARS.YGRID, flipud(app.OKADA_OUTPUT.CC));
                    end
                    
                    % 等高線のプロパティを設定
                    % 等高線の色やラベルの表示設定
                    h.LineColor = 'k';
                    h.ShowText = 'on';
                    h.LevelStep = app.GRAPHICS_VARS.CONT_INTERVAL;

                    % パッチオブジェクトの線幅を設定
                    i = findobj(app.UIAxes, 'Type', 'patch');
                    i.LineWidth = 1;

                    hold(app.UIAxes, 'off');
                end
            end
            
            %-----------    グリッドを元の値に戻す    ---------------------------
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                app.COORD_VARS.XGRID = tempx;
                app.COORD_VARS.YGRID = tempy;
            end
            
            %----------- データとファイル名のスタンプ    --------------
            hold(app.UIAxes, 'on');  % AppDesignerのUIAxesでの保持
            
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                r = (app.COORD_VARS.LAT_GRID(end) - app.COORD_VARS.LAT_GRID(1)) / (app.COORD_VARS.LON_GRID(end) - app.COORD_VARS.LON_GRID(1));
                if r > 1
                    r = 1;
                end
                % x = app.COORD_VARS.LON_GRID(end) + (app.COORD_VARS.LON_GRID(end) - app.COORD_VARS.LON_GRID(1)) / (10.0 / r);
                x = app.COORD_VARS.LON_GRID(1);
                y = app.COORD_VARS.LAT_GRID(1) - ((app.COORD_VARS.LAT_GRID(end) - app.COORD_VARS.LAT_GRID(1)) / 10.0) / r;
                lsp = ((app.COORD_VARS.LAT_GRID(end) - app.COORD_VARS.LAT_GRID(1)) + (app.COORD_VARS.LON_GRID(end) - app.COORD_VARS.LON_GRID(1))) / 75.0;
            else
                r = (app.COORD_VARS.YGRID(end) - app.COORD_VARS.YGRID(1)) / (app.COORD_VARS.XGRID(end) - app.COORD_VARS.XGRID(1));
                if r > 1
                    r = 1;
                end
                % x = app.COORD_VARS.XGRID(end) + (app.COORD_VARS.XGRID(end) - app.COORD_VARS.XGRID(1)) / (10.0 / r);
                x = app.COORD_VARS.XGRID(1);
                y = app.COORD_VARS.YGRID(1) - ((app.COORD_VARS.YGRID(end) - app.COORD_VARS.YGRID(1)) / 10.0) / r;
                lsp = ((app.COORD_VARS.YGRID(end) - app.COORD_VARS.YGRID(1)) + (app.COORD_VARS.XGRID(end) - app.COORD_VARS.XGRID(1))) / 75.0;
            end
            
            if isempty(app.ASSIST_VARS.CALC_DEPTH_RANGE)
                app.ASSIST_VARS.CALC_DEPTH_RANGE = 0:5:10;
            end
            
            % タイトルやラベル、計算情報を描画
            try
                dm1 = app.CALC_CONTROL.STRIKE(1);
                dm2 = app.CALC_CONTROL.DIP(1);
                dm3 = app.CALC_CONTROL.RAKE(1);
            catch
                dm1 = 90;
                dm2 = 90;
                dm3 = 0;
            end
            
            % record_stamp関数を呼び出して、UIAxesにスタンプを描画
            % 必要なオプションだけ指定（あとは [] に補完される）
            partialOpts = struct( ...
                'FileName', app.SYSTEM_VARS.INPUT_FILE, ...
                'LineSpace', lsp, ...
                'FunctionType', app.CALC_CONTROL.FUNC_SWITCH, ...
                'StressType', app.CALC_CONTROL.STRESS_TYPE, ...
                'DepthType', app.CALC_CONTROL.DEPTH_RANGE_TYPE, ...
                'DepthMin', app.ASSIST_VARS.CALC_DEPTH_RANGE(1), ...
                'DepthMax', app.ASSIST_VARS.CALC_DEPTH_RANGE(end), ...
                'Friction', app.INPUT_VARS.FRIC, ...
                'Depth', app.INPUT_VARS.CALC_DEPTH, ...
                'Strike', dm1, ...
                'Dip', dm2, ...
                'Rake', dm3, ...
                'FontSize', 9 ...
            );
            % 全フィールドを埋めた構造体を生成
            opts = buildStampOptions(app, partialOpts);
            % record_stamp に構造体を渡す
            record_stamp(app, app.UIAxes, x, y, opts);
            
            app.ASSIST_VARS.CALC_DEPTH_RANGE = [];
        end


        % function Okada_halfspace(app)
        %     % =====================================================================
        %     %                Okada_halfspace(app)
        %     % =====================================================================
        %     % Okada_halfspace: 岡田1992年のサブルーチンを使用して変位の計算を行う
        % 
        %     % 岡田モデルのパラメータ初期化
        %     app.CALC_CONTROL.IIRET = 0;
        %     alpha = 1.0 / (2.0 * (1.0 - app.INPUT_VARS.POIS));
        %     z = app.INPUT_VARS.CALC_DEPTH * (-1.0);
        % 
        %     % グリッド情報の設定
        %     xstart = app.INPUT_VARS.GRID(1, 1);
        %     ystart = app.INPUT_VARS.GRID(2, 1);
        %     xinc = app.INPUT_VARS.GRID(5, 1);
        %     yinc = app.INPUT_VARS.GRID(6, 1);
        % 
        %     if app.SECTION_VARS.SEC_FLAG == 1
        %         % cross section
        %         % 断面計算の場合
        %         temp = zeros(1, 2);
        %         temp(1) = app.ASSIST_VARS.NXINC;
        %         temp(2) = app.ASSIST_VARS.NYINC;
        %         app.ASSIST_VARS.NXINC = app.ASSIST_VARS.NSEC;
        %         app.ASSIST_VARS.NYINC = abs(app.ASSIST_VARS.NDEPTH);
        %     else
        %         % map view
        %         % マップビュー計算の場合
        %         app.ASSIST_VARS.NXINC = length(app.COORD_VARS.XGRID);
        %         app.ASSIST_VARS.NYINC = length(app.COORD_VARS.YGRID);
        %     end
        % 
        %     % 計算用変数の初期化
        %     n = app.ASSIST_VARS.NXINC * app.ASSIST_VARS.NYINC;
        %     app.ASSIST_VARS.N_CELL = n;
        %     UX = zeros(n, 1, 'double');
        %     UY = zeros(n, 1, 'double');
        %     UZ = zeros(n, 1, 'double');
        %     UXX = zeros(n, 1, 'double');
        %     UYX = zeros(n, 1, 'double');
        %     UZX = zeros(n, 1, 'double');
        %     UXY = zeros(n, 1, 'double');
        %     UYY = zeros(n, 1, 'double');
        %     UZY = zeros(n, 1, 'double');
        %     UXZ = zeros(n, 1, 'double');
        %     UYZ = zeros(n, 1, 'double');
        %     UZZ = zeros(n, 1, 'double');
        %     IRET = zeros(n, 1);
        %     app.ASSIST_VARS.XYCOORD = zeros(n, 2, 'double');
        % 
        %     if app.SECTION_VARS.SEC_FLAG == 1
        %         % for cross section calc (to keep the result of mapview)
        %         aax = reshape(app.ASSIST_VARS.AX, n, 1);
        %         aay = reshape(app.ASSIST_VARS.AY, n, 1);
        %         app.OKADA_OUTPUT.DC3DS = zeros(n, 14, 'double');
        %         app.OKADA_OUTPUT.DC3DS0 = zeros(n, 14, 'double');
        %     else
        %         % for mapview calc.
        %         app.OKADA_OUTPUT.DC3D = zeros(n, 14, 'double');
        %         app.OKADA_OUTPUT.DC3D0 = zeros(n, 14, 'double');
        %     end
        % 
        %     % ==========================================================
        %     % uiprogressdlg 初期化
        %     % ==========================================================
        %     if ~isfield(app.ASSIST_VARS, 'ProgressDlg') || ~isvalid(app.ASSIST_VARS.ProgressDlg)
        %         d = uiprogressdlg(app.main_menu_window, ...
        %             'Title', 'Calculating deformation', ...
        %             'Message', 'Please wait...', ...
        %             'Indeterminate', 'off', ...
        %             'Cancelable', 'off');
        %         app.ASSIST_VARS.ProgressDlg = d;  % 新規登録
        %     else
        %         d = app.ASSIST_VARS.ProgressDlg;  % 既存のを再利用（深度ループなどで）
        %     end
        % 
        %     % カウンタの初期化
        %     if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
        %         ncount = 0;
        %         ntotal = double(app.ASSIST_VARS.NYINC) * ...
        %                  double(app.ASSIST_VARS.NXINC) * ...
        %                  double(app.INPUT_VARS.NUM);
        %     else
        %         if app.INPUT_VARS.CALC_DEPTH == app.ASSIST_VARS.CALC_DEPTH_RANGE(1)
        %             app.ASSIST_VARS.NCOUNT = 0;
        %         end
        %         ntotal = double(app.ASSIST_VARS.NYINC) * ...
        %                  double(app.ASSIST_VARS.NXINC) * ...
        %                  double(app.INPUT_VARS.NUM) * ...
        %                  double(length(app.ASSIST_VARS.CALC_DEPTH_RANGE));
        %     end
        % 
        %     % % 進行バーの初期化
        %     % if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
        %     %     % h = uiprogressdlg(app.main_menu_window, 'Title', 'Calculating deformation', 'Message', 'Please wait...');
        %     %     h = waitbar(0, 'Calculating deformation... please wait...');
        %     %     % 進捗バー用カウンター for waitbar counter
        %     %     ncount = 0;  
        %     %     ntotal = app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC * int32(app.INPUT_VARS.NUM);
        %     % else
        %     %     % 修正必要？
        %     %     if app.INPUT_VARS.CALC_DEPTH == app.ASSIST_VARS.CALC_DEPTH_RANGE(1)
        %     %         H_WAITBR = waitbar(0,'Calculating deformation... please wait...');
        %     %         % fr = waitbar(0, 'Calculating deformation... please wait...');
        %     %         app.ASSIST_VARS.NCOUNT = 0; % for waitbar counter
        %     %     end
        %     %     ntotal = app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC * int16(app.INPUT_VARS.NUM) * length(app.ASSIST_VARS.CALC_DEPTH_RANGE);
        %     % end
        % 
        %     % ==========================================================
        %     % 各要素について計算
        %     % ==========================================================
        %     for ii = 1:app.INPUT_VARS.NUM
        %         % depth should be positive
        %         depth = (app.INPUT_VARS.ELEMENT(ii, 8) + app.INPUT_VARS.ELEMENT(ii, 9)) / 2.0;
        % 
        %         for k = 1:app.ASSIST_VARS.NXINC
        %             xx = xstart + double(k - 1) * xinc;
        %             for m = 1:app.ASSIST_VARS.NYINC
        %                 yy = ystart + double(m - 1) * yinc;
        %                 nn = m + (k - 1) * app.ASSIST_VARS.NYINC;
        %                 if app.SECTION_VARS.SEC_FLAG == 1
        %                     app.ASSIST_VARS.XYCOORD(nn, 1) = aax(nn, 1);
        %                     app.ASSIST_VARS.XYCOORD(nn, 2) = aay(nn, 1);
        %                 else
        %                     app.ASSIST_VARS.XYCOORD(nn, 1) = xx;
        %                     app.ASSIST_VARS.XYCOORD(nn, 2) = yy;
        %                 end
        % 
        %                 % 各ループ内で進捗率を更新
        %                 if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
        %                     ncount = ncount + 1;
        %                     fr = double(ncount) / ntotal;
        %                 else
        %                     app.ASSIST_VARS.NCOUNT = app.ASSIST_VARS.NCOUNT + 1;
        %                     fr = double(app.ASSIST_VARS.NCOUNT) / ntotal;
        %                 end
        % 
        %                 % 安全に値を更新（[0,1]の範囲にクリップ）
        %                 if isvalid(app.ASSIST_VARS.ProgressDlg)
        %                     app.ASSIST_VARS.ProgressDlg.Value = min(1, max(0, fr));
        %                 end
        %                 % % 上記の安全に値を更新でエラーが出た場合、以下に修正必要かもしれない。
        %                 % % 無効値の防止（NaN, Inf, ベクトル, 空配列）
        %                 % if isempty(fr) || ~isscalar(fr) || isnan(fr) || isinf(fr)
        %                 %     fr = 0; % fallback
        %                 % end
        %                 % % 安全に範囲をクリップして更新
        %                 % if isvalid(app.ASSIST_VARS.ProgressDlg)
        %                 %     app.ASSIST_VARS.ProgressDlg.Value = max(0, min(1, fr));
        %                 % end
        %             end
        %         end
        % 
        %         if ii == app.INPUT_VARS.NUM && isvalid(d)
        %             d.Value = 1.0;
        %             d.Message = 'Now making image... please wait';
        %         end
        % 
        %         [c1, c2, c3, c4] = coord_conversion(app, ...
        %             app.ASSIST_VARS.XYCOORD(:, 1), app.ASSIST_VARS.XYCOORD(:, 2), ...
        %             app.INPUT_VARS.ELEMENT(ii, 1), app.INPUT_VARS.ELEMENT(ii, 2), ...
        %             app.INPUT_VARS.ELEMENT(ii, 3), app.INPUT_VARS.ELEMENT(ii, 4), ...
        %             app.INPUT_VARS.ELEMENT(ii, 8), app.INPUT_VARS.ELEMENT(ii, 9), ...
        %             app.INPUT_VARS.ELEMENT(ii, 7));
        %         format long;
        % 
        %         % ----------------------------------------------------------
        %         % 岡田モデルのパラメータ設定
        %         % ----------------------------------------------------------
        %         aa = zeros(n, 1, 'double') + double(alpha);
        %         if app.SECTION_VARS.SEC_FLAG == 1
        %             zz = zeros(n, 1, 'double') + reshape(double(app.ASSIST_VARS.AZ), n, 1);
        %         else
        %             zz = zeros(n, 1, 'double') + double(z);
        %         end
        % 
        %         dp = zeros(n, 1, 'double') + double(depth);
        %         e7 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 7));
        %         e5 = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5)); % left-lat positive in Okada's code
        %         e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
        %         zr = zeros(n, 1, 'double');
        % 
        %         if app.INPUT_VARS.KODE(ii) == 200
        %             e5 = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5));
        %             e6 = zeros(n, 1, 'double');
        %             zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
        %         elseif app.INPUT_VARS.KODE(ii) == 300
        %             e5 = zeros(n, 1, 'double');
        %             e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
        %             zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 5));
        %         end
        % 
        %         x = zeros(n, 1, 'double') + double(c1);
        %         y = zeros(n, 1, 'double') + double(c2);
        %         al = zeros(n, 1, 'double') + double(c3);
        %         aw = zeros(n, 1, 'double') + double(c4);
        % 
        %         % for point source calculation (unit: input m**3, output m**3/km**2 -> m => thus ./(1.0E+3)
        %         if app.INPUT_VARS.KODE(ii) == 400
        %             aw = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5));
        %             e5 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
        %             e6 = zeros(n, 1, 'double');
        %             zr = zeros(n, 1, 'double');
        %         elseif app.INPUT_VARS.KODE(ii) == 500
        %             aw = zeros(n, 1, 'double');
        %             e5 = zeros(n, 1, 'double');
        %             e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 5));
        %             zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
        %         end 
        %         a = [aa x y zz dp e7 al al aw aw e5 e6 zr];
        % 
        % 
        %         % ----------------------------------------------------------
        %         % 岡田サブルーチン呼び出し
        %         % ----------------------------------------------------------
        %         if (app.INPUT_VARS.ELEMENT(ii, 5) ~= 0.0 || app.INPUT_VARS.ELEMENT(ii, 6) ~= 0.0 || ii == 1)
        %             % to skip calc for zero slip
        %             if app.INPUT_VARS.KODE(ii) == 100 || app.INPUT_VARS.KODE(ii) == 200 || app.INPUT_VARS.KODE(ii) == 300
        %                 [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1),...
        %                     UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), IRET(:, 1)] = ...
        %                     Okada_DC3D(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 7), a(:, 8), a(:, 9), a(:, 10), a(:, 11), a(:, 12), a(:, 13));
        % 
        %             elseif app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
        %                 [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1),...
        %                     UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), IRET(:, 1)] = ...
        %                     Okada_DC3D0(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 10), a(:, 11), a(:, 12), a(:, 13));
        %             end
        % 
        %             % to detect singular point
        %             app.CALC_CONTROL.IIRET = app.CALC_CONTROL.IIRET + sum(IRET);
        % 
        %             % cell to matrices
        %             X = a(:, 2);
        %             Y = a(:, 3);
        %             Z = a(:, 4);
        % 
        %             % Displacement Conversion from Okada's field to Given field
        %             sw = sqrt((app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2)) ^ 2 + (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) ^ 2);
        %             sina = (app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2)) / double(sw);
        %             cosa = (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) / double(sw);
        %             UXG = UX * cosa - UY * sina;
        %             UYG = UX * sina + UY * cosa;
        %             UZG = UZ;
        % 
        %             % for point source calculation (unit: input m**3, output m**3/km**2 -> m => thus ./(1.0E+3)
        %             if app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
        %                 UXG = UXG ./ 1000.0;
        %                 UYG = UYG ./ 1000.0;
        %                 UZG = UZG ./ 1000.0;
        %             end
        % 
        %             % Strain to Stress for the normal component
        %             sk = app.INPUT_VARS.YOUNG / (1.0 + app.INPUT_VARS.POIS);
        %             gk = app.INPUT_VARS.POIS / (1.0 - 2.0 * app.INPUT_VARS.POIS);
        %             vol = UXX + UYY + UZZ;
        % 
        %             if app.CALC_CONTROL.FUNC_SWITCH ~= 6
        %                 % converted to Stress
        %                 % caution! strain dimension is from x,y,z coordinate (should be /1000)
        %                 sxx = sk * (gk * vol + UXX) * 0.001;
        %                 syy = sk * (gk * vol + UYY) * 0.001;
        %                 szz = sk * (gk * vol + UZZ) * 0.001;
        %                 sxy = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UXY + UYX) * 0.001;
        %                 sxz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UXZ + UZX) * 0.001;
        %                 syz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UYZ + UZY) * 0.001;
        %             else
        %                 % remain strain
        %                 % caution! strain dimension is from x,y,z coordinate (should be /1000)
        %                 sxx = UXX * 0.001;
        %                 syy = UYY * 0.001;
        %                 szz = UZZ * 0.001;
        %                 sxy = (UXY + UYX) / 2.0 * 0.001;
        %                 sxz = (UXZ + UZX) / 2.0 * 0.001;
        %                 syz = (UYZ + UZY) / 2.0 * 0.001;
        %             end
        % 
        %             ssxx = reshape(sxx, 1, n);
        %             ssyy = reshape(syy, 1, n);
        %             sszz = reshape(szz, 1, n);
        %             ssxy = reshape(sxy, 1, n);
        %             ssxz = reshape(sxz, 1, n);
        %             ssyz = reshape(syz, 1, n);
        %             s0 = [ssxx; ssyy; sszz; ssyz; ssxz; ssxy];
        % 
        %             % Strain Conversion from Okada's field to Given field
        %             s1 = tensor_trans(app, sina, cosa, s0, n);
        % 
        %             SXX = reshape(s1(1, :), n, 1);
        %             SYY = reshape(s1(2, :), n, 1);
        %             SZZ = reshape(s1(3, :), n, 1);
        %             SYZ = reshape(s1(4, :), n, 1);
        %             SXZ = reshape(s1(5, :), n, 1);
        %             SXY = reshape(s1(6, :), n, 1);
        % 
        %             if ii == 1
        %                 if app.SECTION_VARS.SEC_FLAG == 1
        %                     app.OKADA_OUTPUT.DC3DS0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
        %                 else
        %                     app.OKADA_OUTPUT.DC3D0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
        %                 end
        %             else
        %                 if app.SECTION_VARS.SEC_FLAG == 1
        %                     app.OKADA_OUTPUT.DC3DS0 = horzcat(zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1),...
        %                         UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
        %                 else
        %                     app.OKADA_OUTPUT.DC3D0 = horzcat(zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1),...
        %                         UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
        %                 end
        %             end
        %             if app.SECTION_VARS.SEC_FLAG == 1
        %                 app.OKADA_OUTPUT.DC3DS = app.OKADA_OUTPUT.DC3DS + app.OKADA_OUTPUT.DC3DS0;
        %             else
        %                 app.OKADA_OUTPUT.DC3D = app.OKADA_OUTPUT.DC3D + app.OKADA_OUTPUT.DC3D0;
        %             end
        %         end
        %         % to skip calc for zero slip
        %     end
        % 
        % 
        %     % ==========================================================
        %     % 終了処理
        %     % ==========================================================
        %     if isfield(app.ASSIST_VARS, 'ProgressDlg') && isvalid(app.ASSIST_VARS.ProgressDlg)
        %         app.ASSIST_VARS.ProgressDlg.Message = 'Completed.';
        %         pause(0.3);
        %         close(app.ASSIST_VARS.ProgressDlg);
        %         app.ASSIST_VARS = rmfield(app.ASSIST_VARS, 'ProgressDlg'); % 削除して再利用を防ぐ
        %     end
        % 
        %     % singular point 警告
        %     if app.CALC_CONTROL.IIRET > 0
        %         disp('Warning! At least one calculation node is on one of the singular points.');
        %         disp('Shift all nodes very slightly to escape the point.');
        %         showMessage(app, {'Warning! At least one calculation node is on one of the singular points.', ...
        %             'Shift all nodes very slightly to escape the point.'}, false);
        %         uialert(app.main_menu_window, ...
        %             'A calc. point is on a fault! A node met a singular point!', ...
        %             'Warning!');
        %     end
        % 
        %     if app.SECTION_VARS.SEC_FLAG == 1
        %         app.ASSIST_VARS.NXINC = temp(1);
        %         app.ASSIST_VARS.NYINC = temp(2);
        %     end
        % 
        % end

        function Okada_halfspace(app)
            % =====================================================================
            %                Okada_halfspace(app)
            % =====================================================================
            % Okada_halfspace: 岡田1992年のサブルーチンを使用して変位の計算を行う

            % 岡田モデルのパラメータ初期化
            app.CALC_CONTROL.IIRET = 0;
            alpha = 1.0 / (2.0 * (1.0 - app.INPUT_VARS.POIS));
            z = app.INPUT_VARS.CALC_DEPTH * (-1.0);
    
            % グリッド情報の設定
            xstart = app.INPUT_VARS.GRID(1, 1);
            ystart = app.INPUT_VARS.GRID(2, 1);
            xinc = app.INPUT_VARS.GRID(5, 1);
            yinc = app.INPUT_VARS.GRID(6, 1);
            
            if app.SECTION_VARS.SEC_FLAG == 1
                % cross section
                % 断面計算の場合
                temp = zeros(1, 2);
                temp(1) = app.ASSIST_VARS.NXINC;
                temp(2) = app.ASSIST_VARS.NYINC;
                app.ASSIST_VARS.NXINC = app.ASSIST_VARS.NSEC;
                app.ASSIST_VARS.NYINC = abs(app.ASSIST_VARS.NDEPTH);
            else
                % map view
                % マップビュー計算の場合
                app.ASSIST_VARS.NXINC = length(app.COORD_VARS.XGRID);
                app.ASSIST_VARS.NYINC = length(app.COORD_VARS.YGRID);
            end
        
            % 計算用変数の初期化
            n = app.ASSIST_VARS.NXINC * app.ASSIST_VARS.NYINC;
            app.ASSIST_VARS.N_CELL = n;
            UX = zeros(n, 1, 'double');
            UY = zeros(n, 1, 'double');
            UZ = zeros(n, 1, 'double');
            UXX = zeros(n, 1, 'double');
            UYX = zeros(n, 1, 'double');
            UZX = zeros(n, 1, 'double');
            UXY = zeros(n, 1, 'double');
            UYY = zeros(n, 1, 'double');
            UZY = zeros(n, 1, 'double');
            UXZ = zeros(n, 1, 'double');
            UYZ = zeros(n, 1, 'double');
            UZZ = zeros(n, 1, 'double');
            IRET = zeros(n, 1);
            app.ASSIST_VARS.XYCOORD = zeros(n, 2, 'double');
        
            if app.SECTION_VARS.SEC_FLAG == 1
                % for cross section calc (to keep the result of mapview)
                aax = reshape(app.ASSIST_VARS.AX, n, 1);
                aay = reshape(app.ASSIST_VARS.AY, n, 1);
                app.OKADA_OUTPUT.DC3DS = zeros(n, 14, 'double');
                app.OKADA_OUTPUT.DC3DS0 = zeros(n, 14, 'double');
            else
                % for mapview calc.
                app.OKADA_OUTPUT.DC3D = zeros(n, 14, 'double');
                app.OKADA_OUTPUT.DC3D0 = zeros(n, 14, 'double');
            end
        
            % ==========================================================
            % uiprogressdlg 初期化
            % ==========================================================
            if ~isfield(app.ASSIST_VARS, 'ProgressDlg') || ~isvalid(app.ASSIST_VARS.ProgressDlg)
                d = uiprogressdlg(app.main_menu_window, ...
                    'Title', 'Calculating deformation', ...
                    'Message', 'Please wait...', ...
                    'Indeterminate', 'off', ...
                    'Cancelable', 'off');
                app.ASSIST_VARS.ProgressDlg = d;  % 新規登録
            else
                d = app.ASSIST_VARS.ProgressDlg;  % 既存のを再利用（深度ループなどで）
            end
            
            % カウンタの初期化
            if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
                ncount = 0;
                ntotal = double(app.ASSIST_VARS.NYINC) * ...
                         double(app.ASSIST_VARS.NXINC) * ...
                         double(app.INPUT_VARS.NUM);
            else
                if app.INPUT_VARS.CALC_DEPTH == app.ASSIST_VARS.CALC_DEPTH_RANGE(1)
                    app.ASSIST_VARS.NCOUNT = 0;
                end
                ntotal = double(app.ASSIST_VARS.NYINC) * ...
                         double(app.ASSIST_VARS.NXINC) * ...
                         double(app.INPUT_VARS.NUM) * ...
                         double(length(app.ASSIST_VARS.CALC_DEPTH_RANGE));
            end

            % % 進行バーの初期化
            % if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
            %     % h = uiprogressdlg(app.main_menu_window, 'Title', 'Calculating deformation', 'Message', 'Please wait...');
            %     h = waitbar(0, 'Calculating deformation... please wait...');
            %     % 進捗バー用カウンター for waitbar counter
            %     ncount = 0;  
            %     ntotal = app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC * int32(app.INPUT_VARS.NUM);
            % else
            %     % 修正必要？
            %     if app.INPUT_VARS.CALC_DEPTH == app.ASSIST_VARS.CALC_DEPTH_RANGE(1)
            %         H_WAITBR = waitbar(0,'Calculating deformation... please wait...');
            %         % fr = waitbar(0, 'Calculating deformation... please wait...');
            %         app.ASSIST_VARS.NCOUNT = 0; % for waitbar counter
            %     end
            %     ntotal = app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC * int16(app.INPUT_VARS.NUM) * length(app.ASSIST_VARS.CALC_DEPTH_RANGE);
            % end
        
            % 計算で用いる弾性定数の事前計算
            E   = app.INPUT_VARS.YOUNG;
            nu  = app.INPUT_VARS.POIS;
            sk  = E / (1.0 + nu);
            gk  = nu / (1.0 - 2.0 * nu);
            shearCoef = E / (2.0 * (1.0 + nu));

            % uiprogressdlg 更新頻度の間引き
            % ループの外で
            updateStride = max(1, floor(n / 500));  % 画面上は ~500ステップ程度に見えるように
            
            % ==========================================================
            % 各要素について計算
            % ==========================================================
            for ii = 1:app.INPUT_VARS.NUM
                % depth should be positive
                depth = (app.INPUT_VARS.ELEMENT(ii, 8) + app.INPUT_VARS.ELEMENT(ii, 9)) / 2.0;
            
                for k = 1:app.ASSIST_VARS.NXINC
                    xx = xstart + double(k - 1) * xinc;
                    for m = 1:app.ASSIST_VARS.NYINC
                        yy = ystart + double(m - 1) * yinc;
                        nn = m + (k - 1) * app.ASSIST_VARS.NYINC;
                        if app.SECTION_VARS.SEC_FLAG == 1
                            app.ASSIST_VARS.XYCOORD(nn, 1) = aax(nn, 1);
                            app.ASSIST_VARS.XYCOORD(nn, 2) = aay(nn, 1);
                        else
                            app.ASSIST_VARS.XYCOORD(nn, 1) = xx;
                            app.ASSIST_VARS.XYCOORD(nn, 2) = yy;
                        end
            
                        % 各ループ内で進捗率を更新
                        if app.CALC_CONTROL.DEPTH_RANGE_TYPE == 0
                            ncount = ncount + 1;
                            fr = double(ncount) / ntotal;
                        else
                            app.ASSIST_VARS.NCOUNT = app.ASSIST_VARS.NCOUNT + 1;
                            fr = double(app.ASSIST_VARS.NCOUNT) / ntotal;
                        end
                        
                        % 安全に値を更新（[0,1]の範囲にクリップ）
                        if isvalid(app.ASSIST_VARS.ProgressDlg)
                            if mod(ncount, updateStride) == 0 || ncount == ntotal
                                app.ASSIST_VARS.ProgressDlg.Value = min(1, max(0, fr));
                            end
                        end
                        % % 上記の安全に値を更新でエラーが出た場合、以下に修正必要かもしれない。
                        % % 無効値の防止（NaN, Inf, ベクトル, 空配列）
                        % if isempty(fr) || ~isscalar(fr) || isnan(fr) || isinf(fr)
                        %     fr = 0; % fallback
                        % end
                        % % 安全に範囲をクリップして更新
                        % if isvalid(app.ASSIST_VARS.ProgressDlg)
                        %     app.ASSIST_VARS.ProgressDlg.Value = max(0, min(1, fr));
                        % end
                    end
                end
            
                if ii == app.INPUT_VARS.NUM && isvalid(d)
                    d.Value = 1.0;
                    d.Message = 'Now making image... please wait';
                end
            
                [c1, c2, c3, c4] = coord_conversion(app, ...
                    app.ASSIST_VARS.XYCOORD(:, 1), app.ASSIST_VARS.XYCOORD(:, 2), ...
                    app.INPUT_VARS.ELEMENT(ii, 1), app.INPUT_VARS.ELEMENT(ii, 2), ...
                    app.INPUT_VARS.ELEMENT(ii, 3), app.INPUT_VARS.ELEMENT(ii, 4), ...
                    app.INPUT_VARS.ELEMENT(ii, 8), app.INPUT_VARS.ELEMENT(ii, 9), ...
                    app.INPUT_VARS.ELEMENT(ii, 7));
                % format long;

                % ----------------------------------------------------------
                % 岡田モデルのパラメータ設定
                % ----------------------------------------------------------
                aa = zeros(n, 1, 'double') + double(alpha);
                if app.SECTION_VARS.SEC_FLAG == 1
                    zz = zeros(n, 1, 'double') + reshape(double(app.ASSIST_VARS.AZ), n, 1);
                else
                    zz = zeros(n, 1, 'double') + double(z);
                end
        
                dp = zeros(n, 1, 'double') + double(depth);
                e7 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 7));
                e5 = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5)); % left-lat positive in Okada's code
                e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
                zr = zeros(n, 1, 'double');
        
                if app.INPUT_VARS.KODE(ii) == 200
                    e5 = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5));
                    e6 = zeros(n, 1, 'double');
                    zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
                elseif app.INPUT_VARS.KODE(ii) == 300
                    e5 = zeros(n, 1, 'double');
                    e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
                    zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 5));
                end
        
                x = zeros(n, 1, 'double') + double(c1);
                y = zeros(n, 1, 'double') + double(c2);
                al = zeros(n, 1, 'double') + double(c3);
                aw = zeros(n, 1, 'double') + double(c4);
        
                % for point source calculation (unit: input m**3, output m**3/km**2 -> m => thus ./(1.0E+3)
                if app.INPUT_VARS.KODE(ii) == 400
                    aw = zeros(n, 1, 'double') - double(app.INPUT_VARS.ELEMENT(ii, 5));
                    e5 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
                    e6 = zeros(n, 1, 'double');
                    zr = zeros(n, 1, 'double');
                elseif app.INPUT_VARS.KODE(ii) == 500
                    aw = zeros(n, 1, 'double');
                    e5 = zeros(n, 1, 'double');
                    e6 = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 5));
                    zr = zeros(n, 1, 'double') + double(app.INPUT_VARS.ELEMENT(ii, 6));
                end 
                a = [aa x y zz dp e7 al al aw aw e5 e6 zr];
        

                % ----------------------------------------------------------
                % 岡田サブルーチン呼び出し
                % ----------------------------------------------------------
                if (app.INPUT_VARS.ELEMENT(ii, 5) ~= 0.0 || app.INPUT_VARS.ELEMENT(ii, 6) ~= 0.0 || ii == 1)
                    % to skip calc for zero slip
                    if app.INPUT_VARS.KODE(ii) == 100 || app.INPUT_VARS.KODE(ii) == 200 || app.INPUT_VARS.KODE(ii) == 300
                        [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1),...
                            UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), IRET(:, 1)] = ...
                            Okada_DC3D(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 7), a(:, 8), a(:, 9), a(:, 10), a(:, 11), a(:, 12), a(:, 13));

                    elseif app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
                        [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1),...
                            UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), IRET(:, 1)] = ...
                            Okada_DC3D0(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 10), a(:, 11), a(:, 12), a(:, 13));
                    end
        
                    % to detect singular point
                    app.CALC_CONTROL.IIRET = app.CALC_CONTROL.IIRET + sum(IRET);
        
                    % cell to matrices
                    X = a(:, 2);
                    Y = a(:, 3);
                    Z = a(:, 4);
        
                    % Displacement Conversion from Okada's field to Given field
                    sw = sqrt((app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2)) ^ 2 + (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) ^ 2);
                    sina = (app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2)) / double(sw);
                    cosa = (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) / double(sw);
                    UXG = UX * cosa - UY * sina;
                    UYG = UX * sina + UY * cosa;
                    UZG = UZ;
        
                    % for point source calculation (unit: input m**3, output m**3/km**2 -> m => thus ./(1.0E+3)
                    if app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
                        UXG = UXG ./ 1000.0;
                        UYG = UYG ./ 1000.0;
                        UZG = UZG ./ 1000.0;
                    end
        
                    % Strain to Stress for the normal component
                    vol = UXX + UYY + UZZ;

                    if app.CALC_CONTROL.FUNC_SWITCH ~= 6
                        % converted to Stress
                        % caution! strain dimension is from x,y,z coordinate (should be /1000)
                        sxx = sk * (gk * vol + UXX) * 0.001;
                        syy = sk * (gk * vol + UYY) * 0.001;
                        szz = sk * (gk * vol + UZZ) * 0.001;
                        sxy = shearCoef * (UXY + UYX) * 0.001;
                        sxz = shearCoef * (UXZ + UZX) * 0.001;
                        syz = shearCoef * (UYZ + UZY) * 0.001;
                    else
                        % remain strain
                        % caution! strain dimension is from x,y,z coordinate (should be /1000)
                        sxx = UXX * 0.001;
                        syy = UYY * 0.001;
                        szz = UZZ * 0.001;
                        sxy = (UXY + UYX) / 2.0 * 0.001;
                        sxz = (UXZ + UZX) / 2.0 * 0.001;
                        syz = (UYZ + UZY) / 2.0 * 0.001;
                    end
        
                    ssxx = reshape(sxx, 1, n);
                    ssyy = reshape(syy, 1, n);
                    sszz = reshape(szz, 1, n);
                    ssxy = reshape(sxy, 1, n);
                    ssxz = reshape(sxz, 1, n);
                    ssyz = reshape(syz, 1, n);
                    s0 = [ssxx; ssyy; sszz; ssyz; ssxz; ssxy];

                    % Strain Conversion from Okada's field to Given field
                    s1 = tensor_trans(app, sina, cosa, s0, n);
        
                    SXX = reshape(s1(1, :), n, 1);
                    SYY = reshape(s1(2, :), n, 1);
                    SZZ = reshape(s1(3, :), n, 1);
                    SYZ = reshape(s1(4, :), n, 1);
                    SXZ = reshape(s1(5, :), n, 1);
                    SXY = reshape(s1(6, :), n, 1);
        
                    if ii == 1
                        if app.SECTION_VARS.SEC_FLAG == 1
                            app.OKADA_OUTPUT.DC3DS0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        else
                            app.OKADA_OUTPUT.DC3D0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        end
                    else
                        if app.SECTION_VARS.SEC_FLAG == 1
                            app.OKADA_OUTPUT.DC3DS0 = horzcat(zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1),...
                                UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        else
                            app.OKADA_OUTPUT.DC3D0 = horzcat(zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1), zeros(n, 1),...
                                UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        end
                    end
                    if app.SECTION_VARS.SEC_FLAG == 1
                        app.OKADA_OUTPUT.DC3DS = app.OKADA_OUTPUT.DC3DS + app.OKADA_OUTPUT.DC3DS0;
                    else
                        app.OKADA_OUTPUT.DC3D = app.OKADA_OUTPUT.DC3D + app.OKADA_OUTPUT.DC3D0;
                    end
                end
                % to skip calc for zero slip
            end


            % ==========================================================
            % 終了処理
            % ==========================================================
            if isfield(app.ASSIST_VARS, 'ProgressDlg') && isvalid(app.ASSIST_VARS.ProgressDlg)
                app.ASSIST_VARS.ProgressDlg.Message = 'Completed.';
                pause(0.1);
                close(app.ASSIST_VARS.ProgressDlg);
                app.ASSIST_VARS = rmfield(app.ASSIST_VARS, 'ProgressDlg'); % 削除して再利用を防ぐ
            end
            
            % singular point 警告
            if app.CALC_CONTROL.IIRET > 0
                disp('Warning! At least one calculation node is on one of the singular points.');
                disp('Shift all nodes very slightly to escape the point.');
                showMessage(app, {'Warning! At least one calculation node is on one of the singular points.', ...
                    'Shift all nodes very slightly to escape the point.'}, false);
                uialert(app.main_menu_window, ...
                    'A calc. point is on a fault! A node met a singular point!', ...
                    'Warning!');
            end
            
            if app.SECTION_VARS.SEC_FLAG == 1
                app.ASSIST_VARS.NXINC = temp(1);
                app.ASSIST_VARS.NYINC = temp(2);
            end
        
        end


        function Okada_halfspace_one(app)
            % =====================================================================
            %                Okada_halfspace_one(app) 固定点がある場合の岡田式計算
            % =====================================================================
            % この関数は、半無限媒体中の断層による変位場を計算します。
            % 固定点がある場合に使われる計算？
        
            % 初期化
            app.SECTION_VARS.SEC_FLAG = 0;
            alpha  =  1.0 / (2.0 * (1.0 - app.INPUT_VARS.POIS));  % 岡田モデルのパラメータα
            z = app.INPUT_VARS.CALC_DEPTH * (-1.0);               % 深さz
        
            % グリッドの設定
            xstart  = app.INPUT_VARS.GRID(1,1);
            ystart  = app.INPUT_VARS.GRID(2,1);
            xfinish = app.INPUT_VARS.GRID(3,1);
            yfinish = app.INPUT_VARS.GRID(4,1);
            xinc    = app.INPUT_VARS.GRID(5,1);
            yinc    = app.INPUT_VARS.GRID(6,1);
        
            % 断面計算の場合
            if app.SECTION_VARS.SEC_FLAG == 1
                app.ASSIST_VARS.NXINC = app.ASSIST_VARS.nsec;
                app.ASSIST_VARS.NYINC = abs(app.ASSIST_VARS.ndepth);
            else
                app.ASSIST_VARS.NXINC = int16((xfinish - xstart) / xinc);
                app.ASSIST_VARS.NYINC = int16((yfinish - ystart) / yinc);
            end
        
            % n = app.ASSIST_VARS.NXINC * app.ASSIST_VARS.NYINC;
            n = 1;
            app.ASSIST_VARS.N_CELL = n;
        
            % 各変数の初期化
            UX   = zeros(n,1);
            UY   = zeros(n,1);
            UZ   = zeros(n,1);
            UXX  = zeros(n,1);
            UYX  = zeros(n,1);
            UZX  = zeros(n,1);
            UXY  = zeros(n,1);
            UYY  = zeros(n,1);
            UZY  = zeros(n,1);
            UXZ  = zeros(n,1);
            UZZ  = zeros(n,1);
            app.ASSIST_VARS.IRET = zeros(n,1);
            app.ASSIST_VARS.XYCOORD = zeros(n,2);
        
            % 出力の初期化
            if app.SECTION_VARS.SEC_FLAG == 1
                aax = reshape(app.ASSIST_VARS.AX, n, 1);
                aay = reshape(app.ASSIST_VARS.AY, n, 1);
                app.OKADA_OUTPUT.DC3DS = zeros(n,14);
                app.OKADA_OUTPUT.DC3DS0 = zeros(n,14);
            else
                app.OKADA_OUTPUT.DC3D = zeros(n,14);
                app.OKADA_OUTPUT.DC3D0 = zeros(n,14);
            end
                
            % 各要素ごとの計算ループ
            for ii = 1:app.INPUT_VARS.NUM
                depth = (app.INPUT_VARS.ELEMENT(ii,8) + app.INPUT_VARS.ELEMENT(ii,9)) / 2.0;  % 深さ
        
                % XY座標の設定
                if app.SECTION_VARS.SEC_FLAG == 1
                    app.ASSIST_VARS.XYCOORD(n,1) = aax(n,1);
                    app.ASSIST_VARS.XYCOORD(n,2) = aay(n,1);
                else
                    app.ASSIST_VARS.XYCOORD(n,1) = app.ASSIST_VARS.FIXX;
                    app.ASSIST_VARS.XYCOORD(n,2) = app.ASSIST_VARS.FIXY;
                end
               
                % 座標変換
                [c1,c2,c3,c4] = coord_conversion(app, app.ASSIST_VARS.XYCOORD(:,1), app.ASSIST_VARS.XYCOORD(:,2), ...
                    app.INPUT_VARS.ELEMENT(ii,1), app.INPUT_VARS.ELEMENT(ii,2), ...
                    app.INPUT_VARS.ELEMENT(ii,3), app.INPUT_VARS.ELEMENT(ii,4), ...
                    app.INPUT_VARS.ELEMENT(ii,8), app.INPUT_VARS.ELEMENT(ii,9), app.INPUT_VARS.ELEMENT(ii,7));
        
                % 岡田モデルの計算パラメータ
                aa = zeros(n,1) + alpha;
                if app.SECTION_VARS.SEC_FLAG == 1
                    zz = zeros(n,1) + reshape(app.ASSIST_VARS.AZ,n,1);
                else
                    zz = zeros(n,1) + z;
                end

                dp = depth * ones(n,1);
                e7 = app.INPUT_VARS.ELEMENT(ii,7) * ones(n,1);
                e5 = -app.INPUT_VARS.ELEMENT(ii,5) * ones(n,1);
                e6 = app.INPUT_VARS.ELEMENT(ii,6) * ones(n,1);
                zr = zeros(n,1);
        
                % KODE に基づいたパラメータ設定
                if app.INPUT_VARS.KODE(ii) == 200
                    e5 = double(app.INPUT_VARS.ELEMENT(ii,5)) * ones(n,1);
                    e6 = zeros(n,1);
                    zr = double(app.INPUT_VARS.ELEMENT(ii,6)) * ones(n,1);
                elseif app.INPUT_VARS.KODE(ii) == 300
                    e5 = zeros(n,1);
                    e6 = double(app.INPUT_VARS.ELEMENT(ii,6)) * ones(n,1);
                    zr = double(app.INPUT_VARS.ELEMENT(ii,5)) * ones(n,1);
                end
        
                x = zeros(n,1) + double(c1);
                y = zeros(n,1) + double(c2);
                al = zeros(n,1) + double(c3);
                aw = zeros(n,1) + double(c4);
        
                if app.INPUT_VARS.KODE(ii) == 400
                    aw = zeros(n,1,'double') - double(app.INPUT_VARS.ELEMENT(ii,5));
                    e5 = zeros(n,1,'double') + double(app.INPUT_VARS.ELEMENT(ii,6));
                    e6 = zeros(n,1,'double');
                    zr = zeros(n,1,'double');    
                elseif app.INPUT_VARS.KODE(ii) == 500
                    aw = zeros(n,1,'double');
                    e5 = zeros(n,1,'double');
                    e6 = zeros(n,1,'double') + double(app.INPUT_VARS.ELEMENT(ii,5));
                    zr = zeros(n,1,'double') + double(app.INPUT_VARS.ELEMENT(ii,6));
                end
                
                % 変数をまとめて岡田の DC3D 関数に渡す
                a = [aa x y zz dp e7 al al aw aw e5 e6 zr];
                
                if ((app.INPUT_VARS.ELEMENT(ii, 5) ~= 0.0) || (app.INPUT_VARS.ELEMENT(ii, 6) ~= 0.0))  % to skip calc for zero slip
                    
                    if app.INPUT_VARS.KODE(ii) == 100 || app.INPUT_VARS.KODE(ii) == 200 || app.INPUT_VARS.KODE(ii) == 300
                        [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1), ...
                            UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), app.ASSIST_VARS.IRET(:, 1)] = ...
                            Okada_DC3D(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 7), a(:, 8), a(:, 9), a(:, 10), a(:, 11), a(:, 12), a(:, 13));

                    elseif app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
                        [UX(:, 1), UY(:, 1), UZ(:, 1), UXX(:, 1), UYX(:, 1), UZX(:, 1), UXY(:, 1), UYY(:, 1), UZY(:, 1),...
                            UXZ(:, 1), UYZ(:, 1), UZZ(:, 1), app.ASSIST_VARS.IRET(:, 1)] = ...
                            Okada_DC3D0(app, a(:, 1), a(:, 2), a(:, 3), a(:, 4), a(:, 5), a(:, 6), a(:, 10), a(:, 11), a(:, 12), a(:, 13));
                    end
                
                    % cell to matrices
                    X = a(:,2);
                    Y = a(:,3);
                    Z = a(:,4);
                
                    % 変位場を元の座標系に変換
                    sw = sqrt((app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2))^2 + (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) ^ 2);
                    sina = (app.INPUT_VARS.ELEMENT(ii, 4) - app.INPUT_VARS.ELEMENT(ii, 2)) / sw;
                    cosa = (app.INPUT_VARS.ELEMENT(ii, 3) - app.INPUT_VARS.ELEMENT(ii, 1)) / sw;
        
                    UXG = UX * cosa - UY * sina;
                    UYG = UX * sina + UY * cosa;
                    UZG = UZ;
        
                    if app.INPUT_VARS.KODE(ii) == 400 || app.INPUT_VARS.KODE(ii) == 500
                        % KODE 400 および 500 の場合、変位量を km 単位に変換
                        UXG = UXG ./ 1000.0;
                        UYG = UYG ./ 1000.0;
                        UZG = UZG ./ 1000.0;
                    end
        
                    % ストレインから応力への変換
                    sk = app.INPUT_VARS.YOUNG / (1.0 + app.INPUT_VARS.POIS);  % ヤング率とポアソン比を使った応力計算係数
                    gk = app.INPUT_VARS.POIS / (1.0 - 2.0 * app.INPUT_VARS.POIS);
                    vol = UXX + UYY + UZZ;
        
                    % 応力テンソルの各成分を計算
                    sxx = sk * (gk * vol + UXX) * 0.001;
                    syy = sk * (gk * vol + UYY) * 0.001;
                    szz = sk * (gk * vol + UZZ) * 0.001;
                    sxy = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UXY + UYX) * 0.001;
                    sxz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UXZ + UZX) * 0.001;
                    syz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (UYZ + UZY) * 0.001;
        
                    % 応力テンソルを配列にまとめる
                    ssxx = reshape(sxx, 1, n);
                    ssyy = reshape(syy, 1, n);
                    sszz = reshape(szz, 1, n);
                    ssxy = reshape(sxy, 1, n);
                    ssxz = reshape(sxz, 1, n);
                    ssyz = reshape(syz, 1, n);
        
                    s0 = [ssxx; ssyy; sszz; ssyz; ssxz; ssxy];
                    s1 = tensor_trans(app, sina, cosa, s0, 1);

                    SXX = reshape(s1(1, :), n, 1);
                    SYY = reshape(s1(2, :), n, 1);
                    SZZ = reshape(s1(3, :), n, 1);
                    SYZ = reshape(s1(4, :), n, 1);
                    SXZ = reshape(s1(5, :), n, 1);
                    SXY = reshape(s1(6, :), n, 1); 
        
                    % 結果を OKADA_OUTPUT 構造体に保存
                    if ii == 1
                        if app.SECTION_VARS.SEC_FLAG == 1
                            app.OKADA_OUTPUT.DC3DS0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        else
                            app.OKADA_OUTPUT.DC3D0 = horzcat(app.ASSIST_VARS.XYCOORD, X, Y, Z, UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        end
                    else
                        if app.SECTION_VARS.SEC_FLAG == 1
                            app.OKADA_OUTPUT.DC3DS0 = horzcat(zeros(n,1), zeros(n,1), zeros(n,1), zeros(n,1), zeros(n,1), ...
                                UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        else
                            app.OKADA_OUTPUT.DC3D0 = horzcat(zeros(n,1), zeros(n,1), zeros(n,1), zeros(n,1), zeros(n,1), ...
                                UXG, UYG, UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                        end
                    end
        
                    % 合計値を計算して保存
                    if app.SECTION_VARS.SEC_FLAG == 1
                        app.OKADA_OUTPUT.DC3DS = app.OKADA_OUTPUT.DC3DS + app.OKADA_OUTPUT.DC3DS0;
                    else
                        app.OKADA_OUTPUT.DC3D = app.OKADA_OUTPUT.DC3D + app.OKADA_OUTPUT.DC3D0;
                    end
                end  % すべり量がゼロでない場合の計算終了
            end  % 各要素に対するループ終了
        end


        % function [UX, UY, UZ, UXX, UYX, UZX, UXY, UYY, UZY, UXZ, UYZ, UZZ, IRET] = ...
        %             Okada_DC3D(app, ALPHA, X, Y, Z, DEPTH, DIP, AL1, AL2, AW1, AW2, DISL1, DISL2, DISL3)
        %     % =====================================================================
        %     %                Okada_DC3D
        %     % =====================================================================
        %     app.ASSIST_VARS.N_CELL = numel(X);  % Xの要素数を取得
        %     % F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');  % ゼロ初期化
        %     if Z > 0.0
        %         disp(' ** POSITIVE Z WAS GIVEN IN SUB-DC3D');
        %         showMessage(app, 'POSITIVE Z WAS GIVEN IN SUB-DC3D', false);
        %     end
        % 
        %     % 必要な変数の初期化
        %     U = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        %     DU = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        %     IRET = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     AALPHA = ALPHA;
        %     DDIP = DIP;
        % 
        %     % DIPとALPHAに基づく計算
        %     Okada_DCCON0(app, AALPHA, DDIP);
        % 
        %     % --------------------------------------
        %     %     REAL-SOURCE CONTRIBUTION
        %     % --------------------------------------
        %     % 座標変換のための計算
        %     D = DEPTH + Z;
        %     P = Y .* app.OKADA_CALC_VARS.CD + D .* app.OKADA_CALC_VARS.SD;
        %     Q = Y .* app.OKADA_CALC_VARS.SD - D .* app.OKADA_CALC_VARS.CD;
        % 
        %     JXI = zeros(app.ASSIST_VARS.N_CELL, 1, 'int8');
        %     JET = zeros(app.ASSIST_VARS.N_CELL, 1, 'int8');
        % 
        %     % 各座標に対する条件チェック
        %     aa = (X + AL1) .* (X - AL2); % 要素ごとの計算
        %     cneg = aa <= 0.0;            % 0.0以下の要素を検出して論理値配列に
        %     JXI = JXI + int8(cneg);      % 論理配列を int8 に変換し、JXI に加算
        % 
        %     bb = (P + AW1) .* (P - AW2); % 要素ごとの計算
        %     cneg = bb <= 0.0;            % 0.0以下の要素を検出して論理値配列に
        %     JET = JET + int8(cneg);      % 論理配列を int8 に変換し、JXI に加算
        % 
        %     % 変位成分の取得
        %     DD1 = DISL1;
        %     DD2 = DISL2;
        %     DD3 = DISL3;
        % 
        %     % 各方向に対する計算
        %     for K = 1:2
        %         if K == 1
        %             ET = P + AW1;
        %         else
        %             ET = P - AW2;
        %         end
        % 
        %         for J = 1:2
        %             if J == 1
        %                 XI = X + AL1;
        %             else
        %                 XI = X - AL2;
        %             end
        % 
        %             Okada_DCCON2(app, XI, ET, Q, app.OKADA_CALC_VARS.SD, app.OKADA_CALC_VARS.CD);
        % 
        %             % 特異点の検出
        %             cc0 = (JXI == 1 & abs(Q) <= 1.0e-12 & abs(ET) <= 1.0e-12) | ...
        %                   (JET == 1 & abs(Q) <= 1.0e-12 & abs(XI) <= 1.0e-12);
        %             IRET = IRET + cc0;
        % 
        %             % 変位場の計算
        %             DUA = Okada_UA(app, XI, ET, Q, DD1, DD2, DD3);
        % 
        %             for I = 1:3:10
        %                 DU(:, I) = -DUA(:, I);
        %                 DU(:, I + 1) = -DUA(:, I + 1) .* app.OKADA_CALC_VARS.CD + DUA(:, I + 2) .* app.OKADA_CALC_VARS.SD;
        %                 DU(:, I + 2) = -DUA(:, I + 1) .* app.OKADA_CALC_VARS.SD - DUA(:, I + 2) .* app.OKADA_CALC_VARS.CD;
        %                 if I < 10.0
        %                     continue;
        %                 end
        %                 DU(:, I) = -DU(:, I);
        %                 DU(:, I + 1) = -DU(:, I + 1);
        %                 DU(:, I + 2) = -DU(:, I + 2);
        %             end
        % 
        %             % JとKの値に応じてUに加算・減算
        %             if (J + K) ~= 3
        %                 U(:, 1:12) = U(:, 1:12) + DU(:, 1:12);
        %             else
        %                 U(:, 1:12) = U(:, 1:12) - DU(:, 1:12);
        %             end
        %         end
        %     end
        % 
        %     % --------------------------------------
        %     %      IMAGE-SOURCE CONTRIBUTION
        %     % --------------------------------------
        %     % イメージソース貢献の計算
        %     ZZ = Z;
        %     D = DEPTH - Z;
        %     P = Y .* app.OKADA_CALC_VARS.CD + D .* app.OKADA_CALC_VARS.SD;
        %     Q = Y .* app.OKADA_CALC_VARS.SD - D .* app.OKADA_CALC_VARS.CD;
        %     % 
        %     % JET = ones(app.ASSIST_VARS.N_CELL, 1, 'int8');
        %     % cc = (P + AW1) .* (P - AW2);
        %     % JET(cc <= 0.0) = 1;
        % 
        %     for K = 1:2
        %         if K == 1
        %             ET = P + AW1;
        %         else
        %             ET = P - AW2;
        %         end
        % 
        %         for J = 1:2
        %             if J == 1
        %                 XI = X + AL1;
        %             else
        %                 XI = X - AL2;
        %             end
        % 
        %             Okada_DCCON2(app, XI, ET, Q, app.OKADA_CALC_VARS.SD, app.OKADA_CALC_VARS.CD);
        %             DUA = Okada_UA(app, XI, ET, Q, DD1, DD2, DD3);
        %             DUB = Okada_UB(app, XI, ET, Q, DD1, DD2, DD3);
        %             DUC = Okada_UC(app, XI, ET, Q, ZZ, DD1, DD2, DD3);
        % 
        %             for I = 1:3:10
        %                 DU(:, I) = DUA(:, I) + DUB(:, I) + Z .* DUC(:, I);
        %                 DU(:, I + 1) = (DUA(:, I + 1) + DUB(:, I + 1) + Z .* DUC(:, I + 1)) .* app.OKADA_CALC_VARS.CD - ...
        %                                (DUA(:, I + 2) + DUB(:, I + 2) + Z .* DUC(:, I + 2)) .* app.OKADA_CALC_VARS.SD;
        %                 DU(:, I + 2) = (DUA(:, I + 1) + DUB(:, I + 1) - Z .* DUC(:, I + 1)) .* app.OKADA_CALC_VARS.SD + ...
        %                                (DUA(:, I + 2) + DUB(:, I + 2) - Z .* DUC(:, I + 2)) .* app.OKADA_CALC_VARS.CD;
        %                 if I<10.0
        %                     continue;
        %                 end
        %                 DU(:, 10) = DU(:, 10) + DUC(:, 1);
        %                 DU(:, 11) = DU(:, 11) + DUC(:, 2) .* app.OKADA_CALC_VARS.CD - DUC(:, 3) .* app.OKADA_CALC_VARS.SD;
        %                 DU(:, 12) = DU(:, 12) - DUC(:, 2) .* app.OKADA_CALC_VARS.SD - DUC(:, 3) .* app.OKADA_CALC_VARS.CD;
        %             end
        % 
        %             % JとKの値に応じてUに加算・減算
        %             if (J + K == 3)
        %                 U(:, 1:12) = U(:, 1:12) - DU(:, 1:12);
        %             else
        %                 U(:, 1:12) = U(:, 1:12) + DU(:, 1:12);
        %             end
        %         end
        %     end
        % 
        %     % 結果の出力
        %     UX = U(:, 1);
        %     UY = U(:, 2);
        %     UZ = U(:, 3);
        %     UXX = U(:, 4);
        %     UYX = U(:, 5);
        %     UZX = U(:, 6);
        %     UXY = U(:, 7);
        %     UYY = U(:, 8);
        %     UZY = U(:, 9);
        %     UXZ = U(:, 10);
        %     UYZ = U(:, 11);
        %     UZZ = U(:, 12);
        % 
        %     % IRETの更新
        %     cc5 = IRET >= 1;
        %     IRET = cc5;
        %     % sum(rot90(sum(IRET)));
        % end

        function [UX, UY, UZ, UXX, UYX, UZX, UXY, UYY, UZY, UXZ, UYZ, UZZ, IRET] = ...
                    Okada_DC3D(app, ALPHA, X, Y, Z, DEPTH, DIP, AL1, AL2, AW1, AW2, DISL1, DISL2, DISL3)
            % =====================================================================
            %                Okada_DC3D
            % =====================================================================
            % 要素数の取得
            N = numel(X);
            app.ASSIST_VARS.N_CELL = N;

            if Z > 0.0
                disp(' ** POSITIVE Z WAS GIVEN IN SUB-DC3D');
                showMessage(app, 'POSITIVE Z WAS GIVEN IN SUB-DC3D', false);
            end

            % 必要な変数の初期化
            U    = zeros(N, 12, 'double');
            DU   = zeros(N, 12, 'double');
            IRET = zeros(N, 1,  'double');
            AALPHA = ALPHA;
            DDIP = DIP;

            % DIPとALPHAに基づく計算
            Okada_DCCON0(app, AALPHA, DDIP);

            % --------------------------------------
            %     REAL-SOURCE CONTRIBUTION
            % --------------------------------------
            % 座標変換のための計算
            D = DEPTH + Z;
            P = Y .* app.OKADA_CALC_VARS.CD + D .* app.OKADA_CALC_VARS.SD;
            Q = Y .* app.OKADA_CALC_VARS.SD - D .* app.OKADA_CALC_VARS.CD;

            JXI = zeros(app.ASSIST_VARS.N_CELL, 1, 'int8');
            JET = zeros(app.ASSIST_VARS.N_CELL, 1, 'int8');

            % 各座標に対する条件チェック
            aa = (X + AL1) .* (X - AL2); % 要素ごとの計算
            cneg = aa <= 0.0;            % 0.0以下の要素を検出して論理値配列に
            JXI = JXI + int8(cneg);      % 論理配列を int8 に変換し、JXI に加算

            bb = (P + AW1) .* (P - AW2); % 要素ごとの計算
            cneg = bb <= 0.0;            % 0.0以下の要素を検出して論理値配列に
            JET = JET + int8(cneg);      % 論理配列を int8 に変換し、JXI に加算

            % 変位成分の取得
            DD1 = DISL1;
            DD2 = DISL2;
            DD3 = DISL3;

            % 各方向に対する計算
            for K = 1:2
                if K == 1
                    ET = P + AW1;
                else
                    ET = P - AW2;
                end

                for J = 1:2
                    if J == 1
                        XI = X + AL1;
                    else
                        XI = X - AL2;
                    end

                    Okada_DCCON2(app, XI, ET, Q, app.OKADA_CALC_VARS.SD, app.OKADA_CALC_VARS.CD);

                    % 特異点の検出
                    cc0 = (JXI == 1 & abs(Q) <= 1.0e-12 & abs(ET) <= 1.0e-12) | ...
                          (JET == 1 & abs(Q) <= 1.0e-12 & abs(XI) <= 1.0e-12);
                    IRET = IRET + cc0;

                    % 変位場の計算
                    DUA = Okada_UA(app, XI, ET, Q, DD1, DD2, DD3);

                    for I = 1:3:10
                        DU(:, I) = -DUA(:, I);
                        DU(:, I + 1) = -DUA(:, I + 1) .* app.OKADA_CALC_VARS.CD + DUA(:, I + 2) .* app.OKADA_CALC_VARS.SD;
                        DU(:, I + 2) = -DUA(:, I + 1) .* app.OKADA_CALC_VARS.SD - DUA(:, I + 2) .* app.OKADA_CALC_VARS.CD;
                        if I < 10.0
                            continue;
                        end
                        DU(:, I) = -DU(:, I);
                        DU(:, I + 1) = -DU(:, I + 1);
                        DU(:, I + 2) = -DU(:, I + 2);
                    end

                    % JとKの値に応じてUに加算・減算
                    if (J + K) ~= 3
                        U(:, 1:12) = U(:, 1:12) + DU(:, 1:12);
                    else
                        U(:, 1:12) = U(:, 1:12) - DU(:, 1:12);
                    end
                end
            end

            % --------------------------------------
            %      IMAGE-SOURCE CONTRIBUTION
            % --------------------------------------
            % イメージソース貢献の計算
            ZZ = Z;
            D = DEPTH - Z;
            P = Y .* app.OKADA_CALC_VARS.CD + D .* app.OKADA_CALC_VARS.SD;
            Q = Y .* app.OKADA_CALC_VARS.SD - D .* app.OKADA_CALC_VARS.CD;

            % JET = ones(app.ASSIST_VARS.N_CELL, 1, 'int8');
            % cc = (P + AW1) .* (P - AW2);
            % JET(cc <= 0.0) = 1;

            for K = 1:2
                if K == 1
                    ET = P + AW1;
                else
                    ET = P - AW2;
                end

                for J = 1:2
                    if J == 1
                        XI = X + AL1;
                    else
                        XI = X - AL2;
                    end

                    Okada_DCCON2(app, XI, ET, Q, app.OKADA_CALC_VARS.SD, app.OKADA_CALC_VARS.CD);
                    DUA = Okada_UA(app, XI, ET, Q, DD1, DD2, DD3);
                    DUB = Okada_UB(app, XI, ET, Q, DD1, DD2, DD3);
                    DUC = Okada_UC(app, XI, ET, Q, ZZ, DD1, DD2, DD3);

                    for I = 1:3:10
                        DU(:, I) = DUA(:, I) + DUB(:, I) + Z .* DUC(:, I);
                        DU(:, I + 1) = (DUA(:, I + 1) + DUB(:, I + 1) + Z .* DUC(:, I + 1)) .* app.OKADA_CALC_VARS.CD - ...
                                       (DUA(:, I + 2) + DUB(:, I + 2) + Z .* DUC(:, I + 2)) .* app.OKADA_CALC_VARS.SD;
                        DU(:, I + 2) = (DUA(:, I + 1) + DUB(:, I + 1) - Z .* DUC(:, I + 1)) .* app.OKADA_CALC_VARS.SD + ...
                                       (DUA(:, I + 2) + DUB(:, I + 2) - Z .* DUC(:, I + 2)) .* app.OKADA_CALC_VARS.CD;
                        if I<10.0
                            continue;
                        end
                        DU(:, 10) = DU(:, 10) + DUC(:, 1);
                        DU(:, 11) = DU(:, 11) + DUC(:, 2) .* app.OKADA_CALC_VARS.CD - DUC(:, 3) .* app.OKADA_CALC_VARS.SD;
                        DU(:, 12) = DU(:, 12) - DUC(:, 2) .* app.OKADA_CALC_VARS.SD - DUC(:, 3) .* app.OKADA_CALC_VARS.CD;
                    end

                    % JとKの値に応じてUに加算・減算
                    if (J + K == 3)
                        U(:, 1:12) = U(:, 1:12) - DU(:, 1:12);
                    else
                        U(:, 1:12) = U(:, 1:12) + DU(:, 1:12);
                    end
                end
            end

            % 結果の出力
            UX = U(:, 1);
            UY = U(:, 2);
            UZ = U(:, 3);
            UXX = U(:, 4);
            UYX = U(:, 5);
            UZX = U(:, 6);
            UXY = U(:, 7);
            UYY = U(:, 8);
            UZY = U(:, 9);
            UXZ = U(:, 10);
            UYZ = U(:, 11);
            UZZ = U(:, 12);

            % IRETの更新
            cc5 = IRET >= 1;
            IRET = cc5;
            % sum(rot90(sum(IRET)));
        end


        function [UX, UY, UZ, UXX, UYX, UZX, UXY, UYY, UZY, UXZ, UYZ, UZZ, IRET] = ...
                    Okada_DC3D0(app, ALPHA, X, Y, Z, DEPTH, DIP, POT1, POT2, POT3, POT4)
            % =====================================================================
            %                Okada_DC3D0
            % =====================================================================
            app.ASSIST_VARS.N_CELL = numel(X);
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            if Z > 0.0
                disp(' ** POSITIVE Z WAS GIVEN IN SUB-DC3D');
                showMessage(app, 'POSITIVE Z WAS GIVEN IN SUB-DC3D', false);
            end
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
            DUA(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
            DUB(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
            DUC(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
            AALPHA = ALPHA;
            DDIP = DIP;
            
            Okada_DCCON0(app, AALPHA, DDIP);
            
            % --------------------------------------
            %     REAL-SOURCE CONTRIBUTION
            % --------------------------------------
            XX = X;
            YY = Y;
            ZZ = Z;
            DD = DEPTH + Z;
            Okada_DCCON1(app, XX, YY, DD);
            
            % --------------------------------------
            %     IN CASE OF SINGULAR (R=0)
            % --------------------------------------
            c1 = app.OKADA_CALC_VARS.R == F0;
            if sum(rot90(sum(c1))) > 1
                UX = F0;
                UY = F0;
                UZ = F0;
                UXX = F0;
                UYX = F0;
                UZX = F0;
                UXY = F0;
                UYY = F0;
                UZY = F0;
                UXZ = F0;
                UYZ = F0;
                UZZ = F0;
                IRET = ones(app.ASSIST_VARS.N_CELL, 1);           
                return;
            end
            
            PP1 = POT1;
            PP2 = POT2;
            PP3 = POT3;
            PP4 = POT4;
            
            DUA = Okada_UA0(app, XX, YY, DD, PP1, PP2, PP3, PP4);
            for I = 1:12
                if(I < 10) 
                    U(:, I) = U(:, I) - DUA(:, I);
                end
                if(I >= 10)
                    U(:, I) = U(:, I) + DUA(:, I);
                end
            end
            
            % --------------------------------------
            %      IMAGE-SOURCE CONTRIBUTION
            % --------------------------------------
            DD = DEPTH - Z;
            Okada_DCCON1(app, XX, YY, DD);
            DUA = Okada_UA0(app, XX, YY, DD, PP1, PP2, PP3, PP4);
            DUB = Okada_UB0(app, XX, YY, DD, ZZ, PP1, PP2, PP3, PP4);
            DUC = Okada_UC0(app, XX, YY, DD, ZZ, PP1, PP2, PP3, PP4);
            for I = 1:12
                DU = DUA(:, I) + DUB(:, I) + ZZ .* DUC(:, I);
                if I >= 10
                    DU = DU + DUC(:, I - 9);
                end
                    U(:, I) = U(:, I) + DU;
            end
            UX = U(:, 1) ./ (1.0E+6);
            UY = U(:, 2) ./ (1.0E+6);
            UZ = U(:, 3) ./ (1.0E+6);
            UXX = U(:, 4) ./ (1.0E+6);
            UYX = U(:, 5) ./ (1.0E+6);
            UZX = U(:, 6) ./ (1.0E+6);
            UXY = U(:, 7) ./ (1.0E+6);
            UYY = U(:, 8) ./ (1.0E+6);
            UZY = U(:, 9) ./ (1.0E+6);
            UXZ = U(:, 10) ./ (1.0E+6);
            UYZ = U(:, 11) ./ (1.0E+6);
            UZZ = U(:, 12) ./ (1.0E+6);
            IRET = F0;
        end


        function Okada_DCCON0(app, ALPHA, DIP)
            % =====================================================================
            %                Okada_DCCON0
            % =====================================================================
            % Okada 92 code subroutine Okada_DCCON0
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
            PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
            EPS = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 1.0e-6;

            app.OKADA_CALC_VARS.ALP1 = (F1 - ALPHA) ./ F2;
            app.OKADA_CALC_VARS.ALP2 = ALPHA ./ F2;
            app.OKADA_CALC_VARS.ALP3 = (F1 - ALPHA) ./ ALPHA;
            app.OKADA_CALC_VARS.ALP4 = F1 - ALPHA;
            app.OKADA_CALC_VARS.ALP5 = ALPHA;

            P18 = PI2 ./ double(360.0);
            app.OKADA_CALC_VARS.SD = double(sin(DIP .* P18));
            app.OKADA_CALC_VARS.CD = double(cos(DIP .* P18));
            c1 = abs(app.OKADA_CALC_VARS.CD) < EPS;
            c2 = abs(app.OKADA_CALC_VARS.CD) >= EPS;
            s1 = app.OKADA_CALC_VARS.SD > F0;
            s2 = app.OKADA_CALC_VARS.SD == F0;
            s3 = app.OKADA_CALC_VARS.SD < F0;

            app.OKADA_CALC_VARS.CD = F0 .* c1 + app.OKADA_CALC_VARS.CD .* c2;

            app.OKADA_CALC_VARS.SD = c1 .* (F1 .* s1 + app.OKADA_CALC_VARS.SD .* s2 + (-1.0) .* F1 .* s3) + c2 .* app.OKADA_CALC_VARS.SD;
            app.OKADA_CALC_VARS.SDSD = app.OKADA_CALC_VARS.SD .* app.OKADA_CALC_VARS.SD;
            app.OKADA_CALC_VARS.CDCD = app.OKADA_CALC_VARS.CD .* app.OKADA_CALC_VARS.CD;
            app.OKADA_CALC_VARS.SDCD = app.OKADA_CALC_VARS.SD .* app.OKADA_CALC_VARS.CD;
            app.OKADA_CALC_VARS.S2D = F2 .* app.OKADA_CALC_VARS.SDCD;
            app.OKADA_CALC_VARS.C2D = app.OKADA_CALC_VARS.CDCD - app.OKADA_CALC_VARS.SDSD;
        end


        function Okada_DCCON1(app, X, Y, D)
            % =====================================================================
            %                Okada_DCCON1
            % =====================================================================
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            F3 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 3.0;
            F5 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 5.0;
            EPS = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 0.000001;
            
            c1 = abs(X) < EPS;
            c2 = abs(X) >=  EPS;
            X = F0 .* c1 + X .* c2;
            c1 = abs(Y) < EPS;
            c2 = abs(Y) >=  EPS;
            Y = F0 .* c1 + Y .* c2;
            c1 = abs(D) < EPS;
            c2 = abs(D) >=  EPS;
            D = F0 .* c1 + D .* c2;
            app.OKADA_CALC_VARS.P = Y .* app.OKADA_CALC_VARS.CD + D .* app.OKADA_CALC_VARS.SD;
            app.OKADA_CALC_VARS.Q = Y .* app.OKADA_CALC_VARS.SD - D .* app.OKADA_CALC_VARS.CD;
            app.OKADA_CALC_VARS.S = app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.CD;
            app.OKADA_CALC_VARS.T = app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.SD;
            app.OKADA_CALC_VARS.XY = X .* Y;
            app.OKADA_CALC_VARS.X2 = X .* X;
            app.OKADA_CALC_VARS.Y2 = Y .* Y;
            app.OKADA_CALC_VARS.D2 = D .* D;
            app.OKADA_CALC_VARS.R2 = app.OKADA_CALC_VARS.X2 + app.OKADA_CALC_VARS.Y2 + app.OKADA_CALC_VARS.D2;
            app.OKADA_CALC_VARS.R  = sqrt(app.OKADA_CALC_VARS.R2);
            c1 = app.OKADA_CALC_VARS.R ==  F0;
            if sum(rot90(sum(c1))) > 0
                return;
            end
            app.OKADA_CALC_VARS.R3 = app.OKADA_CALC_VARS.R  .* app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.R5 = app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.A3 = F1 - F3 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.A5 = F1 - F5 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.B3 = F1 - F3 .* app.OKADA_CALC_VARS.Y2 ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.C3 = F1 - F3 .* app.OKADA_CALC_VARS.D2 ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.QR = F3 .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R5;
            app.OKADA_CALC_VARS.QRX = F5 .* app.OKADA_CALC_VARS.QR .* X ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.UY = app.OKADA_CALC_VARS.SD - F5 .* Y .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.UZ = app.OKADA_CALC_VARS.CD + F5 .* D .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.VY = app.OKADA_CALC_VARS.S - F5 .* Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.VZ = app.OKADA_CALC_VARS.T + F5 .* D .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.WY = app.OKADA_CALC_VARS.UY + app.OKADA_CALC_VARS.SD;
            app.OKADA_CALC_VARS.WZ = app.OKADA_CALC_VARS.UZ + app.OKADA_CALC_VARS.CD;
        end


        function Okada_DCCON2(app, XI, ET, Q, SD, CD)
            % =====================================================================
            %                Okada_DCCON2
            % =====================================================================
            % Okada 92 code subroutine Okada_DCCON2
            % F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            % F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            % F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
            % EPS = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 0.000001;
            % 
            % c1 = abs(XI) < EPS;
            % c2 = abs(XI) >= EPS;
            % XI = F0 .* c1 + XI .* c2;
            % c1 = abs(ET) < EPS;
            % c2 = abs(ET) >= EPS;
            % ET = F0 .* c1 + ET .* c2;
            % c1 = abs(Q) < EPS;
            % c2 = abs(Q) >= EPS;
            % Q = F0 .* c1 + Q .* c2;

            % N   = app.ASSIST_VARS.N_CELL;
            F0  = 0.0;
            F1  = 1.0;
            F2  = 2.0;
            EPS = 1.0e-6;
        
            % --- 極小値のクリップ（ゼロ処理） ---
            c1 = abs(XI) < EPS;
            c2 = ~c1;
            XI = F0 .* c1 + XI .* c2;
        
            c1 = abs(ET) < EPS;
            c2 = ~c1;
            ET = F0 .* c1 + ET .* c2;
        
            c1 = abs(Q) < EPS;
            c2 = ~c1;
            Q  = F0 .* c1 + Q  .* c2;

            % --- 基本量 ---
            app.OKADA_CALC_VARS.XI2 = XI .* XI;
            app.OKADA_CALC_VARS.ET2 = ET .* ET;
            app.OKADA_CALC_VARS.Q2 = Q .* Q;
            app.OKADA_CALC_VARS.R2 = app.OKADA_CALC_VARS.XI2 + app.OKADA_CALC_VARS.ET2 + app.OKADA_CALC_VARS.Q2;
            app.OKADA_CALC_VARS.R = double(sqrt(app.OKADA_CALC_VARS.R2));
            c1 = app.OKADA_CALC_VARS.R == F0;
            c1_sum = sum(rot90(sum(c1)));
            if c1_sum > 0
                return;
            end
            app.OKADA_CALC_VARS.R3 = app.OKADA_CALC_VARS.R  .* app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.R5 = app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.Y = ET .* CD + Q .* SD;
            app.OKADA_CALC_VARS.D = ET .* SD - Q .* CD;
            c1 = Q == F0;
            c2 = Q ~= F0;
            % s1 = Q .* app.OKADA_CALC_VARS.R == F0;
            % s2 = Q .* app.OKADA_CALC_VARS.R ~= F0;
            app.OKADA_CALC_VARS.TT = c1 .* F0 + c2 .* double(atan(XI .* ET ./ (Q .* app.OKADA_CALC_VARS.R)));
            
            % ---------- ALX, X11, X32 ----------
            c1 = XI < F0;
            c2 = Q == F0;
            c3 = ET == F0;
            c4 = c1 .* c2 .* c3;
            c5 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            c5 = (c5 - c4) + 1.0;
            RXI = app.OKADA_CALC_VARS.R + XI;
            app.OKADA_CALC_VARS.ALX = (- double(log(app.OKADA_CALC_VARS.R - XI))) .* c4 + double(log(RXI)) .* c5;
            app.OKADA_CALC_VARS.X11 = F0 .* c4 + (F1 ./ (app.OKADA_CALC_VARS.R .* RXI)) .* c5;
            app.OKADA_CALC_VARS.X32 = F0 .* c4 + ((app.OKADA_CALC_VARS.R + RXI) .* app.OKADA_CALC_VARS.X11 .* app.OKADA_CALC_VARS.X11 ./ app.OKADA_CALC_VARS.R) .* c5;
            
            % ---------- ALE, Y11, Y32 ----------
            c1 = ET < F0;
            c2 = Q == F0;
            c3 = XI == F0;
            c4 = c1 .* c2 .* c3;
            c5 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            c5 = (c5 - c4) + 1.0;
            RET = app.OKADA_CALC_VARS.R + ET;
            app.OKADA_CALC_VARS.ALE = ( - double(log(app.OKADA_CALC_VARS.R - ET))) .* c4 + double(log(RET)) .* c5;
            app.OKADA_CALC_VARS.Y11 = F0 .* c4 + (F1 ./ (app.OKADA_CALC_VARS.R .* RET)) .* c5;
            app.OKADA_CALC_VARS.Y32 = F0 .* c4 + ((app.OKADA_CALC_VARS.R + RET) .* app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.Y11 ./ app.OKADA_CALC_VARS.R) .* c5;
            
            app.OKADA_CALC_VARS.EY = SD ./ app.OKADA_CALC_VARS.R - app.OKADA_CALC_VARS.Y .* Q ./ app.OKADA_CALC_VARS.R3;
            app.OKADA_CALC_VARS.EZ = CD ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.D .* Q ./ app.OKADA_CALC_VARS.R3;
            app.OKADA_CALC_VARS.FY = app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.XI2 .* app.OKADA_CALC_VARS.Y32 .* SD;
            app.OKADA_CALC_VARS.FZ = app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.XI2 .* app.OKADA_CALC_VARS.Y32 .* CD;
            app.OKADA_CALC_VARS.GY = F2 .* app.OKADA_CALC_VARS.X11 .* SD - app.OKADA_CALC_VARS.Y .* Q .* app.OKADA_CALC_VARS.X32;
            app.OKADA_CALC_VARS.GZ = F2 .* app.OKADA_CALC_VARS.X11 .* CD + app.OKADA_CALC_VARS.D .* Q .* app.OKADA_CALC_VARS.X32;
            app.OKADA_CALC_VARS.HY = app.OKADA_CALC_VARS.D .* Q .* app.OKADA_CALC_VARS.X32 + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* SD;
            app.OKADA_CALC_VARS.HZ = app.OKADA_CALC_VARS.Y .* Q .* app.OKADA_CALC_VARS.X32 + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* CD;
        end


        % function [U] = Okada_UA(app, XI, ET, Q, DISL1, DISL2, DISL3)
        %     % =====================================================================
        %     %                Okada_UA
        %     % =====================================================================
        %     F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
        %     PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
        % 
        %     % DU = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        %     du1 = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        %     du2 = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        %     du3 = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        % 
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
        %     XY = XI .* app.OKADA_CALC_VARS.Y11;
        %     QX = Q  .* app.OKADA_CALC_VARS.X11;
        %     QY = Q  .* app.OKADA_CALC_VARS.Y11;
        % 
        %     % --------------------------------------
        %     %      STRIKE-SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c1 = DISL1 ~= F0;
        %     du1(:, 1) = app.OKADA_CALC_VARS.TT ./ F2 + app.OKADA_CALC_VARS.ALP2 .* XI .* QY;
        %     du1(:, 2) = app.OKADA_CALC_VARS.ALP2 .* Q ./ app.OKADA_CALC_VARS.R;
        %     du1(:, 3) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALE - app.OKADA_CALC_VARS.ALP2 .* Q .* QY;
        %     du1(:, 4) = - app.OKADA_CALC_VARS.ALP1 .* QY - app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.XI2 .* Q .* app.OKADA_CALC_VARS.Y32;
        %     du1(:, 5) = - app.OKADA_CALC_VARS.ALP2 .* XI .* Q ./ app.OKADA_CALC_VARS.R3;
        %     du1(:, 6) = app.OKADA_CALC_VARS.ALP1 .* XY  + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
        %     du1(:, 7) = app.OKADA_CALC_VARS.ALP1 .* XY .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.FY + app.OKADA_CALC_VARS.D ./ F2 .* app.OKADA_CALC_VARS.X11;
        %     du1(:, 8) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EY;
        %     du1(:, 9) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + QY .* app.OKADA_CALC_VARS.SD) - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FY;
        %     du1(:, 10) = app.OKADA_CALC_VARS.ALP1 .* XY .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.FZ + app.OKADA_CALC_VARS.Y ./ F2 .* app.OKADA_CALC_VARS.X11;
        %     du1(:, 11) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EZ;
        %     du1(:, 12) = -app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R - QY .* app.OKADA_CALC_VARS.CD) - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FZ;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL1 ./ PI2, 1, 12) .* du1(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
        % 
        %     % --------------------------------------
        %     %      DIP-SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c2 = DISL2 ~= F0;
        %     du2(:, 1) = app.OKADA_CALC_VARS.ALP2 .* Q ./ app.OKADA_CALC_VARS.R;
        %     du2(:, 2) = app.OKADA_CALC_VARS.TT ./ F2 + app.OKADA_CALC_VARS.ALP2 .* ET .* QX;
        %     du2(:, 3) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALX - app.OKADA_CALC_VARS.ALP2 .* Q .* QX;
        %     du2(:, 4) = - app.OKADA_CALC_VARS.ALP2 .* XI .* Q ./ app.OKADA_CALC_VARS.R3;
        %     du2(:, 5) = - QY ./ F2 - app.OKADA_CALC_VARS.ALP2 .* ET .* Q ./ app.OKADA_CALC_VARS.R3;
        %     du2(:, 6) = app.OKADA_CALC_VARS.ALP1 ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3;
        %     du2(:, 7) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EY;
        %     du2(:, 8) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 + XY ./ F2 .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* ET .* app.OKADA_CALC_VARS.GY;
        %     du2(:, 9) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GY;
        %     du2(:, 10) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EZ;
        %     du2(:, 11) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + XY ./ F2 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* ET .* app.OKADA_CALC_VARS.GZ;
        %     du2(:, 12) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GZ;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL2 ./ PI2, 1, 12) .* du2(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
        % 
        %     % --------------------------------------
        %     %      TENSILE-FAULT CONTRIBUTION
        %     % --------------------------------------
        %     c3 = DISL3 ~= F0;
        %     du3(:, 1) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALE - app.OKADA_CALC_VARS.ALP2 .* Q .* QY;
        %     du3(:, 2) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALX - app.OKADA_CALC_VARS.ALP2 .* Q .* QX;
        %     du3(:, 3) = app.OKADA_CALC_VARS.TT ./ F2 - app.OKADA_CALC_VARS.ALP2 .* (ET .* QX + XI .* QY);
        %     du3(:, 4) = - app.OKADA_CALC_VARS.ALP1 .* XY + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
        %     du3(:, 5) = - app.OKADA_CALC_VARS.ALP1 ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3;
        %     du3(:, 6) = - app.OKADA_CALC_VARS.ALP1 .* QY - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
        %     du3(:, 7) = - app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + QY .* app.OKADA_CALC_VARS.SD) - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FY;
        %     du3(:, 8) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GY;
        %     du3(:, 9) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 + XY .* app.OKADA_CALC_VARS.SD) + app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.HY;
        %     du3(:, 10) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R - QY .* app.OKADA_CALC_VARS.CD) - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FZ;
        %     du3(:, 11) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GZ;
        %     du3(:, 12) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + XY .* app.OKADA_CALC_VARS.CD) + app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.HZ;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL3 ./ PI2, 1, 12) .* du3(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
        % end

        function U = Okada_UA(app, XI, ET, Q, DISL1, DISL2, DISL3)
            % =====================================================================
            %                         Okada_UA  (optimized)
            % =====================================================================
            
            N = app.ASSIST_VARS.N_CELL;
            
            % ---- 定数のスカラー化（結果は元と完全一致） ----
            F0  = 0.0;
            F2  = 2.0;
            PI2 = 6.283185307179586;   % 2*pi とほぼ同じ値（元コードの値をそのまま使用）
            
            % ---- 出力配列 ----
            U  = zeros(N, 12, 'double');
            
            % ---- 共通前計算 ----
            XY = XI .* app.OKADA_CALC_VARS.Y11;
            QX = Q  .* app.OKADA_CALC_VARS.X11;
            QY = Q  .* app.OKADA_CALC_VARS.Y11;
            
            % =====================================================================
            %                       STRIKE-SLIP  contribution
            % =====================================================================
            c1 = DISL1 ~= F0;          % 0 or 1 の論理 → double に暗黙変換される
            
            du1 = zeros(N,12,'double');
            du1(:,1)  = app.OKADA_CALC_VARS.TT ./ F2 + app.OKADA_CALC_VARS.ALP2 .* XI .* QY;
            du1(:,2)  = app.OKADA_CALC_VARS.ALP2 .* Q ./ app.OKADA_CALC_VARS.R;
            du1(:,3)  = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALE - app.OKADA_CALC_VARS.ALP2 .* Q .* QY;
            du1(:,4)  = -app.OKADA_CALC_VARS.ALP1 .* QY - app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.XI2 .* Q .* app.OKADA_CALC_VARS.Y32;
            du1(:,5)  = -app.OKADA_CALC_VARS.ALP2 .* XI .* Q ./ app.OKADA_CALC_VARS.R3;
            du1(:,6)  =  app.OKADA_CALC_VARS.ALP1 .* XY + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
            du1(:,7)  =  app.OKADA_CALC_VARS.ALP1 .* XY .* app.OKADA_CALC_VARS.SD + ...
                         app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.FY + ...
                         app.OKADA_CALC_VARS.D ./ F2 .* app.OKADA_CALC_VARS.X11;
            du1(:,8)  = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EY;
            du1(:,9)  = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + QY .* app.OKADA_CALC_VARS.SD) ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FY;
            du1(:,10) = app.OKADA_CALC_VARS.ALP1 .* XY .* app.OKADA_CALC_VARS.CD + ...
                         app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.FZ + ...
                         app.OKADA_CALC_VARS.Y ./ F2 .* app.OKADA_CALC_VARS.X11;
            du1(:,11) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EZ;
            du1(:,12) = -app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R - QY .* app.OKADA_CALC_VARS.CD) ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FZ;
            
            scale1 = (DISL1 ./ PI2) .* c1;   % 元の repmat(DISL1./PI2).*repmat(c1) と完全同値
            U = U + scale1 .* du1;
            
            
            % =====================================================================
            %                       DIP-SLIP contribution
            % =====================================================================
            c2 = DISL2 ~= F0;
            du2 = zeros(N,12,'double');
            
            du2(:,1)  = app.OKADA_CALC_VARS.ALP2 .* Q ./ app.OKADA_CALC_VARS.R;
            du2(:,2)  = app.OKADA_CALC_VARS.TT ./ F2 + app.OKADA_CALC_VARS.ALP2 .* ET .* QX;
            du2(:,3)  = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALX - app.OKADA_CALC_VARS.ALP2 .* Q .* QX;
            du2(:,4)  = -app.OKADA_CALC_VARS.ALP2 .* XI .* Q ./ app.OKADA_CALC_VARS.R3;
            du2(:,5)  = -QY ./ F2 - app.OKADA_CALC_VARS.ALP2 .* ET .* Q ./ app.OKADA_CALC_VARS.R3;
            du2(:,6)  =  app.OKADA_CALC_VARS.ALP1 ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3;
            du2(:,7)  =  app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EY;
            du2(:,8)  =  app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 + ...
                          XY ./ F2 .* app.OKADA_CALC_VARS.SD + ...
                          app.OKADA_CALC_VARS.ALP2 .* ET .* app.OKADA_CALC_VARS.GY;
            du2(:,9)  =  app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GY;
            du2(:,10) =  app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.EZ;
            du2(:,11) =  app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + ...
                          XY ./ F2 .* app.OKADA_CALC_VARS.CD + ...
                          app.OKADA_CALC_VARS.ALP2 .* ET .* app.OKADA_CALC_VARS.GZ;
            du2(:,12) = -app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GZ;
            
            scale2 = (DISL2 ./ PI2) .* c2;
            U = U + scale2 .* du2;
            
            
            % =====================================================================
            %                       TENSILE contribution
            % =====================================================================
            c3 = DISL3 ~= F0;
            du3 = zeros(N,12,'double');
            
            du3(:,1)  = -app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALE - app.OKADA_CALC_VARS.ALP2 .* Q .* QY;
            du3(:,2)  = -app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.ALX - app.OKADA_CALC_VARS.ALP2 .* Q .* QX;
            du3(:,3)  =  app.OKADA_CALC_VARS.TT ./ F2 - app.OKADA_CALC_VARS.ALP2 .* (ET .* QX + XI .* QY);
            du3(:,4)  = -app.OKADA_CALC_VARS.ALP1 .* XY + app.OKADA_CALC_VARS.ALP2 .* XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
            du3(:,5)  = -app.OKADA_CALC_VARS.ALP1 ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3;
            du3(:,6)  = -app.OKADA_CALC_VARS.ALP1 .* QY - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32;
            du3(:,7)  = -app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + QY .* app.OKADA_CALC_VARS.SD) ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FY;
            du3(:,8)  = -app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GY;
            du3(:,9)  =  app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 + XY .* app.OKADA_CALC_VARS.SD) ...
                         + app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.HY;
            du3(:,10) =  app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R - QY .* app.OKADA_CALC_VARS.CD) ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.FZ;
            du3(:,11) =  app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 ...
                         - app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.GZ;
            du3(:,12) =  app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + XY .* app.OKADA_CALC_VARS.CD) ...
                         + app.OKADA_CALC_VARS.ALP2 .* Q .* app.OKADA_CALC_VARS.HZ;
            
            scale3 = (DISL3 ./ PI2) .* c3;
            U = U + scale3 .* du3;
        end


        function [U] = Okada_UA0(app, X, Y, D, POT1, POT2, POT3, POT4)
            % =====================================================================
            %                Okada_UA0
            % =====================================================================
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            F3 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 3.0;
            PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
            
            DU = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
            U  = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
            
            % --------------------------------------
            %     STRIKE-SLIP CONTRIBUTION
            % --------------------------------------
            c1 = POT1 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.X2 .* app.OKADA_CALC_VARS.QR;
            DU(:, 2) = app.OKADA_CALC_VARS.ALP1 .* X ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.XY .* app.OKADA_CALC_VARS.QR;
            DU(:, 3) = - app.OKADA_CALC_VARS.ALP1 .* X ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* X .* D .* app.OKADA_CALC_VARS.QR;
            DU(:, 4) = X .* app.OKADA_CALC_VARS.QR .* ( - app.OKADA_CALC_VARS.ALP1 + app.OKADA_CALC_VARS.ALP2 .* (F1 + app.OKADA_CALC_VARS.A5) );
            DU(:, 5) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5;
            DU(:, 6) =-app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5;
            DU(:, 7) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R3 - Y .* app.OKADA_CALC_VARS.QR) + app.OKADA_CALC_VARS.ALP2 .* F3 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UY;
            DU(:, 8) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP1 .* Y .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* (Y .* app.OKADA_CALC_VARS.UY + app.OKADA_CALC_VARS.Q) );
            DU(:, 9) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP1 .* Y .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.UY );
            DU(:, 10) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R3 + D .* app.OKADA_CALC_VARS.QR) + app.OKADA_CALC_VARS.ALP2 .* F3 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UZ;
            DU(:, 11) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP1 .* D .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.UZ );
            DU(:, 12) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP1 .* D .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP2 .* (D .* app.OKADA_CALC_VARS.UZ - app.OKADA_CALC_VARS.Q) );
            
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT1 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
            
            % --------------------------------------
            %       DIP-SLIP CONTRIBUTION 
            % --------------------------------------
            c2 = POT2 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP2 .* X .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR;
            DU(:, 2) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR;
            DU(:, 3) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR;
            DU(:, 4) = app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5;
            DU(:, 5) = - app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5 - app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QRX;
            DU(:, 6) = app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5 - app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QRX;
            DU(:, 7) =  app.OKADA_CALC_VARS.ALP2 .* F3 .* X ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY;
            DU(:, 8) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.S2D ./ app.OKADA_CALC_VARS.R3-F3 .* Y .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5) + app.OKADA_CALC_VARS.ALP2 .* (F3 .* Y ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY + app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR);
            DU(:, 9) = - app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.C2D ./ app.OKADA_CALC_VARS.R3 - F3 .* Y .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5) + app.OKADA_CALC_VARS.ALP2 .* F3 .* D ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY;
            DU(:, 10) = app.OKADA_CALC_VARS.ALP2 .* F3 .* X ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ;
            DU(:, 11) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.C2D ./ app.OKADA_CALC_VARS.R3 + F3 .* D .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5) + app.OKADA_CALC_VARS.ALP2 .* F3 .* Y ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ;
            DU(:, 12) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.S2D ./ app.OKADA_CALC_VARS.R3 - F3 .* D .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5) + app.OKADA_CALC_VARS.ALP2 .* (F3 .* D ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ - app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR);
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT2 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
            
            % --------------------------------------
            %       TENSILE-FAULT CONTRIBUTION 
            % --------------------------------------
            c3 = POT3 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP1 .* X ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP2 .* X .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR;
            DU(:, 2) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR;
            DU(:, 3) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR;
            DU(:, 4) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP2 .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5;
            DU(:, 5) = - app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5 + app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QRX;
            DU(:, 6) = - app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5 + app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QRX;
            DU(:, 7) = - app.OKADA_CALC_VARS.ALP1 .* F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5 - app.OKADA_CALC_VARS.ALP2 .* X .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WY;
            DU(:, 8) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.C2D ./ app.OKADA_CALC_VARS.R3 - F3 .* Y .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5) - app.OKADA_CALC_VARS.ALP2 .* (Y .* app.OKADA_CALC_VARS.WY + app.OKADA_CALC_VARS.Q) .* app.OKADA_CALC_VARS.QR;
            DU(:, 9) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.S2D ./ app.OKADA_CALC_VARS.R3 - F3 .* Y .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5) - app.OKADA_CALC_VARS.ALP2 .* D .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WY;
            DU(:, 10) = app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* D ./ app.OKADA_CALC_VARS.R5 -app.OKADA_CALC_VARS.ALP2 .* X .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WZ;
            DU(:, 11) = - app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.S2D ./ app.OKADA_CALC_VARS.R3 - F3 .* D .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5) - app.OKADA_CALC_VARS.ALP2 .* Y .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WZ;
            DU(:, 12) = app.OKADA_CALC_VARS.ALP1 .* (app.OKADA_CALC_VARS.C2D ./ app.OKADA_CALC_VARS.R3 + F3 .* D .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5) - app.OKADA_CALC_VARS.ALP2 .* (D .* app.OKADA_CALC_VARS.WZ - app.OKADA_CALC_VARS.Q) .* app.OKADA_CALC_VARS.QR;
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT3 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
            
            % --------------------------------------
            %       INFLATE SOURCE CONTRIBUTION
            % --------------------------------------
            c4 = POT4 ~= F0;
            DU(:, 1) = - app.OKADA_CALC_VARS.ALP1 .* X ./ app.OKADA_CALC_VARS.R3;
            DU(:, 2) = - app.OKADA_CALC_VARS.ALP1 .* Y ./ app.OKADA_CALC_VARS.R3;
            DU(:, 3) = - app.OKADA_CALC_VARS.ALP1 .* D ./ app.OKADA_CALC_VARS.R3;
            DU(:, 4) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3;
            DU(:, 5) = app.OKADA_CALC_VARS.ALP1 .* F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5;
            DU(:, 6) = app.OKADA_CALC_VARS.ALP1 .* F3 .* X .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = - app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.B3 ./ app.OKADA_CALC_VARS.R3;
            DU(:, 9) = app.OKADA_CALC_VARS.ALP1 .* F3 .* Y .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 10) = - DU(:, 6);
            DU(:, 11) = - DU(:, 9);
            DU(:, 12) = app.OKADA_CALC_VARS.ALP1 .* app.OKADA_CALC_VARS.C3 ./ app.OKADA_CALC_VARS.R3;
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT4 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c4, 1, 12);
        end


        % function [U] = Okada_UB(app, XI, ET, Q, DISL1, DISL2, DISL3)
        %     % =====================================================================
        %     %                Okada_UB
        %     % =====================================================================
        %     F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
        %     PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
        %     DU = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        % 
        %     RD = app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.D;
        %     D11 = F1 ./ (app.OKADA_CALC_VARS.R .* RD);
        %     AJ2 = XI .* app.OKADA_CALC_VARS.Y ./ RD .* D11;
        %     AJ5 = - (app.OKADA_CALC_VARS.D + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y ./ RD) .* D11;
        % 
        %     c1 = app.OKADA_CALC_VARS.CD ~= F0;
        %     c2 = app.OKADA_CALC_VARS.CD == F0;
        %     s1 = XI == F0;
        %     s2 = XI ~= F0;
        % 
        %     % ----- To avoid 'Inf' and 'nan' troubles (divided by zero) ------
        %     tempCD = app.OKADA_CALC_VARS.CD;
        %     tempCDCD = app.OKADA_CALC_VARS.CDCD;
        %     app.OKADA_CALC_VARS.CD = c1 .* app.OKADA_CALC_VARS.CD + c2 .* 1.0e-12;
        %     app.OKADA_CALC_VARS.CDCD = c1 .* app.OKADA_CALC_VARS.CDCD + c2 .* 1.0e-12;
        % 
        %     X = double(sqrt(app.OKADA_CALC_VARS.XI2 + app.OKADA_CALC_VARS.Q2));
        %     RD2 = RD .* RD;
        %     AI4 = c1 .* (s1 .* F0 + s2 .* (F1 ./ app.OKADA_CALC_VARS.CDCD .* ( XI ./ RD .* app.OKADA_CALC_VARS.SDCD...
        %         +F2 .* atan((ET .* (X+Q .* app.OKADA_CALC_VARS.CD)+X .* (app.OKADA_CALC_VARS.R + X) .* app.OKADA_CALC_VARS.SD) ./ (XI .* (app.OKADA_CALC_VARS.R + X) .* app.OKADA_CALC_VARS.CD))))) +c2 .* (XI .* app.OKADA_CALC_VARS.Y ./ RD2 ./ F2);
        %     AI3 = c1 .* ((app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.CD ./ RD - app.OKADA_CALC_VARS.ALE + app.OKADA_CALC_VARS.SD .* double(log(RD))) ./ app.OKADA_CALC_VARS.CDCD) + c2 .* ((ET ./ RD + app.OKADA_CALC_VARS.Y .* Q ./ RD2 - app.OKADA_CALC_VARS.ALE) ./ F2);
        %     AK1 = c1 .* (XI .* (D11 - app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD) + c2 .* (XI .* Q ./ RD .* D11);
        %     AK3 = c1 .* ((Q .* app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.Y .* D11) ./ app.OKADA_CALC_VARS.CD) + c2 .* (app.OKADA_CALC_VARS.SD ./ RD .* (app.OKADA_CALC_VARS.XI2 .* D11 - F1));
        %     AJ3 = c1 .* ((AK1 - AJ2 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD) + c2 .* (-XI ./ RD2 .* (app.OKADA_CALC_VARS.Q2 .* D11 - F1 ./ F2));
        %     AJ6 = c1 .* ((AK3 - AJ5 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD) + c2 .* (-app.OKADA_CALC_VARS.Y ./ RD2 .* (app.OKADA_CALC_VARS.XI2 .* D11 - F1 ./ F2));
        % 
        %     app.OKADA_CALC_VARS.CD = tempCD;
        %     app.OKADA_CALC_VARS.CDCD = tempCDCD;
        %     XY = XI .* app.OKADA_CALC_VARS.Y11;
        %     AI1 = - XI ./ RD .* app.OKADA_CALC_VARS.CD - AI4 .* app.OKADA_CALC_VARS.SD;
        %     AI2 = double(log(RD)) + AI3 .* app.OKADA_CALC_VARS.SD;
        %     AK2 = F1 ./ app.OKADA_CALC_VARS.R + AK3 .* app.OKADA_CALC_VARS.SD;
        %     AK4 = XY .* app.OKADA_CALC_VARS.CD - AK1 .* app.OKADA_CALC_VARS.SD;
        %     AJ1 = AJ5 .* app.OKADA_CALC_VARS.CD - AJ6 .* app.OKADA_CALC_VARS.SD;
        %     AJ4 = - XY - AJ2 .* app.OKADA_CALC_VARS.CD + AJ3 .* app.OKADA_CALC_VARS.SD;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
        %     QX = Q .* app.OKADA_CALC_VARS.X11;
        %     QY = Q .* app.OKADA_CALC_VARS.Y11;
        % 
        %     % --------------------------------------
        %     %     STRIKE-SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c1 = DISL1 ~= F0;
        %     DU(:, 1) = - XI .* QY - app.OKADA_CALC_VARS.TT - app.OKADA_CALC_VARS.ALP3 .* AI1 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 2) = - Q ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.Y ./ RD .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 3) = Q .* QY - app.OKADA_CALC_VARS.ALP3 .* AI2 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 4) = app.OKADA_CALC_VARS.XI2 .* Q .* app.OKADA_CALC_VARS.Y32 - app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 5) = XI .* Q ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 6) = - XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 - app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 7) = - XI .* app.OKADA_CALC_VARS.FY - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.ALP3 .* (XY + AJ4) .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 8) = - app.OKADA_CALC_VARS.EY + app.OKADA_CALC_VARS.ALP3 .* (F1 ./ app.OKADA_CALC_VARS.R + AJ5) .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 9) = Q .* app.OKADA_CALC_VARS.FY - app.OKADA_CALC_VARS.ALP3 .* (QY - AJ6) .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 10) = - XI .* app.OKADA_CALC_VARS.FZ - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.ALP3 .* AK1 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 11) = - app.OKADA_CALC_VARS.EZ + app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.Y .* D11 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 12) = Q .* app.OKADA_CALC_VARS.FZ + app.OKADA_CALC_VARS.ALP3 .* AK2 .* app.OKADA_CALC_VARS.SD;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL1 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
        % 
        %     % --------------------------------------
        %     %     DIP-SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c2 = DISL2 ~= F0;
        %     DU(:, 1) = - Q ./ app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.ALP3 .* AI3 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 2) = - ET .* QX - app.OKADA_CALC_VARS.TT - app.OKADA_CALC_VARS.ALP3 .* XI ./ RD .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 3) = Q .* QX + app.OKADA_CALC_VARS.ALP3 .* AI4 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 4) = XI .* Q ./ app.OKADA_CALC_VARS.R3 +app.OKADA_CALC_VARS.ALP3 .* AJ4 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 5) = ET .* Q ./ app.OKADA_CALC_VARS.R3 + QY +app.OKADA_CALC_VARS.ALP3 .* AJ5 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 6) = - app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP3 .* AJ6 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 7) = - app.OKADA_CALC_VARS.EY + app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 8) = - ET .* app.OKADA_CALC_VARS.GY - XY .* app.OKADA_CALC_VARS.SD +app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 9) = Q .* app.OKADA_CALC_VARS.GY + app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 10) = - app.OKADA_CALC_VARS.EZ - app.OKADA_CALC_VARS.ALP3 .* AK3 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 11) = - ET .* app.OKADA_CALC_VARS.GZ - XY .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP3 .* XI .* D11 .* app.OKADA_CALC_VARS.SDCD;
        %     DU(:, 12) = Q .* app.OKADA_CALC_VARS.GZ - app.OKADA_CALC_VARS.ALP3 .* AK4 .* app.OKADA_CALC_VARS.SDCD;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL2 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
        % 
        %     % --------------------------------------
        %     %      TENSILE-FAULT CONTRIBUTION
        %     % --------------------------------------
        %     c3 = DISL3 ~= F0;
        %     DU(:, 1) = Q .* QY - app.OKADA_CALC_VARS.ALP3 .* AI3 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 2) = Q .* QX + app.OKADA_CALC_VARS.ALP3 .* XI ./ RD .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 3) = ET .* QX + XI .* QY - app.OKADA_CALC_VARS.TT - app.OKADA_CALC_VARS.ALP3 .* AI4 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 4) = - XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 - app.OKADA_CALC_VARS.ALP3 .* AJ4 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 5) = - app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3 - app.OKADA_CALC_VARS.ALP3 .* AJ5 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 6) = Q .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 - app.OKADA_CALC_VARS.ALP3 .* AJ6 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 7) = Q .* app.OKADA_CALC_VARS.FY - app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 8) = Q .* app.OKADA_CALC_VARS.GY - app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 9) = - Q .* app.OKADA_CALC_VARS.HY - app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 10) = Q .* app.OKADA_CALC_VARS.FZ + app.OKADA_CALC_VARS.ALP3 .* AK3 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 11) = Q .* app.OKADA_CALC_VARS.GZ + app.OKADA_CALC_VARS.ALP3 .* XI .* D11 .* app.OKADA_CALC_VARS.SDSD;
        %     DU(:, 12) = - Q .* app.OKADA_CALC_VARS.HZ + app.OKADA_CALC_VARS.ALP3 .* AK4 .* app.OKADA_CALC_VARS.SDSD;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL3 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
        % end

        function U = Okada_UB(app, XI, ET, Q, DISL1, DISL2, DISL3)
            % =====================================================================
            %                           Okada_UB (optimized)
            % =====================================================================
            
            N   = app.ASSIST_VARS.N_CELL;
            
            % ---- 定数（スカラー化：元のベクトル版と結果は完全一致） ----
            F0  = 0.0;
            F1  = 1.0;
            F2  = 2.0;
            PI2 = 6.283185307179586;   % 元コードと同じ値 (2*pi 近似)
            
            DU = zeros(N, 12, 'double');   % 各寄与で使い回す一時配列
            
            % =====================================================================
            %                     事前計算 (REAL/IMAGE 共通部)
            % =====================================================================
            RD  = app.OKADA_CALC_VARS.R + app.OKADA_CALC_VARS.D;
            D11 = F1 ./ (app.OKADA_CALC_VARS.R .* RD);
            AJ2 = XI .* app.OKADA_CALC_VARS.Y ./ RD .* D11;
            AJ5 = -(app.OKADA_CALC_VARS.D + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y ./ RD) .* D11;
            
            c1 = app.OKADA_CALC_VARS.CD ~= F0;
            c2 = app.OKADA_CALC_VARS.CD == F0;
            s1 = XI == F0;
            s2 = XI ~= F0;
            
            % ----- To avoid 'Inf' and 'nan' troubles (divided by zero) -----
            tempCD   = app.OKADA_CALC_VARS.CD;
            tempCDCD = app.OKADA_CALC_VARS.CDCD;
            
            app.OKADA_CALC_VARS.CD   = c1 .* app.OKADA_CALC_VARS.CD   + c2 .* 1.0e-12;
            app.OKADA_CALC_VARS.CDCD = c1 .* app.OKADA_CALC_VARS.CDCD + c2 .* 1.0e-12;
            
            X   = sqrt(app.OKADA_CALC_VARS.XI2 + app.OKADA_CALC_VARS.Q2);
            RD2 = RD .* RD;
            
            AI4 = c1 .* ( ...
                       s1 .* F0 + ...
                       s2 .* ( F1 ./ app.OKADA_CALC_VARS.CDCD .* ...
                             ( XI ./ RD .* app.OKADA_CALC_VARS.SDCD ...
                               + F2 .* atan( ...
                                   ( ET .* (X + Q .* app.OKADA_CALC_VARS.CD) ...
                                     + X .* (app.OKADA_CALC_VARS.R + X) .* app.OKADA_CALC_VARS.SD ) ...
                                   ./ ( XI .* (app.OKADA_CALC_VARS.R + X) .* app.OKADA_CALC_VARS.CD ) ...
                                 ) ...
                             ) ...
                           ) ...
                   ) ...
                   + c2 .* (XI .* app.OKADA_CALC_VARS.Y ./ RD2 ./ F2);
            
            AI3 = c1 .* ( ...
                      ( app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.CD ./ RD ...
                        - app.OKADA_CALC_VARS.ALE ...
                        + app.OKADA_CALC_VARS.SD .* log(RD) ) ...
                      ./ app.OKADA_CALC_VARS.CDCD ...
                   ) ...
                   + c2 .* ( ...
                      ( ET ./ RD + app.OKADA_CALC_VARS.Y .* Q ./ RD2 - app.OKADA_CALC_VARS.ALE ) ./ F2 ...
                   );
            
            AK1 = c1 .* ( XI .* (D11 - app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD ) ...
                  + c2 .* ( XI .* Q ./ RD .* D11 );
            
            AK3 = c1 .* ( (Q .* app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.Y .* D11) ./ app.OKADA_CALC_VARS.CD ) ...
                  + c2 .* ( app.OKADA_CALC_VARS.SD ./ RD .* (app.OKADA_CALC_VARS.XI2 .* D11 - F1 ./ F2) );
            
            AJ3 = c1 .* ( (AK1 - AJ2 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD ) ...
                  + c2 .* ( -XI ./ RD2 .* (app.OKADA_CALC_VARS.Q2 .* D11 - F1 ./ F2) );
            
            AJ6 = c1 .* ( (AK3 - AJ5 .* app.OKADA_CALC_VARS.SD) ./ app.OKADA_CALC_VARS.CD ) ...
                  + c2 .* ( -app.OKADA_CALC_VARS.Y ./ RD2 .* (app.OKADA_CALC_VARS.XI2 .* D11 - F1 ./ F2) );
            
            % CD を元に戻す
            app.OKADA_CALC_VARS.CD   = tempCD;
            app.OKADA_CALC_VARS.CDCD = tempCDCD;
            
            XY  = XI .* app.OKADA_CALC_VARS.Y11;
            AI1 = -XI ./ RD .* app.OKADA_CALC_VARS.CD - AI4 .* app.OKADA_CALC_VARS.SD;
            AI2 = log(RD) + AI3 .* app.OKADA_CALC_VARS.SD;
            AK2 = F1 ./ app.OKADA_CALC_VARS.R + AK3 .* app.OKADA_CALC_VARS.SD;
            AK4 = XY .* app.OKADA_CALC_VARS.CD - AK1 .* app.OKADA_CALC_VARS.SD;
            AJ1 = AJ5 .* app.OKADA_CALC_VARS.CD - AJ6 .* app.OKADA_CALC_VARS.SD;
            AJ4 = -XY - AJ2 .* app.OKADA_CALC_VARS.CD + AJ3 .* app.OKADA_CALC_VARS.SD;
            
            U  = zeros(N, 12, 'double');   % 出力
            QX = Q .* app.OKADA_CALC_VARS.X11;
            QY = Q .* app.OKADA_CALC_VARS.Y11;
            
            % =====================================================================
            %                     STRIKE-SLIP contribution
            % =====================================================================
            c1 = DISL1 ~= F0;
            
            DU(:, 1)  = -XI .* QY - app.OKADA_CALC_VARS.TT ...
                        - app.OKADA_CALC_VARS.ALP3 .* AI1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 2)  = -Q ./ app.OKADA_CALC_VARS.R ...
                        + app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.Y ./ RD .* app.OKADA_CALC_VARS.SD;
            DU(:, 3)  =  Q .* QY ...
                        - app.OKADA_CALC_VARS.ALP3 .* AI2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 4)  =  app.OKADA_CALC_VARS.XI2 .* Q .* app.OKADA_CALC_VARS.Y32 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 5)  =  XI .* Q ./ app.OKADA_CALC_VARS.R3 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 6)  = -XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SD;
            DU(:, 7)  = -XI .* app.OKADA_CALC_VARS.FY - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 ...
                        + app.OKADA_CALC_VARS.ALP3 .* (XY + AJ4) .* app.OKADA_CALC_VARS.SD;
            DU(:, 8)  = -app.OKADA_CALC_VARS.EY ...
                        + app.OKADA_CALC_VARS.ALP3 .* (F1 ./ app.OKADA_CALC_VARS.R + AJ5) .* app.OKADA_CALC_VARS.SD;
            DU(:, 9)  =  Q .* app.OKADA_CALC_VARS.FY ...
                        - app.OKADA_CALC_VARS.ALP3 .* (QY - AJ6) .* app.OKADA_CALC_VARS.SD;
            DU(:,10)  = -XI .* app.OKADA_CALC_VARS.FZ - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 ...
                        + app.OKADA_CALC_VARS.ALP3 .* AK1 .* app.OKADA_CALC_VARS.SD;
            DU(:,11)  = -app.OKADA_CALC_VARS.EZ ...
                        + app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.Y .* D11 .* app.OKADA_CALC_VARS.SD;
            DU(:,12)  =  Q .* app.OKADA_CALC_VARS.FZ ...
                        + app.OKADA_CALC_VARS.ALP3 .* AK2 .* app.OKADA_CALC_VARS.SD;
            
            scale1 = (DISL1 ./ PI2) .* c1;    % 0 または DISL1/2π
            U = U + scale1 .* DU;
            
            % =====================================================================
            %                     DIP-SLIP contribution
            % =====================================================================
            c2 = DISL2 ~= F0;
            
            DU(:, 1)  = -Q ./ app.OKADA_CALC_VARS.R ...
                        + app.OKADA_CALC_VARS.ALP3 .* AI3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 2)  = -ET .* QX - app.OKADA_CALC_VARS.TT ...
                        - app.OKADA_CALC_VARS.ALP3 .* XI ./ RD .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 3)  =  Q .* QX ...
                        + app.OKADA_CALC_VARS.ALP3 .* AI4 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 4)  =  XI .* Q ./ app.OKADA_CALC_VARS.R3 ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ4 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 5)  =  ET .* Q ./ app.OKADA_CALC_VARS.R3 + QY ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ5 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 6)  = -app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3 ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ6 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 7)  = -app.OKADA_CALC_VARS.EY ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 8)  = -ET .* app.OKADA_CALC_VARS.GY - XY .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 9)  =  Q .* app.OKADA_CALC_VARS.GY ...
                        + app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:,10)  = -app.OKADA_CALC_VARS.EZ ...
                        - app.OKADA_CALC_VARS.ALP3 .* AK3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:,11)  = -ET .* app.OKADA_CALC_VARS.GZ - XY .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP3 .* XI .* D11 .* app.OKADA_CALC_VARS.SDCD;
            DU(:,12)  =  Q .* app.OKADA_CALC_VARS.GZ ...
                        - app.OKADA_CALC_VARS.ALP3 .* AK4 .* app.OKADA_CALC_VARS.SDCD;
            
            scale2 = (DISL2 ./ PI2) .* c2;
            U = U + scale2 .* DU;
            
            % =====================================================================
            %                    TENSILE-FAULT contribution
            % =====================================================================
            c3 = DISL3 ~= F0;
            
            DU(:, 1)  =  Q .* QY ...
                        - app.OKADA_CALC_VARS.ALP3 .* AI3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 2)  =  Q .* QX ...
                        + app.OKADA_CALC_VARS.ALP3 .* XI ./ RD .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 3)  =  ET .* QX + XI .* QY - app.OKADA_CALC_VARS.TT ...
                        - app.OKADA_CALC_VARS.ALP3 .* AI4 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 4)  = -XI .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ4 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 5)  = -app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R3 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ5 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 6)  =  Q .* app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.Y32 ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ6 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 7)  =  Q .* app.OKADA_CALC_VARS.FY ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 8)  =  Q .* app.OKADA_CALC_VARS.GY ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ2 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 9)  = -Q .* app.OKADA_CALC_VARS.HY ...
                        - app.OKADA_CALC_VARS.ALP3 .* AJ3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:,10)  =  Q .* app.OKADA_CALC_VARS.FZ ...
                        + app.OKADA_CALC_VARS.ALP3 .* AK3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:,11)  =  Q .* app.OKADA_CALC_VARS.GZ ...
                        + app.OKADA_CALC_VARS.ALP3 .* XI .* D11 .* app.OKADA_CALC_VARS.SDSD;
            DU(:,12)  = -Q .* app.OKADA_CALC_VARS.HZ ...
                        + app.OKADA_CALC_VARS.ALP3 .* AK4 .* app.OKADA_CALC_VARS.SDSD;
            
            scale3 = (DISL3 ./ PI2) .* c3;
            U = U + scale3 .* DU;
            
        end


        function [U] = Okada_UB0(app, X, Y, D, Z, POT1, POT2, POT3, POT4)
            % =====================================================================
            %                Okada_UB0
            % =====================================================================
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
            F3 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 3.0;
            F4 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 4.0;
            F5 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 5.0;
            F8 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 8.0;
            F9 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 9.0;
            PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
            
            C = D + Z;
            RD = app.OKADA_CALC_VARS.R + D;
            D12 = F1 ./ (app.OKADA_CALC_VARS.R .* RD .* RD);
            D32 = D12 .* (F2 .* app.OKADA_CALC_VARS.R + D) ./ app.OKADA_CALC_VARS.R2;
            D33 = D12 .* (F3 .* app.OKADA_CALC_VARS.R + D) ./ (app.OKADA_CALC_VARS.R2 .* RD);
            D53 = D12 .* (F8 .* app.OKADA_CALC_VARS.R2 + F9 .* app.OKADA_CALC_VARS.R .* D + F3 .* app.OKADA_CALC_VARS.D2) ./ (app.OKADA_CALC_VARS.R2 .* app.OKADA_CALC_VARS.R2 .* RD);
            D54 = D12 .* (F5 .* app.OKADA_CALC_VARS.R2 + F4 .* app.OKADA_CALC_VARS.R .* D + app.OKADA_CALC_VARS.D2) ./ app.OKADA_CALC_VARS.R3 .* D12;
            
            FI1 = Y .* (D12 - app.OKADA_CALC_VARS.X2 .* D33);
            FI2 = X .* (D12 - app.OKADA_CALC_VARS.Y2 .* D33);
            FI3 = X ./ app.OKADA_CALC_VARS.R3 - FI2;
            FI4 = - app.OKADA_CALC_VARS.XY .* D32;
            FI5 = F1 ./ (app.OKADA_CALC_VARS.R .* RD)-app.OKADA_CALC_VARS.X2 .* D32;
            FJ1 = - F3 .* app.OKADA_CALC_VARS.XY .* (D33 - app.OKADA_CALC_VARS.X2 .* D54);
            FJ2 = F1 ./ app.OKADA_CALC_VARS.R3 - F3 .* D12 + F3 .* app.OKADA_CALC_VARS.X2 .* app.OKADA_CALC_VARS.Y2 .* D54;
            FJ3 = app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 - FJ2;
            FJ4 = - F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5 - FJ1;
            FK1 = - Y .* (D32 - app.OKADA_CALC_VARS.X2 .* D53);
            FK2 = - X .* (D32 - app.OKADA_CALC_VARS.Y2 .* D53);
            FK3 = - F3 .* X .* D ./ app.OKADA_CALC_VARS.R5 - FK2;
            U = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
            
            % --------------------------------------
            %  	 STRIKE-SLIP CONTRIBUTION
            % --------------------------------------
            c1 = POT1 ~= F0;
            DU(:, 1) = - app.OKADA_CALC_VARS.X2 .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 2) = - app.OKADA_CALC_VARS.XY .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 3) = - C .* X .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI4 .* app.OKADA_CALC_VARS.SD;
            DU(:, 4) = - X .* app.OKADA_CALC_VARS.QR .* (F1 + app.OKADA_CALC_VARS.A5) - app.OKADA_CALC_VARS.ALP3 .* FJ1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 5) = - Y .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5 - app.OKADA_CALC_VARS.ALP3 .* FJ2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 6) = - C .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5 - app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 7) = - F3 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UY - app.OKADA_CALC_VARS.ALP3 .* FJ2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 8) = - F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UY - X .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FJ4 .* app.OKADA_CALC_VARS.SD;
            DU(:, 9) = - F3 .* C .* X ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UY - app.OKADA_CALC_VARS.ALP3 .* FK2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 10) = - F3 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UZ + app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SD;
            DU(:, 11) = - F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.UZ + app.OKADA_CALC_VARS.ALP3 .* FK2 .* app.OKADA_CALC_VARS.SD;
            DU(:, 12) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - C .* app.OKADA_CALC_VARS.UZ + app.OKADA_CALC_VARS.ALP3 .* Y .* app.OKADA_CALC_VARS.SD);
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT1 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
            
            % --------------------------------------
            %   	DIP-SLIP CONTRIBUTION
            % --------------------------------------
            c2 = POT2 ~= F0;
            DU(:, 1) = - X .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR + app.OKADA_CALC_VARS.ALP3 .* FI3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 2) = - Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR + app.OKADA_CALC_VARS.ALP3 .* FI1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 3) = - C .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR + app.OKADA_CALC_VARS.ALP3 .* FI5 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 4) = - app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5 + app.OKADA_CALC_VARS.ALP3 .* FJ3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 5) = Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QRX + app.OKADA_CALC_VARS.ALP3 .* FJ1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 6) = C .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QRX + app.OKADA_CALC_VARS.ALP3 .* FK3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 7) = - F3 .* X ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY + app.OKADA_CALC_VARS.ALP3 .* FJ1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 8) = - F3 .* Y ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY - app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR +app.OKADA_CALC_VARS.ALP3 .* FJ2 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 9) = - F3 .* C ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VY + app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 10) = - F3 .* X ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ - app.OKADA_CALC_VARS.ALP3 .* FK3 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 11) = - F3 .* Y ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ - app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SDCD;
            DU(:, 12) = - F3 .* C ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.VZ + app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SDCD;
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT2 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
            
            % --------------------------------------
            %   	TENSILE-FAULT CONTRIBUTION
            % --------------------------------------
            c3 = POT3 ~= F0;
            DU(:, 1) = X .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 2) = Y .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 3) = C .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR - app.OKADA_CALC_VARS.ALP3 .* FI5 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 4) = app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5 - app.OKADA_CALC_VARS.ALP3 .* FJ3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 5) = - Y .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QRX - app.OKADA_CALC_VARS.ALP3 .* FJ1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 6) = - C .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QRX - app.OKADA_CALC_VARS.ALP3 .* FK3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 7) = X .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WY -app.OKADA_CALC_VARS.ALP3 .* FJ1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 8) = app.OKADA_CALC_VARS.QR .* (Y .* app.OKADA_CALC_VARS.WY + app.OKADA_CALC_VARS.Q) - app.OKADA_CALC_VARS.ALP3 .* FJ2 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 9) = C .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WY - app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 10) = X .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WZ + app.OKADA_CALC_VARS.ALP3 .* FK3 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 11) = Y .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WZ + app.OKADA_CALC_VARS.ALP3 .* FK1 .* app.OKADA_CALC_VARS.SDSD;
            DU(:, 12) = C .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.WZ - app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SDSD;
            
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT3 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
            
            % --------------------------------------
            %   	INFLATE SOURCE CONTRIBUTION
            % --------------------------------------
            c4 = POT4 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP3 .* X ./ app.OKADA_CALC_VARS.R3;
            DU(:, 2) = app.OKADA_CALC_VARS.ALP3 .* Y ./ app.OKADA_CALC_VARS.R3;
            DU(:, 3) = app.OKADA_CALC_VARS.ALP3 .* D ./ app.OKADA_CALC_VARS.R3;
            DU(:, 4) = app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3;
            DU(:, 5) = - app.OKADA_CALC_VARS.ALP3 .* F3 .* app.OKADA_CALC_VARS.XY ./ app.OKADA_CALC_VARS.R5;
            DU(:, 6) = - app.OKADA_CALC_VARS.ALP3 .* F3 .* X .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.B3 ./ app.OKADA_CALC_VARS.R3;
            DU(:, 9) = - app.OKADA_CALC_VARS.ALP3 .* F3 .* Y .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 10) = - DU(:, 6);
            DU(:, 11) = - DU(:, 9);
            DU(:, 12) = - app.OKADA_CALC_VARS.ALP3 .* app.OKADA_CALC_VARS.C3 ./ app.OKADA_CALC_VARS.R3;
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT4 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c4, 1, 12);
        end


        % function [U] = Okada_UC(app, XI, ET, Q, Z, DISL1, DISL2, DISL3)
        %     % =====================================================================
        %     %                Okada_UC
        %     % =====================================================================
        %     F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
        %     F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
        %     F3 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 3.0;
        %     PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;  
        %     DU = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
        % 
        %     C = app.OKADA_CALC_VARS.D + Z;
        %     X53 = (double(8.0) .* app.OKADA_CALC_VARS.R2 + double(9.0) .* app.OKADA_CALC_VARS.R .* XI + F3 .* app.OKADA_CALC_VARS.XI2) .* app.OKADA_CALC_VARS.X11 .* app.OKADA_CALC_VARS.X11 .* app.OKADA_CALC_VARS.X11 ./ app.OKADA_CALC_VARS.R2;
        %     Y53 = (double(8.0) .* app.OKADA_CALC_VARS.R2 + double(9.0) .* app.OKADA_CALC_VARS.R .* ET + F3 .* app.OKADA_CALC_VARS.ET2) .* app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.Y11 ./ app.OKADA_CALC_VARS.R2;
        %     H = Q .* app.OKADA_CALC_VARS.CD - Z;
        %     Z32 = app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R3 - H .* app.OKADA_CALC_VARS.Y32;
        %     Z53 = F3 .* app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R5 - H .* Y53;
        %     Y0 = app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.XI2 .* app.OKADA_CALC_VARS.Y32;
        %     Z0 = Z32 - app.OKADA_CALC_VARS.XI2 .* Z53;
        %     PPY = app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R3 + Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD;
        %     PPZ = app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R3 - Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD;
        %     QQ = Z .* app.OKADA_CALC_VARS.Y32 + Z32 + Z0;
        %     QQY = F3 .* C .* app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R5 - QQ .* app.OKADA_CALC_VARS.SD;
        %     QQZ = F3 .* C .* app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R5 - QQ .* app.OKADA_CALC_VARS.CD + Q .* app.OKADA_CALC_VARS.Y32;
        %     XY = XI .* app.OKADA_CALC_VARS.Y11;
        %     QX = Q .* app.OKADA_CALC_VARS.X11;
        %     QY = Q .* app.OKADA_CALC_VARS.Y11;
        %     QR = F3 .* Q ./ app.OKADA_CALC_VARS.R5;
        %     CQX = C .* Q .* X53;
        %     app.OKADA_CALC_VARS.CDR = (C + app.OKADA_CALC_VARS.D) ./ app.OKADA_CALC_VARS.R3;
        %     YY0 = app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.CD;
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = 0.0;
        % 
        %     % --------------------------------------
        %     %      STRIKE - SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c1 = DISL1 ~=  F0;
        %     DU(:, 1) = app.OKADA_CALC_VARS.ALP4 .* XY .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* XI .* Q .* Z32;
        %     DU(:, 2) = app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + F2 .* QY .* app.OKADA_CALC_VARS.SD) - app.OKADA_CALC_VARS.ALP5 .* C .* Q ./ app.OKADA_CALC_VARS.R3;
        %     DU(:, 3) = app.OKADA_CALC_VARS.ALP4 .* QY .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* (C .* ET ./ app.OKADA_CALC_VARS.R3 - Z .* app.OKADA_CALC_VARS.Y11 + app.OKADA_CALC_VARS.XI2 .* Z32);
        %     DU(:, 4) = app.OKADA_CALC_VARS.ALP4 .* Y0 .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* Q .* Z0;
        %     DU(:, 5) = - app.OKADA_CALC_VARS.ALP4 .* XI .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R3 + F2 .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD) + app.OKADA_CALC_VARS.ALP5 .* C .* XI .* QR;
        %     DU(:, 6) = - app.OKADA_CALC_VARS.ALP4 .* XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP5 .* XI .* (F3 .* C .* ET ./ app.OKADA_CALC_VARS.R5 - QQ);
        %     DU(:, 7) = - app.OKADA_CALC_VARS.ALP4 .* XI .* PPY .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* XI .* QQY;
        %     DU(:, 8) =  app.OKADA_CALC_VARS.ALP4 .* F2 .* (app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD - ET ./ app.OKADA_CALC_VARS.R3 - C .* app.OKADA_CALC_VARS.Y .* QR);
        %     DU(:, 9) = - app.OKADA_CALC_VARS.ALP4 .* Q ./ app.OKADA_CALC_VARS.R3 + YY0 .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD + C .* app.OKADA_CALC_VARS.D .* QR - (Y0 .* app.OKADA_CALC_VARS.CD + Q .* Z0) .* app.OKADA_CALC_VARS.SD);
        %     DU(:, 10) = app.OKADA_CALC_VARS.ALP4 .* XI .* PPZ .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* XI .* QQZ;
        %     DU(:, 11) = app.OKADA_CALC_VARS.ALP4 .* F2 .* (app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD + C .* app.OKADA_CALC_VARS.D .* QR);
        %     DU(:, 12) = YY0 .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD - C .* app.OKADA_CALC_VARS.Y .* QR - Y0 .* app.OKADA_CALC_VARS.SDSD + Q .* Z0 .* app.OKADA_CALC_VARS.CD);    
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL1 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
        % 
        %     % --------------------------------------
        %     %     DIP - SLIP CONTRIBUTION
        %     % --------------------------------------
        %     c2 = DISL2 ~=  F0;
        %     DU(:, 1) = app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R - QY .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.ALP5 .* C .* Q ./ app.OKADA_CALC_VARS.R3;
        %     DU(:, 2) = app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP5 .* C .* ET .* Q .* app.OKADA_CALC_VARS.X32;
        %     DU(:, 3) = - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 - XY .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.X32);
        %     DU(:, 4) = - app.OKADA_CALC_VARS.ALP4 .* XI ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP5 .* C .* XI .* QR + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD;
        %     DU(:, 5) = - app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP5 .* C .* ET .* QR;
        %     DU(:, 6) = app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.ALP5 .* C ./ app.OKADA_CALC_VARS.R3 .* (F1 - F3 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R2);
        %     DU(:, 7) = - app.OKADA_CALC_VARS.ALP4 .* ET ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.SDSD - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD - C .* app.OKADA_CALC_VARS.Y .* QR);
        %     DU(:, 8) = app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X32) - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D + F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.Y .* ET .* Q .* X53);
        %     DU(:, 9) = XI .* PPY .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 + app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y + F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Q2 .* X53);
        %     DU(:, 10) = - Q ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.SDCD - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD + C .* app.OKADA_CALC_VARS.D .* QR);
        %     DU(:, 11) =  app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y - F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 + app.OKADA_CALC_VARS.D .* ET .* Q .* X53);
        %     DU(:, 12) = - XI .* PPZ .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D - F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.Q2 .* X53);
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL2 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
        % 
        %     % --------------------------------------
        %     %       TENSILE - FAULT CONTRIBUTION
        %     % --------------------------------------
        %     c3 = DISL3 ~=  F0;
        %     DU(:, 1) = - app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R + QY .* app.OKADA_CALC_VARS.CD) - app.OKADA_CALC_VARS.ALP5 .* (Z .* app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.Q2 .* Z32);
        %     DU(:, 2) = app.OKADA_CALC_VARS.ALP4 .* F2 .* XY .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.X32);
        %     DU(:, 3) = app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + XY .* app.OKADA_CALC_VARS.CD) + app.OKADA_CALC_VARS.ALP5 .* Q .* (C .* ET .* app.OKADA_CALC_VARS.X32 + XI .* Z32);
        %     DU(:, 4) = app.OKADA_CALC_VARS.ALP4 .* XI ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.ALP5 .* XI .* (F3 .* C .* ET ./ app.OKADA_CALC_VARS.R5 - F2 .* Z32 - Z0);
        %     DU(:, 5) = app.OKADA_CALC_VARS.ALP4 .* F2 .* Y0 .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 + app.OKADA_CALC_VARS.ALP5 .* C ./ app.OKADA_CALC_VARS.R3 .* (F1 - F3 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R2);
        %     DU(:, 6) = - app.OKADA_CALC_VARS.ALP4 .* YY0 - app.OKADA_CALC_VARS.ALP5 .* (C .* ET .* QR - Q .* Z0);
        %     DU(:, 7) = app.OKADA_CALC_VARS.ALP4 .* (Q ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.SDCD) + app.OKADA_CALC_VARS.ALP5 .* (Z ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD + C .* app.OKADA_CALC_VARS.D .* QR - Q .* Z0 .* app.OKADA_CALC_VARS.SD);
        %     DU(:, 8) = - app.OKADA_CALC_VARS.ALP4 .* F2 .* XI .* PPY .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 + app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y + F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Q2 .* X53);
        %     DU(:, 9) = - app.OKADA_CALC_VARS.ALP4 .* (XI .* PPY .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X32) + app.OKADA_CALC_VARS.ALP5 .* (C .* ((app.OKADA_CALC_VARS.D + F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.Y .* ET .* Q .* X53) + XI .* QQY);
        %     DU(:, 10) = - ET ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.CDCD - app.OKADA_CALC_VARS.ALP5 .* (Z ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD - C .* app.OKADA_CALC_VARS.Y .* QR - Y0 .* app.OKADA_CALC_VARS.SDSD + Q .* Z0 .* app.OKADA_CALC_VARS.CD);
        %     DU(:, 11) = app.OKADA_CALC_VARS.ALP4 .* F2 .* XI .* PPZ .* app.OKADA_CALC_VARS.SD - app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D - F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.Q2 .* X53);
        %     DU(:, 12) = app.OKADA_CALC_VARS.ALP4 .* (XI .* PPZ .* app.OKADA_CALC_VARS.CD + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32) + app.OKADA_CALC_VARS.ALP5 .* (C .* ((app.OKADA_CALC_VARS.Y - F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 + app.OKADA_CALC_VARS.D .* ET .* Q .* X53) + XI .* QQZ);
        %     U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(DISL3 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
        % end

        function U = Okada_UC(app, XI, ET, Q, Z, DISL1, DISL2, DISL3)
            % =====================================================================
            %                           Okada_UC (optimized)
            % =====================================================================
            
            N   = app.ASSIST_VARS.N_CELL;
            
            % ---- 定数（スカラー化：元のベクトル版と結果は完全一致） ----
            F0  = 0.0;
            F1  = 1.0;
            F2  = 2.0;
            F3  = 3.0;
            PI2 = 6.283185307179586;   % 元コードと同じ値 (2*pi 近似)
            
            DU = zeros(N, 12, 'double');
            
            % =====================================================================
            %                              前計算
            % =====================================================================
            C   = app.OKADA_CALC_VARS.D + Z;
            
            X53 = (8.0 .* app.OKADA_CALC_VARS.R2 + 9.0 .* app.OKADA_CALC_VARS.R .* XI + ...
                   F3 .* app.OKADA_CALC_VARS.XI2) .* ...
                  app.OKADA_CALC_VARS.X11 .* app.OKADA_CALC_VARS.X11 .* app.OKADA_CALC_VARS.X11 ./ ...
                  app.OKADA_CALC_VARS.R2;
            
            Y53 = (8.0 .* app.OKADA_CALC_VARS.R2 + 9.0 .* app.OKADA_CALC_VARS.R .* ET + ...
                   F3 .* app.OKADA_CALC_VARS.ET2) .* ...
                  app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.Y11 .* app.OKADA_CALC_VARS.Y11 ./ ...
                  app.OKADA_CALC_VARS.R2;
            
            H   = Q .* app.OKADA_CALC_VARS.CD - Z;
            
            Z32 = app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R3 - H .* app.OKADA_CALC_VARS.Y32;
            Z53 = F3 .* app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R5 - H .* Y53;
            
            Y0  = app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.XI2 .* app.OKADA_CALC_VARS.Y32;
            Z0  = Z32 - app.OKADA_CALC_VARS.XI2 .* Z53;
            
            PPY = app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R3 + ...
                  Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD;
            PPZ = app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R3 - ...
                  Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD;
            
            QQ  = Z .* app.OKADA_CALC_VARS.Y32 + Z32 + Z0;
            QQY = F3 .* C .* app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R5 - QQ .* app.OKADA_CALC_VARS.SD;
            QQZ = F3 .* C .* app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R5 - QQ .* app.OKADA_CALC_VARS.CD + ...
                  Q .* app.OKADA_CALC_VARS.Y32;
            
            XY  = XI .* app.OKADA_CALC_VARS.Y11;
            QX  = Q .* app.OKADA_CALC_VARS.X11;
            QY  = Q .* app.OKADA_CALC_VARS.Y11;
            QR  = F3 .* Q ./ app.OKADA_CALC_VARS.R5;
            
            % CQX は定義されているが元コードでも未使用なので削除してOK（計算結果に影響なし）
            % CQX = C .* Q .* X53;
            
            app.OKADA_CALC_VARS.CDR = (C + app.OKADA_CALC_VARS.D) ./ app.OKADA_CALC_VARS.R3;
            YY0 = app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.CD;
            
            U = zeros(N, 12, 'double');
            
            % =====================================================================
            %                     STRIKE-SLIP CONTRIBUTION
            % =====================================================================
            c1 = DISL1 ~= F0;
            
            DU(:, 1)  = app.OKADA_CALC_VARS.ALP4 .* XY .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* XI .* Q .* Z32;
            DU(:, 2)  = app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R + ...
                         F2 .* QY .* app.OKADA_CALC_VARS.SD) ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* Q ./ app.OKADA_CALC_VARS.R3;
            DU(:, 3)  = app.OKADA_CALC_VARS.ALP4 .* QY .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* (C .* ET ./ app.OKADA_CALC_VARS.R3 - Z .* app.OKADA_CALC_VARS.Y11 + app.OKADA_CALC_VARS.XI2 .* Z32);
            DU(:, 4)  = app.OKADA_CALC_VARS.ALP4 .* Y0 .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* Q .* Z0;
            DU(:, 5)  = -app.OKADA_CALC_VARS.ALP4 .* XI .* (app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R3 + ...
                         F2 .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD) ...
                        + app.OKADA_CALC_VARS.ALP5 .* C .* XI .* QR;
            DU(:, 6)  = -app.OKADA_CALC_VARS.ALP4 .* XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD ...
                        + app.OKADA_CALC_VARS.ALP5 .* XI .* (F3 .* C .* ET ./ app.OKADA_CALC_VARS.R5 - QQ);
            DU(:, 7)  = -app.OKADA_CALC_VARS.ALP4 .* XI .* PPY .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* XI .* QQY;
            DU(:, 8)  =  app.OKADA_CALC_VARS.ALP4 .* F2 .* (app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 ...
                         - Y0 .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.SD ...
                         - app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD ...
                         - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD ...
                         - ET ./ app.OKADA_CALC_VARS.R3 - C .* app.OKADA_CALC_VARS.Y .* QR);
            DU(:, 9)  = -app.OKADA_CALC_VARS.ALP4 .* Q ./ app.OKADA_CALC_VARS.R3 ...
                        + YY0 .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD ...
                         + C .* app.OKADA_CALC_VARS.D .* QR ...
                         - (Y0 .* app.OKADA_CALC_VARS.CD + Q .* Z0) .* app.OKADA_CALC_VARS.SD);
            DU(:,10)  =  app.OKADA_CALC_VARS.ALP4 .* XI .* PPZ .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* XI .* QQZ;
            DU(:,11)  =  app.OKADA_CALC_VARS.ALP4 .* F2 .* (app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 ...
                         - Y0 .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.SD ...
                         + app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD ...
                         - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD ...
                         + C .* app.OKADA_CALC_VARS.D .* QR);
            DU(:,12)  =  YY0 .* app.OKADA_CALC_VARS.CD ...
                        - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD ...
                         - C .* app.OKADA_CALC_VARS.Y .* QR ...
                         - Y0 .* app.OKADA_CALC_VARS.SDSD + Q .* Z0 .* app.OKADA_CALC_VARS.CD);
            
            scale1 = (DISL1 ./ PI2) .* c1;
            U = U + scale1 .* DU;
            
            % =====================================================================
            %                      DIP-SLIP CONTRIBUTION
            % =====================================================================
            c2 = DISL2 ~= F0;
            
            DU(:, 1)  = app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.CD ./ app.OKADA_CALC_VARS.R ...
                        - QY .* app.OKADA_CALC_VARS.SD ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* Q ./ app.OKADA_CALC_VARS.R3;
            DU(:, 2)  = app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* ET .* Q .* app.OKADA_CALC_VARS.X32;
            DU(:, 3)  = -app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 - XY .* app.OKADA_CALC_VARS.SD ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.X32);
            DU(:, 4)  = -app.OKADA_CALC_VARS.ALP4 .* XI ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD ...
                        + app.OKADA_CALC_VARS.ALP5 .* C .* XI .* QR ...
                        + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.SD;
            DU(:, 5)  = -app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y ./ app.OKADA_CALC_VARS.R3 ...
                        + app.OKADA_CALC_VARS.ALP5 .* C .* ET .* QR;
            DU(:, 6)  =  app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 - Y0 .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.ALP5 .* C ./ app.OKADA_CALC_VARS.R3 .* (F1 - F3 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R2);
            DU(:, 7)  = -app.OKADA_CALC_VARS.ALP4 .* ET ./ app.OKADA_CALC_VARS.R3 ...
                        + Y0 .* app.OKADA_CALC_VARS.SDSD ...
                        - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.SD ...
                         - C .* app.OKADA_CALC_VARS.Y .* QR);
            DU(:, 8)  =  app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X32) ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D + F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.Y .* ET .* Q .* X53);
            DU(:, 9)  =  XI .* PPY .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 ...
                        + app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y + F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Q2 .* X53);
            DU(:,10)  = -Q ./ app.OKADA_CALC_VARS.R3 ...
                        + Y0 .* app.OKADA_CALC_VARS.SDCD ...
                        - app.OKADA_CALC_VARS.ALP5 .* (app.OKADA_CALC_VARS.CDR .* app.OKADA_CALC_VARS.CD ...
                         + C .* app.OKADA_CALC_VARS.D .* QR);
            DU(:,11)  =  app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y - F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 ...
                         + app.OKADA_CALC_VARS.D .* ET .* Q .* X53);
            DU(:,12)  = -XI .* PPZ .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D - F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.Q2 .* X53);
            
            scale2 = (DISL2 ./ PI2) .* c2;
            U = U + scale2 .* DU;
            
            % =====================================================================
            %                     TENSILE-FAULT CONTRIBUTION
            % =====================================================================
            c3 = DISL3 ~= F0;
            
            DU(:, 1)  = -app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.SD ./ app.OKADA_CALC_VARS.R + ...
                         QY .* app.OKADA_CALC_VARS.CD) ...
                        - app.OKADA_CALC_VARS.ALP5 .* (Z .* app.OKADA_CALC_VARS.Y11 - app.OKADA_CALC_VARS.Q2 .* Z32);
            DU(:, 2)  =  app.OKADA_CALC_VARS.ALP4 .* F2 .* XY .* app.OKADA_CALC_VARS.SD ...
                        + app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X11 ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.X11 - app.OKADA_CALC_VARS.Q2 .* app.OKADA_CALC_VARS.X32);
            DU(:, 3)  =  app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X11 + ...
                         XY .* app.OKADA_CALC_VARS.CD) ...
                        + app.OKADA_CALC_VARS.ALP5 .* Q .* (C .* ET .* app.OKADA_CALC_VARS.X32 + XI .* Z32);
            DU(:, 4)  =  app.OKADA_CALC_VARS.ALP4 .* XI ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD ...
                        + XI .* Q .* app.OKADA_CALC_VARS.Y32 .* app.OKADA_CALC_VARS.CD ...
                        + app.OKADA_CALC_VARS.ALP5 .* XI .* (F3 .* C .* ET ./ app.OKADA_CALC_VARS.R5 - F2 .* Z32 - Z0);
            DU(:, 5)  =  app.OKADA_CALC_VARS.ALP4 .* F2 .* Y0 .* app.OKADA_CALC_VARS.SD ...
                        - app.OKADA_CALC_VARS.D ./ app.OKADA_CALC_VARS.R3 ...
                        + app.OKADA_CALC_VARS.ALP5 .* C ./ app.OKADA_CALC_VARS.R3 .* (F1 - F3 .* app.OKADA_CALC_VARS.Q2 ./ app.OKADA_CALC_VARS.R2);
            DU(:, 6)  = -app.OKADA_CALC_VARS.ALP4 .* YY0 ...
                        - app.OKADA_CALC_VARS.ALP5 .* (C .* ET .* QR - Q .* Z0);
            DU(:, 7)  =  app.OKADA_CALC_VARS.ALP4 .* (Q ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.SDCD) ...
                        + app.OKADA_CALC_VARS.ALP5 .* (Z ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD ...
                         + C .* app.OKADA_CALC_VARS.D .* QR - Q .* Z0 .* app.OKADA_CALC_VARS.SD);
            DU(:, 8)  = -app.OKADA_CALC_VARS.ALP4 .* F2 .* XI .* PPY .* app.OKADA_CALC_VARS.SD ...
                        - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 ...
                        + app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.Y + F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Q2 .* X53);
            DU(:, 9)  = -app.OKADA_CALC_VARS.ALP4 .* (XI .* PPY .* app.OKADA_CALC_VARS.CD - ...
                         app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.X32) ...
                        + app.OKADA_CALC_VARS.ALP5 .* (C .* ((app.OKADA_CALC_VARS.D + F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.Y .* ET .* Q .* X53) + XI .* QQY);
            DU(:,10)  = -ET ./ app.OKADA_CALC_VARS.R3 + Y0 .* app.OKADA_CALC_VARS.CDCD ...
                        - app.OKADA_CALC_VARS.ALP5 .* (Z ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SD ...
                         - C .* app.OKADA_CALC_VARS.Y .* QR - Y0 .* app.OKADA_CALC_VARS.SDSD + Q .* Z0 .* app.OKADA_CALC_VARS.CD);
            DU(:,11)  =  app.OKADA_CALC_VARS.ALP4 .* F2 .* XI .* PPZ .* app.OKADA_CALC_VARS.SD ...
                        - app.OKADA_CALC_VARS.X11 + app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32 ...
                        - app.OKADA_CALC_VARS.ALP5 .* C .* ((app.OKADA_CALC_VARS.D - F2 .* Q .* app.OKADA_CALC_VARS.CD) .* app.OKADA_CALC_VARS.X32 ...
                         - app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.Q2 .* X53);
            DU(:,12)  =  app.OKADA_CALC_VARS.ALP4 .* (XI .* PPZ .* app.OKADA_CALC_VARS.CD + ...
                         app.OKADA_CALC_VARS.Y .* app.OKADA_CALC_VARS.D .* app.OKADA_CALC_VARS.X32) ...
                        + app.OKADA_CALC_VARS.ALP5 .* (C .* ((app.OKADA_CALC_VARS.Y - F2 .* Q .* app.OKADA_CALC_VARS.SD) .* app.OKADA_CALC_VARS.X32 ...
                         + app.OKADA_CALC_VARS.D .* ET .* Q .* X53) + XI .* QQZ);
            
            scale3 = (DISL3 ./ PI2) .* c3;
            U = U + scale3 .* DU;
            
        end


        function [U] = Okada_UC0(app, X, Y, D, Z, POT1, POT2, POT3, POT4)
            % =====================================================================
            %                Okada_UC0
            % =====================================================================
            F0 = zeros(app.ASSIST_VARS.N_CELL, 1, 'double');
            F1 = ones(app.ASSIST_VARS.N_CELL, 1, 'double');
            F2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 2.0;
            F3 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 3.0;
            F4 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 4.0;
            F5 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 5.0;
            F7 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 7.0;
            F10 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 10.0;
            F15 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 15.0;
            PI2 = ones(app.ASSIST_VARS.N_CELL, 1, 'double') .* 6.283185307179586;
            C = D + Z;
            Q2 = app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.Q;
            R7 = app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.R2;
            A7 = F1 - F7 .* app.OKADA_CALC_VARS.X2 ./ app.OKADA_CALC_VARS.R2;
            B5 = F1 - F5 .* app.OKADA_CALC_VARS.Y2 ./ app.OKADA_CALC_VARS.R2;
            B7 = F1 - F7 .* app.OKADA_CALC_VARS.Y2 ./ app.OKADA_CALC_VARS.R2;
            C5 = F1 - F5 .* app.OKADA_CALC_VARS.D2 ./ app.OKADA_CALC_VARS.R2;
            C7 = F1 - F7 .* app.OKADA_CALC_VARS.D2 ./ app.OKADA_CALC_VARS.R2;
            D7 = F2 - F7 .* Q2 ./ app.OKADA_CALC_VARS.R2;
            Qapp.OKADA_CALC_VARS.R5 = F5 .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            app.OKADA_CALC_VARS.QR7 = F7 .* app.OKADA_CALC_VARS.Q ./ app.OKADA_CALC_VARS.R2;
            Dapp.OKADA_CALC_VARS.R5 = F5 .* D ./ app.OKADA_CALC_VARS.R2;
            U = zeros(app.ASSIST_VARS.N_CELL, 12, 'double');
            
            % --------------------------------------
            %   	STRIKE - SLIP CONTRIBUTION
            % --------------------------------------
            c1 = POT1 ~= F0;
            DU(:, 1) = - app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.CD   + app.OKADA_CALC_VARS.ALP5 .* C .* app.OKADA_CALC_VARS.QR .* app.OKADA_CALC_VARS.A5;
            DU(:, 2) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* Y .* app.OKADA_CALC_VARS.CD  + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.SD - Y .* Qapp.OKADA_CALC_VARS.R5) );
            DU(:, 3) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* Y .* app.OKADA_CALC_VARS.SD  + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.CD + D .* Qapp.OKADA_CALC_VARS.R5) );
            DU(:, 4) = app.OKADA_CALC_VARS.ALP4 .* F3 .* X ./ app.OKADA_CALC_VARS.R5 .* (F2 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* C .* app.OKADA_CALC_VARS.QRX .* (F2 + A7);
            DU(:, 5) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* Y .* app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.CD  + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SD - Y .* Qapp.OKADA_CALC_VARS.R5 .* A7) );
            DU(:, 6) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* Y .* app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SD  + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.CD + D .* Qapp.OKADA_CALC_VARS.R5 .* A7) );
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* B5 .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.ALP5 .* F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (F2 .* Y .* app.OKADA_CALC_VARS.SD + app.OKADA_CALC_VARS.Q .* B7) );
            DU(:, 9) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* B5 .* app.OKADA_CALC_VARS.SD  + app.OKADA_CALC_VARS.ALP5 .* F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (D .* B7 .* app.OKADA_CALC_VARS.SD - Y .* C7 .* app.OKADA_CALC_VARS.CD) );
            DU(:, 10) = F3 ./ app.OKADA_CALC_VARS.R5 .*    ( - app.OKADA_CALC_VARS.ALP4 .* D .* app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.CD  + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.CD + D .* Qapp.OKADA_CALC_VARS.R5 .* A7) );
            DU(:, 11) = F15 .* X ./ R7 .* ( app.OKADA_CALC_VARS.ALP4 .* Y .* D .* app.OKADA_CALC_VARS.CD   + app.OKADA_CALC_VARS.ALP5 .* C .* (D .* B7 .* app.OKADA_CALC_VARS.SD - Y .* C7 .* app.OKADA_CALC_VARS.CD) );
            DU(:, 12) = F15 .* X ./ R7 .* ( - app.OKADA_CALC_VARS.ALP4 .* Y .* D .* app.OKADA_CALC_VARS.SD   + app.OKADA_CALC_VARS.ALP5 .* C .* (F2 .* D .* app.OKADA_CALC_VARS.CD - app.OKADA_CALC_VARS.Q .* C7) );
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT1 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c1, 1, 12);
            
            % --------------------------------------
            %       DIP - SLIP CONTRIBUTION
            % --------------------------------------
            c2 = POT2 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP4 .* F3 .* X .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5 - app.OKADA_CALC_VARS.ALP5 .* C .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QRX;
            DU(:, 2) = - app.OKADA_CALC_VARS.ALP4 ./ app.OKADA_CALC_VARS.R3 .* (app.OKADA_CALC_VARS.C2D - F3 .* Y .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R2) + app.OKADA_CALC_VARS.ALP5 .* F3 .* C ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.S - Y .* app.OKADA_CALC_VARS.P .* Qapp.OKADA_CALC_VARS.R5);
            DU(:, 3) = - app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.A3 ./ app.OKADA_CALC_VARS.R3 .* app.OKADA_CALC_VARS.SDCD + app.OKADA_CALC_VARS.ALP5 .* F3 .* C ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.T + D .* app.OKADA_CALC_VARS.P .* Qapp.OKADA_CALC_VARS.R5);
            DU(:, 4) = app.OKADA_CALC_VARS.ALP4 .* F3 .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.A5 - app.OKADA_CALC_VARS.ALP5 .* F5 .* C .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR ./ app.OKADA_CALC_VARS.R2 .* A7;
            DU(:, 5) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.C2D - F5 .* Y .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R2) - app.OKADA_CALC_VARS.ALP5 .* F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (app.OKADA_CALC_VARS.S - Y .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR7));
            DU(:, 6) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP4 .* (F2 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.SDCD - app.OKADA_CALC_VARS.ALP5 .* F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (app.OKADA_CALC_VARS.T + D .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR7));
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = F3 ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP4 .* (F2 .* Y .* app.OKADA_CALC_VARS.C2D + app.OKADA_CALC_VARS.T .* B5) + app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.S2D - F10 .* Y .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R2 - app.OKADA_CALC_VARS.P .* Qapp.OKADA_CALC_VARS.R5 .* B7));
            DU(:, 9) = F3 ./ app.OKADA_CALC_VARS.R5 .* (app.OKADA_CALC_VARS.ALP4 .* Y .* app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SDCD - app.OKADA_CALC_VARS.ALP5 .* C .* ((F3 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.C2D + Y .* app.OKADA_CALC_VARS.P .* Dapp.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.QR7));
            DU(:, 10) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.S2D - app.OKADA_CALC_VARS.T .* Dapp.OKADA_CALC_VARS.R5) - app.OKADA_CALC_VARS.ALP5 .* F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (app.OKADA_CALC_VARS.T + D .* app.OKADA_CALC_VARS.P .* app.OKADA_CALC_VARS.QR7));
            DU(:, 11) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* (D .* B5 .* app.OKADA_CALC_VARS.C2D + Y .* C5 .* app.OKADA_CALC_VARS.S2D) - app.OKADA_CALC_VARS.ALP5 .* C .* ((F3 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.C2D + Y .* app.OKADA_CALC_VARS.P .* Dapp.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.QR7));
            DU(:, 12) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* D .* app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SDCD - app.OKADA_CALC_VARS.ALP5 .* C .* (app.OKADA_CALC_VARS.S2D - F10 .* D .* app.OKADA_CALC_VARS.T ./ app.OKADA_CALC_VARS.R2 + app.OKADA_CALC_VARS.P .* Qapp.OKADA_CALC_VARS.R5 .* C7));
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT2 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c2, 1, 12);
            
            % --------------------------------------
            %   	TENSILE - FAULT CONTRIBUTION
            % --------------------------------------
            c3 = POT3 ~= F0;
            DU(:, 1) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.S  + app.OKADA_CALC_VARS.ALP5 .* (C .* app.OKADA_CALC_VARS.Q .* Qapp.OKADA_CALC_VARS.R5 - Z));
            DU(:, 2) = app.OKADA_CALC_VARS.ALP4 ./ app.OKADA_CALC_VARS.R3 .* (app.OKADA_CALC_VARS.S2D - F3 .* Y .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R2) + app.OKADA_CALC_VARS.ALP5 .* F3 ./ app.OKADA_CALC_VARS.R5 .* (C .* (app.OKADA_CALC_VARS.T - Y + Y .* app.OKADA_CALC_VARS.Q .* Qapp.OKADA_CALC_VARS.R5) - Y .* Z);
            DU(:, 3) = - app.OKADA_CALC_VARS.ALP4 ./ app.OKADA_CALC_VARS.R3 .* (F1 - app.OKADA_CALC_VARS.A3 .* app.OKADA_CALC_VARS.SDSD) - app.OKADA_CALC_VARS.ALP5 .* F3 ./ app.OKADA_CALC_VARS.R5 .* (C .* (app.OKADA_CALC_VARS.S - D + D .* app.OKADA_CALC_VARS.Q .* Qapp.OKADA_CALC_VARS.R5) - D .* Z);
            DU(:, 4) = - app.OKADA_CALC_VARS.ALP4 .* F3 .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.A5 + app.OKADA_CALC_VARS.ALP5 .* (C .* app.OKADA_CALC_VARS.QR .* Qapp.OKADA_CALC_VARS.R5 .* A7 - F3 .* Z ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.A5);
            DU(:, 5) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.S2D - F5 .* Y .* app.OKADA_CALC_VARS.S ./ app.OKADA_CALC_VARS.R2) - app.OKADA_CALC_VARS.ALP5 .* F5 ./ app.OKADA_CALC_VARS.R2 .* (C .* (app.OKADA_CALC_VARS.T - Y + Y .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR7) - Y .* Z));
            DU(:, 6) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* (F1 - (F2 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.SDSD) + app.OKADA_CALC_VARS.ALP5 .* F5 ./ app.OKADA_CALC_VARS.R2 .* (C .* (app.OKADA_CALC_VARS.S - D + D .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR7) - D .* Z));
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* (F2 .* Y .* app.OKADA_CALC_VARS.S2D + app.OKADA_CALC_VARS.S .* B5) - app.OKADA_CALC_VARS.ALP5 .* (C .* (F2 .* app.OKADA_CALC_VARS.SDSD + F10 .* Y .* (app.OKADA_CALC_VARS.T - Y) ./ app.OKADA_CALC_VARS.R2 - app.OKADA_CALC_VARS.Q .* Qapp.OKADA_CALC_VARS.R5 .* B7) + Z .* B5));
            DU(:, 9) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* Y .* (F1 - app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SDSD) + app.OKADA_CALC_VARS.ALP5 .* (C .* (F3 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.S2D - Y .* Dapp.OKADA_CALC_VARS.R5 .* (C .* D7 + Z)));
            DU(:, 10) = F3 .* X ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* (app.OKADA_CALC_VARS.C2D + app.OKADA_CALC_VARS.S .* Dapp.OKADA_CALC_VARS.R5) + app.OKADA_CALC_VARS.ALP5 .* (F5 .* C ./ app.OKADA_CALC_VARS.R2 .* (app.OKADA_CALC_VARS.S - D + D .* app.OKADA_CALC_VARS.Q .* app.OKADA_CALC_VARS.QR7) - F1 - Z .* Dapp.OKADA_CALC_VARS.R5));
            DU(:, 11) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( app.OKADA_CALC_VARS.ALP4 .* (D .* B5 .* app.OKADA_CALC_VARS.S2D - Y .* C5 .* app.OKADA_CALC_VARS.C2D) + app.OKADA_CALC_VARS.ALP5 .* (C .* ((F3 + app.OKADA_CALC_VARS.A5) .* app.OKADA_CALC_VARS.S2D - Y .* Dapp.OKADA_CALC_VARS.R5 .* D7) - Y .* (F1 + Z .* Dapp.OKADA_CALC_VARS.R5)));
            DU(:, 12) = F3 ./ app.OKADA_CALC_VARS.R5 .* ( - app.OKADA_CALC_VARS.ALP4 .* D .* (F1 - app.OKADA_CALC_VARS.A5 .* app.OKADA_CALC_VARS.SDSD) - app.OKADA_CALC_VARS.ALP5 .* (C .* (app.OKADA_CALC_VARS.C2D + F10 .* D .* (app.OKADA_CALC_VARS.S - D) ./ app.OKADA_CALC_VARS.R2 - app.OKADA_CALC_VARS.Q .* Qapp.OKADA_CALC_VARS.R5 .* C7) + Z .* (F1 + C5)));
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT3 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c3, 1, 12);
            
            % --------------------------------------
            % 	  INFLATE SOURCE CONTRIBUTION
            % --------------------------------------
            c4 = POT4 ~= F0;
            DU(:, 1) = app.OKADA_CALC_VARS.ALP4 .* F3 .* X .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 2) = app.OKADA_CALC_VARS.ALP4 .* F3 .* Y .* D ./ app.OKADA_CALC_VARS.R5;
            DU(:, 3) = app.OKADA_CALC_VARS.ALP4 .* app.OKADA_CALC_VARS.C3 ./ app.OKADA_CALC_VARS.R3;
            DU(:, 4) = app.OKADA_CALC_VARS.ALP4 .* F3 .* D ./ app.OKADA_CALC_VARS.R5 .* app.OKADA_CALC_VARS.A5;
            DU(:, 5) = - app.OKADA_CALC_VARS.ALP4 .* F15 .* app.OKADA_CALC_VARS.XY .* D ./ R7;
            DU(:, 6) = - app.OKADA_CALC_VARS.ALP4 .* F3 .* X ./ app.OKADA_CALC_VARS.R5 .* C5;
            DU(:, 7) = DU(:, 5);
            DU(:, 8) = app.OKADA_CALC_VARS.ALP4 .* F3 .* D ./ app.OKADA_CALC_VARS.R5 .* B5;
            DU(:, 9) = - app.OKADA_CALC_VARS.ALP4 .* F3 .* Y ./ app.OKADA_CALC_VARS.R5 .* C5;
            DU(:, 10) = DU(:, 6);
            DU(:, 11) = DU(:, 9);
            DU(:, 12) = app.OKADA_CALC_VARS.ALP4 .* F3 .* D ./ app.OKADA_CALC_VARS.R5 .* (F2 + C5);
            U(1:app.ASSIST_VARS.N_CELL, 1:12) = U(1:app.ASSIST_VARS.N_CELL, 1:12) + repmat(POT4 ./ PI2, 1, 12) .* DU(1:app.ASSIST_VARS.N_CELL, 1:12) .* repmat(c4, 1, 12);
        end


        function [xn, yn, al, aw] = coord_conversion(~, xgg, ygg, xs, ys, xf, yf, top, bottom, dip)
            % =====================================================================
            %                coord_conversion(~, xgg, ygg, xs, ys, xf, yf, top, bottom, dip)
            % =====================================================================
            % coord_conversion - グローバル座標系から断層座標系への座標変換を行う
            % 入力:
            %   xgg, ygg - グローバル座標系のX, Y座標（行列形式）
            %   xs, ys - 断層の開始点のX, Y座標（スカラー）
            %   xf, yf - 断層の終了点のX, Y座標（スカラー）
            %   top - 断層の上部深度（スカラー）
            %   bottom - 断層の下部深度（スカラー）
            %   dip - 断層の傾斜角（スカラー）
            % 出力:
            %   xn, yn - 断層座標系でのX, Y座標（行列形式）
            %   al - 断層の長さ（スカラー）
            %   aw - 断層の幅（スカラー）
        
            % 断層の中心位置を計算
            cx = (xf + xs) / 2.0;  % X方向の中心座標
            cy = (yf + ys) / 2.0;  % Y方向の中心座標
        
            % 傾斜角に基づく傾斜距離を計算
            k = tan(deg2rad(dip));  % 傾斜角のタンジェント値
            if k == 0
                k = 0.000001;  % 0除算を避けるための微小値
            end
            % h = bottom - top;  % 深さの差（断層の高さ）
            h = (bottom - top)/2.0;  % 深さの差（断層の高さ）
            d = h / k;  % 水平方向のシフト距離
        
            % 断層の方位角を計算
            % b = atan2((yf - ys), (xf - xs));  % atan2で方向を正確に計算
            b = atan((yf - ys)./(xf - xs));  % atan2で方向を正確に計算
        
            % 傾斜によるシフト量を計算
            ydipshift = abs(d * cos(b));  % Y方向のシフト量
            xdipshift = abs(d * sin(b));  % X方向のシフト量
        
            % 断層の中心位置をシフト
            if (xf > xs)
                if (yf > ys)
                    cx = cx + xdipshift;
                    cy = cy - ydipshift;
                else
                    cx = cx - xdipshift;
                    cy = cy - ydipshift;
                end
            else
                if (yf > ys)
                    cx = cx + xdipshift;
                    cy = cy + ydipshift;
                else
                    cx = cx - xdipshift;
                    cy = cy + ydipshift;
                end
            end
        
            % グローバル座標系からOkada断層座標系への変換
            xn = (xgg - cx) .* cos(b) + (ygg - cy) .* sin(b);
            yn = -(xgg - cx) .* sin(b) + (ygg - cy) .* cos(b);
            
            % 断層の長さが負の場合、座標を反転
            if (xf - xs) < 0.0
                xn = -xn;
                yn = -yn;
            end
        
            % 断層の長さと幅を計算
            al = sqrt((xf - xs)^2 + (yf - ys)^2) / 2.0;  % 断層の長さ
            aw = ((bottom - top) / 2.0) / sin(deg2rad(dip));  % 断層の幅
        end


        function [sn] = tensor_trans(~, sinb, cosb, so, nn)
            % =====================================================================
            %                tensor_trans(~, sinb, cosb, so, nn)
            % =====================================================================
            %  INPUT:  sinb,so,sn,flag
            %            so(1x6) matix: original stress tensor
            %               --> sxx,syy,szz,syz,sxz,sxy
            %  OUTPUT:   sn(1x6) matix: newly calculated stress tensor with
            %                         horizontal rotation (not plunge change)
        
            t = zeros(6, 6);
            sn = zeros(6, nn);
            ver = pi / 2.0;
            bt = asin(sinb); % contains ys & yf information (applicable for strike = 0-180 deg)
        
            if cosb > 0.0
                % rotation of axes (positive:clock wise, negative, anti-cw)
                xbeta = -bt;
                xdel = 0.0;
                ybeta = -bt + ver;
                ydel = 0.0;
                zbeta = -bt - ver;
                zdel = ver;
            else
                xbeta = bt - pi;
                xdel = 0.0;
                ybeta = bt - ver;
                ydel = 0.0;
                zbeta = bt - ver;
                zdel = ver;    
            end
        
            xl = cos(xdel) * cos(xbeta);
            xm = cos(xdel) * sin(xbeta);
            xn = sin(xdel);
            yl = cos(ydel) * cos(ybeta);
            ym = cos(ydel) * sin(ybeta);
            yn = sin(ydel);
            zl = cos(zdel) * cos(zbeta);
            zm = cos(zdel) * sin(zbeta);
            zn = sin(zdel);
        
            t(1, 1) = xl * xl;
            t(1, 2) = xm * xm;
            t(1, 3) = xn * xn;
            t(1, 4) = 2.0 * xm * xn;
            t(1, 5) = 2.0 * xn * xl;
            t(1, 6) = 2.0 * xl * xm;
            t(2, 1) = yl * yl;
            t(2, 2) = ym * ym;
            t(2, 3) = yn * yn;
            t(2, 4) = 2.0 * ym * yn;
            t(2, 5) = 2.0 * yn * yl;
            t(2, 6) = 2.0 * yl * ym;
            t(3, 1) = zl * zl;
            t(3, 2) = zm * zm;
            t(3, 3) = zn * zn;
            t(3, 4) = 2.0 * zm * zn;
            t(3, 5) = 2.0 * zn * zl;
            t(3, 6) = 2.0 * zl * zm;
            t(4, 1) = yl * zl;
            t(4, 2) = ym * zm;
            t(4, 3) = yn * zn;
            t(4, 4) = ym * zn + zm * yn;
            t(4, 5) = yn * zl + zn * yl;
            t(4, 6) = yl * zm + zl * ym;
            t(5, 1) = zl * xl;
            t(5, 2) = zm * xm;
            t(5, 3) = zn * xn;
            t(5, 4) = xm * zn + zm * xn;
            t(5, 5) = xn * zl + zn * xl;
            t(5, 6) = xl * zm + zl * xm;
            t(6, 1) = xl * yl;
            t(6, 2) = xm * ym;
            t(6, 3) = xn * yn;
            t(6, 4) = xm * yn + ym * xn;
            t(6, 5) = xn * yl + yn * xl;
            t(6, 6) = xl * ym + yl * xm;
        
            for k = 1:nn
                sn(:, k) = t(:, :) * so(:, k);
            end
        end


        function [a] = regional_stress(app, rs, dep)
            % =====================================================================
            %                regional_stress(app, rs, dep)
            % =====================================================================
            % changes regional stress inputs into one stress tensor matrix
            % INPUT: rs (regional stress matrix, 3x4)
            %        dep (calculation depth)
            % OUTPUT: a (stress tensor, 6x1)
        
            a = zeros(6, 1);
            m3 = rs(3, 3) + rs(3, 4) * dep;
            rxx = (-1.0) * (rs(1, 3) + rs(1, 4) * dep - m3);
            ryy = (-1.0) * (rs(2, 3) + rs(2, 4) * dep - m3);
            rzz = 0.0;
            rxy = 0.0;
            rxz = 0.0;
            ryz = 0.0;
            ss = [rxx; ryy; rzz; ryz; rxz; rxy];
            % changeconvention
            % call stress trans
            [a] = stress_trans(app, rs(1, 1), rs(2, 1), rs(3, 1), rs(1, 2), rs(2, 2), rs(3, 2), ss);
        end


        function [st] = stress_trans(app, bb1, bb2, bb3, dd1, dd2, dd3, ss)
            % =====================================================================
            %                stress_trans(app, bb1, bb2, bb3, dd1, dd2, dd3, ss)
            % =====================================================================
            % For transforming stress tensor in a given oordinate
            % INPUT: ss (stress tensor in a given coordinate, 6 x 1)
            %        b1-b3, d1-d3 (orientation and dip of the principal axes)
            % OUTPUT: st (stress tensor in another coordinate, 6 x 1)
            %        sequence of stress tensor comp
            %        [sxx; syy; szz; syz; sxz; sxy]
            
            [b1, d1] = adjust_principal(app, bb1, dd1);
            [b2, d2] = adjust_principal(app, bb2, dd2);
            [b3, d3] = adjust_principal(app, bb3, dd3);
            % tt = zeros(6, 6, 'double');
            % st = zeros(6, 1, 'double');
            
            % adjustment for our coordinate system from Aki & Richards convension
            xdel = deg2rad(double(d1));
            ydel = deg2rad(double(d2));
            zdel = deg2rad(double(d3));
            xbeta = deg2rad(double(b1));
            ybeta = deg2rad(double(b2));
            zbeta = deg2rad(double(b3));
            
            % seeking cosine
            xl = cos(xdel) * cos(xbeta);
            xm = cos(xdel) * sin(xbeta);
            xn = sin(xdel);
            yl = cos(ydel) * cos(ybeta);
            ym = cos(ydel) * sin(ybeta);
            yn = sin(ydel);
            zl = cos(zdel) * cos(zbeta);
            zm = cos(zdel) * sin(zbeta);
            zn = sin(zdel);
            
            % matrix inversion (should be converted to matrix form calc for MATLAB)
            xll = xl; xmm = xm; xnn = xn;
            yll = yl; ymm = ym; ynn = yn;
            zll = zl; zmm = zm; znn = zn;
            
            t11 = xll * xll;
            t12 = xmm * xmm;
            t13 = xnn * xnn;
            t14 = 2.0 * xmm * xnn;
            t15 = 2.0 * xnn * xll;
            t16 = 2.0 * xll * xmm;
            t21 = yll * yll;
            t22 = ymm * ymm;
            t23 = ynn * ynn;
            t24 = 2.0 * ymm * ynn;
            t25 = 2.0 * ynn * yll;
            t26 = 2.0 * yll * ymm;
            t31 = zll * zll;
            t32 = zmm * zmm;
            t33 = znn * znn;
            t34 = 2.0 * zmm * znn;
            t35 = 2.0 * znn * zll;
            t36 = 2.0 * zll * zmm;
            t41 = yll * zll;
            t42 = ymm * zmm;
            t43 = ynn * znn;
            t44 = ymm * znn + zmm * ynn;
            t45 = ynn * zll + znn * yll;
            t46 = yll * zmm + zll * ymm;
            t51 = zll * xll;
            t52 = zmm * xmm;
            t53 = znn * xnn;
            t54 = xmm * znn + zmm * xnn;
            t55 = xnn * zll + znn * xll;
            t56 = xll * zmm + zll * xmm;
            t61 = xll * yll;
            t62 = xmm * ymm;
            t63 = xnn * ynn;
            t64 = xmm * ynn + ymm * xnn;
            t65 = xnn * yll + ynn * xll;
            t66 = xll * ymm + yll * xmm;
            
            tt = [t11 t12 t13 t14 t15 t16; t21 t22 t23 t24 t25 t26; t31 t32 t33 t34 t35 t36;...
                t41 t42 t43 t44 t45 t46; t51 t52 t53 t54 t55 t56; t61 t62 t63 t64 t65 t66];
            st = tt \ ss;
        end


        function [bb, dd] = adjust_principal(~, b, d)
            % =====================================================================
            %                adjust_principal(~, b, d)
            % =====================================================================
            % adjust_principal は、与えられた角度 b（主軸の方位角）と d（主軸の傾斜角）を調整する関数です。
            % この調整は、標準的な地質学的または力学的な主軸の表現に合わせるために行われます。
            % 入力:
            %   b: 方位角（角度） - 通常は0度から360度の範囲
            %   d: 傾斜角（角度） - 通常は-90度から90度の範囲
            % 出力:
            %   bb: 調整後の方位角
            %   dd: 調整後の傾斜角
            
            % 方位角 b の調整 - 方位角を90度基準で調整します
            b = 90.0 - b;
            % 調整後の方位角が負の場合、180度を加えて正の範囲にします
            if b < 0.0
                b = 180.0 + b;
            end
            % 傾斜角 d が負の場合、その絶対値を取って正の角度にし、同時に方位角 b に180度を減算して方向を反転させます
            if d < 0.0
                d = -d;
                b = b - 180.0;    
            end
            bb = b;
            dd = d;
        end


        %=========================================================================
        %	  CALC & VIEW BUTTON 計算および表示ボタンに対応するコールバック関数
        %=========================================================================
        function calc_button_Callback(app)
            % =====================================================================
            %                calc_button_Callback(app)
            % =====================================================================
            % 計算および表示ボタンが押されたときの処理
        
            temp_reserve = app.CALC_CONTROL.FUNC_SWITCH;
            %--- 深度が変更されたときの処理 -----------
            if app.CALC_CONTROL.IACT ~= 1        
                Okada_halfspace(app);
            end

            app.CALC_CONTROL.IACT = 1; % Okadaの結果を保持
            a = app.OKADA_OUTPUT.DC3D(:, 1:2);
            b = app.OKADA_OUTPUT.DC3D(:, 5:8);
            c = horzcat(a, b);

            % Displacement.couを読み込んで、上書き
            menu_displacementcou_read_save(app, c);

            app.ASSIST_VARS.FLAG_DEPTH = 0;
            %------------------------------------------------------
            % 計算および表示
            selectedButton = app.FP_ButtonGroup.SelectedObject;
            disp(selectedButton)
            % ボタンに応じてコールバックを設定
            if selectedButton == app.NofixedpointButton
                % no-fix button
                % 固定点なしの計算
                cla(app.UIAxes); % 既存のプロットを削除
                hold(app.UIAxes, 'on');
                app.CALC_CONTROL.FUNC_SWITCH = temp_reserve; % 関数スイッチの状態を復元
                % displ_open(app, app.Rescale_output_Slider.Value / 10000); % スライダーの値を反映
                displ_open(app, app.ASSIST_VARS.DISP_SCALE);

            elseif selectedButton == app.FixedpointButton
                % fix button
                if app.ASSIST_VARS.FIXFLAG == 1
                    % xy座標系での固定点あり計算
                    app.ASSIST_VARS.FIXX = app.FP_fixXEditField.Value; % X座標取得
                    app.ASSIST_VARS.FIXY = app.FP_fixYEditField.Value; % Y座標取得
            
                    cla(app.UIAxes); % 既存のプロットを削除
                    hold(app.UIAxes, 'on');
                    app.CALC_CONTROL.FUNC_SWITCH = temp_reserve; % 関数スイッチの状態を復元5                
                    % displ_open(app, app.Rescale_output_Slider.Value / 10000); % スライダーの値を反映
    
                    % % SIZE(3, 1)を元にした、DISP_SCALEを用いて計算
                    ds = app.Rescale_output_x_EditField.Value;
                    app.ASSIST_VARS.DISP_SCALE = ds / double(app.INPUT_VARS.SIZE(3, 1));
    
                    displ_open(app, app.ASSIST_VARS.DISP_SCALE);
                    plot(app.UIAxes, app.ASSIST_VARS.FIXX, app.ASSIST_VARS.FIXY, 'ro'); % 固定点を表示
                
                elseif app.ASSIST_VARS.FIXFLAG == 2
                    % 経度・緯度座標系での固定点あり計算
                    b1 = app.FP_fixXEditField.Value; % 経度
                    b2 = app.FP_fixYEditField.Value; % 緯度
                    a = lonlat2xy(app, [b1 b2]); % 経度緯度をXYに変換
                    app.ASSIST_VARS.FIXX = a(1);
                    app.ASSIST_VARS.FIXY = a(2);
            
                    cla(app.UIAxes); % 既存のプロットを削除
                    hold(app.UIAxes, 'on');
                    app.CALC_CONTROL.FUNC_SWITCH = temp_reserve; % 関数スイッチの状態を復元
                    % displ_open(app, app.Rescale_output_Slider.Value / 10000); % スライダーの値を反映
    
                    % % SIZE(3, 1)を元にした、DISP_SCALEを用いて計算
                    ds = app.Rescale_output_x_EditField.Value;
                    app.ASSIST_VARS.DISP_SCALE = ds / double(app.INPUT_VARS.SIZE(3, 1));
                    
                    displ_open(app, app.ASSIST_VARS.DISP_SCALE);
                    plot(app.UIAxes, b1, b2, 'ro'); % 経度緯度固定点を表示
                end
            end
        
            % ----- オーバーレイ描画 --------------------------------
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end
        
            % ----- 断面ウィンドウが存在する場合の更新処理 ------------
            if app.ASSIST_VARS.XSEC_FLAG == 1
                CSC_draw_dipped_cross_section(app);
                CSC_displacement_section(app);
            end
        
            % ----- 表示をカルテシアンまたは経度・緯度座標系で更新
            if app.CALC_CONTROL.IACT == 0
                if app.COORD_VARS.ICOORD == 1
                    % カルテシアン座標系
                    app.FixedpointButton.Text = 'Fixed point (Cartesian)';
                    app.FP_xkmLabel.Text = 'km';
                    app.FP_ykmLabel.Text = 'km';
                else
                    % 経度・緯度座標系
                    app.FixedpointButton.Text = 'Fixed point (Lon/Lat, °)';
                    app.FP_xkmLabel.Text = '°';
                    app.FP_ykmLabel.Text = '°';
                end 
            end


        end


        function VD_view_button_Callback(app)
            % =====================================================================
            %                VD_view_button_Callback
            % =====================================================================
            % Vertical_displで用いる計算関数
        
            % スライダーの現在の値を取得
            n = app.Rescale_output_Slider.Value;

            coulomb_view(app, n);
            fault_overlay(app);

            % ----- overlay drawing --------------------------------
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app)
            end
        end

        
        function strain_view_button_Callback(app)
            % =====================================================================
            %                strain_view_button_Callback(app)
            % =====================================================================
            % 各種strainで用いる計算関数
            if app.CALC_CONTROL.IACT == 0
                Okada_halfspace(app);
                app.CALC_CONTROL.IACT = 1;
            end

            a = length(app.OKADA_OUTPUT.DC3D);
            if a < 14
                uialert(app.main_menu_window, 'Increase total grid number more than 14.', 'Warning!');
                return;
            end
            
            % Strain componentsを取得
            s9 = reshape(app.OKADA_OUTPUT.DC3D(:, 9), 1, a);   % SXX (strain)
            s10 = reshape(app.OKADA_OUTPUT.DC3D(:, 10), 1, a); % SYY
            s11 = reshape(app.OKADA_OUTPUT.DC3D(:, 11), 1, a); % SZZ
            s12 = reshape(app.OKADA_OUTPUT.DC3D(:, 12), 1, a); % SYZ
            s13 = reshape(app.OKADA_OUTPUT.DC3D(:, 13), 1, a); % SXZ
            s14 = reshape(app.OKADA_OUTPUT.DC3D(:, 14), 1, a); % SXY
            dil = s9 + s10 + s11;                              % Dilatation
            ss = [s9; s10; s11; s12; s13; s14; dil];

            a0 = app.OKADA_OUTPUT.DC3D(:, 1:2);
            a1 = zeros(length(a0), 1) + app.INPUT_VARS.CALC_DEPTH;
            b0 = app.OKADA_OUTPUT.DC3D(:, 9:14);
            c0 = app.OKADA_OUTPUT.DC3D(:, 9) + app.OKADA_OUTPUT.DC3D(:, 10) + app.OKADA_OUTPUT.DC3D(:, 11);
            d0 = horzcat(a0, a1, b0, c0);
            
            % Strain.couを読み込んで、上書きする関数
            cd (app.SYSTEM_VARS.HOME_DIR);
            format long;
            % データファイル 'Strain.cou' からデータを読み込む
            filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Strain.cou');
            fid = fopen(filePath, 'w');
            % ヘッダーを書き込む
            fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
            fprintf(fid, 'x y z exx eyy ezz eyz exz exy dilatation\n');
            fprintf(fid, '(km) (km) (km) (-) (-) (-) (-) (-) (-) (-)\n');
            % 数値データの書き込み（行ごとに書く）
            % d0 が Nx10 の行列と仮定
            for i = 1:size(d0, 1)
                fprintf(fid, '%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\n', d0(i, :));
            end
            fclose(fid);
            disp(['Strain.cou is saved in ', pwd]);
            showMessage(app, ['Strain.cou is saved in ', pwd], false);
            cd(app.SYSTEM_VARS.HOME_DIR);
        
            % Strain表示設定
            app.OKADA_OUTPUT.CC = zeros(app.ASSIST_VARS.NYINC, app.ASSIST_VARS.NXINC);
            switch (app.CALC_CONTROL.STRAIN_SWITCH)
                case 1  % SXX
                    n = 1;
                case 2  % SYY
                    n = 2;
                case 3  % SZZ
                    n = 3;
                case 4  % SYZ
                    n = 4;
                case 5  % SXZ
                    n = 5;
                case 6  % SXY
                    n = 6;
                case 7  % Dilatation
                    n = 7;
                otherwise
                    uialert(app.main_menu_window, 'under construction', 'Warning!');
                    return;
            end

            app.OKADA_OUTPUT.CC = reshape(ss(n, :), app.ASSIST_VARS.NYINC, app.ASSIST_VARS.NXINC);
            app.OKADA_OUTPUT.CC = app.OKADA_OUTPUT.CC(app.ASSIST_VARS.NYINC:-1:1, :);
            cmean = mean(reshape(mean(abs(app.OKADA_OUTPUT.CC)), app.ASSIST_VARS.NXINC, 1));
            app.ASSIST_VARS.COLORSN = log10(cmean);
        
            if isnan(app.ASSIST_VARS.COLORSN)
                % COLORSONにNaNが含まれている場合、警告して終了し、ボタンをmapview2DButtonにして再計算
                uialert(app.main_menu_window, 'A calculation point hits a singular point. Shift the grid slightly.', 'Warning!');
                app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
                menu_grid_mapview_Callback(app);
                return;
            end

            % Rescale outputスライダー、EditFieldとCOLORSONの値を比較し、異なっていた場合にCOLORSONへ代入
            if app.Rescale_output_Slider.Value ~= app.ASSIST_VARS.COLORSN
                app.ASSIST_VARS.COLORSN = app.Rescale_output_Slider.Value;
            elseif app.Rescale_output_x_EditField.Value ~= app.ASSIST_VARS.COLORSN
                app.ASSIST_VARS.COLORSN = app.Rescale_output_x_EditField.Value;
            end
        
            % coulomb_view関数で描画
            hold(app.UIAxes, 'on');
            coulomb_view(app, app.ASSIST_VARS.COLORSN);
            fault_overlay(app);
        
            % オーバーレイの描画
            % データが存在する場合、overlay_drawingを実行
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.EQ_DATA) || ...
                    ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on');
                overlay_drawing(app);
            end
        end


        function button_change_before_starting(app)
            % =====================================================================
            %                button_change_before_starting(app)
            % =====================================================================
            % ボタン切り替え時の初期化関数

            % メインウィンドウの既存のグラフを削除（app.UIAxesの内容をクリア）
            % クロスセクションの既存のグラフを削除（app.UIAxes2の内容をクリア）
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            % 2Dビューに変更
            view(app.UIAxes, 2);
            % カラーバーやタイトルも再設定
            title(app.UIAxes, 'Now calculating...', 'FontSize', 18);
            title(app.UIAxes2, 'Please operate the Calculation Tab.', 'FontSize', 18);
            % カラーバーをオフにする
            colorbar(app.UIAxes, 'off');
            colorbar(app.UIAxes2, 'off');
            % 凡例をオフにする
            legend(app.UIAxes, 'off');
            legend(app.UIAxes2, 'off');
            % サブフィギュアであるパネル類をクリア
            subfig_clear(app);

            % クロスセクションパネルを無効化
            app.CrossSectionControlPanel.Enable = 'off';
            
            % クロスセクション未演算とする
            app.ASSIST_VARS.XSEC_FLAG = 0;

            % 起動時に計算震度のデフォルト値を7.5に設定
            if isempty(app.INPUT_VARS.CALC_DEPTH)
                app.CalcDepthEditField.Value = 7.5;
            else
                app.CalcDepthEditField.Value = app.INPUT_VARS.CALC_DEPTH;
            end

            % 起動時に摩擦係数のデフォルト値を0.4に設定
            if isempty(app.INPUT_VARS.FRIC)
                app.FrictionEditField.Value = 0.4;
            else
                app.FrictionEditField.Value = app.INPUT_VARS.FRIC;
            end

            % 起動時に誇張度合いのデフォルト値を10000に設定
            if isempty(app.INPUT_VARS.SIZE(3, 1))
                app.DisplexaggerationEditField.Value = 10000;
                app.INPUT_VARS.SIZE(3, 1) = 10000;
            else
                app.DisplexaggerationEditField.Value = double(app.INPUT_VARS.SIZE(3, 1));
            end

            % 起動時にRescale outputスライダーの範囲を0~100、デフォルト値を20に設定
            app.Rescale_output_Slider.Limits = [0, 100];
            app.Rescale_output_Slider.Value = 20;
            % 起動時にRescale outputX編集ボックスの範囲を0~100、デフォルト値を20に設定
            app.Rescale_output_x_EditField.Limits = [0, 100];
            app.Rescale_output_x_EditField.Value = 20;
            % Rescale outputはこの後の関数で改めて初期値を設定する

            % 起動時にtiledを初期選択
            app.MosaicSwitch.Value = 'Tiled';

            % 等高線チェックボックスの初期状態をオフに設定する
            app.ContourlineCheckBox.Value = false;

            % 起動時にInterval編集ボックスのデフォルト値をボタンに応じて変更
            app.IntervalEditField.Value = 0.5;
        end

        function Rescale_output_Exaggeration_Start(app)
            % =====================================================================
            %                Rescale_output_Exaggeration_Start(app)
            % =====================================================================
            % スライダー値と誇張フィールドをボタンに応じて変更する起動時関数

            if isempty(app.GRAPHICS_VARS.C_SAT)
                % default color saturation value is 5 bars
                % 指定がない場合、デフォルトのColor saturationは5barとする
                app.GRAPHICS_VARS.C_SAT = 5;
            end

            % 選択されているボタンを取得
            selectedButton = app.FunctionButtonGroup.SelectedObject;
            % calc_button_Callback(app) を呼び出すボタンのチェック
            if selectedButton == app.mapview2DButton
                % 特に何もせず、初期とのままとする。

            elseif selectedButton == app.view3DButton
                % Color saturationの変更で使用する。
                % 修正必要？
                % app.F3D_Color_EditFieldと共存させるか検討する必要あり
                % ただし、こちらで使用するのはapp.SYSTEM_VARS.C_SLIP_SATなので、COLORSONではない

            elseif selectedButton == app.HDvectorsButton || ...
                    selectedButton == app.HDwireframeButton
                % 元はdispl_h_window
                % SIZE(3, 1)を元にした、DISP_SCALEを用いて計算

                % Rescale output値の範囲を0 ~ 10*app.INPUT_VARS.SIZE(3, 1)に変更
                % 入力ファイルでは10000であることが多いので、通常は0~100000
                cmax = double(10 * app.INPUT_VARS.SIZE(3, 1));
                app.Rescale_output_Slider.Limits = [0, cmax];
                app.Rescale_output_x_EditField.Limits = [0, cmax];
                % 初期値にapp.INPUT_VARS.SIZE(3, 1)
                app.Rescale_output_Slider.Value = double(app.INPUT_VARS.SIZE(3, 1));
                % ラベルをRescale outputに変更
                app.Rescaleoutput_Label.Text = 'Rescale output';
                % Rescale outputフィールドの初期値を設定
                app.Rescale_output_x_EditField.Value = double(app.INPUT_VARS.SIZE(3, 1));

                % 以下の% がもとだが、クロスセクションでy軸が大きくなりすぎるため以下に修正
                % app.ASSIST_VARS.DISP_SCALE = app.Rescale_output_x_EditField.Value;
                ds = app.Rescale_output_x_EditField.Value;
                app.ASSIST_VARS.DISP_SCALE = ds / double(app.INPUT_VARS.SIZE(3, 1));

            elseif selectedButton == app.VDButton
                % 元はvertical_displacement
                % 独自の変数を用いて計算(coulomb_view(app, n))

                if app.CALC_CONTROL.FUNC_SWITCH == 1 || app.CALC_CONTROL.FUNC_SWITCH == 10
                    dummy = zeros(length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                else
                    % データファイル 'Displacement.cou' からデータを読み込む
                    filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Displacement.cou');
                    fid = fopen(filePath, 'r');
                    % fopenが失敗した場合のエラーチェック
                    if fid == -1
                        error('Could not open file: %s', filePath);
                    end
                    coul = textscan(fid, '%f %f %f %f %f %f', 'delimiter', '\t', 'headerlines', 3);
                    fclose(fid);
                    % 読み込んだセルデータを行列に変換
                    uz = [coul{6}];
                    uuz = reshape(uz, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                    % 作業ディレクトリを元に戻す
                    cd(app.SYSTEM_VARS.HOME_DIR);
                end
                app.OKADA_OUTPUT.CC = reshape(uuz, length(app.COORD_VARS.YGRID), length(app.COORD_VARS.XGRID));
                app.OKADA_OUTPUT.CC = app.OKADA_OUTPUT.CC(length(app.COORD_VARS.YGRID):-1:1, :);

                % Rescale output値の範囲をcmin~cmaxに変更
                cmax = max(reshape(max(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                cmin = min(reshape(min(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                cmean = mean(reshape(mean(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                app.Rescale_output_Slider.Limits = [cmin, cmax];
                app.Rescale_output_x_EditField.Limits = [cmin, cmax];
                % 初期値にcmean
                app.Rescale_output_Slider.Value = cmean;
                % ラベルをColor Saturationに変更
                app.Rescaleoutput_Label.Text = 'Color Saturation';
                % Rescale outputフィールドの初期値を設定
                app.Rescale_output_x_EditField.Value = cmean;
                app.ASSIST_VARS.COLORSN = cmean;

            elseif selectedButton == app.image3DdrapeButton || ...
                   selectedButton == app.image3DwireframedsurfaceButton || ...
                   selectedButton == app.vectors3DButton
                % 元はmain_menu_windowでのexaggeration変更
                % Exaggerationの値、つまりapp.INPUT_VARS.SIZE(3, 1)を用いて計算を行う
                % 直前のbutton_change_before_starting(app)にて、Exaggerationボックス、つまりSIZE(3,1)は指定済み。
                % Rescale outputではSIZE(3,1)を用いて計算を行う。

                % Rescale output値の範囲は0以上ではあるが、便宜的に10^5としておく
                app.Rescale_output_Slider.Limits = [0.01, 100000];
                app.Rescale_output_x_EditField.Limits = [0, 100000];
                % 初期値にapp.INPUT_VARS.SIZE(3, 1)
                app.Rescale_output_Slider.Value = double(app.INPUT_VARS.SIZE(3, 1));
                % ラベルをRescale outputに変更
                app.Rescaleoutput_Label.Text = 'Exaggeration';
                % Rescale outputフィールドの初期値を設定
                app.Rescale_output_x_EditField.Value = double(app.INPUT_VARS.SIZE(3, 1));
                % app.ASSIST_VARS.DISP_SCALEがなかった場合、初期値にapp.INPUT_VARS.SIZE(3, 1)を設定
                % displ_open(app, scl)のsclとして使用される。
                % sclは倍率なので1なら等倍の誇張となる
                if isempty(app.ASSIST_VARS.DISP_SCALE)
                    app.ASSIST_VARS.DISP_SCALE = app.Rescale_output_x_EditField.Value / double(app.INPUT_VARS.SIZE(3, 1));
                end

            % strain_view_button_Callback(app) を呼び出すボタンのチェック
            elseif selectedButton == app.exxstrainButton || ...
                   selectedButton == app.eyystrainButton || ...
                   selectedButton == app.ezzstrainButton || ...
                   selectedButton == app.exystrainButton || ...
                   selectedButton == app.exzstrainButton || ...
                   selectedButton == app.eyzstrainButton || ...
                   selectedButton == app.DilatationButton
                % 元はstrain_window
                % COLORSONを用いて計算

                if app.CALC_CONTROL.IACT == 0
                    Okada_halfspace(app);
                    app.CALC_CONTROL.IACT = 1;
                end

                a = length(app.OKADA_OUTPUT.DC3D);
                if a < 14
                    warndlg('Increase total grid number more than 14.', 'Warning!');
                end
                % ss = zeros(7, a);
                s9 = reshape(app.OKADA_OUTPUT.DC3D(:, 9), 1, a);   % SXX (strain)
                s10 = reshape(app.OKADA_OUTPUT.DC3D(:, 10), 1, a); % SYY
                s11 = reshape(app.OKADA_OUTPUT.DC3D(:, 11), 1, a); % SZZ
                s12 = reshape(app.OKADA_OUTPUT.DC3D(:, 12), 1, a); % SYZ
                s13 = reshape(app.OKADA_OUTPUT.DC3D(:, 13), 1, a); % SXZ
                s14 = reshape(app.OKADA_OUTPUT.DC3D(:, 14), 1, a); % SXY
                dil = s9 + s10 + s11;                              % Dilatation
                ss = [s9; s10; s11; s12; s13; s14; dil];

                a0 = app.OKADA_OUTPUT.DC3D(:, 1:2);
                a1 = zeros(length(a0), 1) + app.INPUT_VARS.CALC_DEPTH;
                b0 = app.OKADA_OUTPUT.DC3D(:, 9:14);
                c0 = app.OKADA_OUTPUT.DC3D(:, 9) + app.OKADA_OUTPUT.DC3D(:, 10) + app.OKADA_OUTPUT.DC3D(:, 11);
                d0 = horzcat(a0, a1, b0, c0);
                
                % Strain.couを読み込んで、上書きする関数
                cd (app.SYSTEM_VARS.HOME_DIR);
                format long;
                % データファイル 'Strain.cou' からデータを読み込む
                filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Strain.cou');
                fid = fopen(filePath, 'w');
                % ヘッダーを書き込む
                fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
                fprintf(fid, 'x y z exx eyy ezz eyz exz exy dilatation\n');
                fprintf(fid, '(km) (km) (km) (-) (-) (-) (-) (-) (-) (-)\n');
                % 数値データの書き込み（行ごとに書く）
                % d0 が Nx10 の行列と仮定
                for i = 1:size(d0, 1)
                    fprintf(fid, '%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\t%.8f\n', d0(i, :));
                end
                fclose(fid);
                disp(['Strain.cou is saved in ', pwd]);
                showMessage(app, ['Strain.cou is saved in ', pwd], false);
                cd(app.SYSTEM_VARS.HOME_DIR);

                app.OKADA_OUTPUT.CC = zeros(app.ASSIST_VARS.NYINC, app.ASSIST_VARS.NXINC);
                switch (selectedButton)
                    case app.exxstrainButton % SXX
                        n = 1;
                    case app.eyystrainButton % SYY
                        n = 2;
                    case app.ezzstrainButton % SZZ
                        n = 3;
                    case app.eyzstrainButton % SYZ
                        n = 4;
                    case app.exzstrainButton % SXZ
                        n = 5;
                    case app.exystrainButton % SXY
                        n = 6;
                    case app.DilatationButton % Dilatation
                        n = 7;
                    case app.DilatationButton % Dilatation
                        n = 7;
                    otherwise       % cross section
                        warndlg('under construction', '!! Warning !!');
                end

                disp(['size(ss) = ', mat2str(size(ss))]);
                disp(['n = ', num2str(n)]);
                disp(['NYINC * NXINC = ', num2str(app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC)]);
                disp(['length(ss(n, :)) = ', num2str(length(ss(n, :)))]);
                showMessage(app, {['size(ss) = ', mat2str(size(ss))], ...
                    ['n = ', num2str(n)], ...
                    ['NYINC * NXINC = ', num2str(app.ASSIST_VARS.NYINC * app.ASSIST_VARS.NXINC)], ...
                    ['length(ss(n, :)) = ', num2str(length(ss(n, :)))] ...
                    }, false);

                app.OKADA_OUTPUT.CC = reshape(ss(n, :), app.ASSIST_VARS.NYINC, app.ASSIST_VARS.NXINC);
                app.OKADA_OUTPUT.CC = app.OKADA_OUTPUT.CC(app.ASSIST_VARS.NYINC:-1:1, :);
                % Rescale outputスライダー値の範囲をcmin~cmaxに変更
                cmax = max(reshape(max(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                cmin = min(reshape(min(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                cmean = mean(reshape(mean(abs(app.OKADA_OUTPUT.CC)), length(app.COORD_VARS.XGRID), 1));
                app.ASSIST_VARS.COLORSN = log10(cmean);

                if isnan(app.ASSIST_VARS.COLORSN)
                    % COLORSONにNaNが含まれている場合、警告して終了し、ボタンをmapview2DButtonにして再計算
                    uialert(app.main_menu_window, 'A calculation point hits a singular point. Shift the grid slightly.', 'Warning!');
                    app.FunctionButtonGroup.SelectedObject = app.mapview2DButton;
                    menu_grid_mapview_Callback(app);
                    return;
                end

                % Rescale output値の範囲をlog10(cmin)~log10(cmax)に変更
                app.Rescale_output_Slider.Limits = [log10(cmin), log10(cmax)];
                app.Rescale_output_x_EditField.Limits = [log10(cmin), log10(cmax)];
                % 初期値にapp.ASSIST_VARS.COLORSN = log10(cmean)を設定
                app.Rescale_output_Slider.Value = app.ASSIST_VARS.COLORSN;
                % ラベルをColor Saturationに変更
                app.Rescaleoutput_Label.Text = 'Color Saturation';
                % Rescale outputフィールドの初期値を設定
                app.Rescale_output_x_EditField.Value = app.ASSIST_VARS.COLORSN;

            % stress windowによって計算する場合
            elseif selectedButton == app.ShearstresschangeButton || ...
                   selectedButton == app.UnclampingstresschangeButton || ...
                   selectedButton == app.CoulombstresschangeButton
                % 元はcoulomb_window

                % Rescale output値の範囲を0~100に変更
                app.Rescale_output_Slider.Limits = [0.000001, 100];
                app.Rescale_output_x_EditField.Limits = [0.000001, 100];
                % 初期値に5.0を設定
                app.Rescale_output_Slider.Value = 5.0;
                % ラベルをColor Saturationに変更
                app.Rescaleoutput_Label.Text = 'Color Saturation (±bar)';
                % Rescale outputフィールドの初期値を設定
                app.Rescale_output_x_EditField.Value = 5.0;
                % app.ASSIST_VARS.COLORSNの初期値を5.0とする
                app.ASSIST_VARS.COLORSN = 5.0;

            end      

        end


        function Rescale_output_calc_Callback(app)
            % =====================================================================
            %                Rescale_output_calc_Callback(app)
            % =====================================================================
            % Rescale output or exaggerationを変更したときの計算関数
            % 計算を実行する関数を呼び出し

            % 現在の関数スイッチの状態を一時的に保存
            temp_reserve = app.CALC_CONTROL.FUNC_SWITCH;

            % 'record_stamp'タグのついたスタンプの描画を削除
            record_stamp = findall(app.UIAxes, 'Tag', 'record_stamp');
            if ~isempty(record_stamp)
                delete(record_stamp);
            end
            
            % 関数スイッチの状態を復元
            app.CALC_CONTROL.FUNC_SWITCH = temp_reserve;

            % メインウィンドウの既存のグラフを削除（app.UIAxesの内容をクリア）
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            % 選択されているボタンを取得
            selectedButton = app.FunctionButtonGroup.SelectedObject;
            % calc_button_Callback(app) を呼び出すボタンのチェック
            if selectedButton == app.mapview2DButton
                % 特に何もせず、初期とのままとする。
                % 計算
                calc_button_Callback(app);

            elseif selectedButton == app.view3DButton
                % Color saturationの変更で使用する。
                % 修正必要？
                % app.F3D_Color_EditFieldと共存させるか検討する必要あり
                % ただし、こちらで使用するのはapp.SYSTEM_VARS.C_SLIP_SATなので、COLORSONではない
                % 計算
                calc_button_Callback(app);

            elseif selectedButton == app.HDvectorsButton || ...
                    selectedButton == app.HDwireframeButton
                % SIZE(3, 1)を元にした、DISP_SCALEを用いて計算
                ds = app.Rescale_output_x_EditField.Value;
                app.ASSIST_VARS.DISP_SCALE = ds / double(app.INPUT_VARS.SIZE(3, 1));
                disp(app.ASSIST_VARS.DISP_SCALE)
                % mx = app.Rescale_output_Slider.Limits(2);
                % % 以下、修正必要、検討
                % if app.ASSIST_VARS.DISP_SCALE >= mx
                %     % 最大値より大きい時
                %     % スライダーを最大値に設定
                %     app.Rescale_output_Slider.Value = mx;
                %     app.ASSIST_VARS.DISP_SCALE = mx * double(app.INPUT_VARS.SIZE(3, 1));
                % else
                %     app.Rescale_output_Slider.Value = app.ASSIST_VARS.DISP_SCALE;
                %     app.Rescale_output_x_EditField.Value= app.ASSIST_VARS.DISP_SCALE;
                %     app.DisplexaggerationEditField.Value= app.ASSIST_VARS.DISP_SCALE;
                % end

                % 計算
                calc_button_Callback(app);
            
            elseif selectedButton == app.VDButton
                % n = app.Rescale_output_x_EditField.Valueとして、coulomb_view(app, n)をする必要がある
                % スライダー、もしくはボックスの値をapp.ASSIST_VARS.COLORSNに代入
                % さらに、編集フィールドの値を更新
                if app.ASSIST_VARS.COLORSN ~= app.Rescale_output_Slider.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_Slider.Value;
                elseif app.ASSIST_VARS.COLORSN ~= app.Rescale_output_x_EditField.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_x_EditField.Value;
                end

                calc_button_Callback(app);

            elseif selectedButton == app.image3DdrapeButton || ...
               selectedButton == app.image3DwireframedsurfaceButton || ...
               selectedButton == app.vectors3DButton

                % スライダー、もしくはボックスの値をapp.INPUT_VARS.SIZE(3, 1)に代入
                % さらに、編集フィールドの値を更新
                if app.INPUT_VARS.SIZE(3, 1) ~= app.Rescale_output_Slider.Value
                    app.INPUT_VARS.SIZE(3, 1) = app.Rescale_output_Slider.Value;
                    app.DisplexaggerationEditField.Value = app.Rescale_output_Slider.Value;
                elseif app.INPUT_VARS.SIZE(3, 1) ~= app.Rescale_output_x_EditField.Value
                    app.INPUT_VARS.SIZE(3, 1) = app.Rescale_output_x_EditField.Value;
                    app.DisplexaggerationEditField.Value = app.Rescale_output_x_EditField.Value;
                end
                % 計算
                calc_button_Callback(app);  % calc_button_Callback を呼び出す

            % strain_view_button_Callback(app) を呼び出すボタンのチェック
            elseif selectedButton == app.exxstrainButton || ...
                   selectedButton == app.eyystrainButton || ...
                   selectedButton == app.ezzstrainButton || ...
                   selectedButton == app.exystrainButton || ...
                   selectedButton == app.exzstrainButton || ...
                   selectedButton == app.eyzstrainButton || ...
                   selectedButton == app.DilatationButton

                % スライダー、もしくはボックスの値をapp.ASSIST_VARS.COLORSNに代入
                % さらに、編集フィールドの値を更新
                if app.ASSIST_VARS.COLORSN ~= app.Rescale_output_Slider.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_Slider.Value;
                elseif app.ASSIST_VARS.COLORSN ~= app.Rescale_output_x_EditField.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_x_EditField.Value;
                end
                % 計算
                strain_view_button_Callback(app);  % strain_view_button_Callback を呼び出す

            % stress windowによって計算する場合
            elseif selectedButton == app.ShearstresschangeButton || ...
                   selectedButton == app.UnclampingstresschangeButton || ...
                   selectedButton == app.CoulombstresschangeButton

                % スライダー、もしくはボックスの値をapp.ASSIST_VARS.COLORSNに代入
                % さらに、編集フィールドの値を更新
                if app.ASSIST_VARS.COLORSN ~= app.Rescale_output_Slider.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_Slider.Value;
                elseif app.ASSIST_VARS.COLORSN ~= app.Rescale_output_x_EditField.Value
                    app.ASSIST_VARS.COLORSN = app.Rescale_output_x_EditField.Value;
                end
                % 計算
                % - 通常：従来の 2D 計算 (SW_coulomb_calc_and_view)
                % - 3D モード ON：新しい 3D スライス計算 (SW_coulomb_calc_and_view_3D)
        
                if isprop(app, 'SW_3Dcalc_CheckBox') && app.SW_3Dcalc_CheckBox.Value
                    % 3D スライスモード
                    SW_coulomb_calc_and_view_3D(app);
                else
                    % 従来どおりの 2D 計算
                    SW_coulomb_calc_and_view(app);
                end
            end            
        end    


        % ==========================================================================================================================================
        %                ストレスの計算関連
        % ==========================================================================================================================================
        function ec_control_window(app)
            % =====================================================================
            %                ec_control_window(app)
            % =====================================================================
            % デフォルトのストレスタイプを設定
            app.ASSIST_VARS.EC_STRESS_TYPE = 1;
        end
                    
        function bb2(app, fm, centerX, centerY, diam, ta, iFlag, color)
            % =====================================================================
            %                bb2(app, fm, centerX, centerY, diam, ta, iFlag, color)
            % =====================================================================
            % bb2: 地震の二重カップル焦点メカニズムのビーチボール図を描画する関数
            % fm: 焦点メカニズム、(NM) x 3 の行列 (strike, dip, rake) または (NM) x 6 の行列
            % (mxx, myy, mzz, mxy, mxz, myz - モーメントテンソルの独立した6成分)
            % centerX, centerY: ビーチボールの配置位置
            % diam            : 直径、0の場合はステレオネット上に描画
            % ta              : 軸のタイプ（0: 通常軸, 1: マップ軸 - 緯度経度で描画）
            % iFlag           : ノーダル面を描画するフラグ（0: 通常, 1: 赤いアークを描画）
            % color           : 張力四分円の色を指定する（例: 'r'、[R G B]など）
        
            penw = 0.1;  % ペンの幅
            penc = 0.3;  % クーロン計算用のノーダル面の描画用ペン幅
            adj = 1.00;  % ビーチボールのアスペクト比を調整
              
            [ne, n] = size(fm);
            % モーメントテンソルが与えられている場合は、strike, dip, rakeを計算
            if n == 6
                for i = 1:ne
                    [s1(i), d1(i), r1(i)] = mij2sdr(fm(i, 1), fm(i, 2), fm(i, 3), fm(i, 4), fm(i, 5), fm(i, 6));
                end
            else
                % (strike, dip, rake) の情報が与えられている場合
                s1 = round(fm(:, 1));
                d1 = round(fm(:, 2));
                r1 = round(fm(:, 3));
            end
        
            % ラジアンと度の変換用
            d2r = pi / 180;
            % 中心Yの平均を使用して描画比を調整
            ampy = cos(mean(centerY) * d2r) * adj;
        
            % rake の値を調整
            mech = zeros(ne, 1);
            j = find(r1 > 180);
            r1(j) = r1(j) - 180;
            mech(j) = 1;
            j = find(r1 < 0);
            r1(j) = r1(j) + 180;
            mech(j) = 1;

            % *** Originally implemented 'AuxPlane' has a big trouble (bug!) **
            % Get azimuth and dip of second plane
            % [s2,d2,r2] = AuxPlane(s1,d1,r1);
            % format short
            % [fm(:,1) fm(:,2) fm(:,3) s1 d1 r1 s2 d2 r2]
        
            % ノーダル面の計算
            [outsdr] = nodal_plane_calc(app, s1, d1, r1);
            s1 = outsdr(:, 1);
            d1 = outsdr(:, 2);
            r1 = outsdr(:, 3);
            s2 = outsdr(:, 4);
            d2 = outsdr(:, 5);
        
            if diam(1) > 0
                hold(app.UIAxes, 'on');
            end
        

            % ==========================
            % 進捗バーの初期化
            % ==========================
            dlg = uiprogressdlg(app.main_menu_window, ...
                'Title', 'Drawing Beachballs', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'on', ...
                'Value', 0);
            try

                % ビーチボールの描画
                for ev = 1:ne
                    if dlg.CancelRequested
                        delete(dlg);
                        uialert(app.main_menu_window, 'Operation cancelled by user.', 'Cancelled');
                        return;
                    end

                    % ---- ビーチボール描画処理 ----
                    S1 = s1(ev);
                    D1 = d1(ev);
                    S2 = s2(ev);
                    D2 = d2(ev);
                    P = r1(ev);
                    CX = centerX(ev);
                    CY = centerY(ev);
                    D = diam(ev);
                    M = mech(ev);
            
                    if M > 0
                        P = 2;
                    else
                        P = 1;
                    end
            
                    % dip の値を調整（90度を超えないように）
                    if D1 >= 90
                        D1 = 89.9999;
                    end
                    if D2 >= 90
                        D2 = 89.9999;
                    end
            
                    % 各ノーダル面の円弧を計算
                    phi = 0:.05:pi;
                    d = 90 - D1;
                    m = 90;
                    l1 = sqrt(d ^ 2 ./ (sin(phi) .^ 2 + cos(phi) .^ 2 * d ^ 2 / m ^ 2));
                    d = 90 - D2;
                    m = 90;
                    l2 = sqrt(d ^ 2 ./ (sin(phi) .^ 2 + cos(phi) .^ 2 * d ^ 2 / m ^ 2));
            
                    if D == 0
                        % stereo(app, phi + S1 * d2r, l1, 'k');
                        % stereo(app, phi + S2 * d2r, l2, 'k');
                        stereo(app.UIAxes, phi + S1 * d2r, l1, 'k');
                        stereo(app.UIAxes, phi + S2 * d2r, l2, 'k');
                    end
            
                    inc = 1;
                    [X1, Y1] = pol2cart(phi + S1 * d2r, l1);
                    if P == 1
                        lo = S1 - 180;
                        hi = S2;
                        if lo > hi
                            inc = -inc;
                        end
                        th1 = S1 - 180:inc:S2;
                        [Xs1, Ys1] = pol2cart(th1 * d2r, 90 * ones(1, length(th1)));
                        [X2, Y2] = pol2cart(phi + S2 * d2r, l2);
                        th2 = S2+180:-inc:S1;
                    else
                        hi = S1 - 180;
                        lo = S2 - 180;
                        if lo > hi
                            inc = -inc;
                        end
                        th1 = hi:-inc:lo;
                        [Xs1, Ys1] = pol2cart(th1 * d2r, 90 * ones(1, length(th1)));
                        [X2, Y2] = pol2cart(phi + S2 * d2r, l2);
                        X2 = fliplr(X2);
                        Y2 = fliplr(Y2);
                        th2 = S2:inc:S1;
                    end
            
                    [Xs2, Ys2] = pol2cart(th2 * d2r, 90 * ones(1, length(th2)));
                    X = cat(2, X1, Xs1, X2, Xs2);
                    Y = cat(2, Y1, Ys1, Y2, Ys2);
            
                    hold(app.UIAxes, 'on');
                    if D > 0
                        X = ampy * X * D / 90 + CY;
                        Y = Y * D / 90 + CX;
                        Xc1 = ampy * X1 * D / 90 + CY;
                        Yc1 = Y1 * D / 90 + CX;
                        phid = 0:.01:2 * pi;
               
                        [x, y] = pol2cart(phid, 90);
                        xx = x * D / 90 + CX;
                        yy = ampy * y * D / 90 + CY;
                        if ta == 0
                            p1 = fill(app.UIAxes, xx, yy, 'w');
                            p1.Tag = 'EqObj';
                            try
                                p2 = fill(app.UIAxes, Y, X, color(ev, :));
                            catch
                                p2 = fill(app.UIAxes, Y, X, color); % when simple color (e.g., 'r')
                            end
                            p2.Tag = 'EqObj';
                            if iFlag == 1
                                p3 = plot(app.UIAxes, Yc1, Xc1,'Color', [0.1, 0.1, 0.1], 'linewidth', penc);
                                p3.Tag = 'EqObj';
                            end
                            p4 = line(app.UIAxes, xx, yy, 'color', 'k', 'linewidth', penw);
                            p4.Tag = 'EqObj';
                        else
                            p1 = fillm(app.UIAxes, yy, xx, 'w');
                            p2 = fillm(app.UIAxes, X, Y, color);
                            p1.Tag = 'EqObj';
                            p2.Tag = 'EqObj';
                            if iFlag == 1
                                p3 = plot(app.UIAxes, Yc1, Xc1, 'r', 'linewidth', penc);
                                p3.Tag = 'EqObj';
                            end
                            p4 = linem(app.UIAxes, yy, xx,'color','k','linewidth', penw);
                            p4.Tag = 'EqObj';
                        end
                    else
                        if ta == 0
                            p1 = fill(app.UIAxes, X, Y, color(ev, :));
                        else
                            p1 = fillm(app.UIAxes, Y, X, color);
                        end
                        p1.Tag = 'EqObj';
                        view(app.UIAxes, 90, -90);
                    end

                    % ---- 進捗バーの更新 ----
                    if mod(ev, max(1, floor(ne/100))) == 0 || ev == ne
                        dlg.Value = ev / ne;
                        dlg.Message = sprintf('Drawing beachballs... (%d / %d)', ev, ne);
                        drawnow limitrate; % UIフリーズ防止
                    end
                end

                % ---- 完了メッセージ ----
                dlg.Value = 1;
                dlg.Message = 'All beachballs drawn successfully!';
                pause(0.3);  % 完了表示を一瞬見せる
            
            catch ME
                delete(dlg);
                rethrow(ME);
            end
            
            % ---- 終了処理 ----
            delete(dlg);


            % % ビーチボールの描画
            % for ev = 1:ne
            %     S1 = s1(ev);
            %     D1 = d1(ev);
            %     S2 = s2(ev);
            %     D2 = d2(ev);
            %     P = r1(ev);
            %     CX = centerX(ev);
            %     CY = centerY(ev);
            %     D = diam(ev);
            %     M = mech(ev);
            % 
            %     if M > 0
            %         P = 2;
            %     else
            %         P = 1;
            %     end
            % 
            %     % dip の値を調整（90度を超えないように）
            %     if D1 >= 90
            %         D1 = 89.9999;
            %     end
            %     if D2 >= 90
            %         D2 = 89.9999;
            %     end
            % 
            %     % 各ノーダル面の円弧を計算
            %     phi = 0:.05:pi;
            %     d = 90 - D1;
            %     m = 90;
            %     l1 = sqrt(d ^ 2 ./ (sin(phi) .^ 2 + cos(phi) .^ 2 * d ^ 2 / m ^ 2));
            %     d = 90 - D2;
            %     m = 90;
            %     l2 = sqrt(d ^ 2 ./ (sin(phi) .^ 2 + cos(phi) .^ 2 * d ^ 2 / m ^ 2));
            % 
            %     if D == 0
            %         % stereo(app, phi + S1 * d2r, l1, 'k');
            %         % stereo(app, phi + S2 * d2r, l2, 'k');
            %         stereo(app.UIAxes, phi + S1 * d2r, l1, 'k');
            %         stereo(app.UIAxes, phi + S2 * d2r, l2, 'k');
            %     end
            % 
            %     inc = 1;
            %     [X1, Y1] = pol2cart(phi + S1 * d2r, l1);
            %     if P == 1
            %         lo = S1 - 180;
            %         hi = S2;
            %         if lo > hi
            %             inc = -inc;
            %         end
            %         th1 = S1 - 180:inc:S2;
            %         [Xs1, Ys1] = pol2cart(th1 * d2r, 90 * ones(1, length(th1)));
            %         [X2, Y2] = pol2cart(phi + S2 * d2r, l2);
            %         th2 = S2+180:-inc:S1;
            %     else
            %         hi = S1 - 180;
            %         lo = S2 - 180;
            %         if lo > hi
            %             inc = -inc;
            %         end
            %         th1 = hi:-inc:lo;
            %         [Xs1, Ys1] = pol2cart(th1 * d2r, 90 * ones(1, length(th1)));
            %         [X2, Y2] = pol2cart(phi + S2 * d2r, l2);
            %         X2 = fliplr(X2);
            %         Y2 = fliplr(Y2);
            %         th2 = S2:inc:S1;
            %     end
            % 
            %     [Xs2, Ys2] = pol2cart(th2 * d2r, 90 * ones(1, length(th2)));
            %     X = cat(2, X1, Xs1, X2, Xs2);
            %     Y = cat(2, Y1, Ys1, Y2, Ys2);
            % 
            %     hold(app.UIAxes, 'on');
            %     if D > 0
            %         X = ampy * X * D / 90 + CY;
            %         Y = Y * D / 90 + CX;
            %         Xc1 = ampy * X1 * D / 90 + CY;
            %         Yc1 = Y1 * D / 90 + CX;
            %         phid = 0:.01:2 * pi;
            % 
            %         [x, y] = pol2cart(phid, 90);
            %         xx = x * D / 90 + CX;
            %         yy = ampy * y * D / 90 + CY;
            %         if ta == 0
            %             p1 = fill(app.UIAxes, xx, yy, 'w');
            %             p1.Tag = 'EqObj';
            %             try
            %                 p2 = fill(app.UIAxes, Y, X, color(ev, :));
            %             catch
            %                 p2 = fill(app.UIAxes, Y, X, color); % when simple color (e.g., 'r')
            %             end
            %             p2.Tag = 'EqObj';
            %             if iFlag == 1
            %                 p3 = plot(app.UIAxes, Yc1, Xc1,'Color', [0.1, 0.1, 0.1], 'linewidth', penc);
            %                 p3.Tag = 'EqObj';
            %             end
            %             p4 = line(app.UIAxes, xx, yy, 'color', 'k', 'linewidth', penw);
            %             p4.Tag = 'EqObj';
            %         else
            %             p1 = fillm(app.UIAxes, yy, xx, 'w');
            %             p2 = fillm(app.UIAxes, X, Y, color);
            %             p1.Tag = 'EqObj';
            %             p2.Tag = 'EqObj';
            %             if iFlag == 1
            %                 p3 = plot(app.UIAxes, Yc1, Xc1, 'r', 'linewidth', penc);
            %                 p3.Tag = 'EqObj';
            %             end
            %             p4 = linem(app.UIAxes, yy, xx,'color','k','linewidth', penw);
            %             p4.Tag = 'EqObj';
            %         end
            %     else
            %         if ta == 0
            %             p1 = fill(app.UIAxes, X, Y, color(ev, :));
            %         else
            %             p1 = fillm(app.UIAxes, Y, X, color);
            %         end
            %         p1.Tag = 'EqObj';
            %         view(app.UIAxes, 90, -90);
            %     end
            % end
            
            
            function [strike, dip, rake] = AuxPlane(s1, d1, r1)
                %==========================================================================
                %     AuxPlane
                %==========================================================================
                % AuxPlane関数は、s1, d1, r1の引数を使用して補助平面を計算する関数です。
                % s1: 平面の位置ベクトル
                % d1: 平面の法線ベクトル
                % r1: 平面の半径またはスケール
                %function [strike, dip, rake] = AuxPlane(s1,d1,r1);
                % Get Strike and dip of second plane, adapted from Andy Michael bothplanes.c
                r2d = 180 / pi;
                z = (s1 + 90) / r2d;
                z2 = d1 / r2d;
                z3 = r1 / r2d;
                %/* slick vector in plane 1 */
                sl1 = -cos(z3) .* cos(z) - sin(z3) .* sin(z) .* cos(z2);
                sl2 = cos(z3) .* sin(z) - sin(z3) .* cos(z) .* cos(z2);
                sl3 = sin(z3) .* sin(z2);
                [strike, dip] = strikedip(sl2, sl1, sl3);
            
                n1 = sin(z) .* sin(z2);  %/* normal vector to plane 1 */
                n2 = cos(z) .* sin(z2);
                h1 = -sl2; %/* strike vector of plane 2 */
                h2 = sl1; %/* note h3=0 always so we leave it out */
            
                z = h1 .* n1 + h2 .* n2;
                z = z ./ sqrt(h1 .* h1 + h2 .* h2);
                z = acos(z);
            
                rake = zeros(size(strike));
                j = find(sl3 > 0);
                rake(j) = z(j) * r2d;
                j = find(sl3 <= 0);
                rake(j) = -z(j) * r2d;
            end
            
            
            function [strike, dip] = strikedip(n, e, u)
                %==========================================================================
                %     strikedip
                %==========================================================================
                %{
                この関数は、北方向(n)、東方向(e)、および上方向(u)のベクトル成分を入力として受け取り、
                ストライク角(strike)と傾斜角(dip)を計算して返します。
                入力:
                - n: 北方向のベクトル成分
                - e: 東方向のベクトル成分
                - u: 上方向のベクトル成分
                出力:
                - strike: ストライク角（度単位）
                - dip: 傾斜角（度単位）
                %}
                % Finds strike and dip of plane given normal vector having components n, e, and u
                r2d = 180 / pi;
                j = find(u < 0);
                n(j) = -n(j);
                e(j) = -e(j);
                u(j) = -u(j);
            
                strike = atan2(e, n) * r2d;
                strike = strike - 90;
                while strike >= 360
                    strike = strike - 360;
                end
                while strike < 0
                    strike = strike + 360;
                end
                x = sqrt(n .^ 2 + e .^ 2);
                dip = atan2(x, u) * r2d;
            end
            
            
            function stereo(app, theta, rho, line_style)
                %==========================================================================
                %     stereo(app, theta, rho, line_style)
                %==========================================================================
                % STEREO - ステレオ投影をプロットする関数
                % 入力:
                %   theta - 角度データ (ラジアン)
                %   rho - 半径データ
                %   line_style - プロットの線スタイル (オプション)
                % 出力:
                %   hpol - プロットされたオブジェクトのハンドル
                % 説明:
                %   この関数は、与えられた角度データと半径データを使用してステレオ投影をプロットします。
                %   オプションで線スタイルを指定することができます。
            
                % 入力引数の検証と初期化
                if nargin < 1
                    error('Requires 2 or 3 input arguments.')
                elseif nargin == 2 
                    % rho が文字列の場合、theta と rho を調整する
                    if ischar(rho)
                        line_style = rho;
                        rho = theta;
                        [mr, nr] = size(rho);
                        if mr == 1
                            theta = 1:nr;
                        else
                            th = (1:mr);
                            theta = th(:, ones(1, nr));
                        end
                    else
                        line_style = 'auto';
                    end
                elseif nargin == 1
                    % theta のみが指定された場合、rho を初期化
                    line_style = 'auto';
                    rho = theta;
                    [mr, nr] = size(rho);
                    if mr == 1
                        theta = 1:nr;
                    else
                        th = (1:mr)';
                        theta = th(:, ones(1, nr));
                    end
                end
            
                % 入力データの形式確認
                if ischar(theta) || ischar(rho)
                    error('Input arguments must be numeric.');
                end
                if ~isequal(size(theta), size(rho))
                    error('THETA and RHO must be the same size.');
                end
            
                % get hold state
                % プロット環境の設定
                % get x-axis text color so grid is in same color
                % x 軸テキストの色とグリッドのスタイルを取得
                app.UIAxes.Color = 'xcolor'; % 軸の色を設定
                app.UIAxes.GridLineStyle = 'gridlinestyle'; % グリッドのスタイルを設定
            
                % Hold on to current Text defaults, reset them to the
                % Axes' font attributes so tick marks use them.
                % 現在のテキストデフォルトを保持し、フォント属性を設定
                app.UIAxes.FontAngle = 'normal'; % フォントの角度を設定
                app.UIAxes.FontName = 'Arial';   % フォント名を設定
                app.UIAxes.FontSize = 'DefaultTextFontSize';        % フォントサイズを設定
                app.UIAxes.FontWeight = 'DefaultTextFontWeight';    % フォントの太さを設定
                app.UIAxes.FontUnits = 'data';                      % フォントの単位を設定
            
                % make a radial grid
                % 放射状グリッドの作成
                hold(app.UIAxes, 'on');
            
                % define a circle
                % 円を定義
                % 放射状グリッドの描画
                th = 0:pi / 50:2 * pi; % 0から2πまでの角度を生成（円の形状を描画する基盤）。
                xunit = cos(th); % 円周上のx座標を計算。
                yunit = sin(th); % 円周上のy座標を計算。
            
                % now really force points on x/y axes to lie on them exactly
                % x/y 軸上の点を調整
                inds = 1:(length(th) - 1) / 4:length(th);
                xunit(inds(2:2:4)) = zeros(2, 1);
                yunit(inds(1:2:5)) = zeros(3, 1);
            
                % カスタムプロットの初期化
                maxrho = max(abs(rho(:)));
                rmin = 0;
                rmax = maxrho;
                rticks = 1;
            
                % カラープロパティや線のスタイルを取得
                tc = app.UIAxes.XColor; % x軸の色
                ls = '-'; % グリッド線のスタイル
            
                % ラジアルグリッドと外円のプロット
                th = linspace(0, 2 * pi, 100);
                xunit = cos(th);
                yunit = sin(th);
            
                for i = linspace(rmin, rmax, rticks+1)
                    plot(app.UIAxes, xunit * i, yunit * i, ls, 'Color', tc, 'LineWidth', 0.5);
                end
            
                % 放射方向の線（スポーク）をプロット
                spokes = 0:30:330; % 30度間隔
                for angle = spokes
                    x = [0, cosd(angle) * rmax];
                    y = [0, sind(angle) * rmax];
                    plot(app.UIAxes, x, y, ls, 'Color', tc, 'LineWidth', 0.5);
                end
            
                % ラベル（角度）を追加
                rt = 1.1 * rmax;
                for angle = spokes
                    x = rt * cosd(angle);
                    y = rt * sind(angle);
                    text(app.UIAxes, x, y, num2str(angle), 'HorizontalAlignment', 'center', 'FontSize', 8);
                end
            
                % transform data to Cartesian coordinates.
                % データをデカルト座標に変換
                xx = rho .* cos(theta); % 極座標 -> 直交座標 (x)
                yy = rho .* sin(theta); % 極座標 -> 直交座標 (y)
            
                % plot data on top of grid
                % グリッド上にデータをプロット
                if strcmp(line_style, 'auto')
                    plot(app.UIAxes, xx, yy);
                else
                    plot(app.UIAxes, xx, yy, line_style);
                end
            
                % 軸の設定を調整
                axis(app.UIAxes, 'equal');
                app.UIAxes.XLim = [-rmax, rmax];
                app.UIAxes.YLim = [-rmax, rmax];
                app.UIAxes.XColor = 'none';
                app.UIAxes.YColor = 'none';
                hold(app.UIAxes, 'off');
            end
            
            function [str, dip, rake] = mij2sdr(mxx, myy, mzz, mxy, mxz, myz)
                %==========================================================================
                %     mij2sdr(mxx, myy, mzz, mxy, mxz, myz)
                %==========================================================================
                %   INPUT
                %	mij - siz independent components of the moment tensor
                %   OUTPUT
                %	str - strike of first focal plane (degrees)
                %	dip - dip of first focal plane (degrees)
                %	rake - rake of first focal plane (degrees)
            
                a = [mxx mxy mxz; mxy myy myz; mxz myz mzz];
                [V,d] = eig(a);
                D = [d(3, 3) d(1, 1) d(2, 2)];
                V(2:3, 1:3) = -V(2:3, 1:3);
                V = [V(2, 3) V(2, 1) V(2, 2); V(3, 3) V(3, 1) V(3, 2); V(1, 3) V(1, 1) V(1, 2)];
            
                IMAX = find(D == max(D));
                IMIN = find(D == min(D));
                AE = (V(:, IMAX) + V(:, IMIN)) / sqrt(2.0);
                AN = (V(:, IMAX) - V(:, IMIN)) / sqrt(2.0);
                AER = sqrt(AE(1) ^ 2 + AE(2) ^ 2 + AE(3) ^ 2);
                ANR = sqrt(AN(1) ^ 2 + AN(2) ^ 2 + AN(3) ^ 2);
                AE = AE / AER;
                AN = AN / ANR;
                if (AN(3) <= 0)
                    AN1 = AN;
                    AE1 = AE;
                else
                    AN1 = -AN;
                    AE1 = -AE;
                end 
                [ft, fd, fl] = TDL(AN1, AE1);
                str = 360 - ft;
                dip = fd;
                rake = 180 - fl;
            end
    
            function [FT, FD, FL] = TDL(AN, BN)
                %==========================================================================
                %     TDL(AN, BN)
                %==========================================================================
                XN = AN(1);
                YN = AN(2);
                ZN = AN(3);
                XE = BN(1);
                YE = BN(2);
                ZE = BN(3);
                AAA = 1.0E-06;
                CON = 57.2957795;
                if (abs(ZN) < AAA)
                    FD = 90;
                    AXN = abs(XN);
                    if (AXN > 1.0) 
                        AXN = 1.0;
                    end
                    FT = asin(AXN) * CON;
                    ST = -XN;
                    CT = YN;
                    if (ST >= 0 && CT < 0) 
                        FT = 180 - FT;
                    end
                    if (ST < 0 && CT <= 0) 
                        FT = 180 + FT;
                    end
                    if (ST < 0 && CT > 0) 
                        FT = 360 - FT;
                    end
                    FL = asin(abs(ZE)) * CON;
                    SL = -ZE;
                    if (abs(XN) < AAA) 
                        THEN;
                        CL = XE / YN;
                    else
                        CL = -YE / XN;
                    end 
                    if (SL >= 0 && CL < 0) 
                        FL = 180 - FL;
                    end
                    if (SL < 0 && CL <= 0) 
                        FL = FL - 180;
                    end
                    if (SL < 0 && CL > 0) 
                        FL = -FL;
                    end
                else
                    if (-ZN > 1.0) 
                        ZN = -1.0;
                    end
                    FDH = acos(-ZN);
                    FD = FDH * CON;
                    SD = sin(FDH);
                    if SD == 0
                        return;
                    end 
                    ST = -XN / SD;
                    CT = YN / SD;
                    SX = abs(ST);
                    if (SX > 1.0) 
                        SX = 1.0;
                    end
                    FT = asin(SX) * CON;
                    if (ST >= 0 && CT < 0) 
                        FT = 180 - FT;
                    end
                    if (ST < 0 && CT <= 0) 
                        FT = 180 + FT;
                    end
                    if (ST < 0 && CT > 0) 
                        FT = 360 - FT;
                    end
                    SL = -ZE / SD;
                    SX = abs(SL);
                    if (SX > 1.0) 
                        SX = 1.0;
                    end
                    FL = asin(SX) * CON;
                    if (ST == 0)
                        THEN;
                        CL = XE / CT;
                    else
                        XXX = YN * ZN * ZE / SD / SD + YE;
                        CL = -SD * XXX / XN;
                        if CT == 0
                            CL = YE / ST;
                        end
                    end 
                    if (SL >= 0 && CL < 0) 
                        FL = 180 - FL;
                    end
                    if (SL < 0 && CL <= 0) 
                        FL = FL - 180;
                    end
                    if (SL < 0 && CL > 0) 
                        FL = -FL;
                    end
                end 
            end
        end


        function focal_mech_calc(app)
            % =====================================================================
            %                focal_mech_calc(app)
            % =====================================================================
            % This function calculates resolved stress on two nodal planes and plots earthquakes on Coulomb map.
            % This function can be accessible when app.OVERLAY_VARS.EQ_DATA is stored in the software memory.
            %------ app.OVERLAY_VARS.EQ_DATA format (17 columns) -------------------------------
            % 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min, 
            % 10)strike1, 11)dip1, 12)rake1, 13)strike2, 14)dip2, 15)rake2, 
            % 16) x position, 17) y position
            %------------------------------------------------------------------
        
            % Special option to make the dots vectorized object
            % IVECTOR = 1; % (0: raster image, 1: vector object, 2: beachball)
            app.SYSTEM_VARS.IVECTOR = 2;
            
            xs = app.INPUT_VARS.GRID(1, 1);
            xf = app.INPUT_VARS.GRID(3, 1);
            ys = app.INPUT_VARS.GRID(2, 1);
            yf = app.INPUT_VARS.GRID(4, 1);
            xinc = (xf - xs) / (app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON);
            yinc = (yf - ys) / (app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);
            lon = app.OVERLAY_VARS.EQ_DATA(:, 1);
            lat = app.OVERLAY_VARS.EQ_DATA(:, 2);
            xx = xs + (lon - app.COORD_VARS.MIN_LON) .* xinc;
            yy = ys + (lat - app.COORD_VARS.MIN_LAT) .* yinc;
            zz = app.OVERLAY_VARS.EQ_DATA(:, 7);
            m = size(app.OVERLAY_VARS.EQ_DATA, 1);
            
            if app.ASSIST_VARS.NODAL_ACT == 0
                check1 = sum(app.OVERLAY_VARS.EQ_DATA(:, 10)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 11)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 12));
                check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
                if check1 == 0
                    uialert(app.main_menu_window, 'No focal mechanism data available in this catalog.', 'Warning!');
                    return;
                else
                    app.ASSIST_VARS.INODAL = 1;
                    if check2 == 0
                        h = uiconfirm(app.main_menu_window, ...
                            ['Only one nodal plane data set. Do you want to make the other set? ' ...
                            'Original set will be "Nodal 1". The other will be "Nodal 2".'], ...
                            'Confirmation', 'Options', {'Yes', 'No', 'Cancel'}, ...
                            'DefaultOption', 1, 'CancelOption', 3);
                    
                        % 選択結果に応じた処理
                        switch h
                            case 'Yes'
                                % Nodal plane data を計算
                                out = nodal_plane_calc(app, app.OVERLAY_VARS.EQ_DATA(:, 10), ...
                                    app.OVERLAY_VARS.EQ_DATA(:, 11), app.OVERLAY_VARS.EQ_DATA(:, 12));
                                
                                % 計算結果を格納
                                app.OVERLAY_VARS.EQ_DATA(:, 10) = out(:, 1);
                                app.OVERLAY_VARS.EQ_DATA(:, 11) = out(:, 2);
                                app.OVERLAY_VARS.EQ_DATA(:, 12) = out(:, 3);
                                app.OVERLAY_VARS.EQ_DATA(:, 13) = out(:, 4);
                                app.OVERLAY_VARS.EQ_DATA(:, 14) = out(:, 5);
                                app.OVERLAY_VARS.EQ_DATA(:, 15) = out(:, 6);
                                
                                % INODAL フラグの更新
                                app.ASSIST_VARS.INODAL = 1;
                    
                            case 'No'
                                % ユーザーが "No" を選択した場合の処理
                                uialert(app.main_menu_window, 'No changes made to the nodal plane data.', 'Information', 'Icon', 'info');
                    
                            case 'Cancel'
                                % キャンセルされた場合の処理
                                uialert(app.main_menu_window, 'User selected cancel.', 'Information', 'Icon', 'info');
                        end
                    end
                end
        
                app.ASSIST_VARS.N_CELL = m;
                app.SECTION_VARS.SEC_FLAG = 0;
                % loop process may slow the calc down (change matrix calc in the near future using 'Okada_halfspace' instead of 'Okada_halfspace_one.')
                app.ASSIST_VARS.f_stress = zeros(m, 21);
        
                % initialization
                dc3de = zeros(m, 14, 'double');
                dc3de0 = zeros(m, 14, 'double');
                app.OKADA_CALC_VARS.UX = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UY = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UZ = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UXX = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UYX = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UZX = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UXY = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UYY = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UZY = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UXZ = zeros(m, 1, 'double');
                app.OKADA_CALC_VARS.UZZ = zeros(m, 1, 'double');
                app.ASSIST_VARS.IRET = zeros(m, 1);
        
                for k = 1:app.INPUT_VARS.NUM
                    depth = (app.INPUT_VARS.ELEMENT(k, 8) + app.INPUT_VARS.ELEMENT(k, 9)) / 2.0;  % depth should be positive
                    [c1, c2, c3, c4] = coord_conversion(app, xx, yy, app.INPUT_VARS.ELEMENT(k, 1), app.INPUT_VARS.ELEMENT(k, 2), app.INPUT_VARS.ELEMENT(k, 3), app.INPUT_VARS.ELEMENT(k, 4), app.INPUT_VARS.ELEMENT(k, 8), app.INPUT_VARS.ELEMENT(k, 9), app.INPUT_VARS.ELEMENT(k, 7));
                    alpha = 1.0 / (2.0 * (1.0 - app.INPUT_VARS.POIS));
                    z  = double(zz) * (-1.0);
                    aa = zeros(m, 1, 'double') + double(alpha);
                    zc = zeros(m, 1, 'double') + double(z);
                    dp = zeros(m, 1, 'double') + double(depth);
                    e7 = zeros(m, 1, 'double') + double(app.INPUT_VARS.ELEMENT(k, 7));
                    e5 = zeros(m, 1, 'double') - double(app.INPUT_VARS.ELEMENT(k, 5));    % left-lat positive in Okada's code
                    e6 = zeros(m, 1, 'double') + double(app.INPUT_VARS.ELEMENT(k, 6));
                    zr = zeros(m, 1, 'double');
                    x  = zeros(m, 1, 'double') + double(c1);
                    y  = zeros(m, 1, 'double') + double(c2);
                    al = zeros(m, 1, 'double') + double(c3);
                    aw = zeros(m, 1, 'double') + double(c4);
                    a = [aa x y zc dp e7 al al aw aw e5 e6 zr];
                    
                    [app.OKADA_CALC_VARS.UX(:, 1), app.OKADA_CALC_VARS.UY(:, 1), app.OKADA_CALC_VARS.UZ(:, 1), app.OKADA_CALC_VARS.UXX(:, 1), app.OKADA_CALC_VARS.UYX(:, 1), app.OKADA_CALC_VARS.UZX(:, 1), app.OKADA_CALC_VARS.UXY(:, 1), app.OKADA_CALC_VARS.UYY(:, 1), app.OKADA_CALC_VARS.UZY(:, 1), ...
                        app.OKADA_CALC_VARS.UXZ(:, 1), app.OKADA_CALC_VARS.UYZ(:, 1), app.OKADA_CALC_VARS.UZZ(:, 1), app.ASSIST_VARS.IRET(:, 1)] = Okada_DC3D(app, a(:, 1), a(:, 2), a(:, 3), ...
                        a(:, 4), a(:, 5), a(:, 6), a(:, 7), a(:, 8), a(:, 9), a(:, 10), a(:, 11), a(:, 12), a(:, 13));
                    
                    if app.INPUT_VARS.KODE(k) == 400 || app.INPUT_VARS.KODE(k) == 500
                        [app.OKADA_CALC_VARS.UX(:, 1), app.OKADA_CALC_VARS.UY(:, 1), app.OKADA_CALC_VARS.UZ(:, 1), app.OKADA_CALC_VARS.UXX(:, 1), app.OKADA_CALC_VARS.UYX(:, 1), app.OKADA_CALC_VARS.UZX(:, 1), app.OKADA_CALC_VARS.UXY(:, 1), app.OKADA_CALC_VARS.UYY(:, 1), app.OKADA_CALC_VARS.UZY(:, 1), ...
                            app.OKADA_CALC_VARS.UXZ(:, 1), app.OKADA_CALC_VARS.UYZ(:, 1), app.OKADA_CALC_VARS.UZZ(:, 1), app.ASSIST_VARS.IRET(:, 1)] = Okada_DC3D0(app, a(:, 1), a(:, 2), a(:, 3), ...
                            a(:, 4), a(:, 5), a(:, 6), a(:, 10), a(:, 11), a(:, 12), a(:, 13));    
                    end
                    
                    % cell to matrices
                    X = a(:, 2);
                    Y = a(:, 3);
                    Z = a(:, 4);
        
                    %-- Displacement Conversion from Okada's field to Given field -------------
                    sw = sqrt((app.INPUT_VARS.ELEMENT(k, 4) - app.INPUT_VARS.ELEMENT(k, 2)) ^ 2 + (app.INPUT_VARS.ELEMENT(k, 3) - app.INPUT_VARS.ELEMENT(k, 1)) ^ 2);
                    sina = (app.INPUT_VARS.ELEMENT(k, 4) - app.INPUT_VARS.ELEMENT(k, 2)) / double(sw);
                    cosa = (app.INPUT_VARS.ELEMENT(k, 3) - app.INPUT_VARS.ELEMENT(k, 1)) / double(sw);
                    app.OKADA_CALC_VARS.UXG = app.OKADA_CALC_VARS.UX * cosa - app.OKADA_CALC_VARS.UY * sina;
                    app.OKADA_CALC_VARS.UYG = app.OKADA_CALC_VARS.UX * sina + app.OKADA_CALC_VARS.UY * cosa;
                    app.OKADA_CALC_VARS.UZG = app.OKADA_CALC_VARS.UZ;
        
                    % C-- Strain to Stress for the normal component -----------------------------
                    % strain to stress
                    sk = app.INPUT_VARS.YOUNG / (1.0 + app.INPUT_VARS.POIS);
                    gk = app.INPUT_VARS.POIS / (1.0 - 2.0 * app.INPUT_VARS.POIS);
                    vol = app.OKADA_CALC_VARS.UXX + app.OKADA_CALC_VARS.UYY + app.OKADA_CALC_VARS.UZZ;
        
                    % caution! strain dimension is from x, y, z coordinate (should be  / 1000)
                    sxx = sk * (gk * vol + app.OKADA_CALC_VARS.UXX) * 0.001;
                    syy = sk * (gk * vol + app.OKADA_CALC_VARS.UYY) * 0.001;
                    szz = sk * (gk * vol + app.OKADA_CALC_VARS.UZZ) * 0.001;
                    sxy = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (app.OKADA_CALC_VARS.UXY + app.OKADA_CALC_VARS.UYX) * 0.001;
                    sxz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (app.OKADA_CALC_VARS.UXZ + app.OKADA_CALC_VARS.UZX) * 0.001;
                    syz = (app.INPUT_VARS.YOUNG / (2.0 * (1.0 + app.INPUT_VARS.POIS))) * (app.OKADA_CALC_VARS.UYZ + app.OKADA_CALC_VARS.UZY) * 0.001;
                    ssxx = reshape(sxx, 1, m);
                    ssyy = reshape(syy, 1, m);
                    sszz = reshape(szz, 1, m);
                    ssxy = reshape(sxy, 1, m);
                    ssxz = reshape(sxz, 1, m);
                    ssyz = reshape(syz, 1, m);    
                    s0 = [ssxx; ssyy; sszz; ssyz; ssxz; ssxy];
        
                    %-- Strain Conversion from Okada's field to Given field -------------
                    s1 = tensor_trans(app, sina, cosa, s0, m);
                    SXX = reshape(s1(1, :), m, 1);
                    SYY = reshape(s1(2, :), m, 1);
                    SZZ = reshape(s1(3, :), m, 1);
                    SYZ = reshape(s1(4, :), m, 1);
                    SXZ = reshape(s1(5, :), m, 1);
                    SXY = reshape(s1(6, :), m, 1); 
        
                    if k == 1
                        dc3de = horzcat(xx, yy, X, Y, Z, app.OKADA_CALC_VARS.UXG, app.OKADA_CALC_VARS.UYG, app.OKADA_CALC_VARS.UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                    else
                        dc3de0 = horzcat(zeros(m, 1), zeros(m, 1), zeros(m, 1), zeros(m, 1), zeros(m, 1), app.OKADA_CALC_VARS.UXG, app.OKADA_CALC_VARS.UYG, app.OKADA_CALC_VARS.UZG, SXX, SYY, SZZ, SYZ, SXZ, SXY);
                    end
        
                    dc3de = dc3de + dc3de0;
                end
        
                s9 = reshape(dc3de(:, 9), 1, m);
                s10 = reshape(dc3de(:, 10), 1, m);
                s11 = reshape(dc3de(:, 11), 1, m);
                s12 = reshape(dc3de(:, 12), 1, m);
                s13 = reshape(dc3de(:, 13), 1, m);
                s14 = reshape(dc3de(:, 14), 1, m);
                ss = [s9; s10; s11; s12; s13; s14];
        
                % nodal plane 1
                c1 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 10);
                c2 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 11);
                c3 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 12);
        
                % nodal plane 2
                c11 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 13);
                c22 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 14);
                c33 = zeros(m, 1, 'double') + app.OVERLAY_VARS.EQ_DATA(:, 15);
                c4 = zeros(m, 1, 'double') + app.INPUT_VARS.FRIC;
        
                app.ASSIST_VARS.f_stress(:, 1) = app.OVERLAY_VARS.EQ_DATA(:, 3);
                app.ASSIST_VARS.f_stress(:, 2) = app.OVERLAY_VARS.EQ_DATA(:, 4);
                app.ASSIST_VARS.f_stress(:, 3) = app.OVERLAY_VARS.EQ_DATA(:, 5);
                app.ASSIST_VARS.f_stress(:, 4) = app.OVERLAY_VARS.EQ_DATA(:, 8);
                app.ASSIST_VARS.f_stress(:, 5) = app.OVERLAY_VARS.EQ_DATA(:, 9);
                app.ASSIST_VARS.f_stress(:, 6) = app.OVERLAY_VARS.EQ_DATA(:, 1);
                app.ASSIST_VARS.f_stress(:, 7) = app.OVERLAY_VARS.EQ_DATA(:, 2);
                app.ASSIST_VARS.f_stress(:, 8) = app.OVERLAY_VARS.EQ_DATA(:, 7);
                app.ASSIST_VARS.f_stress(:, 9) = app.OVERLAY_VARS.EQ_DATA(:, 6);
                
                % Coulomb calc for nodal plane 1
                app.ASSIST_VARS.f_stress(:, 10) = c1;
                app.ASSIST_VARS.f_stress(:, 11) = c2;
                app.ASSIST_VARS.f_stress(:, 12) = c3;
                [app.ASSIST_VARS.f_stress(:, 13), app.ASSIST_VARS.f_stress(:, 14), app.ASSIST_VARS.f_stress(:, 15)] = calc_coulomb(app, c1, c2, c3, c4, ss);
        
                % Coulomb calc for nodal plane 2
                app.ASSIST_VARS.f_stress(:, 16) = c11;
                app.ASSIST_VARS.f_stress(:, 17) = c22;
                app.ASSIST_VARS.f_stress(:, 18) = c33;
                [app.ASSIST_VARS.f_stress(:, 19), app.ASSIST_VARS.f_stress(:, 20), app.ASSIST_VARS.f_stress(:, 21)] = calc_coulomb(app, c11, c22, c33, c4, ss);
            end
            
            check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
            if app.ASSIST_VARS.INODAL == 1 || app.ASSIST_VARS.INODAL == 2
                if check2 == 0
                    cd (app.SYSTEM_VARS.HOME_DIR);
                    format long;
                    % データファイル 'Focal_mech_stress_output.csv' を読み込み上書き
                    filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Focal_mech_stress_output.csv');
                    fid = fopen(filePath, 'w');
                    % ヘッダーを書き込む
                    fprintf(fid, 'year, month, day, hour, minute, lon., lat., depth, magnitude, strike, dip, rake, shear, normal, coulomb\n');
                    fprintf(fid, '-, -, -, -, -, (deg), (deg), (km), -, (deg), (deg), (deg), (bar), (bar), (bar)\n');
                    % 数値データ（15列）を書き込み
                    data = app.ASSIST_VARS.f_stress(:, 1:15);
                    for i = 1:size(data, 1)
                        fprintf(fid, '%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f\n', data(i, :));
                    end
                    fclose(fid);
                    cd(app.SYSTEM_VARS.HOME_DIR);
                    app.ASSIST_VARS.NODAL_STRESS = app.ASSIST_VARS.f_stress(:, 15);
                else
                    cd (app.SYSTEM_VARS.HOME_DIR);
                    format long;
                    % データファイル 'Focal_mech_stress_output.csv' を読み込み上書き
                    filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Focal_mech_stress_output.csv');
                    fid = fopen(filePath, 'w');
                    % ヘッダーを書き込む
                    fprintf(fid, 'year, month, day, hour, minute, lon., lat., depth, magnitude, strike1, dip1, rake1, shear1, normal1, coulomb1, strike2, dip2, rake2, shear2, normal2, coulomb2\n');
                    fprintf(fid, '-, -, -, -, -, (deg), (deg), (km), -, (deg), (deg), (deg), (bar), (bar), (bar), (deg), (deg), (deg), (bar), (bar), (bar)\n');
                    % 数値データ（21列）を書き込み
                    data = app.ASSIST_VARS.f_stress;
                    for i = 1:size(data, 1)
                        fprintf(fid, '%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f\n', data(i, :));
                    end
                    fclose(fid);
                    cd(app.SYSTEM_VARS.HOME_DIR);

                    if app.ASSIST_VARS.INODAL == 1
                        app.ASSIST_VARS.NODAL_STRESS = app.ASSIST_VARS.f_stress(:, 15);
                    elseif app.ASSIST_VARS.INODAL == 2
                        app.ASSIST_VARS.NODAL_STRESS = app.ASSIST_VARS.f_stress(:, 21);
                    end

                end
            else
                if app.ASSIST_VARS.INODAL == 3 % random
                    r1 = int32(rand(m, 1));
                    r2 = (-1) * (r1 - 1);
                    randstrike = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 10) .* double(r1) + app.ASSIST_VARS.f_stress(:, 16) .* double(r2);
                    randdip = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 11) .* double(r1) + app.ASSIST_VARS.f_stress(:, 17) .* doubledouble(r2);
                    randrake = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 12) .* double(r1) + app.ASSIST_VARS.f_stress(:, 18) .* double(r2);
                    randshear  = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 13) .* double(r1) + app.ASSIST_VARS.f_stress(:, 19) .* double(r2);
                    randnormal = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 14) .* double(r1) + app.ASSIST_VARS.f_stress(:, 20) .* double(r2);
                    app.ASSIST_VARS.NODAL_STRESS = zeros(m, 1, 'double') + app.ASSIST_VARS.f_stress(:, 15) .* double(r1) + app.ASSIST_VARS.f_stress(:, 21) .* double(r2);
                    header1 = 'year, month, day, hour, minute, lon., lat., depth, magnitude, strike-rand, dip-rand, rake-rand, shear, normal, coulomb';
                    header2 = '';

                elseif app.ASSIST_VARS.INODAL == 4  % max delta CFF
                    ind1 = app.ASSIST_VARS.f_stress(:, 15) >= app.ASSIST_VARS.f_stress(:, 21);
                    ind2 = app.ASSIST_VARS.f_stress(:, 21) >  app.ASSIST_VARS.f_stress(:, 15);
                    randstrike   = app.ASSIST_VARS.f_stress(:, 10) .* ind1 + app.ASSIST_VARS.f_stress(:, 16) .* ind2;
                    randdip      = app.ASSIST_VARS.f_stress(:, 11) .* ind1 + app.ASSIST_VARS.f_stress(:, 17) .* ind2;
                    randrake     = app.ASSIST_VARS.f_stress(:, 12) .* ind1 + app.ASSIST_VARS.f_stress(:, 18) .* ind2;
                    randshear    = app.ASSIST_VARS.f_stress(:, 13) .* ind1 + app.ASSIST_VARS.f_stress(:, 19) .* ind2;
                    randnormal   = app.ASSIST_VARS.f_stress(:, 14) .* ind1 + app.ASSIST_VARS.f_stress(:, 20) .* ind2;
                    app.ASSIST_VARS.NODAL_STRESS = app.ASSIST_VARS.f_stress(:, 15) .* ind1 + app.ASSIST_VARS.f_stress(:, 21) .* ind2;
                    header1 = 'year, month, day, hour, minute, lon., lat., depth, magnitude, strike-max, dip-max, rake-max, shear, normal, coulomb';
                    header2 = '';

                else  % min delta CFF
                    ind1 = app.ASSIST_VARS.f_stress(:, 15) <= app.ASSIST_VARS.f_stress(:, 21);
                    ind2 = app.ASSIST_VARS.f_stress(:, 21) <  app.ASSIST_VARS.f_stress(:, 15);
                    randstrike   = app.ASSIST_VARS.f_stress(:, 10) .* ind1 + app.ASSIST_VARS.f_stress(:, 16) .* ind2;
                    randdip      = app.ASSIST_VARS.f_stress(:, 11) .* ind1 + app.ASSIST_VARS.f_stress(:, 17) .* ind2;
                    randrake     = app.ASSIST_VARS.f_stress(:, 12) .* ind1 + app.ASSIST_VARS.f_stress(:, 18) .* ind2;
                    randshear    = app.ASSIST_VARS.f_stress(:, 13) .* ind1 + app.ASSIST_VARS.f_stress(:, 19) .* ind2;
                    randnormal   = app.ASSIST_VARS.f_stress(:, 14) .* ind1 + app.ASSIST_VARS.f_stress(:, 20) .* ind2;
                    app.ASSIST_VARS.NODAL_STRESS = app.ASSIST_VARS.f_stress(:, 15) .* ind1 + app.ASSIST_VARS.f_stress(:, 21) .* ind2;
                    header1 = 'year, month, day, hour, minute, lon., lat., depth, magnitude, strike-min, dip-min, rake-min, shear, normal, coulomb';
                    header2 = '';
                end

                cd (app.SYSTEM_VARS.HOME_DIR);
                format long;
                % データファイル 'Focal_mech_stress_output.csv' を読み込み上書き
                filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Focal_mech_stress_output.csv');
                fid = fopen(filePath, 'w');
                % ヘッダーを書き込む
                fprintf(fid, '%s\n', header1);
                fprintf(fid, '%s\n', header2);
                % 数値データ（21列）を書き込み
                temp = [app.ASSIST_VARS.f_stress(:, 1:9) randstrike randdip randrake randshear randnormal app.ASSIST_VARS.NODAL_STRESS];
                for i = 1:size(temp, 1)
                    fprintf(fid, '%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f,%15.6f\n', temp(i, :));
                end
                fclose(fid);
                cd(app.SYSTEM_VARS.HOME_DIR);

            end
        
            disp(['Focal_mech_stress_output.csv is saved in ' pwd]);
            disp('To change color saturation limits, change variable C_SAT in the workspace.');
            disp('And then redo the same action.');
            showMessage(app, {['Focal_mech_stress_output.csv is saved in ' pwd], ...
                'To change color saturation limits, change variable C_SAT in the workspace.', ...
                'And then redo the same action.' ...
                }, false);
        
            if isempty(app.SYSTEM_VARS.IVECTOR)
                % 修正必要
                disp('--------------------------------------------------------- ');
                disp(' This image is saved as a raster image when saved.');
                disp(' If you want to save a vectorized & editable image, type');   
                disp('       IVECTOR = 1;');
                disp(' in the command window here and execute it again.'); 
                disp('--------------------------------------------------------- ');
                showMessage(app, {'This image is saved as a raster image when saved.', ...
                    'If you want to save a vectorized & editable image, type', ...
                    '       IVECTOR = 1;'
                    ' in the command window here and execute it again.' ...
                    }, false);
            end
        
            cd(app.SYSTEM_VARS.HOME_DIR);

            hold(app.UIAxes, 'on');
            grid_drawing(app);
            c_index = zeros(64, 3);
        
            switch app.SYSTEM_VARS.PREF(7, 1)
                case 1
                    c_index = colormap(jet);
                case 2
                    c_index = colormap(app.GRAPHICS_VARS.ANATOLIA);                
                case 3
                    c_index = colormap(gray);                
            end
        
            if isempty(app.GRAPHICS_VARS.C_SAT)
                c_mean = mean(abs(app.ASSIST_VARS.NODAL_STRESS));
            else
                c_mean = app.GRAPHICS_VARS.C_SAT;
            end
        
            c_unit = c_mean * 2.0 / 64;
            ni = zeros(m, 1, 'int8');
            
            if app.ASSIST_VARS.INODAL == 4
                temp3 = sortrows([app.ASSIST_VARS.NODAL_STRESS app.ASSIST_VARS.f_stress(:, 6) app.ASSIST_VARS.f_stress(:, 7)], 1);
                ni(:, 1) = round((temp3(:, 1) - (-c_mean)) / c_unit);
            elseif app.ASSIST_VARS.INODAL == 5
                temp3 = flipud(sortrows([app.ASSIST_VARS.NODAL_STRESS app.ASSIST_VARS.f_stress(:, 6) app.ASSIST_VARS.f_stress(:, 7)], 1));
                ni(:, 1) = round((temp3(:, 1) - (-c_mean)) / c_unit);            
            else
                temp3 = [app.ASSIST_VARS.NODAL_STRESS app.ASSIST_VARS.f_stress(:, 6) app.ASSIST_VARS.f_stress(:, 7)];   
                ni(:, 1) = round((app.ASSIST_VARS.NODAL_STRESS(:, 1) - (-c_mean)) / c_unit);
            end
        
            c1 = ni(:, 1) > 64;
            c2 = ni(:, 1) <  1;
            c3 = c1 + c2 <= 0.0;
            ni(:, 1) = int16(64 .* double(c1) + 1 .* double(c2) + double(ni(:, 1)) .* double(c3));
            adj = 0.5; % adjusment of stress dots


% =====================================================================
% 並べ替えオプション: 地震の新しさ／大きさ順
% =====================================================================
if app.CSNP_LatestquakesontopCheckBox.Value
    % 年月日時分を数値化して「新しい順」にソート
    % 年 + 月/12 + 日/365 + 時間/8760 + 分/525600 で近似的に連続値に変換
    eq_time = app.OVERLAY_VARS.EQ_DATA(:,3) + ...
              app.OVERLAY_VARS.EQ_DATA(:,4)/12 + ...
              app.OVERLAY_VARS.EQ_DATA(:,5)/365 + ...
              app.OVERLAY_VARS.EQ_DATA(:,8)/8760 + ...
              app.OVERLAY_VARS.EQ_DATA(:,9)/525600;
    [~, sort_idx] = sort(eq_time, 'ascend'); % 古い→新しい順
    % ビーチボールは後の描画ほど前面に出るので、この順序でループする
elseif app.CSNP_LargestquakesontopCheckBox.Value
    % マグニチュードでソート（小→大）
    [~, sort_idx] = sort(app.OVERLAY_VARS.EQ_DATA(:,6), 'ascend');
else
    % 並び替えなし
    sort_idx = 1:size(app.OVERLAY_VARS.EQ_DATA, 1);
end

% ソート順に従って、すべての対応データを並べ替える
app.OVERLAY_VARS.EQ_DATA = app.OVERLAY_VARS.EQ_DATA(sort_idx, :);
temp3 = temp3(sort_idx, :);
ni = ni(sort_idx, :);


            if app.COORD_VARS.ICOORD == 2 
                % lon & lat coordinates

                if app.SYSTEM_VARS.IVECTOR == 1 
                    % vector image for dot
                    for jj = 1:length(temp3)
                        p1 = scatter(app.UIAxes, temp3(jj, 2), temp3(jj, 3), 6 * app.SYSTEM_VARS.PREF(5, 4) * adj * 1.3, c_index(ni(jj, 1), 1:3));
                        p1.Tag = 'EqObj';
                    end

                elseif app.SYSTEM_VARS.IVECTOR == 2 
                    % beachball plot
                    if app.ASSIST_VARS.INODAL == 1
                        bb2(app, [app.OVERLAY_VARS.EQ_DATA(:, 10) app.OVERLAY_VARS.EQ_DATA(:, 11) app.OVERLAY_VARS.EQ_DATA(:, 12)], ...
                            temp3(:, 2), temp3(:, 3), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));
                    elseif app.ASSIST_VARS.INODAL == 2
                        bb2(app, [app.OVERLAY_VARS.EQ_DATA(:, 13) app.OVERLAY_VARS.EQ_DATA(:, 14) app.OVERLAY_VARS.EQ_DATA(:, 15)], ...
                            temp3(:, 2), temp3(:, 3), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));
                    else
                        bb2(app, [randstrike(:, 1) randdip(:, 1) randrake(:, 1)], ...
                            temp3(:, 2), temp3(:, 3), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));
                    end

                else
                    p1 = scatter(app.UIAxes, temp3(:, 2), temp3(:, 3), 10 * app.SYSTEM_VARS.PREF(5, 4) * adj, 'MarkerEdgeColor', 'k', 'LineWidth', 9.0);
                    p2 = scatter(app.UIAxes, temp3(:, 2), temp3(:, 3), 6 * app.SYSTEM_VARS.PREF(5, 4) * adj, c_index(ni(:, 1), 1:3), 'LineWidth', 8.0);
                    p1.Tag ='EqObj';
                    p2.Tag ='EqObj';
                end 
        
            else
                % x & y (cartesian) coordinates
                a  = lonlat2xy(app, [temp3(:, 2) temp3(:, 3)]);
                if app.SYSTEM_VARS.IVECTOR == 1
                    for jj = 1:length(temp3)
                        p1 = scatter(app.UIAxes, a(jj, 1), a(jj, 2), 6 * app.SYSTEM_VARS.PREF(5, 4) * adj * 1.3, c_index(ni(jj, 1), 1:3));
                        p1.Tag ='EqObj';
                    end
                elseif app.SYSTEM_VARS.IVECTOR == 2 % beachball plot
                    if app.ASSIST_VARS.INODAL == 1
                        bb2(app, [app.OVERLAY_VARS.EQ_DATA(:, 10) app.OVERLAY_VARS.EQ_DATA(:, 11) app.OVERLAY_VARS.EQ_DATA(:, 12)], ...
                            a(:, 1), a(:, 2), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));  
                    elseif app.ASSIST_VARS.INODAL == 2
                        bb2(app, [app.OVERLAY_VARS.EQ_DATA(:, 13) app.OVERLAY_VARS.EQ_DATA(:, 14) app.OVERLAY_VARS.EQ_DATA(:, 15)], ...
                            a(:, 1), a(:, 2), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));
                    else
                        bb2(app, [randstrike(:, 1) randdip(:, 1) randrake(:, 1)], ...
                            a(:, 1), a(:, 2), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) * adj, 0, 1, c_index(ni(:, 1), 1:3));
                    end
                else
                    p1 = scatter(app.UIAxes, a(:, 1), a(:, 2), 10 * app.SYSTEM_VARS.PREF(5, 4) * adj, 'MarkerEdgeColor', 'k', 'LineWidth', 9.0);
                    p2 = scatter(app.UIAxes, a(:, 1), a(:, 2), 6 * app.SYSTEM_VARS.PREF(5, 4) * adj, c_index(ni(:, 1), 1:3), 'LineWidth', 8.0);
                    p1.Tag ='EqObj';
                    p2.Tag ='EqObj';
                end
            end
        
            % 'menu_focal_mech' タグを持つUIコンポーネントを有効化する
            % calc_stress_on_nodal_plane
            app.CalcStNodalplanesButton.Enable = 'on';

            % カラースケールの設定
            clim(app.UIAxes, [-c_mean c_mean]);
            % カラーバーをUIAxesに表示
            colorbar(app.UIAxes, 'EastOutside');
            % タイトルを設定
            title(app.UIAxes, 'Coulomb stress changes on nodal planes (bar)', 'FontSize', 14);
        
            nodal_stress_change = [app.ASSIST_VARS.f_stress(:, 15); app.ASSIST_VARS.f_stress(:, 21)];
            save nodal_stress.mat nodal_stress_change; 
            
            fault_overlay(app);
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) || ~isempty(app.OVERLAY_VARS.VOLCANO) || ~isempty(app.OVERLAY_VARS.AFAULT_DATA) || ~isempty(app.OVERLAY_VARS.GNSS_DATA)
                hold(app.UIAxes, 'on')
                overlay_drawing2(app); % not overlay_drawing (this is internal function no to plot EQ)
            end
            
            if app.ASSIST_VARS.INODAL == 1 || app.ASSIST_VARS.INODAL == 2
                if check2 ~= 0
                    % nodal_plane_window(app);
                    if app.ASSIST_VARS.NODAL_ACT == 0
                        % 初回のみメッセージを表示
                        uialert(app.main_menu_window, 'Select the Nodal plane type from the list box at the bottom right.', 'Information', 'Icon', 'info');
                    end
                    % Select Nodal plane type Panelを使用可能に
                    app.CSNP_SelectNodalplanetypePanel.Enable = 'on';
                    app.CSNP_SelectNodalplanetypePanel.Visible = 'on';
                end
            end

            % ネスト関数----------------------------------------------------
            function overlay_drawing2(app)
                % ----- coast line plot -----
                if ~isempty(app.OVERLAY_VARS.COAST_DATA) && app.CoastlinesCheckBox.Value == 1
                    overlay_coastline_drawing(app);
                end
            
                % ----- active fault plot -----
                if ~isempty(app.OVERLAY_VARS.AFAULT_DATA) && app.ActivefaultsCheckBox.Value == 1
                    overlay_activefault_drawing(app);
                end
            
                % --- GNSS plot ---------------
                if ~isempty(app.OVERLAY_VARS.GNSS_DATA) && app.GNSSstationsCheckBox.Value == 1
                    overlay_gnss_2d_drawing(app);
                end
            
                % % --- Label or comment plot ---------------
                % if ~isempty(app.ASSIST_VARS.GTEXT_DATA) && app.menu_put_comment.Checked == "on"
                %     % Get current axes children
                %     ch = app.H_MAIN.Children;
                %     app.ASSIST_VARS.NH_COMMENT(1) = length(ch) + 1;
                % 
                %     % Loop through comments in GTEXT_DATA
                %     numComments = numel(app.ASSIST_VARS.GTEXT_DATA);
                %     for kk = 1:numComments
                %         gtextData = app.ASSIST_VARS.GTEXT_DATA(kk);
                %         gtextData.handle = text(app.UIAxes, gtextData.x, gtextData.y, gtextData.text, ...
                %             'FontSize', str2double(gtextData.font));
                %         app.ASSIST_VARS.GTEXT_DATA(kk).handle = gtextData.handle;
                %     end
                % 
                %     % Update NH_COMMENT and handle ordering
                %     app.ASSIST_VARS.NH_COMMENT(2) = length(app.H_MAIN.Children) - app.ASSIST_VARS.NH_COMMENT(1);
                %     app.ASSIST_VARS.NH_COMMENT(1) = 1;
                %     order_overlay_handle(app, 4);
                % end
            
                % --- Volcano PLUG-IN (not default) --------
                try
                    if ~isempty(app.OVERLAY_VARS.VOLCANO)
                        overlay_volcano_drawing(app);
                    end
                catch
                    return
                end
            end
        end

        
        function [outsdr] = nodal_plane_calc(~, st1, dp1, rk1)
            % =====================================================================
            %                nodal_plane_calc(~, st1, dp1, rk1)
            % =====================================================================
            % 90 deg causes error!
            dp_index = dp1 == 90;
            dp1(dp_index) = 89.99;
            rk_index = rk1 == 90;
            rk1(rk_index) = 89.5;
            rk_index = rk1 == -90;
            rk1(rk_index) = -89.5;
        
            % to avoid error
            st1 = st1 - 0.1;
        
            % m = length(st1);
            sdr(:, 1) = st1;
            sdr(:, 2) = dp1;
            sdr(:, 3) = rk1;
            [ptb, ~, ~, ~] = sdr2pt(sdr);
            
            p(:, 1)=ptb(:, 1);
            p(:, 2)=ptb(:, 2);
            t(:, 1)=ptb(:, 3);
            t(:, 2)=ptb(:, 4);
            [st1, dp1, rk1, st2, dp2, rk2] = pt2sdr(p, t);
        
            % process to find out the input nodal plane to make the order correct.
            allowance = 1.5;
            dum_st_min = sdr(:, 1) - allowance; dum_st_max = sdr(:, 1) + allowance; % strike check
            dum_dp_min = sdr(:, 2) - allowance; dum_dp_max = sdr(:, 2) + allowance; % dip check
            c1 = st1 > dum_st_min; c2 = st1 < dum_st_max;
            c3 = dp1 > dum_dp_min; c4 = dp1 < dum_dp_max;
            c5 = (c1 + c2 + c3 + c4) >= 4; c6 = (c1 + c2 + c3 + c4) < 4;
            st11 = double(c5) .* double(st1) + double(c6) .* double(st2);
            dp11 = double(c5) .* double(dp1) + double(c6) .* double(dp2);
            rk11 = double(c5) .* double(rk1) + double(c6) .* double(rk2);
            st22 = double(c6) .* double(st1) + double(c5) .* double(st2);
            dp22 = double(c6) .* double(dp1) + double(c5) .* double(dp2);
            rk22 = double(c6) .* double(rk1) + double(c5) .* double(rk2);
            outsdr = [st11 dp11 rk11 st22 dp22 rk22];
        
            % ネスト関数----------------------------------------------------
            function [ptb, p, t, b] = sdr2pt(sdr)
                % PURPOSE: STRIKE, DIP, RAKE --> P, T, B AXIS
                % INPUT ARGUMENTS:
                %       sdr(1):strike
                %       sdr(2):dip
                %       sdr(3):rake
                % OUTPUT ARGUMENTS:
                %       p: P axis (p(1), p(2), p(3)) -> (north, east, down)
                %       t: T axis
                %       b: B axis
                %       (ptb(1), ptb(2)): P axis (trend, plunge)
                %       (ptb(3), ptb(4)): T axis (trend, plunge)
                %       (ptb(5), ptb(6)): B axis (trend, plunge)
                angs(:, 2) = deg2rad(sdr(:, 1));
                angs(:, 1) = deg2rad(sdr(:, 2));
                angs(:, 3) = deg2rad(sdr(:, 3));
                [pttp, anbtp, p, t, b] = dsrin(angs);
                ptb(:, 1) = rad2deg(pttp(:, 1));
                ptb(:, 2) = rad2deg(pttp(:, 2));
                ptb(:, 3) = rad2deg(pttp(:, 3));
                ptb(:, 4) = rad2deg(pttp(:, 4));
                ptb(:, 5) = rad2deg(anbtp(:, 5));
                ptb(:, 6) = rad2deg(anbtp(:, 6));
            end
            
            function [pttp, anbtp, p, t, b] = dsrin(angs)
                % Calculates other representations of fault planes with dip, strike and rake (A&R convinention) input.
                % All angles are in radians.
                sr2 = 0.707107;
                rake =angs(:, 3);
                str = angs(:, 2);
                dip = angs(:, 1);
        
                a(:, 1) = cos(double(rake)) .* cos(double(str)) + sin(double(rake)) .* cos(double(dip)) .* sin(double(str));
                a(:, 2) = cos(double(rake)) .* sin(double(str)) - sin(double(rake)) .* cos(double(dip)) .* cos(double(str));
                a(:, 3) = -sin(double(rake)) .* sin(double(dip));
                n(:, 1) = -sin(double(str)) .* sin(double(dip));
                n(:, 2) = cos(double(str)) .* sin(double(dip));
                n(:, 3) = -cos(double(dip));
        
                [dummy1] = v2trpl(a);
                [dummy2] = v2trpl(n);
                anbtp(:, 1) = dummy1(:, 1);
                anbtp(:, 2) = dummy1(:, 2);
                anbtp(:, 3) = dummy2(:, 1);
                anbtp(:, 4) = dummy2(:, 2);
                 
	            t(:, 1:3) = double(sr2 .* (a(:, 1:3) + n(:, 1:3)));
	            p(:, 1:3) = double(sr2 .* (a(:, 1:3) - n(:, 1:3)));  
                b(:, 1) = p(:, 2) .* t(:, 3) - p(:, 3) .* t(:, 2);
                b(:, 2) = p(:, 3) .* t(:, 1) - p(:, 1) .* t(:, 3);
                b(:, 3) = p(:, 1) .* t(:, 2) - p(:, 2) .* t(:, 1);
        
                % problem code
                [dummy1]  = v2trpl(p);
                [dummy2]  = v2trpl(t);
                [dummy3]  = v2trpl(b);
        
                pttp(:, 1) = dummy1(:, 1);
                pttp(:, 2) = dummy1(:, 2);
                pttp(:, 3) = dummy2(:, 1);
                pttp(:, 4) = dummy2(:, 2);
                anbtp(:, 5) = dummy3(:, 1);
                anbtp(:, 6) = dummy3(:, 2);
            end
        
            function [angs] = an2dsr(a, n)
                c1 = abs(n(:, 3)) == 1.0;
                c2 = abs(n(:, 3)) ~= 1.0;
                angs(:, 2) = (atan2(double(a(:, 2)), double(a(:, 1)))) .* c1 + (atan2(double(-n(:, 1)), double(n(:, 2)))) .* c2; 
                d1 = n(:, 3) == 1.0;
                d2 = abs(sin(double(angs(:, 2)))) >= 0.1;
                d2 = d2 .* (-1) .* (d1-1);
                d3 = (d1 + d2) <= 0.0;
                d  = 0.5*pi .* d1 + (atan2(double(-n(:, 1) ./ sin(double(angs(:, 2)))), double(-n(:, 3)))) .* d2...
                    + (atan2(double(n(:, 2) ./ cos(double(angs(:, 2)))), double(-n(:, 3)))) .* d3;
                angs(:, 1) = 0.0 .* c1 + d .* c2;
                   
                a1 = a(:, 1) .* cos(double(angs(:, 2))) + a(:, 2) .* sin(double(angs(:, 2)));
                c1 = abs(a1) < 0.001;
                c2 = abs(a1) >= 0.001;
                a1 = 0.0 .* c1 + a1 .* c2;
        
                c1 = a(:, 3) ~= 0.0;
                c2 = a(:, 3) == 0.0;
                d1 = abs(sin(2 .* double(angs(:, 2)))) >= 0.0001;
                d2 = abs(sin(double(angs(2)))) >= 0.0001;
                d2 = d2 .* (-1) .* (d1-1);
                d3 = d1 + d2 <= 0.0;
                a2 = a(:, 1) .* sin(double(angs(:, 2))) - a(:, 2) .* cos(double(angs(:, 2)));
                e1 = abs(a2) >= 0.0001;
                a2 = a2 .* e1;
                
                angs(:, 3) = (atan2(double(-a(:, 3) ./ sin(double(angs(:, 1)))), double(a1))) .* c1...
                     + ((atan2(double(a2 ./ sin(2*double(angs(:, 2)))), double(a1))) .* d1...
                     + (acos(double(a(:, 2) ./ sin(double(angs(:, 2))))) .* d2...
                     + (acos(double(a1)))) .* d3) .* c2;
        
                c1 = angs(:, 1) < 0.0;
                c2 = angs(:, 1) >= 0.0;
                d1 = angs(:, 3) > pi;
                d2 = angs(:, 3) <= pi;
                angs(:, 1) = (angs(:, 1) + pi) .* c1 + angs(:, 1) .* c2;
                angs(:, 3) = ((angs(:, 3) - 2*pi) .* d1 + (pi-angs(:, 3)) .* d2) .* c1 + angs(:, 3) .* c2;
        
                c1 = angs(:, 1) > 0.5*pi;
                c2 = angs(:, 1) <= 0.5*pi;
                d1 = angs(:, 2) >= 2*pi;
                d2 = angs(:, 2) < 2*pi;
                angs(:, 1) = (pi - angs(:, 1)) .* c1 + angs(:, 1) .* c2;
                angs(:, 2) = ((angs(:, 2) - 2*pi) .* d1 + (angs(:, 2) + pi) .* d2) .* c1 + angs(:, 2) .* c2;
                angs(:, 3) = (-angs(:, 3)) .* c1 + angs(:, 3) .* c2;        
        
                c1 = angs(:, 2) < 0.0;
                c2 = angs(:, 2) >= 0.0;
                angs(:, 2) = (angs(:, 2) + 2.0*pi) .* c1 + angs(:, 2) .* c2;
            end
        
            function [trpl] = v2trpl(xyz)
                % Transforms from XYZ components of a unit vector to the trend and plunge for the vector.
                % Trend is the azimuth (clockwise from north looking down)
                % Plunge is the downward dip measured from the horizontal.
                % All angles in radians
                % X is north, Y is east, Z is down
                % If the component of Z is negative (up), the plunge, TRPL(2), is replaced by its negative and the trend, TRPL(1), Is changed by PI.
                % The trend is returned between 0 and 2*PI, the plunge between 0 and PI/2.
                    
                xyz = double(xyz);
                c1 = abs(xyz(:, 1:3)) > 0.000001;
                xyz(:, 1:3) = xyz(:, 1:3) .* c1;
        
                c1 = abs(abs(xyz(:, 1:3))-1.0) < 0.00001;
                c2 = abs(abs(xyz(:, 1:3))-1.0) >= 0.00001;
                xyz(:, 1:3) = double(xyz(:, 1:3) ./ abs(xyz(:, 1:3))) .* c1 + xyz(:, 1:3) .* c2;
        
                c1 = abs(xyz(:, 3)) == 1.0;
                c2 = abs(xyz(:, 3)) ~= 1.0;
        
                d1 = abs(xyz(:, 1)) < 0.0001;
                d2 = abs(xyz(:, 1)) >= 0.0001;
                e1 = xyz(:, 2) > 0.0;
                e2 = xyz(:, 2) < 0.0;
                e3 = xyz(:, 2) == 0.0;
                trpl(:, 1) = 0.0 .* c1 + (((pi/2.) .* e1 + (3.0*pi/2.0) .* e2 + 0.0 .* e3) .* d1 + (atan2(double(xyz(:, 2)), double(xyz(:, 1)))) .* d2) .* c2;
        
                c = cos(double(trpl(:, 1)));
                s = sin(double(trpl(:, 1)));
                f1 = abs(c) >= 0.1;
                f2 = abs(c) < 0.1;
                trpl(:, 2) = 0.5*pi .* c1 + ((atan2(double(xyz(:, 3)), double(xyz(:, 1) ./ c))) .* f1 + (atan2(double(xyz(:, 3)), double(xyz(:, 2) ./ s))) .* f2) .* c2;
        
                c1 = trpl(:, 2) < 0.0;
                c2 = trpl(:, 2) >= 0.0;
                trpl(:, 2) = (-trpl(:, 2)) .* c1 + trpl(:, 2) .* c2;
                trpl(:, 1) = (trpl(:, 1) - pi) .* c1 + trpl(:, 1) .* c2;
                c1 = trpl(:, 1) < 0.0;
                c2 = trpl(:, 1) >= 0.0;
                trpl(:, 1) = (trpl(:, 1) + 2.0*pi) .* c1 + trpl(:, 1) .* c2; 
            end
        
            function [momten] = an2mom(a, n)
                % Starting with the a and N axis, calculates the elements of the moment tensor with unit scalar moment.
                momten(:, 1) = 2.0 .* a(:, 3) .* n(:, 3);
                momten(:, 2) = 2.0 .* a(:, 1) .* n(:, 1);
                momten(:, 3) = 2.0 .* a(:, 2) .* n(:, 2);
                momten(:, 4) = a(:, 1) .* n(:, 3) + a(:, 3) .* n(:, 1);
                momten(:, 5) = -a(:, 2) .* n(:, 3)-a(:, 3) .* n(:, 2);
                momten(:, 6) = -a(:, 2) .* n(:, 1)-a(:, 1) .* n(:, 2);
                c1 = abs(momten(:, 1:6)) >= 0.000001;
                momten(:, 1:6) = momten(:, 1:6) .* c1;
            end
        
            function [xyz] = trpl2v(trpl)
                % Transforms to XYZ components of a unit vector from the trend and plunge for the vector.
                % Trend is the azimuth (clockwise from north looking down)
                % Plunge is the downward dip measured from the horizontal.
                % All angles in radians
                % X is north, Y is east, Z is down
                xyz(:, 1) = cos(double(trpl(:, 1))) .* cos(double(trpl(:, 2)));
                xyz(:, 2) = sin(double(trpl(:, 1))) .* cos(double(trpl(:, 2)));
                xyz(:, 3) = sin(double(trpl(:, 2)));
        
                c1 = abs(xyz(:, 1:3)) >= 0.000001;
                xyz(:, 1:3) = xyz(:, 1:3) .* c1;
                c1 = abs(abs(xyz(:, 1:3))-1.0) < 0.00001;
                c2 = abs(abs(xyz(:, 1:3))-1.0) >= 0.00001;
                xyz(:, 1:3) = (xyz(:, 1:3) ./ abs(xyz(:, 1:3))) .* c1 + xyz(:, 1:3) .* c2;
            end
        
            function [angs, angs2, anbtp, momten] = pttpin(pttp)
                % Calculates other representations of fault planes with trend and plunge of P and T as input. 
                % All angles are in radians.
                sr2 = 0.707107;
                [p] = trpl2v([pttp(:, 1) pttp(:, 2)]);
                [t] = trpl2v([pttp(:, 3) pttp(:, 4)]);
                a(:, 1:3) = sr2 .* (p(:, 1:3) + t(:, 1:3));
                n(:, 1:3) = sr2 .* (t(:, 1:3) - p(:, 1:3));
                b(:, 1) = p(:, 2) .* t(:, 3) - p(:, 3) .* t(:, 2);
                b(:, 2) = p(:, 3) .* t(:, 1) - p(:, 1) .* t(:, 3);
                b(:, 3) = p(:, 1) .* t(:, 2) - p(:, 2) .* t(:, 1);
                 
                [dummy1] = v2trpl(a);
                [dummy2] = v2trpl(n); 
                [dummy3] = v2trpl(b);
                 
                anbtp(:, 1) = dummy1(:, 1);
                anbtp(:, 2) = dummy1(:, 2);
                anbtp(:, 3) = dummy2(:, 1);
                anbtp(:, 4) = dummy2(:, 2);
                anbtp(:, 5) = dummy3(:, 1);
                anbtp(:, 6) = dummy3(:, 2);
                 
                [angs]     = an2dsr(a, n);
                [angs2]    = an2dsr(n, a);
                [momten]   = an2mom(a, n);
            end
        
            function [str1, dip1, rake1, str2, dip2, rake2] = pt2sdr(p, t)
                % PURPOSE: P, T AXIS --> STRIKE, DIP, RAKE
                % INPUT ARGUMENTS:
                %     p(1): p-axis azimth
                %     p(2): plunge
                %     t(1): t-axis azimth
                %     t(2): pluge
                % OUTPUT ARGUMENTS:
                %     str1:  strike
                %     dip1:  dip
                %     rake1: rake
                %     str2:  strike
                %     dip2:  dip
                %     rake2: rake
        
                pttp(:, 1)=deg2rad(p(:, 1));
                pttp(:, 2)=deg2rad(p(:, 2));
                pttp(:, 3)=deg2rad(t(:, 1));
                pttp(:, 4)=deg2rad(t(:, 2));
                 
                [angs, angs2, ~, ~] = pttpin(pttp);
                 
                str1  = int16(rad2deg(angs(:, 2)));
                dip1  = int16(rad2deg(angs(:, 1)));
                rake1 = int16(rad2deg(angs(:, 3)));
                str2  = int16(rad2deg(angs2(:, 2)));
                dip2  = int16(rad2deg(angs2(:, 1)));
                rake2 = int16(rad2deg(angs2(:, 3)));
            end
        end

        
        function nodal_plane_window(app)
            % =====================================================================
            %                nodal_plane_window(app)
            % =====================================================================
            % デフォルト値を設定
            app.ASSIST_VARS.INODAL = 1;
            app.ASSIST_VARS.NODAL_ACT = 0;

            list = {'Nodal plane 1', ...
                    'Nodal plane 2', ...
                    'Shuffle', ...
                    'Max. values', ...
                    'Min. values'};

            % indexは選択された行を返す(1 or 2)、~にはtrue or falseが格納される
            [index, ~] = listdlg('PromptString', ... % {}内をテキスト表示
                                    {'Choose one of the following', ...
                                    '', ...
                                    'See numerical output for corresponding planes.'},...
                                    'SelectionMode', 'single', ... % 一つのみ選択可能に
                                    'ListString', list, ... % 表示するリスト
                                    'ListSize', [300, 250], ... % リストのサイズ [width height]
                                    'Name', 'Select Nodal plane type'); % リストダイアログウィンドウの名前

            if index == 1
                % Nodal plane 1が選択されたとき
                app.ASSIST_VARS.INODAL = 1;
                app.ASSIST_VARS.NODAL_ACT = 1;
                cd(app.SYSTEM_VARS.HOME_DIR);
                focal_mech_calc(app);

            elseif index == 2
                % Nodal plane 2が選択されたとき
                check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
                if check2 == 0
                    h = uialert(app.main_menu_window, 'No second set of focal mechanisms exist', 'Warning!');
                    waitfor(h);
                    return;
                end
                app.ASSIST_VARS.INODAL = 2;
                app.ASSIST_VARS.NODAL_ACT = 1;
                cd(app.SYSTEM_VARS.HOME_DIR);
                focal_mech_calc(app);

            elseif index == 3
                % Shuffleが選択されたとき
                check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
                if check2 == 0
                    h = uialert(app.main_menu_window, 'No second set of focal mechanisms exist', 'Warning!');
                    waitfor(h);
                    return;
                end
                app.ASSIST_VARS.INODAL = 3;
                app.ASSIST_VARS.NODAL_ACT = 1;
                cd(app.SYSTEM_VARS.HOME_DIR);
                focal_mech_calc(app);

            elseif index == 4
                % Max. valuesが選択されたとき
                check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
                if check2 == 0
                    h = uialert(app.main_menu_window, 'No second set of focal mechanisms exist', 'Warning!');
                    waitfor(h);
                    return;
                end
                app.ASSIST_VARS.INODAL = 4;
                app.ASSIST_VARS.NODAL_ACT = 1;
                cd(app.SYSTEM_VARS.HOME_DIR);
                focal_mech_calc(app);

            elseif index == 5
                % Min. valuesが選択されたとき
                check2 = sum(app.OVERLAY_VARS.EQ_DATA(:, 13)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 14)) + sum(app.OVERLAY_VARS.EQ_DATA(:, 15));
                if check2 == 0
                    h = uialert(app.main_menu_window, 'No second set of focal mechanisms exist', 'Warning!');
                    waitfor(h);
                    return;
                end
                app.ASSIST_VARS.INODAL = 5;
                app.ASSIST_VARS.NODAL_ACT = 1;
                cd(app.SYSTEM_VARS.HOME_DIR);
                focal_mech_calc(app);
            end

            % 作業ディレクトリをホームディレクトリに戻す
            cd(app.SYSTEM_VARS.HOME_DIR);
        end

        % ==========================================================================================================================================
        %                クロスセクション関連の関数
        % ==========================================================================================================================================
        function xsec_window(app)
            % =====================================================================
            %                xsec_window(app)
            % =====================================================================
            % クロスセクションコントロールパネル関連
            % グラフのタイトルをパネルの操作への誘導に変更
            title(app.UIAxes2, 'Please operate the panel on the right.', 'FontSize', 18);
            % 表示項目の初期設定
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                app.CSC_XLabel.String = 'lon.';
                app.CSC_YLabel.String = 'lat.';
                app.CSC_StartkmLabel.String = 'deg.';
                app.CSC_FinishkmLabel.String = 'deg.';
            end
                            
            % App Designerでのコンポーネントの非表示設定
            % app.CSC_DowndipIncEditField.Visible = 'off';
            % app.CSC_downinckm_Label.Visible = 'off';
            % app.CSC_DipEditField.Visible = 'off';
            % app.CSC_dip_Label.Visible = 'off';
        
            % 初期値設定
            if ~isempty(app.INPUT_VARS.SECTION)
                app.SECTION_VARS.SEC_XS = app.INPUT_VARS.SECTION(1);
                app.SECTION_VARS.SEC_YS = app.INPUT_VARS.SECTION(2);
                app.SECTION_VARS.SEC_XF = app.INPUT_VARS.SECTION(3);
                app.SECTION_VARS.SEC_YF = app.INPUT_VARS.SECTION(4);
                app.SECTION_VARS.SEC_INCRE = app.INPUT_VARS.SECTION(5);
                app.SECTION_VARS.SEC_DEPTH = app.INPUT_VARS.SECTION(6);
                app.SECTION_VARS.SEC_DEPTHINC = app.INPUT_VARS.SECTION(7);
                app.SECTION_VARS.SEC_DIP = 90.0;
                app.SECTION_VARS.SEC_DOWNDIP_INC = app.INPUT_VARS.SECTION(7);
            else
                app.SECTION_VARS.SEC_XS = app.INPUT_VARS.GRID(1) + (app.INPUT_VARS.GRID(3) - app.INPUT_VARS.GRID(1)) / 4;
                app.SECTION_VARS.SEC_YS = app.INPUT_VARS.GRID(2) + (app.INPUT_VARS.GRID(4) - app.INPUT_VARS.GRID(2)) / 4;
                app.SECTION_VARS.SEC_XF = app.INPUT_VARS.GRID(3) - (app.INPUT_VARS.GRID(3) - app.INPUT_VARS.GRID(1)) / 4;
                app.SECTION_VARS.SEC_YF = app.INPUT_VARS.GRID(4) - (app.INPUT_VARS.GRID(4) - app.INPUT_VARS.GRID(2)) / 4;
                app.SECTION_VARS.SEC_DEPTHINC = (app.INPUT_VARS.GRID(5) + app.INPUT_VARS.GRID(6)) / 5.0;
                app.SECTION_VARS.SEC_INCRE = app.SECTION_VARS.SEC_DEPTHINC;
                app.SECTION_VARS.SEC_DEPTH = app.ASSIST_VARS.AV_DEPTH + app.ASSIST_VARS.AV_DEPTH / 2.0;
                app.SECTION_VARS.SEC_DIP = 90.0;
                app.SECTION_VARS.SEC_DOWNDIP_INC = (app.INPUT_VARS.GRID(5) + app.INPUT_VARS.GRID(6)) / 5.0;
            end

            % 座標変換およびフィールドの値を設定
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % lon & lat coordinates
                a = xy2lonlat(app, [app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS]);
                b = xy2lonlat(app, [app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF]);
                app.CSC_StartX_EditField.Value = a(1);
                app.CSC_StartY_EditField.Value = a(2);
                app.CSC_FinishX_EditField.Value = b(1);
                app.CSC_FinishY_EditField.Value = b(2);
            else
                % 修正必要？
                % xy coordinates
                app.CSC_StartX_EditField.Value = app.SECTION_VARS.SEC_XS;
                app.CSC_StartY_EditField.Value = app.SECTION_VARS.SEC_YS;
                app.CSC_FinishX_EditField.Value = app.SECTION_VARS.SEC_XF;
                app.CSC_FinishY_EditField.Value = app.SECTION_VARS.SEC_YF;
            end

            % Check and set StartX
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a = xy2lonlat(app, [app.SECTION_VARS.SEC_XS, 0.0]);
                app.CSC_StartX_EditField.Value = a(1);
            else
                app.CSC_StartX_EditField.Value = app.SECTION_VARS.SEC_XS;
            end
            
            % Check and set StartY
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a = xy2lonlat(app, [0.0, app.SECTION_VARS.SEC_YS]);
                app.CSC_StartY_EditField.Value = a(2);
            else
                app.CSC_StartY_EditField.Value = app.SECTION_VARS.SEC_YS;
            end
            
            % Check and set FinishX
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a = xy2lonlat(app, [app.SECTION_VARS.SEC_XF, 0.0]);
                app.CSC_FinishX_EditField.Value = a(1);
            else
                app.CSC_FinishX_EditField.Value = app.SECTION_VARS.SEC_XF;
            end
            
            % Check and set FinishY
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                a = xy2lonlat(app, [0.0, app.SECTION_VARS.SEC_YF]);
                app.CSC_FinishY_EditField.Value = a(2);
            else
                app.CSC_FinishY_EditField.Value = app.SECTION_VARS.SEC_YF;
            end

            % 編集フィールドの初期値を設定
            % Horizontal Increment
            app.CSC_HI_EditField.Value = app.SECTION_VARS.SEC_INCRE;
        
            % Depth
            app.CSC_DepthEditField.Value = app.SECTION_VARS.SEC_DEPTH;
        
            % Depth Increment
            app.CSC_DepthIncEditField.Value = app.SECTION_VARS.SEC_DEPTHINC;
        
            % Down Dip Increment
            app.CSC_DowndipIncEditField.Value = app.SECTION_VARS.SEC_DOWNDIP_INC;

            % Section Dip
            app.CSC_DipEditField.Value = app.SECTION_VARS.SEC_DIP;

            % 初期の描画
            CSC_draw_dipped_cross_section(app);
        end


        function CSC_draw_dipped_cross_section(app)
            % =====================================================================
            %                CSC_draw_dipped_cross_section(app)
            % =====================================================================
            % 断面の線をグラフに表示
            % SECTION_VARS から各値を取得
            app.SECTION_VARS.SEC_XS = app.CSC_StartX_EditField.Value;
            app.SECTION_VARS.SEC_YS = app.CSC_StartY_EditField.Value;
            app.SECTION_VARS.SEC_XF = app.CSC_FinishX_EditField.Value;
            app.SECTION_VARS.SEC_YF = app.CSC_FinishY_EditField.Value;

            % 座標変換の処理
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                temp  = [app.SECTION_VARS.SEC_XS app.SECTION_VARS.SEC_XF app.SECTION_VARS.SEC_YS app.SECTION_VARS.SEC_YF];
                a = lonlat2xy(app, [app.SECTION_VARS.SEC_XS 0.0]); app.SECTION_VARS.SEC_XS = a(1);
                a = lonlat2xy(app, [app.SECTION_VARS.SEC_XF 0.0]); app.SECTION_VARS.SEC_XF = a(1);
                a = lonlat2xy(app, [0.0 app.SECTION_VARS.SEC_YS]); app.SECTION_VARS.SEC_YS = a(2);
                a = lonlat2xy(app, [0.0 app.SECTION_VARS.SEC_YF]); app.SECTION_VARS.SEC_YF = a(2);
            end
        
            % SECTION_VARS に深さ、傾斜などの値を設定
            app.SECTION_VARS.SEC_INCRE = app.CSC_HI_EditField.Value;
            app.SECTION_VARS.SEC_DEPTH = app.CSC_DepthEditField.Value;
            app.SECTION_VARS.SEC_DEPTHINC = app.CSC_DepthIncEditField.Value;
            app.SECTION_VARS.SEC_DIP = app.CSC_DipEditField.Value;
            app.SECTION_VARS.SEC_DOWNDIP_INC = app.CSC_DowndipIncEditField.Value;
        
            % 現在のプロットを削除
            hold(app.UIAxes, 'on');
            % 修正必要？
            dipped_cross_section_lines = findall(app.UIAxes, 'Tag', 'dipped_cross_section_lines');
            cross_section_line = findall(app.UIAxes, 'Tag', 'cross_section_line');
            cross_section_A = findall(app.UIAxes, 'Tag', 'cross_section_A');
            cross_section_B = findall(app.UIAxes, 'Tag', 'cross_section_B');
            if ~isempty(dipped_cross_section_lines)
                delete(dipped_cross_section_lines);
            end
            if ~isempty(cross_section_line)
                delete(cross_section_line);
            end
            if ~isempty(cross_section_A)
                delete(cross_section_A);
            end
            if ~isempty(cross_section_B)
                delete(cross_section_B);
            end

            % Fault cornersを計算
            c = fault_corners(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.SECTION_VARS.SEC_DIP, 0.0, app.SECTION_VARS.SEC_DEPTH);
            
            % app.UIAxesに対して保持
            hold(app.UIAxes, 'on');
            
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                HO = plot(app.UIAxes, [temp(1) temp(2)], [temp(3) temp(4)]);
                HT1 = text(app.UIAxes, temp(1), temp(3), 'A', 'FontSize', 18, 'Color', 'k');
                HT2 = text(app.UIAxes, temp(2), temp(4), 'B', 'FontSize', 18, 'Color', 'k');
                a = xy2lonlat(app, [c(:,1), c(:,2)]);
                HF = plot(app.UIAxes, [a(1,1) a(2,1) a(3,1) a(4,1) a(1,1)], [a(1,2) a(2,2) a(3,2) a(4,2) a(1,2)]);
            else
                HO = plot(app.UIAxes, [app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_XF], [app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_YF]);
                HT1 = text(app.UIAxes, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, 'A', 'FontSize', 18, 'Color', 'k');
                HT2 = text(app.UIAxes, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, 'B', 'FontSize', 18, 'Color', 'k');
                HF = plot(app.UIAxes, [c(1,1) c(2,1) c(3,1) c(4,1) c(1,1)], [c(1,2) c(2,2) c(3,2) c(4,2) c(1,2)]);
            end
            % 設定
            % 修正必要？
            HO.Tag = 'cross_section_line';
            HO.Color = 'b';
            HO.LineWidth = 1.5;
            HT1.HorizontalAlignment = 'center';
            HT1.Tag = 'cross_section_A';
            HT2.HorizontalAlignment = 'center';
            HT2.Tag = 'cross_section_B';
            HF.Tag = 'dipped_cross_section_lines';
            HF.Color = 'b';
            HF.LineWidth = 0.5;
        end

        
        function CSC_displacement_section(app)
            % =====================================================================
            %                CSC_displacement_section(app)
            % =====================================================================
            % This function renders the Coulomb calculation result in a cross section
            % 始点と終点の座標を編集フィールドから取得
            % おそらく変換は既にされているため必要ない
            app.SECTION_VARS.SEC_XS = app.CSC_StartX_EditField.Value;
            app.SECTION_VARS.SEC_YS = app.CSC_StartY_EditField.Value;
            app.SECTION_VARS.SEC_XF = app.CSC_FinishX_EditField.Value;
            app.SECTION_VARS.SEC_YF = app.CSC_FinishY_EditField.Value;

            % セクション距離と深さの計算
            % Calculate section distance and depth
            distance = sqrt((app.SECTION_VARS.SEC_XF - app.SECTION_VARS.SEC_XS)^2 + (app.SECTION_VARS.SEC_YF - app.SECTION_VARS.SEC_YS)^2);
            app.ASSIST_VARS.NSEC = int32(distance / app.SECTION_VARS.SEC_INCRE) + 1;
            app.ASSIST_VARS.NDEPTH = abs(int32(app.SECTION_VARS.SEC_DEPTH / app.SECTION_VARS.SEC_DEPTHINC)) + 1;

        
            % X軸とY軸の位置設定
            x = 1:1:app.ASSIST_VARS.NSEC;
            y = 1:1:abs(app.ASSIST_VARS.NDEPTH);
            xt = x;
            x = (double(x) - 1.0) * app.SECTION_VARS.SEC_INCRE;
            y = (-1.0) * (double(y) - 1.0) * app.SECTION_VARS.SEC_DEPTHINC;

            % xとyの範囲を取得
            xmin = min(x);      % min km (normally zero) along the cross section x axis
            xmax = max(x);      % maximum distance along the cross section x axis
            ymin = min(y);      % deepest position along the depth axis (negative)
            ymax = max(y);      % shallowest position along the depth axis (normally zero)

            app.SECTION_VARS.SEC_DEPTH = (-1.0) * app.SECTION_VARS.SEC_DEPTH;

            disp('app.SECTION_VARS.SEC_DEPTH');
            disp(app.SECTION_VARS.SEC_DEPTH);
            disp('app.SECTION_VARS.SEC_INCRE');
            disp(app.SECTION_VARS.SEC_INCRE);
            disp('max(y)');
            disp(max(y));

            % x y position in a mapped coordinate along the cross section
            % 断面に沿った座標を計算
            xx = app.SECTION_VARS.SEC_XS + (app.SECTION_VARS.SEC_XF - app.SECTION_VARS.SEC_XS) / double(app.ASSIST_VARS.NSEC - 1) * double(xt - 1);
            yy = app.SECTION_VARS.SEC_YS + (app.SECTION_VARS.SEC_YF - app.SECTION_VARS.SEC_YS) / double(app.ASSIST_VARS.NSEC - 1) * double(xt - 1);
            
            % repmat(M, v, h) copy function of Matrix
            app.ASSIST_VARS.AX = repmat(xx, abs(app.ASSIST_VARS.NDEPTH), 1);
            app.ASSIST_VARS.AY = repmat(yy, abs(app.ASSIST_VARS.NDEPTH), 1);
            app.ASSIST_VARS.AZ = repmat(reshape(y, abs(app.ASSIST_VARS.NDEPTH), 1), 1, app.ASSIST_VARS.NSEC);

            app.SECTION_VARS.SEC_FLAG = 1;

            % もしIACTSが1でない場合は、Okada計算を実行
            if app.CALC_CONTROL.IACTS ~= 1
                Okada_halfspace(app);
            end
            % to keep okada output
            % Okada計算済みフラグ
            app.CALC_CONTROL.IACTS = 1; 

            % OKADA_OUTPUTから変位データを取得
            % Retrieve displacement data from OKADA_OUTPUT
            a = length(app.OKADA_OUTPUT.DC3DS);
            if a <= 14
                uialert(app.main_menu_window, 'Increase total grid number more than 14.', 'Warning!');
            end

            % ss = zeros(3, a);
            uhorizon = zeros(1, a);
            s6 = reshape(app.OKADA_OUTPUT.DC3DS(:, 6), 1, a); % UXG
            s7 = reshape(app.OKADA_OUTPUT.DC3DS(:, 7), 1, a); % UYG
            s8 = reshape(app.OKADA_OUTPUT.DC3DS(:, 8), 1, a); % UZG
            ss = [s6; s7; s8];
        
            % Prepare for plotting in the section view window on app.UIAxes2
            cla(app.UIAxes2, 'reset');  % Clear previous plot on UIAxes2

            % DISP_SCALEが空でなければリサイズを計算
            if ~isempty(app.ASSIST_VARS.DISP_SCALE)
                resz = (app.INPUT_VARS.SIZE(3, 1) / 10000) * double(app.ASSIST_VARS.DISP_SCALE);
            else
                resz = app.INPUT_VARS.SIZE(3, 1) / 10000;    
            end
            resz = double(resz);
            above_surface = 2.0 * round(resz / 1.0) * app.SECTION_VARS.SEC_INCRE;

            disp('app.ASSIST_VARS.DISP_SCALE');
            disp(app.ASSIST_VARS.DISP_SCALE);
            disp('above_surface');
            disp(above_surface);

            % Keep plots on the same axis
            hold(app.UIAxes2, 'on');

            %=== draw cross section grid ===================
            % 断面グリッドを描画
            % Draw the main grid outline
            plot(app.UIAxes2, [xmin xmax xmax xmin xmin], [ymin ymin ymax ymax ymin]);
            % Set axis properties for app.UIAxes2
            set(app.UIAxes2, 'DataAspectRatio', [1 1 1], ...
                'Color', [0.9 0.9 0.9], ...
                'PlotBoxAspectRatio', [1 1 1], ...
                'XLim', [xmin, xmax], ...
                'YLim', [ymin, ymax + above_surface], ...
                'TickDir', 'out');
        
            % x方向のグリッドラインを描画
            % Draw grid lines in the x-direction
            for m = 1:app.ASSIST_VARS.NSEC
                x0 = xmin + double(m) * app.SECTION_VARS.SEC_INCRE;
                % Plot each x grid line
                gridln1 = plot(app.UIAxes2, [x0 x0], [ymin ymax]);
                gridln1.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                gridln1.Color = app.SYSTEM_VARS.PREF(3, 1:3);
            end
        
            % y方向のグリッドラインを描画
            % Draw grid lines in the y-direction
            for n = 1:app.ASSIST_VARS.NDEPTH - 1
                y0 = ymin + double(n) * app.SECTION_VARS.SEC_DEPTHINC;
                % Plot each y grid line
                gridln2 = plot(app.UIAxes2, [xmin xmax], [y0 y0]);
                gridln2.LineWidth = app.SYSTEM_VARS.PREF(3, 4);
                gridln2.Color = app.SYSTEM_VARS.PREF(3, 1:3);
            end
        
            % xとyの位置の再設定
            % Redefine x and y positions for plotting
            x = repmat(x, abs(app.ASSIST_VARS.NDEPTH), 1);
            x = reshape(x, 1, app.ASSIST_VARS.NSEC * app.ASSIST_VARS.NDEPTH);
            y = repmat(y, 1, abs(app.ASSIST_VARS.NSEC));
        
            % 各ポイントでの水平変位を計算
            for k = 1:app.ASSIST_VARS.NSEC * app.ASSIST_VARS.NDEPTH
                uhorizon(1, k) = CSC_disp_sect_conv(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, ss(1, k), ss(2, k));
            end

            % CALC_CONTROL.FUNC_SWITCH の値によって描画方法を変更
            % Modify the display based on CALC_CONTROL.FUNC_SWITCH
            if app.CALC_CONTROL.FUNC_SWITCH == 2
                % For CALC_CONTROL.FUNC_SWITCH == 2, display displacement vectors
                a1 = quiver(app.UIAxes2, x, y, uhorizon(1, :) * resz, ss(3, :) * resz, 0);
                a1.Color = app.SYSTEM_VARS.PREF(2, 1:3);
                a1.LineWidth = app.SYSTEM_VARS.PREF(2, 4);
                title(app.UIAxes2, 'Displacement projected onto cross section', 'FontSize', 14);

            elseif app.CALC_CONTROL.FUNC_SWITCH == 3 || app.CALC_CONTROL.FUNC_SWITCH == 4
                % CALC_CONTROL.FUNC_SWITCH が3または4の場合、変形したワイヤーフレームを描画
                % For CALC_CONTROL.FUNC_SWITCH == 3 or 4, display the deformed wireframe
                xds = reshape(x, app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
                yds = reshape(y, app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
                uho = reshape(uhorizon, app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
                sz  = reshape(ss(3, :), app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
                xds = xds + uho * resz;
                yds = yds + sz  * resz;
        
                % Plot each line segment in x and y directions
                for k = 1:app.ASSIST_VARS.NSEC - 1
                    hold(app.UIAxes2, 'on');
                    a1 = plot(app.UIAxes2, [xds(:, k), xds(:, k + 1)], [yds(:, k), yds(:, k + 1)]);
                    for i = 1:numel(a1)
                        a1(i).LineWidth = 1.0;
                        a1(i).Color = 'b';
                    end
                end
        
                for k = 1:app.ASSIST_VARS.NDEPTH - 1
                    hold(app.UIAxes2, 'on');
                    a1 = plot(app.UIAxes2, [xds(k, :), xds(k + 1, :)], [yds(k, :), yds(k + 1, :)]);
                    for i = 1:numel(a1)
                        a1.LineWidth = 1.0;
                        a1.Color = 'b';
                    end
                end
                title(app.UIAxes2, 'Deformed wireframe projected onto cross section', 'FontSize', 14);
            
                % % 変位データの設定
                % ss = [app.OKADA_OUTPUT.DC3DS(:, 6)'; app.OKADA_OUTPUT.DC3DS(:, 7)'; app.OKADA_OUTPUT.DC3DS(:, 8)'];
                % 
                % % グリッドの描画
                % % Set up labels and axis properties in UIAxes2
                % cla(app.UIAxes2);  % Clear existing plots on UIAxes2
                % hold(app.UIAxes2, 'on');
                % xlabel(app.UIAxes2, 'Distance (km)');
                % ylabel(app.UIAxes2, 'Depth (km)');
                % 
                % % Draw the bounding box and grid lines within UIAxes2
                % plot(app.UIAxes2, [0, max(x)], [min(y), min(y)], 'k');
                % plot(app.UIAxes2, [0, max(x)], [max(y), max(y)], 'k');
                % plot(app.UIAxes2, [0, 0], [min(y), max(y)], 'k');
                % plot(app.UIAxes2, [max(x), max(x)], [min(y), max(y)], 'k');
                % 
                % % Optional additional settings for appearance if needed
                % set(app.UIAxes2, 'XLim', [min(x), max(x)], 'YLim', [min(y), max(y)], 'DataAspectRatio', [1 1 1], 'Color', [0.9 0.9 0.9]);
                % 
                % % 各種描画モード
                % switch app.CALC_CONTROL.FUNC_SWITCH
                %     case 2  % ベクトルでの表示
                %         % CALC_CONTROL.FUNC_SWITCH が2の場合、変位をベクトルで表示
                %         quiver(app.UIAxes2, x, y, uhorizon(1, :) * resz, ss(3, :) * resz, 0, 'Color', app.SYSTEM_VARS.PREF(2, 1:3), 'LineWidth', app.SYSTEM_VARS.PREF(2, 4));
                %         title(app.UIAxes2, 'Displacement projected onto cross section', 'FontSize', 14);
                %     case {3, 4}  % 変形したワイヤーフレーム
                %         % ワイヤーフレームの描画処理をここに追加
                %         title(app.UIAxes2, 'Deformed Wireframe Projected onto Cross Section');
                % end

                % これがなぜ加えられたのか、存在するのか不明↓、修正必要？
                % cd (app.SYSTEM_VARS.HOME_DIR);
                % format long;
                % % データファイル 'Displacement.cou' の読み込みと上書き
                % filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Displacement_section.cou');
                % fid = fopen(filePath, 'w');
                % % ヘッダーを書き込む
                % fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
                % fprintf(fid, 'x y distance z UX UY UZ\n');
                % fprintf(fid, '(km) (km) (km) (km) (m) (m) (m)\n');
                % % 数値データ書き込み（カラム数7）
                % data = [app.OKADA_OUTPUT.DC3DS(:, 1), ...
                %         app.OKADA_OUTPUT.DC3DS(:, 2), ...
                %         x(:), y(:), ss(:)];
                % % c が Nx7 の行列と仮定
                % for i = 1:size(data, 1)
                %     fprintf(fid, '%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\n', data(i, :));
                % end
                % fclose(fid);
                % disp(['Displacement_section.cou is saved in ', pwd]);
                % showMessage(app, ['Displacement_section.cou is saved in ', pwd], false);
                % cd(app.SYSTEM_VARS.HOME_DIR);
            end

            % ***** save numerical output file (start) *************************
            % 数値出力ファイルの保存処理（開始）
            surface = zeros(app.ASSIST_VARS.NSEC, 1);
            format long;

            % OKADA_OUTPUT のデータを reshape して利用し、出力ファイルに保存
            bx    = reshape(app.OKADA_OUTPUT.DC3DS(:, 1)', app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            by    = reshape(app.OKADA_OUTPUT.DC3DS(:, 2)', app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            bdist = reshape(x, app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            bux   = reshape(ss(1, :), app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            buy   = reshape(ss(2, :), app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            buz   = reshape(ss(3, :), app.ASSIST_VARS.NDEPTH, app.ASSIST_VARS.NSEC);
            b = [bx(1, 1:app.ASSIST_VARS.NSEC)' by(1, 1:app.ASSIST_VARS.NSEC)' bdist(1, 1:app.ASSIST_VARS.NSEC)' surface bux(1, 1:app.ASSIST_VARS.NSEC)' buy(1, 1:app.ASSIST_VARS.NSEC)' buz(1, 1:app.ASSIST_VARS.NSEC)'];

            cd (app.SYSTEM_VARS.HOME_DIR);
            format long;
            % データファイル 'Displacement_section.cou' の読み込みと上書き
            filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'Displacement_section.cou');
            fid = fopen(filePath, 'w');
            % ヘッダーを書き込む
            fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
            fprintf(fid, 'x y distance z UX UY UZ\n');
            fprintf(fid, '(km) (km) (km) (km) (m) (m) (m)\n');
            % c が Nx7 の行列と仮定
            for i = 1:size(b, 1)
                fprintf(fid, '%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\n', b(i, :));
            end
            fclose(fid);
            disp(['Displacement_section.cou is saved in ', pwd]);
            showMessage(app, ['Displacement_section.cou is saved in ', pwd], false);
            cd(app.SYSTEM_VARS.HOME_DIR);
            % 数値出力ファイルの保存処理（終了）
            % ***** save numerical output file (end) *************************

            hold(app.UIAxes2, 'on');
            % draw fault line-----------------------------------
            % 断層ラインを描画
            for n = 1:app.INPUT_VARS.NUM
                [lp_top, dp_top, lp_bottom, dp_bottom] = CSC_fault_int_sec(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, ...
                    app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), app.INPUT_VARS.ELEMENT(n, 3), app.INPUT_VARS.ELEMENT(n, 4), ...
                    app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));

                % 修正必要
                % disp([lp_top, dp_top, lp_bottom, dp_bottom])
                % disp('--')
                % Plot the fault line in two layers for visibility
                % 断層線を強調するため、白線の上に指定した色で二重に線を引く
                % 白い線（下地）
                f1 = plot(app.UIAxes2, [lp_top lp_bottom], [dp_top dp_bottom]);
                if ~isempty(f1)
                    f1.Color = 'w';
                    f1.LineWidth = 2;
                else
                    disp('skip drawing fault line');
                    showMessage(app, 'skip drawing fault line', false);
                end
                % 指定色の線（上書き）
                f2 = plot(app.UIAxes2, [lp_top lp_bottom], [dp_top dp_bottom]);
                if ~isempty(f2)
                    f2.Color = app.SYSTEM_VARS.PREF(1, 1:3);
                    f2.LineWidth = app.SYSTEM_VARS.PREF(1, 4);
                else
                    disp('skip drawing fault line');
                    showMessage(app, 'skip drawing fault line', false);
                end
            end
        
            % mapview line on cross section-----------------------------------
            % 断面における地表面のラインを描画
            fsurf1 = plot(app.UIAxes2, [xmin xmax], [-app.INPUT_VARS.CALC_DEPTH -app.INPUT_VARS.CALC_DEPTH]);
            fsurf1.LineStyle = '--';
            fsurf1.Color = 'b';
            fsurf1.LineWidth = 0.2;
        
            % unit displacement (1m)-----------------------------------
            % 単位変位（1m）を描画
            [unit, unitText] = check_unit_vector(app, xmin, xmax, 1.0*resz, 0.15, 0.4);
            % scale vector
            unit1 = quiver(app.UIAxes2, xmin + app.SECTION_VARS.SEC_INCRE, ymin + app.SECTION_VARS.SEC_DEPTHINC * 1.5, unit * resz, 0, 0);
            unit1.LineWidth = 1.2;
            unit1.Color = 'r';
            text(app.UIAxes2, xmin + unit * resz * 0.5, ymin + app.SECTION_VARS.SEC_DEPTHINC * 2.2, unitText, ...
                'FontName', 'Helvetica', 'FontSize', 14, 'Color', 'r', 'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        
            % draw labels-----------------------------------
            % ラベルを描画
            text(app.UIAxes2, xmin + app.SECTION_VARS.SEC_INCRE, ymax + app.SECTION_VARS.SEC_DEPTHINC, 'A', 'Color', 'b', 'FontSize', 14, 'FontWeight', 'bold');
            text(app.UIAxes2, xmax - app.SECTION_VARS.SEC_INCRE * 2.0, ymax + app.SECTION_VARS.SEC_DEPTHINC, 'B', 'Color', 'b', 'FontSize', 14, 'FontWeight', 'bold');
            xlabel(app.UIAxes2, 'Distance (km)', 'FontSize', 14);
            ylabel(app.UIAxes2, 'Depth (km)', 'FontSize', 14);
        
            % record stamp-----------------------------------
            % 出力スタンプを描画
            % x = xmin + (xmax - xmin) / 10.0;
            x = xmin;
            y = ymin - (ymax - ymin) / 2.0;
            lsp = (ymax - ymin) * 10.0 / 100.0;

            % 必要なオプションだけ指定（あとは [] に補完される）
            partialOpts = struct( ...
                'SoftwareVersion', app.SYSTEM_VARS.CURRENT_VERSION, ...
                'FileName', app.SYSTEM_VARS.INPUT_FILE, ...
                'LineSpace', lsp, ...
                'SectionFlag', app.SECTION_VARS.SEC_FLAG, ...
                'FunctionType', app.CALC_CONTROL.FUNC_SWITCH, ...
                'SectionStartX', app.SECTION_VARS.SEC_XS, ...
                'SectionStartY', app.SECTION_VARS.SEC_YS, ...
                'SectionFinishX', app.SECTION_VARS.SEC_XF, ...
                'SectionFinishY', app.SECTION_VARS.SEC_YF ...
            );
            % 全フィールドを埋めた構造体を生成
            opts = buildStampOptions(app, partialOpts);
            % record_stamp に構造体を渡す
            record_stamp(app, app.UIAxes2, x, y, opts);
            
        
            % ending treatment-----------------------------------
            % 処理の終了
            app.SECTION_VARS.SEC_FLAG = 0;

            % === To plot earthquakes on the section =======
            % 地震の断面へのプロット処理
            if ~isempty(app.OVERLAY_VARS.EQ_DATA) && app.EarthquakesCheckBox.Value == 1
                % plotting width to collect earthquakes
                % 地震を集める幅を設定
                if isempty(app.OVERLAY_VARS.EQPICK_WIDTH)
                    app.OVERLAY_VARS.EQPICK_WIDTH = 20.0;    % ( + - km)
                end
        
                %------ OVERLAY_VARS.EQ_DATA format (17 columns) ------------------
                % 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min, 
                % 10)strike1, 11)dip1, 12)rake1, 13)strike2, 14)dip2, 15)rake2, 
                % 16) x position, 17) y position
                %------------------------------------------------------------------
        
                % 断面に沿った地震をプロット
                [neq, ~] = size(app.OVERLAY_VARS.EQ_DATA);
                secpos = ones(neq, 7, 'double');
                secpos(:, 1) = app.SECTION_VARS.SEC_XS;
                secpos(:, 2) = app.SECTION_VARS.SEC_YS;
                secpos(:, 3) = app.SECTION_VARS.SEC_XF;
                secpos(:, 4) = app.SECTION_VARS.SEC_YF;
                secpos(:, 5) = 90.0;
                secpos(:, 6) = 0.0;
                secpos(:, 7) = 100.0;        
                [c1, c2, c3, ~] = coord_conversion(app, app.OVERLAY_VARS.EQ_DATA(:, 16), app.OVERLAY_VARS.EQ_DATA(:, 17), secpos(:, 1), secpos(:, 2), ...
                    secpos(:, 3), secpos(:, 4), secpos(:, 5), secpos(:, 6), secpos(:, 7));
                wcut = abs(c2) <= ones(neq, 1, 'double') .* app.OVERLAY_VARS.EQPICK_WIDTH;
        
                n = sum(rot90(sum(wcut)));
                c1a = [(c1 + c3) .* wcut app.OVERLAY_VARS.EQ_DATA(:, 7)];
                c1flip = flipud(sortrows(c1a));
                c1sort = c1flip(1:n, :);

                % plot to figures
                hold(app.UIAxes2, 'on');
                eq1 = scatter(app.UIAxes2, c1sort(:, 1), -c1sort(:, 2));
                eq1.MarkerSize = 5 * app.SYSTEM_VARS.PREF(5, 4);
                eq1.MarkerEdgeColor = app.SYSTEM_VARS.PREF(5, 1:3);

                disp(' ');
                disp(['  Width to collect earthquakes is now ' num2str(app.OVERLAY_VARS.EQPICK_WIDTH, '%5.1f') ' km.']); 
                disp('  To change the width, change the parameter ''EQPICK_WIDTH''(e.g., EQPICK_WIDTH=15.0)');
                showMessage(app, {['Width to collect earthquakes is now ' num2str(app.OVERLAY_VARS.EQPICK_WIDTH, '%5.1f') ' km.'], ...
                    'To change the width, change the parameter ''EQPICK_WIDTH''(e.g., EQPICK_WIDTH=15.0)'}, ...
                    false);
            end
        end


        function CSC_coulomb_section(app)
            % =====================================================================
            %                CSC_coulomb_section(app)
            % =====================================================================
            % この関数は、クーロン応力変化の計算結果を断面図に表示するためのものです。
            % 始点と終点の座標を編集フィールドから取得
            % おそらく変換は既にされているため必要ない
            app.SECTION_VARS.SEC_XS = app.CSC_StartX_EditField.Value;
            app.SECTION_VARS.SEC_YS = app.CSC_StartY_EditField.Value;
            app.SECTION_VARS.SEC_XF = app.CSC_FinishX_EditField.Value;
            app.SECTION_VARS.SEC_YF = app.CSC_FinishY_EditField.Value;

                
            % 断面の距離を計算し、セクションと深度の分割数を決定
            % ピタゴラスの定理を使用して、断面の距離を計算
            distance = sqrt((app.SECTION_VARS.SEC_XF - app.SECTION_VARS.SEC_XS) ^ 2 + (app.SECTION_VARS.SEC_YF - app.SECTION_VARS.SEC_YS) ^ 2);
            % セクションの分割数を距離と増分に基づいて決定します
            app.ASSIST_VARS.NSEC = int32(distance / app.SECTION_VARS.SEC_INCRE) + 1;
            % 深度方向の分割数も同様に決定します
            app.ASSIST_VARS.NDEPTH = int32(app.SECTION_VARS.SEC_DEPTH / app.SECTION_VARS.SEC_DEPTHINC) + 1;
                
            % x軸とy軸のデータを計算
            % セクションに沿ったx軸のグリッドを生成
            x = 1:1:app.ASSIST_VARS.NSEC;
            % 深度に沿ったy軸のグリッドを生成
            y = 1:1:abs(app.ASSIST_VARS.NDEPTH);
            % xtはxのコピー
            xt = x;
            % 距離と深度に基づいてグリッドを変換
            x = double(x - 1.0) * app.SECTION_VARS.SEC_INCRE;
            ycal = (-1.0) * double(y - 1.0) * app.SECTION_VARS.SEC_DEPTHINC;
            y = (-1.0) * double(y - 1.0) * app.SECTION_VARS.SEC_DOWNDIP_INC;
            
            % x軸およびy軸の最小・最大値を計算
            xmin = min(x);      % 断面x軸に沿った最小距離
            xmax = max(x);      % 断面x軸に沿った最大距離
            ymin = min(y);      % 深度軸に沿った最深位置（負）
            ymax = max(y);      % 深度軸に沿った最浅位置（通常は0）
        
            % 断面の深さを負の値に変換
            app.SECTION_VARS.SEC_DEPTH = (-1.0) * app.SECTION_VARS.SEC_DEPTH;
                
            % x y position in a mapped coordinate along the cross section
            % マップ座標系でのx, y位置を計算
            xx = app.SECTION_VARS.SEC_XS + (app.SECTION_VARS.SEC_XF - app.SECTION_VARS.SEC_XS) / double(app.ASSIST_VARS.NSEC - 1) * double(xt - 1);
            yy = app.SECTION_VARS.SEC_YS + (app.SECTION_VARS.SEC_YF - app.SECTION_VARS.SEC_YS) / double(app.ASSIST_VARS.NSEC - 1) * double(xt - 1);
        
            % 断面図のx, y軸のグリッドを計算
            app.ASSIST_VARS.AX = zeros(app.ASSIST_VARS.NDEPTH, length(xt));
            app.ASSIST_VARS.AY = zeros(app.ASSIST_VARS.NDEPTH, length(xt));
            size(app.ASSIST_VARS.AX);
            size(app.ASSIST_VARS.AY);
            
            % 断面ごとに深度に沿って座標を計算
            fc = zeros(4, 2);
            for m = 1:app.ASSIST_VARS.NDEPTH
                z = double(m) * app.SECTION_VARS.SEC_DEPTHINC;
                for k = 1:length(xt)
                    if k ~= length(xt)
                        % fault_corners関数を使用して断層のコーナーを計算
                        % fc = [xs, ys; xf, yf; xf_bottom, yf_bottom; xs_bottom, ys_bottom]
                        fc = fault_corners(app, xx(k), yy(k), xx(k + 1), yy(k + 1), app.SECTION_VARS.SEC_DIP, 0.0, z);
                        app.ASSIST_VARS.AX(m, k) = fc(4, 1);  % x座標
                        app.ASSIST_VARS.AY(m, k) = fc(4, 2);  % y座標
                    else
                        fc = fault_corners(app, xx(k - 1), yy(k - 1), xx(k), yy(k), app.SECTION_VARS.SEC_DIP, 0.0, z);
                        app.ASSIST_VARS.AX(m, k) = fc(3, 1);
                        app.ASSIST_VARS.AY(m, k) = fc(3, 2);     
                    end
                end
            end
            
            % 深度に沿ったz軸のデータを計算
            app.ASSIST_VARS.AZ = repmat(reshape(ycal, abs(app.ASSIST_VARS.NDEPTH), 1), 1, app.ASSIST_VARS.NSEC);
            size(app.ASSIST_VARS.AX);
            size(app.ASSIST_VARS.AY);
            size(app.ASSIST_VARS.AZ);
        
            % セクションフラグを設定
            app.SECTION_VARS.SEC_FLAG = 1;
        
            % Okadaによる半空間モデルを計算
            if app.ASSIST_VARS.IACTS ~= 1        
                Okada_halfspace(app);
            end

            % to keep okada output
            % Okadaの出力を保持
            app.ASSIST_VARS.IACTS = 1;

            % OKADA_OUTPUT.DC3DSから応力データを取得
            a = length(app.OKADA_OUTPUT.DC3DS);
            if a <= 14
                uialert(app.main_menu_window, 'Increase total grid number more than 14.', 'Warning');
            end

            ss = zeros(6, a);
            s9 = reshape(app.OKADA_OUTPUT.DC3DS(:, 9), 1, a);
            s10 = reshape(app.OKADA_OUTPUT.DC3DS(:, 10), 1, a);
            s11 = reshape(app.OKADA_OUTPUT.DC3DS(:, 11), 1, a);
            s12 = reshape(app.OKADA_OUTPUT.DC3DS(:, 12), 1, a);
            s13 = reshape(app.OKADA_OUTPUT.DC3DS(:, 13), 1, a);
            s14 = reshape(app.OKADA_OUTPUT.DC3DS(:, 14), 1, a);
            ss = [s9; s10; s11; s12; s13; s14];
        
            % クーロン応力、せん断応力、法線応力の計算
            if app.CALC_CONTROL.FUNC_SWITCH ~= 6 % 6: strain, Function>Strain
                app.ASSIST_VARS.SHEAR = zeros(a, 1);
                app.ASSIST_VARS.NORMAL = zeros(a, 1);
                if app.INPUT_VARS.FRIC == 0
                    beta = 0.5 * (atan(1.0 / 0.0000001));
                else
                    beta = 0.5 * (atan(1.0 / app.INPUT_VARS.FRIC));
                end
                        
                % find optimally oriented fault planes
                % 最適に配向された断層面を求める-------------------------------------   
                [nzinc, dum] = size(app.ASSIST_VARS.AZ);
                rss1 = zeros(a, 1);
                rss2 = zeros(a, 1);
                rss3 = zeros(a, 1);
                rss4 = zeros(a, 1);
                rss5 = zeros(a, 1);
                rss6 = zeros(a, 1); 
                top_az = (-1.0) * max(app.ASSIST_VARS.AZ(:, 1));
                incr_az = app.ASSIST_VARS.AZ(1, 1) - app.ASSIST_VARS.AZ(2, 1);

                for k = 1:nzinc
                    calcd = top_az + (k - 1) * incr_az;
                    [rs] = regional_stress(app, app.INPUT_VARS.R_STRESS, calcd);
                    a0 = (k - 1) * dum + 1;
                    a1 = k * dum;
                    rss1(a0:a1) = rss1(a0:a1) + rs(1, 1);
                    rss2(a0:a1) = rss2(a0:a1) + rs(2, 1);
                    rss3(a0:a1) = rss3(a0:a1) + rs(3, 1);
                    rss4(a0:a1) = rss4(a0:a1) + rs(4, 1);
                    rss5(a0:a1) = rss5(a0:a1) + rs(5, 1);
                    rss6(a0:a1) = rss6(a0:a1) + rs(6, 1);
                end
                        
                sgx  = zeros(a, 1) + rss1 + reshape(ss(1, 1:a), a, 1);
                sgy  = zeros(a, 1) + rss2 + reshape(ss(2, 1:a), a, 1);
                sgz  = zeros(a, 1) + rss3 + reshape(ss(3, 1:a), a, 1);
                sgyz = zeros(a, 1) + rss4 + reshape(ss(4, 1:a), a, 1);
                sgxz = zeros(a, 1) + rss5 + reshape(ss(5, 1:a), a, 1);
                sgxy = zeros(a, 1) + rss6 + reshape(ss(6, 1:a), a, 1);

                %===== Find sigma-1 and sigma-3 for plain stress condition =============
                % 平面応力条件下での最大・最小主応力の計算
                if app.CALC_CONTROL.STRESS_TYPE ~= 5
                    phi = zeros(a, 1) + 0.5 * atan((2.0 * sgxy) ./ (sgx - sgy)) + pi / 2.0;
                    ct = zeros(a, 1) + cos(phi);
                    st = zeros(a, 1) + cos(phi);
                    erad1 = sgx .* ct .* ct + sgy .* st + 2.0 .* sgx .* ct .* st;
                    ct = zeros(a, 1) + cos(phi + pi / 2.0);
                    st = zeros(a, 1) + cos(phi + pi / 2.0);
                    erad2 = sgx .* ct .* ct + sgy .* st + 2.0 .* sgx .* ct .* st;
                    nn = length(phi);
                    for k = 1:nn
                        if erad2(k) >= erad1(k)
                            phi(k) = pi/2.0 - phi(k);
                        end
                    end   
                end
                        
                %===== Find strike, dip, and rake for opt faults options =============
                % 最適な断層の走向、傾斜、滑り角を計算
                if app.CALC_CONTROL.STRESS_TYPE == 2
                    % for opt strike-slip fauts
                    % 走向すべり（横ずれ）断層の場合
                    strike  = zeros(a, 1) + rad2deg(phi) - rad2deg(beta);

                    % 修正必要？
                    app.ASSIST_VARS.strike2 = zeros(a,1) + rad2deg(phi) + rad2deg(beta);
                    % strike2 = zeros(a,1) + rad2deg(phi) + rad2deg(beta);

                    cg1 = strike < 0.0;
                    cg2 = strike >= 0.0;
                    strike = (180.0 + strike) .* cg1 + strike .* cg2;
                    strike = round(strike);
                    dip = 90.0;
                    rake = 180.0;

                elseif app.CALC_CONTROL.STRESS_TYPE == 3 
                    % for opt thrust fauts
                    % 逆断層の場合
                    strike = zeros(a,1) + rad2deg(phi) + 90.0;
                    if strike >= 360.0
                        strike = strike - 360.0;
                    end
                    dip = abs(rad2deg(beta));
                    rake = 90.0;

                elseif app.CALC_CONTROL.STRESS_TYPE == 4
                    % for opt normal fauts
                    % 正断層の場合    
                    strike = zeros(a, 1) + rad2deg(phi);
                    dip = abs(90.0-rad2deg(beta));
                    rake = -90.0;

                elseif app.CALC_CONTROL.STRESS_TYPE == 5
                    % specified fault
                    % 指定した断層の場合
                    strike = app.SW_StrikeEditField.Value;
                    dip = app.SW_DipEditField.Value;
                    rake = app.SW_RakeEditField.Value;

                else
                    % その他の場合
                    uialert(app.main_menu_window, 'Under construction. Sorry.', 'Warning!');
                    return;
                end
                        
                % to hand variables to the other functions
                % 計算結果を他の関数に渡すための変数に格納
                app.CALC_CONTROL.STRIKE = strike;
                app.CALC_CONTROL.DIP = dip;
                app.CALC_CONTROL.RAKE = rake;
        
                % クーロン応力、せん断応力、法線応力を計算
                c1 = zeros(a, 1) + double(app.CALC_CONTROL.STRIKE);
                c2 = zeros(a, 1) + app.CALC_CONTROL.DIP;
                c3 = zeros(a, 1) + app.CALC_CONTROL.RAKE;
                c4 = zeros(a, 1) + app.INPUT_VARS.FRIC;
                % クーロン応力の計算
                [app.ASSIST_VARS.SHEAR, app.ASSIST_VARS.NORMAL, coulomb] = calc_coulomb(app, c1, c2, c3, c4, ss);
                b = [app.OKADA_OUTPUT.DC3DS(:, 1) app.OKADA_OUTPUT.DC3DS(:, 2) -app.OKADA_OUTPUT.DC3DS(:, 5) coulomb app.ASSIST_VARS.SHEAR app.ASSIST_VARS.NORMAL];
                    
                cd (app.SYSTEM_VARS.HOME_DIR);
                format long;
                % データファイル 'dcff_section.cou' を読み込み上書き
                filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'dcff_section.cou');
                fid = fopen(filePath, 'w');
                % ヘッダーを書き込む
                fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
                fprintf(fid, 'x y z coulomb shear normal\n');
                fprintf(fid, '(km) (km) (km) (bar) (bar,right-lat.positive) (bar,unclamping.positive)\n');
                % 数値データの書き込み（行ごとに書く）
                % c が Nx6 の行列と仮定
                for i = 1:size(b, 1)
                    fprintf(fid, '%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\n', b(i, :));
                end
                fclose(fid);
                disp(['dcff_section.cou is saved in ' pwd]);
                showMessage(app, ['dcff_section.cou is saved in ' pwd], false);
                % 再読み込み部分
                fid = fopen('dcff_section.cou', 'r');
                coul = textscan(fid, '%f %f %f %f %f %f', 'Headerlines', 3);
                cd(app.SYSTEM_VARS.HOME_DIR);

            else % CALC_CONTROL.FUNC_SWITCHが6の場合、最適方向の応力を計算
                dil = reshape((s9 + s10 + s11), a, 1); % 最適方向の応力を計算
                b = [app.OKADA_OUTPUT.DC3DS(:, 1) app.OKADA_OUTPUT.DC3DS(:, 2) -app.OKADA_OUTPUT.DC3DS(:, 5) dil dil dil]; % 最後の2つはダミー
                
                cd (app.SYSTEM_VARS.HOME_DIR);
                format long;
                % データファイル 'dilatation_section.cou を読み込み上書き
                filePath = fullfile(app.SYSTEM_VARS.HOME_DIR, 'dilatation_section.cou');
                fid = fopen(filePath, 'w');
                % ヘッダーを書き込む
                fprintf(fid, 'Input file selected: %s\n', app.SYSTEM_VARS.INPUT_FILE);
                fprintf(fid, 'x y z dilatation(ex+ey+ez) dilatation dilatation\n');
                fprintf(fid, '(km) (km) (km) (-) (-) (-)\n');
                % 数値データの書き込み（行ごとに書く）
                % c が Nx6 の行列と仮定
                for i = 1:size(b, 1)
                    fprintf(fid, '%.6f\t%.6f\t%.6f\t%.6f\t%.6f\t%.6f\n', b(i, :));
                end
                fclose(fid);
                disp(['dilatation_section.cou is saved in ' pwd]);
                showMessage(app, ['dilatation_section.cou is saved in ' pwd], false);
                % 再読み込み部分
                fid = fopen('dilatation_section.cou', 'r');
                coul = textscan(fid, '%f %f %f %f %f %f', 'Headerlines', 3);
                cd(app.SYSTEM_VARS.HOME_DIR);
            end
            fclose (fid);
                
            % クーロン応力、せん断応力、法線応力の最小値・最大値・平均値を計算
            cl = coul{4};
            sigs = coul{5};
            sign = coul{6};
        
            % Coulomb stress matrix
            % クーロン応力行列を計算
            switch app.CALC_CONTROL.FUNC_SWITCH
                case 6          % 膨張応力のプロットの場合
                    cc = reshape(cl, abs(app.ASSIST_VARS.NDEPTH), app.ASSIST_VARS.NSEC);        
                case 7
                    cc = reshape(sigs, abs(app.ASSIST_VARS.NDEPTH), app.ASSIST_VARS.NSEC);
                case 8
                    cc = reshape(sign, abs(app.ASSIST_VARS.NDEPTH), app.ASSIST_VARS.NSEC);
                case 9
                    cc = reshape(cl, abs(app.ASSIST_VARS.NDEPTH), app.ASSIST_VARS.NSEC);
                otherwise
            end
                
            % complicated calculation for interpolation
            % インターポレーションによるグリッド補間
            if app.CALC_CONTROL.SHADE_TYPE == 2
                % how much fine grid we need
                % 補間のための細かいグリッド数
                n_interp = 10.0;
                nxg = app.ASSIST_VARS.NSEC;
                xgmin = xmin; 
                xgmax = xmax;
                nyg = app.ASSIST_VARS.NDEPTH;
                ygmin = ymin;
                ygmax = ymax;
                xnew_inc = (xgmax - xgmin) / (double(nxg) * n_interp);
                ynew_inc = (ygmax - ygmin) / (double(nyg) * n_interp);
                new_xgrid = xgmin:xnew_inc:xgmax;
                new_ygrid = rot90(ygmin:ynew_inc:ygmax);
                % new_xgrid_mtr = zeros(length(new_ygrid), length(new_xgrid)); % 初期化
                % new_ygrid_mtr = zeros(length(new_ygrid), length(new_xgrid)); % 初期化
                % cc_new = zeros(length(new_ygrid), length(new_xgrid));        % 初期化
                new_xgrid_mtr = repmat(new_xgrid, length(new_ygrid), 1);
                new_ygrid_mtr = repmat(new_ygrid, 1,length(new_xgrid));
                old_xgrid_mtr = repmat(x, app.ASSIST_VARS.NDEPTH, 1);
                old_ygrid_mtr = repmat(flipud(rot90(y)), 1, app.ASSIST_VARS.NSEC);
        
                % use interp2 function (interp2(x,y,z,xi,yi) using default 'linear'
                % interp2関数を使用して補間を実行
                cc_new = interp2(old_xgrid_mtr, old_ygrid_mtr, fliplr(cc), new_xgrid_mtr, new_ygrid_mtr);
                cc_new = rot90(cc_new, 2);
            end

            % セクションビューにカラーマップとプロットを設定
            set(app.UIAxes2, 'Colormap', app.GRAPHICS_VARS.ANATOLIA);
            hold(app.UIAxes2, 'on');
            
            if app.CALC_CONTROL.SHADE_TYPE == 2
                switch app.SYSTEM_VARS.PREF(7, 1)
                    case 1
                        % Jet カラーマップの設定
                        image(app.UIAxes2, cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1), new_xgrid(end)], ...
                            'YData', [new_ygrid(end), new_ygrid(1)]);
                        colormap(app.UIAxes2, jet);
                    case 2
                        % Anatolia カラーマップの設定
                        image(app.UIAxes2, cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1), new_xgrid(end)], ...
                            'YData', [new_ygrid(end), new_ygrid(1)]);
                        colormap(app.UIAxes2, app.GRAPHICS_VARS.ANATOLIA);
                    case 3
                        % Gray カラーマップの設定
                        image(app.UIAxes2, cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1), new_xgrid(end)], ...
                            'YData', [new_ygrid(end), new_ygrid(1)]);
                        colormap(app.UIAxes2, gray);
                    otherwise
                        % デフォルトはJetカラーマップ
                        image(app.UIAxes2, cc_new, 'CDataMapping', 'scaled', ...
                            'XData', [new_xgrid(1), new_xgrid(end)], ...
                            'YData', [new_ygrid(end), new_ygrid(1)]);
                        colormap(app.UIAxes2, jet);
                end
            else
                switch app.SYSTEM_VARS.PREF(7, 1)
                    case 1
                        image(app.UIAxes2, cc, 'CDataMapping', 'scaled', ...
                            'XData', [xmin, xmax], ...
                            'YData', [ymax, ymin]);
                        colormap(app.UIAxes2, jet);
                    case 2
                        image(app.UIAxes2, cc, 'CDataMapping', 'scaled', ...
                            'XData', [xmin, xmax], ...
                            'YData', [ymax, ymin]);
                        colormap(app.UIAxes2, app.GRAPHICS_VARS.ANATOLIA);
                    case 3
                        image(app.UIAxes2, cc, 'CDataMapping', 'scaled', ...
                            'XData', [xmin, xmax], ...
                            'YData', [ymax, ymin]);
                        colormap(app.UIAxes2, gray);
                    otherwise
                        image(app.UIAxes2, cc, 'CDataMapping', 'scaled', ...
                            'XData', [xmin, xmax], ...
                            'YData', [ymax, ymin]);
                        colormap(app.UIAxes2, jet);
                end
                set(app.UIAxes2, 'YDir', 'normal');
            end
                
            hold(app.UIAxes2, 'on');
            % draw fault line
            % 断層線の描画
            for n = 1:app.INPUT_VARS.NUM
                % if fault bottom is shallower than cross-section bottom
                % 断層底が断面の底より浅い場合の処理
                dinc = app.SECTION_VARS.SEC_DOWNDIP_INC / 5.0;
                ninc = (app.INPUT_VARS.ELEMENT(n, 9) - app.INPUT_VARS.ELEMENT(n, 8)) / dinc;
                
                % 断層の上端と下端を見つけてラインを引く
                fct = fault_corners(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.SECTION_VARS.SEC_DIP, 0.0, app.INPUT_VARS.ELEMENT(n, 8));
                [x1, y1, ~, ~] = CSC_fault_int_sec(app, fct(4, 1), fct(4, 2), fct(3, 1), fct(3, 2), app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), app.INPUT_VARS.ELEMENT(n, 3), ...
                    app.INPUT_VARS.ELEMENT(n, 4), app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));
                y1 = y1 / sin(deg2rad(app.SECTION_VARS.SEC_DIP));
                
                fcb = fault_corners(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.SECTION_VARS.SEC_DIP, 0.0, app.INPUT_VARS.ELEMENT(n, 9));
                [~, ~, x2, y2] = CSC_fault_int_sec(app, fcb(4, 1), fcb(4, 2), fcb(3, 1), fcb(3, 2), app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), app.INPUT_VARS.ELEMENT(n, 3), ...
                    app.INPUT_VARS.ELEMENT(n, 4), app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));
                y2 = y2 / sin(deg2rad(app.SECTION_VARS.SEC_DIP));
            
                % find the other edge point
                % 他のエッジポイントを見つける処理  
                if isempty(x1) == 1 && ~isempty(x2)
                    for k = 1:ninc
                        fct = fault_corners(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.SECTION_VARS.SEC_DIP, 0.0, app.INPUT_VARS.ELEMENT(n, 8) + (k-1) * dinc);
                        [x1, y1, ~, ~] = CSC_fault_int_sec(app, fct(4, 1), fct(4, 2), fct(3, 1), fct(3, 2), app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), app.INPUT_VARS.ELEMENT(n, 3), ...
                            app.INPUT_VARS.ELEMENT(n, 4), app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));
                        y1 = y1 / sin(deg2rad(app.SECTION_VARS.SEC_DIP));
                        if ~isempty(x1)
                            break;
                        end
                    end
                end
                        
                if isempty(x2) == 1 && ~isempty(x1)
                    for k = 1:ninc
                        fcb = fault_corners(app, app.SECTION_VARS.SEC_XS, app.SECTION_VARS.SEC_YS, app.SECTION_VARS.SEC_XF, app.SECTION_VARS.SEC_YF, app.SECTION_VARS.SEC_DIP, 0.0, app.INPUT_VARS.ELEMENT(n,9)-(k-1)*dinc);
                        [~, ~, x2, y2] = CSC_fault_int_sec(app, fcb(4, 1), fcb(4, 2), fcb(3, 1), fcb(3, 2), app.INPUT_VARS.ELEMENT(n, 1), app.INPUT_VARS.ELEMENT(n, 2), app.INPUT_VARS.ELEMENT(n, 3), ...
                            app.INPUT_VARS.ELEMENT(n, 4), app.INPUT_VARS.ELEMENT(n, 7), app.INPUT_VARS.ELEMENT(n, 8), app.INPUT_VARS.ELEMENT(n, 9));
                        y2 = y2 / sin(deg2rad(app.SECTION_VARS.SEC_DIP));
                        if ~isempty(x2)
                            break;
                        end
                    end
                end
        
                % 修正必要
                % ラインを描画
                fpt0 = plot(app.UIAxes2, [x1 x2], [y1 y2]);
                fpt0.LineWidth = app.SYSTEM_VARS.PREF(1, 4);
                fpt0.Color = app.SYSTEM_VARS.PREF(1, 1:3);
            end
                
            hold(app.UIAxes2, 'on');
            % mapview line on cross section
            %--------------------------断面上にマップビューラインを描画--------------------------
            z = -app.INPUT_VARS.CALC_DEPTH / sin(deg2rad(app.SECTION_VARS.SEC_DIP));
            fpt = plot(app.UIAxes2, [xmin xmax], [z z]);
            fpt.LineWidth = 0.2;
            fpt.LineStyle = '--';
            fpt.Color = 'b';
        
            % draw labels
            %--------------------------ラベルを描画--------------------------
            text(app.UIAxes2, xmin + app.SECTION_VARS.SEC_INCRE, ymax - app.SECTION_VARS.SEC_DEPTHINC, 'A', 'Color', 'k', 'FontSize', 14, 'FontWeight', 'bold');
            text(app.UIAxes2, xmax - app.SECTION_VARS.SEC_INCRE * 2.0, ymax - app.SECTION_VARS.SEC_DEPTHINC, 'B', 'Color', 'k', 'FontSize', 14, 'FontWeight', 'bold');
        
            % タイトル設定
            if app.CALC_CONTROL.FUNC_SWITCH == 6
                title(app.UIAxes2, 'Dilatation (exx+eyy+ezz)', 'FontSize', 18);    
            elseif app.CALC_CONTROL.FUNC_SWITCH == 7
                title(app.UIAxes2, 'Shear stress change (bar)', 'FontSize', 18);
            elseif app.CALC_CONTROL.FUNC_SWITCH == 8
                title(app.UIAxes2, 'Umclamping stress change (bar)', 'FontSize', 18);
            else
                title(app.UIAxes2, 'Coulomb stress change (bar)', 'FontSize', 18);
            end
        
            % カラースケールの設定
            if app.CALC_CONTROL.FUNC_SWITCH == 6
                % strain function
                % ひずみ関数の場合
                app.GRAPHICS_VARS.C_SAT = abs(power(10, app.ASSIST_VARS.COLORSN));
            else
                app.GRAPHICS_VARS.C_SAT = abs(app.ASSIST_VARS.COLORSN);
            end
                
            clim(app.UIAxes2, [-abs(app.GRAPHICS_VARS.C_SAT) abs(app.GRAPHICS_VARS.C_SAT)]);
            colorbar(app.UIAxes2, 'EastOutside');
            xlabel(app.UIAxes2, 'Distance (km)', 'FontSize', 14);
            ylabel(app.UIAxes2, 'Down-dip distance (km)', 'FontSize', 14);
        
            % draw cross section grid
            %=================== クロスセクショングリッドを描画 ===================
            gridline = plot(app.UIAxes2, [xmin, xmax, xmax, xmin, xmin], [ymin, ymin, ymax, ymax, ymin]);
            gridline.Color = 'k';
            gridline.LineWidth = 1.0;
            set(app.UIAxes2, 'DataAspectRatio', [1, 1, 1], 'Color', [0.9, 0.9, 0.9], ...
                'PlotBoxAspectRatio', [1, 1, 1], 'XLim', [xmin, xmax], 'YLim', [ymin, ymax], ...
                'TickDir', 'out');

            % To plot earthquakes on the section
            %=================== 地震データを断面にプロット ===================
            if ~isempty(app.OVERLAY_VARS.AFAULT_DATA) && app.EarthquakesCheckBox.Value == 1
                % plotting width to collect earthquakes
                % プロット幅の設定
                if isempty(app.OVERLAY_VARS.EQPICK_WIDTH)
                    app.OVERLAY_VARS.EQPICK_WIDTH = 10.0; % (+- km)
                end
            
                %------ 地震データの形式 OVERLAY_VARS.EQ_DATA format (17 columns) ---------------
                % 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min,
                % 10)strike1, 11)dip1, 12)rake1, 13)strike2, 14)dip2, 15)rake2, 16)x position, 17)y position
                %------------------------------------------------------------------
            
                neq    = size(app.OVERLAY_VARS.EQ_DATA, 1);
                secpos = ones(neq, 7, 'double');
                secpos(:,1) = app.SECTION_VARS.SEC_XS;
                secpos(:,2) = app.SECTION_VARS.SEC_YS;
                secpos(:,3) = app.SECTION_VARS.SEC_XF;
                secpos(:,4) = app.SECTION_VARS.SEC_YF;
                secpos(:,5) = 0.0;   % top km
                secpos(:,6) = 200.0; % bottom km
                secpos(:,7) = 90.0;  % dip (90 degree, vertical)
            
                [c1, c2, c3, ~] = coord_conversion(app, app.OVERLAY_VARS.EQ_DATA(:, 16), app.OVERLAY_VARS.EQ_DATA(:, 17), ...
                    secpos(:, 1), secpos(:, 2), secpos(:, 3), secpos(:, 4), secpos(:, 5), secpos(:, 6), secpos(:, 7));
            
                wcut = abs(c2) <= ones(neq, 1, 'double') .* (app.OVERLAY_VARS.EQPICK_WIDTH / 2.0);
                n = sum(rot90(sum(wcut)));
                c1a = [(c1 + c3) .* wcut app.OVERLAY_VARS.EQ_DATA(:, 7)];
                c1flip = flipud(sortrows(c1a));
                c1sort = c1flip(1:n, :);
            
                % プロット
                hold(app.UIAxes2, 'on');
                eq1 = scatter(app.UIAxes2, c1sort(:, 1), -c1sort(:, 2));
                eq1.MarkerSize = 5 * app.SYSTEM_VARS.PREF(5, 4);
                eq1.MarkerEdgeColor = app.SYSTEM_VARS.PREF(5, 1:3);

                % 表示メッセージ
                % 修正必要？
                msg = sprintf( ...
                    'Width to collect earthquakes is now %5.1f km.', ...
                    'To change the width, change the parameter ''OVERLAY_VARS.EQPICK_WIDTH', ...
                    '(e.g., OVERLAY_VARS.EQPICK_WIDTH = 15.0)', ...
                    'Note that this earthquake plot function works only when the section is vertical.', ...
                    app.OVERLAY_VARS.EQPICK_WIDTH);
                uialert(app.main_menu_window, msg, 'Information', 'Icon', 'info');
            end

            % data & file name stamp
            %----------- データとファイル名のスタンプ --------------
            hold(app.UIAxes2, 'on');
            % スタンプの位置を指定
            % x = xmax + (xmax - xmin) / 10.0;
            x = xmin;
            y = ymin - (ymax - ymin) / 2.0;
            lsp = (ymax - ymin) * 10.0 / 100.0;
        
            try
                dm1 = app.CALC_CONTROL.STRIKE(1);
                dm2 = app.CALC_CONTROL.DIP(1);
                dm3 = app.CALC_CONTROL.RAKE(1);
            catch
                dm1 = 50.;
                dm2 = 90.;
                dm3 = 180.;
            end
        
            % to see the paramaters, type 'help record_stamp' in command window.
            % パラメータを見るには、コマンドウィンドウで "help record_stamp" と入力してください。
            % 修正必要↑
            % 必要なオプションだけ指定（あとは [] に補完される）
            partialOpts = struct( ...
                'SoftwareVersion', app.SYSTEM_VARS.CURRENT_VERSION, ...
                'FileName', app.SYSTEM_VARS.INPUT_FILE, ...
                'LineSpace', lsp, ...
                'SectionFlag', app.SECTION_VARS.SEC_FLAG, ...
                'FunctionType', app.CALC_CONTROL.FUNC_SWITCH, ...
                'StressType', app.CALC_CONTROL.STRESS_TYPE, ...
                'Depth', app.INPUT_VARS.CALC_DEPTH, ...
                'Friction', app.INPUT_VARS.FRIC, ...
                'Strike', dm1, ...
                'Dip', dm2, ...
                'Rake', dm3, ...
                'SectionStartX', app.SECTION_VARS.SEC_XS, ...
                'SectionStartY', app.SECTION_VARS.SEC_YS, ...
                'SectionFinishX', app.SECTION_VARS.SEC_XF, ...
                'SectionFinishY', app.SECTION_VARS.SEC_YF, ...
                'SectionDip', app.SECTION_VARS.SEC_DIP ...
            );
            % 全フィールドを埋めた構造体を生成
            opts = buildStampOptions(app, partialOpts);
            % record_stamp に構造体を渡す
            record_stamp(app, app.UIAxes2, x, y, opts);
            
            % flag reset
            %---------- フラグのリセット
            app.SECTION_VARS.SEC_FLAG = 0;
        end

        
        function [uu] = CSC_disp_sect_conv(~, x0, y0, x1, y1, uux, uuy)
            % =====================================================================
            %                CSC_disp_sect_conv(~, x0, y0, x1, y1, uux, uuy)
            % =====================================================================
            % 与えられた水平単位変位 (uux, uuy) を特定の断面平面上に投影し、その結果を uu として返します。
            % **** 注意 **** すべてのパラメータはスカラーである必要があります。
            % 入力パラメータ:
            %   x0, y0 : 始点の座標
            %   x1, y1 : 終点の座標
            %   uux, uuy : 水平単位変位
            
            % y1がy0と同じ場合に対処（ゼロ除算を避けるためにy1をわずかに調整）
            if y1 == y0
                y1 = y1 + 0.0001;
            end
            
            % 断面のストライク角を計算
            strk = rad2deg(atan((x1 - x0) / (y1 - y0)));
            % 水平変位のストライク角を計算
            u_strk = rad2deg(atan(uux / uuy));
            % 水平変位の大きさを計算
            uxy = sqrt(uux * uux + uuy * uuy);

            % 始点と終点の位置関係に応じて角度を計算
            if x1 > x0
                if y1 > y0 % X+ & Y+ の場合
                    if uux > 0
                        if uuy > 0
                            ang = abs(u_strk - strk); % 変位角と断面角の差の絶対値を計算
                        else
                            ang = abs(u_strk+180. - strk);
                        end
                    else
                        if uuy > 0
                            ang = abs(u_strk+180. - strk);
                        else
                            ang = abs(u_strk - strk);
                        end
                    end        
                else % X+ & Y- の場合
                    if uux < 0
                        if uuy > 0
                            ang = abs(u_strk - strk);
                        else
                            ang = abs(u_strk+180. - strk);
                        end
                    else
                        if uuy > 0
                            ang = abs(u_strk+180. - strk);
                        else
                            ang = abs(u_strk - strk);
                        end
                    end  
                end
            else
                if y1 > y0 % X- & Y+ の場合
                   if uux > 0
                        if uuy > 0
                            ang = abs(u_strk - strk);
                        else
                            ang = abs(u_strk+180. - strk);
                        end
                    else
                        if uuy > 0
                            ang = abs(u_strk+180. - strk);
                        else
                            ang = abs(u_strk - strk);
                        end
                    end     
                else % X- & Y- の場合
                    if uux > 0
                        if uuy > 0
                            ang = abs(u_strk+180. - strk);
                        else
                            ang = abs(u_strk - strk);
                        end
                    else
                        if uuy > 0
                            ang = abs(u_strk - strk);
                        else
                            ang = abs(u_strk+180. - strk);
                        end
                    end  
                    
                end
            end
            
            % 断面平面上に投影された変位の大きさを計算
            uu = cos(deg2rad(ang)) * uxy;
            
            % 水平単位変位のx成分が負の場合、符号を反転
            if uux < 0.0
                uu = -uu;
            end
        end


        function CSC_check_sec_input(app)
            % =====================================================================
            %                CSC_check_sec_input(app)
            % =====================================================================
            % この関数は、断面入力パラメータが正しいかどうかをチェックします。不適切な入力に対して警告を表示します。

            % UTM座標系を使用していない場合は処理を実行します。
            if app.COORD_VARS.ICOORD ~= 2
                % 断面の開始点と終了点、その他のパラメータを取得します
                % 始点のX座標を取得
                sec_xs = app.CSC_StartX_EditField.Value;
                % 始点のY座標を取得
                sec_ys = app.CSC_StartY_EditField.Value;
                % 終点のX座標を取得
                sec_xf = app.CSC_FinishX_EditField.Value;
                % 終点のY座標を取得
                sec_yf = app.CSC_FinishY_EditField.Value;
                % 断面の増分を取得
                sec_incre = app.CSC_HI_EditField.Value;
                % 断面の深さを取得
                sec_depth = app.CSC_DepthEditField.Value;
                % 断面の深さの増分を取得
                sec_depthinc = app.CSC_DepthIncEditField.Value;
        
                % 開始点と終了点の距離を計算します。
                distance = sqrt((sec_xf - sec_xs) ^ 2 + (sec_yf - sec_ys) ^ 2);
                % 深さと距離の増分許容範囲を計算します。
                depth_allowance = sec_depth / sec_depthinc;
                distance_allowance = distance / sec_incre;
                % デフォルトの許容値を設定します。
                allowance = 3;
                
                % 不適切な入力値に対する警告を表示します。
                % Validate input values and display warnings for invalid conditions

                % 深さが0以下の場合の警告
                % Warning for non-positive depth
                if sec_depth <= 0.0
                    uialert(app.main_menu_window, 'Depth should be positive.', 'Warning!');
                end
                % 増分が0以下の場合の警告
                % Warning for non-positive increment
                if sec_incre <= 0.0
                    uialert(app.main_menu_window, 'Increment should be positive.', 'Warning!');
                end

                % 深さの増分が0以下の場合の警告
                % Warning for non-positive depth increment
                if sec_depthinc <= 0.0
                    uialert(app.main_menu_window, 'Depth increment should be positive.', 'Warning!');
                end

                % 深さの増分が深さより大きい場合の警告
                % Warning if depth increment is larger than depth
                if depth_allowance < allowance
                    uialert(app.main_menu_window, 'Depth increment should be small enough compared to the depth.', 'Warning!');
                end
                
                % 距離の増分が距離より大きい場合の警告
                % Warning if distance increment is larger than the section length
                if distance_allowance < allowance
                    uialert(app.main_menu_window, 'Distance increment should be small enough compared to the section.', 'Warning!');
                end

                % 開始点と終了点が研究領域外の場合の警告
                % Warning if the starting or ending point is outside the study area
                if sec_xs < app.INPUT_VARS.GRID(1,1) || sec_xf > app.INPUT_VARS.GRID(3,1)
                    uialert(app.main_menu_window, 'Selected point should be within the area.', 'Warning!');
                end
                if sec_ys < app.INPUT_VARS.GRID(2,1) || sec_yf > app.INPUT_VARS.GRID(4,1)
                    uialert(app.main_menu_window, 'Selected point should be within the area.', 'Warning');
                end
            end
        end


        function [lp_top, dp_top, lp_bottom, dp_bottom] = CSC_fault_int_sec(app, x0, y0, x1, y1, fx0, fy0, fx1, fy1, dip, top, bottom)
            % =====================================================================
            %                CSC_fault_int_sec(~, x0, y0, x1, y1, fx0, fy0, fx1, fy1, dip, top, bottom)
            % =====================================================================
            % This function seeks cross sectio position of the fault intersection to a give cross section
            % OUTPUT: lp (distance along the horizontal cross section axis)
            %         dp (distance along the vertical cross section axis)
            %         lp_top,dp_top (for top position)
            %         lp_bottom,dp_bottom (for bottom position)
            % INPUT:  x0,y0 (cross section starting point)
            %         x1,y1 (cross section ending point)
            %         fx0,fy0 (fault starting point)
            %         fx1,fy1 (fault ending point)
            %         dip, udepth, ldepth (fault dip, upper depth, and lower depth)
            
            sm = 0.00001; % to prevent dividing by zero
            if fx0 == fx1
                fx1 = fx1 + sm;
            end
            
            angle = atan((fy1 - fy0) / (fx1 - fx0));
            dipr = deg2rad(dip);
            shift = (bottom - top) / tan(dipr);
            x_shift = shift * sin(angle);
            y_shift = shift * cos(angle);
            
            if (fx1-fx0) >= 0.0
                xs_bottom = fx0 + x_shift;
                ys_bottom = fy0 - y_shift;
                xf_bottom = fx1 + x_shift;
                yf_bottom = fy1 - y_shift;
            else
                xs_bottom = fx0 - x_shift;
                ys_bottom = fy0 + y_shift;
                xf_bottom = fx1 - x_shift;
                yf_bottom = fy1 + y_shift;
            end
            
            % TOP DEPTH X Y---------------------------------
            a0 = (fy1 - fy0) / (fx1 - fx0);
            if (fx1 - fx0) == 0.0
                a0 = (fy1 - fy0) / sm;
            end
            b0 = fy0 - a0 * fx0;
            a_ftop = a0;
            b_ftop = b0;
            a1 = (y1 - y0) / (x1 - x0);
            if (x1 - x0) == 0.0
                a1 = (y1 - y0) / sm;
            end
            b1 = y0 - a1 * x0;
            aa = a1 / a0;
            ytop = (b1 - aa * b0) / (1 - aa);   % crossing point y
            xtop = (ytop - b0) / a0;            % crossing point x
            
            % BOTTOM DEPTH X Y---------------------------------
            a0 = (yf_bottom - ys_bottom) / (xf_bottom - xs_bottom);
            if (xf_bottom - xs_bottom) == 0.0
                a0 = (yf_bottom - ys_bottom) / sm;
            end
            b0 = ys_bottom - a0 * xs_bottom;
            a_fbottom = a0;
            b_fbottom = b0;
            a1 = (y1 - y0) / (x1 - x0);
            if (y1 - y0) == 0.0
                a1 = (x1 - x0) / sm;
            end
            b1 = y0 - a1 * x0;
            aa = a1 / a0;
            ybottom = (b1 - aa * b0) / (1 - aa); % crossing point y
            xbottom = (ybottom - b0) / a0;       % crossing point x
            
            % Downdip crossing---------------------------------
            a0 = (fy1 - yf_bottom) / (fx1 - xf_bottom);
            if (fx1 - xf_bottom) == 0.0
                a0 = (fy1 - yf_bottom) / sm;
            end
            b0 = yf_bottom - a0 * xf_bottom;
            a_side1 = a0;
            b_side1 = b0;
            a1 = (y1 - y0) / (x1 - x0);
            if (x1 - x0) == 0.0
                a1 = (y1 - y0) / sm;
            end
            b1 = y0 - a1 * x0;
            aa = a1 / a0;
            yd1 = (b1 - aa * b0) / (1 - aa); % crossing point y
            xd1 = (yd1 - b0) / a0;           % crossing point x
            
            % Downdip crossing (the other side)---------------------------------
            a0 = (fy0 - ys_bottom) / (fx0 - xs_bottom);
            if (fx0 - xs_bottom) == 0.0
                a0 = (fy0 - ys_bottom) / sm;
            end
            b0 = ys_bottom - a0 * xs_bottom;
            a_side2 = a0;
            b_side2 = b0;
            a1 = (y1 - y0) / (x1 - x0);
            if (x1 - x0) == 0.0
                a1 = (y1 - y0) / sm;
            end
            b1 = y0 - a1 * x0;
            aa = a1 / a0;
            yd2 = (b1 - aa * b0) / (1 - aa); % crossing point y
            xd2 = (yd2 - b0) / a0;           % crossing point x
            
            % SCREENING to fit the section--------------------------------
            % appearent slope
            lp_top    = sqrt((xtop - x0) ^ 2 + (ytop - y0) ^ 2);
            lp_bottom = sqrt((xbottom - x0) ^ 2 + (ybottom - y0) ^ 2);
            
            if x0 > xbottom && x0 < xtop
                slope = atan((bottom-top)/(lp_bottom+lp_top)); % apparent dip along the section
            elseif x0 > xtop && x0 < xbottom
                slope = atan((bottom-top)/(lp_bottom+lp_top)); % apparent dip along the section  
            else
                slope = atan((bottom-top)/(lp_bottom-lp_top)); % apparent dip along the section
            end
            % rad2deg(slope)
            slope     = abs(slope);
            dp_top    = -top;
            dp_bottom = -bottom;
            
            %---------- check (if four crossing points are in section line)
            [itop, dtop]       = check_in_sec(x0, y0, x1, y1, xtop, ytop);
            [ibottom, dbottom] = check_in_sec(x0, y0, x1, y1, xbottom, ybottom);
            [i1, d1]           = check_in_sec(x0, y0, x1, y1, xd1, yd1);
            [i2, d2]           = check_in_sec(x0, y0, x1, y1, xd2, yd2);
            %---------- check (if four crossing points are in fault lines)
            [it, ~]   = check_in_sec(fx0, fy0, fx1, fy1, xtop, ytop);
            [ib, ~]   = check_in_sec(xs_bottom, ys_bottom, xf_bottom, yf_bottom, xbottom, ybottom);
            [i1f, ~]  = check_in_sec(fx1, fy1, xf_bottom, yf_bottom, xd1, yd1);
            [i2f, ~]  = check_in_sec(fx0, fy0, xs_bottom, ys_bottom, xd2, yd2);
            %---------- select two proper points
            itop    = itop    * it;
            ibottom = ibottom * ib;
            i1      = i1      * i1f;
            i2      = i2      * i2f;
            
            %---------- check any cross section end point is within a area of map
            %           projection of the fault
            y0_ftop    = a_ftop    * x0 + b_ftop;
            y0_fbottom = a_fbottom * x0 + b_fbottom;
            y0_side1   = a_side1   * x0 + b_side1;
            y0_side2   = a_side2   * x0 + b_side2;
            
            y1_ftop    = a_ftop    * x1 + b_ftop;
            y1_fbottom = a_fbottom * x1 + b_fbottom;
            y1_side1   = a_side1   * x1 + b_side1;
            y1_side2   = a_side2   * x1 + b_side2;
            
            sec_start_on_flt  = 0;
            sec_finish_on_flt = 0;

            if fx1 >= fx0
                if fy1 >= fy0
                    if y0 < y0_ftop && y0 > y0_fbottom && y0 < y0_side1 && y0 > y0_side2
                        sec_start_on_flt   = 1;
                    end
                    if y1 < y1_ftop && y1 > y1_fbottom && y1 < y1_side1 && y1 > y1_side2
                        sec_finish_on_flt  = 1;
                    end
                else
                    if y0 < y0_ftop && y0 > y0_fbottom && y0 < y0_side2 && y0 > y0_side1
                        sec_start_on_flt   = 1;
                    end
                    if y1 < y1_ftop && y1 > y1_fbottom && y1 < y1_side2 && y1 > y1_side1
                        sec_finish_on_flt  = 1;
                    end
                end
            else
	            if fy1 >= fy0
                    if y0 > y0_ftop && y0 < y0_fbottom && y0 < y0_side1 && y0 > y0_side2
                        sec_start_on_flt   = 1;
                    end
                    if y1 > y1_ftop && y1 < y1_fbottom && y1 < y1_side1 && y1 > y1_side2
                        sec_finish_on_flt  = 1;
                    end
                else
                    if y0 > y0_ftop && y0 < y0_fbottom && y0 < y0_side2 && y0 > y0_side1
                        sec_start_on_flt   = 1;
                    end
                    if y1 > y1_ftop && y1 < y1_fbottom && y1 < y1_side2 && y1 > y1_side1
                        sec_finish_on_flt  = 1;
                    end
                end  
            end
            
            iflag = [itop ibottom i1 i2];
            if sum(iflag) <= 1
                if sec_start_on_flt == 1 && sec_finish_on_flt == 1
                    dist_top0 = sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.);
                    dist_top1 = sqrt((xtop - x1) ^ 2. + (ytop - y1) ^ 2.);
                    if dist_top0 <= dist_top1
                        lp_top    = 0;
                        lp_bottom = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^ 2.);
                        dp_top    = dp_top - sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.) * tan(slope);
                        dp_bottom = dp_top - lp_bottom * tan(slope);
                    else
                        lp_top    = 0;
                        lp_bottom = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^2.);
                        dp_top    = dp_top - sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.) * tan(slope);
                        dp_bottom = dp_top + lp_bottom * tan(slope);
                    end
                elseif sec_start_on_flt == 1 && sec_finish_on_flt == 0
                    if     itop == 1
                        lp_bottom = 0.0;
                        lp_top    = sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.);
                        dp_bottom = dp_top - lp_top * tan(slope);
                    elseif ibottom == 1
                        lp_top    = 0.0;
                        lp_bottom = sqrt((xbottom - x0) ^ 2. + (ybottom - y0) ^ 2.);
            
                        dp_top    = dp_bottom + lp_bottom * tan(slope); 
                    elseif i1 == 1 % CHECK
                        lp_temp1  = sqrt((xtop - xd1) ^ 2. + (ytop - yd1) ^ 2.);
                        lp_temp2  = sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.);
                        dp_temp1  = dp_top - lp_temp1 * tan(slope);
                        dp_temp2  = dp_top - lp_temp2 * tan(slope); 
                        if lp_temp1 <= lp_temp2
                            lp_top    = 0.0;
                            lp_bottom = sqrt((xd1 - x0) ^ 2. + (yd1 - y0) ^ 2.);
                            dp_top    = dp_temp2;
                            dp_bottom = dp_temp1;
                        else
                            lp_top    = sqrt((xd1 - x0) ^ 2. + (yd1 - y0) ^ 2.);
                            lp_bottom = 0.0;
                            dp_top    = dp_temp1;
                            dp_bottom = dp_temp2;
                        end
                    elseif i2 == 1
                        lp_temp1  = sqrt((xtop - xd2) ^ 2. + (ytop - yd2) ^ 2.);
                        lp_temp2  = sqrt((xtop - x0) ^ 2. + (ytop - y0) ^ 2.);
                        dp_temp1  = dp_top - lp_temp1 * tan(slope);
                        dp_temp2  = dp_top - lp_temp2 * tan(slope); 
                        if lp_temp1 <= lp_temp2
                            lp_top    = sqrt((xd2 - x0) ^ 2. + (yd2 - y0) ^ 2.);
                            lp_bottom = 0.0;
                            dp_top    = dp_temp1;
                            dp_bottom = dp_temp2;
                        else
                            lp_top    = 0.0;
                            lp_bottom = sqrt((xd2 - x0) ^ 2. + (yd2 - y0) ^ 2.);
                            dp_top    = dp_temp2;
                            dp_bottom = dp_temp1;
                        end
                    end
                elseif sec_start_on_flt == 0 && sec_finish_on_flt == 1
                    if itop == 1
                        lp_bottom = sqrt((x0 - x1) ^ 2. + (y0 - y1) ^ 2.);
                        lp_top    = sqrt((x0 - xtop) ^ 2. + (y0 - ytop) ^ 2.);
                        dp_bottom = dp_top - sqrt((x1 - xtop) ^ 2. + (y1 - ytop) ^ 2.) * tan(slope);
                    elseif ibottom == 1
                        lp_bottom = sqrt((xbottom - x0) ^ 2. + (ybottom - y0) ^ 2.);
                        lp_top    = sqrt((x0 - x1) ^ 2. + (y0 - y1) ^ 2.);
                        dp_top    = dp_bottom + sqrt((x1 - xbottom) ^ 2. + (y1 - ybottom) ^ 2.) * tan(slope); 
                    elseif i1 == 1
                        lp_temp1  = sqrt((xtop - xd1) ^ 2. + (ytop - yd1) ^ 2.);
                        lp_temp2  = sqrt((xtop - x1) ^ 2. + (ytop - y1) ^ 2.);
                        dp_temp1  = dp_top - lp_temp1 * tan(slope);
                        dp_temp2  = dp_top - lp_temp2 * tan(slope); 
                        if lp_temp1 <= lp_temp2
                            lp_top    = sqrt((xd1 - x0) ^ 2. + (yd1 - y0) ^ 2.);
                            lp_bottom = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^ 2.);
                            dp_top    = dp_temp1;
                            dp_bottom = dp_temp2;
                        else
                            lp_top    = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^ 2.);
                            lp_bottom = sqrt((xd1 - x0) ^ 2. + (yd1 - y0) ^ 2.);
                            dp_top    = dp_temp2;
                            dp_bottom = dp_temp1;
                        end
                    elseif i2 == 1
                        lp_temp1  = sqrt((xtop - xd2) ^ 2. + (ytop - yd2) ^ 2.);
                        lp_temp2  = sqrt((xtop - x1) ^ 2. + (ytop - y1) ^ 2.);
                        dp_temp1  = dp_top - lp_temp1 * tan(slope);
                        dp_temp2  = dp_top - lp_temp2 * tan(slope); 
                        if lp_temp1 <= lp_temp2
                            lp_top    = sqrt((xd2 - x0) ^ 2. + (yd2 - y0) ^ 2.);
                            lp_bottom = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^ 2.);
                            dp_top    = dp_temp1;
                            dp_bottom = dp_temp2;
                        else
                            lp_top    = sqrt((x1 - x0) ^ 2. + (y1 - y0) ^ 2.);
                            lp_bottom = sqrt((xd2 - x0) ^ 2. + (yd2 - y0) ^ 2.);
                            dp_top    = dp_temp2;
                            dp_bottom = dp_temp1;
                        end 
                    end       
                else
                    dp_top    = [];
                    lp_top    = [];
                    dp_bottom = [];
                    lp_bottom = [];
                end
            
            elseif sum(iflag) >= 3
                disp('something wrong. see fault_init_sec.m');
                showMessage(app, 'something wrong. see fault_init_sec.m', false);
            else % sum(iflag) = 2
                if itop == 1 && ibottom == 1 % sec line crosses the fault top & bottom
            
                elseif itop == 1 && i1 == 1
                    dp_bottom = dp_top - abs(d1 - dtop) * tan(slope);
                    lp_bottom = d1;        
                elseif itop == 1 && i2 == 1
                    dp_bottom = dp_top - abs(d2 - dtop) * tan(slope);
                    lp_bottom = d2;  
                elseif ibottom == 1 && i1 == 1
                    dp_top = dp_bottom + abs(d1 - dbottom) * tan(slope);        
                    lp_top = d1;
                elseif ibottom == 1 && i2 == 1
                    dp_top = dp_bottom + abs(d2 - dbottom) * tan(slope);
                    lp_top = d2;
                elseif i1 == 1 && i2 == 1   % problem
                    temp_dp_top    = dp_top;
                    temp_dp_bottom = dp_bottom;
                    if x0 > xtop && x0 <= xd1
                        dp_top    = temp_dp_top - abs(d1 + dtop) * tan(slope);
                        dp_bottom = temp_dp_top - abs(dtop + d2) * tan(slope);
                        lp_top    = d1;
                        lp_bottom = d2;
                    elseif x0 < xbottom && x0 >= xd2
                        dp_bottom = temp_dp_bottom + abs(d2 + dbottom) * tan(slope);
                        dp_top    = temp_dp_bottom + abs(dbottom + d1) * tan(slope);
                        lp_top    = d1;
                        lp_bottom = d2;
                    elseif x0 > xbottom && x0 <= xd1
                        dp_bottom = temp_dp_bottom + abs(d1 + dbottom) * tan(slope);
                        dp_top    = temp_dp_bottom + abs(dbottom + d2) * tan(slope);
                        lp_top    = d2;
                        lp_bottom = d1;
                    elseif x0 > xd2 && x0 <= xtop
                        dp_top    = temp_dp_top - abs(d2 + dtop) * tan(slope);
                        dp_bottom = temp_dp_top - abs(dtop + d1) * tan(slope);
                        lp_top    = d2;
                        lp_bottom = d1;
                    else
                        disp('Unknown error to draw the fault line.');
                        showMessage(app, 'Unknown error to draw the fault line.', false);
                    end
                end
            end


            % -----------------------------------------------------
            % check_in_sec (CSC_fault_int_secの内部ネスト関数)
            function [i, dist] = check_in_sec(xs, ys, xf, yf, xp, yp)
                i    = 0;
                xmax = max([xs; xf]);
                xmin = min([xs; xf]);
                ymax = max([ys; yf]);
                ymin = min([ys; yf]);
                if xp >= xmin && xp <= xmax
                    if yp >= ymin && yp <= ymax
                        i = 1;
                    end
                end
                dist = sqrt((xp - xs) ^ 2 + (yp - ys) ^ 2);
            end
        end


        function NFC_draw_new_fault_line(app)
            % =====================================================================
            %                NFC_draw_new_fault_line(app)
            % =====================================================================
            % 新規で設定する断層候補の線をグラフに表示する関数
            % 断層の開始点と終了点の座標情報を取得
            NFC_xs = app.NFC_Xstart_EditField.Value;  % 始点のX座標を取得
            NFC_ys = app.NFC_Ystart_EditField.Value;  % 始点のY座標を取得
            NFC_xf = app.NFC_Xfinish_EditField.Value; % 終点のX座標を取得
            NFC_yf = app.NFC_Yfinish_EditField.Value; % 終点のY座標を取得
        
            % 既存の候補線を削除
            NFC_new_fault = findall(app.UIAxes, 'Tag', 'NFC_new_fault');
            if ~isempty(NFC_new_fault)
                delete(NFC_new_fault);
            end

            % 現在のプロットを削除
            hold(app.UIAxes, 'on');
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % lonlat座標の場合
                if app.NFC_Type_DropDown.ValueIndex == 2
                    % dropdownがcartesianであるとき、変換してからプロット
                    s = xy2lonlat(app, [NFC_xs, NFC_ys]);
                    f = xy2lonlat(app, [NFC_xf, NFC_yf]);
                    new_NFC_xs = s(1);
                    new_NFC_xf = f(1);
                    new_NFC_ys = s(2);
                    new_NFC_yf = f(2);
                elseif app.NFC_Type_DropDown.ValueIndex == 3
                    % dropdownがlonlatであるとき、そのままプロット
                    new_NFC_xs = NFC_xs;
                    new_NFC_xf = NFC_xf;
                    new_NFC_ys = NFC_ys;
                    new_NFC_yf = NFC_yf;
                else
                    % No selectionの場合
                    uialert(app.main_menu_window, 'Choose either Cartesian or LonLat.', 'Warning!');
                end
            else
                % Cartesian座標の場合
                if app.NFC_Type_DropDown.ValueIndex == 2
                    % dropdownがcartesianであるとき、そのままプロット
                    new_NFC_xs = NFC_xs;
                    new_NFC_xf = NFC_xf;
                    new_NFC_ys = NFC_ys;
                    new_NFC_yf = NFC_yf;
                elseif app.NFC_Type_DropDown.ValueIndex == 3
                    % dropdownがlonlatであるとき、変換してからプロット
                    s = lonlat2xy(app, [NFC_xs, NFC_ys]);
                    f = lonlat2xy(app, [NFC_xf, NFC_yf]);
                    new_NFC_xs = s(1);
                    new_NFC_xf = f(1);
                    new_NFC_ys = s(2);
                    new_NFC_yf = f(2);
                else
                    % No selectionの場合
                    uialert(app.main_menu_window, 'Choose either Cartesian or LonLat.', 'Warning!');
                end
            end

            % 断層候補の線を描画
            H = plot(app.UIAxes, [new_NFC_xs, new_NFC_xf], [new_NFC_ys, new_NFC_yf]);
            HTS = text(app.UIAxes, new_NFC_xs, new_NFC_ys, 'Start', 'FontSize', 14, 'Color', 'k');
            HTF = text(app.UIAxes, new_NFC_xf, new_NFC_yf, 'Finish', 'FontSize', 14, 'Color', 'k');
            % 設定
            H.Tag = 'NFC_new_fault';
            H.Color = 'r';
            H.LineWidth = 1.0;
            HTS.HorizontalAlignment = 'center';
            HTS.Tag = 'NFC_new_fault';
            HTF.HorizontalAlignment = 'center';
            HTF.Tag = 'NFC_new_fault';

            % 既存のセレクトポイントを削除
            selectPoint = findall(app.UIAxes, 'Tag', 'selectPoint');
            if ~isempty(selectPoint)
                delete(selectPoint);
            end
        end

        function BXY_draw_new_fault_line(app)
            % =====================================================================
            %                BXY_draw_new_fault_line(app)
            % =====================================================================
            % 新規で設定する断層候補の線をグラフに表示する関数
            % 断層の開始点と終了点の座標情報を取得
            BXY_xs = app.BXY_Xstart_EditField.Value;  % 始点のX座標を取得
            BXY_ys = app.BXY_Ystart_EditField.Value;  % 始点のY座標を取得
            BXY_xf = app.BXY_Xfinish_EditField.Value; % 終点のX座標を取得
            BXY_yf = app.BXY_Yfinish_EditField.Value; % 終点のY座標を取得
        
            % 既存の候補線を削除
            BXY_new_fault = findall(app.UIAxes, 'Tag', 'BXY_new_fault');
            if ~isempty(BXY_new_fault)
                delete(BXY_new_fault);
            end

            % 現在のプロットを削除
            hold(app.UIAxes, 'on');
            if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                % lonlat座標の場合
                if app.BXY_Type_DropDown.ValueIndex == 1
                    % dropdownがcartesianであるとき、変換してからプロット
                    s = xy2lonlat(app, [BXY_xs, BXY_ys]);
                    f = xy2lonlat(app, [BXY_xf, BXY_yf]);
                    new_BXY_xs = s(1);
                    new_BXY_xf = f(1);
                    new_BXY_ys = s(2);
                    new_BXY_yf = f(2);
                elseif app.BXY_Type_DropDown.ValueIndex == 2
                    % dropdownがlonlatであるとき、そのままプロット
                    new_BXY_xs = BXY_xs;
                    new_BXY_xf = BXY_xf;
                    new_BXY_ys = BXY_ys;
                    new_BXY_yf = BXY_yf;
                end
            else
                % Cartesian座標の場合
                if app.BXY_Type_DropDown.ValueIndex == 1
                    % dropdownがcartesianであるとき、そのままプロット
                    new_BXY_xs = BXY_xs;
                    new_BXY_xf = BXY_xf;
                    new_BXY_ys = BXY_ys;
                    new_BXY_yf = BXY_yf;
                elseif app.BXY_Type_DropDown.ValueIndex == 2
                    % dropdownがlonlatであるとき、変換してからプロット
                    s = lonlat2xy(app, [BXY_xs, BXY_ys]);
                    f = lonlat2xy(app, [BXY_xf, BXY_yf]);
                    new_BXY_xs = s(1);
                    new_BXY_xf = f(1);
                    new_BXY_ys = s(2);
                    new_BXY_yf = f(2);
                end
            end

            % new_BXY_xs = BXY_xs;
            % new_BXY_xf = BXY_xf;
            % new_BXY_ys = BXY_ys;
            % new_BXY_yf = BXY_yf;

            % 断層候補の線を描画
            H = plot(app.UIAxes, [new_BXY_xs, new_BXY_xf], [new_BXY_ys, new_BXY_yf]);
            HTS = text(app.UIAxes, new_BXY_xs, new_BXY_ys, 'Start', 'FontSize', 14, 'Color', 'k');
            HTF = text(app.UIAxes, new_BXY_xf, new_BXY_yf, 'Finish', 'FontSize', 14, 'Color', 'k');
            % 設定
            H.Tag = 'BXY_new_fault';
            H.Color = 'r';
            H.LineWidth = 1.0;
            HTS.HorizontalAlignment = 'center';
            HTS.Tag = 'BXY_new_fault';
            HTF.HorizontalAlignment = 'center';
            HTF.Tag = 'BXY_new_fault';

            % 既存のセレクトポイントを削除
            selectPoint = findall(app.UIAxes, 'Tag', 'selectPoint');
            if ~isempty(selectPoint)
                delete(selectPoint);
            end
        end


        % ==========================================================================================================================================
        %                オーバーレイ関連の関数
        % ==========================================================================================================================================
        function overlay_drawing_list_start(app)
            % =====================================================================
            %                overlay_drawing_list_start(app)
            % =====================================================================
            % オーバーレイリスト関連の起動時関数
            % リストを有効化
            app.OverlaysPanel.Enable = 'on';
            % 選択をリセットし、何も選択していない状態にする
            app.CoastlinesCheckBox.Value = 0;
            app.ActivefaultsCheckBox.Value = 0;
            app.VolcanoesCheckBox.Value = 0;
            app.EarthquakesCheckBox.Value = 0;
            app.GNSSstationsCheckBox.Value = 0;
            app.CitiesCheckBox.Value = 0;
        end

        function overlay_drawing(app)
            % =====================================================================
            %                overlay_drawing(app)
            % =====================================================================
            % オーバーレイボックスで選択されているものを再描画する関数

            % 処理が遅いので修正必要？
            h = msgbox('Now drawing...', 'Notification', 'help');
            % ----- 海岸線の描画 -----
            if ~isempty(app.OVERLAY_VARS.COAST_DATA) && app.CoastlinesCheckBox.Value == 1
                % 既にapp.OVERLAY_VARS.COAST_DATAにデータが格納されているとき
                overlay_coastline_drawing(app);  % 海岸線を描画
            end
                        
            % ----- 活断層の描画 -----
            if ~isempty(app.OVERLAY_VARS.AFAULT_DATA) && app.ActivefaultsCheckBox.Value == 1
                % 既にapp.OVERLAY_VARS.AFAULT_DATAにデータが格納されているとき
                overlay_activefault_drawing(app);  % 活断層を描画
            end

            % --- 火山データの描画 ---
            if ~isempty(app.OVERLAY_VARS.VOLCANO) && app.VolcanoesCheckBox.Value == 1
                overlay_volcano_drawing(app);  % 火山を描画
            end

            % --- 地震データの描画 ---
            if ~isempty(app.OVERLAY_VARS.EQ_DATA) && app.EarthquakesCheckBox.Value == 1
                overlay_earthquake_plot_drawing(app);  % 地震データを描画
            end

            % --- GNSSデータの描画 ---
            if ~isempty(app.OVERLAY_VARS.GNSS_DATA) && app.GNSSstationsCheckBox.Value == 1
                overlay_gnss_2d_drawing(app);  % GNSSデータを描画
            end

            % --- 街（地図）データの描画 ---
            % 作成中
            % 修正必要

            close(h);
        end


% % ==========================================================================================================================================
% function overlay_coastline_read_data(app)
% % =====================================================================
% % overlay_coastline_read_data(app)
% % 海岸線データを読み込み、[lon lat x y] を NaN 区切りで app.OVERLAY_VARS.COAST_DATA に格納
% % 対応形式: .shp / .mat / ASCII(.dat, .txt 等: 既存フォーマット)
% % =====================================================================
% 
% % --- GSHHS + worldmap による自動描画を優先
% if license('test','MAP_Toolbox')
%     try
%         overlay_coastline_auto(app, 'high');
%         return;
%     catch
%         % 失敗した場合は従来のファイル選択へフォールバック
%     end
% end
% 
% 
% 
%     % 描画領域（画面座標系）
%     xs = app.INPUT_VARS.GRID(1,1);  xf = app.INPUT_VARS.GRID(3,1);
%     ys = app.INPUT_VARS.GRID(2,1);  yf = app.INPUT_VARS.GRID(4,1);
% 
%     % 参照ディレクトリ
%     cd(app.SYSTEM_VARS.HOME_DIR);
%     try
%         defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_overlay_files', 'coastline_data');
%     catch
%         uialert(app.main_menu_window,'Operation failed.','Warning!');
%         app.CoastlinesCheckBox.Value = 0;
%         cd(app.SYSTEM_VARS.HOME_DIR);
%         return;
%     end
% 
%     % ファイル選択（拡張）
%     if isempty(app.OVERLAY_VARS.COAST_DATA)
%         [filename, pathname] = uigetfile( ...
%             { '*.shp;*.mat;*.dat;*.txt','Coastline files (*.shp,*.mat,*.dat,*.txt)';
%               '*.shp','ESRI Shapefile (*.shp)'; ...
%               '*.mat','MAT-file (*.mat)'; ...
%               '*.dat;*.txt;*.*','ASCII/Other (*.*)'}, ...
%             'Open coastline data file', defaultDir);
%         if isequal(filename,0)
%             uialert(app.main_menu_window,'User selected Cancel.','Information','Icon','info');
%             app.CoastlinesCheckBox.Value = 0;
%             return;
%         end
%         fullpath = fullfile(pathname, filename);
% 
%         % 形式ごとに読み分け
%         [~,~,ext] = fileparts(filename);
%         ext = lower(ext);
%         try
%             switch ext
%                 case '.shp'
%                     app.OVERLAY_VARS.COAST_DATA = local_read_shapefile_coast(app, fullpath, xs, xf, ys, yf);
%                 case '.mat'
%                     app.OVERLAY_VARS.COAST_DATA = local_read_mat_coast(app, fullpath);
%                 case '.gshhs'
%                     app.OVERLAY_VARS.COAST_DATA = local_read_gshhs_coast(app, 'high', xs, xf, ys, yf);
%                 otherwise
%                     % 既存 ASCII ルート（NOAA 形式 or デリミタ '<' 形式）
%                     app.OVERLAY_VARS.COAST_DATA = local_read_ascii_coast(app, fullpath, xs, xf, ys, yf);
%             end
%         catch ME
%             uialert(app.main_menu_window, ...
%                 sprintf('Reading failed:\n%s', ME.message), 'Error', 'Icon', 'error');
%             app.CoastlinesCheckBox.Value = 0;
%             return;
%         end
% 
%         % ガイダンス（従来出力を簡潔化）
%         dummy = app.OVERLAY_VARS.OVERLAY_MARGIN;
%         showMessage(app, { ...
%             ['Extra margin width of the data screening is ', num2str(int16(dummy)) ,' km'], ...
%             'To save coastline data as binary: save mycoastline.mat OVERLAY_VARS.COAST_DATA', ...
%             'To re-open, use File -> Open... (.mat)'}, false);
%     end
% 
%     % メモリ節約
%     if ~isempty(app.OVERLAY_VARS.COAST_DATA)
%         app.OVERLAY_VARS.COAST_DATA = single(app.OVERLAY_VARS.COAST_DATA);
%     end
% 
%     cd(app.SYSTEM_VARS.HOME_DIR);
% end
% 
% 
% % ==========================================================================================================================================
% function COAST = local_read_shapefile_coast(app, shpPath, xs, xf, ys, yf)
% % Shapefile を読み込み、[lon lat x y] に統一。セグメントは NaN 区切り。
% 
%     % まずは shaperead を採用（readgeotable より互換性が広い）
%     % UseGeoCoords=true なら Lon/Lat を返す（地理座標のとき）
%     S = [];
%     try
%         S = shaperead(shpPath, 'UseGeoCoords', true);
%     catch
%         % 地理座標でない場合や古い環境では X/Y になる
%         S = shaperead(shpPath);
%     end
%     if isempty(S)
%         error('No shapes found in "%s".', shpPath);
%     end
% 
%     % CRS（投影系）の補足情報
%     CRS = [];
%     try
%         info = shapeinfo(shpPath); % 新しめの MATLAB で CRS が入る
%         if isfield(info,'CoordinateReferenceSystem')
%             CRS = info.CoordinateReferenceSystem; % projected/geographic の別
%         end
%     catch
%         % 何も得られなければ空のまま
%     end
% 
%     % すべてのパートを連結（NaN 区切り）
%     lonAll = []; latAll = [];
%     for i = 1:numel(S)
%         if isfield(S,'Lon') && isfield(S,'Lat')
%             lon = S(i).Lon(:); lat = S(i).Lat(:);
%         elseif isfield(S,'X') && isfield(S,'Y')
%             % 投影座標かもしれないので、可能なら projinv で緯度経度へ
%             X = S(i).X(:); Y = S(i).Y(:);
%             if ~isempty(CRS)
%                 try
%                     if isprop(CRS,'Type') && strcmpi(CRS.Type,'projected')
%                         [lat, lon] = projinv(CRS, X, Y);
%                     else
%                         % 地理座標系なのに X/Y 名のこともある（稀）
%                         lon = X; lat = Y;
%                     end
%                 catch
%                     % projinv 不可なら「そのまま lon=X, lat=Y と仮定」
%                     lon = X; lat = Y;
%                 end
%             else
%                 lon = X; lat = Y;
%             end
%         else
%             error('Unsupported shapefile structure. Expected (Lon/Lat) or (X/Y).');
%         end
% 
%         % 末尾 NaN 整理
%         if isempty(lonAll)
%             lonAll = lon; latAll = lat;
%         else
%             lonAll = [lonAll; NaN; lon]; %#ok<AGROW>
%             latAll = [latAll; NaN; lat]; %#ok<AGROW>
%         end
%     end
% 
%     % 経度の正負体系（0–360 or -180–180）を推定
%     westType = local_guess_westType(app, lonAll);
% 
%     % 表示範囲→画面座標への変換係数
%     if strcmp(westType,'plus')
%         minlon = mod(app.COORD_VARS.MIN_LON,360);
%         maxlon = mod(app.COORD_VARS.MAX_LON,360);
%         lonUse = mod(lonAll,360);
%     else
%         minlon = app.COORD_VARS.MIN_LON;
%         maxlon = app.COORD_VARS.MAX_LON;
%         lonUse = lonAll;
%     end
%     xinc = (xf - xs)/(maxlon - minlon);
%     yinc = (yf - ys)/(app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);
% 
%     % 画面座標へ
%     x = xs + (lonUse - minlon) .* xinc;
%     y = ys + (latAll - app.COORD_VARS.MIN_LAT) .* yinc;
% 
%     % トリミング（余白含む）
%     dummy = app.OVERLAY_VARS.OVERLAY_MARGIN;
%     keep =  (x >= (xs - dummy) & x <= (xf + dummy) & ...
%              y >= (ys - dummy) & y <= (yf + dummy)) | (isnan(x) | isnan(y));
% 
%     lonAll(~keep) = []; latAll(~keep) = [];
%     x(~keep)      = []; y(~keep)      = [];
% 
%     % 内部統一形式 [lon lat x y]（NaN 行はそのまま）
%     COAST = [lonAll, latAll, x, y];
% end
% 
% 
% % ==========================================================================================================================================
% function COAST = local_read_ascii_coast(app, fullpath, xs, xf, ys, yf)
% % 既存 ASCII/NOAA or '<' デリミタ形式を読み込み、[lon lat x y] に統一
% 
%     fid = fopen(fullpath,'r');
%     if fid<0, error('Cannot open: %s', fullpath); end
% 
%     n = 10000; count = 0; aa = cell(n,1); bb = cell(n,1);
%     for m = 1:n
%         count = count + 1;
%         if m==1, test = textscan(fid,'%f %f','headerlines',2);
%         else,    test = textscan(fid,'%f %f','headerlines',1);
%         end
%         aa{m} = test{1}; bb{m} = test{2};
%         if numel(test{1})<=1, break; end
%     end
%     fclose(fid);
% 
%     aaa = cell2mat(aa'); % 経度検査
%     westType = local_guess_westType(app, aaa);
% 
%     % 西半球設定＆スケール
%     if strcmp(westType,'plus')
%         minlon = mod(app.COORD_VARS.MIN_LON,360);
%         maxlon = mod(app.COORD_VARS.MAX_LON,360);
%     else
%         minlon = app.COORD_VARS.MIN_LON;
%         maxlon = app.COORD_VARS.MAX_LON;
%     end
%     xinc = (xf - xs)/(maxlon - minlon);
%     yinc = (yf - ys)/(app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);
% 
%     % 再読
%     fid = fopen(fullpath,'r');
%     xC = cell(n,1); yC = cell(n,1);
%     for m = 1:n
%         if m==1, a = textscan(fid,'%f %f','headerlines',2);
%         else,    a = textscan(fid,'%f %f','headerlines',1);
%         end
%         xC{m} = a{1}; yC{m} = a{2};
%         if numel(a{1})<=1, break; end
%     end
%     fclose(fid);
% 
%     % 蓄積
%     dummy = app.OVERLAY_VARS.OVERLAY_MARGIN;
%     nn = 0; COAST = [];
% 
%     if count<=2
%         % NOAA 形式（NaN NaN 区切り）
%         nlength = size(xC,1);
%         for m = 1:nlength
%             lon = xC{m}; lat = yC{m};
%             if strcmp(westType,'plus'), lon = mod(lon,360); end
%             xx = xs + (lon - minlon).*xinc;
%             yy = ys + (lat - app.COORD_VARS.MIN_LAT).*yinc;
% 
%             for k=1:numel(xx)
%                 if isnan(xx(k))
%                     COAST(end+1,:) = [NaN NaN NaN NaN]; %#ok<AGROW>
%                 else
%                     if xx(k)>=(xs-dummy)&&xx(k)<=(xf+dummy)&&yy(k)>=(ys-dummy)&&yy(k)<=(yf+dummy)
%                         a = xy2lonlat(app, [xx(k), yy(k)]);
%                         COAST(end+1,:) = [a(1), a(2), xx(k), yy(k)]; %#ok<AGROW>
%                     end
%                 end
%             end
%         end
%     else
%         % '<' デリミタ形式（セグメントごとに NaN 区切り付与）
%         for m = 1:count
%             lon = xC{m}; lat = yC{m};
%             if isempty(lon), continue; end
%             if strcmp(westType,'plus'), lon = mod(lon,360); end
%             xx = xs + (lon - minlon).*xinc;
%             yy = ys + (lat - app.COORD_VARS.MIN_LAT).*yinc;
% 
%             nkeep = size(COAST,1);
%             for k=1:numel(xx)
%                 if xx(k)>=(xs-dummy)&&xx(k)<=(xf+dummy)&&yy(k)>=(ys-dummy)&&yy(k)<=(yf+dummy)
%                     a = xy2lonlat(app, [xx(k), yy(k)]);
%                     COAST(end+1,:) = [a(1), a(2), xx(k), yy(k)]; %#ok<AGROW>
%                 end
%             end
%             if size(COAST,1)>nkeep
%                 COAST(end+1,:) = [NaN NaN NaN NaN]; %#ok<AGROW>
%             end
%         end
%     end
% end
% 
% function COAST = local_read_gshhs_coast(app, resolution, xs, xf, ys, yf)
% % resolution: 'full' | 'high' | 'intermediate' | 'low' | 'crude'
% % Mapping Toolbox の GSHHS データを読み込み、[lon lat x y] 形式に変換
% 
%     % 1. 読み込み
%     gshhs = readgeotable("gshhs", "Resolution", resolution);
% 
%     % 2. 範囲を Coulomb の現在座標に合わせてトリム
%     lonmin = app.COORD_VARS.MIN_LON;
%     lonmax = app.COORD_VARS.MAX_LON;
%     latmin = app.COORD_VARS.MIN_LAT;
%     latmax = app.COORD_VARS.MAX_LAT;
% 
%     % ROI で抽出
%     gshhs = geocrop(gshhs, [latmin latmax], [lonmin lonmax]);
% 
%     % 3. 座標変換（描画範囲 → 画面座標）
%     xinc = (xf - xs) / (lonmax - lonmin);
%     yinc = (yf - ys) / (latmax - latmin);
% 
%     lonAll = gshhs.Lon(:);
%     latAll = gshhs.Lat(:);
% 
%     x = xs + (lonAll - lonmin) .* xinc;
%     y = ys + (latAll - latmin) .* yinc;
% 
%     % 4. NaN 区切りで統一
%     COAST = [lonAll, latAll, x, y];
% end
% 
% 
% function COAST = local_read_mat_coast(~, fullpath)
% % .mat から OVERLAY_VARS.COAST_DATA を取り出す（なければ推測）
%     S = load(fullpath);
%     if isfield(S,'OVERLAY_VARS') && isfield(S.OVERLAY_VARS,'COAST_DATA')
%         COAST = S.OVERLAY_VARS.COAST_DATA;
%     elseif isfield(S,'COAST_DATA')
%         COAST = S.COAST_DATA;
%     else
%         error('COAST_DATA not found in MAT-file.');
%     end
%     % 列数が 4 以外（古い9列など）の場合は描画側の既存ロジックが警告して対応します
% end
% 
% 
% function westType = local_guess_westType(~, lonVec)
% % lon が 0–360 主体なら 'plus'、-180–180 主体なら 'minus'
%     lonVec = lonVec(~isnan(lonVec));
%     if isempty(lonVec), westType = 'minus'; return; end
%     if max(lonVec) > 180, westType = 'plus'; else, westType = 'minus'; end
% end
% 
% 
% function overlay_coastline_auto(app, resolution)
% % overlay_coastline_auto(app, resolution)
% % --------------------------------------------------------------
% % GSHHS データを自動取得し、研究範囲に基づいて海岸線を描画
% % resolution: 'full' | 'high' | 'intermediate' | 'low' | 'crude'
% % --------------------------------------------------------------
% 
%     % 必須範囲情報を取得
%     lonlim = [app.COORD_VARS.MIN_LON app.COORD_VARS.MAX_LON];
%     latlim = [app.COORD_VARS.MIN_LAT app.COORD_VARS.MAX_LAT];
% 
%     % ----------------------------------------------------------
%     % 1. Mapping Toolbox の worldmap 軸を生成（ただし非表示）
%     % ----------------------------------------------------------
%     ax = worldmap(latlim, lonlim);   % worldmap が座標軸を初期化
%     setm(ax, 'Frame', 'off', 'Grid', 'off', 'ParallelLabel', 'off', 'MeridianLabel', 'off');
%     hold(ax, 'on');
% 
%     % ----------------------------------------------------------
%     % 2. GSHHS データ読み込み
%     % ----------------------------------------------------------
%     try
%         gshhs = readgeotable("gshhs", "Resolution", resolution);
%     catch
%         uialert(app.main_menu_window, ...
%             'Mapping Toolbox or GSHHS data not available.','Error','Icon','error');
%         delete(ax);
%         return;
%     end
% 
%     % ----------------------------------------------------------
%     % 3. 範囲で切り出し
%     % ----------------------------------------------------------
%     gshhs_sub = geocrop(gshhs, latlim, lonlim);
% 
%     % データが空の場合
%     if isempty(gshhs_sub)
%         uialert(app.main_menu_window, ...
%             'No coastline data found within the current area.','Info','Icon','info');
%         delete(ax);
%         return;
%     end
% 
%     % ----------------------------------------------------------
%     % 4. Coulomb の座標系に変換
%     % ----------------------------------------------------------
%     xs = app.INPUT_VARS.GRID(1, 1);
%     xf = app.INPUT_VARS.GRID(3, 1);
%     ys = app.INPUT_VARS.GRID(2, 1);
%     yf = app.INPUT_VARS.GRID(4, 1);
% 
%     lon = gshhs_sub.Lon(:);
%     lat = gshhs_sub.Lat(:);
%     xinc = (xf - xs) / (lonlim(2) - lonlim(1));
%     yinc = (yf - ys) / (latlim(2) - latlim(1));
%     x = xs + (lon - lonlim(1)) .* xinc;
%     y = ys + (lat - latlim(1)) .* yinc;
% 
%     % ----------------------------------------------------------
%     % 5. オーバーレイ変数に格納
%     % ----------------------------------------------------------
%     app.OVERLAY_VARS.COAST_DATA = single([lon, lat, x, y]);
% 
%     % ----------------------------------------------------------
%     % 6. Coulomb の UIAxes に描画
%     % ----------------------------------------------------------
%     hold(app.UIAxes, 'on');
%     plot(app.UIAxes, x, y, 'Color', app.SYSTEM_VARS.PREF(4,1:3), ...
%         'LineWidth', app.SYSTEM_VARS.PREF(4,4), 'Tag', 'CoastlineObj');
% 
%     % ----------------------------------------------------------
%     % 7. 後処理
%     % ----------------------------------------------------------
%     delete(ax);  % worldmap 軸は非表示のため削除
%     cd(app.SYSTEM_VARS.HOME_DIR);
% 
%     showMessage(app, sprintf('Coastline drawn automatically (resolution: %s)', resolution), false);
% end


        function overlay_coastline_read_data(app)
            % =====================================================================
            %                overlay_coastline_read_data(app)
            % =====================================================================
            % 海岸線データを使用して海岸線を描画する関数

            % 描画のための座標系の設定
            xs = app.INPUT_VARS.GRID(1, 1);
            xf = app.INPUT_VARS.GRID(3, 1);
            ys = app.INPUT_VARS.GRID(2, 1);
            yf = app.INPUT_VARS.GRID(4, 1);

            % デフォルトのディレクトリに移動
            cd(app.SYSTEM_VARS.HOME_DIR);
            % ディレクトリ 'coastline_data' に移動
            try
                % 'coastline_data' のディレクトリを設定
                defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_overlay_files', 'coastline_data');
            catch % 失敗した場合以下のディレクトリを表示
                uialert(app.main_menu_window, 'Operation failed.', 'Warning!');
                app.CoastlinesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end

            % デフォルトのダイアログでデータファイルを選択
            if isempty(app.OVERLAY_VARS.COAST_DATA) == 1
                [filename, pathname] = uigetfile('*.*', ' Open coastline data file', defaultDir);
                if isequal(filename, 0)
                    uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                    app.CoastlinesCheckBox.Value = 0;
                    return;
                end

                % ファイルの読み込みと初期チェック
                fid = fopen(fullfile(pathname, filename), 'r');
                % *****  % 西半球のデータかどうかをテストするために読み込む *****************
                n = 10000;  % ダミーの数値
                count = 0;
                for m = 1:n
                    count = count + 1;
                    if m == 1
                        test = textscan(fid, '%f %f', 'headerlines', 2);
                    else
                        test = textscan(fid, '%f %f', 'headerlines', 1);
                    end
                    aa{m} = test{1};
                    bb{m} = test{2};
                    check_test = [test{1}];
                    if length(check_test) <= 1
                        break;
                    end 
                end

                aaa = cell2mat(aa');
                if max(aaa) > 180.0
                    westType = 'plus';
                else
                    westType = 'minus';
                end

                % ****************************************************************
                % 西半球がプラスかマイナスかによって、経度を調整
                if strcmp(westType, 'plus')
                    % 西半球を正の値として扱う
                    minlon = mod(app.COORD_VARS.MIN_LON, 360);
                    maxlon = mod(app.COORD_VARS.MAX_LON, 360);
                elseif strcmp(westType, 'minus')
                    % 西半球を負の値として扱う
                    minlon = app.COORD_VARS.MIN_LON;
                    maxlon = app.COORD_VARS.MAX_LON;
                else % 無効な値の場合
                    uialert(app.main_menu_window, 'Check your coastline file. Operation canceled.', 'Warning!');
                    app.CoastlinesCheckBox.Value = 0;
                    return;
                end
                xinc = (xf - xs)/(maxlon - minlon);
                yinc = (yf - ys)/(app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);

                % 計算ウィンドウの表示
                % hm = wait_calc_window(app);

                % 再度ファイル読み込み（座標変換用）
                fid = fopen(fullfile(pathname, filename), 'r');
                for m = 1:n
                    if m == 1
                        a = textscan(fid,'%f %f','headerlines', 2);
                    else
                        a = textscan(fid,'%f %f','headerlines', 1);
                    end
                    x{m} = a{1};
                    y{m} = a{2};
                    if length(a{1}) <= 1
                        break;
                    end
                end
                fclose(fid);

                % 海岸線データの処理
                % データ格納
                dummy = app.OVERLAY_VARS.OVERLAY_MARGIN;
                disp(['   * Extra margin width of the data screening is ', num2str(int16(dummy)) ,' km']);
                disp('   * If you want to trim more, change the value OVERLAY_VARS.OVERLAY_MARGIN in this');
                disp('   * command window and then read it again. It is useful for 3D view.');
                disp(' ');
                disp('   * To save the areal coastline data as binary, use the following command in the Command Window.');
                disp('   * save filename OVERLAY_VARS.COAST_DATA (e.g., save mycoastline.mat OVERLAY_VARS.COAST_DATA)');
                disp('   * To read the .mat formatted coastline data, use ''File -> Open...'' menu later.');
                disp(' ');
                showMessage(app, { ...
                    ['Extra margin width of the data screening is ', num2str(int16(dummy)) ,' km'], ...
                    'If you want to trim more, change the value OVERLAY_VARS.OVERLAY_MARGIN in this command window and then read it again. It is useful for 3D view.', ...
                    '', ...
                    'To save the areal coastline data as binary, use the following command in the Command Window.', ...
                    'save filename OVERLAY_VARS.COAST_DATA (e.g., save mycoastline.mat OVERLAY_VARS.COAST_DATA)', ...
                    'To read the .mat formatted coastline data, use ''File -> Open...'' menu later.' ...
                    }, false);

                icount = 0;
                temp = 0;
                nn = 0;
                nlength = size(x,1);
                if count <= 2 % NOAA形式 ('nan nan') の場合の処理
                    for m = 1:nlength
                        xx = [x{m}];
                        yy = [y{m}];
                        xx = xs + (xx - minlon) * xinc;
                        yy = ys + (yy - app.COORD_VARS.MIN_LAT) * yinc;
                        for k = 1:length(xx)
                            if isnan(xx(k))
                                nn = nn + 1;
                                app.OVERLAY_VARS.COAST_DATA(nn, :) = NaN;
                                continue;
                            else
                                if xx(k) >= (xs - dummy) && xx(k) <= (xf + dummy) && yy(k) >= (ys - dummy) && yy(k) <= (yf + dummy)
                                    nn = nn + 1;
                                    if m ~= temp
                                        icount = icount + 1;
                                        temp = m;
                                    end
                                    a = xy2lonlat(app, [xx(k), yy(k)]);
                                    app.OVERLAY_VARS.COAST_DATA(nn, :) = [a(1), a(2), xx(k), yy(k)];
                                end
                            end
                        end
                    end
                else % デリミタ形式 ('<') の場合の処理
                    for m = 1:count
                        xx = [x{m}];
                        yy = [y{m}];
                        xx = xs + (xx - minlon) * xinc;
                        yy = ys + (yy - app.COORD_VARS.MIN_LAT) * yinc;
                        nkeep = nn;
                        for k = 1:length(xx)
                            if xx(k) >= (xs - dummy) && xx(k) <= (xf + dummy) && yy(k) >= (ys - dummy) && yy(k) <= (yf + dummy)
                                nn = nn + 1;
                                if m ~= temp
                                    icount = icount + 1;
                                    temp = m;
                                end
                                a = xy2lonlat(app, [xx(k), yy(k)]);
                                app.OVERLAY_VARS.COAST_DATA(nn, :) = [a(1), a(2), xx(k), yy(k)];
                            end
                        end
                        % 描画ペンを上げるためにNaNタグを付与（海岸線セグメントを分ける）
                        if nn > nkeep
                            nn = nn + 1;
                            % セグメント区切り
                            app.OVERLAY_VARS.COAST_DATA(nn, :) = NaN;
                        end
                    end
                end
            end
            % メモリサイズを削減するための処理
            app.OVERLAY_VARS.COAST_DATA = single(app.OVERLAY_VARS.COAST_DATA);
        end

        function overlay_CL = overlay_coastline_drawing(app)
            % =====================================================================
            %                overlay_coastline_drawing(app)
            % =====================================================================
            % 海岸線の描画処理 --------------
            if isempty(app.OVERLAY_VARS.COAST_DATA)
                % データがない時、何もせず終了
                cd(app.SYSTEM_VARS.HOME_DIR); % ホームディレクトリに戻る
                overlay_CL = 1;
                return;
            end

            hold(app.UIAxes, 'on');
            if app.CoastlinesCheckBox.Value == 1
                % データがあるとき
                % 古いフォーマットか新しいフォーマットかを判別
                [~, n] = size(app.OVERLAY_VARS.COAST_DATA); 
                if n == 9
                    % ===== 古いフォーマットの場合
                    uialert(app.main_menu_window, ...
                        ['This coastline data OVERLAY_VARS.COAST_DATA are from old versions. ', ...
                         'Since old format produces fragmented lines, we strongly recommend to clear the data and re-read ascii data.'], ...
                        'Warning!');
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        x1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 2)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 4))];
                        y1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 3)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 5))];
                    else
                        x1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 6)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 8))];
                        y1 = [rot90(app.OVERLAY_VARS.COAST_DATA(:, 7)); rot90(app.OVERLAY_VARS.COAST_DATA(:, 9))];
                    end
                else
                    % ===== 新しいフォーマットの場合
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        x1 = app.OVERLAY_VARS.COAST_DATA(:, 1);
                        y1 = app.OVERLAY_VARS.COAST_DATA(:, 2);
                    else
                        x1 = app.OVERLAY_VARS.COAST_DATA(:, 3);
                        y1 = app.OVERLAY_VARS.COAST_DATA(:, 4);
                    end
                end
                p = plot(app.UIAxes, x1, y1, 'Color', app.SYSTEM_VARS.PREF(4, 1:3), 'LineWidth', app.SYSTEM_VARS.PREF(4, 4));
                p.Tag = 'CoastlineObj';
                overlay_CL = 0;
            else
                % チェックボックスが外されているとき
                % 'CoastlineObj'タグのついた海岸線の描画を削除
                coastLines = findall(app.UIAxes, 'Tag', 'CoastlineObj');
                if ~isempty(coastLines)
                    delete(coastLines);
                end
            end

            cd(app.SYSTEM_VARS.HOME_DIR); % ホームディレクトリに戻る
        end
            
        % 上二つがもとのもの


        function overlay_activefault_read_data(app)
            % =====================================================================
            %                overlay_activefault_read_data(app)
            % =====================================================================
            % 活断層データを読み込み、地図上に描画する関数
            % 描画用の座標系の設定
            xs = app.INPUT_VARS.GRID(1, 1);
            xf = app.INPUT_VARS.GRID(3, 1);
            ys = app.INPUT_VARS.GRID(2, 1);
            yf = app.INPUT_VARS.GRID(4, 1);   
            xinc = (xf - xs) / (app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON);
            yinc = (yf - ys) / (app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);
        
            % デフォルトのディレクトリに移動
            cd(app.SYSTEM_VARS.HOME_DIR);
            % ディレクトリ 'active_fault_data' に移動
            try
                % 'active_fault_data' のディレクトリを設定
                defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_overlay_files', 'active_fault_data');
            catch % 失敗した場合以下のディレクトリを表示
                uialert(app.main_menu_window, 'Operation failed.', 'Warning!');
                app.ActivefaultsCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end

            % 読み込むデータの形式を選択させる。
            activefault_flag = overlay_activefault_format_window(app);
            % キャンセルされた場合
            if isempty(activefault_flag)
                uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                app.ActivefaultsCheckBox.Value = 0;
                return;
            end

            % デフォルトのダイアログでデータファイルを選択
            if isempty(app.OVERLAY_VARS.AFAULT_DATA)
                [filename, pathname] = uigetfile('*.*', ' Open fault line data file', defaultDir);
                if isequal(filename, 0)
                    uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                    app.ActivefaultsCheckBox.Value = 0;
                    return;
                end

                % ファイルの読み込みと初期チェック
                fid = fopen(fullfile(pathname, filename), 'r');
                n = 2000000; % 読み込み行数の上限
                count = 0;
                % ファイルからデータを読み込み
                for m = 1:n
                    count = count + 1;
                    if m == 1
                        a = textscan(fid, '%f %f', 'HeaderLines', 2);
                        if activefault_flag == 0
                            y{m} = a{1};
                            x{m} = a{2};
                        elseif activefault_flag == 1
                            x{m} = a{1};
                            y{m} = a{2};
                        end
                    else
                        a = textscan(fid, '%f %f', 'HeaderLines', 1);
                        if activefault_flag == 0
                            y{m} = a{1};
                            x{m} = a{2};
                        elseif activefault_flag == 1
                            x{m} = a{1};
                            y{m} = a{2};
                        end
                    end
                    if isempty(a{1}) && isempty(a{2})
                        break;
                    end
                end
                fclose(fid);

                % 調査地域より少し大きい活断層情報を選択するためのダミー（バッファー）の幅
                dummy = app.OVERLAY_VARS.OVERLAY_MARGIN;
                disp(['* Extra margin width of the data screening is ', num2str(int16(dummy)), ' km']);
                disp('* To save the areal active fault data as binary, use the save command.');
                disp('* Example: save myfault.mat AFAULT_DATA');
                disp(' ');
                showMessage(app, { ...
                    ['Extra margin width of the data screening is ', num2str(int16(dummy)), ' km'], ...
                    'To save the areal active fault data as binary, use the save command.', ...
                    'Example: save myfault.mat AFAULT_DATA' ...
                    }, false);
        
                icount = 0;
                temp = 0;
                nn = 0;
                % 各セグメントのデータを地図上に変換
                for m = 1:count
                    xx = [x{m}];
                    yy = [y{m}];
                    xx = xs + (xx - app.COORD_VARS.MIN_LON) * xinc;
                    yy = ys + (yy - app.COORD_VARS.MIN_LAT) * yinc;
                    nkeep = nn;
                    % 地図範囲内のデータを保持
                    for k = 1:length(xx)
                        if xx(k) >= (xs - dummy) && xx(k) <= (xf + dummy) && yy(k) >= (ys - dummy) && yy(k) <= (yf + dummy)
                            nn = nn + 1;
                            if m ~= temp
                                icount = icount + 1;
                                temp = m;
                            end
                            a = xy2lonlat(app, [xx(k), yy(k)]);
                            app.OVERLAY_VARS.AFAULT_DATA(nn, :) = [a(1), a(2), xx(k), yy(k)];  % a(1):経度, a(2):緯度
                        end
                    end
                    % セグメント間をNaNで区切る
                    if nn > nkeep
                        nn = nn + 1;
                        app.OVERLAY_VARS.AFAULT_DATA(nn, :) = NaN;
                    end
                end
            end
        
            % メモリ節約のため、データを single 型に変換
            app.OVERLAY_VARS.AFAULT_DATA = single(app.OVERLAY_VARS.AFAULT_DATA);
        end


        function activefault_flag = overlay_activefault_format_window(~)
            % =====================================================================
            %                overlay_activefault_format_window(app)  
            % =====================================================================
            % 断層ラインのデータを入力する前に、データのフォーマットを選択させるポップアップ関数
            list = {'Lon. (1st column) & lat. (2nd column)', 'Lat. (1st column) & lon. (2nd column)'};

            % indexは選択された行を返す(1 or 2)、~にはtrue or falseが格納される
            [index, ~] = listdlg('PromptString', ... % {}内をテキスト表示
                                    {'Example', ...
                                    'Japanese active fault data  <-- Header (one line)',...
                                    '> break     0.000             <-- Line break mark', ...
                                    '  145.180  44.118            <-- Lon. & lat.', ...
                                    '  145.183  44.119 ', ...
                                    '  145.188  44.121 ', ...
                                    '> break     0.000             <-- Line break mark', ...
                                    '  145.193  44.125 ', ...
                                    ' continue... ', ...
                                    '', ...
                                    'Choose one of the following'},...
                                    'SelectionMode', 'single', ... % 一つのみ選択可能に
                                    'ListString', list, ... % 表示するリスト
                                    'ListSize', [280, 40], ... % リストのサイズ [width height]
                                    'Name', 'Mapped active fault data format'); % リストダイアログウィンドウの名前
            
            % キャンセルされた場合
            if isempty(index)
                activefault_flag = [];  % 空を返すことで呼び出し元で return できるようにする
                return;
            end

            if index == 1
                % Lon. (1st column) & lat. (2nd column)が選択されたとき
                activefault_flag = 1;
            else
                % Lat. (1st column) & lon. (2nd column)が選択されたとき
                activefault_flag = 0;
            end
        end


        function overlay_AF = overlay_activefault_drawing(app)
            % =====================================================================
            %                overlay_activefault_drawing(app)
            % =====================================================================
            % 活断層の描画処理 -----------------
            if isempty(app.OVERLAY_VARS.AFAULT_DATA)
                % データがない時、何もせず終了
                cd(app.SYSTEM_VARS.HOME_DIR); % ホームディレクトリに戻る
                overlay_AF = 1;
                return;
            end

            hold(app.UIAxes, 'on');
            if app.ActivefaultsCheckBox.Value == 1
                % データがあるとき
                % 古いフォーマットか新しいフォーマットかを判別
                [~, n] = size(app.OVERLAY_VARS.AFAULT_DATA);
                if n == 9
                    % 古いデータフォーマットの対応
                    uialert(app.main_menu_window, 'This active fault data is from an old version.', 'Warning!');
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        try
                            x1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 2)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 4))];
                            y1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 3)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 5))];
                        catch
                            uialert(app.main_menu_window, 'Operation failed. Probably the format is wrong.', 'Warning!');
                            app.ActivefaultsCheckBox.Value = 0;
                            return;
                        end
                    else
                        try
                            x1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 6)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 8))];
                            y1 = [rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 7)); rot90(app.OVERLAY_VARS.AFAULT_DATA(:, 9))];
                        catch
                            uialert(app.main_menu_window, 'Operation failed. Probably the format is wrong.', 'Warning!');
                            app.ActivefaultsCheckBox.Value = 0;
                            return;
                        end
                    end
                else
                    % 新しいデータフォーマットの対応
                    if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                        try
                            x1 = app.OVERLAY_VARS.AFAULT_DATA(:, 1);
                            y1 = app.OVERLAY_VARS.AFAULT_DATA(:, 2);
                        catch
                            uialert(app.main_menu_window, 'Operation failed. Probably the format is wrong.', 'Warning!');
                            app.ActivefaultsCheckBox.Value = 0;
                            return;
                        end
                    else
                        try
                            x1 = app.OVERLAY_VARS.AFAULT_DATA(:, 3);
                            y1 = app.OVERLAY_VARS.AFAULT_DATA(:, 4);
                        catch
                            uialert(app.main_menu_window, 'Operation failed. Probably the format is wrong.', 'Warning!');
                            app.ActivefaultsCheckBox.Value = 0;
                            return;
                        end
                    end
                end
                p = plot(app.UIAxes, x1, y1, 'Color', app.SYSTEM_VARS.PREF(6, 1:3), 'LineWidth', app.SYSTEM_VARS.PREF(6, 4));
                p.Tag = 'AfaultObj';
                overlay_AF = 0;
            else
                % チェックボックスが外されているとき
                % 'AfaultObj'タグのついた活断層の描画を削除
                activefault = findall(app.UIAxes, 'Tag', 'AfaultObj');
                if ~isempty(activefault)
                    delete(activefault);
                end
            end

            % 作業ディレクトリをホームディレクトリに戻す
            cd(app.SYSTEM_VARS.HOME_DIR);
        end

        
        function overlay_volcano_read_data(app)
            % =====================================================================
            %                overlay_volcano_read_data(app)
            % =====================================================================
            % 火山データを読み込む処理
            % ファイル読み込み方法を選択
            choice = questdlg('How do you want to load volcano data? Autofill is recommended.', ...
                              'Volcano Data Load Option', ...
                              'Auto (Default File)', 'Select File Manually', 'Cancel', 'Auto (Default File)');
        
            % ユーザーがキャンセルした場合
            if strcmp(choice, 'Cancel') || isempty(choice)
                uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                app.VolcanoesCheckBox.Value = 0;
                return;
            end

            % volcano_data フォルダへのパス
            defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_overlay_files', 'volcano_data');

            if strcmp(choice, 'Auto (Default File)')
                % 自動選択が選ばれた場合、所定のファイルを読み込み
                csvFile = fullfile(defaultDir, 'volcanoes_NGDC_NOAA.csv');
            elseif strcmp(choice, 'Select File Manually')
                % ユーザー側での選択が選ばれた場合、ディレクトリを開く
                [fileName, fileDir] = uigetfile(fullfile(defaultDir, '*.csv'), 'Select Volcano Data File');
                if isequal(fileName, 0)
                    uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                    app.VolcanoesCheckBox.Value = 0;
                    return;
                end
                csvFile = fullfile(fileDir, fileName);
            end

            try
                % 既存のCSVをテーブルとして読み込み
                volcanoTable = readtable(csvFile, 'TextType', 'string');
                % 欠損値（missing）を処理： string 列には "" を、numeric 列には NaN を
                volcanoTable = standardizeMissing(volcanoTable, ["", missing]);
            catch
                uialert(app.main_menu_window, 'Failed to load the volcano data file.', 'Error Message', 'Icon', 'error');
                app.VolcanoesCheckBox.Value = 0;
                return;
            end
            
            % カラム数チェック
            if width(volcanoTable) < 9
                uialert(app.main_menu_window, 'CSV file must have at least 9 columns (A~I).', 'Error Message', 'Icon', 'error');
                app.VolcanoesCheckBox.Value = 0;
                return;
            end

            % 変数名の行を削除
            volcanoTable = volcanoTable(2:end, :);

            % 火山データの数を取得（変数名を除いた行数）
            volcanoDataSize = height(volcanoTable);
            % app.OVERLAY_VARS.VOLCANOを9列の空のcell配列として初期化
            app.OVERLAY_VARS.VOLCANO = cell(volcanoDataSize, 9);
            
            % 注意！！app.OVERLAY_VARS.VOLCANO を cell 配列でなく double 型の配列や table にしない限り、中に入るのは {[27.0800]} 形式になる。
            % そのため、利用時には変換が必要となる。
            try
                % 各列を代入（行方向でベクトル代入可能）
                app.OVERLAY_VARS.VOLCANO(:, 1) = cellstr(volcanoTable{:, 1});  % number : number
                app.OVERLAY_VARS.VOLCANO(:, 2) = cellstr(volcanoTable{:, 2});  % name   : volcano name
                app.OVERLAY_VARS.VOLCANO(:, 3) = cellstr(volcanoTable{:, 3});  % region : region
                
                app.OVERLAY_VARS.VOLCANO(:, 4) = num2cell(volcanoTable{:, 4}); % lat     : latitude
                app.OVERLAY_VARS.VOLCANO(:, 5) = num2cell(volcanoTable{:, 5}); % lon     : longitude
                app.OVERLAY_VARS.VOLCANO(:, 6) = num2cell(volcanoTable{:, 6}); % elev    : elevation
                
                app.OVERLAY_VARS.VOLCANO(:, 7) = cellstr(volcanoTable{:, 7});  % type   : type
                app.OVERLAY_VARS.VOLCANO(:, 8) = cellstr(volcanoTable{:, 8});  % status : status
                app.OVERLAY_VARS.VOLCANO(:, 9) = cellstr(volcanoTable{:, 9});  % lasterp: last know eruption
            catch ME
                uialert(app.main_menu_window, ['Failed to process volcano data: ' ME.message], 'Error Message', 'Icon', 'error');
                app.VolcanoesCheckBox.Value = 0;
                return;
            end
            
            disp('Volcano data successfully loaded.');
            showMessage(app, 'Volcano data successfully loaded.', false);
        end


        function overlay_VO = overlay_volcano_drawing(app)
            % =====================================================================
            %                overlay_volcano_drawing(app)
            % =====================================================================
            % 火山の描画処理 -----------------
            if isempty(app.OVERLAY_VARS.VOLCANO)
                % データがない時、何もせず終了
                cd(app.SYSTEM_VARS.HOME_DIR); % ホームディレクトリに戻る
                overlay_VO = 1;
                return;
            end

            % --- default values ----------------
            EruptionType    = 'all';
            SymbolType      = '^';
            MarkerEdgeColor = [.1 .1 .1];
            MarkerFaceColor = app.SYSTEM_VARS.PREF(9, 1:3);
            MarkerSize      = app.SYSTEM_VARS.PREF(9, 4) * 10; % デフォルトは10
            LabelToggle     = 'Off';
            FontName        = 'Helvetica';
            FontSize        = 14;
            % -----------------------------------

            hold(app.UIAxes, 'on');
            if app.VolcanoesCheckBox.Value == 1 
                % データがあるとき
                % 4列目（lat）と5列目（lon）の中身を取り出してセル配列をベクトルにする
                lat = cell2mat(app.OVERLAY_VARS.VOLCANO(:, 4));
                lon = cell2mat(app.OVERLAY_VARS.VOLCANO(:, 5));

                if app.COORD_VARS.ICOORD == 2
                    % lonlat座標で描画
                    x = lon;  % lon: longitude
                    y = lat;  % lat: latitude
                    
                    % 範囲内に描画対象があるかをチェック
                    inLonRange = x >= app.COORD_VARS.MIN_LON & x <= app.COORD_VARS.MAX_LON;
                    inLatRange = y >= app.COORD_VARS.MIN_LAT & y <= app.COORD_VARS.MAX_LAT;
                    inMapRange = inLonRange & inLatRange;
                    if ~any(inMapRange)
                        uialert(app.main_menu_window, 'No volcanoes fall within the current coordinate bounds.', 'No Data in Range');
                        % overlay_VO = 1;  % 描画スキップを示す
                        % return;
                    end

                    % 必要に応じて、描画対象を範囲内のデータに絞ることも可能：
                    % x = x(inMapRange);
                    % y = y(inMapRange);
                else
                    % cartesian座標の場合、座標変換
                    xy = lonlat2xy(app, [lon lat]);
                    x = xy(:, 1)';
                    y = xy(:, 2)';

                    % 範囲内に描画対象があるかをチェック
                    inLonRange = x >= app.INPUT_VARS.GRID(1, 1) & x <= app.INPUT_VARS.GRID(3, 1);
                    inLatRange = y >= app.INPUT_VARS.GRID(2, 1) & y <= app.INPUT_VARS.GRID(4, 1);
                    inMapRange = inLonRange & inLatRange;
                    if ~any(inMapRange)
                        uialert(app.main_menu_window, 'No volcanoes fall within the current coordinate bounds.', 'No Data in Range');
                        app.VolcanoesCheckBox.Value = 0;
                        app.OVERLAY_VARS.VOLCANO = [];
                        % overlay_VO = 1;  % 描画スキップを示す
                        % return;
                    end
                    
                    % 必要に応じて、描画対象を範囲内のデータに絞ることも可能：
                    % x = x(inMapRange);
                    % y = y(inMapRange);
                end
    
                p = plot(app.UIAxes, x, y, SymbolType, 'MarkerFaceColor', MarkerFaceColor, 'MarkerEdgeColor', MarkerEdgeColor, 'MarkerSize', MarkerSize);
                p.Tag = 'VolcanoObj';
                overlay_VO = 0;
            else
                % チェックボックスが外されているとき
                % 'VolcanoObj'タグのついた火山の描画を削除
                volcano = findall(app.UIAxes, 'Tag', 'VolcanoObj');
                if ~isempty(volcano)
                    delete(volcano);
                end
            end

            % 作業ディレクトリをホームディレクトリに戻す
            cd(app.SYSTEM_VARS.HOME_DIR);
        end


        function overlay_earthquake_read_data(app)
            % =====================================================================
            %                overlay_earthquake_read_data(app)
            % =====================================================================
            % 地震データを読み込み、マップ上にプロットする関数
        
            % 地震データがまだ存在しない場合、新しいデータを選択します
            % デフォルトのディレクトリに移動
            cd(app.SYSTEM_VARS.HOME_DIR);
            % ディレクトリ 'earthquake_catalog_data' に移動
            try
                % 'earthquake_catalog_data' のディレクトリを設定
                defaultDir = fullfile(app.SYSTEM_VARS.HOME_DIR, 'input_overlay_files', 'earthquake_catalog_data');
            catch % 失敗した場合以下のディレクトリを表示
                uialert(app.main_menu_window, 'Operation failed.', 'Warning!');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end

            %データを選択し、z-formatに変換
            % 読み込むデータの形式を選択させる。
            index = overlay_eq_catalog_format_window(app);
            % キャンセルされた場合
            if isempty(index)
                uialert(app.main_menu_window, 'User selected Cancel.', 'Information', 'Icon', 'info');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end

            % データを選択し、カタログ種類に応じてデータの読み込みとz-formatへの変換を実行
            %------ EQ_ZFORMAT_DATA format (17 columns) -----------------------
            % 1)lon, 2)lat, 3)year, 4)month, 5)day, 6)M, 7)depth, 8)hr, 9)min,
            % 10)strike1, 11)dip1, 12)rake1, 13)strike2, 14)dip2, 15)rake2, 
            % 16) x position, 17) y position
            %------------------------------------------------------------------
            if index == 1
                % Global CMT ("ndk" format)が選択されたとき
                % Harvard CMT
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_harvard_catalog(app, defaultDir);
            elseif index == 2
                % % International Seismological Centre (ISC)が選択されたとき
                % ISC_toolbox_Download(app);
                uialert(app.main_menu_window, 'We apologize, but we are currently making adjustments to the ISC earthquake toolbox.', 'Information', 'Icon', 'info');
            elseif index == 3
                % Japan Meteorological Agency (JMA)が選択されたとき
                % JMA catalog
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_jma_catalog(app, defaultDir); 
            elseif index == 4
                % Southern California EQ Center  (SCEC)が選択されたとき
                % SCEC catalog
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_scec_catalog(app, defaultDir);
            elseif index == 5
                % Northern California EQ Data Center (NCSN) readableが選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_ncsc_readable_catalog(app, defaultDir);
            elseif index == 6
                % Northern California EQ Data Center (NCSN) hypoinverseが選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_ncsc_hypoinverse_catalog(app, defaultDir);
            elseif index == 7
                % Northern California EQ Data Center (NCSN) Fpfit (focal mech)が選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_ncsc_fpfit_catalog(app, defaultDir);
            elseif index == 8
                % USGS NEIC screen formatが選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_neic_catalog_screenformat(app, defaultDir);
            elseif index == 9
                % ZMAP formatted catalogが選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_zmap_catalog(app, defaultDir);
            elseif index == 10
                % NIED F-net catalog (JAPAN, focal mech)が選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_nied_fnet_catalog(app, defaultDir);
            elseif index == 11
                % NIED Hi-net auto catalog (JAPAN)が選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_nied_hinet_auto_catalog(app, defaultDir);
            elseif index == 12
                % Kandilli Turkey catalogが選択されたとき
                % kandilli Turkey earthquake catalog
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_kandilli_catalog(app, defaultDir);
            elseif index == 13
                % HypoDD/tomoDDが選択されたとき
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_hypoDD_tomoDD(app, defaultDir);
            elseif index == 14
                % Northen California DDが選択されたとき
                % Northern California DD filter
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_ncadd(app, defaultDir);
            elseif index == 15
                % SOSUSが選択されたとき
                % Sosus (Sound Surveillance System) catalog 
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_sosus_catalog(app, defaultDir);

                % 以下用途不明----------------------------------
                % 修正必要
            elseif index == 16
                % Read receiver fault data (strike, dip, & rake info)
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_receivers_data(app);
            elseif index == 17
                % Read receiver fault data (strike, dip, & rake info)
                [app.ASSIST_VARS.EQ_ZFORMAT_DATA, app.ASSIST_VARS.EQ_DIR, app.ASSIST_VARS.EQ_FLAG] = format_usgs_csv(app); 
            end
    
            % ----------- 読み込み後の処理 -----------
            % EQ_ZFORMAT_DATAの有無による確認
            % 読み込みのキャンセルや、読み込んだ結果、データがなかった場合、操作を終了しリセット
            if isempty(app.ASSIST_VARS.EQ_ZFORMAT_DATA)
                uialert(app.main_menu_window, 'Operation failed. There is no earthquake data or it has been canceled.', 'Warning!');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end
    
            % EQ_FLAG が0の場合、データをクリアして終了
            % ファイル選択がキャンセルされた場合や、何らかのエラーが出た場合に操作を終了しリセット
            if app.ASSIST_VARS.EQ_FLAG == 0
                app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
                uialert(app.main_menu_window, 'Operation failed.', 'Warning!');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end
    
            % データ内に NaN 値が含まれているかチェックします
            % NaN 値が含まれる場合、処理できないので終了。
            icheck = sum(sum(isnan(app.ASSIST_VARS.EQ_ZFORMAT_DATA)));
            if icheck >=1
                app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
                uialert(app.main_menu_window, 'This may not be a properly formatted data.', 'Warning!');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end
            
            % find min time and max time for EQ screening process
            % 地震データの最小・最大時刻を取得して、範囲を決定
            [m, ~] = size(app.ASSIST_VARS.EQ_ZFORMAT_DATA);
            sec = zeros(m, 1);
            eq_datenum = zeros(m, 1);
            eq_datenum = datetime(floor(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 3)), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 4), ...
                app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 5), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 8), app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 9), sec);
            app.ASSIST_VARS.MIN_EQ_TIME = datevec(min(eq_datenum)); % [yr mo dy hr min sec]
            app.ASSIST_VARS.MAX_EQ_TIME = datevec(max(eq_datenum)); % [yr mo dy hr min sec]
            
            % open 'overlay_earthquake_screening window
            % 'overlay_earthquake_screening' ウィンドウを開きます
            uialert(app.main_menu_window, 'Please operate the panel on the right and screen the earthquake data. If there are no problems, press OK.', 'Information', 'Icon', 'info');
            overlay_earthquake_screening(app);
        end


        function overlay_earthquake_EDSafter_read_data(app)
            % =====================================================================
            %                overlay_earthquake_EDSafter_read_data(app)
            % =====================================================================
            % パネル操作の結果に応じてその後の処理を行う。
            % 描画用の座標系の設定
            xs = app.INPUT_VARS.GRID(1, 1);
            xf = app.INPUT_VARS.GRID(3, 1);
            ys = app.INPUT_VARS.GRID(2, 1);
            yf = app.INPUT_VARS.GRID(4, 1);
            xinc = (xf - xs) / (app.COORD_VARS.MAX_LON - app.COORD_VARS.MIN_LON);
            yinc = (yf - ys) / (app.COORD_VARS.MAX_LAT - app.COORD_VARS.MIN_LAT);
            
            if app.ASSIST_VARS.EDS_FLAG == 0
                % OKボタンの場合, 続行
            else
                % キャンセルボタンの場合
                % リセットして終了
                app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end
    
            % スクリーニングによる絞り込みでデータがなくなった場合、警告を表示して終了
            if isempty(app.ASSIST_VARS.EQ_ZFORMAT_DATA)
                app.ASSIST_VARS.EQ_ZFORMAT_DATA = [];
                uialert(app.main_menu_window, 'As a result of the screening, no valid earthquake data was available.', 'Information', 'Icon', 'info');
                app.EarthquakesCheckBox.Value = 0;
                cd(app.SYSTEM_VARS.HOME_DIR);
                return;
            end
            
            % 計算待ちのウィンドウを表示します
            % hm = wait_calc_window(app);
            
            lon = app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 1);
            lat = app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 2);
            xx = xs + (lon - app.COORD_VARS.MIN_LON) * xinc;
            yy = ys + (lat - app.COORD_VARS.MIN_LAT) * yinc;
            
            % Now single from double precision to save memory (only a little influenced by nodal plane stress changes (ignorable)
            % メモリを節約するため、単精度に変換し、app.OVERLAY_VARS.EQ_DATAに格納
            app.OVERLAY_VARS.EQ_DATA = single([app.ASSIST_VARS.EQ_ZFORMAT_DATA xx yy]); 
            
            overlay_earthquake_plot_drawing(app); % 地震を描画

            % ユーザーに地震データの保存方法を通知
            % 修正必要
            disp(' ');
            disp('   * To save the earthquake data only as binary, use the following command in the Command Window.');
            disp('   * save filename OVERLAY_VARS.EQ_DATA (e.g., save myearthquake.mat OVERLAY_VARS.EQ_DATA)');
            disp('   * To read the .mat formatted earthquake catalog data, use ''File -> Open...'' menu later.');
            disp(' ');
            showMessage(app, { ...
                'To save the earthquake data only as binary, use the following command in the Command Window.', ...
                'save filename OVERLAY_VARS.EQ_DATA (e.g., save myearthquake.mat OVERLAY_VARS.EQ_DATA)', ...
                'To read the .mat formatted earthquake catalog data, use ''File -> Open...'' menu later.' ...
                }, false);
        end


        function index = overlay_eq_catalog_format_window(~)
            % =====================================================================
            %                overlay_eq_catalog_format_window(app)
            % =====================================================================
            % 地震データを読み込むためのカタログを選択させる
            list = {'Global CMT ("ndk" format)', ...
                    'International Seismological Centre (ISC)', ...
                    'Japan Meteorological Agency (JMA)', ...
                    'Southern California EQ Center  (SCEC)', ...
                    'Northern California EQ Data Center (NCSN) readable', ...
                    'Northern California EQ Data Center (NCSN) hypoinverse', ...
                    'Northern California EQ Data Center (NCSN) Fpfit (focal mech)', ...
                    'USGS NEIC screen format', ...
                    'ZMAP formatted catalog', ...
                    'NIED F-net catalog (JAPAN, focal mech)', ...
                    'NIED Hi-net auto catalog (JAPAN)', ...
                    'Kandilli Turkey catalog', ...
                    'HypoDD/tomoDD', ...
                    'Northen California DD', ...
                    'SOSUS', ...
                    'Receiver fault data', ...
                    'USGS csv'};

            % indexは選択された行を返す(1 or 2)、~にはtrue or falseが格納される
            [index, ~] = listdlg('PromptString', ... % {}内をテキスト表示
                                    {'Choose one of the following', ...
                                    '', ...
                                    'Warning!! Large file takes much time to read.'},...
                                    'SelectionMode', 'single', ... % 一つのみ選択可能に
                                    'ListString', list, ...        % 表示するリスト
                                    'ListSize', [340, 230], ...    % リストのサイズ [width height]
                                    'Name', 'Select earthquake catalog format'); % リストダイアログウィンドウの名前

        end


        function overlay_earthquake_screening(app)
            % =====================================================================
            %                overlay_earthquake_screening(app)
            % =====================================================================
            % Earthquake Screening Panelの起動時関数
            % 絞り込み前の変数を複製
            app.ASSIST_VARS.EQ_EDIT_ZFORMAT = app.ASSIST_VARS.EQ_ZFORMAT_DATA;

            % Earthquake Screening Panelを使用可能に
            app.EarthquakeDataScreeningPanel.Visible = 'on';
            app.EarthquakeDataScreeningPanel.Enable = 'on';

            % パネルの初期値とスライダーの範囲を設定

            % カタログに含まれる地震の個数
            [m, ~] = size(app.ASSIST_VARS.EQ_ZFORMAT_DATA);
            app.EDS_EQNO_catalog_EditField.Value = m;

            % 地震データ開始時間
            app.EDS_StartDateDatePicker.Value = datetime( ...
                app.ASSIST_VARS.MIN_EQ_TIME(1), ... % 年
                app.ASSIST_VARS.MIN_EQ_TIME(2), ... % 月
                app.ASSIST_VARS.MIN_EQ_TIME(3)  ... % 日
            );
            app.EDS_StartHourEditField.Value = app.ASSIST_VARS.MIN_EQ_TIME(4);
            app.EDS_StartMinuteEditField.Value = app.ASSIST_VARS.MIN_EQ_TIME(5);
        
            % 地震データ終了時間
            app.EDS_EndDateDatePicker.Value = datetime( ...
                app.ASSIST_VARS.MAX_EQ_TIME(1), ... % 年
                app.ASSIST_VARS.MAX_EQ_TIME(2), ... % 月
                app.ASSIST_VARS.MAX_EQ_TIME(3)  ... % 日
            );
            app.EDS_EndHourEditField.Value = app.ASSIST_VARS.MAX_EQ_TIME(4);
            app.EDS_EndMinuteEditField.Value = app.ASSIST_VARS.MAX_EQ_TIME(5);
        

            % 修正必要？lonlatのスライダーが動かない
            % lon. (deg)
            app.EDS_MinLonEditField.Value = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 1));
            app.EDS_MaxLonEditField.Value = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 1));
            % 経度データの最小・最大を取得
            lonMin = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 1));
            lonMax = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 1));
            % 範囲スライダーの設定
            app.EDS_LonSlider.Limits = [lonMin, lonMax];
            app.EDS_LonSlider.Value = [lonMin, lonMax]; % 全範囲を選択
            app.EDS_LonSlider.Step = 0.01;  % ステップサイズを設定

            % lat. (deg)
            app.EDS_MinLatEditField.Value = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 2));
            app.EDS_MaxLatEditField.Value = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 2));
            % 緯度データの最小・最大を取得
            latMin = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 2));
            latMax = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 2));
            % 範囲スライダーの設定
            app.EDS_LatSlider.Limits = [latMin, latMax];
            app.EDS_LatSlider.Value = [latMin, latMax]; % 全範囲を選択
            app.EDS_LatSlider.Step = 0.01;  % ステップサイズを設定

            % Magnitude
            app.EDS_MinMwEditField.Value = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 6));
            app.EDS_MaxMwEditField.Value = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 6));
            % Mwデータの最小・最大を取得
            MwMin = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 6));
            MwMax = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 6));
            % 範囲スライダーの設定
            app.EDS_MwSlider.Limits = [MwMin, MwMax];
            app.EDS_MwSlider.Value = [MwMin, MwMax]; % 全範囲を選択

            % Depth (km)
            app.EDS_MinDepthEditField.Value = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 7));
            app.EDS_MaxDepthEditField.Value = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 7));
            % 深度データの最小・最大を取得
            depthMin = min(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 7));
            depthMax = max(app.ASSIST_VARS.EQ_ZFORMAT_DATA(:, 7));
            % 範囲スライダーの設定
            app.EDS_DepthSlider.Limits = [depthMin, depthMax];
            app.EDS_DepthSlider.Value = [depthMin, depthMax]; % 全範囲を選択
        
        end


        function overlay_EQ = overlay_earthquake_plot_drawing(app)
            % =====================================================================
            %                overlay_earthquake_plot_drawing(app)
            % =====================================================================
            % 地震の描画処理 -----------------
            if isempty(app.OVERLAY_VARS.EQ_DATA)
                % データがない時、何もせず終了
                cd(app.SYSTEM_VARS.HOME_DIR); % ホームディレクトリに戻る
                overlay_EQ = 1;
                return;
            end

            hold(app.UIAxes, 'on');
            if app.EarthquakesCheckBox.Value == 1
                % if OVERLAY_VARS.EQ_DATA already exist ---------------
                % 地震データが既に存在する場合、そのデータをプロットします
                if app.COORD_VARS.ICOORD == 2 && ~isempty(app.COORD_VARS.LON_GRID)
                    % 経度・緯度座標のプロット条件に従って地震を描画
                    p = scatter(app.UIAxes, app.OVERLAY_VARS.EQ_DATA(:, 1), app.OVERLAY_VARS.EQ_DATA(:, 2), 5 * app.SYSTEM_VARS.PREF(5, 4), ...
                        'MarkerEdgeColor', app.SYSTEM_VARS.PREF(5, 1:3)); % white edge color for earthquakes
                    p.Tag = 'EqObj';
                else
                    % xy座標のプロット条件に従って地震を描画
                    if isempty(app.OVERLAY_VARS.EQ_DATA(1, 10)) || app.OVERLAY_VARS.EQ_DATA(1, 11) == 0
                        p = scatter(app.UIAxes, app.OVERLAY_VARS.EQ_DATA(:, 16), app.OVERLAY_VARS.EQ_DATA(:, 17), 5 * app.SYSTEM_VARS.PREF(5, 4), ...
                            'MarkerEdgeColor', app.SYSTEM_VARS.PREF(5, 1:3)); % white edge color for earthquakes
                        p.Tag = 'EqObj';
                    else
                        % if ~isempty(OVERLAY_VARS.EQ_DATA(1,10))
                        % lonlat座標のとき、ビーチボールの大きさが正しくないバグがあるため、切り替えスイッチをオフにしている。
                        % タグ付けはbb2内部で行う
                        bb2(app, [app.OVERLAY_VARS.EQ_DATA(:, 10) app.OVERLAY_VARS.EQ_DATA(:, 11) app.OVERLAY_VARS.EQ_DATA(:, 12)],...
                            app.OVERLAY_VARS.EQ_DATA(:, 16), app.OVERLAY_VARS.EQ_DATA(:, 17), app.OVERLAY_VARS.EQ_DATA(:, 6) * app.SYSTEM_VARS.PREF(5, 4) / 3.0, 0, 0, 'b');
                    end
                end

                % 関数ボタンのfocal_mechを利用可能に
                app.CalcStNodalplanesButton.Enable = 'on';
            
            else
                % チェックボックスが外されているとき
                % 'EqObj'タグのついた地震の描画を削除
                earthquake = findall(app.UIAxes, 'Tag', 'EqObj');
                if ~isempty(earthquake)
                    delete(earthquake);
                end
            end

            % 作業ディレクトリをホームディレクトリに戻す
            cd(app.SYSTEM_VARS.HOME_DIR);
        end


        function [outData, pathname, flag] = format_harvard_catalog(app, defaultDir)
            % =====================================================================
            %                format_harvard_catalog(app, defaultDir)
            % =====================================================================
            % Read Harvard CMT earthquake catalog
            % data is properly read (flag = 0, not properly read)
            flag = 1;
            % ファイル選択ダイアログを表示
            [filename, pathname] = uigetfile({'*.*'}, 'Select Harvard CMT catalog file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                flag = 0;
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end

            fid = fopen(fullfile(pathname, filename), 'rt');
            if fid == -1
                disp('Failed to open the file.');
                showMessage(app, 'Failed to open the file.', false);
                flag = 0;
                return;
            end
	        
            lines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = lines{1};
            numQuakes = floor(length(lines) / 5);
            outData = zeros(numQuakes, 15);
            nerror = 0;

            for i = 1:numQuakes
                try
                    l1 = lines{(i - 1) * 5 + 1};
                    l4 = lines{(i - 1) * 5 + 4};
                    l5 = lines{(i - 1) * 5 + 5};
        
                    % Line 1: date/time/location/depth
                    outData(i, 3) = str2double(l1(6:9));     % Year
                    outData(i, 4) = str2double(l1(11:12));   % Month
                    outData(i, 5) = str2double(l1(14:15));   % Day
                    outData(i, 2) = str2double(l1(28:33));   % Latitude
                    outData(i, 1) = str2double(l1(35:41));   % Longitude
                    outData(i, 7) = str2double(l1(43:47));   % Depth
                    outData(i, 8) = str2double(l1(17:18));   % Hour
                    outData(i, 9) = str2double(l1(20:21));   % Minute
        
                    % Line 4: Mo (part1)
                    mo1 = str2double(l4(1:2));
        
                    % Line 5: Mo (part2) and focal mechanism
                    mo2 = str2double(l5(50:56));
                    mo  = mo2 * 10.0^mo1;
                    mw  = log10(mo) / 1.5 - 10.73;
                    outData(i, 6) = mw;                      % Mw
                    outData(i, 10) = str2double(l5(58:60));  % Strike-1
                    outData(i, 11) = str2double(l5(62:63));  % Dip-1
                    outData(i, 12) = str2double(l5(65:68));  % Rake-1
                    outData(i, 13) = str2double(l5(70:72));  % Strike-2
                    outData(i, 14) = str2double(l5(74:75));  % Dip-2
                    outData(i, 15) = str2double(l5(77:80));  % Rake-2
        
                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Problem in lines ', num2str((i - 1) * 5 + 1), '–', num2str(i * 5), ' of ', filename, '. Ignored.']);
                    showMessage(app, ['Problem in lines ', num2str((i - 1) * 5 + 1), '–', num2str(i * 5), ' of ', filename, '. Ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    flag = 0;
                    h = errordlg('This may not be a properly formatted global CMT catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_hypoDD_tomoDD(app, defaultDir)
            % =====================================================================
            %                format_hypoDD_tomoDD(~, defaultDir)
            % =====================================================================
            % Read hypoDD and tomoDD files
            flag = 1;
            [filename, pathname] = uigetfile({'*.*'}, 'Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            fid = fopen(fullfile(pathname, filename), 'r');
            if fid == -1
                disp('Failed to open file.');
                flag = 0;
                return;
            end
        
            lines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = lines{1};
        
            nLines = length(lines);
            outData = zeros(nLines, 15);
            nerror = 0;
        
            for i = 1:nLines
                try
                    line = lines{i};
                    outData(i, 1)  = str2double(strtrim(line(21:32)));   % longitude
                    outData(i, 2)  = str2double(strtrim(line(10:20)));   % latitude
                    outData(i, 3)  = str2double(strtrim(line(103:107))); % Year
                    outData(i, 4)  = str2double(strtrim(line(108:110))); % Month
                    outData(i, 5)  = str2double(strtrim(line(111:113))); % Day
                    outData(i, 6)  = str2double(strtrim(line(127:130))); % Magnitude
                    outData(i, 7)  = str2double(strtrim(line(33:42)));   % Depth
                    outData(i, 8)  = str2double(strtrim(line(114:116))); % Hour
                    outData(i, 9)  = str2double(strtrim(line(117:119))); % Minute
                    outData(i, 10) = 0.0;                                % Nodal 1: strike
                    outData(i, 11) = 0.0;                                % Nodal 1: dip
                    outData(i, 12) = 0.0;                                % Nodal 1: rake
                    outData(i, 13) = 0.0;                                % Nodal 2: strike
                    outData(i, 14) = 0.0;                                % Nodal 2: dip
                    outData(i, 15) = 0.0;                                % Nodal 2: rake

                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted hypoDD/tomoDD catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted hypoDD/tomoDD catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted hypoDD/tomoDD catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_jma_catalog(app, defaultDir)
            % =====================================================================
            %                format_jma_catalog(~, defaultDir)
            % =====================================================================
            % Read Japan Meteorological Agency (JMA) data earthquake catalog
            flag = 1; % default: data read correctly
            [filename, pathname] = uigetfile({'*.*'}, 'Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            fid = fopen(fullfile(pathname, filename), 'rt');
            rawLines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            rawLines = rawLines{1};
        
            outData = zeros(length(rawLines), 15);
            nerror = 0;
        
            for i = 1:length(rawLines)
                try 
                    line = rawLines{i};
                    lon_deg = str2double(line(33:36));
                    lon_min = str2double(line(37:41)) / 100;
                    outData(i, 1) = lon_deg + lon_min / 60.0;        % longitude

                    lat_deg = str2double(line(22:24));
                    lat_min = str2double(line(25:28)) / 100;
                    outData(i, 2) = lat_deg + lat_min / 60.0;        % latitude
                    outData(i, 3) = str2double(line(2:5));           % Year
                    outData(i, 4) = str2double(line(6:7));           % Month
                    outData(i, 5) = str2double(line(8:9));           % Day
                    outData(i, 6) = str2double(line(53:54)) / 10.0;  % Magnitude
                    if isnan(outData(i, 6))
                        outData(i, 6) = -9.9; % Temporary placeholder for missing magnitude
                    end
                    outData(i, 7) = str2double(line(45:49)) / 100.0; % Depth
                    outData(i, 8) = str2double(line(10:11));         % Hour
                    outData(i, 9) = str2double(line(12:13));         % Minute

                    % Placeholder values for nodal plane1, 2 info (strike, dip, rake, strike, dip, rake)
                    outData(i, 10:15) = 0.0;

                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted JMA catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted JMA catalog.', false);
                    h = errordlg('This may not be a properly formatted JMA catalog.');
                    flag = 0;
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_kandilli_catalog(app, defaultDir)
            % =====================================================================
            %                format_kandilli_catalog(~, defaultDir)
            % =====================================================================
            % Read formatted Turkish earthquake catalog
            % http://www.koeri.boun.edu.tr
            flag = 1;
            [filename, pathname] = uigetfile({'*.*'}, 'Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            fid = fopen(fullfile(pathname, filename), 'r');
            rawLines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            rawLines = rawLines{1};
            outData = zeros(length(rawLines), 15);
            nerror = 0;
        
            for i = 1:length(rawLines)
                try
                    line = rawLines{i};
                    outData(i, 1) = str2double(line(32:38));        % longitude
                    outData(i, 2) = str2double(line(22:28));        % latitude
                    outData(i, 3) = str2double(line(1:4));          % Year
                    outData(i, 4) = str2double(line(6:7));          % Month
                    outData(i, 5) = str2double(line(9:10));         % Day
                    md = str2double(line(56:58));                   % Md
                    ml = str2double(line(61:63));                   % Ml
                    ms = str2double(line(66:68));                   % Ms
        
                    % Choose best available magnitude
                    if isfinite(ms)
                        outData(i, 6) = ms;
                    elseif isfinite(ml)
                        outData(i, 6) = ml;
                    else
                        outData(i, 6) = md;
                    end
        
                    outData(i, 7) = str2double(line(46:49));        % Depth
                    outData(i, 8) = str2double(line(12:13));        % Hour
                    outData(i, 9) = str2double(line(15:16));        % Minute
        
                    % Initialize nodal plane data to 0
                    outData(i, 10:15) = 0.0;
        
                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted Kandilli catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted Kandilli catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted Kandilli catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_ncadd(app, defaultDir)
            % =====================================================================
            %                format_ncadd(~, defaultDir)
            % =====================================================================
            % Northern California Earthquake DD Catalog Filter 
            % http://www.ldeo.columbia.edu/~felixw/NCA_DD/ ←URL失効中
            flag = 1;
            [filename, pathname] = uigetfile({'*.*'},' Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end

            fid = fopen(fullfile(pathname, filename), 'r');
            myData = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = myData{1};
            outData = zeros(length(lines), 15);
            nerror = 0;

            for i = 1:length(lines)
                try
                    line = lines{i};
                    outData(i, 1) = str2double(line(36:48));       % longitude (Western, negative)
                    outData(i, 2) = str2double(line(28:35));       % latitude
                    outData(i, 3) = str2double(line(1:4));         % Year
                    outData(i, 4) = str2double(line(6:7));         % Month
                    outData(i, 5) = str2double(line(9:10));        % Day
                    outData(i, 6) = str2double(line(86:90));       % Magnitude
                    outData(i, 7) = str2double(line(49:57));       % Depth
                    outData(i, 8) = str2double(line(12:13));       % Hour
                    outData(i, 9) = str2double(line(15:16));       % Minute
                    outData(i, 10:15) = 0.0;                       % Focal mechanism (not available)

                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end

                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end

                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted Northern California DD catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted Northern California DD catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted Northern California DD catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_ncsc_fpfit_catalog(app, defaultDir)
            % =====================================================================
            %                format_ncsc_fpfit_catalog(~, defaultDir)
            % =====================================================================
            % Read NCSC Fpfit formatted earthquake catalog
            flag = 1;
            [filename, pathname] = uigetfile({'*.*'}, 'Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            fid = fopen(fullfile(pathname, filename), 'rt');
            lines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = lines{1};
        
            outData = zeros(length(lines), 15);
            nerror = 0;
        
            for i = 1:length(lines)
                try
                    line = lines{i};
        
                    lon_deg = str2double(line(30:32));
                    lon_min = str2double(line(34:38));
                    outData(i, 1) = -lon_deg - lon_min / 60.0;  % longitude (Western, negative)
        
                    lat_deg = str2double(line(21:22));
                    lat_min = str2double(line(24:28));
                    if strcmp(line(23), 'S')
                        outData(i, 2) = -lat_deg - lat_min / 60.0;  % latitude (southern hemisphere)
                    else
                        outData(i, 2) = lat_deg + lat_min / 60.0;   % latitude
                    end
        
                    outData(i, 3) = str2double(line(1:4));     % Year
                    outData(i, 4) = str2double(line(5:6));     % Month
                    outData(i, 5) = str2double(line(7:8));     % Day
                    outData(i, 6) = str2double(line(49:52));   % Magnitude
                    outData(i, 7) = str2double(line(40:45));   % Depth
                    outData(i, 8) = str2double(line(10:11));   % Hour
                    outData(i, 9) = str2double(line(12:13));   % Minute
        
                    outData(i, 10) = str2double(line(84:86)) - 90.0;  % Nodal 1: strike
                    if outData(i, 10) < 0.0
                        outData(i, 10) = outData(i, 10) + 360.0;
                    end
        
                    outData(i, 11) = str2double(line(88:89));  % Nodal 1: dip
                    outData(i, 12) = str2double(line(90:93));  % Nodal 1: rake
        
                    outData(i, 13) = 0.0;  % Nodal 2: strike
                    outData(i, 14) = 0.0;  % Nodal 2: dip
                    outData(i, 15) = 0.0;  % Nodal 2: rake
        
                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted ncsc fpfit catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted ncsc fpfit catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted ncsc fpfit catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_ncsc_hypoinverse_catalog(app, defaultDir)
            % =====================================================================
            %                format_ncsc_hypoinverse_catalog(~, defaultDir)
            % =====================================================================
            % Read NCSC hyp2000 formatted earthquake catalog
            flag = 1; % data is properly read (flag = 0, not properly read)
            [filename, pathname] = uigetfile({'*.*'}, ' Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            % ファイルを開いてテキストとして読み込む
            fid = fopen(fullfile(pathname, filename), 'rt');
            if fid == -1
                errordlg('Failed to open the file.');
                flag = 0;
                return;
            end
        
            lines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = lines{1};
            nLines = length(lines);
            outData = zeros(nLines, 15);
            nerror = 0;

            for i = 1:nLines
                try
                    line = lines{i};
                    lon_deg = str2double(line(24:26));
                    lon_min = str2double(line(28:31)) / 100;
                    outData(i, 1) = -lon_deg - lon_min / 60.0;            % longitude (Western, negative)
        
                    lat_deg = str2double(line(17:18));
                    lat_min = str2double(line(20:23)) / 100;
                    outData(i, 2) = lat_deg + lat_min / 60.0;             % latitude
        
                    outData(i, 3) = str2double(line(1:4));                % Year
                    outData(i, 4) = str2double(line(5:6));                % Month
                    outData(i, 5) = str2double(line(7:8));                % Day
                    outData(i, 6) = str2double(line(71:73)) / 100;        % Magnitude
                    outData(i, 7) = str2double(line(32:36)) / 100;        % Depth
                    outData(i, 8) = str2double(line(9:10));               % Hour
                    outData(i, 9) = str2double(line(11:12));              % Minute
        
                    % 残りはデフォルト 0.0
                    outData(i, 10:15) = 0.0;
        
                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted ncsc hypoinverse catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted ncsc hypoinverse catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted ncsc hypoinverse catalog.');
                    waitfor(h);
                    return;
                end
            end
        end

        function [outData, pathname, flag] = format_ncsc_readable_catalog(app, defaultDir)
            % =====================================================================
            %                format_ncsc_readable_catalog(~, defaultDir)
            % =====================================================================
            % Read Japan Meteorological Agency (JMA) data earthquake catalog
            flag = 1; % data is properly read (flag = 0, not properly read)
            [filename, pathname] = uigetfile({'*.*'}, 'Open earthquake file', defaultDir);
            if isequal(filename, 0)
                disp('  User selected Cancel.');
                showMessage(app, 'User selected Cancel.', false);
                return;
            else
                disp('  ----- earthquake data -----');
                disp(['  User selected ', fullfile(pathname, filename)]);
                showMessage(app, { ...
                    '----- earthquake data -----', ...
                    ['User selected', fullfile(pathname, filename)] ...
                    }, false);
            end
        
            % ファイル読み込み
            fid = fopen(fullfile(pathname, filename), 'rt');
            if fid == -1
                errordlg('Failed to open the file.');
                flag = 0;
                return;
            end
        
            lines = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines = lines{1};
            nLines = length(lines);
            outData = zeros(nLines, 15);
            nerror = 0;

            for i = 1:nLines
                try
                    line = lines{i};
        
                    outData(i, 1) = str2double(line(33:41));   % longitude
                    outData(i, 2) = str2double(line(24:31));   % latitude
                    outData(i, 3) = str2double(line(1:4));     % Year
                    outData(i, 4) = str2double(line(6:7));     % Month
                    outData(i, 5) = str2double(line(9:10));    % Day
                    outData(i, 6) = str2double(line(51:54));   % Magnitude
                    outData(i, 7) = str2double(line(43:48));   % Depth
                    outData(i, 8) = str2double(line(12:13));   % Hour
                    outData(i, 9) = str2double(line(15:16));   % Minute
        
                    % 残りは0で初期化
                    outData(i, 10:15) = 0.0;
        
                    if isnan(outData(i, 1)) || isnan(outData(i, 6))
                        nerror = nerror + 1;
                    end
                catch
                    disp(['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.']);
                    showMessage(app, ['Import: Problem in line ' num2str(i) ' of ' filename '. Line ignored.'], false);
                    nerror = nerror + 1;
                end
        
                if nerror > 9
                    disp('!! Warning !! This may not be a properly formatted ncsc readable catalog.');
                    showMessage(app, '!! Warning !! This may not be a properly formatted ncsc readable catalog.', false);
                    flag = 0;
                    h = errordlg('This may not be a properly formatted ncsc readable catalog.');
                    waitfor(h);
                    return;
                end
            end
        end
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